classdef CVCRCI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CVCRCIUIFigure                  matlab.ui.Figure
        C1Button                        matlab.ui.control.Button
        C2Button                        matlab.ui.control.Button
        C3Button                        matlab.ui.control.Button
        C4Button                        matlab.ui.control.Button
        C5Button                        matlab.ui.control.Button
        C6Button                        matlab.ui.control.Button
        C7Button                        matlab.ui.control.Button
        C8Button                        matlab.ui.control.Button
        C9Button                        matlab.ui.control.Button
        C10Button                       matlab.ui.control.Button
        C11Button                       matlab.ui.control.Button
        C12Button                       matlab.ui.control.Button
        C13Button                       matlab.ui.control.Button
        C14Button                       matlab.ui.control.Button
        C15Button                       matlab.ui.control.Button
        C16Button                       matlab.ui.control.Button
        UpdateDisplaysButton            matlab.ui.control.Button
        SetSomeDataButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        TakeDataTab                     matlab.ui.container.Tab
        ScansetupPanel                  matlab.ui.container.Panel
        ScanSetupTable                  matlab.ui.control.Table
        ResetStandardScanButton         matlab.ui.control.Button
        AddoneVariableButton            matlab.ui.control.Button
        RemoveoneVariableButton         matlab.ui.control.Button
        SortingorderButtonGroup         matlab.ui.container.ButtonGroup
        NormalButton                    matlab.ui.control.RadioButton
        ZigZagButton                    matlab.ui.control.RadioButton
        SamplesEditFieldLabel           matlab.ui.control.Label
        SamplesEditField                matlab.ui.control.NumericEditField
        ScanpresetsButtonGroup          matlab.ui.container.ButtonGroup
        S10                             matlab.ui.control.ToggleButton
        S11                             matlab.ui.control.ToggleButton
        S12                             matlab.ui.control.ToggleButton
        S7                              matlab.ui.control.ToggleButton
        S8                              matlab.ui.control.ToggleButton
        S9                              matlab.ui.control.ToggleButton
        S6                              matlab.ui.control.ToggleButton
        S5                              matlab.ui.control.ToggleButton
        S4                              matlab.ui.control.ToggleButton
        S3                              matlab.ui.control.ToggleButton
        S2                              matlab.ui.control.ToggleButton
        S1                              matlab.ui.control.ToggleButton
        UITable12                       matlab.ui.control.Table
        UITable13                       matlab.ui.control.Table
        SETFromScanPositionButton       matlab.ui.control.Button
        PresetslistLabel                matlab.ui.control.Label
        ScanEnvironments                matlab.ui.control.DropDown
        ScErr                           matlab.ui.control.Label
        SETFromPVPointsButton           matlab.ui.control.Button
        AcquisitionVariableSummaryPanel  matlab.ui.container.Panel
        SynchPV                         matlab.ui.control.Table
        SlowPV                          matlab.ui.control.Table
        OnlyOncePV                      matlab.ui.control.Table
        ProfileMonitorPV                matlab.ui.control.Table
        CounterVariableLabel            matlab.ui.control.Label
        RelevantVariableName            matlab.ui.control.DropDown
        RelevantVariablePosition        matlab.ui.control.Label
        SynchronizationandBufferPanel   matlab.ui.container.Panel
        BSA_Mode                        matlab.ui.control.DropDown
        Syncrhonization                 matlab.ui.control.DropDown
        BeamCode                        matlab.ui.control.DropDown
        BufferSizeEditFieldLabel        matlab.ui.control.Label
        BufferSizeEditField             matlab.ui.control.NumericEditField
        BlockSizeEditFieldLabel         matlab.ui.control.Label
        BlockSizeEditField              matlab.ui.control.NumericEditField
        BSATimeEditFieldLabel           matlab.ui.control.Label
        BSATimeEditField                matlab.ui.control.NumericEditField
        BSAwaitLabel                    matlab.ui.control.Label
        BSAwaitEditField                matlab.ui.control.NumericEditField
        AuthorEditFieldLabel            matlab.ui.control.Label
        AuthorEditField                 matlab.ui.control.EditField
        TimeEditFieldLabel              matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.EditField
        StopBufferFilled                matlab.ui.control.CheckBox
        IfFullDropDownLabel             matlab.ui.control.Label
        IfFullDropDown                  matlab.ui.control.DropDown
        ScanOrContinuous                matlab.ui.container.TabGroup
        ContinuousTab                   matlab.ui.container.Tab
        STARTButton                     matlab.ui.control.Button
        PAUSEButton                     matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        DumpatrecorderStopCONT          matlab.ui.control.CheckBox
        BufferUpdate                    matlab.ui.control.Label
        AliveString                     matlab.ui.control.Label
        ScanTab                         matlab.ui.container.Tab
        STARTButtonSCAN                 matlab.ui.control.Button
        PAUSEButtonSCAN                 matlab.ui.control.Button
        STOPButtonSCAN                  matlab.ui.control.Button
        DumpatrecorderStopSCAN          matlab.ui.control.CheckBox
        ScanBufferUpdate                matlab.ui.control.Label
        ScanPoint                       matlab.ui.control.Label
        SetupScalarAcquisitionTab       matlab.ui.container.Tab
        P1                              matlab.ui.control.EditField
        P2                              matlab.ui.control.EditField
        P4                              matlab.ui.control.EditField
        P3                              matlab.ui.control.EditField
        epv_FullList                    matlab.ui.control.ListBox
        AddListButton                   matlab.ui.control.Button
        RemoveListButton                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        epv_MylistSync                  matlab.ui.control.ListBox
        RemovefromSynchronousButton     matlab.ui.control.Button
        AddtoSynchronousButton          matlab.ui.control.Button
        AddToSynchField                 matlab.ui.control.EditField
        SynchronousPVsLabel             matlab.ui.control.Label
        epv_MylistNotSync               matlab.ui.control.ListBox
        SlowPVsListLabel                matlab.ui.control.Label
        AddtoSlowPVEdit                 matlab.ui.control.EditField
        AddasSlowPVButton               matlab.ui.control.Button
        RemoveSlowPVButton              matlab.ui.control.Button
        AddPVButton                     matlab.ui.control.Button
        RemovePVButton                  matlab.ui.control.Button
        OnlyOncePVEdit                  matlab.ui.control.EditField
        epv_MylistOnlyOnce              matlab.ui.control.ListBox
        RecordoncePVsListLabel          matlab.ui.control.Label
        epv_SynchGroup                  matlab.ui.control.Table
        epv_AsynchGroup                 matlab.ui.control.Table
        ReplaceallandAddselectedButton  matlab.ui.control.Button
        AddliststoexistingPVsButton     matlab.ui.control.Button
        FilterListDropDownLabel         matlab.ui.control.Label
        FilterListDropDown              matlab.ui.control.DropDown
        AddSelectionButton              matlab.ui.control.Button
        UselcaGetinsteadoflcaGetSmartCheckBox  matlab.ui.control.CheckBox
        SetupVectorImageAcquisitionTab  matlab.ui.container.Tab
        BackgroundAxes                  matlab.ui.control.UIAxes
        BackgroundAxesReshape           matlab.ui.control.UIAxes
        ProfileMontiorPositionDropDownLabel  matlab.ui.control.Label
        ProfileMontiorPositionDropDown  matlab.ui.control.DropDown
        ProfilemonitornameDropDownLabel  matlab.ui.control.Label
        ProfilemonitornameDropDown      matlab.ui.control.DropDown
        ProfileFullPVName               matlab.ui.control.Label
        TimestampedCheckBox             matlab.ui.control.CheckBox
        GetSizeButton                   matlab.ui.control.Button
        BackgroundSamplesEditFieldLabel  matlab.ui.control.Label
        BackgroundSamplesEditField      matlab.ui.control.NumericEditField
        SizeYEditFieldLabel             matlab.ui.control.Label
        SizeYEditField                  matlab.ui.control.NumericEditField
        ReadsingleprofileButton         matlab.ui.control.Button
        PIDoffsetEditFieldLabel         matlab.ui.control.Label
        PIDoffsetEditField              matlab.ui.control.NumericEditField
        AsynchronousvariableBufferSizeEditFieldLabel  matlab.ui.control.Label
        AsynchronousvariableBufferSizeEditField  matlab.ui.control.NumericEditField
        DoPostprocessingCheckBox        matlab.ui.control.CheckBox
        PostProcessingFunctionDropDownLabel  matlab.ui.control.Label
        PostProcessingFunctionDropDown  matlab.ui.control.DropDown
        PostProcessingStatus            matlab.ui.control.Table
        SetnewPostProcessingoptionsfromTableButton  matlab.ui.control.Button
        PostProcessingOptions           matlab.ui.control.Table
        newbaselinepersistentvarinitButton  matlab.ui.control.Button
        GetBackgroundButton             matlab.ui.control.Button
        SizeXEditField_2Label           matlab.ui.control.Label
        SizeXEditField                  matlab.ui.control.NumericEditField
        ReloadLastBackgroundButton      matlab.ui.control.Button
        ClearBackgroundButton           matlab.ui.control.Button
        ListaBackground                 matlab.ui.control.Label
        StoredCVCRCIPresetsTab          matlab.ui.container.Tab
        A1Button                        matlab.ui.control.Button
        A7Button                        matlab.ui.control.Button
        A13Button                       matlab.ui.control.Button
        A14Button                       matlab.ui.control.Button
        A8Button                        matlab.ui.control.Button
        A2Button                        matlab.ui.control.Button
        A3Button                        matlab.ui.control.Button
        A9Button                        matlab.ui.control.Button
        A15Button                       matlab.ui.control.Button
        A16Button                       matlab.ui.control.Button
        A10Button                       matlab.ui.control.Button
        A4Button                        matlab.ui.control.Button
        A5Button                        matlab.ui.control.Button
        A11Button                       matlab.ui.control.Button
        A17Button                       matlab.ui.control.Button
        A18Button                       matlab.ui.control.Button
        A12Button                       matlab.ui.control.Button
        A6Button                        matlab.ui.control.Button
        A36Button                       matlab.ui.control.Button
        A35Button                       matlab.ui.control.Button
        A34Button                       matlab.ui.control.Button
        A33Button                       matlab.ui.control.Button
        A30Button                       matlab.ui.control.Button
        A29Button                       matlab.ui.control.Button
        A28Button                       matlab.ui.control.Button
        A27Button                       matlab.ui.control.Button
        A32Button                       matlab.ui.control.Button
        A26Button                       matlab.ui.control.Button
        A31Button                       matlab.ui.control.Button
        A25Button                       matlab.ui.control.Button
        A24Button                       matlab.ui.control.Button
        A23Button                       matlab.ui.control.Button
        A22Button                       matlab.ui.control.Button
        A21Button                       matlab.ui.control.Button
        A20Button                       matlab.ui.control.Button
        A19Button                       matlab.ui.control.Button
        CVCRCIPresetNameEditFieldLabel  matlab.ui.control.Label
        CVCRCIPresetNameEditField       matlab.ui.control.EditField
        StorePresetinNewPositionButton  matlab.ui.control.Button
        StorePresetatexistingPositionasButton  matlab.ui.control.Button
        PresetDropDownLabel             matlab.ui.control.Label
        PresetDropDown                  matlab.ui.control.DropDown
        PresetNewnameEditFieldLabel     matlab.ui.control.Label
        PresetNewnameEditField          matlab.ui.control.EditField
        LoadStoredDatasetTab            matlab.ui.container.Tab
        UiGetFileButton                 matlab.ui.control.Button
        AnnoEditFieldLabel              matlab.ui.control.Label
        AnnoEditField                   matlab.ui.control.NumericEditField
        MonthEditFieldLabel             matlab.ui.control.Label
        MonthEditField                  matlab.ui.control.NumericEditField
        DayEditFieldLabel               matlab.ui.control.Label
        DayEditField                    matlab.ui.control.NumericEditField
        pwdButton                       matlab.ui.control.Button
        TodayButton                     matlab.ui.control.Button
        FilterstringEditFieldLabel      matlab.ui.control.Label
        FilterstringEditField           matlab.ui.control.EditField
        Filenames                       matlab.ui.control.ListBox
        LoadButton                      matlab.ui.control.Button
        FolderEditFieldLabel            matlab.ui.control.Label
        FolderEditField                 matlab.ui.control.EditField
        DataFound                       matlab.ui.control.Table
        VariablesListBoxLabel           matlab.ui.control.Label
        VariablesListBox                matlab.ui.control.ListBox
        UpdateOnceButton                matlab.ui.control.Button
        RunUpdateButton                 matlab.ui.control.Button
        pauseEditFieldLabel             matlab.ui.control.Label
        pauseEditField                  matlab.ui.control.NumericEditField
        StopUpdateButton                matlab.ui.control.Button
        FullSetButton                   matlab.ui.control.Button
        PositionLabel                   matlab.ui.control.Label
        POSState                        matlab.ui.control.Label
        POSState_2                      matlab.ui.control.Label
        UNFREEZEButton                  matlab.ui.control.Button
        ReleaseeDefsButton              matlab.ui.control.Button
        AliveDisplaysListBoxLabel       matlab.ui.control.Label
        AliveDisplaysListBox            matlab.ui.control.ListBox
        AD_CloseButton                  matlab.ui.control.Button
        AD_CloseAllButton               matlab.ui.control.Button
        UpdateDisplaysButton_2          matlab.ui.control.Button
        C17Button                       matlab.ui.control.Button
        C18Button                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        Displays % DescriptionOFAllPossibleDisplays
        Use_Lca_Monitor % Uses LCA Monitor if 1
        Presets % Presets for entire GUI at startup
        maximumNumberOfDisplays=18;
        maximumNumberOfPresets=36;
        openDisplays={}; % Displays that are open.
        CV = CVCRCI_Class; %app.CV is the recorder object.
        %Recorder % Recorder data and status
        %Acquisition % Acquisition setup
        Profile % List of the profile monitors to be recorded and their options 
        ScalarList % A List of scalars known to the GUI (e.g. BSA ones, or other groups)
        Waveforms % A list of all profile monitors recognized by software
        MaxWaveforms=7; %Maximum number of waveforms.
        ScalarTables; %The lists of scalars to be recorded
        ScalarGroups; %Groups of scalars
        ConfigurationPath='/u1/lcls/matlab/VOM_Configs';
        color % colors for the UI
        AliveStrings={'|','\','-','/','|','\','-','/'};
        AllScans;
        application='MainRecorder';
    end
    
    methods (Access = private)
        
        function void = handleDisplayVisibility(app)
            for II=1:app.maximumNumberOfDisplays
                if(II<=app.Displays.Number)
                    set(app.(['C',num2str(II),'Button']),'visible','on');
                    set(app.(['C',num2str(II),'Button']),'userdata',app.Displays.Functions(II));
                    app.(['C',num2str(II),'Button']).Text=app.Displays.Functions(II).name;
                else
                    set(app.(['C',num2str(II),'Button']),'visible','off');
                end
            end
            void=0;
        end
        
        function void=openDisplay(app,ButtonNumber)
            UD=app.(['C',num2str(ButtonNumber),'Button']).UserData;
            if(isempty(app.openDisplays))
                app.openDisplays{1}=UD.FigureFunction();
                app.openDisplays{1}.NeedInit=1;
            else
                app.openDisplays{end+1}=UD.FigureFunction();
                app.openDisplays{end}.NeedInit=1;
            end
            app.openDisplays{end}.Update(app.CV,1);
            void=0;
        end
        
        function UpdateAllDisplays(app)
            for II=numel(app.openDisplays):-1:1
                if(~isvalid(app.openDisplays{II}))
                    app.openDisplays(II)=[];
                else
                    app.openDisplays{II}.Update(app.CV,0);
                end
            end
        end
        
        function InitializeAllDisplays(app)
            for II=numel(app.openDisplays):-1:1
                if(~isvalid(app.openDisplays{II}))
                    app.openDisplays(II)=[];
                else
                    app.openDisplays{II}.Update(app.CV,1);
                end
            end
        end
        
        function void = allScalarNames(app)
            void=0;
            app.ScalarList=load([app.ConfigurationPath,'/CVCRCI_BSA_LIST.mat']);
            try
                app.ScalarList.BSA_LIST.BSA_HXR= meme_names('tag',['LCLS.CU', 'H', '.BSA.rootnames'], 'sort','z');
                app.ScalarList.BSA_LIST.BSA_SXR= meme_names('tag',['LCLS.CU', 'S', '.BSA.rootnames'], 'sort','z');
            catch void
                disp('Meme names is broken');
            end
            
            BSA_Fieldnames=fieldnames(app.ScalarList.BSA_LIST);
            app.ScalarList.FULL_HXR_BSA=app.ScalarList.BSA_LIST.BSA_HXR;
            app.ScalarList.FULL_SXR_BSA=app.ScalarList.BSA_LIST.BSA_SXR;
            BSA_Fieldnames=setdiff(BSA_Fieldnames,'BSA_HXR'); BSA_Fieldnames=setdiff(BSA_Fieldnames,'BSA_SXR'); BSA_Fieldnames=setdiff(BSA_Fieldnames,'GDET_SXR');
            for II=1:length(BSA_Fieldnames)
                if(any(strfind(BSA_Fieldnames{II},'DMPH')) || any(strfind(BSA_Fieldnames{II},'HXR')) || any(strfind(BSA_Fieldnames{II},'LTUH')))
                    app.ScalarList.FULL_HXR_BSA=union(app.ScalarList.FULL_HXR_BSA,app.ScalarList.BSA_LIST.(BSA_Fieldnames{II}),'stable');
                end
                if(any(strfind(BSA_Fieldnames{II},'DMPS')) || any(strfind(BSA_Fieldnames{II},'SXR')) || any(strfind(BSA_Fieldnames{II},'LTUS')))
                    app.ScalarList.FULL_SXR_BSA=union(app.ScalarList.FULL_SXR_BSA,app.ScalarList.BSA_LIST.(BSA_Fieldnames{II}),'stable');
                end
                if(any(strfind(BSA_Fieldnames{II},'BLEN')))
                    app.ScalarList.FULL_HXR_BSA=union(app.ScalarList.FULL_HXR_BSA,app.ScalarList.BSA_LIST.(BSA_Fieldnames{II}),'stable');
                    app.ScalarList.FULL_SXR_BSA=union(app.ScalarList.FULL_SXR_BSA,app.ScalarList.BSA_LIST.(BSA_Fieldnames{II}),'stable');
                end
            end
            [fp,sp,tp,qp] = breakPVList(app,app.ScalarList.FULL_HXR_BSA);
            app.ScalarList.FULL_HXR_BSA_Spalato.fp=fp; app.ScalarList.FULL_HXR_BSA_Spalato.sp=sp;
            app.ScalarList.FULL_HXR_BSA_Spalato.tp=tp; app.ScalarList.FULL_HXR_BSA_Spalato.qp=qp;
            app.ScalarList.FULL_HXR_BSA_Spalato.name=app.ScalarList.FULL_HXR_BSA;
            [fp,sp,tp,qp] = breakPVList(app,app.ScalarList.FULL_SXR_BSA);
            app.ScalarList.FULL_SXR_BSA_Spalato.fp=fp; app.ScalarList.FULL_SXR_BSA_Spalato.sp=sp;
            app.ScalarList.FULL_SXR_BSA_Spalato.tp=tp; app.ScalarList.FULL_SXR_BSA_Spalato.qp=qp;
            app.ScalarList.FULL_SXR_BSA_Spalato.name=app.ScalarList.FULL_SXR_BSA;
            app.ScalarList.FULL_BSA=union(app.ScalarList.FULL_HXR_BSA,app.ScalarList.FULL_SXR_BSA,'stable');
            [fp,sp,tp,qp] = breakPVList(app,app.ScalarList.FULL_BSA);
            app.ScalarList.FULL_BSA_Spalato.fp=fp; app.ScalarList.FULL_BSA_Spalato.sp=sp;
            app.ScalarList.FULL_BSA_Spalato.tp=tp; app.ScalarList.FULL_BSA_Spalato.qp=qp;
            app.ScalarList.FULL_BSA_Spalato.name=app.ScalarList.FULL_BSA;
            app.ScalarGroups={'HXR_BSA','SXR_BSA','BSA'};
        end
        
        function void = ManageProfileButtonVisibility(app,POS)
            void=0;
            if(~app.Profile(POS).in_use) % Hide most buttons on this page
                OnOffString='off';
            else %Show a bunch of buttons on this page
                OnOffString='on';
            end
            set(app.GetSizeButton,'visible',OnOffString);
            set(app.TimestampedCheckBox,'visible',OnOffString);
            set(app.SizeXEditField,'visible',OnOffString);
            set(app.SizeXEditField_2Label,'visible',OnOffString);
            set(app.SizeYEditField,'visible',OnOffString);
            set(app.SizeYEditFieldLabel,'visible',OnOffString);
            set(app.ReadsingleprofileButton,'visible',OnOffString);
            set(app.ProfileFullPVName,'visible',OnOffString);
            set(app.PIDoffsetEditField,'visible',OnOffString);
            set(app.PIDoffsetEditFieldLabel,'visible',OnOffString);
            set(app.AsynchronousvariableBufferSizeEditField,'visible',OnOffString);
            set(app.AsynchronousvariableBufferSizeEditFieldLabel,'visible',OnOffString);
            set(app.DoPostprocessingCheckBox,'visible',OnOffString);
            if(strcmp(OnOffString,'on'))
                app.ProfileFullPVName.Text = app.Profile(POS).PVName;
                app.SizeXEditField.Value = app.Profile(POS).size(1);
                app.SizeYEditField.Value = app.Profile(POS).size(2);
                app.AsynchronousvariableBufferSizeEditField.Value = app.Profile(POS).Buffersize;
                app.PIDoffsetEditField.Value = app.Profile(POS).PID_Delay;
            end
        end
        
        function void = ManageBackgroundVisibilityAndUpdate(app,POS)
            void=0;
            if(~app.Profile(POS).BackgroundEnable)
                set(app.GetBackgroundButton,'visible','off');
                set(app.BackgroundSamplesEditField,'visible','off');
                set(app.BackgroundSamplesEditFieldLabel,'visible','off');
                set(app.ReloadLastBackgroundButton,'visible','off');
                set(app.ClearBackgroundButton,'visible','off');
                set(app.BackgroundAxes,'visible','off');
                set(app.BackgroundAxesReshape,'visible','off');
            else
                set(app.GetBackgroundButton,'visible','on');
                set(app.BackgroundSamplesEditField,'visible','on');
                set(app.ReloadLastBackgroundButton,'visible','on');
                set(app.ClearBackgroundButton,'visible','on');
                set(app.BackgroundAxes,'visible','on');
                set(app.BackgroundAxesReshape,'visible','on');
                set(app.BackgroundSamplesEditFieldLabel,'visible','on');
                
                if(isempty(app.Profile(POS).Background))
                    set(app.ReloadLastBackgroundButton,'enable','on');
                    set(app.ClearBackgroundButton,'enable','off');
                    cla(app.BackgroundAxes); cla(app.BackgroundAxesReshape);
                    app.BackgroundAxes.Title.String='Background is empty';
                    app.BackgroundAxesReshape.Title.String='Background is empty';
                else
                    set(app.ReloadLastBackgroundButton,'enable','on');
                    set(app.ClearBackgroundButton,'enable','on');
                    plot(app.BackgroundAxes,app.Profile(POS).Background);
                    imagesc(reshape(app.Profile(POS).Background,app.Profile(POS).size),'parent',app.BackgroundAxesReshape);
                    app.BackgroundAxes.Title.String='Background is recorded';
                    app.BackgroundAxesReshape.Title.String='Background is recorded';
                end
            end
        end
        
        function void = ManagePostProcessingVisibilityAndUpdate(app,POS)
            void=0;
            if(~app.Profile(POS).PostProcessing)
               app.DoPostprocessingCheckBox.Value=0; 
               set(app.PostProcessingFunctionDropDown,'visible','off');
               set(app.PostProcessingFunctionDropDownLabel,'visible','off');
               set(app.PostProcessingOptions,'visible','off');
               set(app.PostProcessingStatus,'visible','off');
               set(app.SetnewPostProcessingoptionsfromTableButton,'visible','off');
               set(app.newbaselinepersistentvarinitButton,'visible','off');
            else
               app.DoPostprocessingCheckBox.Value=1;
               app.PostProcessingFunctionDropDown.Items=app.Profile(POS).PostProcessingF_List;
               app.PostProcessingFunctionDropDown.ItemsData=1:length(app.Profile(POS).PostProcessingF_List);
               app.PostProcessingFunctionDropDown.Value=app.Profile(POS).PostProcessingFunctionID;
               set(app.PostProcessingFunctionDropDown,'visible','on');
               set(app.PostProcessingFunctionDropDownLabel,'visible','on');
               set(app.PostProcessingOptions,'visible','on');
               set(app.PostProcessingStatus,'visible','on');
               set(app.SetnewPostProcessingoptionsfromTableButton,'visible','on');
               set(app.newbaselinepersistentvarinitButton,'visible','on');
               app.PostProcessingOptions.Data=app.Profile(POS).PostProcessingOptions;
               if(app.Profile(POS).PostProcessingFunctionID>1)
                  Data=app.Profile(POS).PostProcessingFunction(lcaGetSmart(app.Profile(POS).PVName,prod(app.Profile(POS).size)),app.Profile(POS).PostProcessingOptions,app.Profile(POS),[],1);
                  InfoTable=app.MakeTableFromData(Data);
               else
                  InfoTable{1,1}=''; InfoTable{1,2}=''; 
               end
               app.PostProcessingStatus.Data=InfoTable;
               if(app.Profile(POS).PersistentInit(app.Profile(POS).PostProcessingFunctionID))
                   set(app.newbaselinepersistentvarinitButton,'enable','on');
               else
                   set(app.newbaselinepersistentvarinitButton,'enable','off');
               end
            end
        end
        
        function OUT = MakeTableFromData(~,Data)
            OUT{1,1}='';OUT{1,2}='';OUT{1,3}='';
            RID=1;
            for II=1:Data.NumberOfScalars
                OUT{RID,1}=Data.ScalarNames{II};
                OUT{RID,2}=['[','1' , ' x ','1',']'];
                RID=RID+1;
            end
            for II=1:Data.NumberOfVectors
                OUT{RID,1}=Data.VectorNames{II};
                OUT{RID,2}=['[','1' , ' x ',num2str(Data.VectorSizes(II)),']'];
                RID=RID+1;
            end
            for II=1:Data.NumberOfArray2D
                OUT{RID,1}=Data.Array2DNames{II};
                OUT{RID,2}=['[',num2str(Data.Array2DSizes(II,1)) , ' x ',num2str(Data.Array2DSizes(II,2)),']'];
                RID=RID+1;
            end
            OUT{RID,1}='External Timestamps';
            if(Data.UseExternalTimeStamps)
                OUT{RID,2}='YES';
            else
                OUT{RID,2}='NO';
            end
            RID=RID+1;
            for II=1:size(Data.AdditionalInformation,1)
                OUT{RID,1}=Data.AdditionalInformation{II,1};
                OUT{RID,2}=Data.AdditionalInformation{II,2};
                RID=RID+1;
            end
        end
        
        function void = ManageChangeInPostProcessingFunction(app,POS)
            void=0;
            FunctionID=app.PostProcessingFunctionDropDown.Value;
            app.Profile(POS).PostProcessingFunctionID=FunctionID;
            app.Profile(POS).PostProcessingFunction=eval(['@', app.Profile(POS).PostProcessingF_List{app.Profile(POS).PostProcessingFunctionID}]);
            app.Profile(POS).PostProcessingOptions=app.Profile(POS).PostProcessingDefaultOptions{app.Profile(POS).PostProcessingFunctionID};
            for KK=1:size(app.Profile(POS).PostProcessingOptions,1)
                if(strcmp(app.Profile(POS).PostProcessingOptions{KK,1},'Var Name'))
                    app.Profile(POS).PostProcessingOptions{KK,2} = app.Profile(POS).name;
                end
            end
            app.ManagePostProcessingVisibilityAndUpdate(POS);
        end
        
        function void = StartUpProfileSetup(app)
            void=0;
            app.Profile=app.Waveforms(1);
            for II=1:app.MaxWaveforms
                app.Profile(II)=app.Waveforms(1);
            end
        end
        
        function void = StartupIncludeNewVariablesInWaveforms(app)
            void=0;
            for II=1:app.MaxWaveforms
                app.Waveforms(II).WaveformID=II;
                app.Waveforms(II).Userdata=[];
            end
        end
        
        function [fp,sp,tp,qp] = breakPVList(app,List) %#ok<INUSL> 
            NPV=numel(List);
            fp=cell(NPV,1);sp=cell(NPV,1);tp=cell(NPV,1);qp=cell(NPV,1);
            for II=1:NPV
                breaks=find(List{II}==':');
                p1=List{II}(1:(breaks(1)-1));
                p2=List{II}((breaks(1)+1):(breaks(2)-1));
                p3=List{II}((breaks(2)+1):(breaks(3)-1));
                p4=List{II}((breaks(3)+1):end);
                fp{II}=p1; sp{II}=p2; tp{II}=p3; qp{II}=p4;
            end
        end
        
        function void = UpdateRecordingSummary(app)
            void=0;
            app.SynchPV.Data=app.ScalarTables.SynchPV;
            app.SlowPV.Data=app.ScalarTables.SlowPV;
            app.OnlyOncePV.Data=app.ScalarTables.OnlyOncePV;
            ActiveProfileList={};
            for II=1:app.MaxWaveforms
                if(app.Profile(II).in_use)
                    ActiveProfileList{end+1}=app.Profile(II).PVName; %#ok<AGROW> 
                end
            end
            if(isempty(ActiveProfileList))
                app.ProfileMonitorPV.Data={'No Vectors or Images'};
                AllVariables = {} ;
            else
                app.ProfileMonitorPV.Data=ActiveProfileList.';
                AllVariables = ActiveProfileList.';
            end
            AllVariables = [AllVariables ; app.SynchPV.Data(:,1) ; app.SlowPV.Data];
            app.RelevantVariableName.Items = AllVariables;
        end
        
        function void=ManageScalarList(app)
            void=0;
            app.epv_FullList.Items=app.ScalarList.(['FULL_',app.FilterListDropDown.Items{app.FilterListDropDown.Value}]);
            app.epv_FullList.UserData=app.ScalarList.(['FULL_',app.FilterListDropDown.Items{app.FilterListDropDown.Value},'_Spalato']);
        end
        
        function void = searchPV(app)
            void=0;
            fn=app.P1.Value; sn=app.P2.Value; tn=app.P3.Value; qn=app.P4.Value;
            UD=app.epv_FullList.UserData;
            Nuova_Lista=UD.name(((strcmp(fn,UD.fp) | isempty(fn)) & (strcmp(sn,UD.sp) | isempty(sn)) & (strcmp (tn,UD.tp) | isempty(tn)) & (strcmp(qn,UD.qp)| isempty(qn))));
            app.epv_FullList.Items=Nuova_Lista;
        end
        
        function AddLists = FindAddLists(app)
            AddSynch=app.epv_SynchGroup.Data(:,2);
            AddSlow=app.epv_AsynchGroup.Data(:,2);
            AddOnce=app.epv_AsynchGroup.Data(:,3);
            List={};
            for II=1:length(AddSynch)
                if(AddSynch{II})
                    List=[List ; app.ScalarList.BSA_LIST.(app.epv_SynchGroup.Data{II,1})]; %#ok<AGROW> 
                end
            end
            AddLists.SynchPV=unique(List,'stable');
            List={};
            for II=1:length(AddSlow)
                if(AddSlow{II})
                    List=[List ; app.ScalarList.NON_BSA_LIST.(app.epv_AsynchGroup.Data{II,1})]; %#ok<AGROW> 
                end
            end
            AddLists.SlowPV=unique(List,'stable');
            List={};
            for II=1:length(AddOnce)
                if(AddOnce{II})
                    List=[List ; app.ScalarList.NON_BSA_LIST.(app.epv_AsynchGroup.Data{II,1})]; %#ok<AGROW> 
                end
            end
            AddLists.OnlyOncePV=unique(List,'stable');
        end
        
        function void=ManagePresetButtons(app)
            void=0;
            PresetNumber=numel(app.Presets); PresetNameList={};
            for II=1:app.maximumNumberOfPresets
                if(II<=PresetNumber)
                    app.(['A',num2str(II),'Button']).Text=app.Presets{II}.name;
                    app.(['A',num2str(II),'Button']).Visible='on';
                    app.(['A',num2str(II),'Button']).UserData=II;
                    PresetNameList{end+1}=app.Presets{II}.name; %#ok<AGROW> 
                else
                    app.(['A',num2str(II),'Button']).Visible='off';
                end
            end
            app.PresetDropDown.Items=PresetNameList;
            app.PresetDropDown.ItemsData=1:length(PresetNameList);
        end
        
        function void = RestorePreset(app,PresetID)
            void=0;
            app.ScalarTables.SynchPV=app.Presets{PresetID}.Variables.SynchPV;
            app.ScalarTables.SlowPV=app.Presets{PresetID}.Variables.SlowPV;
            app.ScalarTables.OnlyOnceV=app.Presets{PresetID}.Variables.OnlyOncePV;
            app.Profile=app.Presets{PresetID}.Profile;
            app.BSA_Mode.Value=app.Presets{PresetID}.AcquisitionSetup.BSA_Mode;
            app.Syncrhonization.Value=app.Presets{PresetID}.AcquisitionSetup.Synch_Mode;
            app.BeamCode.Value=app.Presets{PresetID}.AcquisitionSetup.BeamCode;
            app.BufferSizeEditField.Value=app.Presets{PresetID}.AcquisitionSetup.BufferSize;
            app.BlockSizeEditField.Value=app.Presets{PresetID}.AcquisitionSetup.BlockSize;
            app.BSATimeEditField.Value=app.Presets{PresetID}.AcquisitionSetup.BSATime;
            app.BSAwaitEditField.Value=app.Presets{PresetID}.AcquisitionSetup.BSAwait;
            app.AuthorEditField.Value=app.Presets{PresetID}.AcquisitionSetup.Author;
            app.TimeEditField.Value=app.Presets{PresetID}.AcquisitionSetup.TimeReference;
            app.IfFullDropDown.Value=app.Presets{PresetID}.AcquisitionSetup.AtBufferFullFunction;
            app.StopBufferFilled.Value=app.Presets{PresetID}.AcquisitionSetup.ExitAtBufferFilled;
        end
        
        function InputStructure = ReadAllInputs(app)
            if(strcmp(app.ContinuousTab.Title,'Continuous')), InputStructure.DoScan=0; else,  InputStructure.DoScan=1; end
            SynchScalars=app.ScalarTables.SynchPV;
            SlowScalars=app.ScalarTables.SlowPV;
            ReadOnce=app.ScalarTables.OnlyOncePV;
            switch(app.BSA_Mode.Value)
                case 'No BSA'
                    InputStructure.BSA=0;
                case 'Double Buffer BSA'
                    InputStructure.BSA=1;
                case 'History Buffer BSA'
                    InputStructure.BSA=2;
                otherwise
                    InputStructure.BSA=0;
                    disp('Something went wrong, not recognized BSA mode!');
            end
            switch(app.Syncrhonization.Value)
                case 'Synchronous Mode'
                    InputStructure.Synchronous=1;
                case 'Asynchronous Mode'
                    InputStructure.Synchronous=0;
                otherwise
                    disp('Something went wrong, not recognized Synchronous Mode');
                    InputStructure.Synchronous=1;
            end
            InputStructure.Buffersize=app.BufferSizeEditField.Value;
            InputStructure.Blocksize=app.BlockSizeEditField.Value;
            InputStructure.TimeCycle=app.BSATimeEditField.Value;
            InputStructure.PV_For_Time_Reference=app.TimeEditField.Value;
            InputStructure.BSA_Safe_Trash_Data=app.BSAwaitEditField.Value;
            InputStructure.Author=app.AuthorEditField.Value;
            InputStructure.BufferFullFunction=app.IfFullDropDown.Value;
            InputStructure.StopBufferFilled=app.StopBufferFilled.Value;
            
            switch(app.BeamCode.Value)
                case 'All 120 Hz'
                    InputStructure.BSA_Exclusion=0;
                case 'HXR Branch only'
                    InputStructure.BSA_Exclusion=1;
                case 'SXR Branch only'
                    InputStructure.BSA_Exclusion=2;
                otherwise
                    disp('Something went wrong, not recognized Beam Code');
                    InputStructure.BSA_Exclusion=0;
            end
 
            InputStructure.Variables={};
            InputStructure.Rejection=[];
            InputStructure.name='General Acquisition';
            InputStructure.InsertedCounterVariableName=app.RelevantVariableName.Value;
            InputStructure.InsertedCounterVariable=1;
            InputStructure.SpawnMultiple=0;
            InputStructure.Profile=app.Profile;
            
            for TT=1:numel(InputStructure.Profile)
                if(~InputStructure.Profile(TT).in_use)
                    continue
                end
                InputStructure.Variables{end+1}=InputStructure.Profile(TT);
            end
            
            for TT=1:size(SynchScalars,1)
                InputStructure.Variables{end+1}.name=SynchScalars{TT,1};
                InputStructure.Variables{end}.PVname=SynchScalars{TT,1};
                InputStructure.Variables{end}.size=[1,1];
                InputStructure.Variables{end}.Synchronous=1;
                InputStructure.Variables{end}.ReadOnlyOnce=NaN;
                InputStructure.Variables{end}.Background=[];
                if(~isempty(SynchScalars{TT,2}))
                    if(isnan(SynchScalars{TT,2}))
                        SynchScalars{TT,2}=[];
                    end
                end
                if(~isempty(SynchScalars{TT,3}))
                    if(isnan(SynchScalars{TT,3}))
                        SynchScalars{TT,3}=[];
                    end
                end
                if ( (~isempty(SynchScalars{TT,2})) || (~isempty(SynchScalars{TT,3})) )
                    InputStructure.Rejection(end+1).PVname=SynchScalars{TT,1};
                    if(isempty(SynchScalars{TT,2}))
                        InputStructure.Rejection(end).Range(1)=-inf;
                    else
                        InputStructure.Rejection(end).Range(1)=SynchScalars{TT,2};
                    end
                    if(isempty(SynchScalars{TT,3}))
                        InputStructure.Rejection(end).Range(2)=+inf;
                    else
                        InputStructure.Rejection(end).Range(2)=SynchScalars{TT,3};
                    end
                end
                if(strcmp(InputStructure.InsertedCounterVariable,InputStructure.Variables{end}.PVname))
                    InputStructure.InsertedCounterVariable=length(InputStructure.Variables);
                end
            end
            
            for TT=1:size(SlowScalars,1)
                InputStructure.Variables{end+1}.name=SlowScalars{TT,1};
                InputStructure.Variables{end}.PVname=SlowScalars{TT,1};
                InputStructure.Variables{end}.size=[1,1];
                InputStructure.Variables{end}.Synchronous=0;
                InputStructure.Variables{end}.ReadOnlyOnce=0;
                InputStructure.Variables{end}.Background=[];
                if(strcmp(InputStructure.InsertedCounterVariable,InputStructure.Variables{end}.PVname))
                    InputStructure.InsertedCounterVariable=length(InputStructure.Variables);
                end
            end
            
            for TT=1:size(ReadOnce,1)
                InputStructure.Variables{end+1}.name=ReadOnce{TT,1};
                InputStructure.Variables{end}.PVname=ReadOnce{TT,1};
                InputStructure.Variables{end}.size=[1,1];
                InputStructure.Variables{end}.Synchronous=0;
                InputStructure.Variables{end}.ReadOnlyOnce=1;
                InputStructure.Variables{end}.Background=[];
            end
            
            for TT=1:numel(InputStructure.Profile)
                if(~InputStructure.Profile(TT).in_use)
                    continue
                end
                if(~isempty(InputStructure.Profile(TT).AdditionalPv{1}))
                    for ZZ=1:numel(InputStructure.Profile(TT).AdditionalPv)
                        InputStructure.Variables{end+1}.name=InputStructure.Profile(TT).AdditionalPv{ZZ};
                        InputStructure.Variables{end}.PVname=InputStructure.Profile(TT).AdditionalPv{ZZ};
                        InputStructure.Variables{end}.size=[1,1];
                        InputStructure.Variables{end}.Synchronous=0;
                        InputStructure.Variables{end}.ReadOnlyOnce=1;
                        InputStructure.Variables{end}.Background=[];
                    end
                end
                if(strcmp(InputStructure.InsertedCounterVariable,InputStructure.Variables{end}.PVname))
                    InputStructure.InsertedCounterVariable=length(InputStructure.Variables);
                end
            end
            if(InputStructure.DoScan)
                InputStructure.ScanSetting=ScanSetting;
            end
        end
        
        function void = getAppColors(app)
            void=0;
            app.color.Idle=app.C1Button.BackgroundColor;
            app.color.On=[0,1,0];
            app.color.Off=[1,0,0];
            app.color.Wait=[1,1,0];
            app.color.logbook=[0.7,0.7,1];
            app.color.logbook2Steps=[0.5,0.5,1];
        end
        
        function void = unfreeze(app)
            void=0;
            app.ReleaseeDefsButton.Enable='on'; app.ReleaseeDefsButton.BackgroundColor=app.color.Idle;
            app.STARTButton.Enable='on'; app.STARTButton.BackgroundColor=app.color.Idle;
            app.PAUSEButton.Enable='off'; app.PAUSEButton.BackgroundColor=app.color.Idle;
            app.STOPButton.Enable='off'; app.STOPButton.BackgroundColor=app.color.Idle;
            app.STARTButtonSCAN.Enable='on'; app.STARTButtonSCAN.BackgroundColor=app.color.Idle;
            app.PAUSEButtonSCAN.Enable='off'; app.PAUSEButtonSCAN.BackgroundColor=app.color.Idle;
            app.STOPButtonSCAN.Enable='on'; app.STOPButtonSCAN.BackgroundColor=app.color.Idle;
            app.releaseEDef();
        end        
        
        function void = releaseEDef(app)
            void=0;
            OldeDefs=app.ReleaseeDefsButton.UserData;
            if ~isempty(OldeDefs)
                for II=1:length(OldeDefs)
                    try
                        eDefRelease(OldeDefs(II));
                    catch ME %#ok<NASGU> 
                        %maybe somebody cleaned the thing before you did!
                    end
                end
            end
            app.ReleaseeDefsButton.UserData=[];
            app.ReleaseeDefsButton.BackgroundColor=app.color.Idle;
            app.ReleaseeDefsButton.Enable='off';
        end
        
        function populateScan(app)
            try
                CustomAdditionalStuff=load('ADD_CustomStuff.mat');
            catch ME
                CustomAdditionalStuff=struct();
            end
            app.AllScans=CVCRCI_ScanConfigurationFunction;
            try
                if(isfield(CustomAdditionalStuff,'ScanTabNames'))
                        for II=1:numel(CustomAdditionalStuff.ScanTabNames)
                            app.AllScans.EnvironmentNames{end+1}=CustomAdditionalStuff.ScanTabNames{II};
                            app.AllScans.Environment{end+1}=CustomAdditionalStuff.Scans{II};
                        end
                end
            catch
               disp('Error while adding custom scans from ADD_CustomStuff.mat'); 
            end
            app.ScanEnvironments.Items=app.AllScans.EnvironmentNames;
            app.ScanEnvironments.ItemsData=1:length(app.AllScans.EnvironmentNames);
            app.updatePresetListSelection();
            app.scanPresetPicked();
            app.evaluateScanSettingFromTable();
        end
        
        function updatePresetListSelection(app)
            for II=1:12
                if(II<=numel(app.AllScans.Environment{app.ScanEnvironments.Value}.Scan))
                    app.(['S',num2str(II)]).Text=app.AllScans.Environment{app.ScanEnvironments.Value}.Scan(II).Name;
                    app.(['S',num2str(II)]).UserData=app.AllScans.Environment{app.ScanEnvironments.Value}.Scan(II); 
                    app.(['S',num2str(II)]).Visible='on'; 
                else
                   app.(['S',num2str(II)]).Visible='off'; 
                   app.(['S',num2str(II)]).UserData=[];
                end
            end
            app.ScanpresetsButtonGroup.SelectedObject=app.S1;
        end
        
        function scanPresetPicked(app)
            selectedButton = app.ScanpresetsButtonGroup.SelectedObject;
            app.ScanSetupTable.UserData=selectedButton.UserData;
            SCAN=app.ScanSetupTable.UserData;
            app.ScanSetupTable.Data=SCAN.Table.';
            app.ScanSetupTable.RowName=SCAN.RowNames;
            if(~SCAN.Allownewlines)
                app.AddoneVariableButton.Enable='Off';
                app.RemoveoneVariableButton.Enable='Off';
            else
                app.AddoneVariableButton.Enable='On';
                app.RemoveoneVariableButton.Enable='On';
            end
            app.ScanSetupTable.ColumnEditable=true(1,size(SCAN.Table,2));
        end
        
        function evaluateScanSettingFromTable(app)
            disp('Run Scan Setup Table');
            Scan=app.ScanSetupTable.UserData;
            Table=app.ScanSetupTable.Data;
            Table=Scan.Functions.ParseTable(Table);
            app.ScanSetupTable.Data=Table;
            %Set new values in the tables Guarda il numero delle knobs
            Options.Normal=app.NormalButton.Value;
            Options.ZigZag=app.ZigZagButton.Value;
            [ScanSetting,ErrorMessage]=Scan.Functions.CalculatePoints(Table,Options);
            if(~isempty(ErrorMessage))
                app.STARTButtonSCAN.Enable='Off';
                app.ScErr.Text=ErrorMessage;
                return
            else
                app.STARTButtonSCAN.Enable='On';
                app.ScErr.Text='Ok to Scan.';
            end
            ScanSetting.Scan=Scan;
            
            for II=1:numel(ScanSetting.Knob)
                SIZES(II)=ScanSetting.Knob{II}.Steps;
            end
            
            [SPV, POSITIONS]=size(ScanSetting.PVValues);
            for II=1:SPV
                if(length(SIZES)==1)
                    ParameterSpace{II}=zeros(SIZES,1);
                else
                    ParameterSpace{II}=zeros(SIZES);
                end
            end
            
            CT=ScanSetting.ConditionsTable;
            
            switch(numel(SIZES))
                case 1
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 2
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 3
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 4
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 5
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 6
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT),CT(6,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 7
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT),CT(6,TT),CT(7,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 8
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT),CT(6,TT),CT(7,TT),CT(8,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 9
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT),CT(6,TT),CT(7,TT),CT(8,TT),CT(9,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 10
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT),CT(6,TT),CT(7,TT),CT(8,TT),CT(9,TT),CT(10,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
            end
            
            ScanSetting.ParameterSpace=ParameterSpace;
            ScanSetting.Samples=app.SamplesEditField.Value;
            app.ScErr.UserData=ScanSetting;
            TABLE={};
            for SSS=1:numel(ScanSetting.Knob)
                TABLE{SSS,1}=['Knob #',num2str(SSS)]; %#ok<AGROW> 
                TABLE{SSS,2}=1; %#ok<AGROW> 
            end
            app.UITable12.Data=TABLE;
            app.UITable12.ColumnEditable=[false,true];
            app.UpdateSecondaryScanTable();
        end
        
        function UpdateSecondaryScanTable(app)
            TABLE=app.UITable12.Data;
            ScanSetting=app.ScErr.UserData;
            CONDITIONS=[TABLE{:,2}];
            [~,MP]=min(sum(abs(ScanSetting.ConditionsTable-repmat(CONDITIONS.',[1,size(ScanSetting.PVValues,2)]))));
            
            for SSS=1:numel(ScanSetting.LcaPutNoWaitList)
                TABLE2{SSS,1}=ScanSetting.LcaPutNoWaitList{SSS}; %#ok<AGROW> 
                TABLE2{SSS,2}=ScanSetting.PVValues(SSS,MP); %#ok<AGROW> 
            end
            app.UITable13.Data=TABLE2;
            app.UITable13.ColumnEditable=[false,true];
        end
        
        function updateListOfScanVariable(app)
            %Reserved for future use.
        end
        
        function [SUCCESS,filename,TargetDir] = writeDataToDisk(app,TimeNow,CVObject,auxillaryData)
            CurrentTime=datevec(TimeNow);
            CurrentYearString=num2str(CurrentTime(1),'%.4d'); CurrentMonthString=num2str(CurrentTime(2),'%.2d');
            CurrentDayString=num2str(CurrentTime(3),'%.2d'); CurrentTempo1String=num2str(CurrentTime(4),'%.2d');
            CurrentTempo2String=num2str(CurrentTime(5),'%.2d'); CurrentTempo3String=num2str(floor(CurrentTime(6)),'%.2d');
            CurrentTempo4String=num2str(round((CurrentTime(6)-floor(CurrentTime(6)))*1000),'%.3d');
            CurrentTimeString=[CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString,'--',CurrentTempo1String,'-',CurrentTempo2String,'-',CurrentTempo3String,'-',CurrentTempo4String];
            filename=['OnlineMonitor-',app.application,'-',CurrentTimeString];
            TargetDir=['/u1/lcls/matlab/data/',CurrentYearString,'/',CurrentYearString,'-',CurrentMonthString,'/',CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString];
            if(~isfolder(TargetDir))
                mkdir(TargetDir);
            end
            DataStructure=CVObject.Acquisition; Data=CVObject.Recorder; %#ok<ADPROPLC> 
            try
                save([TargetDir,'/',filename],'Data','DataStructure','auxillaryData')
                SUCCESS=1;
            catch
                SUCCESS=0;
            end
        end
        
        function InitializeLoadTab(app)
            DateNummber=datevec(now);
            app.AnnoEditField.Value=DateNummber(1);
            app.MonthEditField.Value=DateNummber(2);
            app.DayEditField.Value=DateNummber(3); 
            app.UpdateTargetDir(1);
            app.UpdateFileList();
        end
        
        function UpdateTargetDir(app,From)
            if(From==1)
                CurrentYearString=num2str(app.AnnoEditField.Value,'%.4d');
                CurrentMonthString=num2str(app.MonthEditField.Value,'%.2d');
                CurrentDayString=num2str(app.DayEditField.Value,'%.2d');
                TargetDir=['/u1/lcls/matlab/data/',CurrentYearString,'/',CurrentYearString,'-',CurrentMonthString,'/',CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString];
                app.FolderEditField.Value=TargetDir;
            else
                app.FolderEditField.Value=pwd;
            end
        end
        
        function UpdateFileList(app)
            if(~isfolder(app.FolderEditField.Value))
               app.Filenames.Items={''};
            else
               FN=dir([app.FolderEditField.Value,'/',app.FilterstringEditField.Value]);
               if(isempty(FN))
                   app.Filenames.Items={''};
               else
                   app.Filenames.Items={FN.name};
                   app.Filenames.Value=app.Filenames.Items{1};
               end
            end
        end
        
        function UpdateReplayState(app)
            out=app.CV.GetReplayStatusString();
            app.POSState.Text=out{1};
            app.POSState_2.Text=out{2};
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IS)
            global eDefQuiet %#ok
            try %#ok
                if ~verLessThan('matlab','9.5')
%                     set(groot,'defaultFigureCreateFcn',@(fig,~)addToolbarExplorationButtons(fig));
%                     set(groot,'defaultAxesCreateFcn',  @(ax,~)set(ax.Toolbar,'Visible','off'));
                end
            end
            app.SynchPV.ColumnFormat={'char','numeric','numeric'};
            app.SynchPV.ColumnEditable=[false,true,true];
            app.getAppColors();
            CONF=load([app.ConfigurationPath,'/CVCRCI_MAIN_CONFIGURATION_FILE.mat']);
            BCL=load([app.ConfigurationPath,'/BCL.mat'],'CameraList');
            try
                CustomAdditionalStuff=load('ADD_CustomStuff.mat');
            catch ME
                CustomAdditionalStuff=struct();
                disp('No additional Custom file found in pwd. Running standard configuration alone.')
            end
            app.allScalarNames();
            app.Displays=CONF.DISPLAYS;
            try
                if(isfield(CustomAdditionalStuff,'Analysis'))
                   app.Displays.Functions=[app.Displays.Functions,CustomAdditionalStuff.Analysis];
                end
            catch
                disp('Error while adding custom Analysis displays from ADD_CustomStuff.mat');
            end
            app.Displays.Number=numel(app.Displays.Functions);
            for II=1:numel(app.Displays.Functions)
                app.Displays.Functions(II).DisplayID=II;
            end
            app.Use_Lca_Monitor=CONF.USE_LCA_MONITOR;
            try
                if(isfield(CustomAdditionalStuff,'CameraList'))
                    BCL.CameraList=[BCL.CameraList,CustomAdditionalStuff.CameraList];
                end
            catch
                disp('Error while adding new camera displays from ADD_CustomStuff.mat');
            end
            app.Waveforms=BCL.CameraList;
            app.StartupIncludeNewVariablesInWaveforms();
            app.StartUpProfileSetup();
            app.ProfilemonitornameDropDown.Items={app.Waveforms.name};
            for II=1:app.MaxWaveforms
                WF{II}=['Waveform # ',num2str(II)]; %#ok<AGROW> 
            end
            app.ProfileMontiorPositionDropDown.Items=WF;
            app.ProfileMontiorPositionDropDown.ItemsData=1:7;
            app.ProfilemonitornameDropDown.ItemsData=1:length(app.Waveforms);
            app.handleDisplayVisibility();
            app.ScalarTables.SynchPV={'BPMS:LTUH:250:X',[],[];'GDET:FEE1:241:ENRC',[],[]};
            app.ScalarTables.OnlyOncePV={'REFS:DMPH:400:EDES'};
            app.ScalarTables.SlowPV={'SIOC:SYS0:ML02:AO314'};
            app.UpdateRecordingSummary();
            app.Presets=CONF.Preset;
            app.populateScan();
            app.ManagePresetButtons();
            app.unfreeze();
        end

        % Button pushed function: C10Button, C11Button, C12Button, 
        % C13Button, C14Button, C15Button, C16Button, C17Button, 
        % C18Button, C1Button, C2Button, C3Button, C4Button, 
        % C5Button, C6Button, C7Button, C8Button, C9Button
        function C1ButtonPushed(app, event)
            UD=event.Source.UserData;
            app.openDisplay(UD.DisplayID);
        end

        % Button pushed function: UpdateDisplaysButton
        function UpdateDisplaysButtonPushed(app, event)
            app.UpdateAllDisplays;
        end

        % Button pushed function: SetSomeDataButton
        function SetSomeDataButtonPushed(app, event)
            
        end

        % Selection change function: ScanOrContinuous
        function ScanOrContinuousSelectionChanged(app, event)
            selectedTab = app.ScanOrContinuous.SelectedTab; %handle visibility for a bunch of stuff and reset counter variable
            switch selectedTab.Title
                case 'Scan'
                    set(app.ScansetupPanel,'visible','on');
                    set(app.AcquisitionVariableSummaryPanel,'visible','off');
                case 'Continuous'
                    set(app.ScansetupPanel,'visible','off');
                    set(app.AcquisitionVariableSummaryPanel,'visible','on');
                otherwise    
                    set(app.ScansetupPanel,'visible','off');
                    set(app.AcquisitionVariableSummaryPanel,'visible','on');
            end
            
        end

        % Button pushed function: UpdateDisplaysButton_2
        function UpdateDisplaysButton_2Pushed(app, event)
            UpdateAllDisplays(app);
        end

        % Button pushed function: ReleaseeDefsButton
        function ReleaseeDefsButtonPushed(app, event)
            app.releaseEDef();
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Enable='off'; app.STARTButton.BackgroundColor=app.color.On;
            app.STOPButton.Enable='on'; app.STOPButton.BackgroundColor=app.color.Idle;
            app.PAUSEButton.Enable='on'; app.STOPButton.BackgroundColor=app.color.Idle;
            
            app.CV=CVCRCI_Class;
            InputStructure=app.ReadAllInputs();
            if(app.Use_Lca_Monitor), InputStructure.UseLcaSetMonitor=1; end
            app.CV.uselcaGetSmart=app.UselcaGetinsteadoflcaGetSmartCheckBox.Value;
            app.CV.setupInputStructure(InputStructure);
            app.CV.BuildAcquisitionStructure();
            app.CV.MakeAcquisitionBuffers();
            app.CV.zeroAllBuffers();
            app.CV.Initialize_BSA();
            if(app.CV.Acquisition.BSA==1)
                app.ReleaseeDefsButton.UserData=app.CV.myeDefNumber;
                app.ReleaseeDefsButton.BackgroundColor=app.color.On;
                app.ReleaseeDefsButton.Enable='on';
            end
            runcounter=0;
            app.InitializeAllDisplays(); %Initialize Graphics
            
            while(1) %Acquisition continuous cycle.
                STOP=app.STOPButton.BackgroundColor; PAUSE=app.PAUSEButton.BackgroundColor;
                if(all(STOP==app.color.On))
                    app.unfreeze();
                    if(app.DumpatrecorderStopCONT.Value)
                        [SUCCESS,filename,TargetDir] = app.writeDataToDisk(now,app.CV,[]);
                        if(SUCCESS)
                            disp(['Saved ',TargetDir,'/',filename]);
                        else
                            disp('Saving file on disk failed!')
                        end
                    end
                    return
                end
                if(all(PAUSE==app.color.On))
                    while(1)
                        disp('Acquisition is on hold');
                        pause(0.25);
                        PAUSE=app.PAUSEButton.BackgroundColor;
                        if(all(PAUSE==app.color.Wait))
                            app.PAUSEButton.BackgroundColor=app.color.Idle; 
                            break
                        end
                    end
                end
                runcounter=runcounter+1;
                app.CV.ContAcquisitionCycle();
                app.CV.StateNumberContinuousRecording();
                app.BufferUpdate.Text=num2str(app.CV.Counter);
                app.AliveString.Text=app.AliveStrings{1+mod(runcounter,8)};
                app.UpdateAllDisplays(); %Update Graphics
                drawnow
            end 
        end

        % Value changed function: ProfileMontiorPositionDropDown
        function ProfileMontiorPositionDropDownValueChanged(app, event)
            POS = app.ProfileMontiorPositionDropDown.Value;
            app.ProfilemonitornameDropDown.Value = app.Profile(POS).WaveformID;
            app.ManageProfileButtonVisibility(POS);
            app.ManageBackgroundVisibilityAndUpdate(POS);
            app.ManagePostProcessingVisibilityAndUpdate(POS);
        end

        % Value changed function: ProfilemonitornameDropDown
        function ProfilemonitornameDropDownValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            WID=app.ProfilemonitornameDropDown.Value;
            app.Profile(POS)=app.Waveforms(WID);
            app.ManageProfileButtonVisibility(POS);
            app.ManageBackgroundVisibilityAndUpdate(POS);
            app.ManagePostProcessingVisibilityAndUpdate(POS);
            app.updateListOfScanVariable();
        end

        % Value changed function: TimestampedCheckBox
        function TimestampedCheckBoxValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).TimeStamped = app.TimestampedCheckBox.Value;
        end

        % Value changed function: SizeXEditField
        function SizeXEditFieldValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).size(1) = app.SizeXEditField.Value;
        end

        % Value changed function: SizeYEditField
        function SizeYEditFieldValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).size(2) = app.SizeYEditField.Value;
        end

        % Value changed function: PIDoffsetEditField
        function PIDoffsetEditFieldValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).PID_Delay = app.PIDoffsetEditField.Value;
        end

        % Value changed function: 
        % AsynchronousvariableBufferSizeEditField
        function AsynchronousvariableBufferSizeEditFieldValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).Buffersize = app.AsynchronousvariableBufferSizeEditField.Value;
        end

        % Button pushed function: GetSizeButton
        function GetSizeButtonPushed(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            WID=app.ProfilemonitornameDropDown.Value;
            if(isempty(app.Profile(POS).SizePV{1}))
                SizeXY(1)=app.Waveforms(WID).size(1);
            else
                SizeXY(1)=lcaGetSmart(app.Profile(POS).SizePV{1});
                if(isnan(SizeXY(1)))
                    disp(['Warning: ', app.Profile(POS).SizePV{1}, ' Returned NaN']); SizeXY(1)=app.Waveforms(WID).size(1);
                end
            end
            if(isempty(app.Profile(POS).SizePV{2}))
                SizeXY(2)=app.Waveforms(WID).size(2);
            else
                SizeXY(2)=lcaGetSmart(app.Profile(POS).SizePV{2});
                if(isnan(SizeXY(2)))
                    disp(['Warning: ', app.Profile(POS).SizePV{2}, ' Returned NaN']); SizeXY(2)=app.Waveforms(WID).size(2);
                end
            end
            app.Profile(POS).size(1)=SizeXY(1); app.Profile(POS).size(2)=SizeXY(2);
            app.SizeXEditField.Value=SizeXY(1); app.SizeYEditField.Value=SizeXY(2);
        end

        % Button pushed function: ReadsingleprofileButton
        function ReadsingleprofileButtonPushed(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            profile=app.Profile(POS).PVName;
            [RawData,ts]=lcaGetSmart(profile,prod(app.Profile(POS).size));
            if(any(app.Profile(POS).size))
                ShortLivedFigure=figure('Name','Reshaped Raw Data 2D Array');
                ShortLivedAxes=axes(ShortLivedFigure);
                imagesc(ShortLivedAxes,reshape(RawData,[app.Profile(POS).size(1),app.Profile(POS).size(2)]));
                title(ShortLivedAxes,['lcaGet Timestamp =',num2str(ts)]);
            end
            ShortLivedFigure=figure('Name','Raw Data 1D array');
            ShortLivedAxes=axes(ShortLivedFigure);
            plot(ShortLivedAxes,RawData);
            title(ShortLivedAxes,['lcaGet Timestamp =',num2str(ts)]);
        end

        % Button pushed function: GetBackgroundButton
        function GetBackgroundButtonPushed(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            profile=app.Profile(POS).PVName;
            TimeOutTime=15;
            BackgroundImagesNumber=app.BackgroundSamplesEditField.Value;%
            Size_X=app.Profile(POS).size(1);
            Size_Y=app.Profile(POS).size(2);
            Pid_Old=-1;
            tic, Time=toc;
            Inserite=0;
            while ( (Inserite < BackgroundImagesNumber) && (Time<TimeOutTime) )
                [prof,ts]=lcaGetSmart(profile,Size_X*Size_Y);
                PID=bitand(uint32(imag(ts)),hex2dec('1FFFF'));
                app.ListaBackground.Text=num2str(PID);
                if(PID~=Pid_Old)
                    if(Inserite==0)
                        BackgroundImagesVector=zeros([size(prof),BackgroundImagesNumber]);
                    end
                    Inserite=Inserite+1;
                    BackgroundImagesVector(:,:,Inserite)=prof;
                    Time=toc;
                    Pid_Old=PID;
                    app.ListaBackground.Text = [num2str(PID),' / ',num2str(Inserite)];
                end
                drawnow
            end
            app.ListaBackground.Text='...';
            BackgroundImagesVector=double(BackgroundImagesVector(:,:,1:Inserite));
            BackgroundAverage=mean(BackgroundImagesVector,3);
            if(numel(BackgroundAverage)==1)
                if(isnan(BackgroundAverage))
                    BackgroundAverage=[];
                end
            end
            app.Profile(POS).Background=BackgroundAverage;
            app.ManageBackgroundVisibilityAndUpdate(POS)
            Filename=regexprep(regexprep(profile,' ',''),':','-');
            save([app.ConfigurationPath,'/BackgroundAndBaseline/',Filename],'BackgroundAverage');
        end

        % Value changed function: DoPostprocessingCheckBox
        function DoPostprocessingCheckBoxValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).PostProcessing = app.DoPostprocessingCheckBox.Value;
            app.PostProcessingFunctionDropDown.Items = app.Waveforms(POS).PostProcessingF_List;
            app.PostProcessingFunctionDropDown.ItemsData = 1:length(app.Waveforms(POS).PostProcessingF_List);
            app.PostProcessingFunctionDropDown.Value = 1;
            app.ManageChangeInPostProcessingFunction(POS);
        end

        % Value changed function: PostProcessingFunctionDropDown
        function PostProcessingFunctionDropDownValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.ManageChangeInPostProcessingFunction(POS);
        end

        % Button pushed function: 
        % SetnewPostProcessingoptionsfromTableButton
        function SetnewPostProcessingoptionsfromTableButtonPushed(app, event)
            app.SetnewPostProcessingoptionsfromTableButton.BackgroundColor=app.color.Wait; drawnow;
            Options=app.PostProcessingOptions.Data;
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).PostProcessingOptions=Options;
            app.ManagePostProcessingVisibilityAndUpdate(POS);
            app.SetnewPostProcessingoptionsfromTableButton.BackgroundColor=app.color.Idle;
        end

        % Button pushed function: newbaselinepersistentvarinitButton
        function newbaselinepersistentvarinitButtonPushed(app, event)
            app.newbaselinepersistentvarinitButton.BackgroundColor=app.color.Wait;
            POS=app.ProfileMontiorPositionDropDown.Value;
            TABLE=app.PostProcessingOptions.Data;
            Data=app.Profile(POS).PostProcessingFunction(lcaGetSmart(app.Profile(POS).PVName,prod(app.Profile(POS).size)),TABLE,app.Profile(POS),[],2);
            app.newbaselinepersistentvarinitButton.BackgroundColor=app.color.Idle;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch(selectedTab.Title)
                case 'Take Data'
                    app.UpdateRecordingSummary();
                case 'Setup Vector/Image Acquisition'
                    POS = app.ProfileMontiorPositionDropDown.Value;
                    app.ManageProfileButtonVisibility(POS);
                    app.ManageBackgroundVisibilityAndUpdate(POS);
                    app.ManagePostProcessingVisibilityAndUpdate(POS);
                case 'Setup Scalar Acquisition'
                    app.epv_MylistSync.Items=app.ScalarTables.SynchPV(:,1);
                    app.epv_MylistNotSync.Items=app.ScalarTables.SlowPV;
                    app.epv_MylistOnlyOnce.Items=app.ScalarTables.OnlyOncePV;
                    BsaLists=fieldnames(app.ScalarList.BSA_LIST);
                    BsaLists(:,2)={false};     
                    app.epv_SynchGroup.Data=BsaLists;
                    NonBsaLists=fieldnames(app.ScalarList.NON_BSA_LIST);
                    NonBsaLists(:,2:3)={false}; 
                    app.epv_AsynchGroup.Data=NonBsaLists;
                    app.FilterListDropDown.Items=app.ScalarGroups;
                    app.FilterListDropDown.ItemsData=1:length(app.ScalarGroups);
                    app.FilterListDropDown.Value=1;
                    app.ManageScalarList();
                case 'Stored CVCRCI Presets'
                case 'Load Stored Dataset'
                    app.InitializeLoadTab();
                otherwise
                    disp('App desinger does not allow to work this out by Tab number!')
            end
            
        end

        % Button pushed function: ClearBackgroundButton
        function ClearBackgroundButtonPushed(app, event)
            POS = app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).Background=[];
            app.ManageBackgroundVisibilityAndUpdate(POS);
        end

        % Value changed function: FilterListDropDown
        function FilterListDropDownValueChanged(app, event)
            app.ManageScalarList();
        end

        % Value changed function: P1
        function P1ValueChanged(app, event)
            app.searchPV();
        end

        % Button pushed function: AddListButton
        function AddListButtonPushed(app, event)
            if(isempty(app.ScalarTables.SynchPV))
                app.epv_MylistSync.Items=app.epv_FullList.Items;
                app.ScalarTables.SynchPV=app.epv_FullList.Items(:);
                app.ScalarTables.SynchPV(:,2:3)={[]};
            else
                NewElements=setdiff(app.epv_FullList.Items(:),app.ScalarTables.SynchPV(:,1),'stable');
                NewElements=NewElements(:);
                app.ScalarTables.SynchPV((end+1):(end+length(NewElements)),1)=NewElements;
                app.ScalarTables.SynchPV(:,2:3)={[]};
                app.epv_MylistSync.Items=[app.epv_MylistSync.Items(:);NewElements];
            end       
            app.updateListOfScanVariable();
        end

        % Button pushed function: AddSelectionButton
        function AddSelectionButtonPushed(app, event)
            NewElements=app.epv_FullList.Value; NewElements=NewElements(:);
            if(isempty(app.ScalarTables.SynchPV))
                app.epv_MylistSync.Items=NewElements;
                app.ScalarTables.SynchPV=NewElements;
                app.ScalarTables.SynchPV(:,2:3)={[]};
            else
                NewElements=setdiff(NewElements,app.ScalarTables.SynchPV(:,1),'stable');
                NewElements=NewElements(:);
                app.ScalarTables.SynchPV((end+1):(end+length(NewElements)),1)=NewElements;
                app.ScalarTables.SynchPV(:,2:3)={[]};
                app.epv_MylistSync.Items=[app.epv_MylistSync.Items(:);NewElements];
            end    
            app.updateListOfScanVariable();
        end

        % Button pushed function: RemoveListButton
        function RemoveListButtonPushed(app, event)
            SelectedElements=app.epv_FullList.Items; SelectedElements=SelectedElements(:);
            if(isempty(app.ScalarTables.SynchPV))
                return
            else
                [~,~,ToDeleteElements2]=intersect(SelectedElements,app.ScalarTables.SynchPV(:,1),'stable');
                app.epv_MylistSync.Items(ToDeleteElements2)=[];
                app.ScalarTables.SynchPV(ToDeleteElements2,:)=[];
            end 
            app.updateListOfScanVariable();
        end

        % Value changed function: P2
        function P2ValueChanged(app, event)
            app.searchPV();
        end

        % Value changed function: P3
        function P3ValueChanged(app, event)
            app.searchPV();
        end

        % Value changed function: P4
        function P4ValueChanged(app, event)
            app.searchPV();
        end

        % Button pushed function: RemovefromSynchronousButton
        function RemovefromSynchronousButtonPushed(app, event)
            SelectedElements=app.epv_MylistSync.Value; SelectedElements=SelectedElements(:);
            [~,~,ToDeleteElements2]=intersect(SelectedElements,app.ScalarTables.SynchPV(:,1),'stable');
            app.epv_MylistSync.Items(ToDeleteElements2)=[];
            app.ScalarTables.SynchPV(ToDeleteElements2,:)=[];
            app.updateListOfScanVariable();
        end

        % Button pushed function: AddtoSynchronousButton
        function AddtoSynchronousButtonPushed(app, event)
            NewElement=app.AddToSynchField.Value;
            [~,~,IntersectedElement]=intersect(NewElement,app.ScalarTables.SynchPV(:,1),'stable');
            if(isempty(IntersectedElement))
               app.ScalarTables.SynchPV{end+1,1}=NewElement;
               app.ScalarTables.SynchPV(end,2:3)={[]};
               app.epv_MylistSync.Items{end+1}=NewElement;
            end
            app.updateListOfScanVariable();
        end

        % Button pushed function: AddasSlowPVButton
        function AddasSlowPVButtonPushed(app, event)
            NewElement=app.AddtoSlowPVEdit.Value;
            if(isempty(app.ScalarTables.SlowPV))
                app.ScalarTables.SlowPV{1}=NewElement;
                app.epv_MylistNotSync.Items{1}=NewElement;
            else
                IntersectedElement=intersect(NewElement,app.ScalarTables.SlowPV,'stable');
                if(isempty(IntersectedElement))
                    app.ScalarTables.SlowPV{end+1}=NewElement;
                end
            end
            app.ScalarTables.SlowPV=app.ScalarTables.SlowPV.';
            app.epv_MylistNotSync.Items=app.ScalarTables.SlowPV;
            app.updateListOfScanVariable();
        end

        % Button pushed function: RemoveSlowPVButton
        function RemoveSlowPVButtonPushed(app, event)
            SelectedElements=app.epv_MylistNotSync.Value; SelectedElements=SelectedElements(:);
            [~,ToDeleteElements2,~]=intersect(app.ScalarTables.SlowPV,SelectedElements,'stable');
            app.ScalarTables.SlowPV(ToDeleteElements2)=[];
            app.epv_MylistNotSync.Items=app.ScalarTables.SlowPV;
            app.updateListOfScanVariable();
        end

        % Button pushed function: AddPVButton
        function AddPVButtonPushed(app, event)
            NewElement=app.OnlyOncePVEdit.Value;
            if(isempty(app.ScalarTables.OnlyOncePV))
                app.ScalarTables.OnlyOncePV{1}=NewElement;
                app.epv_MylistOnlyOnce.Items{1}=NewElement;
            else
                IntersectedElement=intersect(NewElement,app.ScalarTables.OnlyOncePV,'stable');
                if(isempty(IntersectedElement))
                    app.ScalarTables.OnlyOncePV{end+1}=NewElement;
                end
            end
            app.ScalarTables.OnlyOncePV=app.ScalarTables.OnlyOncePV(:);
           app.epv_MylistOnlyOnce.Items=app.ScalarTables.OnlyOncePV;
           app.updateListOfScanVariable();
        end

        % Button pushed function: RemovePVButton
        function RemovePVButtonPushed(app, event)
            SelectedElements=app.epv_MylistOnlyOnce.Value; SelectedElements=SelectedElements(:);
            [~,ToDeleteElements2,~]=intersect(app.ScalarTables.OnlyOncePV,SelectedElements,'stable');
            app.ScalarTables.OnlyOncePV(ToDeleteElements2)=[];
            app.epv_MylistOnlyOnce.Items(ToDeleteElements2)=app.ScalarTables.OnlyOncePV;
            app.updateListOfScanVariable();
        end

        % Button pushed function: ReplaceallandAddselectedButton
        function ReplaceallandAddselectedButtonPushed(app, event)
            AddLists=app.FindAddLists;
            app.ScalarTables.OnlyOncePV=AddLists.OnlyOncePV(:);
            app.ScalarTables.SlowPV=AddLists.SlowPV(:);
            app.ScalarTables.SynchPV=AddLists.SynchPV(:);
            app.ScalarTables.SynchPV(:,2:3)={[]};
            app.epv_MylistSync.Items=AddLists.SynchPV;
            app.epv_MylistNotSync.Items=AddLists.SlowPV;
            app.epv_MylistOnlyOnce.Items=AddLists.OnlyOncePV;
            app.updateListOfScanVariable();
        end

        % Button pushed function: AddliststoexistingPVsButton
        function AddliststoexistingPVsButtonPushed(app, event)
            AddLists=app.FindAddLists;
            NewElements=setdiff(AddLists.OnlyOncePV,app.ScalarTables.OnlyOncePV,'stable');
            if(~isempty(NewElements))
                app.ScalarTables.OnlyOncePV((end+1):(end+numel(NewElements)))=NewElements;
            end
            NewElements=setdiff(AddLists.SlowPV,app.ScalarTables.SlowPV,'stable');
            if(~isempty(NewElements))
                app.ScalarTables.SlowPV((end+1):(end+numel(NewElements)))=NewElements;
            end
            NewElements=setdiff(AddLists.SynchPV,app.ScalarTables.SynchPV(:,1),'stable');
            if(~isempty(NewElements))
                for II=1:numel(NewElements)
                    app.ScalarTables.SynchPV{end+1,1}=NewElements{II};
                end
                end
            app.ScalarTables.SlowPV=app.ScalarTables.SlowPV(:); app.ScalarTables.OnlyOncePV=app.ScalarTables.OnlyOncePV(:);
            app.epv_MylistSync.Items=app.ScalarTables.SynchPV(:,1);
            app.epv_MylistNotSync.Items=app.ScalarTables.SlowPV;
            app.epv_MylistOnlyOnce.Items=app.ScalarTables.OnlyOncePV;
            app.updateListOfScanVariable();
        end

        % Button pushed function: StorePresetinNewPositionButton
        function StorePresetinNewPositionButtonPushed(app, event)
            NewPresetName=app.CVCRCIPresetNameEditField.Value;
            app.Presets{end+1}.name=NewPresetName;
            app.Presets{end}.Variables.SynchPV=app.ScalarTables.SynchPV;
            app.Presets{end}.Variables.SlowPV=app.ScalarTables.SlowPV;
            app.Presets{end}.Variables.OnlyOncePV=app.ScalarTables.OnlyOncePV;
            app.Presets{end}.Profile=app.Profile;
            app.Presets{end}.AcquisitionSetup.BSA_Mode=app.BSA_Mode.Value;
            app.Presets{end}.AcquisitionSetup.Synch_Mode=app.Syncrhonization.Value;
            app.Presets{end}.AcquisitionSetup.BeamCode=app.BeamCode.Value;
            app.Presets{end}.AcquisitionSetup.BufferSize=app.BufferSizeEditField.Value;
            app.Presets{end}.AcquisitionSetup.BlockSize=app.BlockSizeEditField.Value;
            app.Presets{end}.AcquisitionSetup.BSATime=app.BSATimeEditField.Value;
            app.Presets{end}.AcquisitionSetup.BSAwait=app.BSAwaitEditField.Value;
            app.Presets{end}.AcquisitionSetup.Author=app.AuthorEditField.Value;
            app.Presets{end}.AcquisitionSetup.TimeReference=app.TimeEditField.Value;
            app.Presets{end}.AcquisitionSetup.ExitAtBufferFilled=app.StopBufferFilled.Value;
            app.Presets{end}.AcquisitionSetup.AtBufferFullFunction=app.IfFullDropDown.Value;
            app.Presets{end}.AcquisitionSetup.InsertedCounterVariableName=app.RelevantVariableName.Value;
            Preset=app.Presets;
            save([app.ConfigurationPath,'/CVCRCI_MAIN_CONFIGURATION_FILE.mat'],'Preset','-append');
            app.ManagePresetButtons();
        end

        % Button pushed function: 
        % StorePresetatexistingPositionasButton
        function StorePresetatexistingPositionasButtonPushed(app, event)
            NewPresetName=app.PresetNewnameEditField.Value;
            ID=app.PresetDropDown.Value;
            if(ID>=1)
                app.Presets{ID}.name=NewPresetName;
                app.Presets{ID}.Variables.SynchPV=app.ScalarTables.SynchPV;
                app.Presets{ID}.Variables.SlowPV=app.ScalarTables.SlowPV;
                app.Presets{ID}.Variables.OnlyOncePV=app.ScalarTables.OnlyOncePV;
                app.Presets{ID}.Profile=app.Profile;
                app.Presets{ID}.AcquisitionSetup.BSA_Mode=app.BSA_Mode.Value;
                app.Presets{ID}.AcquisitionSetup.Synch_Mode=app.Syncrhonization.Value;
                app.Presets{ID}.AcquisitionSetup.BeamCode=app.BeamCode.Value;
                app.Presets{ID}.AcquisitionSetup.BufferSize=app.BufferSizeEditField.Value;
                app.Presets{ID}.AcquisitionSetup.BlockSize=app.BlockSizeEditField.Value;
                app.Presets{ID}.AcquisitionSetup.BSATime=app.BSATimeEditField.Value;
                app.Presets{ID}.AcquisitionSetup.BSAwait=app.BSAwaitEditField.Value;
                app.Presets{ID}.AcquisitionSetup.Author=app.AuthorEditField.Value;
                app.Presets{ID}.AcquisitionSetup.TimeReference=app.TimeEditField.Value;
                app.Presets{ID}.AcquisitionSetup.ExitAtBufferFilled=app.StopBufferFilled.Value;
                app.Presets{ID}.AcquisitionSetup.AtBufferFullFunction=app.IfFullDropDown.Value;
                app.Presets{ID}.AcquisitionSetup.InsertedCounterVariableName=app.RelevantVariableName.Value;
                Preset=app.Presets;
                save([app.ConfigurationPath,'/CVCRCI_MAIN_CONFIGURATION_FILE.mat'],'Preset','-append');
                app.ManagePresetButtons();
            end
        end

        % Button pushed function: A10Button, A11Button, A12Button, 
        % A13Button, A14Button, A15Button, A16Button, A17Button, 
        % A18Button, A19Button, A1Button, A20Button, A21Button, 
        % A22Button, A23Button, A24Button, A25Button, A26Button, 
        % A27Button, A28Button, A29Button, A2Button, A30Button, 
        % A31Button, A32Button, A33Button, A34Button, A35Button, 
        % A36Button, A3Button, A4Button, A5Button, A6Button, 
        % A7Button, A8Button, A9Button
        function A1ButtonPushed(app, event)
            app.RestorePreset(event.Source.UserData);
            app.UpdateRecordingSummary();
            app.TabGroup.SelectedTab=app.TakeDataTab;
        end

        % Button pushed function: UNFREEZEButton
        function UNFREEZEButtonPushed(app, event)
            app.unfreeze();
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            if(all(app.PAUSEButton.BackgroundColor==app.color.Idle))
                app.PAUSEButton.BackgroundColor=app.color.On; drawnow;
            else
                app.PAUSEButton.BackgroundColor=app.color.Wait; drawnow;
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.STOPButton.BackgroundColor=app.color.On; drawnow;
        end

        % Selection changed function: ScanpresetsButtonGroup
        function ScanpresetsButtonGroupSelectionChanged(app, event)
            app.scanPresetPicked();
            app.evaluateScanSettingFromTable();
        end

        % Value changed function: ScanEnvironments
        function ScanEnvironmentsValueChanged(app, event)
            app.updatePresetListSelection();
            app.scanPresetPicked();
            app.evaluateScanSettingFromTable();
        end

        % Cell edit callback: ScanSetupTable
        function ScanSetupTableCellEdit(app, event)
            app.evaluateScanSettingFromTable();
        end

        % Button pushed function: STARTButtonSCAN
        function STARTButtonSCANPushed(app, event)
            app.STARTButtonSCAN.Enable='off'; app.STARTButtonSCAN.BackgroundColor=app.color.On;
            app.STOPButtonSCAN.Enable='on'; app.STOPButtonSCAN.BackgroundColor=app.color.Idle;
            app.PAUSEButtonSCAN.Enable='on'; app.STOPButtonSCAN.BackgroundColor=app.color.Idle;
            
            app.CV=CVCRCI_Class;
            InputStructure=app.ReadAllInputs();
            InputStructure.DoScan=1;
            InputStructure.ScanSetting=app.ScErr.UserData;
            if(app.Use_Lca_Monitor), InputStructure.UseLcaSetMonitor=1; end
            app.CV.setupInputStructure(InputStructure);
            app.CV.BuildAcquisitionStructure();
            app.CV.MakeAcquisitionBuffers();
            app.CV.zeroAllBuffers();
            app.CV.Initialize_BSA();
            if(app.CV.Acquisition.BSA==1)
                app.ReleaseeDefsButton.UserData=app.CV.myeDefNumber;
                app.ReleaseeDefsButton.BackgroundColor=app.color.On;
                app.ReleaseeDefsButton.Enable='on';
            end
            runcounter=0;
            app.InitializeAllDisplays(); %Initialize Graphics
            app.CV.InitializeScan();
            while(~app.CV.ScanState.Finished)
                app.CV.SetNextPosition();
                while(app.CV.ScanState.SamplesRecordedThisStep < app.CV.Acquisition.Scan.Samples)
                    STOP=app.STOPButtonSCAN.BackgroundColor; PAUSE=app.PAUSEButtonSCAN.BackgroundColor;
                    if(all(STOP==app.color.On))
                        app.unfreeze();
                        if(app.DumpatrecorderStopSCAN.Value)
                            [SUCCESS,filename,TargetDir] = app.writeDataToDisk(now,app.CV,[]);
                            if(SUCCESS)
                                disp(['Saved ',TargetDir,'/',filename]);
                            else
                                disp('Saving file on disk failed!')
                            end
                        end
                        return
                    end
                    if(all(PAUSE==app.color.On))
                        while(1)
                            disp('Acquisition is on hold');
                            pause(0.25);
                            PAUSE=app.PAUSEButtonSCAN.BackgroundColor;
                            if(all(PAUSE==app.color.Wait))
                                app.PAUSEButtonSCAN.BackgroundColor=app.color.Idle;
                                break
                            end
                        end
                        app.CV.InvalidateBSAScan();
                    end
                    app.CV.ContAcquisitionCycle();
                    app.CV.StateNumberScan();
                    app.ScanBufferUpdate.Text=app.CV.ScanState.PositionString;
                    app.ScanPoint.Text=app.CV.ScanState.StepPositionString; drawnow
                    runcounter=runcounter+1;
                    app.UpdateAllDisplays();
                end
                app.CV.IncrementScanPosition();
            end
            app.ScanBufferUpdate.Text='Finished.';
            app.PAUSEButtonSCAN.Enable='off';
            while(1)
                STOP=app.STOPButtonSCAN.BackgroundColor;
                if(all(STOP==app.color.On))
                    app.unfreeze();
                    if(app.DumpatrecorderStopSCAN.Value)
                        [SUCCESS,filename,TargetDir] = app.writeDataToDisk(now,app.CV,[]);
                        if(SUCCESS)
                            disp(['Saved ',TargetDir,'/',filename]);
                        else
                            disp('Saving file on disk failed!')
                        end
                    end
                    return
                end
                app.UpdateAllDisplays(); pause(0.15);
            end
        end

        % Button pushed function: PAUSEButtonSCAN
        function PAUSEButtonSCANPushed(app, event)
            if(all(app.PAUSEButtonSCAN.BackgroundColor==app.color.Idle))
                app.PAUSEButtonSCAN.BackgroundColor=app.color.On; drawnow;
            else
                app.PAUSEButtonSCAN.BackgroundColor=app.color.Wait; drawnow;
            end
        end

        % Button pushed function: STOPButtonSCAN
        function STOPButtonSCANPushed(app, event)
            app.STOPButtonSCAN.BackgroundColor=app.color.On; drawnow;
        end

        % Button pushed function: TodayButton
        function TodayButtonPushed(app, event)
            app.UpdateTargetDir(1);
            app.UpdateFileList();
        end

        % Button pushed function: pwdButton
        function pwdButtonPushed(app, event)
            app.UpdateTargetDir(0);
            app.UpdateFileList();
        end

        % Value changed function: AnnoEditField
        function AnnoEditFieldValueChanged(app, event)
            app.UpdateTargetDir(1);
            app.UpdateFileList();
        end

        % Value changed function: MonthEditField
        function MonthEditFieldValueChanged(app, event)
            app.UpdateTargetDir(1);
            app.UpdateFileList();
        end

        % Value changed function: DayEditField
        function DayEditFieldValueChanged(app, event)
            app.UpdateTargetDir(1);
            app.UpdateFileList();
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            app.UpdateFileList();
        end

        % Value changed function: FilterstringEditField
        function FilterstringEditFieldValueChanged(app, event)
            app.UpdateFileList();
        end

        % Button pushed function: UiGetFileButton
        function UiGetFileButtonPushed(app, event)
            [FILENAME, PATHNAME] = uigetfile([app.FolderEditField.Value,'/',app.FilterstringEditField.Value], 'Take a data file.');
            if(FILENAME)
                app.FolderEditField.Value=PATHNAME;
                app.FilterstringEditField.Value=FILENAME;
            end
            app.UpdateFileList();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            PATHNAME=app.FolderEditField.Value;
            FILENAME=app.Filenames.Value;
            app.CV=CVCRCI_Class;
            app.CV.LoadFile([PATHNAME,'/',FILENAME]);
            DataXX={};
            for II=1:numel(app.CV.Recorder.Data)
                for JJ=1:3
                    DataXX{JJ,II}=size(app.CV.Recorder.Data{II},JJ); %#ok<AGROW> 
                end
            end
            set(app.DataFound,'data',DataXX);
            app.InitializeAllDisplays();
            app.VariablesListBox.Items=app.CV.Acquisition.VarNames;
            app.UpdateReplayState();
        end

        % Button pushed function: AddoneVariableButton
        function AddoneVariableButtonPushed(app, event)
            app.ScanSetupTable.Data(:,end+1)=app.ScanSetupTable.Data(:,end);
        end

        % Button pushed function: UpdateOnceButton
        function UpdateOnceButtonPushed(app, event)
            app.UpdateAllDisplays();
            app.UpdateReplayState();
        end

        % Button pushed function: StopUpdateButton
        function StopUpdateButtonPushed(app, event)
            app.StopUpdateButton.BackgroundColor=app.color.On; drawnow;
        end

        % Button pushed function: RunUpdateButton
        function RunUpdateButtonPushed(app, event)
            while(1)
                if(all(app.StopUpdateButton.BackgroundColor==app.color.On))
                    app.StopUpdateButton.BackgroundColor=app.color.Idle;
                    return
                end
                app.CV.CycleEvent();
                app.UpdateReplayState();
                app.UpdateAllDisplays();Pvalue=app.pauseEditField.Value;
                pause(Pvalue);
            end
        end

        % Button pushed function: ReloadLastBackgroundButton
        function ReloadLastBackgroundButtonPushed(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            profile=app.Profile(POS).PVName;
            Filename=regexprep(regexprep(profile,' ',''),':','-');
            load([app.ConfigurationPath,'/BackgroundAndBaseline/',Filename],'BackgroundAverage');
            app.Profile(POS).Background=BackgroundAverage;
            app.ManageBackgroundVisibilityAndUpdate(POS);
        end

        % Value changed function: PresetDropDown
        function PresetDropDownValueChanged(app, event)
            app.PresetNewnameEditField.Value=app.PresetDropDown.Items{app.PresetDropDown.Value};
        end

        % Button pushed function: FullSetButton
        function FullSetButtonPushed(app, event)
            app.CV.RestorePristineState();
            app.UpdateReplayState();
        end

        % Button pushed function: SETFromScanPositionButton
        function SETFromScanPositionButtonPushed(app, event)
            ScanSetting=app.ScErr.UserData;
            TABLE=app.UITable12;
            CONDITIONS=[TABLE{:,2}];
            [~,SCANPOSITION]=min(sum(abs(ScanSetting.ConditionsTable-repmat(CONDITIONS.',[1,size(ScanSetting.PVValues,2)]))));
            ScanSetting.Scan.Functions.BeforeSettingNewValue(ScanSetting);
            ScanSetting.Scan.Functions.SetValue(ScanSetting,SCANPOSITION,0);
            ScanSetting.Scan.Functions.AfterSettingNewValue(ScanSetting);
        end

        % Cell edit callback: UITable12
        function UITable12CellEdit(app, event)
            app.UpdateSecondaryScanTable();
        end

        % Button pushed function: SETFromPVPointsButton
        function SETFromPVPointsButtonPushed(app, event)
            TABLE=app.UITable13.Data;
            for II=1:size(TABLE,1)
                lcaPutNoWait(TABLE{II,1},TABLE{II,2});
            end
        end

        % Cell edit callback: UITable13
        function UITable13CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell edit callback: SynchPV
        function SynchPVCellEdit(app, event)
            app.ScalarTables.SynchPV=app.SynchPV.Data;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CVCRCIUIFigure = uifigure('Visible', 'off');
            app.CVCRCIUIFigure.Name = 'CVCRCI';
            app.C1Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C1Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C1Button.Text = 'C1';
            app.C2Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C2Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C2Button.Text = 'C2';
            app.C3Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C3Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C3Button.Text = 'C3';
            app.C4Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C4Button.Text = 'C4';
            app.C5Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C5Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C5Button.Text = 'C5';
            app.C6Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C6Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C6Button.Text = 'C6';
            app.C7Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C7Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C7Button.Text = 'C7';
            app.C8Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C8Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C8Button.Text = 'C8';
            app.C9Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C9Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C9Button.Text = 'C9';
            app.C10Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C10Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C10Button.Text = 'C10';
            app.C11Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C11Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C11Button.Text = 'C11';
            app.C12Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C12Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C12Button.Text = 'C12';
            app.C13Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C13Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C13Button.Text = 'C13';
            app.C14Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C14Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C14Button.Text = 'C14';
            app.C15Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C15Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C15Button.Text = 'C15';
            app.C16Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C16Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C16Button.Text = 'C16';
            app.UpdateDisplaysButton = uibutton(app.CVCRCIUIFigure, 'push');
            app.UpdateDisplaysButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDisplaysButtonPushed, true);
            app.UpdateDisplaysButton.Text = 'Update Displays';
            app.SetSomeDataButton = uibutton(app.CVCRCIUIFigure, 'push');
            app.SetSomeDataButton.ButtonPushedFcn = createCallbackFcn(app, @SetSomeDataButtonPushed, true);
            app.SetSomeDataButton.Text = 'SetSomeData';
            app.TabGroup = uitabgroup(app.CVCRCIUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TakeDataTab = uitab(app.TabGroup);
            app.TakeDataTab.Title = 'Take Data';
            app.ScansetupPanel = uipanel(app.TakeDataTab);
            app.ScansetupPanel.Title = 'Scan setup';
            app.ScanSetupTable = uitable(app.ScansetupPanel);
            app.ScanSetupTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ScanSetupTable.RowName = {};
            app.ScanSetupTable.CellEditCallback = createCallbackFcn(app, @ScanSetupTableCellEdit, true);
            app.ResetStandardScanButton = uibutton(app.ScansetupPanel, 'push');
            app.ResetStandardScanButton.Text = 'Reset Standard Scan';
            app.AddoneVariableButton = uibutton(app.ScansetupPanel, 'push');
            app.AddoneVariableButton.ButtonPushedFcn = createCallbackFcn(app, @AddoneVariableButtonPushed, true);
            app.AddoneVariableButton.Text = 'Add one Variable';
            app.RemoveoneVariableButton = uibutton(app.ScansetupPanel, 'push');
            app.RemoveoneVariableButton.Text = 'Remove one Variable';
            app.SortingorderButtonGroup = uibuttongroup(app.ScansetupPanel);
            app.SortingorderButtonGroup.Title = 'Sorting order';
            app.NormalButton = uiradiobutton(app.SortingorderButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.ZigZagButton = uiradiobutton(app.SortingorderButtonGroup);
            app.ZigZagButton.Text = 'Zig-Zag';
            app.SamplesEditFieldLabel = uilabel(app.ScansetupPanel);
            app.SamplesEditFieldLabel.Text = 'Samples #';
            app.SamplesEditField = uieditfield(app.ScansetupPanel, 'numeric');
            app.SamplesEditField.Value = 180;
            app.ScanpresetsButtonGroup = uibuttongroup(app.ScansetupPanel);
            app.ScanpresetsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScanpresetsButtonGroupSelectionChanged, true);
            app.ScanpresetsButtonGroup.Title = 'Scan presets:';
            app.S10 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S10.Text = 'Button';
            app.S10.Value = true;
            app.S11 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S11.Text = 'Button2';
            app.S12 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S12.Text = 'Button3';
            app.S7 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S7.Text = 'Button';
            app.S8 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S8.Text = 'Button2';
            app.S9 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S9.Text = 'Button3';
            app.S6 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S6.Text = 'Button3';
            app.S5 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S5.Text = 'Button2';
            app.S4 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S4.Text = 'Button';
            app.S3 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S3.Text = 'Button3';
            app.S2 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S2.Text = 'Button2';
            app.S1 = uitogglebutton(app.ScanpresetsButtonGroup);
            app.S1.Text = 'Button';
            app.UITable12 = uitable(app.ScansetupPanel);
            app.UITable12.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable12.RowName = {};
            app.UITable12.CellEditCallback = createCallbackFcn(app, @UITable12CellEdit, true);
            app.UITable13 = uitable(app.ScansetupPanel);
            app.UITable13.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable13.RowName = {};
            app.UITable13.CellEditCallback = createCallbackFcn(app, @UITable13CellEdit, true);
            app.SETFromScanPositionButton = uibutton(app.ScansetupPanel, 'push');
            app.SETFromScanPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SETFromScanPositionButtonPushed, true);
            app.SETFromScanPositionButton.Text = 'SET From Scan Position';
            app.PresetslistLabel = uilabel(app.ScansetupPanel);
            app.PresetslistLabel.Text = 'Presets list';
            app.ScanEnvironments = uidropdown(app.ScansetupPanel);
            app.ScanEnvironments.ValueChangedFcn = createCallbackFcn(app, @ScanEnvironmentsValueChanged, true);
            app.ScErr = uilabel(app.ScansetupPanel);
            app.ScErr.Text = '';
            app.SETFromPVPointsButton = uibutton(app.ScansetupPanel, 'push');
            app.SETFromPVPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SETFromPVPointsButtonPushed, true);
            app.SETFromPVPointsButton.Text = 'SET From PV Points';
            app.AcquisitionVariableSummaryPanel = uipanel(app.TakeDataTab);
            app.AcquisitionVariableSummaryPanel.Title = 'Acquisition Variable Summary';
            app.SynchPV = uitable(app.AcquisitionVariableSummaryPanel);
            app.SynchPV.ColumnName = {'Synchronous (Timestamped) PVs'; 'Min'; 'Max'};
            app.SynchPV.RowName = {};
            app.SynchPV.ColumnEditable = [false true true];
            app.SynchPV.CellEditCallback = createCallbackFcn(app, @SynchPVCellEdit, true);
            app.SlowPV = uitable(app.AcquisitionVariableSummaryPanel);
            app.SlowPV.ColumnName = {'Slowly updating PVs (once per cycle)'};
            app.SlowPV.RowName = {};
            app.OnlyOncePV = uitable(app.AcquisitionVariableSummaryPanel);
            app.OnlyOncePV.ColumnName = {'Only once recorded PVs (at acquisition start)'};
            app.OnlyOncePV.RowName = {};
            app.ProfileMonitorPV = uitable(app.AcquisitionVariableSummaryPanel);
            app.ProfileMonitorPV.ColumnName = {'Waveform PV recorded'};
            app.ProfileMonitorPV.RowName = {};
            app.CounterVariableLabel = uilabel(app.AcquisitionVariableSummaryPanel);
            app.CounterVariableLabel.Text = 'Counter Variable';
            app.RelevantVariableName = uidropdown(app.AcquisitionVariableSummaryPanel);
            app.RelevantVariablePosition = uilabel(app.AcquisitionVariableSummaryPanel);
            app.RelevantVariablePosition.Text = {'(h,j,k)'; ''};
            app.SynchronizationandBufferPanel = uipanel(app.TakeDataTab);
            app.SynchronizationandBufferPanel.Title = 'Synchronization and Buffer';
            app.BSA_Mode = uidropdown(app.SynchronizationandBufferPanel);
            app.BSA_Mode.Items = {'No BSA', 'Double Buffer BSA', 'History Buffer BSA'};
            app.BSA_Mode.Value = 'No BSA';
            app.Syncrhonization = uidropdown(app.SynchronizationandBufferPanel);
            app.Syncrhonization.Items = {'Synchronous Mode', 'Asynchronous Mode'};
            app.Syncrhonization.Value = 'Synchronous Mode';
            app.BeamCode = uidropdown(app.SynchronizationandBufferPanel);
            app.BeamCode.Items = {'All 120 Hz', 'HXR Branch only', 'SXR Branch only'};
            app.BeamCode.Value = 'All 120 Hz';
            app.BufferSizeEditFieldLabel = uilabel(app.SynchronizationandBufferPanel);
            app.BufferSizeEditFieldLabel.Text = 'Buffer Size';
            app.BufferSizeEditField = uieditfield(app.SynchronizationandBufferPanel, 'numeric');
            app.BufferSizeEditField.Value = 1571;
            app.BlockSizeEditFieldLabel = uilabel(app.SynchronizationandBufferPanel);
            app.BlockSizeEditFieldLabel.Text = 'Block Size';
            app.BlockSizeEditField = uieditfield(app.SynchronizationandBufferPanel, 'numeric');
            app.BlockSizeEditField.Value = 75;
            app.BSATimeEditFieldLabel = uilabel(app.SynchronizationandBufferPanel);
            app.BSATimeEditFieldLabel.Text = 'BSA Time';
            app.BSATimeEditField = uieditfield(app.SynchronizationandBufferPanel, 'numeric');
            app.BSATimeEditField.Value = 0.5;
            app.BSAwaitLabel = uilabel(app.SynchronizationandBufferPanel);
            app.BSAwaitLabel.Text = 'BSA + wait';
            app.BSAwaitEditField = uieditfield(app.SynchronizationandBufferPanel, 'numeric');
            app.AuthorEditFieldLabel = uilabel(app.SynchronizationandBufferPanel);
            app.AuthorEditFieldLabel.Text = 'Author';
            app.AuthorEditField = uieditfield(app.SynchronizationandBufferPanel, 'text');
            app.AuthorEditField.Value = 'Matlab';
            app.TimeEditFieldLabel = uilabel(app.SynchronizationandBufferPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.SynchronizationandBufferPanel, 'text');
            app.TimeEditField.Value = 'GDET:FEE1:241:ENRC';
            app.StopBufferFilled = uicheckbox(app.SynchronizationandBufferPanel);
            app.StopBufferFilled.Text = 'Stop at buffer filled';
            app.IfFullDropDownLabel = uilabel(app.SynchronizationandBufferPanel);
            app.IfFullDropDownLabel.Text = 'If Full';
            app.IfFullDropDown = uidropdown(app.SynchronizationandBufferPanel);
            app.IfFullDropDown.Items = {'DoNothing', 'SaveOnDisk'};
            app.IfFullDropDown.Value = 'DoNothing';
            app.ScanOrContinuous = uitabgroup(app.TakeDataTab);
            app.ScanOrContinuous.SelectionChangedFcn = createCallbackFcn(app, @ScanOrContinuousSelectionChanged, true);
            app.ContinuousTab = uitab(app.ScanOrContinuous);
            app.ContinuousTab.Title = 'Continuous';
            app.STARTButton = uibutton(app.ContinuousTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.PAUSEButton = uibutton(app.ContinuousTab, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.STOPButton = uibutton(app.ContinuousTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.DumpatrecorderStopCONT = uicheckbox(app.ContinuousTab);
            app.DumpatrecorderStopCONT.Text = 'Dump at recorder Stop';
            app.BufferUpdate = uilabel(app.ContinuousTab);
            app.BufferUpdate.Text = '...';
            app.AliveString = uilabel(app.ContinuousTab);
            app.AliveString.Text = '...';
            app.ScanTab = uitab(app.ScanOrContinuous);
            app.ScanTab.Title = 'Scan';
            app.STARTButtonSCAN = uibutton(app.ScanTab, 'push');
            app.STARTButtonSCAN.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonSCANPushed, true);
            app.STARTButtonSCAN.Text = {'START SCAN'; ''};
            app.PAUSEButtonSCAN = uibutton(app.ScanTab, 'push');
            app.PAUSEButtonSCAN.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonSCANPushed, true);
            app.PAUSEButtonSCAN.Text = 'PAUSE';
            app.STOPButtonSCAN = uibutton(app.ScanTab, 'push');
            app.STOPButtonSCAN.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonSCANPushed, true);
            app.STOPButtonSCAN.Text = 'STOP SCAN';
            app.DumpatrecorderStopSCAN = uicheckbox(app.ScanTab);
            app.DumpatrecorderStopSCAN.Text = 'Dump at recorder Stop';
            app.ScanBufferUpdate = uilabel(app.ScanTab);
            app.ScanBufferUpdate.Text = '...';
            app.ScanPoint = uilabel(app.ScanTab);
            app.ScanPoint.Text = '...';
            app.SetupScalarAcquisitionTab = uitab(app.TabGroup);
            app.SetupScalarAcquisitionTab.Title = 'Setup Scalar Acquisition';
            app.P1 = uieditfield(app.SetupScalarAcquisitionTab, 'text');
            app.P1.ValueChangedFcn = createCallbackFcn(app, @P1ValueChanged, true);
            app.P2 = uieditfield(app.SetupScalarAcquisitionTab, 'text');
            app.P2.ValueChangedFcn = createCallbackFcn(app, @P2ValueChanged, true);
            app.P4 = uieditfield(app.SetupScalarAcquisitionTab, 'text');
            app.P4.ValueChangedFcn = createCallbackFcn(app, @P4ValueChanged, true);
            app.P3 = uieditfield(app.SetupScalarAcquisitionTab, 'text');
            app.P3.ValueChangedFcn = createCallbackFcn(app, @P3ValueChanged, true);
            app.epv_FullList = uilistbox(app.SetupScalarAcquisitionTab);
            app.epv_FullList.Items = {'SIOC:SYS0:ML02:AO325', 'Item 2', 'Item 3', 'Item 4'};
            app.epv_FullList.Multiselect = 'on';
            app.epv_FullList.Value = {'SIOC:SYS0:ML02:AO325'};
            app.AddListButton = uibutton(app.SetupScalarAcquisitionTab, 'push');
            app.AddListButton.ButtonPushedFcn = createCallbackFcn(app, @AddListButtonPushed, true);
            app.AddListButton.Text = 'Add List';
            app.RemoveListButton = uibutton(app.SetupScalarAcquisitionTab, 'push');
            app.RemoveListButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveListButtonPushed, true);
            app.RemoveListButton.Text = 'Remove List';
            app.Label = uilabel(app.SetupScalarAcquisitionTab);
            app.Label.Text = ':';
            app.Label_2 = uilabel(app.SetupScalarAcquisitionTab);
            app.Label_2.Text = ':';
            app.Label_3 = uilabel(app.SetupScalarAcquisitionTab);
            app.Label_3.Text = ':';
            app.epv_MylistSync = uilistbox(app.SetupScalarAcquisitionTab);
            app.epv_MylistSync.Items = {'SIOC:SYS0:ML02:AO325', 'Item 2', 'Item 3', 'Item 4'};
            app.epv_MylistSync.Multiselect = 'on';
            app.epv_MylistSync.Value = {'SIOC:SYS0:ML02:AO325'};
            app.RemovefromSynchronousButton = uibutton(app.SetupScalarAcquisitionTab, 'push');
            app.RemovefromSynchronousButton.ButtonPushedFcn = createCallbackFcn(app, @RemovefromSynchronousButtonPushed, true);
            app.RemovefromSynchronousButton.Text = 'Remove from Synchronous';
            app.AddtoSynchronousButton = uibutton(app.SetupScalarAcquisitionTab, 'push');
            app.AddtoSynchronousButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoSynchronousButtonPushed, true);
            app.AddtoSynchronousButton.Text = 'Add to Synchronous';
            app.AddToSynchField = uieditfield(app.SetupScalarAcquisitionTab, 'text');
            app.SynchronousPVsLabel = uilabel(app.SetupScalarAcquisitionTab);
            app.SynchronousPVsLabel.Text = 'Synchronous PVs';
            app.epv_MylistNotSync = uilistbox(app.SetupScalarAcquisitionTab);
            app.epv_MylistNotSync.Items = {'SIOC:SYS0:ML02:AO325', 'Item 2', 'Item 3', 'Item 4'};
            app.epv_MylistNotSync.Multiselect = 'on';
            app.epv_MylistNotSync.Value = {'SIOC:SYS0:ML02:AO325'};
            app.SlowPVsListLabel = uilabel(app.SetupScalarAcquisitionTab);
            app.SlowPVsListLabel.Text = 'Slow PVs List';
            app.AddtoSlowPVEdit = uieditfield(app.SetupScalarAcquisitionTab, 'text');
            app.AddasSlowPVButton = uibutton(app.SetupScalarAcquisitionTab, 'push');
            app.AddasSlowPVButton.ButtonPushedFcn = createCallbackFcn(app, @AddasSlowPVButtonPushed, true);
            app.AddasSlowPVButton.Text = 'Add as Slow PV';
            app.RemoveSlowPVButton = uibutton(app.SetupScalarAcquisitionTab, 'push');
            app.RemoveSlowPVButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSlowPVButtonPushed, true);
            app.RemoveSlowPVButton.Text = 'Remove Slow PV';
            app.AddPVButton = uibutton(app.SetupScalarAcquisitionTab, 'push');
            app.AddPVButton.ButtonPushedFcn = createCallbackFcn(app, @AddPVButtonPushed, true);
            app.AddPVButton.Text = 'Add PV';
            app.RemovePVButton = uibutton(app.SetupScalarAcquisitionTab, 'push');
            app.RemovePVButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePVButtonPushed, true);
            app.RemovePVButton.Text = 'Remove PV';
            app.OnlyOncePVEdit = uieditfield(app.SetupScalarAcquisitionTab, 'text');
            app.epv_MylistOnlyOnce = uilistbox(app.SetupScalarAcquisitionTab);
            app.epv_MylistOnlyOnce.Items = {'SIOC:SYS0:ML02:AO325', 'Item 2', 'Item 3', 'Item 4'};
            app.epv_MylistOnlyOnce.Multiselect = 'on';
            app.epv_MylistOnlyOnce.Value = {'SIOC:SYS0:ML02:AO325'};
            app.RecordoncePVsListLabel = uilabel(app.SetupScalarAcquisitionTab);
            app.RecordoncePVsListLabel.Text = 'Record once PVs List';
            app.epv_SynchGroup = uitable(app.SetupScalarAcquisitionTab);
            app.epv_SynchGroup.ColumnName = {'Group of PVs'; 'synch'};
            app.epv_SynchGroup.RowName = {};
            app.epv_SynchGroup.ColumnEditable = [false true];
            app.epv_AsynchGroup = uitable(app.SetupScalarAcquisitionTab);
            app.epv_AsynchGroup.ColumnName = {'Group of PVs'; 'slow'; 'once'};
            app.epv_AsynchGroup.RowName = {};
            app.epv_AsynchGroup.ColumnEditable = [false true true];
            app.ReplaceallandAddselectedButton = uibutton(app.SetupScalarAcquisitionTab, 'push');
            app.ReplaceallandAddselectedButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceallandAddselectedButtonPushed, true);
            app.ReplaceallandAddselectedButton.Text = 'Replace all and Add selected';
            app.AddliststoexistingPVsButton = uibutton(app.SetupScalarAcquisitionTab, 'push');
            app.AddliststoexistingPVsButton.ButtonPushedFcn = createCallbackFcn(app, @AddliststoexistingPVsButtonPushed, true);
            app.AddliststoexistingPVsButton.Text = 'Add lists to existing PVs';
            app.FilterListDropDownLabel = uilabel(app.SetupScalarAcquisitionTab);
            app.FilterListDropDownLabel.Text = 'Filter List';
            app.FilterListDropDown = uidropdown(app.SetupScalarAcquisitionTab);
            app.FilterListDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterListDropDownValueChanged, true);
            app.AddSelectionButton = uibutton(app.SetupScalarAcquisitionTab, 'push');
            app.AddSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSelectionButtonPushed, true);
            app.AddSelectionButton.Text = 'Add Selection';
            app.UselcaGetinsteadoflcaGetSmartCheckBox = uicheckbox(app.SetupScalarAcquisitionTab);
            app.UselcaGetinsteadoflcaGetSmartCheckBox.Text = 'Use lcaGet instead of lcaGetSmart';
            app.UselcaGetinsteadoflcaGetSmartCheckBox.Value = true;
            app.SetupVectorImageAcquisitionTab = uitab(app.TabGroup);
            app.SetupVectorImageAcquisitionTab.Title = 'Setup Vector/Image Acquisition';
            app.BackgroundAxes = uiaxes(app.SetupVectorImageAcquisitionTab);
            title(app.BackgroundAxes, 'No recorded Background present')
            xlabel(app.BackgroundAxes, 'X')
            ylabel(app.BackgroundAxes, 'Y')
            zlabel(app.BackgroundAxes, 'Z')
            app.BackgroundAxesReshape = uiaxes(app.SetupVectorImageAcquisitionTab);
            title(app.BackgroundAxesReshape, 'No recorded Background present')
            xlabel(app.BackgroundAxesReshape, 'X')
            ylabel(app.BackgroundAxesReshape, 'Y')
            zlabel(app.BackgroundAxesReshape, 'Z')
            app.ProfileMontiorPositionDropDownLabel = uilabel(app.SetupVectorImageAcquisitionTab);
            app.ProfileMontiorPositionDropDownLabel.Text = 'Profile Montior Position';
            app.ProfileMontiorPositionDropDown = uidropdown(app.SetupVectorImageAcquisitionTab);
            app.ProfileMontiorPositionDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileMontiorPositionDropDownValueChanged, true);
            app.ProfilemonitornameDropDownLabel = uilabel(app.SetupVectorImageAcquisitionTab);
            app.ProfilemonitornameDropDownLabel.Text = 'Profile monitor name';
            app.ProfilemonitornameDropDown = uidropdown(app.SetupVectorImageAcquisitionTab);
            app.ProfilemonitornameDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfilemonitornameDropDownValueChanged, true);
            app.ProfileFullPVName = uilabel(app.SetupVectorImageAcquisitionTab);
            app.TimestampedCheckBox = uicheckbox(app.SetupVectorImageAcquisitionTab);
            app.TimestampedCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimestampedCheckBoxValueChanged, true);
            app.TimestampedCheckBox.Text = 'Timestamped';
            app.GetSizeButton = uibutton(app.SetupVectorImageAcquisitionTab, 'push');
            app.GetSizeButton.ButtonPushedFcn = createCallbackFcn(app, @GetSizeButtonPushed, true);
            app.GetSizeButton.Text = 'Get Size';
            app.BackgroundSamplesEditFieldLabel = uilabel(app.SetupVectorImageAcquisitionTab);
            app.BackgroundSamplesEditFieldLabel.Text = 'Background Samples';
            app.BackgroundSamplesEditField = uieditfield(app.SetupVectorImageAcquisitionTab, 'numeric');
            app.BackgroundSamplesEditField.Value = 100;
            app.SizeYEditFieldLabel = uilabel(app.SetupVectorImageAcquisitionTab);
            app.SizeYEditFieldLabel.Text = 'Size Y';
            app.SizeYEditField = uieditfield(app.SetupVectorImageAcquisitionTab, 'numeric');
            app.SizeYEditField.ValueChangedFcn = createCallbackFcn(app, @SizeYEditFieldValueChanged, true);
            app.ReadsingleprofileButton = uibutton(app.SetupVectorImageAcquisitionTab, 'push');
            app.ReadsingleprofileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadsingleprofileButtonPushed, true);
            app.ReadsingleprofileButton.Text = 'Read single profile';
            app.PIDoffsetEditFieldLabel = uilabel(app.SetupVectorImageAcquisitionTab);
            app.PIDoffsetEditFieldLabel.Text = 'PID offset';
            app.PIDoffsetEditField = uieditfield(app.SetupVectorImageAcquisitionTab, 'numeric');
            app.PIDoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @PIDoffsetEditFieldValueChanged, true);
            app.AsynchronousvariableBufferSizeEditFieldLabel = uilabel(app.SetupVectorImageAcquisitionTab);
            app.AsynchronousvariableBufferSizeEditFieldLabel.Text = 'Asynchronous variable Buffer Size';
            app.AsynchronousvariableBufferSizeEditField = uieditfield(app.SetupVectorImageAcquisitionTab, 'numeric');
            app.AsynchronousvariableBufferSizeEditField.ValueChangedFcn = createCallbackFcn(app, @AsynchronousvariableBufferSizeEditFieldValueChanged, true);
            app.DoPostprocessingCheckBox = uicheckbox(app.SetupVectorImageAcquisitionTab);
            app.DoPostprocessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoPostprocessingCheckBoxValueChanged, true);
            app.DoPostprocessingCheckBox.Text = 'Do Post processing (Besides background subtraction)';
            app.PostProcessingFunctionDropDownLabel = uilabel(app.SetupVectorImageAcquisitionTab);
            app.PostProcessingFunctionDropDownLabel.Text = 'Post Processing Function';
            app.PostProcessingFunctionDropDown = uidropdown(app.SetupVectorImageAcquisitionTab);
            app.PostProcessingFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @PostProcessingFunctionDropDownValueChanged, true);
            app.PostProcessingStatus = uitable(app.SetupVectorImageAcquisitionTab);
            app.PostProcessingStatus.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PostProcessingStatus.RowName = {};
            app.SetnewPostProcessingoptionsfromTableButton = uibutton(app.SetupVectorImageAcquisitionTab, 'push');
            app.SetnewPostProcessingoptionsfromTableButton.ButtonPushedFcn = createCallbackFcn(app, @SetnewPostProcessingoptionsfromTableButtonPushed, true);
            app.SetnewPostProcessingoptionsfromTableButton.Text = {'Set new Post-Processing '; 'options from Table'};
            app.PostProcessingOptions = uitable(app.SetupVectorImageAcquisitionTab);
            app.PostProcessingOptions.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.PostProcessingOptions.RowName = {};
            app.PostProcessingOptions.ColumnEditable = [true true true];
            app.newbaselinepersistentvarinitButton = uibutton(app.SetupVectorImageAcquisitionTab, 'push');
            app.newbaselinepersistentvarinitButton.ButtonPushedFcn = createCallbackFcn(app, @newbaselinepersistentvarinitButtonPushed, true);
            app.newbaselinepersistentvarinitButton.Text = {'new baseline / persistent var init'; ''};
            app.GetBackgroundButton = uibutton(app.SetupVectorImageAcquisitionTab, 'push');
            app.GetBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @GetBackgroundButtonPushed, true);
            app.GetBackgroundButton.Text = 'Get Background';
            app.SizeXEditField_2Label = uilabel(app.SetupVectorImageAcquisitionTab);
            app.SizeXEditField_2Label.Text = 'Size X';
            app.SizeXEditField = uieditfield(app.SetupVectorImageAcquisitionTab, 'numeric');
            app.SizeXEditField.ValueChangedFcn = createCallbackFcn(app, @SizeXEditFieldValueChanged, true);
            app.ReloadLastBackgroundButton = uibutton(app.SetupVectorImageAcquisitionTab, 'push');
            app.ReloadLastBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadLastBackgroundButtonPushed, true);
            app.ReloadLastBackgroundButton.Text = 'Reload Last Background';
            app.ClearBackgroundButton = uibutton(app.SetupVectorImageAcquisitionTab, 'push');
            app.ClearBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBackgroundButtonPushed, true);
            app.ClearBackgroundButton.Text = 'Clear Background';
            app.ListaBackground = uilabel(app.SetupVectorImageAcquisitionTab);
            app.ListaBackground.Text = '...';
            app.StoredCVCRCIPresetsTab = uitab(app.TabGroup);
            app.StoredCVCRCIPresetsTab.Title = 'Stored CVCRCI Presets';
            app.A1Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A1Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A1Button.Text = 'A1';
            app.A7Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A7Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A7Button.Text = 'A7';
            app.A13Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A13Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A13Button.Text = 'A13';
            app.A14Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A14Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A14Button.Text = 'A14';
            app.A8Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A8Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A8Button.Text = 'A8';
            app.A2Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A2Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A2Button.Text = 'A2';
            app.A3Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A3Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A3Button.Text = 'A3';
            app.A9Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A9Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A9Button.Text = 'A9';
            app.A15Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A15Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A15Button.Text = 'A15';
            app.A16Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A16Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A16Button.Text = 'A16';
            app.A10Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A10Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A10Button.Text = 'A10';
            app.A4Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A4Button.Text = 'A4';
            app.A5Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A5Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A5Button.Text = 'A5';
            app.A11Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A11Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A11Button.Text = 'A11';
            app.A17Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A17Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A17Button.Text = 'A17';
            app.A18Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A18Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A18Button.Text = 'A18';
            app.A12Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A12Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A12Button.Text = 'A12';
            app.A6Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A6Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A6Button.Text = 'A6';
            app.A36Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A36Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A36Button.Text = 'A36';
            app.A35Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A35Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A35Button.Text = 'A35';
            app.A34Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A34Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A34Button.Text = 'A34';
            app.A33Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A33Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A33Button.Text = 'A33';
            app.A30Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A30Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A30Button.Text = 'A30';
            app.A29Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A29Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A29Button.Text = 'A29';
            app.A28Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A28Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A28Button.Text = 'A28';
            app.A27Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A27Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A27Button.Text = 'A27';
            app.A32Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A32Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A32Button.Text = 'A32';
            app.A26Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A26Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A26Button.Text = 'A26';
            app.A31Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A31Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A31Button.Text = 'A31';
            app.A25Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A25Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A25Button.Text = 'A25';
            app.A24Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A24Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A24Button.Text = 'A24';
            app.A23Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A23Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A23Button.Text = 'A23';
            app.A22Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A22Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A22Button.Text = 'A22';
            app.A21Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A21Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A21Button.Text = 'A21';
            app.A20Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A20Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A20Button.Text = 'A20';
            app.A19Button = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.A19Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A19Button.Text = 'A19';
            app.CVCRCIPresetNameEditFieldLabel = uilabel(app.StoredCVCRCIPresetsTab);
            app.CVCRCIPresetNameEditFieldLabel.Text = 'CVCRCI Preset Name';
            app.CVCRCIPresetNameEditField = uieditfield(app.StoredCVCRCIPresetsTab, 'text');
            app.StorePresetinNewPositionButton = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.StorePresetinNewPositionButton.ButtonPushedFcn = createCallbackFcn(app, @StorePresetinNewPositionButtonPushed, true);
            app.StorePresetinNewPositionButton.Text = 'Store Preset in New Position';
            app.StorePresetatexistingPositionasButton = uibutton(app.StoredCVCRCIPresetsTab, 'push');
            app.StorePresetatexistingPositionasButton.ButtonPushedFcn = createCallbackFcn(app, @StorePresetatexistingPositionasButtonPushed, true);
            app.StorePresetatexistingPositionasButton.Text = 'Store Preset at existing Position as:';
            app.PresetDropDownLabel = uilabel(app.StoredCVCRCIPresetsTab);
            app.PresetDropDownLabel.Text = 'Preset #';
            app.PresetDropDown = uidropdown(app.StoredCVCRCIPresetsTab);
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetDropDownValueChanged, true);
            app.PresetNewnameEditFieldLabel = uilabel(app.StoredCVCRCIPresetsTab);
            app.PresetNewnameEditFieldLabel.Text = 'Preset New name';
            app.PresetNewnameEditField = uieditfield(app.StoredCVCRCIPresetsTab, 'text');
            app.LoadStoredDatasetTab = uitab(app.TabGroup);
            app.LoadStoredDatasetTab.Title = 'Load Stored Dataset';
            app.UiGetFileButton = uibutton(app.LoadStoredDatasetTab, 'push');
            app.UiGetFileButton.ButtonPushedFcn = createCallbackFcn(app, @UiGetFileButtonPushed, true);
            app.UiGetFileButton.Text = 'UiGetFile';
            app.AnnoEditFieldLabel = uilabel(app.LoadStoredDatasetTab);
            app.AnnoEditFieldLabel.Text = 'Anno';
            app.AnnoEditField = uieditfield(app.LoadStoredDatasetTab, 'numeric');
            app.AnnoEditField.ValueChangedFcn = createCallbackFcn(app, @AnnoEditFieldValueChanged, true);
            app.MonthEditFieldLabel = uilabel(app.LoadStoredDatasetTab);
            app.MonthEditFieldLabel.Text = 'Month';
            app.MonthEditField = uieditfield(app.LoadStoredDatasetTab, 'numeric');
            app.MonthEditField.ValueChangedFcn = createCallbackFcn(app, @MonthEditFieldValueChanged, true);
            app.DayEditFieldLabel = uilabel(app.LoadStoredDatasetTab);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.LoadStoredDatasetTab, 'numeric');
            app.DayEditField.ValueChangedFcn = createCallbackFcn(app, @DayEditFieldValueChanged, true);
            app.pwdButton = uibutton(app.LoadStoredDatasetTab, 'push');
            app.pwdButton.ButtonPushedFcn = createCallbackFcn(app, @pwdButtonPushed, true);
            app.pwdButton.Text = 'pwd';
            app.TodayButton = uibutton(app.LoadStoredDatasetTab, 'push');
            app.TodayButton.ButtonPushedFcn = createCallbackFcn(app, @TodayButtonPushed, true);
            app.TodayButton.Text = 'Today';
            app.FilterstringEditFieldLabel = uilabel(app.LoadStoredDatasetTab);
            app.FilterstringEditFieldLabel.Text = 'Filter string';
            app.FilterstringEditField = uieditfield(app.LoadStoredDatasetTab, 'text');
            app.FilterstringEditField.ValueChangedFcn = createCallbackFcn(app, @FilterstringEditFieldValueChanged, true);
            app.FilterstringEditField.Value = '*.mat';
            app.Filenames = uilistbox(app.LoadStoredDatasetTab);
            app.LoadButton = uibutton(app.LoadStoredDatasetTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FolderEditFieldLabel = uilabel(app.LoadStoredDatasetTab);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.LoadStoredDatasetTab, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.DataFound = uitable(app.LoadStoredDatasetTab);
            app.DataFound.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DataFound.RowName = {};
            app.VariablesListBoxLabel = uilabel(app.LoadStoredDatasetTab);
            app.VariablesListBoxLabel.Text = 'Variables';
            app.VariablesListBox = uilistbox(app.LoadStoredDatasetTab);
            app.VariablesListBox.Items = {'no variables'};
            app.VariablesListBox.Value = 'no variables';
            app.UpdateOnceButton = uibutton(app.LoadStoredDatasetTab, 'push');
            app.UpdateOnceButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateOnceButtonPushed, true);
            app.UpdateOnceButton.Text = 'Update Once';
            app.RunUpdateButton = uibutton(app.LoadStoredDatasetTab, 'push');
            app.RunUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @RunUpdateButtonPushed, true);
            app.RunUpdateButton.Text = 'Run Update';
            app.pauseEditFieldLabel = uilabel(app.LoadStoredDatasetTab);
            app.pauseEditFieldLabel.Text = 'pause';
            app.pauseEditField = uieditfield(app.LoadStoredDatasetTab, 'numeric');
            app.pauseEditField.Value = 0.2;
            app.StopUpdateButton = uibutton(app.LoadStoredDatasetTab, 'push');
            app.StopUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @StopUpdateButtonPushed, true);
            app.StopUpdateButton.Text = 'Stop Update';
            app.FullSetButton = uibutton(app.LoadStoredDatasetTab, 'push');
            app.FullSetButton.ButtonPushedFcn = createCallbackFcn(app, @FullSetButtonPushed, true);
            app.FullSetButton.Text = 'Full-Set';
            app.PositionLabel = uilabel(app.LoadStoredDatasetTab);
            app.PositionLabel.Text = 'Position:';
            app.POSState = uilabel(app.LoadStoredDatasetTab);
            app.POSState.Text = 'PositionState';
            app.POSState_2 = uilabel(app.LoadStoredDatasetTab);
            app.POSState_2.Text = 'PositionState';
            app.UNFREEZEButton = uibutton(app.CVCRCIUIFigure, 'push');
            app.UNFREEZEButton.ButtonPushedFcn = createCallbackFcn(app, @UNFREEZEButtonPushed, true);
            app.UNFREEZEButton.Text = 'UNFREEZE';
            app.ReleaseeDefsButton = uibutton(app.CVCRCIUIFigure, 'push');
            app.ReleaseeDefsButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseeDefsButtonPushed, true);
            app.ReleaseeDefsButton.Text = 'Release eDefs';
            app.AliveDisplaysListBoxLabel = uilabel(app.CVCRCIUIFigure);
            app.AliveDisplaysListBoxLabel.Text = 'Alive Displays';
            app.AliveDisplaysListBox = uilistbox(app.CVCRCIUIFigure);
            app.AliveDisplaysListBox.Items = {};
            app.AliveDisplaysListBox.Value = {};
            app.AD_CloseButton = uibutton(app.CVCRCIUIFigure, 'push');
            app.AD_CloseButton.Text = 'Close';
            app.AD_CloseAllButton = uibutton(app.CVCRCIUIFigure, 'push');
            app.AD_CloseAllButton.Text = 'Close All';
            app.UpdateDisplaysButton_2 = uibutton(app.CVCRCIUIFigure, 'push');
            app.UpdateDisplaysButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateDisplaysButton_2Pushed, true);
            app.UpdateDisplaysButton_2.Text = 'Update Displays';
            app.C17Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C17Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C17Button.Text = 'C17';
            app.C18Button = uibutton(app.CVCRCIUIFigure, 'push');
            app.C18Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C18Button.Text = 'C18';
            % Show the figure after all components are created
            app.CVCRCIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVCRCI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CVCRCIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CVCRCIUIFigure)
        end
    end
end