classdef CVCRCI_Basic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CVCRCIBasicUIFigure            matlab.ui.Figure
        SynchronizationandBufferPanel  matlab.ui.container.Panel
        BSA_Mode                       matlab.ui.control.DropDown
        Syncrhonization                matlab.ui.control.DropDown
        BeamCode                       matlab.ui.control.DropDown
        BufferSizeEditFieldLabel       matlab.ui.control.Label
        BufferSizeEditField            matlab.ui.control.NumericEditField
        BlockSizeEditFieldLabel        matlab.ui.control.Label
        BlockSizeEditField             matlab.ui.control.NumericEditField
        BSATimeEditFieldLabel          matlab.ui.control.Label
        BSATimeEditField               matlab.ui.control.NumericEditField
        BSAwaitLabel                   matlab.ui.control.Label
        BSAwaitEditField               matlab.ui.control.NumericEditField
        AuthorEditFieldLabel           matlab.ui.control.Label
        AuthorEditField                matlab.ui.control.EditField
        TimeEditFieldLabel             matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.EditField
        StopBufferFilled               matlab.ui.control.CheckBox
        IfFullDropDownLabel            matlab.ui.control.Label
        IfFullDropDown                 matlab.ui.control.DropDown
        StartStopPanel                 matlab.ui.container.Panel
        STARTButton                    matlab.ui.control.Button
        PAUSEButton                    matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        DumpatrecorderStopCONT         matlab.ui.control.CheckBox
        BufferUpdate                   matlab.ui.control.Label
        AliveString                    matlab.ui.control.Label
        UNFREEZEButton                 matlab.ui.control.Button
        ReleaseeDefsButton             matlab.ui.control.Button
        UpdateDisplaysButton_2         matlab.ui.control.Button
        AutoconfDropDownLabel          matlab.ui.control.Label
        AutoconfDropDown               matlab.ui.control.DropDown
        ProfileDetailsPanel            matlab.ui.container.Panel
        ProfileFullPVName              matlab.ui.control.Label
        SizeXEditField_2Label          matlab.ui.control.Label
        SizeXEditField                 matlab.ui.control.NumericEditField
        SizeYEditFieldLabel            matlab.ui.control.Label
        SizeYEditField                 matlab.ui.control.NumericEditField
        GetSizeButton                  matlab.ui.control.Button
        GetBackgroundButton            matlab.ui.control.Button
        SamplesEditFieldLabel          matlab.ui.control.Label
        SamplesEditField               matlab.ui.control.NumericEditField
        ReloadLastBackgroundButton     matlab.ui.control.Button
        ClearBackgroundButton          matlab.ui.control.Button
        forAsynchronousModeOnlyLabel   matlab.ui.control.Label
        VariableBufferSizeEditFieldLabel  matlab.ui.control.Label
        VariableBufferSizeEditField    matlab.ui.control.NumericEditField
        PIDoffsetEditFieldLabel        matlab.ui.control.Label
        PIDoffsetEditField             matlab.ui.control.NumericEditField
        ReadsingleprofileButton        matlab.ui.control.Button
        MinimumPVtoberecordedCheckBox  matlab.ui.control.CheckBox
        UselcaGetinsteadoflcaGetSmartCheckBox  matlab.ui.control.CheckBox
        ListaBackground                matlab.ui.control.Label
    end

    
    properties (Access = private)
        Displays % DescriptionOFAllPossibleDisplays
        Use_Lca_Monitor % Uses LCA Monitor if 1
        Presets % Presets for entire GUI at startup
        maximumNumberOfDisplays=18;
        maximumNumberOfPresets=36;
        openDisplays={}; % Displays that are open.
        CV = CVCRCI_Class; %app.CV is the recorder object.
        %Recorder % Recorder data and status
        %Acquisition % Acquisition setup
        Profile % List of the profile monitors to be recorded and their options 
        ScalarList % A List of scalars known to the GUI (e.g. BSA ones, or other groups)
        Waveforms % A list of all profile monitors recognized by software
        MaxWaveforms=7; %Maximum number of waveforms.
        ScalarTables; %The lists of scalars to be recorded
        ScalarGroups; %Groups of scalars
        ConfigurationPath='/u1/lcls/matlab/VOM_Configs';
        color % colors for the UI
        AliveStrings={'|','\','-','/','|','\','-','/'};
        AllScans;
        application='MainRecorder';
        ProfileMontiorPositionDropDown;
        ProfilemonitornameDropDown;
        RelevantVariableName;
        ISValue;
        DisplayToBeUsed;
        TheDisplay;
        DisplayOptions=[];
    end
    
    methods (Access = private)
        
        function void = handleDisplayVisibility(app)
            for II=1:app.maximumNumberOfDisplays
                if(II<=app.Displays.Number)
                    set(app.(['C',num2str(II),'Button']),'visible','on');
                    set(app.(['C',num2str(II),'Button']),'userdata',app.Displays.Functions(II));
                    app.(['C',num2str(II),'Button']).Text=app.Displays.Functions(II).name;
                else
                    set(app.(['C',num2str(II),'Button']),'visible','off');
                end
            end
            void=0;
        end
        
        function void=openDisplay(app,ButtonNumber)
            UD=app.(['C',num2str(ButtonNumber),'Button']).UserData;
            if(isempty(app.openDisplays))
                app.openDisplays{1}=UD.FigureFunction();
                app.openDisplays{1}.NeedInit=1;
            else
                app.openDisplays{end+1}=UD.FigureFunction();
                app.openDisplays{end}.NeedInit=1;
            end
            app.openDisplays{end}.Update(app.CV,1);
            void=0;
        end
        
        function UpdateAllDisplays(app)
            for II=numel(app.openDisplays):-1:1
                if(~isvalid(app.openDisplays{II}))
                    app.openDisplays(II)=[];
                else
                    app.openDisplays{II}.Update(app.CV,0);
                end
            end
        end
        
        function InitializeAllDisplays(app)
            try 
               isvalid(app.openDisplays{1});
               if(~isvalid(app.openDisplays{1}))
                   app.openDisplays{1}=app.TheDisplay.FigureFunction(app.DisplayOptions);
                   app.openDisplays{1}.NeedInit=1;
               end
            catch
               app.openDisplays{1}=app.TheDisplay.FigureFunction(app.DisplayOptions);
               app.openDisplays{1}.NeedInit=1;
            end
            app.openDisplays{end}.Update(app.CV,1);   
%             else
%                 app.openDisplays{end+1}=UD.FigureFunction();
%                 app.openDisplays{end}.NeedInit=1;
%             end

%             for II=numel(app.openDisplays):-1:1
%                 if(~isvalid(app.openDisplays{II}))
%                     app.openDisplays(II)=[];
%                 else
%                     app.openDisplays{II}.Update(app.CV,1);
%                 end
%             end
        end
        
        function void = allScalarNames(app)
            void=0;
            app.ScalarList=load([app.ConfigurationPath,'/CVCRCI_BSA_LIST.mat']);
            try
                app.ScalarList.BSA_LIST.BSA_HXR= meme_names('tag',['LCLS.CU', 'H', '.BSA.rootnames'], 'sort','z');
                app.ScalarList.BSA_LIST.BSA_SXR= meme_names('tag',['LCLS.CU', 'S', '.BSA.rootnames'], 'sort','z');
            catch void
                disp('Meme names is broken');
            end
            
            BSA_Fieldnames=fieldnames(app.ScalarList.BSA_LIST);
            app.ScalarList.FULL_HXR_BSA=app.ScalarList.BSA_LIST.BSA_HXR;
            app.ScalarList.FULL_SXR_BSA=app.ScalarList.BSA_LIST.BSA_SXR;
            BSA_Fieldnames=setdiff(BSA_Fieldnames,'BSA_HXR'); BSA_Fieldnames=setdiff(BSA_Fieldnames,'BSA_SXR'); BSA_Fieldnames=setdiff(BSA_Fieldnames,'GDET_SXR');
            for II=1:length(BSA_Fieldnames)
                if(any(strfind(BSA_Fieldnames{II},'DMPH')) || any(strfind(BSA_Fieldnames{II},'HXR')) || any(strfind(BSA_Fieldnames{II},'LTUH')))
                    app.ScalarList.FULL_HXR_BSA=union(app.ScalarList.FULL_HXR_BSA,app.ScalarList.BSA_LIST.(BSA_Fieldnames{II}),'stable');
                end
                if(any(strfind(BSA_Fieldnames{II},'DMPS')) || any(strfind(BSA_Fieldnames{II},'SXR')) || any(strfind(BSA_Fieldnames{II},'LTUS')))
                    app.ScalarList.FULL_SXR_BSA=union(app.ScalarList.FULL_SXR_BSA,app.ScalarList.BSA_LIST.(BSA_Fieldnames{II}),'stable');
                end
                if(any(strfind(BSA_Fieldnames{II},'BLEN')))
                    app.ScalarList.FULL_HXR_BSA=union(app.ScalarList.FULL_HXR_BSA,app.ScalarList.BSA_LIST.(BSA_Fieldnames{II}),'stable');
                    app.ScalarList.FULL_SXR_BSA=union(app.ScalarList.FULL_SXR_BSA,app.ScalarList.BSA_LIST.(BSA_Fieldnames{II}),'stable');
                end
            end
            [fp,sp,tp,qp] = breakPVList(app,app.ScalarList.FULL_HXR_BSA);
            app.ScalarList.FULL_HXR_BSA_Spalato.fp=fp; app.ScalarList.FULL_HXR_BSA_Spalato.sp=sp;
            app.ScalarList.FULL_HXR_BSA_Spalato.tp=tp; app.ScalarList.FULL_HXR_BSA_Spalato.qp=qp;
            app.ScalarList.FULL_HXR_BSA_Spalato.name=app.ScalarList.FULL_HXR_BSA;
            [fp,sp,tp,qp] = breakPVList(app,app.ScalarList.FULL_SXR_BSA);
            app.ScalarList.FULL_SXR_BSA_Spalato.fp=fp; app.ScalarList.FULL_SXR_BSA_Spalato.sp=sp;
            app.ScalarList.FULL_SXR_BSA_Spalato.tp=tp; app.ScalarList.FULL_SXR_BSA_Spalato.qp=qp;
            app.ScalarList.FULL_SXR_BSA_Spalato.name=app.ScalarList.FULL_SXR_BSA;
            app.ScalarList.FULL_BSA=union(app.ScalarList.FULL_HXR_BSA,app.ScalarList.FULL_SXR_BSA,'stable');
            [fp,sp,tp,qp] = breakPVList(app,app.ScalarList.FULL_BSA);
            app.ScalarList.FULL_BSA_Spalato.fp=fp; app.ScalarList.FULL_BSA_Spalato.sp=sp;
            app.ScalarList.FULL_BSA_Spalato.tp=tp; app.ScalarList.FULL_BSA_Spalato.qp=qp;
            app.ScalarList.FULL_BSA_Spalato.name=app.ScalarList.FULL_BSA;
            app.ScalarGroups={'HXR_BSA','SXR_BSA','BSA'};
        end
        
        function void = ManageProfileButtonVisibility(app,POS)
            void=0;
            if(~app.Profile(POS).in_use) % Hide most buttons on this page
                OnOffString='off';
            else %Show a bunch of buttons on this page
                OnOffString='on';
            end
            set(app.GetSizeButton,'visible',OnOffString);
            %set(app.TimestampedCheckBox,'visible',OnOffString);
            set(app.SizeXEditField,'visible',OnOffString);
            set(app.SizeXEditField_2Label,'visible',OnOffString);
            set(app.SizeYEditField,'visible',OnOffString);
            set(app.SizeYEditFieldLabel,'visible',OnOffString);
            set(app.ReadsingleprofileButton,'visible',OnOffString);
            set(app.ProfileFullPVName,'visible',OnOffString);
            set(app.PIDoffsetEditField,'visible',OnOffString);
            set(app.PIDoffsetEditFieldLabel,'visible',OnOffString);
            set(app.VariableBufferSizeEditField,'visible',OnOffString);
            set(app.VariableBufferSizeEditFieldLabel,'visible',OnOffString);
            %set(app.DoPostprocessingCheckBox,'visible',OnOffString);
            if(strcmp(OnOffString,'on'))
                app.ProfileFullPVName.Text = app.Profile(POS).PVName;
                app.SizeXEditField.Value = app.Profile(POS).size(1);
                app.SizeYEditField.Value = app.Profile(POS).size(2);
                app.VariableBufferSizeEditField.Value = app.Profile(POS).Buffersize;
                app.PIDoffsetEditField.Value = app.Profile(POS).PID_Delay;
            end
        end
        
        function void = ManageBackgroundVisibilityAndUpdate(app,POS)
            void=0;
            if(~app.Profile(POS).BackgroundEnable)
                set(app.GetBackgroundButton,'visible','off');
                set(app.SamplesEditField,'visible','off');
                set(app.SamplesEditFieldLabel,'visible','off');
                set(app.ReloadLastBackgroundButton,'visible','off');
                set(app.ClearBackgroundButton,'visible','off');
                set(app.BackgroundAxes,'visible','off');
                set(app.BackgroundAxesReshape,'visible','off');
            else
                set(app.GetBackgroundButton,'visible','on');
                set(app.SamplesEditField,'visible','on');
                set(app.ReloadLastBackgroundButton,'visible','on');
                set(app.ClearBackgroundButton,'visible','on');
                set(app.BackgroundAxes,'visible','on');
                set(app.BackgroundAxesReshape,'visible','on');
                set(app.SamplesEditFieldLabel,'visible','on');
                
                if(isempty(app.Profile(POS).Background))
                    set(app.ReloadLastBackgroundButton,'enable','on');
                    set(app.ClearBackgroundButton,'enable','off');
                    cla(app.BackgroundAxes); cla(app.BackgroundAxesReshape);
                    app.BackgroundAxes.Title.String='Background is empty';
                    app.BackgroundAxesReshape.Title.String='Background is empty';
                else
                    set(app.ReloadLastBackgroundButton,'enable','on');
                    set(app.ClearBackgroundButton,'enable','on');
                    plot(app.BackgroundAxes,app.Profile(POS).Background);
                    imagesc(reshape(app.Profile(POS).Background,app.Profile(POS).size),'parent',app.BackgroundAxesReshape);
                    app.BackgroundAxes.Title.String='Background is recorded';
                    app.BackgroundAxesReshape.Title.String='Background is recorded';
                end
            end
        end
        
        function void = ManagePostProcessingVisibilityAndUpdate(app,POS)
            void=0;
            if(~app.Profile(POS).PostProcessing)
               app.DoPostprocessingCheckBox.Value=0; 
               set(app.PostProcessingFunctionDropDown,'visible','off');
               set(app.PostProcessingFunctionDropDownLabel,'visible','off');
               set(app.PostProcessingOptions,'visible','off');
               set(app.PostProcessingStatus,'visible','off');
               set(app.SetnewPostProcessingoptionsfromTableButton,'visible','off');
               set(app.InitializationofpersistentprocessingvariablesButton,'visible','off');
            else
               app.DoPostprocessingCheckBox.Value=1;
               app.PostProcessingFunctionDropDown.Items=app.Profile(POS).PostProcessingF_List;
               app.PostProcessingFunctionDropDown.ItemsData=1:length(app.Profile(POS).PostProcessingF_List);
               app.PostProcessingFunctionDropDown.Value=app.Profile(POS).PostProcessingFunctionID;
               set(app.PostProcessingFunctionDropDown,'visible','on');
               set(app.PostProcessingFunctionDropDownLabel,'visible','on');
               set(app.PostProcessingOptions,'visible','on');
               set(app.PostProcessingStatus,'visible','on');
               set(app.SetnewPostProcessingoptionsfromTableButton,'visible','on');
               set(app.InitializationofpersistentprocessingvariablesButton,'visible','on');
               app.PostProcessingOptions.Data=app.Profile(POS).PostProcessingOptions;
               if(app.Profile(POS).PostProcessingFunctionID>1)
                  Data=app.Profile(POS).PostProcessingFunction(lcaGetSmart(app.Profile(POS).PVName,prod(app.Profile(POS).size)),app.Profile(POS).PostProcessingOptions,app.Profile(POS),[],1);
                  InfoTable=app.MakeTableFromData(Data);
               else
                  InfoTable{1,1}=''; InfoTable{1,2}=''; 
               end
               app.PostProcessingStatus.Data=InfoTable;
               if(app.Profile(POS).PersistentInit(app.Profile(POS).PostProcessingFunctionID))
                   set(app.InitializationofpersistentprocessingvariablesButton,'enable','on');
               else
                   set(app.InitializationofpersistentprocessingvariablesButton,'enable','off');
               end
            end
        end
        
        function OUT = MakeTableFromData(~,Data)
            OUT{1,1}='';OUT{1,2}='';OUT{1,3}='';
            RID=1;
            for II=1:Data.NumberOfScalars
                OUT{RID,1}=Data.ScalarNames{II};
                OUT{RID,2}=['[','1' , ' x ','1',']'];
                RID=RID+1;
            end
            for II=1:Data.NumberOfVectors
                OUT{RID,1}=Data.VectorNames{II};
                OUT{RID,2}=['[','1' , ' x ',num2str(Data.VectorSizes(II)),']'];
                RID=RID+1;
            end
            for II=1:Data.NumberOfArray2D
                OUT{RID,1}=Data.Array2DNames{II};
                OUT{RID,2}=['[',num2str(Data.Array2DSizes(II,1)) , ' x ',num2str(Data.Array2DSizes(II,2)),']'];
                RID=RID+1;
            end
            OUT{RID,1}='External Timestamps';
            if(Data.UseExternalTimeStamps)
                OUT{RID,2}='YES';
            else
                OUT{RID,2}='NO';
            end
            RID=RID+1;
            for II=1:size(Data.AdditionalInformation,1)
                OUT{RID,1}=Data.AdditionalInformation{II,1};
                OUT{RID,2}=Data.AdditionalInformation{II,2};
                RID=RID+1;
            end
        end
        
        function void = ManageChangeInPostProcessingFunction(app,POS)
            void=0;
            FunctionID=app.PostProcessingFunctionDropDown.Value;
            app.Profile(POS).PostProcessingFunctionID=FunctionID;
            app.Profile(POS).PostProcessingFunction=eval(['@', app.Profile(POS).PostProcessingF_List{app.Profile(POS).PostProcessingFunctionID}]);
            app.Profile(POS).PostProcessingOptions=app.Profile(POS).PostProcessingDefaultOptions{app.Profile(POS).PostProcessingFunctionID};
            for KK=1:size(app.Profile(POS).PostProcessingOptions,1)
                if(strcmp(app.Profile(POS).PostProcessingOptions{KK,1},'Var Name'))
                    app.Profile(POS).PostProcessingOptions{KK,2} = app.Profile(POS).name;
                end
            end
            app.ManagePostProcessingVisibilityAndUpdate(POS);
        end
        
        function void = StartUpProfileSetup(app)
            void=0;
            app.Profile=app.Waveforms(1);
            for II=1:app.MaxWaveforms
                app.Profile(II)=app.Waveforms(1);
            end
        end
        
        function void = StartupIncludeNewVariablesInWaveforms(app)
            void=0;
            for II=1:app.MaxWaveforms
                app.Waveforms(II).WaveformID=II;
                app.Waveforms(II).Userdata=[];
            end
        end
        
        function [fp,sp,tp,qp] = breakPVList(app,List) %#ok<INUSL> 
            NPV=numel(List);
            fp=cell(NPV,1);sp=cell(NPV,1);tp=cell(NPV,1);qp=cell(NPV,1);
            for II=1:NPV
                breaks=find(List{II}==':');
                p1=List{II}(1:(breaks(1)-1));
                p2=List{II}((breaks(1)+1):(breaks(2)-1));
                p3=List{II}((breaks(2)+1):(breaks(3)-1));
                p4=List{II}((breaks(3)+1):end);
                fp{II}=p1; sp{II}=p2; tp{II}=p3; qp{II}=p4;
            end
        end
        
        function void = UpdateRecordingSummary(app)
            void=0;
            app.SynchPV.Data=app.ScalarTables.SynchPV;
            app.SlowPV.Data=app.ScalarTables.SlowPV;
            app.OnlyOncePV.Data=app.ScalarTables.OnlyOncePV;
            ActiveProfileList={};
            for II=1:app.MaxWaveforms
                if(app.Profile(II).in_use)
                    ActiveProfileList{end+1}=app.Profile(II).PVName; %#ok<AGROW> 
                end
            end
            if(isempty(ActiveProfileList))
                app.ProfileMonitorPV.Data={'No Vectors or Images'};
                AllVariables = {} ;
            else
                app.ProfileMonitorPV.Data=ActiveProfileList.';
                AllVariables = ActiveProfileList.';
            end
            AllVariables = [AllVariables ; app.SynchPV.Data(:,1) ; app.SlowPV.Data];
            app.RelevantVariableName.Items = AllVariables;
        end
        
        function void=ManageScalarList(app)
            void=0;
            app.epv_FullList.Items=app.ScalarList.(['FULL_',app.FilterListDropDown.Items{app.FilterListDropDown.Value}]);
            app.epv_FullList.UserData=app.ScalarList.(['FULL_',app.FilterListDropDown.Items{app.FilterListDropDown.Value},'_Spalato']);
        end
        
        function void = searchPV(app)
            void=0;
            fn=app.P1.Value; sn=app.P2.Value; tn=app.P3.Value; qn=app.P4.Value;
            UD=app.epv_FullList.UserData;
            Nuova_Lista=UD.name(((strcmp(fn,UD.fp) | isempty(fn)) & (strcmp(sn,UD.sp) | isempty(sn)) & (strcmp (tn,UD.tp) | isempty(tn)) & (strcmp(qn,UD.qp)| isempty(qn))));
            app.epv_FullList.Items=Nuova_Lista;
        end
        
        function AddLists = FindAddLists(app)
            AddSynch=app.epv_SynchGroup.Data(:,2);
            AddSlow=app.epv_AsynchGroup.Data(:,2);
            AddOnce=app.epv_AsynchGroup.Data(:,3);
            List={};
            for II=1:length(AddSynch)
                if(AddSynch{II})
                    List=[List ; app.ScalarList.BSA_LIST.(app.epv_SynchGroup.Data{II,1})]; %#ok<AGROW> 
                end
            end
            AddLists.SynchPV=unique(List,'stable');
            List={};
            for II=1:length(AddSlow)
                if(AddSlow{II})
                    List=[List ; app.ScalarList.NON_BSA_LIST.(app.epv_AsynchGroup.Data{II,1})]; %#ok<AGROW> 
                end
            end
            AddLists.SlowPV=unique(List,'stable');
            List={};
            for II=1:length(AddOnce)
                if(AddOnce{II})
                    List=[List ; app.ScalarList.NON_BSA_LIST.(app.epv_AsynchGroup.Data{II,1})]; %#ok<AGROW> 
                end
            end
            AddLists.OnlyOncePV=unique(List,'stable');
        end
        
        function void=ManagePresetButtons(app)
            void=0;
            PresetNumber=numel(app.Presets); PresetNameList={};
            for II=1:app.maximumNumberOfPresets
                if(II<=PresetNumber)
                    app.(['A',num2str(II),'Button']).Text=app.Presets{II}.name;
                    app.(['A',num2str(II),'Button']).Visible='on';
                    app.(['A',num2str(II),'Button']).UserData=II;
                    PresetNameList{end+1}=app.Presets{II}.name; %#ok<AGROW> 
                else
                    app.(['A',num2str(II),'Button']).Visible='off';
                end
            end
            app.PresetDropDown.Items=PresetNameList;
            app.PresetDropDown.ItemsData=1:length(PresetNameList);
        end
        
        function void = RestorePreset(app,PresetID)
            void=0;
            app.ScalarTables.SynchPV=app.Presets{PresetID}.Variables.SynchPV;
            app.ScalarTables.SlowPV=app.Presets{PresetID}.Variables.SlowPV;
            app.ScalarTables.OnlyOnceV=app.Presets{PresetID}.Variables.OnlyOncePV;
            app.Profile=app.Presets{PresetID}.Profile;
            app.BSA_Mode.Value=app.Presets{PresetID}.AcquisitionSetup.BSA_Mode;
            app.Syncrhonization.Value=app.Presets{PresetID}.AcquisitionSetup.Synch_Mode;
            app.BeamCode.Value=app.Presets{PresetID}.AcquisitionSetup.BeamCode;
            app.BufferSizeEditField.Value=app.Presets{PresetID}.AcquisitionSetup.BufferSize;
            app.BlockSizeEditField.Value=app.Presets{PresetID}.AcquisitionSetup.BlockSize;
            app.BSATimeEditField.Value=app.Presets{PresetID}.AcquisitionSetup.BSATime;
            app.BSAwaitEditField.Value=app.Presets{PresetID}.AcquisitionSetup.BSAwait;
            app.AuthorEditField.Value=app.Presets{PresetID}.AcquisitionSetup.Author;
            app.TimeEditField.Value=app.Presets{PresetID}.AcquisitionSetup.TimeReference;
            app.IfFullDropDown.Value=app.Presets{PresetID}.AcquisitionSetup.AtBufferFullFunction;
            app.StopBufferFilled.Value=app.Presets{PresetID}.AcquisitionSetup.ExitAtBufferFilled;
        end
        
        function InputStructure = ReadAllInputs(app)
            %if(strcmp(app.ContinuousTab.Title,'Continuous')), InputStructure.DoScan=0; else,  InputStructure.DoScan=1; end
            InputStructure.DoScan=0;
            SynchScalars=app.ScalarTables.SynchPV;
            SlowScalars=app.ScalarTables.SlowPV;
            ReadOnce=app.ScalarTables.OnlyOncePV;
            switch(app.BSA_Mode.Value)
                case 'No BSA'
                    InputStructure.BSA=0;
                case 'Double Buffer BSA'
                    InputStructure.BSA=1;
                case 'History Buffer BSA'
                    InputStructure.BSA=2;
                otherwise
                    InputStructure.BSA=0;
                    disp('Something went wrong, not recognized BSA mode!');
            end
            switch(app.Syncrhonization.Value)
                case 'Synchronous Mode'
                    InputStructure.Synchronous=1;
                case 'Asynchronous Mode'
                    InputStructure.Synchronous=0;
                otherwise
                    disp('Something went wrong, not recognized Synchronous Mode');
                    InputStructure.Synchronous=1;
            end
            InputStructure.Buffersize=app.BufferSizeEditField.Value;
            InputStructure.Blocksize=app.BlockSizeEditField.Value;
            InputStructure.TimeCycle=app.BSATimeEditField.Value;
            InputStructure.PV_For_Time_Reference=app.TimeEditField.Value;
            InputStructure.BSA_Safe_Trash_Data=app.BSAwaitEditField.Value;
            InputStructure.Author=app.AuthorEditField.Value;
            InputStructure.BufferFullFunction=app.IfFullDropDown.Value;
            InputStructure.StopBufferFilled=app.StopBufferFilled.Value;
            
            switch(app.BeamCode.Value)
                case 'All 120 Hz'
                    InputStructure.BSA_Exclusion=0;
                case 'HXR Branch only'
                    InputStructure.BSA_Exclusion=1;
                case 'SXR Branch only'
                    InputStructure.BSA_Exclusion=2;
                otherwise
                    disp('Something went wrong, not recognized Beam Code');
                    InputStructure.BSA_Exclusion=0;
            end
 
            InputStructure.Variables={};
            InputStructure.Rejection=[];
            InputStructure.name='General Acquisition';
            InputStructure.InsertedCounterVariableName=app.RelevantVariableName.Value;
            InputStructure.InsertedCounterVariable=1;
            InputStructure.SpawnMultiple=0;
            InputStructure.Profile=app.Profile;
            
            for TT=1:numel(InputStructure.Profile)
                if(~InputStructure.Profile(TT).in_use)
                    continue
                end
                InputStructure.Variables{end+1}=InputStructure.Profile(TT);
            end
            
            for TT=1:size(SynchScalars,1)
                InputStructure.Variables{end+1}.name=SynchScalars{TT,1};
                InputStructure.Variables{end}.PVname=SynchScalars{TT,1};
                InputStructure.Variables{end}.size=[1,1];
                InputStructure.Variables{end}.Synchronous=1;
                InputStructure.Variables{end}.ReadOnlyOnce=NaN;
                InputStructure.Variables{end}.Background=[];
                if ( (~isempty(SynchScalars{TT,2})) || (~isempty(SynchScalars{TT,3})) )
                    InputStructure.Rejection(end+1).PVname=SynchScalars{TT,1};
                    if(isempty(SynchScalars{TT,2}))
                        InputStructure.Rejection(end).Range(1)=-inf;
                    else
                        InputStructure.Rejection(end).Range(1)=SynchScalars{TT,2};
                    end
                    if(isempty(SynchScalars{TT,3}))
                        InputStructure.Rejection(end).Range(2)=+inf;
                    else
                        InputStructure.Rejection(end).Range(2)=SynchScalars{TT,3};
                    end
                end
                if(strcmp(InputStructure.InsertedCounterVariable,InputStructure.Variables{end}.PVname))
                    InputStructure.InsertedCounterVariable=length(InputStructure.Variables);
                end
            end
            
            for TT=1:size(SlowScalars,1)
                InputStructure.Variables{end+1}.name=SlowScalars{TT,1};
                InputStructure.Variables{end}.PVname=SlowScalars{TT,1};
                InputStructure.Variables{end}.size=[1,1];
                InputStructure.Variables{end}.Synchronous=0;
                InputStructure.Variables{end}.ReadOnlyOnce=0;
                InputStructure.Variables{end}.Background=[];
                if(strcmp(InputStructure.InsertedCounterVariable,InputStructure.Variables{end}.PVname))
                    InputStructure.InsertedCounterVariable=length(InputStructure.Variables);
                end
            end
            
            for TT=1:size(ReadOnce,1)
                InputStructure.Variables{end+1}.name=ReadOnce{TT,1};
                InputStructure.Variables{end}.PVname=ReadOnce{TT,1};
                InputStructure.Variables{end}.size=[1,1];
                InputStructure.Variables{end}.Synchronous=0;
                InputStructure.Variables{end}.ReadOnlyOnce=1;
                InputStructure.Variables{end}.Background=[];
            end
            
            for TT=1:numel(InputStructure.Profile)
                if(~InputStructure.Profile(TT).in_use)
                    continue
                end
                if(~isempty(InputStructure.Profile(TT).AdditionalPv{1}))
                    for ZZ=1:numel(InputStructure.Profile(TT).AdditionalPv)
                        InputStructure.Variables{end+1}.name=InputStructure.Profile(TT).AdditionalPv{ZZ};
                        InputStructure.Variables{end}.PVname=InputStructure.Profile(TT).AdditionalPv{ZZ};
                        InputStructure.Variables{end}.size=[1,1];
                        InputStructure.Variables{end}.Synchronous=0;
                        InputStructure.Variables{end}.ReadOnlyOnce=1;
                        InputStructure.Variables{end}.Background=[];
                    end
                end
                if(strcmp(InputStructure.InsertedCounterVariable,InputStructure.Variables{end}.PVname))
                    InputStructure.InsertedCounterVariable=length(InputStructure.Variables);
                end
            end
            if(InputStructure.DoScan)
                InputStructure.ScanSetting=ScanSetting;
            end
        end
        
        function void = getAppColors(app)
            void=0;
            app.color.Idle=app.GetSizeButton.BackgroundColor;
            app.color.On=[0,1,0];
            app.color.Off=[1,0,0];
            app.color.Wait=[1,1,0];
            app.color.logbook=[0.7,0.7,1];
            app.color.logbook2Steps=[0.5,0.5,1];
        end
        
        function void = unfreeze(app)
            void=0;
            app.ReleaseeDefsButton.Enable='on'; app.ReleaseeDefsButton.BackgroundColor=app.color.Idle;
            app.STARTButton.Enable='on'; app.STARTButton.BackgroundColor=app.color.Idle;
            app.PAUSEButton.Enable='off'; app.PAUSEButton.BackgroundColor=app.color.Idle;
            app.STOPButton.Enable='off'; app.STOPButton.BackgroundColor=app.color.Idle;
            app.releaseEDef();
        end        
        
        function void = releaseEDef(app)
            void=0;
            OldeDefs=app.ReleaseeDefsButton.UserData;
            if ~isempty(OldeDefs)
                for II=1:length(OldeDefs)
                    try
                        eDefRelease(OldeDefs(II));
                    catch ME %#ok<NASGU> 
                        %maybe somebody cleaned the thing before you did!
                    end
                end
            end
            app.ReleaseeDefsButton.UserData=[];
            app.ReleaseeDefsButton.BackgroundColor=app.color.Idle;
            app.ReleaseeDefsButton.Enable='off';
        end
        
        function populateScan(app)
            try
                CustomAdditionalStuff=load('ADD_CustomStuff.mat');
            catch ME
                CustomAdditionalStuff=struct();
            end
            app.AllScans=CVCRCI_ScanConfigurationFunction;
            try
                if(isfield(CustomAdditionalStuff,'ScanTabNames'))
                        for II=1:numel(CustomAdditionalStuff.ScanTabNames)
                            app.AllScans.EnvironmentNames{end+1}=CustomAdditionalStuff.ScanTabNames{II};
                            app.AllScans.Environment{end+1}=CustomAdditionalStuff.Scans{II};
                        end
                end
            catch
               disp('Error while adding custom scans from ADD_CustomStuff.mat'); 
            end
            app.ScanEnvironments.Items=app.AllScans.EnvironmentNames;
            app.ScanEnvironments.ItemsData=1:length(app.AllScans.EnvironmentNames);
            app.updatePresetListSelection();
            app.scanPresetPicked();
            app.evaluateScanSettingFromTable();
        end
        
        function updatePresetListSelection(app)
            for II=1:12
                if(II<=numel(app.AllScans.Environment{app.ScanEnvironments.Value}.Scan))
                    app.(['S',num2str(II)]).Text=app.AllScans.Environment{app.ScanEnvironments.Value}.Scan(II).Name;
                    app.(['S',num2str(II)]).UserData=app.AllScans.Environment{app.ScanEnvironments.Value}.Scan(II); 
                    app.(['S',num2str(II)]).Visible='on'; 
                else
                   app.(['S',num2str(II)]).Visible='off'; 
                   app.(['S',num2str(II)]).UserData=[];
                end
            end
            app.ScanpresetsButtonGroup.SelectedObject=app.S1;
        end
        
        function scanPresetPicked(app)
            selectedButton = app.ScanpresetsButtonGroup.SelectedObject;
            app.ScanSetupTable.UserData=selectedButton.UserData;
            SCAN=app.ScanSetupTable.UserData;
            app.ScanSetupTable.Data=SCAN.Table.';
            app.ScanSetupTable.RowName=SCAN.RowNames;
            if(~SCAN.Allownewlines)
                app.AddoneVariableButton.Enable='Off';
                app.RemoveoneVariableButton.Enable='Off';
            else
                app.AddoneVariableButton.Enable='On';
                app.RemoveoneVariableButton.Enable='On';
            end
            app.ScanSetupTable.ColumnEditable=true(1,size(SCAN.Table,2));
        end
        
        function evaluateScanSettingFromTable(app)
            disp('Run Scan Setup Table');
            Scan=app.ScanSetupTable.UserData;
            Table=app.ScanSetupTable.Data;
            Table=Scan.Functions.ParseTable(Table);
            app.ScanSetupTable.Data=Table;
            %Set new values in the tables Guarda il numero delle knobs
            Options.Normal=app.NormalButton.Value;
            Options.ZigZag=app.ZigZagButton.Value;
            [ScanSetting,ErrorMessage]=Scan.Functions.CalculatePoints(Table,Options);
            if(~isempty(ErrorMessage))
                app.STARTButtonSCAN.Enable='Off';
                app.ScErr.Text=ErrorMessage;
                return
            else
                app.STARTButtonSCAN.Enable='On';
                app.ScErr.Text='Ok to Scan.';
            end
            ScanSetting.Scan=Scan;
            
            for II=1:numel(ScanSetting.Knob)
                SIZES(II)=ScanSetting.Knob{II}.Steps;
            end
            
            [SPV, POSITIONS]=size(ScanSetting.PVValues);
            for II=1:SPV
                if(length(SIZES)==1)
                    ParameterSpace{II}=zeros(SIZES,1);
                else
                    ParameterSpace{II}=zeros(SIZES);
                end
            end
            
            CT=ScanSetting.ConditionsTable;
            
            switch(numel(SIZES))
                case 1
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 2
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 3
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 4
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 5
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 6
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT),CT(6,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 7
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT),CT(6,TT),CT(7,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 8
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT),CT(6,TT),CT(7,TT),CT(8,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 9
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT),CT(6,TT),CT(7,TT),CT(8,TT),CT(9,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
                case 10
                    for TT=1:POSITIONS
                        for II=1:SPV
                            ParameterSpace{II}(CT(1,TT),CT(2,TT),CT(3,TT),CT(4,TT),CT(5,TT),CT(6,TT),CT(7,TT),CT(8,TT),CT(9,TT),CT(10,TT)) = ScanSetting.PVValues(II,TT);
                        end
                    end
            end
            
            ScanSetting.ParameterSpace=ParameterSpace;
            ScanSetting.Samples=app.SamplesEditField.Value;
            app.ScErr.UserData=ScanSetting;
        end
        
        function updateListOfScanVariable(app)
            %Reserved for future use.
        end
        
        function [SUCCESS,filename,TargetDir] = writeDataToDisk(app,TimeNow,CVObject,auxillaryData)
            CurrentTime=datevec(TimeNow);
            CurrentYearString=num2str(CurrentTime(1),'%.4d'); CurrentMonthString=num2str(CurrentTime(2),'%.2d');
            CurrentDayString=num2str(CurrentTime(3),'%.2d'); CurrentTempo1String=num2str(CurrentTime(4),'%.2d');
            CurrentTempo2String=num2str(CurrentTime(5),'%.2d'); CurrentTempo3String=num2str(floor(CurrentTime(6)),'%.2d');
            CurrentTempo4String=num2str(round((CurrentTime(6)-floor(CurrentTime(6)))*1000),'%.3d');
            CurrentTimeString=[CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString,'--',CurrentTempo1String,'-',CurrentTempo2String,'-',CurrentTempo3String,'-',CurrentTempo4String];
            filename=['OnlineMonitor-',app.application,'-',CurrentTimeString];
            TargetDir=['/u1/lcls/matlab/data/',CurrentYearString,'/',CurrentYearString,'-',CurrentMonthString,'/',CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString];
            if(~isfolder(TargetDir))
                mkdir(TargetDir);
            end
            DataStructure=CVObject.Acquisition; Data=CVObject.Recorder; %#ok<ADPROPLC> 
            try
                save([TargetDir,'/',filename],'Data','DataStructure','auxillaryData')
                SUCCESS=1;
            catch
                SUCCESS=0;
            end
        end
        
        function InitializeLoadTab(app)
            DateNummber=datevec(now);
            app.AnnoEditField.Value=DateNummber(1);
            app.MonthEditField.Value=DateNummber(2);
            app.DayEditField.Value=DateNummber(3); 
            app.UpdateTargetDir(1);
            app.UpdateFileList();
        end
        
        function UpdateTargetDir(app,From)
            if(From==1)
                CurrentYearString=num2str(app.AnnoEditField.Value,'%.4d');
                CurrentMonthString=num2str(app.MonthEditField.Value,'%.2d');
                CurrentDayString=num2str(app.DayEditField.Value,'%.2d');
                TargetDir=['/u1/lcls/matlab/data/',CurrentYearString,'/',CurrentYearString,'-',CurrentMonthString,'/',CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString];
                app.FolderEditField.Value=TargetDir;
            else
                app.FolderEditField.Value=pwd;
            end
        end
        
        function UpdateFileList(app)
            if(~isfolder(app.FolderEditField.Value))
               app.Filenames.Items={''};
            else
               FN=dir([app.FolderEditField.Value,'/',app.FilterstringEditField.Value]);
               if(isempty(FN))
                   app.Filenames.Items={''};
               else
                   app.Filenames.Items={FN.name};
                   app.Filenames.Value=app.Filenames.Items{1};
               end
            end
        end
        
        function SetupHXR_Spec(app)
            app.DisplayToBeUsed='Spectrometer';
            app.TheDisplay= app.Displays.Functions(find(strcmp({app.Displays.Functions.name},app.DisplayToBeUsed)));
            app.BeamCode.Value='HXR Branch only';
            app.Syncrhonization.Value='Synchronous Mode';
            app.BSATimeEditField.Value=0.5;
            app.BSAwaitEditField.Value=0;
            app.TimeEditField.Value='BPMS:LTUH:250:TMIT';
            app.ProfilemonitornameDropDown.Value=4; %that's for HXR spectromter lineout.
            app.Profile=app.Waveforms(app.ProfilemonitornameDropDown.Value);
            app.DisplayOptions.AutoBinNumber=5;
            app.DisplayOptions.S_VectorInit='HXR Spectrometer Lineout';
            app.DisplayOptions.S_ScalarInit='BPMS:LTUH:250:X';
            switch(app.AutoconfDropDown.Value)
                case 'General'
                    app.DisplayOptions.Quantity(1).LastN=2000;
                    app.DisplayOptions.Quantity(1).Area=[];
                    app.DisplayOptions.Quantity(1).Style='b-';
                    app.DisplayOptions.Quantity(1).Weight=1;
                    app.DisplayOptions.Quantity(1).BasePV={'SIOC:SYS0:ML07:500','SIOC:SYS0:ML07:501','SIOC:SYS0:ML07:502','SIOC:SYS0:ML07:503','SIOC:SYS0:ML07:504','SIOC:SYS0:ML07:505','SIOC:SYS0:ML07:506','SIOC:SYS0:ML07:507','SIOC:SYS0:ML07:508','SIOC:SYS0:ML07:509'};
                    app.DisplayOptions.Quantity(1).Filter=[true,true];
                    app.DisplayOptions.Quantity(2).LastN=200;
                    app.DisplayOptions.Quantity(2).Area=[];
                    app.DisplayOptions.Quantity(2).Style='r-';
                    app.DisplayOptions.Quantity(2).Weight=1.5;
                    app.DisplayOptions.Quantity(2).BasePV={'SIOC:SYS0:ML07:510','SIOC:SYS0:ML07:511','SIOC:SYS0:ML07:512','SIOC:SYS0:ML07:513','SIOC:SYS0:ML07:514','SIOC:SYS0:ML07:515','SIOC:SYS0:ML07:516','SIOC:SYS0:ML07:517','SIOC:SYS0:ML07:518','SIOC:SYS0:ML07:519'};
                    app.DisplayOptions.Quantity(2).Filter=[true,true];
                    app.DisplayOptions.Quantity(3).LastN=1;
                    app.DisplayOptions.Quantity(3).Area=[];
                    app.DisplayOptions.Quantity(3).Style='m-';
                    app.DisplayOptions.Quantity(3).Weight=0.5;
                    app.DisplayOptions.Quantity(3).BasePV={'SIOC:SYS0:ML07:520','SIOC:SYS0:ML07:521','SIOC:SYS0:ML07:522','SIOC:SYS0:ML07:523','SIOC:SYS0:ML07:524','SIOC:SYS0:ML07:525','SIOC:SYS0:ML07:526','SIOC:SYS0:ML07:527','SIOC:SYS0:ML07:528','SIOC:SYS0:ML07:529'};
                    app.DisplayOptions.Quantity(3).Filter=[true,true];
                    app.DisplayOptions.TypeOfPlot=1;
                    app.DisplayOptions.SetFilter.Var='BPMS:DMPH:502:TMIT';
                    app.DisplayOptions.SetFilter.Range=[10^7,+inf];
                case 'L-Plot'
                    app.DisplayOptions.TypeOfPlot=2;
                    try
                        app.DisplayOptions.Quantity(2)=[];
                    end
                    try
                        app.DisplayOptions.Quantity(3)=[];
                    end
                    app.DisplayOptions.Quantity(1).LastN=NaN;
                    app.DisplayOptions.Quantity(1).Area=[];
                    app.DisplayOptions.Quantity(1).Style='b-';
                    app.DisplayOptions.Quantity(1).Weight=1;
                    app.DisplayOptions.Quantity(1).BasePV={'SIOC:SYS0:ML07:500','SIOC:SYS0:ML07:501','SIOC:SYS0:ML07:502','SIOC:SYS0:ML07:503','SIOC:SYS0:ML07:504','SIOC:SYS0:ML07:505','SIOC:SYS0:ML07:506','SIOC:SYS0:ML07:507','SIOC:SYS0:ML07:508','SIOC:SYS0:ML07:509'};
                    app.DisplayOptions.Quantity(1).Filter=[true,true]; 
                    app.DisplayOptions.SetFilter.Var='BPMS:DMPH:502:TMIT';
                    app.DisplayOptions.SetFilter.Range=[10^7,+inf];
                case 'Partition'
                    app.DisplayOptions.TypeOfPlot=3;
                    try
                        app.DisplayOptions.Quantity(2)=[];
                    end
                    try
                        app.DisplayOptions.Quantity(3)=[];
                    end
                    app.DisplayOptions.Quantity(1).LastN=NaN;
                    app.DisplayOptions.Quantity(1).Area=[];
                    app.DisplayOptions.Quantity(1).Style='b-';
                    app.DisplayOptions.Quantity(1).Weight=1;
                    app.DisplayOptions.Quantity(1).BasePV={'SIOC:SYS0:ML07:500','SIOC:SYS0:ML07:501','SIOC:SYS0:ML07:502','SIOC:SYS0:ML07:503','SIOC:SYS0:ML07:504','SIOC:SYS0:ML07:505','SIOC:SYS0:ML07:506','SIOC:SYS0:ML07:507','SIOC:SYS0:ML07:508','SIOC:SYS0:ML07:509'};
                    app.DisplayOptions.Quantity(1).Filter=[true,true];
                    app.DisplayOptions.SetFilter.Var='BPMS:DMPH:502:TMIT';
                    app.DisplayOptions.SetFilter.Range=[10^7,+inf];
            end
            
            if(app.MinimumPVtoberecordedCheckBox.Value)
                app.ScalarTables.SynchPV={'BPMS:LTUH:250:X',[],[];'GDET:FEE1:241:ENRC',[],[];'BPMS:DMPH:502:TMIT',[],[]};
                app.ScalarTables.OnlyOncePV={'REFS:DMPH:400:EDES'};
                app.ScalarTables.SlowPV={};
                app.BSA_Mode.Value='No BSA';
                app.BufferSizeEditField.Value=1571;
                app.BlockSizeEditField.Value=25;
            else
                app.ScalarTables.SynchPV=[app.ScalarList.BSA_LIST.GDET_HXR;app.ScalarList.BSA_LIST.LTUH_DMPH;app.ScalarList.BSA_LIST.BLEN];
                app.ScalarTables.SynchPV{end,3}=[];
                app.ScalarTables.OnlyOncePV={'REFS:DMPH:400:EDES'};
                app.ScalarTables.SlowPV={};
                app.BSA_Mode.Value='History Buffer BSA';
                app.BufferSizeEditField.Value=1571*3;
                app.BlockSizeEditField.Value=75;
            end
            app.ProfilemonitornameDropDownValueChanged();
            app.RelevantVariableName.Value=app.Profile.PVName;
        end
        
        function ConfigureAll(app)
            switch app.ISValue
                case 'TMO_SPECTROMETER'
                    app.SetupTMO_Spec();
                case 'HXR_SPECTROMETER'
                    app.SetupHXR_Spec();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IS)
            global eDefQuiet %#ok
            try %#ok
                if ~verLessThan('matlab','9.5')
%                     set(groot,'defaultFigureCreateFcn',@(fig,~)addToolbarExplorationButtons(fig));
%                     set(groot,'defaultAxesCreateFcn',  @(ax,~)set(ax.Toolbar,'Visible','off'));
                end
            end
            app.ProfileMontiorPositionDropDown.Value=1;
            app.ProfilemonitornameDropDown.Value=1;
            app.ISValue=IS;
            app.getAppColors();
            CONF=load([app.ConfigurationPath,'/CVCRCI_MAIN_CONFIGURATION_FILE.mat']);
            BCL=load([app.ConfigurationPath,'/BCL.mat'],'CameraList');
            try
                CustomAdditionalStuff=load('ADD_CustomStuff.mat');
            catch ME
                CustomAdditionalStuff=struct();
                disp('No additional Custom file found in pwd. Running standard configuration alone.')
            end
            app.allScalarNames();
            app.Displays=CONF.DISPLAYS;
            try
                if(isfield(CustomAdditionalStuff,'Analysis'))
                   app.Displays.Functions=[app.Displays.Functions,CustomAdditionalStuff.Analysis];
                end
            catch
                disp('Error while adding custom Analysis displays from ADD_CustomStuff.mat');
            end
            app.Displays.Number=numel(app.Displays.Functions);
            for II=1:numel(app.Displays.Functions)
                app.Displays.Functions(II).DisplayID=II;
            end
            app.Use_Lca_Monitor=CONF.USE_LCA_MONITOR;
            try
                if(isfield(CustomAdditionalStuff,'CameraList'))
                    BCL.CameraList=[BCL.CameraList,CustomAdditionalStuff.CameraList];
                end
            catch
                disp('Error while adding new camera displays from ADD_CustomStuff.mat');
            end
            app.Waveforms=BCL.CameraList;
            app.StartupIncludeNewVariablesInWaveforms();
            app.StartUpProfileSetup();
            app.ScalarTables.SynchPV={'BPMS:LTUH:250:X',[],[];'GDET:FEE1:241:ENRC',[],[]};
            app.ScalarTables.OnlyOncePV={'REFS:DMPH:400:EDES'};
            app.ScalarTables.SlowPV={'SIOC:SYS0:ML02:AO314'};
            app.ConfigureAll();
            app.ProfileMontiorPositionDropDown.Value=1;
            app.unfreeze();
        end

        % Callback function
        function C1ButtonPushed(app, event)
            UD=event.Source.UserData;
            app.openDisplay(UD.DisplayID);
        end

        % Callback function
        function UpdateDisplaysButtonPushed(app, event)
            app.UpdateAllDisplays;
        end

        % Callback function
        function ScanOrContinuousSelectionChanged(app, event)
            selectedTab = app.ScanOrContinuous.SelectedTab; %handle visibility for a bunch of stuff and reset counter variable
            switch selectedTab.Title
                case 'Scan'
                    set(app.ScansetupPanel,'visible','on');
                    set(app.AcquisitionVariableSummaryPanel,'visible','off');
                case 'Continuous'
                    set(app.ScansetupPanel,'visible','off');
                    set(app.AcquisitionVariableSummaryPanel,'visible','on');
                otherwise    
                    set(app.ScansetupPanel,'visible','off');
                    set(app.AcquisitionVariableSummaryPanel,'visible','on');
            end
            
        end

        % Button pushed function: UpdateDisplaysButton_2
        function UpdateDisplaysButton_2Pushed(app, event)
            UpdateAllDisplays(app);
        end

        % Button pushed function: ReleaseeDefsButton
        function ReleaseeDefsButtonPushed(app, event)
            app.releaseEDef();
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Enable='off'; app.STARTButton.BackgroundColor=app.color.On;
            app.STOPButton.Enable='on'; app.STOPButton.BackgroundColor=app.color.Idle;
            app.PAUSEButton.Enable='on'; app.STOPButton.BackgroundColor=app.color.Idle;
            
            app.CV=CVCRCI_Class;
            InputStructure=app.ReadAllInputs();
            if(app.Use_Lca_Monitor), InputStructure.UseLcaSetMonitor=1; end
            app.CV.uselcaGetSmart=app.UselcaGetinsteadoflcaGetSmartCheckBox.Value;
            app.CV.setupInputStructure(InputStructure);
            app.CV.BuildAcquisitionStructure();
            app.CV.MakeAcquisitionBuffers();
            app.CV.zeroAllBuffers();
            app.CV.Initialize_BSA();
            if(app.CV.Acquisition.BSA==1)
                app.ReleaseeDefsButton.UserData=app.CV.myeDefNumber;
                app.ReleaseeDefsButton.BackgroundColor=app.color.On;
                app.ReleaseeDefsButton.Enable='on';
            end
            runcounter=0;
            app.InitializeAllDisplays(); %Initialize Graphics
            
            while(1) %Acquisition continuous cycle.
                STOP=app.STOPButton.BackgroundColor; PAUSE=app.PAUSEButton.BackgroundColor;
                if(all(STOP==app.color.On))
                    app.unfreeze();
                    if(app.DumpatrecorderStopCONT.Value)
                        [SUCCESS,filename,TargetDir] = app.writeDataToDisk(now,app.CV,[]);
                        if(SUCCESS)
                            disp(['Saved ',TargetDir,'/',filename]);
                        else
                            disp('Saving file on disk failed!')
                        end
                    end
                    return
                end
                if(all(PAUSE==app.color.On))
                    while(1)
                        disp('Acquisition is on hold');
                        pause(0.25);
                        PAUSE=app.PAUSEButton.BackgroundColor;
                        if(all(PAUSE==app.color.Wait))
                            app.PAUSEButton.BackgroundColor=app.color.Idle; 
                            break
                        end
                    end
                end
                runcounter=runcounter+1;
                app.CV.ContAcquisitionCycle();
                app.CV.StateNumberContinuousRecording();
                app.BufferUpdate.Text=num2str(app.CV.Counter);
                app.AliveString.Text=app.AliveStrings{1+mod(runcounter,8)};
                app.UpdateAllDisplays(); %Update Graphics 
                drawnow
            end 
        end

        % Callback function
        function ProfileMontiorPositionDropDownValueChanged(app, event)
            POS = app.ProfileMontiorPositionDropDown.Value;
            app.ProfilemonitornameDropDown.Value = app.Profile(POS).WaveformID;
            app.ManageProfileButtonVisibility(POS);
            app.ManageBackgroundVisibilityAndUpdate(POS);
            app.ManagePostProcessingVisibilityAndUpdate(POS);
        end

        % Callback function
        function ProfilemonitornameDropDownValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            WID=app.ProfilemonitornameDropDown.Value;
            app.Profile(POS)=app.Waveforms(WID);
            app.ManageProfileButtonVisibility(POS);
            %app.ManageBackgroundVisibilityAndUpdate(POS);
            %app.ManagePostProcessingVisibilityAndUpdate(POS);
            %app.updateListOfScanVariable();
        end

        % Callback function
        function TimestampedCheckBoxValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).TimeStamped = app.TimestampedCheckBox.Value;
        end

        % Value changed function: SizeXEditField
        function SizeXEditFieldValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).size(1) = app.SizeXEditField.Value;
        end

        % Value changed function: SizeYEditField
        function SizeYEditFieldValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).size(2) = app.SizeYEditField.Value;
        end

        % Value changed function: PIDoffsetEditField
        function PIDoffsetEditFieldValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).PID_Delay = app.PIDoffsetEditField.Value;
        end

        % Value changed function: VariableBufferSizeEditField
        function VariableBufferSizeEditFieldValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).Buffersize = app.VariableBufferSizeEditField.Value;
        end

        % Button pushed function: GetSizeButton
        function GetSizeButtonPushed(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            WID=app.ProfilemonitornameDropDown.Value;
            if(isempty(app.Profile(POS).SizePV{1}))
                SizeXY(1)=app.Waveforms(WID).size(1);
            else
                SizeXY(1)=lcaGetSmart(app.Profile(POS).SizePV{1});
                if(isnan(SizeXY(1)))
                    disp(['Warning: ', app.Profile(POS).SizePV{1}, ' Returned NaN']); SizeXY(1)=app.Waveforms(WID).size(1);
                end
            end
            if(isempty(app.Profile(POS).SizePV{2}))
                SizeXY(2)=app.Waveforms(WID).size(2);
            else
                SizeXY(2)=lcaGetSmart(app.Profile(POS).SizePV{2});
                if(isnan(SizeXY(2)))
                    disp(['Warning: ', app.Profile(POS).SizePV{2}, ' Returned NaN']); SizeXY(2)=app.Waveforms(WID).size(2);
                end
            end
            app.Profile(POS).size(1)=SizeXY(1); app.Profile(POS).size(2)=SizeXY(2);
            app.SizeXEditField.Value=SizeXY(1); app.SizeYEditField.Value=SizeXY(2);
        end

        % Button pushed function: ReadsingleprofileButton
        function ReadsingleprofileButtonPushed(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            profile=app.Profile(POS).PVName;
            [RawData,ts]=lcaGetSmart(profile,prod(app.Profile(POS).size));
            if(any(app.Profile(POS).size))
                ShortLivedFigure=figure('Name','Reshaped Raw Data 2D Array');
                ShortLivedAxes=axes(ShortLivedFigure);
                imagesc(ShortLivedAxes,reshape(RawData,[app.Profile(POS).size(1),app.Profile(POS).size(2)]));
                title(ShortLivedAxes,['lcaGet Timestamp =',num2str(ts)]);
            end
            ShortLivedFigure=figure('Name','Raw Data 1D array');
            ShortLivedAxes=axes(ShortLivedFigure);
            plot(ShortLivedAxes,RawData);
            title(ShortLivedAxes,['lcaGet Timestamp =',num2str(ts)]);
        end

        % Button pushed function: GetBackgroundButton
        function GetBackgroundButtonPushed(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            profile=app.Profile(POS).PVName;
            TimeOutTime=15;
            BackgroundImagesNumber=app.SamplesEditField.Value;%
            Size_X=app.Profile(POS).size(1);
            Size_Y=app.Profile(POS).size(2);
            Pid_Old=-1;
            tic, Time=toc;
            Inserite=0;
            while ( (Inserite < BackgroundImagesNumber) && (Time<TimeOutTime) )
                [prof,ts]=lcaGetSmart(profile,Size_X*Size_Y);
                PID=bitand(uint32(imag(ts)),hex2dec('1FFFF'));
                app.ListaBackground.Text=num2str(PID);
                if(PID~=Pid_Old)
                    if(Inserite==0)
                        BackgroundImagesVector=zeros([size(prof),BackgroundImagesNumber]);
                    end
                    Inserite=Inserite+1;
                    BackgroundImagesVector(:,:,Inserite)=prof;
                    Time=toc;
                    Pid_Old=PID;
                    app.ListaBackground.Text = [num2str(PID),' / ',num2str(Inserite)];
                end
                drawnow
            end
            app.ListaBackground.Text='...';
            BackgroundImagesVector=double(BackgroundImagesVector(:,:,1:Inserite));
            BackgroundAverage=mean(BackgroundImagesVector,3);
            if(numel(BackgroundAverage)==1)
                if(isnan(BackgroundAverage))
                    BackgroundAverage=[];
                end
            end
            app.Profile(POS).Background=BackgroundAverage;
            %app.ManageBackgroundVisibilityAndUpdate(POS)
            Filename=regexprep(regexprep(profile,' ',''),':','-');
            save([app.ConfigurationPath,'/BackgroundAndBaseline/',Filename],'BackgroundAverage');
        end

        % Callback function
        function DoPostprocessingCheckBoxValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).PostProcessing = app.DoPostprocessingCheckBox.Value;
            app.PostProcessingFunctionDropDown.Items = app.Waveforms(POS).PostProcessingF_List;
            app.PostProcessingFunctionDropDown.ItemsData = 1:length(app.Waveforms(POS).PostProcessingF_List);
            app.PostProcessingFunctionDropDown.Value = 1;
            app.ManageChangeInPostProcessingFunction(POS);
        end

        % Callback function
        function PostProcessingFunctionDropDownValueChanged(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.ManageChangeInPostProcessingFunction(POS);
        end

        % Callback function
        function SetnewPostProcessingoptionsfromTableButtonPushed(app, event)
            app.SetnewPostProcessingoptionsfromTableButton.BackgroundColor=app.color.Wait; drawnow;
            Options=app.PostProcessingOptions.Data;
            POS=app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).PostProcessingOptions=Options;
            app.ManagePostProcessingVisibilityAndUpdate(POS);
            app.SetnewPostProcessingoptionsfromTableButton.BackgroundColor=app.color.Idle;
        end

        % Callback function
        function InitializationofpersistentprocessingvariablesButtonPushed(app, event)
            app.InitializationofpersistentprocessingvariablesButton.BackgroundColor=app.color.Wait;
            POS=app.ProfileMontiorPositionDropDown.Value;
            TABLE=app.PostProcessingOptions.Data;
            Data=app.Profile(POS).PostProcessingFunction(lcaGetSmart(app.Profile(POS).PVName,prod(app.Profile(POS).size)),TABLE,app.Profile(POS),[],2);
            app.InitializationofpersistentprocessingvariablesButton.BackgroundColor=app.color.Idle;
        end

        % Callback function
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch(selectedTab.Title)
                case 'Take Data'
                    app.UpdateRecordingSummary();
                case 'Setup Vector/Image Acquisition'
                    POS = app.ProfileMontiorPositionDropDown.Value;
                    app.ManageProfileButtonVisibility(POS);
                    app.ManageBackgroundVisibilityAndUpdate(POS);
                    app.ManagePostProcessingVisibilityAndUpdate(POS);
                case 'Setup Scalar Acquisition'
                    app.epv_MylistSync.Items=app.ScalarTables.SynchPV(:,1);
                    app.epv_MylistNotSync.Items=app.ScalarTables.SlowPV;
                    app.epv_MylistOnlyOnce.Items=app.ScalarTables.OnlyOncePV;
                    BsaLists=fieldnames(app.ScalarList.BSA_LIST);
                    BsaLists(:,2)={false};     
                    app.epv_SynchGroup.Data=BsaLists;
                    NonBsaLists=fieldnames(app.ScalarList.NON_BSA_LIST);
                    NonBsaLists(:,2:3)={false}; 
                    app.epv_AsynchGroup.Data=NonBsaLists;
                    app.FilterListDropDown.Items=app.ScalarGroups;
                    app.FilterListDropDown.ItemsData=1:length(app.ScalarGroups);
                    app.FilterListDropDown.Value=1;
                    app.ManageScalarList();
                case 'Stored CVCRCI Presets'
                case 'Load Stored Dataset'
                    app.InitializeLoadTab();
                otherwise
                    disp('App desinger does not allow to work this out by Tab number!')
            end
            
        end

        % Button pushed function: ClearBackgroundButton
        function ClearBackgroundButtonPushed(app, event)
            POS = app.ProfileMontiorPositionDropDown.Value;
            app.Profile(POS).Background=[];
            %app.ManageBackgroundVisibilityAndUpdate(POS);
        end

        % Callback function
        function FilterListDropDownValueChanged(app, event)
            app.ManageScalarList();
        end

        % Callback function
        function P1ValueChanged(app, event)
            app.searchPV();
        end

        % Callback function
        function AddListButtonPushed(app, event)
            if(isempty(app.ScalarTables.SynchPV))
                app.epv_MylistSync.Items=app.epv_FullList.Items;
                app.ScalarTables.SynchPV=app.epv_FullList.Items(:);
                app.ScalarTables.SynchPV(:,2:3)={[]};
            else
                NewElements=setdiff(app.epv_FullList.Items(:),app.ScalarTables.SynchPV(:,1),'stable');
                NewElements=NewElements(:);
                app.ScalarTables.SynchPV((end+1):(end+length(NewElements)),1)=NewElements;
                app.ScalarTables.SynchPV(:,2:3)={[]};
                app.epv_MylistSync.Items=[app.epv_MylistSync.Items(:);NewElements];
            end       
            app.updateListOfScanVariable();
        end

        % Callback function
        function AddSelectionButtonPushed(app, event)
            NewElements=app.epv_FullList.Value; NewElements=NewElements(:);
            if(isempty(app.ScalarTables.SynchPV))
                app.epv_MylistSync.Items=NewElements;
                app.ScalarTables.SynchPV=NewElements;
                app.ScalarTables.SynchPV(:,2:3)={[]};
            else
                NewElements=setdiff(NewElements,app.ScalarTables.SynchPV(:,1),'stable');
                NewElements=NewElements(:);
                app.ScalarTables.SynchPV((end+1):(end+length(NewElements)),1)=NewElements;
                app.ScalarTables.SynchPV(:,2:3)={[]};
                app.epv_MylistSync.Items=[app.epv_MylistSync.Items(:);NewElements];
            end    
            app.updateListOfScanVariable();
        end

        % Callback function
        function RemoveListButtonPushed(app, event)
            SelectedElements=app.epv_FullList.Items; SelectedElements=SelectedElements(:);
            if(isempty(app.ScalarTables.SynchPV))
                return
            else
                [~,~,ToDeleteElements2]=intersect(SelectedElements,app.ScalarTables.SynchPV(:,1),'stable');
                app.epv_MylistSync.Items(ToDeleteElements2)=[];
                app.ScalarTables.SynchPV(ToDeleteElements2,:)=[];
            end 
            app.updateListOfScanVariable();
        end

        % Callback function
        function P2ValueChanged(app, event)
            app.searchPV();
        end

        % Callback function
        function P3ValueChanged(app, event)
            app.searchPV();
        end

        % Callback function
        function P4ValueChanged(app, event)
            app.searchPV();
        end

        % Callback function
        function RemovefromSynchronousButtonPushed(app, event)
            SelectedElements=app.epv_MylistSync.Value; SelectedElements=SelectedElements(:);
            [~,~,ToDeleteElements2]=intersect(SelectedElements,app.ScalarTables.SynchPV(:,1),'stable');
            app.epv_MylistSync.Items(ToDeleteElements2)=[];
            app.ScalarTables.SynchPV(ToDeleteElements2,:)=[];
            app.updateListOfScanVariable();
        end

        % Callback function
        function AddtoSynchronousButtonPushed(app, event)
            NewElement=app.AddToSynchField.Value;
            [~,~,IntersectedElement]=intersect(NewElement,app.ScalarTables.SynchPV(:,1),'stable');
            if(isempty(IntersectedElement))
               app.ScalarTables.SynchPV{end+1,1}=NewElement;
               app.ScalarTables.SynchPV(end,2:3)={[]};
               app.epv_MylistSync.Items{end+1}=NewElement;
            end
            app.updateListOfScanVariable();
        end

        % Callback function
        function AddasSlowPVButtonPushed(app, event)
            NewElement=app.AddtoSlowPVEdit.Value;
            [~,~,IntersectedElement]=intersect(NewElement,app.ScalarTables.SlowPV,'stable');
            if(isempty(IntersectedElement))
               app.ScalarTables.SlowPV{end+1}=NewElement;
               app.epv_MylistNotSync.Items{end+1}=NewElement;
            end
            app.updateListOfScanVariable();
        end

        % Callback function
        function RemoveSlowPVButtonPushed(app, event)
            SelectedElements=app.epv_MylistNotSync.Value; SelectedElements=SelectedElements(:);
            [~,~,ToDeleteElements2]=intersect(SelectedElements,app.ScalarTables.SlowPV,'stable');
            app.epv_MylistNotSync.Items(ToDeleteElements2)=[];
            app.ScalarTables.SlowPV(ToDeleteElements2,:)=[];
            app.updateListOfScanVariable();
        end

        % Callback function
        function AddPVButtonPushed(app, event)
            NewElement=app.OnlyOncePVEdit.Value;
            [~,~,IntersectedElement]=intersect(NewElement,app.ScalarTables.OnlyOncePV,'stable');
            if(isempty(IntersectedElement))
               app.ScalarTables.OnlyOncePV{end+1}=NewElement;
               app.epv_MylistOnlyOnce.Items{end+1}=NewElement;
            end
            app.updateListOfScanVariable();
        end

        % Callback function
        function RemovePVButtonPushed(app, event)
            SelectedElements=app.epv_MylistOnlyOnce.Value; SelectedElements=SelectedElements(:);
            [~,~,ToDeleteElements2]=intersect(SelectedElements,app.ScalarTables.OnlyOncePV,'stable');
            app.epv_MylistOnlyOnce.Items(ToDeleteElements2)=[];
            app.ScalarTables.OnlyOncePV(ToDeleteElements2,:)=[];
            app.updateListOfScanVariable();
        end

        % Callback function
        function ReplaceallandAddselectedButtonPushed(app, event)
            AddLists=app.FindAddLists;
            app.ScalarTables.OnlyOncePV=AddLists.OnlyOncePV(:);
            app.ScalarTables.SlowPV=AddLists.SlowPV(:);
            app.ScalarTables.SynchPV=AddLists.SynchPV(:);
            app.ScalarTables.SynchPV(:,2:3)={[]};
            app.epv_MylistSync.Items=AddLists.SynchPV;
            app.epv_MylistNotSync.Items=AddLists.SlowPV;
            app.epv_MylistOnlyOnce.Items=AddLists.OnlyOncePV;
            app.updateListOfScanVariable();
        end

        % Callback function
        function AddliststoexistingPVsButtonPushed(app, event)
            AddLists=app.FindAddLists;
            NewElements=setdiff(AddLists.OnlyOncePV,app.ScalarTables.OnlyOncePV,'stable');
            if(~isempty(NewElements))
                app.ScalarTables.OnlyOncePV((end+1):(end+numel(NewElements)))=NewElements;
            end
            NewElements=setdiff(AddLists.SlowPV,app.ScalarTables.SlowPV,'stable');
            if(~isempty(NewElements))
                app.ScalarTables.SlowPV((end+1):(end+numel(NewElements)))=NewElements;
            end
            NewElements=setdiff(AddLists.SynchPV,app.SynchPV(:,1),'stable');
            if(~isempty(NewElements))
                app.ScalarTables.SynchPV((end+1):(end+numel(NewElements)),:)=NewElements;
            end
            app.epv_MylistSync.Items=AddLists.SynchPV(:,1);
            app.epv_MylistNotSync.Items=AddLists.SlowPV;
            app.epv_MylistOnlyOnce.Items=AddLists.OnlyOncePV;
            app.updateListOfScanVariable();
        end

        % Callback function
        function StorePresetinNewPositionButtonPushed(app, event)
            NewPresetName=app.CVCRCIPresetNameEditField.Value;
            app.Presets{end+1}.name=NewPresetName;
            app.Presets{end}.Variables.SynchPV=app.ScalarTables.SynchPV;
            app.Presets{end}.Variables.SlowPV=app.ScalarTables.SlowPV;
            app.Presets{end}.Variables.OnlyOncePV=app.ScalarTables.OnlyOncePV;
            app.Presets{end}.Profile=app.Profile;
            app.Presets{end}.AcquisitionSetup.BSA_Mode=app.BSA_Mode.Value;
            app.Presets{end}.AcquisitionSetup.Synch_Mode=app.Syncrhonization.Value;
            app.Presets{end}.AcquisitionSetup.BeamCode=app.BeamCode.Value;
            app.Presets{end}.AcquisitionSetup.BufferSize=app.BufferSizeEditField.Value;
            app.Presets{end}.AcquisitionSetup.BlockSize=app.BlockSizeEditField.Value;
            app.Presets{end}.AcquisitionSetup.BSATime=app.BSATimeEditField.Value;
            app.Presets{end}.AcquisitionSetup.BSAwait=app.BSAwaitEditField.Value;
            app.Presets{end}.AcquisitionSetup.Author=app.AuthorEditField.Value;
            app.Presets{end}.AcquisitionSetup.TimeReference=app.TimeEditField.Value;
            app.Presets{end}.AcquisitionSetup.ExitAtBufferFilled=app.StopBufferFilled.Value;
            app.Presets{end}.AcquisitionSetup.AtBufferFullFunction=app.IfFullDropDown.Value;
            app.Presets{end}.AcquisitionSetup.InsertedCounterVariableName=app.RelevantVariableName.Value;
            Preset=app.Presets;
            save([app.ConfigurationPath,'/CVCRCI_MAIN_CONFIGURATION_FILE.mat'],'Preset','-append');
            app.ManagePresetButtons();
        end

        % Callback function
        function StorePresetatexistingPositionasButtonPushed(app, event)
            NewPresetName=app.PresetNewnameEditField.Value;
            ID=app.PresetDropDown.Value;
            if(ID>=1)
                app.Presets{ID}.name=NewPresetName;
                app.Presets{ID}.Variables.SynchPV=app.ScalarTables.SynchPV;
                app.Presets{ID}.Variables.SlowPV=app.ScalarTables.SlowPV;
                app.Presets{ID}.Variables.OnlyOncePV=app.ScalarTables.OnlyOncePV;
                app.Presets{ID}.Profile=app.Profile;
                app.Presets{ID}.AcquisitionSetup.BSA_Mode=app.BSA_Mode.Value;
                app.Presets{ID}.AcquisitionSetup.Synch_Mode=app.Syncrhonization.Value;
                app.Presets{ID}.AcquisitionSetup.BeamCode=app.BeamCode.Value;
                app.Presets{ID}.AcquisitionSetup.BufferSize=app.BufferSizeEditField.Value;
                app.Presets{ID}.AcquisitionSetup.BlockSize=app.BlockSizeEditField.Value;
                app.Presets{ID}.AcquisitionSetup.BSATime=app.BSATimeEditField.Value;
                app.Presets{ID}.AcquisitionSetup.BSAwait=app.BSAwaitEditField.Value;
                app.Presets{ID}.AcquisitionSetup.Author=app.AuthorEditField.Value;
                app.Presets{ID}.AcquisitionSetup.TimeReference=app.TimeEditField.Value;
                app.Presets{ID}.AcquisitionSetup.ExitAtBufferFilled=app.StopBufferFilled.Value;
                app.Presets{ID}.AcquisitionSetup.AtBufferFullFunction=app.IfFullDropDown.Value;
                app.Presets{ID}.AcquisitionSetup.InsertedCounterVariableName=app.RelevantVariableName.Value;
                Preset=app.Presets;
                save([app.ConfigurationPath,'/CVCRCI_MAIN_CONFIGURATION_FILE.mat'],'Preset','-append');
                app.ManagePresetButtons();
            end
        end

        % Callback function
        function A1ButtonPushed(app, event)
            app.RestorePreset(event.Source.UserData);
            app.UpdateRecordingSummary();
            app.TabGroup.SelectedTab=app.TakeDataTab;
        end

        % Button pushed function: UNFREEZEButton
        function UNFREEZEButtonPushed(app, event)
            app.unfreeze();
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            if(all(app.PAUSEButton.BackgroundColor==app.color.Idle))
                app.PAUSEButton.BackgroundColor=app.color.On; drawnow;
            else
                app.PAUSEButton.BackgroundColor=app.color.Wait; drawnow;
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.STOPButton.BackgroundColor=app.color.On; drawnow;
        end

        % Callback function
        function ScanpresetsButtonGroupSelectionChanged(app, event)
            app.scanPresetPicked();
            app.evaluateScanSettingFromTable();
        end

        % Callback function
        function ScanEnvironmentsValueChanged(app, event)
            app.updatePresetListSelection();
            app.scanPresetPicked();
            app.evaluateScanSettingFromTable();
        end

        % Callback function
        function ScanSetupTableCellEdit(app, event)
            app.evaluateScanSettingFromTable();
        end

        % Callback function
        function STARTButtonSCANPushed(app, event)
            app.STARTButtonSCAN.Enable='off'; app.STARTButtonSCAN.BackgroundColor=app.color.On;
            app.STOPButtonSCAN.Enable='on'; app.STOPButtonSCAN.BackgroundColor=app.color.Idle;
            app.PAUSEButtonSCAN.Enable='on'; app.STOPButtonSCAN.BackgroundColor=app.color.Idle;
            
            app.CV=CVCRCI_Class;
            InputStructure=app.ReadAllInputs();
            InputStructure.DoScan=1;
            InputStructure.ScanSetting=app.ScErr.UserData;
            if(app.Use_Lca_Monitor), InputStructure.UseLcaSetMonitor=1; end
            app.CV.setupInputStructure(InputStructure);
            app.CV.BuildAcquisitionStructure();
            app.CV.MakeAcquisitionBuffers();
            app.CV.zeroAllBuffers();
            app.CV.Initialize_BSA();
            if(app.CV.Acquisition.BSA==1)
                app.ReleaseeDefsButton.UserData=app.CV.myeDefNumber;
                app.ReleaseeDefsButton.BackgroundColor=app.color.On;
                app.ReleaseeDefsButton.Enable='on';
            end
            runcounter=0;
            app.InitializeAllDisplays(); %Initialize Graphics
            app.CV.InitializeScan();
            while(~app.CV.ScanState.Finished)
                app.CV.SetNextPosition();
                while(app.CV.ScanState.SamplesRecordedThisStep < app.CV.Acquisition.Scan.Samples)
                    STOP=app.STOPButtonSCAN.BackgroundColor; PAUSE=app.PAUSEButtonSCAN.BackgroundColor;
                    if(all(STOP==app.color.On))
                        app.unfreeze();
                        if(app.DumpatrecorderStopSCAN.Value)
                            [SUCCESS,filename,TargetDir] = app.writeDataToDisk(now,app.CV,[]);
                            if(SUCCESS)
                                disp(['Saved ',TargetDir,'/',filename]);
                            else
                                disp('Saving file on disk failed!')
                            end
                        end
                        return
                    end
                    if(all(PAUSE==app.color.On))
                        while(1)
                            disp('Acquisition is on hold');
                            pause(0.25);
                            PAUSE=app.PAUSEButtonSCAN.BackgroundColor;
                            if(all(PAUSE==app.color.Wait))
                                app.PAUSEButtonSCAN.BackgroundColor=app.color.Idle;
                                break
                            end
                        end
                        app.CV.InvalidateBSAScan();
                    end
                    app.CV.ContAcquisitionCycle();
                    app.CV.StateNumberScan();
                    app.ScanBufferUpdate.Text=app.CV.ScanState.PositionString;
                    app.ScanPoint.Text=app.CV.ScanState.StepPositionString; drawnow
                    runcounter=runcounter+1;
                    app.UpdateAllDisplays();
                end
                app.CV.IncrementScanPosition();
            end
            app.ScanBufferUpdate.Text='Finished.';
            app.PAUSEButtonSCAN.Enable='off';
            while(1)
                STOP=app.STOPButtonSCAN.BackgroundColor;
                if(all(STOP==app.color.On))
                    app.unfreeze();
                    if(app.DumpatrecorderStopSCAN.Value)
                        [SUCCESS,filename,TargetDir] = app.writeDataToDisk(now,app.CV,[]);
                        if(SUCCESS)
                            disp(['Saved ',TargetDir,'/',filename]);
                        else
                            disp('Saving file on disk failed!')
                        end
                    end
                    return
                end
                app.UpdateAllDisplays(); pause(0.15);
            end
        end

        % Callback function
        function PAUSEButtonSCANPushed(app, event)
            if(all(app.PAUSEButtonSCAN.BackgroundColor==app.color.Idle))
                app.PAUSEButtonSCAN.BackgroundColor=app.color.On; drawnow;
            else
                app.PAUSEButtonSCAN.BackgroundColor=app.color.Wait; drawnow;
            end
        end

        % Callback function
        function STOPButtonSCANPushed(app, event)
            app.STOPButtonSCAN.BackgroundColor=app.color.On; drawnow;
        end

        % Callback function
        function TodayButtonPushed(app, event)
            app.UpdateTargetDir(1);
            app.UpdateFileList();
        end

        % Callback function
        function pwdButtonPushed(app, event)
            app.UpdateTargetDir(0);
            app.UpdateFileList();
        end

        % Callback function
        function AnnoEditFieldValueChanged(app, event)
            app.UpdateTargetDir(1);
            app.UpdateFileList();
        end

        % Callback function
        function MonthEditFieldValueChanged(app, event)
            app.UpdateTargetDir(1);
            app.UpdateFileList();
        end

        % Callback function
        function DayEditFieldValueChanged(app, event)
            app.UpdateTargetDir(1);
            app.UpdateFileList();
        end

        % Callback function
        function FolderEditFieldValueChanged(app, event)
            app.UpdateFileList();
        end

        % Callback function
        function FilterstringEditFieldValueChanged(app, event)
            app.UpdateFileList();
        end

        % Callback function
        function UiGetFileButtonPushed(app, event)
            [FILENAME, PATHNAME] = uigetfile([app.FolderEditField.Value,'/',app.FilterstringEditField.Value], 'Take a data file.');
            if(FILENAME)
                app.FolderEditField.Value=PATHNAME;
                app.FilterstringEditField.Value=FILENAME;
            end
            app.UpdateFileList();
        end

        % Callback function
        function LoadButtonPushed(app, event)
            PATHNAME=app.FolderEditField.Value;
            FILENAME=app.Filenames.Value;
            app.CV=CVCRCI_Class;
            app.CV.LoadFile([PATHNAME,'/',FILENAME]);
            DataXX={};
            for II=1:numel(app.CV.Recorder.Data)
                for JJ=1:3
                    DataXX{JJ,II}=size(app.CV.Recorder.Data{II},JJ); %#ok<AGROW> 
                end
            end
            set(app.DataFound,'data',DataXX);
            app.InitializeAllDisplays();
            app.VariablesListBox.Items=app.CV.Acquisition.VarNames;
        end

        % Callback function
        function AddoneVariableButtonPushed(app, event)
            app.ScanSetupTable.Data(:,end+1)=app.ScanSetupTable.Data(:,end);
        end

        % Callback function
        function UpdateOnceButtonPushed(app, event)
            app.UpdateAllDisplays();
        end

        % Callback function
        function StopUpdateButtonPushed(app, event)
            app.StopUpdateButton.BackgroundColor=app.color.On; drawnow;
        end

        % Callback function
        function RunUpdateButtonPushed(app, event)
            while(1)
                if(all(app.StopUpdateButton.BackgroundColor==app.color.On))
                    app.StopUpdateButton.BackgroundColor=app.color.Idle;
                    return
                end
                app.CV.CycleEvent();
                app.UpdateAllDisplays();Pvalue=app.pauseEditField.Value;
                pause(Pvalue);
            end
        end

        % Button pushed function: ReloadLastBackgroundButton
        function ReloadLastBackgroundButtonPushed(app, event)
            POS=app.ProfileMontiorPositionDropDown.Value;
            profile=app.Profile(POS).PVName;
            Filename=regexprep(regexprep(profile,' ',''),':','-');
            load([app.ConfigurationPath,'/BackgroundAndBaseline/',Filename],'BackgroundAverage');
            app.Profile(POS).Background=BackgroundAverage;
            %app.ManageBackgroundVisibilityAndUpdate(POS);
        end

        % Callback function
        function PresetDropDownValueChanged(app, event)
            app.PresetNewnameEditField.Value=app.PresetDropDown.Items{app.PresetDropDown.Value};
        end

        % Callback function
        function AD_CloseButtonPushed(app, event)
            disp('non fa niente')
        end

        % Value changed function: MinimumPVtoberecordedCheckBox
        function MinimumPVtoberecordedCheckBoxValueChanged(app, event)
            app.ConfigureAll();
        end

        % Value changed function: AutoconfDropDown
        function AutoconfDropDownValueChanged(app, event)
            app.ConfigureAll();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CVCRCIBasicUIFigure = uifigure('Visible', 'off');
            app.CVCRCIBasicUIFigure.Name = 'CVCRCI - Basic ';
            app.SynchronizationandBufferPanel = uipanel(app.CVCRCIBasicUIFigure);
            app.SynchronizationandBufferPanel.Title = 'Synchronization and Buffer';
            app.BSA_Mode = uidropdown(app.SynchronizationandBufferPanel);
            app.BSA_Mode.Items = {'No BSA', 'Double Buffer BSA', 'History Buffer BSA'};
            app.BSA_Mode.Value = 'No BSA';
            app.Syncrhonization = uidropdown(app.SynchronizationandBufferPanel);
            app.Syncrhonization.Items = {'Synchronous Mode', 'Asynchronous Mode'};
            app.Syncrhonization.Value = 'Synchronous Mode';
            app.BeamCode = uidropdown(app.SynchronizationandBufferPanel);
            app.BeamCode.Items = {'All 120 Hz', 'HXR Branch only', 'SXR Branch only'};
            app.BeamCode.Value = 'All 120 Hz';
            app.BufferSizeEditFieldLabel = uilabel(app.SynchronizationandBufferPanel);
            app.BufferSizeEditFieldLabel.Text = 'Buffer Size';
            app.BufferSizeEditField = uieditfield(app.SynchronizationandBufferPanel, 'numeric');
            app.BufferSizeEditField.Value = 1571;
            app.BlockSizeEditFieldLabel = uilabel(app.SynchronizationandBufferPanel);
            app.BlockSizeEditFieldLabel.Text = 'Block Size';
            app.BlockSizeEditField = uieditfield(app.SynchronizationandBufferPanel, 'numeric');
            app.BlockSizeEditField.Value = 75;
            app.BSATimeEditFieldLabel = uilabel(app.SynchronizationandBufferPanel);
            app.BSATimeEditFieldLabel.Text = 'BSA Time';
            app.BSATimeEditField = uieditfield(app.SynchronizationandBufferPanel, 'numeric');
            app.BSATimeEditField.Value = 0.5;
            app.BSAwaitLabel = uilabel(app.SynchronizationandBufferPanel);
            app.BSAwaitLabel.Text = 'BSA + wait';
            app.BSAwaitEditField = uieditfield(app.SynchronizationandBufferPanel, 'numeric');
            app.AuthorEditFieldLabel = uilabel(app.SynchronizationandBufferPanel);
            app.AuthorEditFieldLabel.Text = 'Author';
            app.AuthorEditField = uieditfield(app.SynchronizationandBufferPanel, 'text');
            app.AuthorEditField.Value = 'Matlab';
            app.TimeEditFieldLabel = uilabel(app.SynchronizationandBufferPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.SynchronizationandBufferPanel, 'text');
            app.TimeEditField.Value = 'GDET:FEE1:241:ENRC';
            app.StopBufferFilled = uicheckbox(app.SynchronizationandBufferPanel);
            app.StopBufferFilled.Text = 'Stop at buffer filled';
            app.IfFullDropDownLabel = uilabel(app.SynchronizationandBufferPanel);
            app.IfFullDropDownLabel.Text = 'If Full';
            app.IfFullDropDown = uidropdown(app.SynchronizationandBufferPanel);
            app.IfFullDropDown.Items = {'DoNothing', 'SaveOnDisk'};
            app.IfFullDropDown.Value = 'DoNothing';
            app.StartStopPanel = uipanel(app.CVCRCIBasicUIFigure);
            app.StartStopPanel.Title = 'Start & Stop';
            app.STARTButton = uibutton(app.StartStopPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.PAUSEButton = uibutton(app.StartStopPanel, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.STOPButton = uibutton(app.StartStopPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.DumpatrecorderStopCONT = uicheckbox(app.StartStopPanel);
            app.DumpatrecorderStopCONT.Text = 'Dump at recorder Stop';
            app.BufferUpdate = uilabel(app.StartStopPanel);
            app.BufferUpdate.Text = '...';
            app.AliveString = uilabel(app.StartStopPanel);
            app.AliveString.Text = '...';
            app.UNFREEZEButton = uibutton(app.StartStopPanel, 'push');
            app.UNFREEZEButton.ButtonPushedFcn = createCallbackFcn(app, @UNFREEZEButtonPushed, true);
            app.UNFREEZEButton.Text = 'UNFREEZE';
            app.ReleaseeDefsButton = uibutton(app.StartStopPanel, 'push');
            app.ReleaseeDefsButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseeDefsButtonPushed, true);
            app.ReleaseeDefsButton.Text = 'Release eDefs';
            app.UpdateDisplaysButton_2 = uibutton(app.StartStopPanel, 'push');
            app.UpdateDisplaysButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateDisplaysButton_2Pushed, true);
            app.UpdateDisplaysButton_2.Text = 'Update Displays';
            app.AutoconfDropDownLabel = uilabel(app.StartStopPanel);
            app.AutoconfDropDownLabel.Text = 'Autoconf.';
            app.AutoconfDropDown = uidropdown(app.StartStopPanel);
            app.AutoconfDropDown.Items = {'General', 'L-Plot', 'Partition'};
            app.AutoconfDropDown.ValueChangedFcn = createCallbackFcn(app, @AutoconfDropDownValueChanged, true);
            app.AutoconfDropDown.Value = 'General';
            app.ProfileDetailsPanel = uipanel(app.CVCRCIBasicUIFigure);
            app.ProfileDetailsPanel.Title = 'Profile Details';
            app.ProfileFullPVName = uilabel(app.ProfileDetailsPanel);
            app.ProfileFullPVName.Text = 'NO PROFILE PV TO BE RECORDED';
            app.SizeXEditField_2Label = uilabel(app.ProfileDetailsPanel);
            app.SizeXEditField_2Label.Text = 'Size X';
            app.SizeXEditField = uieditfield(app.ProfileDetailsPanel, 'numeric');
            app.SizeXEditField.ValueChangedFcn = createCallbackFcn(app, @SizeXEditFieldValueChanged, true);
            app.SizeYEditFieldLabel = uilabel(app.ProfileDetailsPanel);
            app.SizeYEditFieldLabel.Text = 'Size Y';
            app.SizeYEditField = uieditfield(app.ProfileDetailsPanel, 'numeric');
            app.SizeYEditField.ValueChangedFcn = createCallbackFcn(app, @SizeYEditFieldValueChanged, true);
            app.GetSizeButton = uibutton(app.ProfileDetailsPanel, 'push');
            app.GetSizeButton.ButtonPushedFcn = createCallbackFcn(app, @GetSizeButtonPushed, true);
            app.GetSizeButton.Text = 'Get Size';
            app.GetBackgroundButton = uibutton(app.ProfileDetailsPanel, 'push');
            app.GetBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @GetBackgroundButtonPushed, true);
            app.GetBackgroundButton.Text = 'Get Background';
            app.SamplesEditFieldLabel = uilabel(app.ProfileDetailsPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.ProfileDetailsPanel, 'numeric');
            app.SamplesEditField.Value = 100;
            app.ReloadLastBackgroundButton = uibutton(app.ProfileDetailsPanel, 'push');
            app.ReloadLastBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadLastBackgroundButtonPushed, true);
            app.ReloadLastBackgroundButton.Text = 'Reload Last Background';
            app.ClearBackgroundButton = uibutton(app.ProfileDetailsPanel, 'push');
            app.ClearBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBackgroundButtonPushed, true);
            app.ClearBackgroundButton.Text = 'Clear Background';
            app.forAsynchronousModeOnlyLabel = uilabel(app.ProfileDetailsPanel);
            app.forAsynchronousModeOnlyLabel.Text = 'for Asynchronous Mode Only:';
            app.VariableBufferSizeEditFieldLabel = uilabel(app.ProfileDetailsPanel);
            app.VariableBufferSizeEditFieldLabel.Text = 'Variable Buffer Size';
            app.VariableBufferSizeEditField = uieditfield(app.ProfileDetailsPanel, 'numeric');
            app.VariableBufferSizeEditField.ValueChangedFcn = createCallbackFcn(app, @VariableBufferSizeEditFieldValueChanged, true);
            app.PIDoffsetEditFieldLabel = uilabel(app.ProfileDetailsPanel);
            app.PIDoffsetEditFieldLabel.Text = 'PID offset';
            app.PIDoffsetEditField = uieditfield(app.ProfileDetailsPanel, 'numeric');
            app.PIDoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @PIDoffsetEditFieldValueChanged, true);
            app.ReadsingleprofileButton = uibutton(app.ProfileDetailsPanel, 'push');
            app.ReadsingleprofileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadsingleprofileButtonPushed, true);
            app.ReadsingleprofileButton.Text = 'Read single profile';
            app.MinimumPVtoberecordedCheckBox = uicheckbox(app.ProfileDetailsPanel);
            app.MinimumPVtoberecordedCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinimumPVtoberecordedCheckBoxValueChanged, true);
            app.MinimumPVtoberecordedCheckBox.Text = 'Minimum PV to be recorded';
            app.MinimumPVtoberecordedCheckBox.Value = true;
            app.UselcaGetinsteadoflcaGetSmartCheckBox = uicheckbox(app.ProfileDetailsPanel);
            app.UselcaGetinsteadoflcaGetSmartCheckBox.Text = 'use lcaGet instead of lcaGetSmart';
            app.ListaBackground = uilabel(app.ProfileDetailsPanel);
            app.ListaBackground.Text = '...';
            % Show the figure after all components are created
            app.CVCRCIBasicUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVCRCI_Basic(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CVCRCIBasicUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CVCRCIBasicUIFigure)
        end
    end
end