classdef CVCRCI_Dispersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DispersionDisplayUIFigure     matlab.ui.Figure
        XMinEditFieldLabel            matlab.ui.control.Label
        XMinEditField                 matlab.ui.control.EditField
        XMaxEditFieldLabel            matlab.ui.control.Label
        XMaxEditField                 matlab.ui.control.EditField
        YMinEditFieldLabel            matlab.ui.control.Label
        YMinEditField                 matlab.ui.control.EditField
        YMaxEditFieldLabel            matlab.ui.control.Label
        YMaxEditField                 matlab.ui.control.EditField
        AxesFigureButton              matlab.ui.control.Button
        MoveAxesNearButton            matlab.ui.control.Button
        PositionDropDown              matlab.ui.control.DropDown
        DefaultAxesSizeButton         matlab.ui.control.Button
        LogbookButton                 matlab.ui.control.Button
        SaveDataCheckBox              matlab.ui.control.CheckBox
        EditTextFigCheckBox           matlab.ui.control.CheckBox
        SaveLabel                     matlab.ui.control.Label
        TABULA                        matlab.ui.control.Table
        FilteringoptionsLabel         matlab.ui.control.Label
        AxiscontrolLabel              matlab.ui.control.Label
        STOP                          matlab.ui.control.Button
        FilterAddLine                 matlab.ui.control.Button
        FilterRemoveLine              matlab.ui.control.Button
        ExpressionEditFieldLabel      matlab.ui.control.Label
        ExpressionEditField           matlab.ui.control.EditField
        ShowstatsCheckBox             matlab.ui.control.CheckBox
        LogButton                     matlab.ui.control.Button
        AuthorEditFieldLabel          matlab.ui.control.Label
        AuthorEditField               matlab.ui.control.EditField
        TitleEditFieldLabel           matlab.ui.control.Label
        TitleEditField                matlab.ui.control.EditField
        commentEditFieldLabel         matlab.ui.control.Label
        commentEditField              matlab.ui.control.EditField
        EnergyBPMLabel                matlab.ui.control.Label
        S_Scalar_1                    matlab.ui.control.DropDown
        AreaDropDownLabel             matlab.ui.control.Label
        AreaDropDown                  matlab.ui.control.DropDown
        LiveorbitaveragesEditFieldLabel  matlab.ui.control.Label
        LiveorbitaveragesEditField    matlab.ui.control.EditField
        RequiresBSALabel              matlab.ui.control.Label
        YMaxEditField_2Label          matlab.ui.control.Label
        YMaxEditField_2               matlab.ui.control.EditField
        YMinEditField_2Label          matlab.ui.control.Label
        YMinEditField_2               matlab.ui.control.EditField
        XMaxEditField_2Label          matlab.ui.control.Label
        XMaxEditField_2               matlab.ui.control.EditField
        XMinEditField_2Label          matlab.ui.control.Label
        XMinEditField_2               matlab.ui.control.EditField
        XLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        ScanVariableLabel             matlab.ui.control.Label
        S_Scalar_2                    matlab.ui.control.DropDown
        UseFilter1CheckBox            matlab.ui.control.CheckBox
        UseFilter2CheckBox            matlab.ui.control.CheckBox
        ALabel                        matlab.ui.control.Label
        MaxEditFieldLabel             matlab.ui.control.Label
        MaxEditField                  matlab.ui.control.EditField
        MinEditFieldLabel             matlab.ui.control.Label
        MinEditField                  matlab.ui.control.EditField
        DispersionEditFieldLabel      matlab.ui.control.Label
        DispersionEditField           matlab.ui.control.EditField
        RequiresSynchronousmodeLabel  matlab.ui.control.Label
        BoxGridCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Petition % Structure used by the update function .
        DataStructure % Stores Acquisition variable for re initialization.
        ASSE % True axes not UI axes for speed to be located in PGR figure.
        ASSE2 % Used for Y or X the other one!
        ASSE3 % Used for la Istoria
        PGR % Patently-slow Graphics Remedy, a figure that supports faster graphics.
        ASSEDefaultSize; %default size when new figure is turned on.
        color; %colors for the panel
        application='Dispersion';
        ToBeDeleted=[];
        Annotations=[];
        Permannotation;
        FIELDNAMES;
        ARC;
        BPM_Sorting;
        AdditionalOrbits=[];
        ConfPath='/u1/lcls/matlab/VOM_Configs/';
        CircularBuffer=[];
    end
    
    properties (Access = public)
        NeedInit=1; % Description
    end
    
    methods (Access = public)
        
        function Update(app,CV,Initialize)
            if(Initialize)
                app.UpdateInitialize(CV);
            else
                if(app.Petition.STOP), return, end
                %if(~app.Petition.S_SEL(1)), return, end
                if(~isvalid(app.PGR)), app.CheckExistenceMakeAxes(); disp('Axes were remade because could not be found'); end
                delete(app.ASSE.Children); %if(~isempty(app.Petition.LastSeconds)), MT=-inf; end
                hold(app.ASSE,'on');
                delete(app.ASSE2.Children); %if(~isempty(app.Petition.LastSeconds)), MT=-inf; end
                hold(app.ASSE2,'on');
                delete(app.ASSE3.Children); %if(~isempty(app.Petition.LastSeconds)), MT=-inf; end
                hold(app.ASSE3,'on');
                if(app.DataStructure.Synchronous)
                    if(CV.Recorder.TimesBufferFilled)
                        if(CV.Recorder.LastWrittenElement==app.DataStructure.Buffersize)
                            TENUTI=1:app.DataStructure.Buffersize;
                        else
                            TENUTI=[(CV.Recorder.LastWrittenElement+1):app.DataStructure.Buffersize,(1:CV.Recorder.LastWrittenElement)];
                        end
                    else
                        TENUTI=1:CV.Recorder.LastWrittenElement;
                    end
                    FilterNum=2^(app.Petition.UseFilter(1))*3^(app.Petition.UseFilter(2));
                    switch(FilterNum)
                        case 1
                            TF=TENUTI;
                        case 2
                            TF=TENUTI;
                            for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                                if(app.Petition.FilterUse{FN}(1))
                                    TF=TF((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                                end
                            end
                        case 3
                            TF=TENUTI;
                            for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                                if(app.Petition.FilterUse{FN}(2))
                                    TF=TF((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                                end
                            end
                        case 6
                            TF=TENUTI;
                            for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                                if(any(app.Petition.FilterUse{FN}))
                                    TF=TF((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                                end
                            end
                    end
                    if(length(TF)>app.Petition.LastN)
                        TF=TF((end-app.Petition.LastN+1):end);
                    end
                    ExcludeNaN=find(any(isnan(CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF,app.BPM_Sorting(app.Petition.Area).Y.POS)).') | any(isnan(CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF,app.BPM_Sorting(app.Petition.Area).X.POS)).'));
                    if(~isempty(ExcludeNaN))
                        TF(ExcludeNaN)=[];
                    end
                    if(~isempty(TF) && app.Petition.S_SEL1(1) && ~app.Petition.S_SEL2(1))
                        EnergyBPM=CV.Recorder.Data{app.Petition.S_SEL1(1)}(TF,app.Petition.S_SEL1(2));
                        LM=[EnergyBPM,ones(size(EnergyBPM))];
                        [XFIT_LSCOV,XFIT_ERR]=lscov(LM,CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(:),app.BPM_Sorting(app.Petition.Area).X.POS));
                        [YFIT_LSCOV,YFIT_ERR]=lscov(LM,CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(:),app.BPM_Sorting(app.Petition.Area).Y.POS));
                        plot(app.ASSE2,app.BPM_Sorting(app.Petition.Area).X.z,XFIT_LSCOV(1,:)*app.Petition.DispersionCoefficient,':o','Color',[0,0,1]);
                        plot(app.ASSE,app.BPM_Sorting(app.Petition.Area).Y.z,YFIT_LSCOV(1,:)*app.Petition.DispersionCoefficient,':o','Color',[0,0,1]);
                        app.CircularBuffer=circshift(app.CircularBuffer,-1);
                        app.CircularBuffer(end,1)=max(abs(XFIT_LSCOV(1,:)*app.Petition.DispersionCoefficient));
                        app.CircularBuffer(end,2)=max(abs(YFIT_LSCOV(1,:)*app.Petition.DispersionCoefficient));
                        plot(app.ASSE3,app.CircularBuffer(:,1),'-r'); plot(app.ASSE3,app.CircularBuffer(:,2),'-k');
                    elseif(~isempty(TF) && app.Petition.S_SEL1(1) && app.Petition.S_SEL2(1))
                        ScanData=CV.NonTimeStamped_On_Timestamped(CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.S_SEL2(1)},CV.Recorder.Data{app.Petition.S_SEL2(1)}(:,app.Petition.S_SEL2(2)),CV.Recorder.AcquisitionWritingCycle(TF(:)));
                        USD=unique(ScanData(~isnan(ScanData)));
                        TutteLinee_X=zeros(length(USD),length(app.BPM_Sorting(app.Petition.Area).X.POS));
                        TutteLinee_XErr=TutteLinee_X;
                        TutteLinee_Y=zeros(length(USD),length(app.BPM_Sorting(app.Petition.Area).Y.POS));
                        TutteLinee_YErr=TutteLinee_Y;
                        for II=1:length(USD)
                            EnergyBPM=CV.Recorder.Data{app.Petition.S_SEL1(1)}(TF(ScanData==USD(II)),app.Petition.S_SEL1(2));
                            LM=[EnergyBPM,ones(size(EnergyBPM))];
                            [XFIT_LSCOV,XFIT_ERR]=lscov(LM,CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(ScanData==USD(II)),app.BPM_Sorting(app.Petition.Area).X.POS));
                            [YFIT_LSCOV,YFIT_ERR]=lscov(LM,CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(ScanData==USD(II)),app.BPM_Sorting(app.Petition.Area).Y.POS));
                            TutteLinee_X(II,:)=XFIT_LSCOV(1,:)*app.Petition.DispersionCoefficient;
                            TutteLinee_XErr(II,:)=XFIT_ERR(1,:)*app.Petition.DispersionCoefficient;
                            TutteLinee_Y(II,:)=YFIT_LSCOV(1,:)*app.Petition.DispersionCoefficient;
                            TutteLinee_YErr(II,:)=YFIT_ERR(1,:)*app.Petition.DispersionCoefficient;
                        end
                        if(length(USD)>1) %further fit and plot
                            disp('Further Fit and Plot')
                            FitX=lscov([USD,ones(size(USD))], TutteLinee_X);
                            FitY=lscov([USD,ones(size(USD))], TutteLinee_Y);
                            t=[min(USD)-(max(USD)-min(USD))/10,max(USD)+(max(USD)-min(USD))/10];
                            Lines_X=[FitX(2,:);FitX(2,:)]+t.'*FitX(1,:);
                            Lines_Y=[FitY(2,:);FitY(2,:)]+t.'*FitY(1,:);
                            Colori=mat2cell(app.color.DistantColors((1:length(FitX)),:),ones(length(FitX),1),3);
                            h_temp=plot(app.ASSE2,USD,TutteLinee_X,'o');
                            set(h_temp, {'color'}, Colori);
                            h_temp=plot(app.ASSE2,t,Lines_X.');
                            set(h_temp, {'color'}, Colori);
                            h_temp=plot(app.ASSE,USD,TutteLinee_Y,'o');
                            set(h_temp, {'color'}, Colori);
                            h_temp=plot(app.ASSE,t,Lines_Y.');
                            set(h_temp, {'color'}, Colori);
                        end
                    end
                        %EnergyBPMF=linspace(0,100,120).';
                        
                        %LMF=[EnergyBPMF,ones(size(EnergyBPMF))];
                        %MatrixF=[3*EnergyBPMF+randn(size(EnergyBPMF)),5*EnergyBPMF+3*randn(size(EnergyBPMF)),7*EnergyBPMF+0.1*randn(size(EnergyBPMF))];
                        
                        %XBPM=CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(:),app.BPM_Sorting(app.Petition.Area).X.POS);
                        %YBPM=CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(:),app.BPM_Sorting(app.Petition.Area).Y.POS);
                        
                        %if(app.Petition.QR)
%                         tic
%                         [Q,R]=qr(LM.');
%                         XFIT=XBPM.'/R*Q;
%                         YFIT=YBPM.'/R*Q;
%                         QRREAL_TIME=toc;
%                         
%                         tic
%                         [QF,RF]=qr(LMF.');
%                         XFITF=MatrixF.'/RF*QF;
%                         YFITF=MatrixF.'/RF*QF;
%                         QRFAKE_TIME=toc;
                        
%                         tic
%                         [XFIT_LSCOV,XFIT_ERR]=lscov(LM,CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(:),app.BPM_Sorting(app.Petition.Area).X.POS));
%                         [YFIT_LSCOV,YFIT_ERR]=lscov(LM,CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(:),app.BPM_Sorting(app.Petition.Area).Y.POS));
%                         LSCOV_REALTIME=toc;
%                         
%                         tic
%                         [XFIT_LSCOVF,XFIT_ERRF]=lscov(LMF,MatrixF);
%                         [YFIT_LSCOVF,YFIT_ERRF]=lscov(LMF,MatrixF);
%                         LSCOV_FAKETIME=toc;


                            %Sort about the other variable, be it
                            %timestamped, use it as continuous, be it
                            %discrete use it as a scan
                        %end
                        %end
                        %XBPM=CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(:),app.BPM_Sorting(app.Petition.Area).X.POS);
                        %YBPM=CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(:),app.BPM_Sorting(app.Petition.Area).Y.POS);
                    %end           
                    
                else % Asynchronous mode.

                end 

                xlim(app.ASSE2,app.Petition.xLim);
                ylim(app.ASSE2,app.Petition.yLim);
                xlim(app.ASSE,app.Petition.xLim2);
                ylim(app.ASSE,app.Petition.yLim2);

                if(app.Petition.Logbook)
                    app.logBookButtonCall(CV);
                    app.LogbookButton.BackgroundColor=app.color.logbook; app.Petition.Logbook=0;
                end
            end
        end
        
        function [SUCCESS,filename,TargetDir] = writeDataToDisk(app,TimeNow,CVObject,auxillaryData)
            CurrentTime=datevec(TimeNow);
            CurrentYearString=num2str(CurrentTime(1),'%.4d'); CurrentMonthString=num2str(CurrentTime(2),'%.2d');
            CurrentDayString=num2str(CurrentTime(3),'%.2d'); CurrentTempo1String=num2str(CurrentTime(4),'%.2d');
            CurrentTempo2String=num2str(CurrentTime(5),'%.2d'); CurrentTempo3String=num2str(floor(CurrentTime(6)),'%.2d');
            CurrentTempo4String=num2str(round((CurrentTime(6)-floor(CurrentTime(6)))*1000),'%.3d');
            CurrentTimeString=[CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString,'--',CurrentTempo1String,'-',CurrentTempo2String,'-',CurrentTempo3String,'-',CurrentTempo4String];
            filename=['OnlineMonitor-','ScalarAndScalar','-',CurrentTimeString];
            TargetDir=['/u1/lcls/matlab/data/',CurrentYearString,'/',CurrentYearString,'-',CurrentMonthString,'/',CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString];
            if(~isfolder(TargetDir))
                mkdir(TargetDir);
            end
            DataStructure=CVObject.Acquisition; Data=CVObject.Recorder; %#ok<ADPROPLC> 
            try
                save([TargetDir,'/',filename],'Data','DataStructure','auxillaryData')
                SUCCESS=1;
            catch
                SUCCESS=0;
            end
        end
    end
    
    methods (Access = private)
        
        function void = UpdateLimits(app,direction)
            if(direction=='x')
                valuem = str2num(app.XMinEditField.Value); %#ok<ST2NM> 
                valueM = str2num(app.XMaxEditField.Value); %#ok<ST2NM> 
            else
                valuem = str2num(app.YMinEditField.Value); %#ok<ST2NM> 
                valueM = str2num(app.YMaxEditField.Value); %#ok<ST2NM> 
            end
            if(isempty(valuem)), valuem=-inf; end
            if(isempty(valueM)), valueM=+inf; end
            valuem=valuem(1); valueM=valueM(1);
            if(valuem>valueM)
                temp=valuem; valuem=valueM; valueM=temp; 
                if(direction=='x')
                    app.XMinEditField.Value = num2str(valuem); app.XMaxEditField.Value = num2str(valueM);
                else
                    app.YMinEditField.Value = num2str(valuem); app.YMaxEditField.Value = num2str(valueM);
                end
            end
            if(abs(valuem-valueM)<10^-13)
                valueM=valueM+10^-13/2; valuem=valuem-10^-13/2;
            end
            if(direction=='x')
                app.Petition.xLim=[valuem,valueM];
            else
                app.Petition.yLim=[valuem,valueM];
            end
            void=0;
        end
        
        function void = UpdateLimits2(app,direction)
            if(direction=='x')
                valuem = str2num(app.XMinEditField_2.Value); %#ok<ST2NM> 
                valueM = str2num(app.XMaxEditField_2.Value); %#ok<ST2NM> 
            else
                valuem = str2num(app.YMinEditField_2.Value); %#ok<ST2NM> 
                valueM = str2num(app.YMaxEditField_2.Value); %#ok<ST2NM> 
            end
            if(isempty(valuem)), valuem=-inf; end
            if(isempty(valueM)), valueM=+inf; end
            valuem=valuem(1); valueM=valueM(1);
            if(valuem>valueM)
                temp=valuem; valuem=valueM; valueM=temp; 
                if(direction=='x')
                    app.XMinEditField_2.Value = num2str(valuem); app.XMaxEditField_2.Value = num2str(valueM);
                else
                    app.YMinEditField_2.Value = num2str(valuem); app.YMaxEditField_2.Value = num2str(valueM);
                end
            end
            if(abs(valuem-valueM)<10^-13)
                valueM=valueM+10^-13/2; valuem=valuem-10^-13/2;
            end
            if(direction=='x')
                app.Petition.xLim2=[valuem,valueM];
            else
                app.Petition.yLim2=[valuem,valueM];
            end
            void=0;
        end
        
        function void = UpdateAmpLimits(app)
            valuem = str2num(app.MinEditField.Value); %#ok<ST2NM> 
            valueM = str2num(app.MaxEditField.Value); %#ok<ST2NM> 

            if(isempty(valuem)), valuem=-inf; end
            if(isempty(valueM)), valueM=+inf; end
            valuem=valuem(1); valueM=valueM(1);
            if(valuem>valueM)
                temp=valuem; valuem=valueM; valueM=temp; 
                app.MinEditField.Value = num2str(valuem); app.MaxEditField_2.Value = num2str(valueM);
            end
            if(abs(valuem-valueM)<10^-13)
                valueM=valueM+10^-13/2; valuem=valuem-10^-13/2;
            end
            app.Petition.AmpRange=[valuem,valueM];
            void=0;
        end
        
        function wasvalid = CheckExistenceMakeAxes(app)
            wasvalid=1;
            if(~isvalid(app.PGR))            
                app.PGR=figure('Name',[app.(app.FIELDNAMES{(cellfun(@(x) any(x),strfind(fieldnames(app),'UIFigure')))}).Name,' *-* Display']);
                app.PGR.Position=[app.PGR.Position(1)-440,app.PGR.Position(2)-180,1000,600];
                app.ASSE=axes(app.PGR,'Position',[0.11,0.09,0.8450,0.8250/3]);
                app.ASSE2=axes(app.PGR,'Position',[0.11,0.09+0.8250/3+0.05,0.8450,0.8250/3]);
                app.ASSE3=axes(app.PGR,'Position',[0.11,0.09+2*0.8250/3+0.1,0.8450,0.7250/3]);
                app.Permannotation=annotation(app.PGR,'textbox',[0 0 1 0.7],'EdgeColor','none','HorizontalAlignment','left','Interpreter','latex');
                %delete(axtoolbar(app.ASSE));
                app.ASSE.Interactions=[];app.ASSE.Toolbar=[];
                app.ASSE2.Interactions=[];app.ASSE2.Toolbar=[];
                app.ASSE3.Interactions=[];app.ASSE3.Toolbar=[];
                app.PGR.MenuBar='none'; app.PGR.ToolBar='none';
                app.ASSE.NextPlot='replacechildren'; app.ASSE2.NextPlot='replacechildren'; app.ASSE3.NextPlot='replacechildren';
                app.ASSEDefaultSize=app.ASSE.Position(3:4);
                wasvalid=0;
                app.ManageFitVisibility(0);
                app.Petition.ShowStats=app.ShowstatsCheckBox.Value;
                annotation(app.PGR,'textbox',[0 0 1 0.26],'EdgeColor','none','HorizontalAlignment','left','String','Y [mm]');
                annotation(app.PGR,'textbox',[0 0 1 0.60],'EdgeColor','none','HorizontalAlignment','left','String','X [mm]');
                annotation(app.PGR,'textbox',[0 0 1 0.93],'EdgeColor','none','HorizontalAlignment','left','String','Hist X','Color',[0,0,0]);
                annotation(app.PGR,'textbox',[0 0 1 0.86],'EdgeColor','none','HorizontalAlignment','left','String','Hist Y','Color',[1,0,0]);
                if(app.BoxGridCheckBox.Value), box(app.ASSE,'on'); grid(app.ASSE,'on'); else, box(app.ASSE,'off'); grid(app.ASSE,'off'); end 
                if(app.BoxGridCheckBox.Value), box(app.ASSE2,'on'); grid(app.ASSE2,'on'); else, box(app.ASSE2,'off'); grid(app.ASSE2,'off'); end 
                if(app.BoxGridCheckBox.Value), box(app.ASSE3,'on'); grid(app.ASSE3,'on'); else, box(app.ASSE3,'off'); grid(app.ASSE3,'off'); end 
                if(~app.Petition.ShowStats)
                    set(app.Permannotation,'String','');
                    SA=0;
                    set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                    set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
                else
                    SA=size(app.TabellaY.Data,1)+1;
                    set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                    set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
                end
            end
        end
        
        function moveAxisNearToMe(app)
            app.CheckExistenceMakeAxes();
            switch app.PositionDropDown.Value
                case 'Right'
                    PGRNewPosition=[app.DispersionDisplayUIFigure.Position(1)+app.DispersionDisplayUIFigure.Position(3)+1,app.DispersionDisplayUIFigure.Position(2),app.PGR.Position(3),app.PGR.Position(4)];
                case 'Above'
                    PGRNewPosition=[app.DispersionDisplayUIFigure.Position(1),app.DispersionDisplayUIFigure.Position(2)+app.DispersionDisplayUIFigure.Position(4)+1,app.PGR.Position(3),app.PGR.Position(4)];
                case 'Left'
                    PGRNewPosition=[app.DispersionDisplayUIFigure.Position(1)-app.PGR.Position(3)-1,app.DispersionDisplayUIFigure.Position(2),app.PGR.Position(3),app.PGR.Position(4)];
                case 'Below'
                    PGRNewPosition=[app.DispersionDisplayUIFigure.Position(1),app.DispersionDisplayUIFigure.Position(2)-app.PGR.Position(4)-1,app.PGR.Position(3),app.PGR.Position(4)];
            end
            app.PGR.Position=PGRNewPosition;
            figure(app.PGR);
        end
        
        function void = getAppColors(app)
            void=0;
            app.color.Idle=app.AxesFigureButton.BackgroundColor;
            app.color.On=[0,1,0];
            app.color.Off=[1,0,0];
            app.color.Wait=[1,1,0];
            app.color.logbook=[0.35,0.86,1];
            app.color.logbook2Steps=[0.3,0.5,1];
            app.color.Live=[0,0,1];
            app.color.Scalar1=[0,0,0];
            app.color.Scalar2=[1,0,1];
            app.color.Fixed1=[0.8,0.1,0.1];
            app.color.Fixed2=[0.9,0.9,0];
            app.color.Fixed3=[0.3,0.6,1];
            app.color.ColorScale1=jet;%[linspace(0.8,1,256);linspace(0.8,0,256);linspace(0.8,0,256)].';
            app.color.ColorScale2=jet;%[linspace(0.8,0,256);linspace(0.8,0,256);linspace(0.8,1,256)].';
            app.color.DistantColors=1/255*repmat([0,0,0;230,25,75;0,130,200;128,128,0;170,170,40;0,0,128;240,50,230;128,128,128;60,180,75;255,225,25;170,110,40;250,190,212;170,255,195;145,30,180;70,240,240;210,245,60;255,215,180;128,0,0;0,128,128;255,250,200;0,0,255;0,255,0;55,55,55;80,232,156;200,90,200],[20,1]);
        end
        
        function ManageBPMList(app,CV)
            BPMEnv={'Undulator Line','LTU-DUMP','All BPMs','Hard Line','Soft Line','Hard after CLTH:140 - end UND','Soft CLTH:140 -end UND'};
            BPMEnvN=1:length(BPMEnv);
            app.AreaDropDown.Items=BPMEnv;
            app.AreaDropDown.ItemsData=BPMEnvN;
            app.AreaDropDown.Value=app.Petition.Area;
            for II=1:length(BPMEnv)
                switch(II)
                    case 1
                        INDEX=cellfun(@(x) contains(x,'UND'),app.ARC.static.bpmList_e);
                        REMOVE=cellfun(@(x) contains(x,'5190'),app.ARC.static.bpmList_e);
                        INDEX(REMOVE)=false;
                    case 2
                        INDEX=cellfun(@(x) (contains(x,'UND') | contains(x,'LTU') | contains(x,'DMP')),app.ARC.static.bpmList_e);
                    case 3
                        INDEX=cellfun(@(x) (contains(x,'BPMS')),app.ARC.static.bpmList_e); 
                    case 4
                        app.ARC.static.hBpm;
                    case 5
                        app.ARC.static.sBpm;
                    case 6
                        ID=find(strcmp(app.ARC.static.bpmList_e,'BPMS:CLTH:140'));
                        Zmin=app.ARC.static.zBPM(ID);
                        INDEX=(app.ARC.static.zBPM>=Zmin) & (app.ARC.static.hBpm);
                        REMOVE=cellfun(@(x) contains(x,'5190'),app.ARC.static.bpmList_e);
                        INDEX(REMOVE)=false;
                        REMOVE=cellfun(@(x) contains(x,'DMPH'),app.ARC.static.bpmList_e);
                        INDEX(REMOVE)=false;
                    case 7
                        ID=find(strcmp(app.ARC.static.bpmList_e,'BPMS:CLTH:140'));
                        Zmin=app.ARC.static.zBPM(ID);
                        INDEX=(app.ARC.static.zBPM>=Zmin) & (app.ARC.static.sBpm);
                        REMOVE=cellfun(@(x) contains(x,'5190'),app.ARC.static.bpmList_e);
                        INDEX(REMOVE)=false;
                        REMOVE=cellfun(@(x) contains(x,'DMPS'),app.ARC.static.bpmList_e);
                        INDEX(REMOVE)=false;
                end
                BaseNames=app.ARC.static.bpmList_e(INDEX);
                zBaseNames=app.ARC.static.zBPM(INDEX);
                [~,TempZPos,TempPos]=intersect(strcat(BaseNames,':X'),CV.Acquisition.Scalars,'stable');
                app.BPM_Sorting(II).X.FullPos=CV.Acquisition.Scalars_Positions(TempPos,:);
                app.BPM_Sorting(II).X.z=zBaseNames(TempZPos);
                [~,TempZPos,TempPos]=intersect(strcat(BaseNames,':Y'),CV.Acquisition.Scalars,'stable');
                app.BPM_Sorting(II).Y.FullPos=CV.Acquisition.Scalars_Positions(TempPos,:);
                app.BPM_Sorting(II).Y.z=zBaseNames(TempZPos);
                [~,TempZPos,TempPos]=intersect(strcat(BaseNames,':T'),CV.Acquisition.Scalars,'stable');
                app.BPM_Sorting(II).T.FullPos=CV.Acquisition.Scalars_Positions(TempPos,:);
                app.BPM_Sorting(II).T.z=zBaseNames(TempZPos);
                try
                    app.BPM_Sorting(II).DataPosition=app.BPM_Sorting(II).X.FullPos(1);
                catch
                    app.BPM_Sorting(II).DataPosition=1;
                end
                app.BPM_Sorting(II).X.POS=app.BPM_Sorting(II).X.FullPos(:,2);
                app.BPM_Sorting(II).Y.POS=app.BPM_Sorting(II).Y.FullPos(:,2);
                app.BPM_Sorting(II).T.POS=app.BPM_Sorting(II).T.FullPos(:,2); 
            end
        end
        
        function UpdateInitialize(app,CV)
            app.LogButton.Text=['','-> Log'];
            app.LogButton.Enable='Off';
            if(~isequal(CV.Acquisition,app.DataStructure))
                app.DataStructure=CV.Acquisition;
                app.Petition.Logbook=0;
                app.LogbookButton.BackgroundColor=app.color.logbook;
                List=app.EvaluateAllLists();
                %List.SynchScalars List.SynchScalarsPositions 
                %List.Scalars List.ScalarsPositions
                %List.Vectors List.VectorsPositions
                %List.Array2D List.Array2DPositions
                
                app.ManageFilterTable(app.TABULA,List);
%                 app.ManageScalarsTable(app.TabellaY,List);
                app.ManageSynchScalarsSelection(app.S_Scalar_1,List,'S_SEL1');
                app.ManageNonTimestampedScalarsSelection(app.S_Scalar_2,List,'S_SEL2');
                app.ManageFitVisibility(1);
                app.ManageOtherInputs();
                app.ManageTypeOfPlotVisibility(1);
                app.ManageBPMList(CV)
  
                if(isinf(app.Petition.xLim(1))), app.XMinEditField.Value=''; else, app.XMinEditField.Value=num2str(app.Petition.xLim(1)); end
                if(isinf(app.Petition.xLim(2))), app.XMaxEditField.Value=''; else, app.XMaxEditField.Value=num2str(app.Petition.xLim(2)); end
                if(isinf(app.Petition.yLim(1))), app.YMinEditField.Value=''; else, app.YMinEditField.Value=num2str(app.Petition.yLim(1)); end
                if(isinf(app.Petition.yLim(2))), app.YMaxEditField.Value=''; else, app.YMaxEditField.Value=num2str(app.Petition.yLim(2)); end
                
                if(isinf(app.Petition.xLim2(1))), app.XMinEditField.Value=''; else, app.XMinEditField.Value=num2str(app.Petition.xLim2(1)); end
                if(isinf(app.Petition.xLim2(2))), app.XMaxEditField.Value=''; else, app.XMaxEditField.Value=num2str(app.Petition.xLim2(2)); end
                if(isinf(app.Petition.yLim2(1))), app.YMinEditField.Value=''; else, app.YMinEditField.Value=num2str(app.Petition.yLim2(1)); end
                if(isinf(app.Petition.yLim2(2))), app.YMaxEditField.Value=''; else, app.YMaxEditField.Value=num2str(app.Petition.yLim2(2)); end
                
                if(isinf(app.Petition.AmpRange(1))), app.MinEditField.Value=''; else, app.MinEditField.Value=num2str(app.Petition.AmpRange(1)); end
                if(isinf(app.Petition.AmpRange(2))), app.MaxEditField.Value=''; else, app.MaxEditField.Value=num2str(app.Petition.AmpRange(2)); end
                
                if(~app.Petition.STOP)
                    app.STOP.Text='Now Running -> Stop Updating';
                    app.STOP.BackgroundColor=app.color.On;
                else
                    app.STOP.Text='Now Stopped -> Resume Updating';
                    app.STOP.BackgroundColor=app.color.Wait;
                end

                if(app.Petition.Save), app.SaveDataCheckBox.Value=1; else, app.SaveDataCheckBox.Value=0; end
                
                if(app.Petition.TwoStepLog), app.EditTextFigCheckBox.Value=1; else, app.EditTextFigCheckBox.Value=0; end
            end
            app.NeedInit=0;
        end
        
        function List=EvaluateAllLists(app)
           List.SynchScalars=app.DataStructure.Scalars;
           List.SynchScalarsPositions=app.DataStructure.Scalars_Positions;
           Delete=false(size(List.SynchScalarsPositions(:,1)));
           for AA=1:length(app.DataStructure.TimeStamped)
               if(~app.DataStructure.TimeStamped(AA))
                   Delete=Delete | (List.SynchScalarsPositions(:,1)==AA);
               end
           end
           List.NotTimeStampedScalars=List.SynchScalars(Delete);
           List.NotTimeStampedScalarsPositions=List.SynchScalarsPositions(Delete,:);
           List.NotTimeStampedScalars=['OFF';List.NotTimeStampedScalars];
           List.NotTimeStampedScalarsPositions=[[0,0,0];List.NotTimeStampedScalarsPositions];
           List.SynchScalars(Delete)=[];
           List.SynchScalarsPositions(Delete,:)=[];
           List.SynchScalars=['OFF';List.SynchScalars];
           List.SynchScalarsPositions=[[0,0,0];List.SynchScalarsPositions];
           List.Scalars=['OFF';app.DataStructure.Scalars];
           List.ScalarsPositions=[0,0,0;app.DataStructure.Scalars_Positions];
           List.Vectors=['OFF';app.DataStructure.Vectors];
           List.VectorsPositions=[0,0,0;app.DataStructure.Vectors_Positions];
           List.Arrays2D=['OFF';app.DataStructure.Arrays2D];
           List.Arrays2DPositions=[0,0,0;app.DataStructure.Arrays2D_Positions];
        end

        
        function ManageFilterTable(app,FilterTable,List)
            TAB_CF=FilterTable.ColumnFormat;
            TAB_CF{1}=List.SynchScalars(:).';
            TAB_CF{2}='numeric'; TAB_CF{3}='numeric'; TAB_CF{4}='logical'; TAB_CF{5}='logical';
            FilterTable.ColumnFormat=TAB_CF;
            FilterTable.ColumnEditable=[true,true,true,true,true];
            for II=app.Petition.FiltriNumber:-1:1
                IDPOS=find(strcmp(List.SynchScalars,app.Petition.FilterVarName{II}));
                if(isempty(IDPOS)) %needs to remove the filer, the variable is not recorded anymore
                    app.Petition.FiltriNumber=app.Petition.FiltriNumber-1;
                    app.Petition.Filter(II)=[];
                    app.Petition.FilterVarName(II)=[];
                    app.Petition.FilterUse(II)=[];
                else
                    IDPOS=IDPOS(1); %rebuild the filter with new position, even if it is the same one.
                    app.Petition.Filter{II}.SEL=List.SynchScalarsPositions(IDPOS,:);
                end
            end
            
            TABULA_DATA={};
            for II=1:app.Petition.FiltriNumber
                TABULA_DATA{II,1}=app.Petition.FilterVarName{II}; %#ok<AGROW>
                if(isinf(app.Petition.Filter{II}.Range(1)))
                    TABULA_DATA{II,2}=[]; %#ok<AGROW>
                else
                    if(~isnan(app.Petition.Filter{II}.Range(1)))
                        TABULA_DATA{II,2}=app.Petition.Filter{II}.Range(1); %#ok<AGROW>
                    else
                        app.Petition.Filter{II}.Range(1)=+inf;
                        TABULA_DATA{II,2}=[]; %#ok<AGROW>
                    end
                end
                if(isinf(app.Petition.Filter{II}.Range(2)))
                    TABULA_DATA{II,3}=[]; %#ok<AGROW>
                else
                    if(~isnan(app.Petition.Filter{II}.Range(2)))
                        TABULA_DATA{II,3}=app.Petition.Filter{II}.Range(2); %#ok<AGROW>
                    else
                        app.Petition.Filter{II}.Range(2)=+inf;
                        TABULA_DATA{II,3}=[]; %#ok<AGROW>
                    end
                end
                TABULA_DATA{II,4}=app.Petition.FilterUse{II}(1); %#ok<AGROW>
                TABULA_DATA{II,5}=app.Petition.FilterUse{II}(2);  %#ok<AGROW>
            end
          
            FilterTable.Data=TABULA_DATA;
            ReducedList.Positions=List.SynchScalarsPositions;
            ReducedList.Names=List.SynchScalars;
            FilterTable.UserData=ReducedList;
        end
        
        function ManageScalarsTable(app,TAB,List)
            TAB.ColumnName={'Filter 1','Filter 2','Scalar Variable Name','Style','Expression','LastN'};
            TAB_CF=TAB.ColumnFormat;
            TAB_CF{3}=List.Scalars(:).';
            TAB.ColumnFormat=TAB_CF;
            TAB.ColumnEditable=[true,true,true,true,true,true];
            InitData{1,1}=true;InitData{1,2}=true;InitData{1,3}=List.SynchScalars{1};InitData{1,4}='.b';InitData{1,5}='';InitData{1,6}=inf;
            TAB.Data=InitData;
            ins=0;
%             List.Scalars=['OFF';app.DataStructure.Scalars];
%             List.ScalarsPositions=[0,0,0;app.DataStructure.Scalars_Positions];
            if(~isempty(app.Petition.Quantity))
                FoundNone=1;
                for II=1:length(app.Petition.Quantity)
                    %Check if variable still exists...
                    ID=find(strcmp(app.Petition.Quantity(II).Name,List.Scalars));
                    if(isempty(ID))
                        app.Petition.Quantity(II)=[];
                    else
                        FoundNone=0; ins=ins+1;
                        NewQuantity(ins).Filter(1)=app.Petition.Quantity(II).Filter(1); %#ok<AGROW>
                        NewQuantity(ins).Filter(2)=app.Petition.Quantity(II).Filter(2); %#ok<AGROW>
                        NewQuantity(ins).Name=app.Petition.Quantity(II).Name; %#ok<AGROW>
                        NewQuantity(ins).Y_SEL=List.ScalarsPositions(ID,:); %#ok<AGROW>
                        NewQuantity(ins).Stilnovo=app.Petition.Quantity(II).Stilnovo; %#ok<AGROW>
                        TAB.Data{ins,1}=logical(app.Petition.Quantity(II).Filter(1)); 
                        TAB.Data{ins,2}=logical(app.Petition.Quantity(II).Filter(2));
                        TAB.Data{ins,3}=app.Petition.Quantity(II).Name; TAB.Data{ins,4}=app.Petition.Quantity(II).Stilnovo;
                        TAB.Data{ins,5}=''; NewQuantity(ins).Function=''; %#ok<AGROW>
                        NewQuantity(ins).LastN=app.Petition.Quantity(II).LastN; %#ok<AGROW>
                        TAB.Data{ins,6}=app.Petition.Quantity(II).LastN;
                    end
                end
                if(FoundNone)
                    TAB.Data{1,1}=true;TAB.Data{1,2}=true;TAB.Data{1,4}='.b';TAB.Data{1,5}='';TAB.Data{1,6}=inf;
                    TAB.Data{1,3}=List.SynchScalars{1}; app.Petition.Quantity=[];
                else
                    app.Petition.Quantity=NewQuantity;
                end
            else
                TAB.Data{1,1}=true;TAB.Data{1,2}=true;TAB.Data{1,4}='.b';TAB.Data{1,5}='';TAB.Data{1,6}=inf; 
                TAB.Data{1,3}=List.SynchScalars{1}; app.Petition.Quantity=[];
            end
            
            if(app.DataStructure.Synchronous)
                CE=TAB.ColumnEditable; CE(1)=true; CE(2)=true;
                TAB.ColumnEditable=CE;
            else
                CE=TAB.ColumnEditable; CE(1)=false; CE(2)=false;
                TAB.ColumnEditable=CE;
                TAB.Data(:,1:2)={false};
            end
            ReducedList.Positions=List.ScalarsPositions;
            ReducedList.Names=List.Scalars;
            TAB.UserData=ReducedList;
        end
        
        function ManageScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Scalars(:).';
            Bottone.ItemsData=1:length(List.Scalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.ScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageSynchScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.SynchScalars(:).';
            Bottone.ItemsData=1:length(List.SynchScalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.SynchScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageNonTimestampedScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.NotTimeStampedScalars(:).';
            Bottone.ItemsData=1:length(List.NotTimeStampedScalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.NotTimeStampedScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end

        function ManageVectorsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Vectors(:).';
            Bottone.ItemsData=1:length(List.Vectors);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.VectorsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageArrays2DSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Arrays2D(:).';
            Bottone.ItemsData=1:length(List.Arrays2D);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.Arrays2DPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageListChange(app,Bottone)
            app.Petition.(Bottone.UserData.PetitionName) = Bottone.UserData.Pos(Bottone.Value,:);
        end
        
        function UpdateTableSelection(app)
            ins=0;
            for II=1:size(app.TabellaY.Data,1)
                QTY = find(strcmp(app.TabellaY.Data{II,3},app.DataStructure.Scalars));
                if(isempty(QTY))
                    continue
                else
                    ins=ins+1;
                    Quantity(ins).Y_SEL=app.DataStructure.Scalars_Positions(QTY,:); %#ok<AGROW> 
                end
                Quantity(ins).Filter(1)=app.TabellaY.Data{II,1}; %#ok<AGROW> 
                Quantity(ins).Filter(2)=app.TabellaY.Data{II,2}; %#ok<AGROW> 
                Quantity(ins).Name=app.TabellaY.Data{II,3}; %#ok<AGROW> 
                Quantity(ins).Stilnovo=app.TabellaY.Data{II,4}; %#ok<AGROW> 
                if(isempty(app.TabellaY.Data{II,5}))
                    Quantity(ins).Function=''; %#ok<AGROW> 
                else
                    Quantity(ins).Function=eval(['@(x) ',vectorize(app.TabellaY.Data{II,5})]); %#ok<AGROW> 
                end
                Quantity(ins).LastN=app.TabellaY.Data{II,6}; %#ok<AGROW> 
            end
            if(ins==0)
                app.Petition.Quantity=[];
            else
                app.Petition.Quantity=Quantity;
            end
        end
        
        function UpdateFilterSelection(app)
            app.Petition.Filter={};app.Petition.FilterUse={}; app.Petition.FilterVarName={}; app.Petition.FiltriNumber=0;
            for II=1:size(app.TABULA.Data,1)
                if((~isempty(app.TABULA.Data{II,2}) && ~isnan(app.TABULA.Data{II,2})) || (~isempty(app.TABULA.Data{II,3}) && ~isnan(app.TABULA.Data{II,3}))) %there is something
                    VAL=app.DataStructure.Scalars_Positions(strcmp(app.TABULA.Data{II,1},app.DataStructure.Scalars),:);
                    if(isempty(VAL))
                        continue
                    end
                    if(~isempty(app.TABULA.Data{II,2}) && ~isnan(app.TABULA.Data{II,2}))
                        MIN=app.TABULA.Data{II,2};
                    else
                        MIN=-inf;
                    end
                    if(~isempty(app.TABULA.Data{II,3}) && ~isnan(app.TABULA.Data{II,3}))
                        MAX=app.TABULA.Data{II,3};
                    else
                        MAX=+inf;
                    end
                    app.Petition.Filter{end+1}.SEL=VAL; app.Petition.Filter{end}.Range=[MIN,MAX];
                    app.Petition.FiltriNumber=app.Petition.FiltriNumber+1;
                    app.Petition.FilterUse{end+1}(1)=app.TABULA.Data{II,4}; app.Petition.FilterUse{end}(2)=app.TABULA.Data{II,5};
                    app.Petition.FilterVarName{end+1}=app.TABULA.Data{II,1};
                end
            end
        end
        
        function logBookButtonCall(app,CVObject)
            TimeNow=now;
            Author=app.AuthorEditField.Value; Title=app.TitleEditField.Value; Comment=app.commentEditField.Value;
            app.Petition.Author=Author; app.Petition.Title=Title; app.Petition.Comment=Comment;
            if(app.SaveDataCheckBox.Value)
                auxillaryData = additionalSavingInformation(app);
                [SUCCESS,filename,~]=app.writeDataToDisk(TimeNow,CVObject,auxillaryData);
                if(SUCCESS)
                    app.SaveLabel.Text=['Last: OK ,',datestr(TimeNow,'yyyy, mmm dd, HH:MM')];
                else
                    app.SaveLabel='Saving Failed';
                    filename='File saving failed.';
                end
            else
                filename='No file was saved';
            end

            if(app.EditTextFigCheckBox.Value) %Two Steps logbook. Make a copy of the figure and open edit boxes.
                NewFigure=figure;
                NewFigure.Position=app.PGR.Position;
                app.DuplicateFigure(app.PGR,NewFigure);
                if(isfield(CVObject.Acquisition,'filename'))
                    annotation(NewFigure,'textbox',[0 .92 1 .08],'string',CVObject.Acquisition.filename,'EdgeColor','none','HorizontalAlignment','center');
                else
                    if(app.SaveDataCheckBox.Value)
                        annotation(NewFigure,'textbox',[0 .92 1 .08],'string',filename,'EdgeColor','none','HorizontalAlignment','center');
                    else
                        annotation(NewFigure,'textbox',[0 .92 1 .08],'string','Image logbook only, data not saved','EdgeColor','none','HorizontalAlignment','center');
                    end
                end
                app.LogButton.Text=[num2str(NewFigure.Number),'-> Log'];
                app.LogButton.Enable='On';
                app.LogButton.UserData=NewFigure;
                app.EditTextFigCheckBox.UserData=filename;
            else
                app.SendImageToLogbook(app.PGR, Author, Title, Comment, filename);
            end          
        end
        
        function auxillaryData = additionalSavingInformation(app) %#ok<MANU> 
            auxillaryData=app.Petition;
        end
        
        function DuplicateFigure(app,OriginalFigure,NewFigure) %#ok<INUSL> 
            children_handler_original = get(OriginalFigure, 'children');
            allchildren = allchild(OriginalFigure);
            if isempty(children_handler_original)
                return
            else
                new_handler_copy = copyobj(children_handler_original,NewFigure);
                for KK=1:length(new_handler_copy)
                    if(strcmpi(new_handler_copy(KK).Type,'axes'))
                        delete(axtoolbar(new_handler_copy(KK)));
                    end
                end
                for AA=1:length(allchildren)
                    if(strcmpi(allchildren(AA).Type,'annotationpane'))
                        copyobj(allchildren(AA),NewFigure);
                    end
                end
            end
        end
        
        function DeepDuplicateFigure(app,OriginalFigure,NewFigure)
            children_handler_original = get(OriginalFigure, 'children');
            if isempty(children_handler_original)
                return
            else
                new_handler_copy = copyobj(children_handler_original,NewFigure);
                for KK=1:length(children_handler_original)
                    app.DuplicateFigure(children_handler_original(KK),new_handler_copy(KK));
                end
            end
        end
        
        function ManageFitVisibility(app,CopyParameters)
%             switch(app.TypeofFitDropDown.Value)
%                 case 'No Fit'
%                     app.P1.Visible='off'; app.P2.Visible='off';
%                     app.Petition.TypeOfFit=1;
%                 case 'Polynomial'
%                     app.P1.Visible='on'; app.P2.Visible='off';
%                     app.Petition.TypeOfFit=2;
%                 case 'Gaussian'
%                     app.P1.Visible='off'; app.P2.Visible='on';
%                     app.Petition.TypeOfFit=3;
%             end
%             if(CopyParameters)
%                 if(isnan(app.Petition.A)), app.AEditField.Value=''; else, app.AEditField.Value=num2str(app.Petition.A); end
%                 if(isnan(app.Petition.B)), app.BEditField.Value=''; else, app.BEditField.Value=num2str(app.Petition.B); end
%                 if(isnan(app.Petition.C)), app.CEditField.Value=''; else, app.CEditField.Value=num2str(app.Petition.C); end
%                 if(isnan(app.Petition.x0)), app.x0EditField.Value=''; else, app.x0EditField.Value=num2str(app.Petition.x0); end
%                 if(isnan(app.Petition.y0)), app.y0EditField.Value=''; else, app.y0EditField.Value=num2str(app.Petition.y0); end
%                 if(isnan(app.Petition.PolyOrder)), app.OrderPolyFitEdit.Value=''; else, app.OrderPolyFitEdit.Value=num2str(app.Petition.PolyOrder); end
%             end
        end
        
        function ManageTypeOfPlotVisibility(app, CopyParameters)
%             switch(app.TypeofPlotDropDown.Value)
%                 case 'Dots Plot'
%                     app.BinsXEditField.Visible='off'; app.BinsXEditFieldLabel.Visible='off';
%                     app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
%                     app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
%                     app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
%                     app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
%                 case 'Density Plot'
%                     app.BinsXEditField.Visible='on'; app.BinsXEditFieldLabel.Visible='on';
%                     app.BinsYEditField.Visible='on'; app.BinsYEditFieldLabel.Visible='on';
%                     app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
%                     app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
%                     app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
%                 case 'Partition on X'
%                     app.BinsXEditField.Visible='off'; app.BinsXEditFieldLabel.Visible='off';
%                     app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
%                     app.PartitionCenterEditField.Visible='on'; app.PartitionCenterEditFieldLabel.Visible='on';
%                     app.PartitionWidthEditField.Visible='on'; app.PartitionWidthEditFieldLabel.Visible='on';
%                     app.ShowonlyEditField.Visible='on'; app.ShowonlyEditFieldLabel.Visible='on';
%                 case '3D XYZ Plot'
%                     app.BinsXEditField.Visible='off'; app.BinsXEditFieldLabel.Visible='off';
%                     app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
%                     app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
%                     app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
%                     app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
%                 case 'Histogram'
%                     app.BinsXEditField.Visible='on'; app.BinsXEditFieldLabel.Visible='on';
%                     app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
%                     app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
%                     app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
%                     app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
%             end
%             if(CopyParameters)
%                 if(isnan(app.Petition.PartitionPos(1))), app.PartitionCenterEditField.Value=''; 
%                 else
%                     string='[';
%                     for II=1:length(app.Petition.PartitionPos)
%                         string=[string,num2str(app.Petition.PartitionPos(II))]; %#ok<AGROW> 
%                         if(II~=length(app.Petition.PartitionPos))
%                             string=[string,',']; %#ok<AGROW> 
%                         else
%                             string=[string,']']; %#ok<AGROW> 
%                         end
%                     end
%                     app.PartitionCenterEditField.Value=string; 
%                 end
%                 if(isnan(app.Petition.PartitionWidth(1))), app.PartitionWidthEditField.Value=''; 
%                 else
%                     string='[';
%                     for II=1:length(app.Petition.PartitionWidth)
%                         string=[string,num2str(app.Petition.PartitionWidth(II))]; %#ok<AGROW> 
%                         if(II~=length(app.Petition.PartitionWidth))
%                             string=[string,',']; %#ok<AGROW> 
%                         else
%                             string=[string,']']; %#ok<AGROW> 
%                         end
%                     end
%                     app.PartitionWidthEditField.Value=string; 
%                 end
%                 if(isnan(app.Petition.ShowOnly(1))), app.ShowonlyEditField.Value=''; 
%                 else
%                     string='[';
%                     for II=1:length(app.Petition.ShowOnly)
%                         string=[string,num2str(app.Petition.ShowOnly(II))]; %#ok<AGROW> 
%                         if(II~=length(app.Petition.ShowOnly))
%                             string=[string,',']; %#ok<AGROW> 
%                         else
%                             string=[string,']']; %#ok<AGROW> 
%                         end
%                     end
%                     app.ShowonlyEditField.Value=string; 
%                 end
%                 if(isnan(app.Petition.binsx)), app.BinsXEditField.Value=''; else, app.BinsXEditField.Value=num2str(app.Petition.binsx); end
%                 if(isnan(app.Petition.binsy)), app.BinsYEditField.Value=''; else, app.BinsYEditField.Value=num2str(app.Petition.binsy); end
%             end
        end
        
        function ManageOtherInputs(app)
%             if(isnan(app.Petition.binsx)), app.BinsXEditField.Value=''; else, app.BinsXEditField.Value=num2str(app.Petition.binsx); end
%             if(isnan(app.Petition.binsy)), app.BinsYEditField.Value=''; else, app.BinsYEditField.Value=num2str(app.Petition.binsy); end
%             app.Petition.AutoBinPartition=0;
%             if(isnan(app.Petition.AutoBinNumber)), app.AutoPartition.Value='7';app.Petition.AutoBinNumber=1; else, app.AutoPartition.Value=num2str(app.Petition.AutoBinNumber); end
            app.ShowstatsCheckBox.Value=app.Petition.ShowStats;
%             app.AboveEditField_1.Value=num2str(app.Petition.Above1);
%             app.AboveEditField_2.Value=num2str(app.Petition.Above2);
%             app.TopEditField_1.Value=num2str(app.Petition.Top1);
%             app.TopEditField_2.Value=num2str(app.Petition.Top2);
%             app.colorscalesinglesCheckBox_1.Value=app.Petition.ColorScale1;
%             app.colorscalesinglesCheckBox_2.Value=app.Petition.ColorScale2;
            app.LiveorbitaveragesEditField.Value=num2str(app.Petition.LastN);
            app.DispersionEditField.Value=num2str(app.Petition.DispersionCoefficient);
%             app.QRCheckBox.Value=app.Petition.QR;
%             app.LSCOVCheckBox.Value=~app.Petition.QR;
%             app.FilenameEditField.Value=app.Petition.Filename;
            app.UseFilter1CheckBox.Value=logical(app.Petition.UseFilter(1));
            app.UseFilter1CheckBox.Value=logical(app.Petition.UseFilter(2));
        end
        
        function SendImageToLogbook(app, FigureHandle, Author, Title, Comment, Filename)
            Comment=[Comment,char(13),'Filename: ', Filename];
            try
                util_printLog(FigureHandle,'title',Title,'author',Author,'text',Comment);
                disp('Image sent successfully to logbook');
            catch
                disp('Failed to send image to logbook');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            TEMP=load([app.ConfPath,'CVCRCI_OrbitDisplay_ARC.mat']);
            app.ARC=TEMP.ARC;
            app.FIELDNAMES=fieldnames(app);
            app.PGR=figure('Name',[app.(app.FIELDNAMES{(cellfun(@(x) any(x),strfind(fieldnames(app),'UIFigure')))}).Name,' *-* Display']);
            app.PGR.Position=[app.PGR.Position(1)-440,app.PGR.Position(2)-180,1000,600];
            app.ASSE=axes(app.PGR,'Position',[0.11,0.09,0.8450,0.8250/3]);
            app.ASSE2=axes(app.PGR,'Position',[0.11,0.09+0.8250/3+0.05,0.8450,0.8250/3]);
            app.ASSE3=axes(app.PGR,'Position',[0.11,0.09+2*0.8250/3+0.1,0.8450,0.7250/3]);
%             
%             app.ASSE=axes(app.PGR,'Position',[0.11,0.09,0.8450,0.8250/2]);
%             app.ASSE2=axes(app.PGR,'Position',[0.11,0.09+0.8250/2+0.05,0.8450,0.8250/2]);
            app.Permannotation=annotation(app.PGR,'textbox',[0 0 1 0.7],'EdgeColor','none','HorizontalAlignment','left','Interpreter','latex');
            annotation(app.PGR,'textbox',[0 0 1 0.26],'EdgeColor','none','HorizontalAlignment','left','String','Y [mm]');
            annotation(app.PGR,'textbox',[0 0 1 0.60],'EdgeColor','none','HorizontalAlignment','left','String','X [mm]');
            annotation(app.PGR,'textbox',[0 0 1 0.93],'EdgeColor','none','HorizontalAlignment','left','String','Hist X','Color',[0,0,0]);
            annotation(app.PGR,'textbox',[0 0 1 0.86],'EdgeColor','none','HorizontalAlignment','left','String','Hist Y','Color',[1,0,0]);
            app.ASSE.Interactions=[];app.ASSE.Toolbar=[];app.ASSE2.Interactions=[];app.ASSE2.Toolbar=[]; app.ASSE3.Interactions=[];app.ASSE3.Toolbar=[];
            app.PGR.MenuBar='none';app.PGR.ToolBar='none'; app.ASSE.NextPlot='replacechildren'; app.ASSE2.NextPlot='replacechildren';
            app.ASSEDefaultSize=app.PGR.Position(3:4);
            app.CircularBuffer=ones(100,2)*NaN;
            if(app.BoxGridCheckBox.Value), box(app.ASSE,'on'); grid(app.ASSE,'on'); else, box(app.ASSE,'off'); grid(app.ASSE,'off'); end
            if(app.BoxGridCheckBox.Value), box(app.ASSE2,'on'); grid(app.ASSE2,'on'); else, box(app.ASSE2,'off'); grid(app.ASSE2,'off'); end
            if(app.BoxGridCheckBox.Value), box(app.ASSE3,'on'); grid(app.ASSE3,'on'); else, box(app.ASSE3,'off'); grid(app.ASSE3,'off'); end
            app.moveAxisNearToMe();
            app.Petition.xLim=[-inf,inf];
            app.Petition.yLim=[-inf,inf];
            app.Petition.xLim2=[-inf,inf];
            app.Petition.yLim2=[-inf,inf];
            app.Petition.AmpRange=[-inf,inf];
            app.getAppColors();
            app.LogbookButton.BackgroundColor=app.color.logbook;
            app.Petition.STOP=0;
            app.Petition.UseAbsoluteTime = 1;
            app.Petition.Save = 1;
            app.Petition.TwoStepLog= 0;
            app.Petition.FiltriNumber=0;
            app.Petition.Quantity=[];
            app.Petition.S_SEL1=[0,0,0];
            app.Petition.S_SEL2=[0,0,0];
            app.Petition.V_SEL=[0,0,0];
            app.Petition.I_SEL=[0,0,0];
%             app.Petition.Above1=-inf;
%             app.Petition.Above2=-inf;
%             app.Petition.Top1=25;
%             app.Petition.Top2=25;
%             app.Petition.ColorScale1=false;
%             app.Petition.ColorScale2=false;
            app.Petition.LastN=120;
            app.Petition.LastSeconds=[];
            app.Petition.Filename='BPM_file.mat';
            app.Petition.Area=1;
            app.Petition.Pid_Delay=0;
            app.Petition.A=NaN;
            app.Petition.B=NaN;
            app.Petition.C=2;
            app.Petition.DispersionCoefficient=125;
%             app.Petition.QR=true;
            app.Petition.x0=NaN;
            app.Petition.y0=NaN;
            app.Petition.PolyOrder=2;
            app.Petition.binsx=25;
            app.Petition.binsy=25;
            app.Petition.AutoBinPartition=1;
            app.Petition.AutoBinNumber=7;
            app.Petition.PartitionPos=NaN;
            app.Petition.PartitionWidth=NaN;
            app.Petition.ShowOnly=NaN;
            app.Petition.TypeOfPlot=1;
            app.Petition.TypeOfFit=1;
            app.Petition.Function='';
            app.Petition.ShowStats=0;
            app.Petition.UseFilter=[false,false];
        end

        % Value changed function: XMinEditField
        function XMinEditFieldValueChanged(app, event)
            app.UpdateLimits('x');
        end

        % Value changed function: XMaxEditField
        function XMaxEditFieldValueChanged(app, event)
            app.UpdateLimits('x');
        end

        % Value changed function: YMinEditField
        function YMinEditFieldValueChanged(app, event)
            app.UpdateLimits('y');
        end

        % Value changed function: YMaxEditField
        function YMaxEditFieldValueChanged(app, event)
            app.UpdateLimits('y');
        end

        % Button pushed function: AxesFigureButton
        function AxesFigureButtonPushed(app, event)
            app.CheckExistenceMakeAxes();
        end

        % Button pushed function: MoveAxesNearButton
        function MoveAxesNearButtonPushed(app, event)
            app.moveAxisNearToMe();
        end

        % Button pushed function: DefaultAxesSizeButton
        function DefaultAxesSizeButtonPushed(app, event)
            app.PGR.Position(3:4)=app.ASSEDefaultSize;
        end

        % Button pushed function: LogbookButton
        function LogbookButtonPushed(app, event)
            app.LogbookButton.BackgroundColor=app.color.On;
            app.Petition.Logbook=1;
        end

        % Button pushed function: STOP
        function STOPButtonPushed(app, event)
            if(app.Petition.STOP)
                app.Petition.STOP=false; app.STOP.Text='Now Running -> Stop Updating'; app.STOP.BackgroundColor=app.color.On; drawnow
            else
                app.Petition.STOP=true; app.STOP.Text='Now Stopped -> Resume Updating'; app.STOP.BackgroundColor=app.color.Wait; drawnow
            end
        end

        % Callback function
        function UsetimeCheckBoxValueChanged(app, event)
            app.Petition.UseAbsoluteTime = app.UsetimeCheckBox.Value;
        end

        % Value changed function: SaveDataCheckBox
        function SaveDataCheckBoxValueChanged(app, event)
            app.Petition.Save = app.SaveDataCheckBox.Value;
        end

        % Value changed function: EditTextFigCheckBox
        function EditTextFigCheckBoxValueChanged(app, event)
            app.Petition.TwoStepLog= app.EditTextFigCheckBox.Value;
        end

        % Value changed function: S_Scalar_1, S_Scalar_2
        function S_ScalarValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Callback function
        function S_VectorValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Callback function
        function S_ImageValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Close request function: DispersionDisplayUIFigure
        function DispersionDisplayUIFigureCloseRequest(app, event)
            try delete(app.PGR); end %#ok<TRYNC> 
            delete(app);
        end

        % Callback function
        function TabellaYCellEdit(app, event)
            %indices = event.Indices; newData = event.NewData;
            ID=find(strcmp(event.NewData,app.DataStructure.Scalars),1, 'first');
            if(~isempty(ID))
                if(~app.DataStructure.TimeStamped(app.DataStructure.Scalars_Positions(ID,1))) %not timestamped, defaults as non scatter plot like
                    try app.TabellaY.Data{event.Indices(1),4}=regexprep(regexprep(app.TabellaY.Data{event.Indices(1),4},'\.','-'),'b','r'); end %#ok<TRYNC> 
                end
            end
            app.UpdateTableSelection();
        end

        % Callback function
        function VarAddLineButtonPushed(app, event)
            if(app.DataStructure.Synchronous)
                NewLine={true,true,'OFF','.b','',inf};
            else
                NewLine={false,false,'OFF','.b','',inf};
            end
            app.TabellaY.Data(end+1,:)=NewLine;
            if(~app.Petition.ShowStats)
                set(app.Permannotation,'String','');
                SA=0;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            else
                SA=size(app.TabellaY.Data,1)+1;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            end
        end

        % Callback function
        function VarRemoveLineButtonPushed(app, event)
            if(~isempty(app.VarRemoveLine.UserData))
                toBeRemoved=unique(app.VarRemoveLine.UserData(:,1),'sorted');
                for II=size(app.TabellaY.Data,1):(-1):1
                    if(any(toBeRemoved==II))
                        app.TabellaY.Data(II,:)=[];
                    end
                end
            end
            app.UpdateTableSelection();
            if(~app.Petition.ShowStats)
                set(app.Permannotation,'String','');
                SA=0;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            else
                SA=size(app.TabellaY.Data,1)+1;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            end
        end

        % Callback function
        function TabellaYCellSelection(app, event)
            indices = event.Indices;
            app.VarRemoveLine.UserData=indices;
        end

        % Button pushed function: FilterAddLine
        function FilterAddLineButtonPushed(app, event)
            NewLine{1,1}=app.TABULA.ColumnFormat{1}{1};
            NewLine{1,2}=[];NewLine{1,3}=[];NewLine{1,4}=true;NewLine{1,5}=true;
            app.TABULA.Data(end+1,:)=NewLine;
        end

        % Button pushed function: FilterRemoveLine
        function FilterRemoveLineButtonPushed(app, event)
            app.FilterRemoveLine.UserData
            if(~isempty(app.FilterRemoveLine.UserData))
                toBeRemoved=unique(app.FilterRemoveLine.UserData(:,1),'sorted');
                for II=size(app.TABULA.Data,1):(-1):1
                    if(any(toBeRemoved==II))
                        app.TABULA.Data(II,:)=[];
                    end
                end
            end
            app.UpdateFilterSelection();
        end

        % Cell selection callback: TABULA
        function TABULACellSelection(app, event)
            indices = event.Indices;
            app.FilterRemoveLine.UserData=indices;
        end

        % Cell edit callback: TABULA
        function TABULACellEdit(app, event)
            app.UpdateFilterSelection();
        end

        % Callback function
        function secondssamplesEditFieldValueChanged(app, event)
            value=app.secondssamplesEditField.Value;
            VAL=str2double(value);
            if(VAL<=0 || isnan(VAL))
                app.secondssamplesEditField.Value='';
                app.Petition.LastSeconds=[];
            else
                app.Petition.LastSeconds=VAL;
            end
        end

        % Callback function
        function TypeofFitDropDownValueChanged(app, event)
            app.ManageFitVisibility(0);
        end

        % Callback function
        function AutoButtonPushed(app, event)
            app.Petition.AutoBinPartition=1;
            Value=str2num(app.AutoPartition.Value);
            Value=round(Value(1));
            if(Value<1)
                Value=1;
                app.AutoPartition.Value='1';
            end
            app.Petition.AutoBinNumber=Value;
        end

        % Value changed function: ExpressionEditField
        function ExpressionEditFieldValueChanged(app, event)
            value = app.ExpressionEditField.Value;
            if(isempty(value)), app.Petition.Function=''; else 
                app.Petition.Function=eval(['@(x) ',vectorize(value)]);
            end
        end

        % Value changed function: ShowstatsCheckBox
        function ShowstatsCheckBoxValueChanged(app, event)
            app.Petition.ShowStats=app.ShowstatsCheckBox.Value;
            if(~app.Petition.ShowStats)
                set(app.Permannotation,'String','');
                SA=0;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            else
                SA=size(app.TabellaY.Data,1)+1;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            end
        end

        % Callback function
        function TypeofPlotDropDownValueChanged(app, event)
            switch(app.TypeofPlotDropDown.Value)
                case 'Dots Plot'
                    app.Petition.TypeOfPlot=1;
                case 'Density Plot'
                    app.Petition.TypeOfPlot=2;
                case 'Partition on X'
                    app.Petition.TypeOfPlot=3;
                case 'Histogram'
                    app.Petition.TypeOfPlot=4;
            end
            app.ManageTypeOfPlotVisibility(0);
        end

        % Callback function
        function PartitionCenterEditFieldValueChanged(app, event)
            strvalue = app.PartitionCenterEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.PartitionPos=NaN;
            else
                app.Petition.PartitionPos=numericlist;
            end
        end

        % Callback function
        function PartitionWidthEditFieldValueChanged(app, event)
            strvalue = app.PartitionWidthEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.PartitionWidth=NaN;
            else
                app.Petition.PartitionWidth=numericlist;
            end
        end

        % Callback function
        function ShowonlyEditFieldValueChanged(app, event)
            strvalue = app.ShowonlyEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.ShowOnly=NaN;
            else
                numericlist=numericlist(~isnan(numericlist) && ~isinf(numericlist));
                numericlist=round(numericlist);
                numericlist=numericlist(numericlist>1);        
                app.Petition.ShowOnly=numericlist;
            end
        end

        % Callback function
        function OrderPolyFitEditValueChanged(app, event)
            app.Petition.PolyOrder=str2double(app.OrderPolyFitEdit.Value);
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            app.LogButton.Text=['','-> Log'];
            app.LogButton.Enable='Off';
            Author=app.AuthorEditField.Value; Title=app.TitleEditField.Value; Comment=app.commentEditField.Value;
            try
                if(isempty(app.EditTextFigCheckBox.UserData))
                    app.EditTextFigCheckBox.UserData='';
                end
                app.SendImageToLogbook(app.LogButton.UserData, Author, Title, Comment, app.EditTextFigCheckBox.UserData);
                disp('Image sent to logbook');
            catch
                disp('Failed to send image to logbook');
            end
            try %#ok<TRYNC> 
                close(app.LogButton.UserData);
            end
            app.LogButton.UserData=[];
        end

        % Value changed function: XMinEditField_2
        function XMinEditField_2ValueChanged(app, event)
            app.UpdateLimits2('x');
        end

        % Value changed function: XMaxEditField_2
        function XMaxEditField_2ValueChanged(app, event)
            app.UpdateLimits2('x');
        end

        % Value changed function: YMinEditField_2
        function YMinEditField_2ValueChanged(app, event)
            app.UpdateLimits2('y');
        end

        % Value changed function: YMaxEditField_2
        function YMaxEditField_2ValueChanged(app, event)
            app.UpdateLimits2('y');
        end

        % Value changed function: UseFilter1CheckBox
        function UseFilter1CheckBoxValueChanged(app, event)
            app.Petition.UseFilter(1) = app.UseFilter1CheckBox.Value;
        end

        % Value changed function: UseFilter2CheckBox
        function UseFilter2CheckBoxValueChanged(app, event)
            app.Petition.UseFilter(2) = app.UseFilter2CheckBox.Value;
        end

        % Callback function
        function ClearfixedorbitsButtonPushed(app, event)
            app.AdditionalOrbits=[];
        end

        % Value changed function: LiveorbitaveragesEditField
        function LiveorbitaveragesEditFieldValueChanged(app, event)
            app.Petition.LastN=str2double(app.LiveorbitaveragesEditField.Value);
        end

        % Callback function
        function TopEditField_1ValueChanged(app, event)
            app.Petition.Top1=str2double(app.TopEditField_1.Value);
        end

        % Callback function
        function TopEditField_2ValueChanged(app, event)
            app.Petition.Top2=str2double(app.TopEditField_2.Value);
        end

        % Callback function
        function AboveEditField_1ValueChanged(app, event)
            app.Petition.Above1=str2double(app.AboveEditField_1.Value);
        end

        % Callback function
        function AboveEditField_2ValueChanged(app, event)
            app.Petition.Above2=str2double(app.AboveEditField_2.Value);
        end

        % Callback function
        function colorscalesinglesCheckBox_1ValueChanged(app, event)
            app.Petition.ColorScale1 = app.colorscalesinglesCheckBox_1.Value;        
        end

        % Callback function
        function colorscalesinglesCheckBox_2ValueChanged(app, event)
            app.Petition.ColorScale2 = app.colorscalesinglesCheckBox_2.Value;   
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            app.UpdateAmpLimits();
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.UpdateAmpLimits();
        end

        % Callback function
        function QRCheckBoxValueChanged(app, event)
            app.Petition.QR=app.QRCheckBox.Value;
            app.LSCOVCheckBox.Value=~app.QRCheckBox.Value;
        end

        % Callback function
        function LSCOVCheckBoxValueChanged(app, event)
            app.QRCheckBox.Value = ~app.LSCOVCheckBox.Value;
            app.Petition.QR=app.QRCheckBox.Value;
        end

        % Value changed function: DispersionEditField
        function DispersionEditFieldValueChanged(app, event)
            app.Petition.DispersionCoefficient=str2double(app.DispersionEditField.Value);
        end

        % Value changed function: AreaDropDown
        function AreaDropDownValueChanged(app, event)
            app.Petition.Area=app.AreaDropDown.Value;
        end

        % Value changed function: BoxGridCheckBox
        function BoxGridCheckBoxValueChanged(app, event)
            if(app.BoxGridCheckBox.Value)
                try %#ok<TRYNC> 
                    box(app.ASSE,'on'); grid(app.ASSE,'on'); box(app.ASSE2,'on'); grid(app.ASSE2,'on'); box(app.ASSE3,'on'); grid(app.ASSE3,'on');
                end
            else
                try %#ok<TRYNC> 
                    box(app.ASSE,'off'); grid(app.ASSE,'off'); box(app.ASSE2,'off'); grid(app.ASSE2,'off'); box(app.ASSE3,'off'); grid(app.ASSE3,'off');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DispersionDisplayUIFigure = uifigure('Visible', 'off');
            app.DispersionDisplayUIFigure.Name = 'Dispersion Display';
            app.DispersionDisplayUIFigure.CloseRequestFcn = createCallbackFcn(app, @DispersionDisplayUIFigureCloseRequest, true);
            app.XMinEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.XMinEditFieldLabel.Text = 'XMin';
            app.XMinEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.XMinEditField.ValueChangedFcn = createCallbackFcn(app, @XMinEditFieldValueChanged, true);
            app.XMaxEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.XMaxEditFieldLabel.Text = 'XMax';
            app.XMaxEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.XMaxEditField.ValueChangedFcn = createCallbackFcn(app, @XMaxEditFieldValueChanged, true);
            app.YMinEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.YMinEditFieldLabel.Text = 'YMin';
            app.YMinEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.YMinEditField.ValueChangedFcn = createCallbackFcn(app, @YMinEditFieldValueChanged, true);
            app.YMaxEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.YMaxEditFieldLabel.Text = 'YMax';
            app.YMaxEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.YMaxEditField.ValueChangedFcn = createCallbackFcn(app, @YMaxEditFieldValueChanged, true);
            app.AxesFigureButton = uibutton(app.DispersionDisplayUIFigure, 'push');
            app.AxesFigureButton.ButtonPushedFcn = createCallbackFcn(app, @AxesFigureButtonPushed, true);
            app.AxesFigureButton.Text = 'Axes Figure';
            app.MoveAxesNearButton = uibutton(app.DispersionDisplayUIFigure, 'push');
            app.MoveAxesNearButton.ButtonPushedFcn = createCallbackFcn(app, @MoveAxesNearButtonPushed, true);
            app.MoveAxesNearButton.Text = 'Move Axes Near';
            app.PositionDropDown = uidropdown(app.DispersionDisplayUIFigure);
            app.PositionDropDown.Items = {'Right', 'Above', 'Left', 'Below'};
            app.PositionDropDown.Value = 'Right';
            app.DefaultAxesSizeButton = uibutton(app.DispersionDisplayUIFigure, 'push');
            app.DefaultAxesSizeButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultAxesSizeButtonPushed, true);
            app.DefaultAxesSizeButton.Text = 'Default Axes Size';
            app.LogbookButton = uibutton(app.DispersionDisplayUIFigure, 'push');
            app.LogbookButton.ButtonPushedFcn = createCallbackFcn(app, @LogbookButtonPushed, true);
            app.LogbookButton.Text = 'Logbook';
            app.SaveDataCheckBox = uicheckbox(app.DispersionDisplayUIFigure);
            app.SaveDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDataCheckBoxValueChanged, true);
            app.SaveDataCheckBox.Text = 'Save Data';
            app.SaveDataCheckBox.Value = true;
            app.EditTextFigCheckBox = uicheckbox(app.DispersionDisplayUIFigure);
            app.EditTextFigCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditTextFigCheckBoxValueChanged, true);
            app.EditTextFigCheckBox.Text = 'Edit Text/Fig';
            app.SaveLabel = uilabel(app.DispersionDisplayUIFigure);
            app.SaveLabel.Text = 'Last: XX/XX/XXXX';
            app.TABULA = uitable(app.DispersionDisplayUIFigure);
            app.TABULA.ColumnName = {'Variable Name'; 'Minimum'; 'Maximum'; 'Filter1'; 'Filter 2'};
            app.TABULA.RowName = {};
            app.TABULA.CellEditCallback = createCallbackFcn(app, @TABULACellEdit, true);
            app.TABULA.CellSelectionCallback = createCallbackFcn(app, @TABULACellSelection, true);
            app.FilteringoptionsLabel = uilabel(app.DispersionDisplayUIFigure);
            app.FilteringoptionsLabel.Text = 'Filtering options:';
            app.AxiscontrolLabel = uilabel(app.DispersionDisplayUIFigure);
            app.AxiscontrolLabel.Text = 'Axis control:';
            app.STOP = uibutton(app.DispersionDisplayUIFigure, 'push');
            app.STOP.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOP.Text = 'Now Running->Resume Updating';
            app.FilterAddLine = uibutton(app.DispersionDisplayUIFigure, 'push');
            app.FilterAddLine.ButtonPushedFcn = createCallbackFcn(app, @FilterAddLineButtonPushed, true);
            app.FilterAddLine.Text = 'Add Line';
            app.FilterRemoveLine = uibutton(app.DispersionDisplayUIFigure, 'push');
            app.FilterRemoveLine.ButtonPushedFcn = createCallbackFcn(app, @FilterRemoveLineButtonPushed, true);
            app.FilterRemoveLine.Text = 'Remove Line';
            app.ExpressionEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.ExpressionEditFieldLabel.Text = 'Expression';
            app.ExpressionEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.ExpressionEditField.ValueChangedFcn = createCallbackFcn(app, @ExpressionEditFieldValueChanged, true);
            app.ShowstatsCheckBox = uicheckbox(app.DispersionDisplayUIFigure);
            app.ShowstatsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowstatsCheckBoxValueChanged, true);
            app.ShowstatsCheckBox.Text = 'Show stats';
            app.LogButton = uibutton(app.DispersionDisplayUIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = '-> Log';
            app.AuthorEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.AuthorEditFieldLabel.Text = 'Author:';
            app.AuthorEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.TitleEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.TitleEditField.Value = 'CVCRCI - Undulator Orbit';
            app.commentEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.commentEditFieldLabel.Text = 'comment';
            app.commentEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.EnergyBPMLabel = uilabel(app.DispersionDisplayUIFigure);
            app.EnergyBPMLabel.Text = 'Energy BPM';
            app.S_Scalar_1 = uidropdown(app.DispersionDisplayUIFigure);
            app.S_Scalar_1.ValueChangedFcn = createCallbackFcn(app, @S_ScalarValueChanged, true);
            app.AreaDropDownLabel = uilabel(app.DispersionDisplayUIFigure);
            app.AreaDropDownLabel.Text = 'Area';
            app.AreaDropDown = uidropdown(app.DispersionDisplayUIFigure);
            app.AreaDropDown.ValueChangedFcn = createCallbackFcn(app, @AreaDropDownValueChanged, true);
            app.LiveorbitaveragesEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.LiveorbitaveragesEditFieldLabel.Text = 'Live orbit averages';
            app.LiveorbitaveragesEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.LiveorbitaveragesEditField.ValueChangedFcn = createCallbackFcn(app, @LiveorbitaveragesEditFieldValueChanged, true);
            app.LiveorbitaveragesEditField.Value = '120';
            app.RequiresBSALabel = uilabel(app.DispersionDisplayUIFigure);
            app.RequiresBSALabel.Text = 'Requires BSA';
            app.YMaxEditField_2Label = uilabel(app.DispersionDisplayUIFigure);
            app.YMaxEditField_2Label.Text = 'YMax';
            app.YMaxEditField_2 = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.YMaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @YMaxEditField_2ValueChanged, true);
            app.YMinEditField_2Label = uilabel(app.DispersionDisplayUIFigure);
            app.YMinEditField_2Label.Text = 'YMin';
            app.YMinEditField_2 = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.YMinEditField_2.ValueChangedFcn = createCallbackFcn(app, @YMinEditField_2ValueChanged, true);
            app.XMaxEditField_2Label = uilabel(app.DispersionDisplayUIFigure);
            app.XMaxEditField_2Label.Text = 'XMax';
            app.XMaxEditField_2 = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.XMaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @XMaxEditField_2ValueChanged, true);
            app.XMinEditField_2Label = uilabel(app.DispersionDisplayUIFigure);
            app.XMinEditField_2Label.Text = 'XMin';
            app.XMinEditField_2 = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.XMinEditField_2.ValueChangedFcn = createCallbackFcn(app, @XMinEditField_2ValueChanged, true);
            app.XLabel = uilabel(app.DispersionDisplayUIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.DispersionDisplayUIFigure);
            app.YLabel.Text = 'Y';
            app.ScanVariableLabel = uilabel(app.DispersionDisplayUIFigure);
            app.ScanVariableLabel.Text = 'Scan Variable';
            app.S_Scalar_2 = uidropdown(app.DispersionDisplayUIFigure);
            app.S_Scalar_2.ValueChangedFcn = createCallbackFcn(app, @S_ScalarValueChanged, true);
            app.UseFilter1CheckBox = uicheckbox(app.DispersionDisplayUIFigure);
            app.UseFilter1CheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFilter1CheckBoxValueChanged, true);
            app.UseFilter1CheckBox.Text = 'Use Filter 1';
            app.UseFilter2CheckBox = uicheckbox(app.DispersionDisplayUIFigure);
            app.UseFilter2CheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFilter2CheckBoxValueChanged, true);
            app.UseFilter2CheckBox.Text = 'Use Filter 2';
            app.ALabel = uilabel(app.DispersionDisplayUIFigure);
            app.ALabel.Visible = 'off';
            app.ALabel.Text = 'A';
            app.MaxEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.MaxEditFieldLabel.Visible = 'off';
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Visible = 'off';
            app.MinEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.MinEditFieldLabel.Visible = 'off';
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MinEditField.Visible = 'off';
            app.DispersionEditFieldLabel = uilabel(app.DispersionDisplayUIFigure);
            app.DispersionEditFieldLabel.Text = 'Dispersion';
            app.DispersionEditField = uieditfield(app.DispersionDisplayUIFigure, 'text');
            app.DispersionEditField.ValueChangedFcn = createCallbackFcn(app, @DispersionEditFieldValueChanged, true);
            app.DispersionEditField.Value = '125';
            app.RequiresSynchronousmodeLabel = uilabel(app.DispersionDisplayUIFigure);
            app.RequiresSynchronousmodeLabel.Text = 'Requires Synchronous mode';
            app.BoxGridCheckBox = uicheckbox(app.DispersionDisplayUIFigure);
            app.BoxGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoxGridCheckBoxValueChanged, true);
            app.BoxGridCheckBox.Text = 'Box&Grid';
            % Show the figure after all components are created
            app.DispersionDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVCRCI_Dispersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DispersionDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DispersionDisplayUIFigure)
        end
    end
end