classdef CVCRCI_Image_and_Scalar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageandScalarUIFigure         matlab.ui.Figure
        XMinEditFieldLabel             matlab.ui.control.Label
        XMinEditField                  matlab.ui.control.EditField
        XMaxEditFieldLabel             matlab.ui.control.Label
        XMaxEditField                  matlab.ui.control.EditField
        YMinEditFieldLabel             matlab.ui.control.Label
        YMinEditField                  matlab.ui.control.EditField
        YMaxEditFieldLabel             matlab.ui.control.Label
        YMaxEditField                  matlab.ui.control.EditField
        AxesFigureButton               matlab.ui.control.Button
        MoveAxesNearButton             matlab.ui.control.Button
        PositionDropDown               matlab.ui.control.DropDown
        DefaultAxesSizeButton          matlab.ui.control.Button
        LogbookButton                  matlab.ui.control.Button
        SaveDataCheckBox               matlab.ui.control.CheckBox
        EditTextFigCheckBox            matlab.ui.control.CheckBox
        SaveLabel                      matlab.ui.control.Label
        TABULA                         matlab.ui.control.Table
        FilteringoptionsLabel          matlab.ui.control.Label
        AxiscontrolLabel               matlab.ui.control.Label
        STOP                           matlab.ui.control.Button
        FilterAddLine                  matlab.ui.control.Button
        FilterRemoveLine               matlab.ui.control.Button
        ScalarVariableLabel            matlab.ui.control.Label
        S_Scalar                       matlab.ui.control.DropDown
        PIDDelayEditFieldLabel         matlab.ui.control.Label
        PIDDelayEditField              matlab.ui.control.EditField
        TypeofPlotDropDownLabel        matlab.ui.control.Label
        TypeofPlotDropDown             matlab.ui.control.DropDown
        ExpressionEditFieldLabel       matlab.ui.control.Label
        ExpressionEditField            matlab.ui.control.EditField
        BinsXEditFieldLabel            matlab.ui.control.Label
        BinsXEditField                 matlab.ui.control.EditField
        BinsYEditFieldLabel            matlab.ui.control.Label
        BinsYEditField                 matlab.ui.control.EditField
        PartitionCenterEditFieldLabel  matlab.ui.control.Label
        PartitionCenterEditField       matlab.ui.control.EditField
        PartitionWidthEditFieldLabel   matlab.ui.control.Label
        PartitionWidthEditField        matlab.ui.control.EditField
        ShowonlyEditFieldLabel         matlab.ui.control.Label
        ShowonlyEditField              matlab.ui.control.EditField
        AutoButton                     matlab.ui.control.Button
        AutoPartition                  matlab.ui.control.EditField
        ShowstatsCheckBox              matlab.ui.control.CheckBox
        S_Vector                       matlab.ui.control.DropDown
        ImageVariableLabel             matlab.ui.control.Label
        AnalysisLabel                  matlab.ui.control.Label
        FWHMCheckBox                   matlab.ui.control.CheckBox
        PeakandpositionCheckBox        matlab.ui.control.CheckBox
        SubtypeofPlotDropDownLabel     matlab.ui.control.Label
        SubtypeofPlotDropDown          matlab.ui.control.DropDown
        TABULA_AREA                    matlab.ui.control.Table
        AreaAddLine                    matlab.ui.control.Button
        areaRemoveLine                 matlab.ui.control.Button
        AreadefinitionLabel            matlab.ui.control.Label
        ChangePV                       matlab.ui.control.Button
        vsmodeCheckBox                 matlab.ui.control.CheckBox
        PublishstatsCheckBox           matlab.ui.control.CheckBox
        IntegralCheckBox               matlab.ui.control.CheckBox
        CalibCenter                    matlab.ui.control.EditField
        XMaxEditFieldLabel_2           matlab.ui.control.Label
        CalibLinear                    matlab.ui.control.EditField
        XMinEditFieldLabel_2           matlab.ui.control.Label
        AxiscalibrationLabel           matlab.ui.control.Label
        UsecalibrationCheckBox         matlab.ui.control.CheckBox
        CalibLinear2                   matlab.ui.control.EditField
        XMaxEditFieldLabel_3           matlab.ui.control.Label
        CalibCenter2                   matlab.ui.control.EditField
        XMinEditFieldLabel_3           matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        Rot                            matlab.ui.control.CheckBox
        flipUD                         matlab.ui.control.CheckBox
        flipLR                         matlab.ui.control.CheckBox
        ImageRotationLabel             matlab.ui.control.Label
        AutoCalibration                matlab.ui.control.Button
        ColorCheat                     matlab.ui.control.Table
        PchipButton                    matlab.ui.control.Button
        ratioLabel                     matlab.ui.control.Label
        valueLabel                     matlab.ui.control.Label
        LastNEditFieldLabel            matlab.ui.control.Label
        LastNEditField                 matlab.ui.control.EditField
        AuthorEditFieldLabel           matlab.ui.control.Label
        AuthorEditField                matlab.ui.control.EditField
        TitleEditFieldLabel            matlab.ui.control.Label
        TitleEditField                 matlab.ui.control.EditField
        LogButton                      matlab.ui.control.Button
        commentEditFieldLabel          matlab.ui.control.Label
        commentEditField               matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Petition % Structure used by the update function .
        DataStructure % Stores Acquisition variable for re initialization.
        ASSE % True axes not UI axes for speed to be located in PGR figure.
        ASSEM % Secondary axes within display figure.
        PGR % Patently-slow Graphics Remedy, a figure that supports faster graphics.
        ASSEDefaultSize; %default size when new figure is turned on.
        color; %colors for the panel
        application='Test';
        ToBeDeleted=[];
        Annotations=[];
        Permannotation;
        FIELDNAMES;
        LineColor=[0,0,0; 0,0,1; 1,0,0; 0,1,0; 1,0.5,0; 0,1,1; 1,0,1; 1,1,0;0,1,0.5;0,0.5,1;1,0,0.5;0.5,0,1;0.5,1,0;0.5,0,0;0,0.5,0;0,0,0.5;0.5,0,0.5;0.5,0.5,0;0,0.5,0.5;1/3,2/3,1;2/3,1/3,1;1,1/3,2/3;1,2/3,1/3;1/3,1,2/3;2/3,1,1/3];
        MaxFigures=10;
        NOF;
        ImageName;
        ScalarName;
        DefaultNewLine={'',[],'-b',1,{'SIOC:SYS0:ML07:500','SIOC:SYS0:ML07:501','SIOC:SYS0:ML07:502','SIOC:SYS0:ML07:503','SIOC:SYS0:ML07:504','SIOC:SYS0:ML07:505','SIOC:SYS0:ML07:506','SIOC:SYS0:ML07:507','SIOC:SYS0:ML07:508','SIOC:SYS0:ML07:509'},true,true};
    end
    
    properties (Access = public)
        NeedInit=1; % Description
    end
    
    methods (Access = public)
        
        function Update(app,CV,Initialize)
            if(Initialize)
                app.UpdateInitialize(CV);
            else
                if(app.Petition.STOP), return, end
                if(~app.Petition.I_SEL(1)), return, end
                if(app.Petition.TypeOfPlot==1) % Image Mode: No scalar is needed.
                    if(~isvalid(app.PGR)), app.CheckExistenceMakeAxes(); disp('Axes were remade because could not be found'); end
                    delete(app.ASSE.Children); %if(~isempty(app.Petition.LastSeconds)), MT=-inf; end
                    hold(app.ASSE,'on');
                    V_Timestamped=app.DataStructure.TimeStamped(app.Petition.I_SEL(1));
                    if(V_Timestamped)
                        if(app.DataStructure.Synchronous)
                            if(CV.Recorder.TimesBufferFilled)
                                if(CV.Recorder.LastWrittenElement==app.DataStructure.Buffersize)
                                    TENUTI=1:app.DataStructure.Buffersize;
                                else
                                    TENUTI=[(CV.Recorder.LastWrittenElement+1):app.DataStructure.Buffersize,(1:CV.Recorder.LastWrittenElement)];
                                end
                            else
                                TENUTI=1:CV.Recorder.LastWrittenElement;
                            end
                            TF{1}=TENUTI;
                            TF{2}=TENUTI;
                            for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                                if(app.Petition.FilterUse{FN}(1))
                                    TF{2}=TF{2}((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{2},app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{2},app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                                end
                            end
                            TF{3}=TENUTI;
                            for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                                if(app.Petition.FilterUse{FN}(2))
                                    TF{3}=TF{3}((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{3},app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{3},app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                                end
                            end
                            TF{4}=intersect(TF{2},TF{3},'stable');
                        else
                            if(CV.Recorder.TimesBufferFilled{app.Petition.I_SEL(1)})
                                if(CV.Recorder.LastWrittenElement{app.Petition.I_SEL(1)}<CV.Acquisition.VarBuffersize(app.Petition.I_SEL(1)))
                                    SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.I_SEL(1)}+1):CV.Acquisition.VarBuffersize(app.Petition.I_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.I_SEL(1)}];
                                else
                                    SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.I_SEL(1));
                                end
                            else
                                SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.I_SEL(1)};
                            end
                        end
                    else
                        if(CV.Recorder.AcquisitionNonTimeStampedTimesBufferFilled(app.Petition.I_SEL(1)))
                            if(CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.I_SEL(1))<CV.Acquisition.VarBuffersize(app.Petition.I_SEL(1)))
                                SORTING_ORDER=[(1+CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.I_SEL(1))):CV.Acquisition.VarBuffersize(app.Petition.I_SEL(1)),1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.I_SEL(1))];
                            else
                                SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.I_SEL(1));
                            end
                        else
                            SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.I_SEL(1));
                        end
                    end
                    
                    if(isempty(app.Petition.CalibLinear) && isempty(app.Petition.CalibCenter))
                        Ax=1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2);
                    elseif(isempty(app.Petition.CalibCenter))
                        Ax=((1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)) - (CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)+1)/2)*app.Petition.CalibLinear;
                    elseif(isempty(app.Petition.CalibLinear))
                        Ax=((1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)) - (CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)+1)/2) + app.Petition.CalibCenter;
                    else
                        Ax=((1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)) - (CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)+1)/2)*app.Petition.CalibLinear + app.Petition.CalibCenter;
                    end
                    
                    if(isempty(app.Petition.CalibLinear2) && isempty(app.Petition.CalibCenter2))
                        Ay=1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(1);
                    elseif(isempty(app.Petition.CalibCenter2))
                        Ay=((1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(1)) - (CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(1)+1)/2)*app.Petition.CalibLinear2;
                    elseif(isempty(app.Petition.CalibLinear2))
                        Ay=((1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(1)) - (CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(1)+1)/2) + app.Petition.CalibCenter2;
                    else
                        Ay=((1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(1)) - (CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(1)+1)/2)*app.Petition.CalibLinear2 + app.Petition.CalibCenter2;
                    end
                    
                    for II=1:1%min(length(app.Petition.Quantity),10)
                        if(app.DataStructure.Synchronous) %apply filters
                            FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
                            switch(FilterNum)
                                case 1, ID=1;
                                case 2, ID=2;
                                case 3, ID=3;
                                case 6, ID=4;
                            end
                            SORTING_ORDER=TF{ID};
                        end
                        if(isempty(SORTING_ORDER)), continue, end
%                         if(isempty(app.Petition.Quantity(II).Area))
%                             AREA=1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2);
%                         else
%                             AREA=app.Petition.Quantity(II).Area;
%                             AREA=intersect(AREA,1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2),'stable');
%                         end
                        
                        if(isnan(app.Petition.LastN) || app.Petition.LastN==1)
                            IMAGE=CV.Recorder.Data{app.Petition.I_SEL(1)}(:,:,SORTING_ORDER(end));
                        elseif(app.Petition.LastN>=length(SORTING_ORDER))
                            IMAGE=mean(CV.Recorder.Data{app.Petition.I_SEL(1)}(:,:,SORTING_ORDER),3);
                        else
                            IMAGE=mean(CV.Recorder.Data{app.Petition.I_SEL(1)}(:,:,SORTING_ORDER((end-app.Petition.LastN+1):end)),3);
                        end
                        if(~isempty(app.Petition.Function)), IMAGE=app.Petition.Function(IMAGE);end
                        app.DisplayImage(Ax,Ay,IMAGE,app.ASSE);
                        if(app.Petition.ShowStats)
                            if(II==1)
                                TABRES=cell(min(length(app.Petition.Quantity),10),7);
                                TABRES{1,1}='Area N.'; TABRES{1,2}='Peak Val'; TABRES{1,3}='Peak Pos'; TABRES{1,4}='Peak Pos ID'; TABRES{1,5}='FWHM'; TABRES{1,6}='Integral';
                            end
                            TABRES{II+1,1}=num2str(II);
                            if(any([app.Petition.FWHM,app.Petition.Peak,app.Petition.Integral]))
                                [FWHM, MP, MV, INT]=CV.EvalVectorBasicStats(VETTORE);
                                if(app.Petition.PublishStats)
                                    lcaPutSmart(app.Petition.Quantity(II).BasePV{1},II);
                                end
                            end
                            if(app.Petition.FWHM)
                                TABRES{II+1,5}=num2str(FWHM);
                                if(app.Petition.PublishStats)
                                    lcaPutSmart(app.Petition.Quantity(II).BasePV{5},FWHM);
                                end
                            end
                            if(app.Petition.Peak)
                                TABRES{II+1,2}=num2str(MV);
                                TABRES{II+1,3}=num2str(Ax(AREA(MP)));
                                TABRES{II+1,4}=num2str(MP);
                                if(app.Petition.PublishStats)
                                    lcaPutSmart(app.Petition.Quantity(II).BasePV{2},MV);
                                    lcaPutSmart(app.Petition.Quantity(II).BasePV{3},Ax(AREA(MP)));
                                    lcaPutSmart(app.Petition.Quantity(II).BasePV{4},MP);
                                end
                            end
                            if(app.Petition.Integral)
                                TABRES{II+1,6}=num2str(INT);
                                if(app.Petition.PublishStats)
                                    lcaPutSmart(app.Petition.Quantity(II).BasePV{6},INT);
                                end
                            end
                        end
                    end
                    if(app.Petition.ShowStats)
                        MT=CV.TextMatrixToTextColumn(TABRES);
                        app.Permannotation.String=MT;
                    end
                elseif(app.Petition.S_SEL(1)) %ok both scalar and vector.
                    if(~isvalid(app.PGR)), app.CheckExistenceMakeAxes(); disp('Axes were remade because could not be found'); end
                    delete(app.ASSE.Children); %if(~isempty(app.Petition.LastSeconds)), MT=-inf; end
                    hold(app.ASSE,'on');
                    V_Timestamped=app.DataStructure.TimeStamped(app.Petition.I_SEL(1));
                    if(V_Timestamped)
                        if(app.DataStructure.Synchronous)
                            if(CV.Recorder.TimesBufferFilled)
                                if(CV.Recorder.LastWrittenElement==app.DataStructure.Buffersize)
                                    TENUTI=1:app.DataStructure.Buffersize;
                                else
                                    TENUTI=[(CV.Recorder.LastWrittenElement+1):app.DataStructure.Buffersize,(1:CV.Recorder.LastWrittenElement)];
                                end
                            else
                                TENUTI=1:CV.Recorder.LastWrittenElement;
                            end
                            TF{1}=TENUTI;
                            TF{2}=TENUTI;
                            for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                                if(app.Petition.FilterUse{FN}(1))
                                    TF{2}=TF{2}((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{2},app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{2},app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                                end
                            end
                            TF{3}=TENUTI;
                            for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                                if(app.Petition.FilterUse{FN}(2))
                                    TF{3}=TF{3}((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{3},app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{3},app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                                end
                            end
                            TF{4}=intersect(TF{2},TF{3},'stable');
                            V_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle;
                        else
                            if(CV.Recorder.TimesBufferFilled{app.Petition.I_SEL(1)})
                                if(CV.Recorder.LastWrittenElement{app.Petition.I_SEL(1)}<CV.Acquisition.VarBuffersize(app.Petition.I_SEL(1)))
                                    V_SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.I_SEL(1)}+1):CV.Acquisition.VarBuffersize(app.Petition.I_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.I_SEL(1)}];
                                else
                                    V_SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.I_SEL(1));
                                end
                            else
                                V_SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.I_SEL(1)};
                            end
                            V_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle{app.Petition.I_SEL(1)};
                            V_Timestamps=CV.Recorder.TimeStamps{app.Petition.I_SEL(1)}; % Not ordered here to keep same order as in data
                        end
                    else
                        if(CV.Recorder.AcquisitionNonTimeStampedTimesBufferFilled(app.Petition.I_SEL(1)))
                            if(CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.I_SEL(1))<CV.Acquisition.VarBuffersize(app.Petition.I_SEL(1)))
                                V_SORTING_ORDER=[(1+CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.I_SEL(1))):CV.Acquisition.VarBuffersize(app.Petition.I_SEL(1)),1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.I_SEL(1))];
                            else
                                V_SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.I_SEL(1));
                            end
                        else
                            V_SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.I_SEL(1));
                        end
                        V_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.I_SEL(1)};
                    end
                    
                    if(app.DataStructure.Synchronous)
                        X_Timestamped=app.DataStructure.TimeStamped(app.Petition.S_SEL(1));
                        if(X_Timestamped)
                            X=CV.Recorder.Data{app.Petition.S_SEL(1)}(:,app.Petition.S_SEL(2));
                            X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle;
                        else
                            X=CV.Recorder.Data{app.Petition.S_SEL(1)}(:,app.Petition.S_SEL(2));
                            if(app.DataStructure.BSA || app.Petition.S_SEL(1)~=1)
                                X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.S_SEL(1)};
                            else
                                X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.S_SEL(1)}(:,app.Petition.S_SEL(2));
                            end
                        end
                    else % asyhcnronous
                        X_Timestamped=app.DataStructure.TimeStamped(app.Petition.S_SEL(1));
                        if(X_Timestamped)
                            if(CV.Acquisition.BSA || app.Petition.S_SEL(1)~=1)
                                if(CV.Recorder.TimesBufferFilled{app.Petition.S_SEL(1)})
                                    if(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}<CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)))
                                        SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}+1):CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}];
                                    else
                                        SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1));
                                    end
                                else
                                    SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)};
                                end
                                X=CV.Recorder.Data{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                                X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle{app.Petition.S_SEL(1)}(SORTING_ORDER);
                                X_Timestamps=CV.Recorder.TimeStamps{app.Petition.S_SEL(1)}(SORTING_ORDER);
                            else
                                if(CV.Recorder.TimesBufferFilled{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2)))
                                    if(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2))<CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)))
                                        SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2))+1):CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2))];
                                    else
                                        SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1));
                                    end
                                else
                                    SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2));
                                end
                                X=CV.Recorder.Data{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                                X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                                X_Timestamps=CV.Recorder.TimeStamps{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                            end
                        else
                            if(CV.Recorder.AcquisitionNonTimeStampedTimesBufferFilled(app.Petition.S_SEL(1)))
                                if(CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1))<CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)))
                                    SORTING_ORDER=[(1+CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1))):CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)),1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1))];
                                else
                                    SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1));
                                end
                            else
                                SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1));
                            end
                            X=CV.Recorder.Data{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                            X_Timestamped=0;
                            if(CV.Acquisition.BSA || app.Petition.S_SEL(1)~=1)
                                X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.S_SEL(1)}(SORTING_ORDER);
                            else
                                X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                            end
                        end
                    end
                    if(isempty(app.Petition.CalibLinear) && isempty(app.Petition.CalibCenter))
                        Ax=1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2);
                    elseif(isempty(app.Petition.CalibCenter))
                        Ax=((1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)) - (CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)+1)/2)*app.Petition.CalibLinear;
                    elseif(isempty(app.Petition.CalibLinear))
                        Ax=((1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)) - (CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)+1)/2) + app.Petition.CalibCenter;
                    else
                        Ax=((1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)) - (CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2)+1)/2)*app.Petition.CalibLinear + app.Petition.CalibCenter;
                    end
                    
                    for II=1:length(app.Petition.Quantity)
                        if(isempty(app.Petition.Quantity(II).Area))
                            AREA=1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2);
                        else
                            AREA=app.Petition.Quantity(II).Area;
                            AREA=intersect(AREA,1:CV.Acquisition.AllSizes{app.Petition.I_SEL(1)}(2),'stable');
                        end
                        if(X_Timestamped && V_Timestamped) %Both timestamped
                            if(app.DataStructure.Synchronous)
                                FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
                                switch(FilterNum)
                                    case 1, ID=1;
                                    case 2, ID=2;
                                    case 3, ID=3;
                                    case 6, ID=4;
                                end
                                KV=TF{ID}; KX=TF{ID};
                            else
                                [~,KX,KV]=intersect(X_Timestamps,V_Timestamps+app.Petition.PidDelay,'stable');
                            end
                            if(~isnan(app.Petition.Quantity(II).LastN) || app.Petition.Quantity(II).LastN<length(KX))
                                KX=KX((end-app.Petition.Quantity(II).LastN+1):end);
                                KV=KV((end-app.Petition.Quantity(II).LastN+1):end);
                            end
                            
                        elseif(X_Timestamped) %only X timestamped
                            if(app.DataStructure.Synchronous)
                                FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
                                switch(FilterNum)
                                    case 1, ID=1;
                                    case 2, ID=2;
                                    case 3, ID=3;
                                    case 6, ID=4;
                                end
                            end
                            KX=TF{ID};
                            KV=CV.NonTimeStamped_On_Timestamped(V_AcquisitionWritingCycle,(1:length(V_AcquisitionWritingCycle)).',X_AcquisitionWritingCycle(TF{ID}));
                            if(~isnan(app.Petition.Quantity(II).LastN) || app.Petition.Quantity(II).LastN<length(KX))
                                KX=KX((end-app.Petition.Quantity(II).LastN+1):end);
                                KV=KV((end-app.Petition.Quantity(II).LastN+1):end);
                            end
                        elseif(V_Timestamped) %only V timestamped (typical for plotting scan results)
                            if(app.DataStructure.Synchronous)
                                FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
                                switch(FilterNum)
                                    case 1, ID=1;
                                    case 2, ID=2;
                                    case 3, ID=3;
                                    case 6, ID=4;
                                end
                            end
                            KV=TF{ID};
                            KX=CV.NonTimeStamped_On_Timestamped(X_AcquisitionWritingCycle,(1:length(X_AcquisitionWritingCycle)).',V_AcquisitionWritingCycle(TF{ID}));
                            if(~isnan(app.Petition.Quantity(II).LastN) || app.Petition.Quantity(II).LastN<length(KX))
                                KX=KX((end-app.Petition.Quantity(II).LastN+1):end);
                                KV=KV((end-app.Petition.Quantity(II).LastN+1):end);
                            end
                        else % neither is timestamped.
                            [~,KX,KV]=intersect(X_AcquisitionWritingCycle,V_AcquisitionWritingCycle,'stable');
                            if(~isnan(app.Petition.Quantity(II).LastN) || app.Petition.Quantity(II).LastN<length(KX))
                                KX=KX((end-app.Petition.Quantity(II).LastN+1):end);
                                KV=KV((end-app.Petition.Quantity(II).LastN+1):end);
                            end
                        end
                        switch(app.Petition.TypeOfPlot)
                            case 2
                                [LPlot,xax]=CV.LPlot(CV.Recorder.Data{app.Petition.I_SEL(1)},X,KV,KX,AREA,app.Petition.binsx);
                                lxax=length(unique(xax));
                                if(lxax>1)
                                    imagesc(Ax(AREA),xax,LPlot,'parent',app.ASSE);
                                    [NSamples,BinCenters] = histcounts(X(KX((X(KX)>=xax(1)) & (X(KX)<=xax(end)))),app.Petition.binsx); %#ok<ASGLU>
                                    barh(app.ASSEM,xax,NSamples);
                                    xlim(app.ASSE,[Ax(AREA(1)),Ax(AREA(end))]);
                                    ylim(app.ASSE,[xax(1),xax(end)]);
                                    ylim(app.ASSEM,[xax(1),xax(end)]);
                                else
                                    imagesc(Ax(AREA),xax,LPlot,'parent',app.ASSE);
                                    [NSamples,BinCenters] = histcounts(X(KX((X(KX)>=xax(1)) & (X(KX)<=xax(end)))),app.Petition.binsx); %#ok<ASGLU>
                                    
                                end
                            case {3,4}
                                Stats=app.Petition.FWHM || app.Petition.Peak || app.Petition.Integral; %app.Petition.vsMode
                                if(app.Petition.TypeOfPlot==3)
                                    [PPlot,SubPlots]=CV.PPlot(CV.Recorder.Data{app.Petition.I_SEL(1)},X,KV,KX,AREA, app.Petition.PartitionPos, app.Petition.PartitionWidth,Stats);
                                elseif(app.Petition.TypeOfPlot==4)
                                    [PPlot,SubPlots]=CV.PPlot(CV.Recorder.Data{app.Petition.I_SEL(1)},X,KV,KX,AREA, inf, inf,Stats);
                                end
                                if(app.Petition.vsMode)
                                    if(II==1)
                                        XDataSubplots=SubPlots;
                                    else
                                        switch app.Petition.TypeOfSubPlot
                                            case 1 %Doesn't work in versus mode
                                                continue
                                            case 2 %FWHM
                                                plot(app.ASSE,XDataSubplots.FWHM,SubPlots.FWHM,app.Petition.Quantity(II).Style);
                                            case 3 %Max Val
                                                plot(app.ASSE,XDataSubplots.MaxVal,SubPlots.MaxVal,app.Petition.Quantity(II).Style);
                                            case 4 %Max Pos
                                                plot(app.ASSE,XDataSubplots.MaxPos,SubPlots.MaxPos,app.Petition.Quantity(II).Style);
                                            case 5 %Integral
                                                plot(app.ASSE,XDataSubplots.Integral,SubPlots.Integral,app.Petition.Quantity(II).Style);
                                        end
                                    end
                                else
                                    switch app.Petition.TypeOfSubPlot
                                        case 1 %Average vector
                                            for JJ=1:length(SubPlots.Elements)
                                                plot(app.ASSE,Ax(AREA),PPlot(JJ,:),app.Petition.Quantity(II).Style,'Color',app.LineColor(mod(JJ,app.MaxFigures),:));
                                                if(II==1) %we write the stats from the first.
                                                    if(JJ==1)
                                                        if(app.Petition.ShowStats)
                                                            TABRES=cell(app.Petition.FWHM+2*app.Petition.Peak+app.Petition.Integral+2,1+length(SubPlots.AVGX));
                                                        end
                                                        TABRES{1,1}=[app.S_Vector.Items{app.S_Vector.Value},' ',app.S_Scalar.Items{app.S_Scalar.Value}];
                                                        INSID=2;
                                                        if(app.Petition.FWHM)
                                                            TABRES{INSID,1}='FWHM'; INSID=INSID+1;
                                                        end
                                                        if(app.Petition.Peak)
                                                            TABRES{INSID,1}='Peak Value'; TABRES{INSID+1,1}='Peak Position'; INSID=INSID+2;
                                                        end
                                                        if(app.Petition.Integral)
                                                            TABRES{INSID,1}='Sum of samples';
                                                        end
                                                        TABRES{end,1}='Number of Samples';
                                                    end
                                                    TABRES{1,JJ+1}=num2str(SubPlots.AVGX(JJ));
                                                    INSID=2;
                                                    if(app.Petition.FWHM)
                                                        if(app.Petition.UseCal && ~isempty(app.Petition.CalibLinear))
                                                            TABRES{INSID,JJ+1}=num2str(SubPlots.FWHM(JJ)*abs(app.Petition.CalibLinear)); INSID=INSID+1;
                                                        else
                                                            TABRES{INSID,JJ+1}=num2str(SubPlots.FWHM(JJ)); INSID=INSID+1;
                                                        end
                                                    end
                                                    if(app.Petition.Peak)
                                                        TABRES{INSID,JJ+1}=num2str(SubPlots.MaxVal(JJ));
                                                        if(app.Petition.UseCal)
                                                            TABRES{INSID+1,JJ+1}=num2str(Ax(AREA(SubPlots.MaxPos(JJ))));
                                                        else
                                                            TABRES{INSID+1,JJ+1}=num2str(SubPlots.MaxPos(JJ));
                                                        end
                                                        
                                                        INSID=INSID+2;
                                                    end
                                                    if(app.Petition.Integral)
                                                        TABRES{INSID,JJ+1}=num2str(SubPlots.Integral(JJ));
                                                    end
                                                    TABRES{end,JJ+1}=num2str(SubPlots.Elements(JJ));
                                                end
                                            end
                                        case 2 %FWHM
                                            plot(app.ASSE,SubPlots.AVGX,SubPlots.FWHM,app.Petition.Quantity(II).Style);
                                        case 3 %Max Val
                                            plot(app.ASSE,SubPlots.AVGX,SubPlots.MaxVal,app.Petition.Quantity(II).Style);
                                        case 4 %Max Pos
                                            plot(app.ASSE,SubPlots.AVGX,SubPlots.MaxPos,app.Petition.Quantity(II).Style);
                                        case 5 %Integral
                                            plot(app.ASSE,SubPlots.AVGX,SubPlots.Integral,app.Petition.Quantity(II).Style);
                                    end
                                end
                                if(app.Petition.ShowStats)
                                    MT=CV.TextMatrixToTextColumn(TABRES);
                                    app.Permannotation.String=MT;
                                end
                        end
                    end
                    
                end
                xlim(app.ASSE,app.Petition.xLim);
                ylim(app.ASSE,app.Petition.yLim);
                
                if(app.Petition.Logbook)
                    app.logBookButtonCall(CV);
                    app.LogbookButton.BackgroundColor=app.color.logbook; app.Petition.Logbook=0;
                end
            end
        end
        
        function [SUCCESS,filename,TargetDir] = writeDataToDisk(app,TimeNow,CVObject,auxillaryData)
            CurrentTime=datevec(TimeNow);
            CurrentYearString=num2str(CurrentTime(1),'%.4d'); CurrentMonthString=num2str(CurrentTime(2),'%.2d');
            CurrentDayString=num2str(CurrentTime(3),'%.2d'); CurrentTempo1String=num2str(CurrentTime(4),'%.2d');
            CurrentTempo2String=num2str(CurrentTime(5),'%.2d'); CurrentTempo3String=num2str(floor(CurrentTime(6)),'%.2d');
            CurrentTempo4String=num2str(round((CurrentTime(6)-floor(CurrentTime(6)))*1000),'%.3d');
            CurrentTimeString=[CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString,'--',CurrentTempo1String,'-',CurrentTempo2String,'-',CurrentTempo3String,'-',CurrentTempo4String];
            filename=['OnlineMonitor-','ImageAndScalar','-',CurrentTimeString];
            TargetDir=['/u1/lcls/matlab/data/',CurrentYearString,'/',CurrentYearString,'-',CurrentMonthString,'/',CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString];
            if(~isfolder(TargetDir))
                mkdir(TargetDir);
            end
            DataStructure=CVObject.Acquisition; Data=CVObject.Recorder; %#ok<ADPROPLC> 
            try
                save([TargetDir,'/',filename],'Data','DataStructure','auxillaryData','-v7.3');
                SUCCESS=1;
            catch
                SUCCESS=0;
            end
        end
    end
    
    methods (Access = private)
        
        function void = UpdateLimits(app,direction)
            if(direction=='x')
                valuem = str2num(app.XMinEditField.Value); %#ok<ST2NM> 
                valueM = str2num(app.XMaxEditField.Value); %#ok<ST2NM> 
            else
                valuem = str2num(app.YMinEditField.Value); %#ok<ST2NM> 
                valueM = str2num(app.YMaxEditField.Value); %#ok<ST2NM> 
            end
            if(isempty(valuem)), valuem=-inf; end
            if(isempty(valueM)), valueM=+inf; end
            valuem=valuem(1); valueM=valueM(1);
            if(valuem>valueM)
                temp=valuem; valuem=valueM; valueM=temp; 
                if(direction=='x')
                    app.XMinEditField.Value = num2str(valuem); app.XMaxEditField.Value = num2str(valueM);
                else
                    app.YMinEditField.Value = num2str(valuem); app.YMaxEditField.Value = num2str(valueM);
                end
            end
            if(abs(valuem-valueM)<10^-13)
                valueM=valueM+10^-13/2; valuem=valuem-10^-13/2;
            end
            if(direction=='x')
                app.Petition.xLim=[valuem,valueM];
            else
                app.Petition.yLim=[valuem,valueM];
            end
            void=0;
        end
        
        function wasvalid = CheckExistenceMakeAxes(app)
            wasvalid=1;
            if(~isvalid(app.PGR))
                app.PGR=figure('Name',[app.(app.FIELDNAMES{(cellfun(@(x) any(x),strfind(fieldnames(app),'UIFigure')))}).Name,' *-* Display']);
                app.ASSE=axes(app.PGR,'Position',[0.13,0.1,0.7750,0.8150]);
                app.Permannotation=annotation(app.PGR,'textbox',[0 0 1 0.7],'EdgeColor','none','HorizontalAlignment','left','Interpreter','latex');
                %delete(axtoolbar(app.ASSE));
                app.ASSE.Interactions=[];app.ASSE.Toolbar=[];
                app.PGR.MenuBar='none'; app.PGR.ToolBar='none';
                app.ASSE.NextPlot='replacechildren';
                app.ASSEDefaultSize=app.ASSE.Position(3:4);
                wasvalid=0;
                app.ManageFitVisibility(0);
                app.Petition.ShowStats=app.ShowstatsCheckBox.Value;
                if(~app.Petition.ShowStats)
                    set(app.Permannotation,'String','');
                    SA=0;
                    set(app.ASSE,'Position',[0.13,0.11 + SA*0.026,0.7750,0.8150-SA*0.026])
                    set(app.Permannotation,'Position',[0,0,1,0.08+0.026*SA]);
                end
%                 else
%                     SA=size(app.TABULA_AREA.Data,1)+1;
%                     set(app.ASSE,'Position',[0.13,0.11 + SA*0.026,0.7750,0.8150-SA*0.026])
%                     set(app.Permannotation,'Position',[0,0,1,0.08+0.026*SA]);
%                 end
                app.SortOutAxes(app.Petition.TypeOfPlot);
            end
        end
        
        function moveAxisNearToMe(app)
            app.CheckExistenceMakeAxes();
            switch app.PositionDropDown.Value
                case 'Right'
                    PGRNewPosition=[app.ImageandScalarUIFigure.Position(1)+app.ImageandScalarUIFigure.Position(3)+1,app.ImageandScalarUIFigure.Position(2),app.PGR.Position(3),app.PGR.Position(4)];
                case 'Above'
                    PGRNewPosition=[app.ImageandScalarUIFigure.Position(1),app.ImageandScalarUIFigure.Position(2)+app.ImageandScalarUIFigure.Position(4)+1,app.PGR.Position(3),app.PGR.Position(4)];
                case 'Left'
                    PGRNewPosition=[app.ImageandScalarUIFigure.Position(1)-app.PGR.Position(3)-1,app.ImageandScalarUIFigure.Position(2),app.PGR.Position(3),app.PGR.Position(4)];
                case 'Below'
                    PGRNewPosition=[app.ImageandScalarUIFigure.Position(1),app.ImageandScalarUIFigure.Position(2)-app.PGR.Position(4)-1,app.PGR.Position(3),app.PGR.Position(4)];
            end
            app.PGR.Position=PGRNewPosition;
            figure(app.PGR);
        end
        
        function void = getAppColors(app)
            void=0;
            app.color.Idle=app.AxesFigureButton.BackgroundColor;
            app.color.On=[0,1,0];
            app.color.Off=[1,0,0];
            app.color.Wait=[1,1,0];
            app.color.logbook=[0.35,0.86,1];
            app.color.logbook2Steps=[0.3,0.5,1];
        end
        
        function UpdateInitialize(app,CV)
            app.LogButton.Text=['','-> Log'];
            app.LogButton.Enable='Off';
            if(~isequal(CV.Acquisition,app.DataStructure))
                app.DataStructure=CV.Acquisition;
                app.Petition.Logbook=0;
                app.LogbookButton.BackgroundColor=app.color.logbook;
                List=app.EvaluateAllLists();
                %List.SynchScalars List.SynchScalarsPositions 
                %List.Scalars List.ScalarsPositions
                %List.Vectors List.VectorsPositions
                %List.Array2D List.Array2DPositions
                
                app.ManageFilterTable(app.TABULA,List);
                %app.ManageScalarsTable(app.TABULA_AREA,List);
                app.ManageAreaTable(app.TABULA_AREA,List);
                app.ManageScalarsSelection(app.S_Scalar,List,'S_SEL');
                app.ManageArrays2DSelection(app.S_Vector,List,'I_SEL');
                app.ManageFitVisibility(1);
                app.ManageOtherInputs();
                app.ManageTypeOfPlotVisibility(1);
  
                if(isinf(app.Petition.xLim(1))), app.XMinEditField.Value=''; else, app.XMinEditField.Value=num2str(app.Petition.xLim(1)); end
                if(isinf(app.Petition.xLim(2))), app.XMaxEditField.Value=''; else, app.XMaxEditField.Value=num2str(app.Petition.xLim(2)); end
                if(isinf(app.Petition.yLim(1))), app.YMinEditField.Value=''; else, app.YMinEditField.Value=num2str(app.Petition.yLim(1)); end
                if(isinf(app.Petition.yLim(2))), app.YMaxEditField.Value=''; else, app.YMaxEditField.Value=num2str(app.Petition.yLim(2)); end
                
                if(~app.Petition.STOP)
                    app.STOP.Text='Now Running -> Stop Updating';
                    app.STOP.BackgroundColor=app.color.On;
                else
                    app.STOP.Text='Now Stopped -> Resume Updating';
                    app.STOP.BackgroundColor=app.color.Wait;
                end

                if(app.Petition.Save), app.SaveDataCheckBox.Value=1; else, app.SaveDataCheckBox.Value=0; end
                
                if(app.Petition.TwoStepLog), app.EditTextFigCheckBox.Value=1; else, app.EditTextFigCheckBox.Value=0; end
            end
            app.NeedInit=0;
        end
        
        function ManageSynchScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.SynchScalars(:).';
            Bottone.ItemsData=1:length(List.SynchScalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.SynchScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageNonTimestampedScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.NotTimeStampedScalars(:).';
            Bottone.ItemsData=1:length(List.NotTimeStampedScalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.NotTimeStampedScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function List=EvaluateAllLists(app)
           List.SynchScalars=app.DataStructure.Scalars;
           List.SynchScalarsPositions=app.DataStructure.Scalars_Positions;
           Delete=false(size(List.SynchScalarsPositions(:,1)));
           for AA=1:length(app.DataStructure.TimeStamped)
               if(~app.DataStructure.TimeStamped(AA))
                   Delete=Delete | (List.SynchScalarsPositions(:,1)==AA);
               end
           end
           List.NotTimeStampedScalars=List.SynchScalars(Delete);
           List.NotTimeStampedScalarsPositions=List.SynchScalarsPositions(Delete,:);
           List.NotTimeStampedScalars=['OFF';List.NotTimeStampedScalars];
           List.NotTimeStampedScalarsPositions=[[0,0,0];List.NotTimeStampedScalarsPositions];
           List.SynchScalars(Delete)=[];
           List.SynchScalarsPositions(Delete,:)=[];
           List.SynchScalars=['OFF';List.SynchScalars];
           List.SynchScalarsPositions=[[0,0,0];List.SynchScalarsPositions];
           List.Scalars=['OFF';app.DataStructure.Scalars];
           List.ScalarsPositions=[0,0,0;app.DataStructure.Scalars_Positions];
           List.Vectors=['OFF';app.DataStructure.Vectors];
           List.VectorsPositions=[0,0,0;app.DataStructure.Vectors_Positions];
           List.Arrays2D=['OFF';app.DataStructure.Arrays2D];
           List.Arrays2DPositions=[0,0,0;app.DataStructure.Arrays2D_Positions];
        end

        
        function ManageFilterTable(app,FilterTable,List)
            TAB_CF=FilterTable.ColumnFormat;
            TAB_CF{1}=List.SynchScalars(:).';
            TAB_CF{2}='numeric'; TAB_CF{3}='numeric'; TAB_CF{4}='logical'; TAB_CF{5}='logical';
            FilterTable.ColumnFormat=TAB_CF;
            FilterTable.ColumnEditable=[true,true,true,true,true];
            for II=app.Petition.FiltriNumber:-1:1
                IDPOS=find(strcmp(List.SynchScalars,app.Petition.FilterVarName{II}));
                if(isempty(IDPOS)) %needs to remove the filer, the variable is not recorded anymore
                    app.Petition.FiltriNumber=app.Petition.FiltriNumber-1;
                    app.Petition.Filter(II)=[];
                    app.Petition.FilterVarName(II)=[];
                    app.Petition.FilterUse(II)=[];
                else
                    IDPOS=IDPOS(1); %rebuild the filter with new position, even if it is the same one.
                    app.Petition.Filter{II}.SEL=List.SynchScalarsPositions(IDPOS,:);
                end
            end
            
            TABULA_DATA={};
            for II=1:app.Petition.FiltriNumber
                TABULA_DATA{II,1}=app.Petition.FilterVarName{II}; %#ok<AGROW>
                if(isinf(app.Petition.Filter{II}.Range(1)))
                    TABULA_DATA{II,2}=[]; %#ok<AGROW>
                else
                    if(~isnan(app.Petition.Filter{II}.Range(1)))
                        TABULA_DATA{II,2}=app.Petition.Filter{II}.Range(1); %#ok<AGROW>
                    else
                        app.Petition.Filter{II}.Range(1)=+inf;
                        TABULA_DATA{II,2}=[]; %#ok<AGROW>
                    end
                end
                if(isinf(app.Petition.Filter{II}.Range(2)))
                    TABULA_DATA{II,3}=[]; %#ok<AGROW>
                else
                    if(~isnan(app.Petition.Filter{II}.Range(2)))
                        TABULA_DATA{II,3}=app.Petition.Filter{II}.Range(2); %#ok<AGROW>
                    else
                        app.Petition.Filter{II}.Range(2)=+inf;
                        TABULA_DATA{II,3}=[]; %#ok<AGROW>
                    end
                end
                TABULA_DATA{II,4}=app.Petition.FilterUse{II}(1); %#ok<AGROW>
                TABULA_DATA{II,5}=app.Petition.FilterUse{II}(2);  %#ok<AGROW>
            end
          
            FilterTable.Data=TABULA_DATA;
            ReducedList.Positions=List.SynchScalarsPositions;
            ReducedList.Names=List.SynchScalars;
            FilterTable.UserData=ReducedList;
        end
        
        function ManageAreaTable(app,TAB,List)
            TAB.ColumnName={'Area','Last N','Style','W','First Output','Filter 1','Filter 2'};
            TAB_CF=TAB.ColumnFormat;
            %TAB_CF{3}=List.Scalars(:).';
            TAB.ColumnFormat=TAB_CF;
            TAB.ColumnEditable=[true,true,true,true,true,true,true];
            Data={length(app.Petition.Quantity),7};
            for II=1:length(app.Petition.Quantity)
                Data{II,1}='';
                Data{II,2}=num2str(app.Petition.Quantity(II).LastN);
                Data{II,5}=app.Petition.Quantity(II).BasePV{1};
                Data{II,3}=app.Petition.Quantity(II).Style;
                Data{II,4}=num2str(app.Petition.Quantity(II).Weight);
                Data{II,6}=app.Petition.Quantity(II).Filter(1);
                Data{II,7}=app.Petition.Quantity(II).Filter(2);
            end
            
            TAB.Data=Data;
            
            if(app.DataStructure.Synchronous)
                CE=TAB.ColumnEditable; CE(1)=true; CE(2)=true;
                TAB.ColumnEditable=CE;
            else
                CE=TAB.ColumnEditable; CE(1)=false; CE(2)=false;
                TAB.ColumnEditable=CE;
                TAB.Data(:,6:7)={false};
            end
            ReducedList.Positions=List.ScalarsPositions;
            ReducedList.Names=List.Scalars;
            TAB.UserData=ReducedList;
        end
        
        function ManageScalarsTable(app,TAB,List)
            TAB.ColumnName={'Filter 1','Filter 2','Scalar Variable Name','Style','Expression','LastN'};
            TAB_CF=TAB.ColumnFormat;
            TAB_CF{3}=List.Scalars(:).';
            TAB.ColumnFormat=TAB_CF;
            TAB.ColumnEditable=[true,true,true,true,true,true];
            InitData{1,1}=true;InitData{1,2}=true;InitData{1,3}=List.SynchScalars{1};InitData{1,4}='.b';InitData{1,5}='';InitData{1,6}=inf;
            TAB.Data=InitData;
            ins=0;
%             List.Scalars=['OFF';app.DataStructure.Scalars];
%             List.ScalarsPositions=[0,0,0;app.DataStructure.Scalars_Positions];
            if(~isempty(app.Petition.Quantity))
                FoundNone=1;
                for II=1:length(app.Petition.Quantity)
                    %Check if variable still exists...
                    ID=find(strcmp(app.Petition.Quantity(II).Name,List.Scalars));
                    if(isempty(ID))
                        app.Petition.Quantity(II)=[];
                    else
                        FoundNone=0; ins=ins+1;
                        NewQuantity(ins).Filter(1)=app.Petition.Quantity(II).Filter(1); %#ok<AGROW>
                        NewQuantity(ins).Filter(2)=app.Petition.Quantity(II).Filter(2); %#ok<AGROW>
                        NewQuantity(ins).Name=app.Petition.Quantity(II).Name; %#ok<AGROW>
                        NewQuantity(ins).Y_SEL=List.ScalarsPositions(ID,:); %#ok<AGROW>
                        NewQuantity(ins).Stilnovo=app.Petition.Quantity(II).Stilnovo; %#ok<AGROW>
                        TAB.Data{ins,1}=logical(app.Petition.Quantity(II).Filter(1)); 
                        TAB.Data{ins,2}=logical(app.Petition.Quantity(II).Filter(2));
                        TAB.Data{ins,3}=app.Petition.Quantity(II).Name; TAB.Data{ins,4}=app.Petition.Quantity(II).Stilnovo;
                        TAB.Data{ins,5}=''; NewQuantity(ins).Function=''; %#ok<AGROW>
                        NewQuantity(ins).LastN=app.Petition.Quantity(II).LastN; %#ok<AGROW>
                        TAB.Data{ins,6}=app.Petition.Quantity(II).LastN;
                    end
                end
                if(FoundNone)
                    TAB.Data{1,1}=true;TAB.Data{1,2}=true;TAB.Data{1,4}='.b';TAB.Data{1,5}='';TAB.Data{1,6}=inf;
                    TAB.Data{1,3}=List.SynchScalars{1}; app.Petition.Quantity=[];
                else
                    app.Petition.Quantity=NewQuantity;
                end
            else
                TAB.Data{1,1}=true;TAB.Data{1,2}=true;TAB.Data{1,4}='.b';TAB.Data{1,5}='';TAB.Data{1,6}=inf; 
                TAB.Data{1,3}=List.SynchScalars{1}; app.Petition.Quantity=[];
            end
            
            if(app.DataStructure.Synchronous)
                CE=TAB.ColumnEditable; CE(1)=true; CE(2)=true;
                TAB.ColumnEditable=CE;
            else
                CE=TAB.ColumnEditable; CE(1)=false; CE(2)=false;
                TAB.ColumnEditable=CE;
                TAB.Data(:,1:2)={false};
            end
            ReducedList.Positions=List.ScalarsPositions;
            ReducedList.Names=List.Scalars;
            TAB.UserData=ReducedList;
        end
        
        function ManageScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Scalars(:).';
            Bottone.ItemsData=1:length(List.Scalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            BottoneUD.Pos=List.ScalarsPositions;
            BottoneUD.PetitionName=PetitionName;
            Bottone.UserData=BottoneUD;
            app.ManageListChange(Bottone);
        end

        function ManageVectorsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Vectors(:).';
            Bottone.ItemsData=1:length(List.Vectors);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            BottoneUD.Pos=List.VectorsPositions;
            BottoneUD.PetitionName=PetitionName;
            Bottone.UserData=BottoneUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageArrays2DSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Arrays2D(:).';
            Bottone.ItemsData=1:length(List.Arrays2D);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            BottoneUD.Pos=List.Arrays2DPositions;
            BottoneUD.PetitionName=PetitionName;
            Bottone.UserData=BottoneUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageListChange(app,Bottone)
            app.Petition.(Bottone.UserData.PetitionName) = Bottone.UserData.Pos(Bottone.Value,:);
        end
        
        function UpdateTableSelection(app)
            ins=0;
            for II=1:size(app.TABULA_AREA.Data,1)
                if(isempty(app.TABULA_AREA.Data{II,1}))
                    QTY.Area=[];
                else
                    QTY.Area=str2num(app.TABULA_AREA.Data{II,1}); %#ok<ST2NM> 
                end
                QTY.Style=app.TABULA_AREA.Data{II,3};
                QTY.LastN=str2double(app.TABULA_AREA.Data{II,2});
                QTY.Weight=str2double(app.TABULA_AREA.Data{II,4});
                QTY.BasePV{1}=app.TABULA_AREA.Data{II,5};
                for AK=1:9
                    QTY.BasePV{AK+1}=QTY.BasePV{1};
                    QTY.BasePV{AK+1}(end)=num2str(AK);
                end
                QTY.Filter(1)=app.TABULA_AREA.Data{II,6};
                QTY.Filter(2)=app.TABULA_AREA.Data{II,7};
                ins=ins+1;
                app.Petition.Quantity(ins)=QTY;
            end
            app.SortOutAxes(app.Petition.TypeOfPlot);
        end
        
        function UpdateFilterSelection(app)
            app.Petition.Filter={};app.Petition.FilterUse={}; app.Petition.FilterVarName={}; app.Petition.FiltriNumber=0;
            for II=1:size(app.TABULA.Data,1)
                if((~isempty(app.TABULA.Data{II,2}) && ~isnan(app.TABULA.Data{II,2})) || (~isempty(app.TABULA.Data{II,3}) && ~isnan(app.TABULA.Data{II,3}))) %there is something
                    VAL=app.DataStructure.Scalars_Positions(strcmp(app.TABULA.Data{II,1},app.DataStructure.Scalars),:);
                    if(isempty(VAL))
                        continue
                    end
                    if(~isempty(app.TABULA.Data{II,2}) && ~isnan(app.TABULA.Data{II,2}))
                        MIN=app.TABULA.Data{II,2};
                    else
                        MIN=-inf;
                    end
                    if(~isempty(app.TABULA.Data{II,3}) && ~isnan(app.TABULA.Data{II,3}))
                        MAX=app.TABULA.Data{II,3};
                    else
                        MAX=+inf;
                    end
                    app.Petition.Filter{end+1}.SEL=VAL; app.Petition.Filter{end}.Range=[MIN,MAX];
                    app.Petition.FiltriNumber=app.Petition.FiltriNumber+1;
                    app.Petition.FilterUse{end+1}(1)=app.TABULA.Data{II,4}; app.Petition.FilterUse{end}(2)=app.TABULA.Data{II,5};
                    app.Petition.FilterVarName{end+1}=app.TABULA.Data{II,1};
                end
            end
        end
        
        function logBookButtonCall(app,CVObject)
            TimeNow=now;
            Author=app.AuthorEditField.Value; Title=app.TitleEditField.Value; Comment=app.commentEditField.Value;
            app.Petition.Author=Author; app.Petition.Title=Title; app.Petition.Comment=Comment;
            if(app.SaveDataCheckBox.Value)
                auxillaryData = additionalSavingInformation(app);
                [SUCCESS,filename,~]=app.writeDataToDisk(TimeNow,CVObject,auxillaryData);
                if(SUCCESS)
                    app.SaveLabel.Text=['Last: OK ,',datestr(TimeNow,'yyyy, mmm dd, HH:MM')];
                else
                    app.SaveLabel='Saving Failed';
                    filename='File saving failed.';
                end
            else
                filename='No file was saved';
            end

            if(app.EditTextFigCheckBox.Value) %Two Steps logbook. Make a copy of the figure and open edit boxes.
                NewFigure=figure;
                NewFigure.Position=app.PGR.Position;
                app.DuplicateFigure(app.PGR,NewFigure);
                if(isfield(CVObject.Acquisition,'filename'))
                    annotation(NewFigure,'textbox',[0 .92 1 .08],'string',CVObject.Acquisition.filename,'EdgeColor','none','HorizontalAlignment','center');
                else
                    if(app.SaveDataCheckBox.Value)
                        annotation(NewFigure,'textbox',[0 .92 1 .08],'string',filename,'EdgeColor','none','HorizontalAlignment','center');
                    else
                        annotation(NewFigure,'textbox',[0 .92 1 .08],'string','Image logbook only, data not saved','EdgeColor','none','HorizontalAlignment','center');
                    end
                end
                app.LogButton.Text=[num2str(NewFigure.Number),'-> Log'];
                app.LogButton.Enable='On';
                app.LogButton.UserData=NewFigure;
                app.EditTextFigCheckBox.UserData=filename;
            else
                app.SendImageToLogbook(app.PGR, Author, Title, Comment, filename);
            end           
        end
        
        function auxillaryData = additionalSavingInformation(app) %#ok<MANU> 
            auxillaryData=app.Petition;
        end
        
        function DuplicateFigure(app,OriginalFigure,NewFigure) %#ok<INUSL> 
            children_handler_original = get(OriginalFigure, 'children');
            allchildren = allchild(OriginalFigure);
            if isempty(children_handler_original)
                return
            else
                new_handler_copy = copyobj(children_handler_original,NewFigure);
                for KK=1:length(new_handler_copy)
                    if(strcmpi(new_handler_copy(KK).Type,'axes'))
                        delete(axtoolbar(new_handler_copy(KK)));
                    end
                end
                for AA=1:length(allchildren)
                    if(strcmpi(allchildren(AA).Type,'annotationpane'))
                        copyobj(allchildren(AA),NewFigure);
                    end
                end
            end
        end
        
        function DeepDuplicateFigure(app,OriginalFigure,NewFigure)
            children_handler_original = get(OriginalFigure, 'children');
            if isempty(children_handler_original)
                return
            else
                new_handler_copy = copyobj(children_handler_original,NewFigure);
                for KK=1:length(children_handler_original)
                    app.DuplicateFigure(children_handler_original(KK),new_handler_copy(KK));
                end
            end
        end
        
        function ManageFitVisibility(app,CopyParameters)
%             switch(app.SubtypeofPlotDropDown.Value)
%                 case 'No Fit'
%                     app.P1.Visible='off'; app.P2.Visible='off';
%                     app.Petition.TypeOfFit=1;
%                 case 'Polynomial'
%                     app.P1.Visible='on'; app.P2.Visible='off';
%                     app.Petition.TypeOfFit=2;
%                 case 'Gaussian'
%                     app.P1.Visible='off'; app.P2.Visible='on';
%                     app.Petition.TypeOfFit=3;
%             end
%             if(CopyParameters)
%                 if(isnan(app.Petition.A)), app.AEditField.Value=''; else, app.AEditField.Value=num2str(app.Petition.A); end
%                 if(isnan(app.Petition.B)), app.BEditField.Value=''; else, app.BEditField.Value=num2str(app.Petition.B); end
%                 if(isnan(app.Petition.C)), app.CEditField.Value=''; else, app.CEditField.Value=num2str(app.Petition.C); end
%                 if(isnan(app.Petition.x0)), app.x0EditField.Value=''; else, app.x0EditField.Value=num2str(app.Petition.x0); end
%                 if(isnan(app.Petition.y0)), app.y0EditField.Value=''; else, app.y0EditField.Value=num2str(app.Petition.y0); end
%                 if(isnan(app.Petition.PolyOrder)), app.OrderPolyFitEdit.Value=''; else, app.OrderPolyFitEdit.Value=num2str(app.Petition.PolyOrder); end
%             end
        end
        
        function ManageTypeOfPlotVisibility(app, CopyParameters)
            switch(app.TypeofPlotDropDown.Value)
                case 'Vector Only'
                    app.BinsXEditField.Visible='off'; app.BinsXEditFieldLabel.Visible='off';
                    app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
                    app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
                    app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
                    app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
                case 'L - Plot'
                    app.BinsXEditField.Visible='on'; app.BinsXEditFieldLabel.Visible='on';
                    app.BinsYEditField.Visible='on'; app.BinsYEditFieldLabel.Visible='on';
                    app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
                    app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
                    app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
                case 'Partition on Scalar'
                    app.BinsXEditField.Visible='off'; app.BinsXEditFieldLabel.Visible='off';
                    app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
                    app.PartitionCenterEditField.Visible='on'; app.PartitionCenterEditFieldLabel.Visible='on';
                    app.PartitionWidthEditField.Visible='on'; app.PartitionWidthEditFieldLabel.Visible='on';
                    app.ShowonlyEditField.Visible='on'; app.ShowonlyEditFieldLabel.Visible='on';
            end
            if(CopyParameters)
                if(isnan(app.Petition.PartitionPos(1))), app.PartitionCenterEditField.Value=''; 
                else
                    string='[';
                    for II=1:length(app.Petition.PartitionPos)
                        string=[string,num2str(app.Petition.PartitionPos(II))]; %#ok<AGROW> 
                        if(II~=length(app.Petition.PartitionPos))
                            string=[string,',']; %#ok<AGROW> 
                        else
                            string=[string,']']; %#ok<AGROW> 
                        end
                    end
                    app.PartitionCenterEditField.Value=string; 
                end
                if(isnan(app.Petition.PartitionWidth(1))), app.PartitionWidthEditField.Value=''; 
                else
                    string='[';
                    for II=1:length(app.Petition.PartitionWidth)
                        string=[string,num2str(app.Petition.PartitionWidth(II))]; %#ok<AGROW> 
                        if(II~=length(app.Petition.PartitionWidth))
                            string=[string,',']; %#ok<AGROW> 
                        else
                            string=[string,']']; %#ok<AGROW> 
                        end
                    end
                    app.PartitionWidthEditField.Value=string; 
                end
                if(isnan(app.Petition.ShowOnly(1))), app.ShowonlyEditField.Value=''; 
                else
                    string='[';
                    for II=1:length(app.Petition.ShowOnly)
                        string=[string,num2str(app.Petition.ShowOnly(II))]; %#ok<AGROW> 
                        if(II~=length(app.Petition.ShowOnly))
                            string=[string,',']; %#ok<AGROW> 
                        else
                            string=[string,']']; %#ok<AGROW> 
                        end
                    end
                    app.ShowonlyEditField.Value=string; 
                end
                if(isnan(app.Petition.binsx)), app.BinsXEditField.Value=''; else, app.BinsXEditField.Value=num2str(app.Petition.binsx); end
                if(isnan(app.Petition.binsy)), app.BinsYEditField.Value=''; else, app.BinsYEditField.Value=num2str(app.Petition.binsy); end
            end
        end
        
        function ManageOtherInputs(app)
            if(isnan(app.Petition.binsx)), app.BinsXEditField.Value=''; else, app.BinsXEditField.Value=num2str(app.Petition.binsx); end
            if(isnan(app.Petition.binsy)), app.BinsYEditField.Value=''; else, app.BinsYEditField.Value=num2str(app.Petition.binsy); end
            app.Petition.AutoBinPartition=0;
            if(isnan(app.Petition.AutoBinNumber)), app.AutoPartition.Value='7';app.Petition.AutoBinNumber=1; else, app.AutoPartition.Value=num2str(app.Petition.AutoBinNumber); end
            app.ShowstatsCheckBox.Value=app.Petition.ShowStats;
            app.FWHMCheckBox.Value=app.Petition.FWHM;
            app.PeakandpositionCheckBox.Value=app.Petition.Peak;
            app.IntegralCheckBox.Value=app.Petition.Integral;
            app.vsmodeCheckBox.Value=app.Petition.vsMode;
            app.UsecalibrationCheckBox.Value=app.Petition.UseCal;
            app.SubtypeofPlotDropDown.Value=app.SubtypeofPlotDropDown.Items(app.Petition.TypeOfSubPlot);
            app.PublishstatsCheckBox.Value=app.Petition.PublishStats;
            app.PIDDelayEditField.Value=num2str(app.Petition.PidDelay);
            app.flipLR.Value=app.Petition.Rotation(3);
            app.flipUD.Value=app.Petition.Rotation(2);
            app.Rot.Value=app.Petition.Rotation(1);
            app.LastNEditField.Value=num2str(app.Petition.LastN);
            app.ColorCheat.Data=app.Petition.pchipTable;
            if(isempty(app.Petition.CalibCenter)), app.CalibCenter.Value='';else, app.CalibCenter.Value=num2str(app.Petition.CalibCenter); end
            if(isempty(app.Petition.CalibLinear)), app.CalibLinear.Value='';else, app.CalibLinear.Value=num2str(app.Petition.CalibLinear); end
        end
        
        function SortOutAxes(app,Mode)
            switch Mode
                case 1 %Vector Only
                    if(~app.Petition.ShowStats)
                        set(app.Permannotation,'String','');
                        SA=0;
                        set(app.ASSE,'Position',[0.13,0.11 + SA*0.026,0.7750,0.8150-SA*0.026]);
                        set(app.Permannotation,'Position',[0,0,1,0.08+0.026*SA]);
                    else
                        SA=size(app.TABULA_AREA.Data,1)+1;
                        set(app.ASSE,'Position',[0.13,0.11 + SA*0.026,0.7750,0.8150-SA*0.026]);
                        set(app.Permannotation,'Position',[0,0,1,0.08+0.026*SA]);
                    end
                    try  delete(app.ASSEM), end %#ok<TRYNC> 
                case 2 %L-Plot (2 Axis )
                    try
                        if(~isvalid(app.ASSEM))
                            app.ASSEM=axes(app.PGR,'Position',[0.09,0.1,0.12,0.8150]);
                        end
                    catch IF %#ok<NASGU> 
                        app.ASSEM=axes(app.PGR,'Position',[0.09,0.1,0.12,0.8150]);
                    end
                    app.ASSEM.Interactions=[];app.ASSEM.Toolbar=[];
                    app.ASSEM.NextPlot='replacechildren';
                    app.ASSEM.YDir='normal';
                    if(~app.Petition.ShowStats)
                        set(app.Permannotation,'String','');
                        SA=0;
                        set(app.ASSE,'Position',[0.13+0.14,0.11 + SA*0.026,0.7750-0.14,0.8150-SA*0.026]);
                        set(app.ASSEM,'Position',[0.09,0.11 + SA*0.026,0.12,0.8150-SA*0.026]);
                        set(app.Permannotation,'Position',[0,0,1,0.08+0.026*SA]);
                    else
                        SA=size(app.TABULA_AREA.Data,1)+1;
                        set(app.ASSE,'Position',[0.13+0.14,0.11 + SA*0.026,0.7750-0.14,0.8150-SA*0.026]);
                        set(app.ASSEM,'Position',[0.09,0.11 + SA*0.026,0.12,0.8150-SA*0.026]);
                        set(app.Permannotation,'Position',[0,0,1,0.08+0.026*SA]);
                    end
                case 3 %Partition Plot 1 axis again
                    if(~app.Petition.ShowStats || app.Petition.vsMode)
                        set(app.Permannotation,'String','');
                        SA=0;
                        set(app.ASSE,'Position',[0.13,0.11 + SA*0.026,0.7750,0.8150-SA*0.026]);
                        set(app.Permannotation,'Position',[0,0,1,0.08+0.026*SA]);
                    else
                        switch app.Petition.TypeOfSubPlot
                            case 1 %no versus mode, stats for different bins (bins on x, quantities on Y, for only the first)
                                SA=app.Petition.FWHM + 2*app.Petition.Peak+ app.Petition.Integral+2;
                            case 2 
                                SA=0;size(app.TABULA_AREA.Data,1)+1;
                            case 3 
                                SA=0;size(app.TABULA_AREA.Data,1)+1;
                            case 4
                                SA=0;size(app.TABULA_AREA.Data,1)+1;
                            case 5
                                SA=0;size(app.TABULA_AREA.Data,1)+1;
                        end
                        set(app.ASSE,'Position',[0.1295,0.11 + SA*0.026,0.7750,0.8150-SA*0.026]);
                        set(app.Permannotation,'Position',[0,0,1,0.08+0.026*SA]);
                    end
                    try  delete(app.ASSEM), end %#ok<TRYNC> 
            end
        end
        
        function DisplayImage(app,ASSE,ASSEY,Matrice,Principal_AX)
            if(~isempty(app.Petition.pchip))
               Matrice=(Matrice+min(min(Matrice)));
               Matrice=Matrice/max(max(Matrice));
               Matrice=ppval(app.Petition.pchip,Matrice);
            end
            Arrangement=2^app.Petition.Rotation(1)*3^app.Petition.Rotation(2)*5^app.Petition.Rotation(3); %Rot1 90 degrees, Rot2 Up/Down, Rot1 Left Right. 
            switch Arrangement
                case 1
                    imagesc(ASSEY,ASSE,Matrice,'parent',Principal_AX);
                case 2
                    imagesc(ASSEY,ASSE,Matrice(:,end:-1:1),'parent',Principal_AX);
                case 3
                    imagesc(ASSEY,ASSE,Matrice(end:-1:1,:),'parent',Principal_AX);
                case 5
                    imagesc(ASSE,ASSEY,Matrice(:,:).','parent',Principal_AX);
                case 6
                    imagesc(ASSEY,ASSE,Matrice(end:-1:1,end:-1:1),'parent',Principal_AX);
                case 10
                    imagesc(ASSE,ASSEY,Matrice(:,end:-1:1).','parent',Principal_AX);
                case 15
                    imagesc(ASSE,ASSEY,Matrice(end:-1:1,:).','parent',Principal_AX);
                case 30
                    imagesc(ASSE,ASSEY,Matrice(end:-1:1,end:-1:1).','parent',Principal_AX);
            end
            set(Principal_AX,'Ydir','normal');
%             XL=xlim(Principal_AX); YL=ylim(Principal_AX);
%             if(~petition.b_autoY)
%                 if(~isnan(petition.lim_y1)), YL(1)=petition.lim_y1; end
%                 if(~isnan(petition.lim_y2)), YL(2)=petition.lim_y2; end
%             end
%             if(~petition.b_autoX)
%                 if(~isnan(petition.lim_x1)), XL(1)=petition.lim_x1; end
%                 if(~isnan(petition.lim_x2)), XL(2)=petition.lim_x2; end
%             end
        end
        
        function SendImageToLogbook(app, FigureHandle, Author, Title, Comment, Filename)
            Comment=[Comment,char(13),'Filename: ', Filename];
            try
                util_printLog(FigureHandle,'title',Title,'author',Author,'text',Comment);
                disp('Image sent successfully to logbook');
            catch
                disp('Failed to send image to logbook');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FIELDNAMES=fieldnames(app);
            app.PGR=figure('Name',[app.(app.FIELDNAMES{(cellfun(@(x) any(x),strfind(fieldnames(app),'UIFigure')))}).Name,' *-* Display']);
            app.ASSE=axes(app.PGR,'Position',[0.13,0.1,0.7750,0.8150]);
            app.Permannotation=annotation(app.PGR,'textbox',[0 0 1 0.7],'EdgeColor','none','HorizontalAlignment','left','Interpreter','latex');
            app.ASSE.Interactions=[];app.ASSE.Toolbar=[];
            app.PGR.MenuBar='none';app.PGR.ToolBar='none'; app.ASSE.NextPlot='replacechildren';
            app.ASSEDefaultSize=app.PGR.Position(3:4);
            app.moveAxisNearToMe();
            app.Petition.xLim=[-inf,inf];
            app.Petition.yLim=[-inf,inf];
            app.getAppColors();
            app.LogbookButton.BackgroundColor=app.color.logbook;
            app.Petition.STOP=0;
            app.Petition.UseAbsoluteTime = 1;
            app.Petition.Save = 1;
            app.Petition.TwoStepLog= 0;
            app.Petition.FiltriNumber=0;
            app.Petition.S_SEL=[0,0,0];
            app.Petition.I_SEL=[0,0,0];
            app.Petition.I_SEL=[0,0,0];
            app.Petition.LastSeconds=[];
            app.Petition.Pid_Delay=0;
            app.Petition.A=NaN;
            app.Petition.B=NaN;
            app.Petition.C=2;
            app.Petition.x0=NaN;
            app.Petition.y0=NaN;
            app.Petition.PolyOrder=2;
            app.Petition.binsx=25;
            app.Petition.binsy=25;
            app.Petition.AutoBinPartition=1;
            app.Petition.AutoBinNumber=7;
            app.Petition.PartitionPos=NaN;
            app.Petition.PartitionWidth=NaN;
            app.Petition.ShowOnly=NaN;
            app.Petition.TypeOfPlot=1;
            app.Petition.TypeOfSubPlot=1;
            app.Petition.TypeOfFit=1;
            app.Petition.Function='';
            app.Petition.ShowStats=0;
            app.Petition.vsMode=0;
            app.Petition.FWHM=0;
            app.Petition.Peak=0;
            app.Petition.PublishStats=0;
            app.Petition.Integral=0;
            app.Petition.CalibCenter=[];
            app.Petition.CalibLinear=[];
            app.Petition.CalibCenter2=[];
            app.Petition.CalibLinear2=[];
            app.Petition.Quantity(1).LastN=NaN;
            app.Petition.Quantity(1).Area=[];
            app.Petition.Quantity(1).Style='b-';
            app.Petition.Quantity(1).Weight=1;
            app.Petition.Quantity(1).BasePV=app.DefaultNewLine{5};
            app.Petition.Quantity(1).Filter=[true,true];
            app.Petition.UseCal=0;
            app.Petition.PidDelay=0;
            app.Petition.NOF=1;
            app.Petition.pchip='';
            app.Petition.LastN=1;
            app.Petition.Rotation=[0,0,0];
            app.Petition.pchipTable{1,1}=[]; app.Petition.pchipTable{1,2}=[]; app.Petition.pchipTable{1,3}=[]; app.Petition.pchipTable{1,4}=[];
            app.Petition.pchipTable{2,1}=[]; app.Petition.pchipTable{2,2}=[]; app.Petition.pchipTable{2,3}=[]; app.Petition.pchipTable{2,4}=[];
            
        end

        % Value changed function: XMinEditField
        function XMinEditFieldValueChanged(app, event)
            app.UpdateLimits('x');
        end

        % Value changed function: XMaxEditField
        function XMaxEditFieldValueChanged(app, event)
            app.UpdateLimits('x');
        end

        % Value changed function: YMinEditField
        function YMinEditFieldValueChanged(app, event)
            app.UpdateLimits('y');
        end

        % Value changed function: YMaxEditField
        function YMaxEditFieldValueChanged(app, event)
            app.UpdateLimits('y');
        end

        % Button pushed function: AxesFigureButton
        function AxesFigureButtonPushed(app, event)
            app.CheckExistenceMakeAxes();
        end

        % Button pushed function: MoveAxesNearButton
        function MoveAxesNearButtonPushed(app, event)
            app.moveAxisNearToMe();
        end

        % Button pushed function: DefaultAxesSizeButton
        function DefaultAxesSizeButtonPushed(app, event)
            app.PGR.Position(3:4)=app.ASSEDefaultSize;
        end

        % Button pushed function: LogbookButton
        function LogbookButtonPushed(app, event)
            app.LogbookButton.BackgroundColor=app.color.On;
            app.Petition.Logbook=1;
        end

        % Button pushed function: STOP
        function STOPButtonPushed(app, event)
            if(app.Petition.STOP)
                app.Petition.STOP=false; app.STOP.Text='Now Running -> Stop Updating'; app.STOP.BackgroundColor=app.color.On; drawnow
            else
                app.Petition.STOP=true; app.STOP.Text='Now Stopped -> Resume Updating'; app.STOP.BackgroundColor=app.color.Wait; drawnow
            end
        end

        % Callback function
        function UsetimeCheckBoxValueChanged(app, event)
            app.Petition.UseAbsoluteTime = app.UsetimeCheckBox.Value;
        end

        % Value changed function: SaveDataCheckBox
        function SaveDataCheckBoxValueChanged(app, event)
            app.Petition.Save = app.SaveDataCheckBox.Value;
        end

        % Value changed function: EditTextFigCheckBox
        function EditTextFigCheckBoxValueChanged(app, event)
            app.Petition.TwoStepLog= app.EditTextFigCheckBox.Value;
        end

        % Value changed function: S_Scalar
        function S_ScalarValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Value changed function: S_Vector
        function S_VectorValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Callback function
        function S_ImageValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Close request function: ImageandScalarUIFigure
        function ImageandScalarUIFigureCloseRequest(app, event)
            try delete(app.PGR); end %#ok<TRYNC> 
            delete(app);
        end

        % Callback function
        function TabellaYCellEdit(app, event)
            %indices = event.Indices; newData = event.NewData;
            ID=find(strcmp(event.NewData,app.DataStructure.Scalars),1, 'first');
            if(~isempty(ID))
                if(~app.DataStructure.TimeStamped(app.DataStructure.Scalars_Positions(ID,1))) %not timestamped, defaults as non scatter plot like
                    try app.TABULA_AREA.Data{event.Indices(1),4}=regexprep(regexprep(app.TABULA_AREA.Data{event.Indices(1),4},'\.','-'),'b','r'); end %#ok<TRYNC> 
                end
            end
            app.UpdateTableSelection();
        end

        % Button pushed function: AreaAddLine
        function VarAddLineButtonPushed(app, event)
            NL=app.NewLine;
            BS=app.NewLine{5};
            NL{5}=BS{1};
            app.TABULA_AREA.Data(end+1,:)=NL;
            app.UpdateTableSelection();
        end

        % Button pushed function: areaRemoveLine
        function VarRemoveLineButtonPushed(app, event)
            if(~isempty(app.areaRemoveLine.UserData))
                toBeRemoved=unique(app.areaRemoveLine.UserData(:,1),'sorted');
                for II=size(app.TABULA_AREA.Data,1):(-1):1
                    if(any(toBeRemoved==II))
                        app.TABULA_AREA.Data(II,:)=[];
                    end
                end
            end
            if(size(app.TABULA_AREA.Data,1)==0)
                app.TABULA_AREA.Data=app.DefaultNewLine;
            end
            app.UpdateTableSelection();
        end

        % Callback function
        function TabellaYCellSelection(app, event)
            indices = event.Indices;
            app.VarRemoveLine.UserData=indices;
        end

        % Button pushed function: FilterAddLine
        function FilterAddLineButtonPushed(app, event)
            NewLine{1,1}=app.TABULA.ColumnFormat{1}{1};
            NewLine{1,2}=[];NewLine{1,3}=[];NewLine{1,4}=true;NewLine{1,5}=true;
            app.TABULA.Data(end+1,:)=NewLine;
        end

        % Button pushed function: FilterRemoveLine
        function FilterRemoveLineButtonPushed(app, event)
            app.FilterRemoveLine.UserData
            if(~isempty(app.FilterRemoveLine.UserData))
                toBeRemoved=unique(app.FilterRemoveLine.UserData(:,1),'sorted');
                for II=size(app.TABULA.Data,1):(-1):1
                    if(any(toBeRemoved==II))
                        app.TABULA.Data(II,:)=[];
                    end
                end
            end
            app.UpdateFilterSelection();
        end

        % Cell selection callback: TABULA
        function TABULACellSelection(app, event)
            indices = event.Indices;
            app.FilterRemoveLine.UserData=indices;
        end

        % Cell edit callback: TABULA
        function TABULACellEdit(app, event)
            app.UpdateFilterSelection();
        end

        % Callback function
        function secondssamplesEditFieldValueChanged(app, event)
            value=app.secondssamplesEditField.Value;
            VAL=str2double(value);
            if(VAL<=0 || isnan(VAL))
                app.secondssamplesEditField.Value='';
                app.Petition.LastSeconds=[];
            else
                app.Petition.LastSeconds=VAL;
            end
        end

        % Callback function
        function TypeofFitDropDownValueChanged(app, event)
            app.ManageFitVisibility(0);
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            app.Petition.AutoBinPartition=1;
            Value=str2num(app.AutoPartition.Value);
            Value=round(Value(1));
            if(Value<1)
                Value=1;
                app.AutoPartition.Value='1';
            end
            app.Petition.AutoBinNumber=Value;
        end

        % Value changed function: ExpressionEditField
        function ExpressionEditFieldValueChanged(app, event)
            value = app.ExpressionEditField.Value;
            if(isempty(value)), app.Petition.Function=''; else 
                app.Petition.Function=eval(['@(x) ',vectorize(value)]);
            end
        end

        % Value changed function: ShowstatsCheckBox
        function ShowstatsCheckBoxValueChanged(app, event)
            app.Petition.ShowStats=app.ShowstatsCheckBox.Value;
            app.SortOutAxes(app.Petition.TypeOfPlot);
        end

        % Value changed function: TypeofPlotDropDown
        function TypeofPlotDropDownValueChanged(app, event)
            switch(app.TypeofPlotDropDown.Value)
                case 'Vector Only'
                    app.Petition.TypeOfPlot=1;
                    for II=1:size(app.TABULA_AREA.Data,1)
                        if(app.TABULA_AREA.Data{II,2}>7101571)
                            app.TABULA_AREA.Data{II,2}=1;
                        end
                    end
                    app.CheckExistenceMakeAxes();
                    app.SortOutAxes(app.Petition.TypeOfPlot);
                    app.Petition.NOF=1;
                case 'L - Plot'
                    app.Petition.TypeOfPlot=2;
                    for II=1:size(app.TABULA_AREA.Data,1)
                        if(str2num(app.TABULA_AREA.Data{II,2})==1)
                            app.TABULA_AREA.Data{II,2}='inf';
                        end
                    end
                    app.CheckExistenceMakeAxes();
                    app.SortOutAxes(app.Petition.TypeOfPlot);
                case 'Partition on Scalar'
                    app.Petition.TypeOfPlot=3;
                    for II=1:size(app.TABULA_AREA.Data,1)
                        if(str2num(app.TABULA_AREA.Data{II,2})==1)
                            app.TABULA_AREA.Data{II,2}='inf';
                        end
                    end
                    app.CheckExistenceMakeAxes();
                    app.SortOutAxes(app.Petition.TypeOfPlot);
                case 'Partition Unique Vals'
                    app.Petition.TypeOfPlot=4;
                    for II=1:size(app.TABULA_AREA.Data,1)
                        if(str2num(app.TABULA_AREA.Data{II,2})==1)
                            app.TABULA_AREA.Data{II,2}='inf';
                        end
                    end
                    app.CheckExistenceMakeAxes();
                    app.SortOutAxes(app.Petition.TypeOfPlot);
            end
            app.UpdateTableSelection();
            app.ManageTypeOfPlotVisibility(0);
        end

        % Value changed function: PartitionCenterEditField
        function PartitionCenterEditFieldValueChanged(app, event)
            strvalue = app.PartitionCenterEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.PartitionPos=NaN;
            else
                app.Petition.PartitionPos=numericlist;
            end
        end

        % Value changed function: PartitionWidthEditField
        function PartitionWidthEditFieldValueChanged(app, event)
            strvalue = app.PartitionWidthEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.PartitionWidth=NaN;
            else
                app.Petition.PartitionWidth=numericlist;
            end
        end

        % Value changed function: ShowonlyEditField
        function ShowonlyEditFieldValueChanged(app, event)
            strvalue = app.ShowonlyEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.ShowOnly=NaN;
            else
                numericlist=numericlist(~isnan(numericlist) && ~isinf(numericlist));
                numericlist=round(numericlist);
                numericlist=numericlist(numericlist>1);        
                app.Petition.ShowOnly=numericlist;
            end
        end

        % Button pushed function: ChangePV
        function ChangePVButtonPushed(app, event)
            for II=1:size(app.TABULA_AREA.Data)
                OldValue=str2double(app.TABULA_AREA.Data{II,4}(end-2:end));
                NewValue=OldValue+10;
                if(NewValue==100), NewValue=0; end
                app.TABULA_AREA.Data{II,4}=[app.TABULA_AREA.Data{II,4}(1:end-3),num2str(NewValue)];
            end
        end

        % Value changed function: SubtypeofPlotDropDown
        function SubtypeofPlotDropDownValueChanged(app, event)
            switch(app.SubtypeofPlotDropDown.Value)
                case 'None'
                    app.Petition.TypeOfSubPlot=1;
                case 'FWHM'
                    app.Petition.TypeOfSubPlot=2;
                case 'Peak Value'
                    app.Petition.TypeOfSubPlot=3;
                case 'Peak Location'
                    app.Petition.TypeOfSubPlot=4; 
                case 'Peak Integral'
                    app.Petition.TypeOfSubPlot=5;
            end
            app.SortOutAxes(app.Petition.TypeOfPlot);
        end

        % Value changed function: FWHMCheckBox
        function FWHMCheckBoxValueChanged(app, event)
            app.Petition.FWHM = app.FWHMCheckBox.Value;   
            app.SortOutAxes(app.Petition.TypeOfPlot);
        end

        % Value changed function: vsmodeCheckBox
        function vsmodeCheckBoxValueChanged(app, event)
            app.Petition.vsMode = app.vsmodeCheckBox.Value;
            app.SortOutAxes(app.Petition.TypeOfPlot);
        end

        % Value changed function: PeakandpositionCheckBox
        function PeakandpositionCheckBoxValueChanged(app, event)
            app.Petition.Peak = app.PeakandpositionCheckBox.Value;
            app.SortOutAxes(app.Petition.TypeOfPlot);
        end

        % Value changed function: IntegralCheckBox
        function IntegralCheckBoxValueChanged(app, event)
            app.Petition.Integral = app.IntegralCheckBox.Value;    
            app.SortOutAxes(app.Petition.TypeOfPlot);
        end

        % Value changed function: PublishstatsCheckBox
        function PublishstatsCheckBoxValueChanged(app, event)
            app.Petition.PublishStats = app.PublishstatsCheckBox.Value;
        end

        % Value changed function: CalibCenter
        function CalibCenterValueChanged(app, event)
            value = str2num(app.CalibCenter.Value); %#ok<ST2NM> 
            if(isempty(value)), app.Petition.CalibCenter=[]; return, end
            value=value(1);
            if(isnan(value(1)) || isinf(value(1))), app.Petition.CalibCenter=[]; return, end
            app.Petition.CalibCenter=value;
        end

        % Value changed function: CalibLinear
        function CalibLinearValueChanged(app, event)
            value = str2num(app.CalibLinear.Value); %#ok<ST2NM> 
            if(isempty(value)), app.Petition.CalibLinear=[]; return, end
            value=value(1);
            if(isnan(value(1)) || isinf(value(1))), app.Petition.CalibLinear=[]; return, end
            app.Petition.CalibLinear=value;
        end

        % Cell edit callback: TABULA_AREA
        function TABULA_AREACellEdit(app, event)
            app.UpdateTableSelection();
        end

        % Cell selection callback: TABULA_AREA
        function TABULA_AREACellSelection(app, event)
            indices = event.Indices;
            app.areaRemoveLine.UserData=indices;
        end

        % Value changed function: UsecalibrationCheckBox
        function UsecalibrationCheckBoxValueChanged(app, event)
            app.Petition.UseCal=app.UsecalibrationCheckBox.Value;
        end

        % Value changed function: PIDDelayEditField
        function PIDDelayEditFieldValueChanged(app, event)
            app.Petition.PidDelay=str2double(app.PIDDelayEditField.Value);
        end

        % Button pushed function: PchipButton
        function PchipButtonPushed(app, event)
           TABLE=app.ColorCheat.Data;
           PchipX=0;
           PchipY=0;
           for II=1:4
               if(~isempty(TABLE{1,II}) && ~isempty(TABLE{2,II}))
                   if(str2num(TABLE{1,II})>=PchipX(end))
                       if(str2num(TABLE{1,II})>PchipX(end))
                            PchipX(end+1)=str2num(TABLE{1,II});
                            PchipY(end+1)=str2num(TABLE{2,II});
                       else
                            PchipY(end)=str2num(TABLE{2,II});
                       end
                   end
               end
           end
           if(PchipX(end)<1)
               PchipX(end+1)=1;
               PchipY(end+1)=1;
           end
           try %#ok<TRYNC> 
                pchipfunction=pchip(PchipX,PchipY);
           end
           UiSEhd_Tran=rand(2);
           try
               ppval(pchipfunction,UiSEhd_Tran);
               app.Petition.pchip=pchipfunction;
           catch ME %#ok<NASGU> 
               app.Petition.pchip='';
           end
           app.ColorCheat.Data=app.ColorCheat.Data;
        end

        % Value changed function: CalibCenter2
        function CalibCenter2ValueChanged(app, event)
            value = str2num(app.CalibCenter2.Value); %#ok<ST2NM> 
            if(isempty(value)), app.Petition.CalibCenter2=[]; return, end
            value=value(1);
            if(isnan(value(1)) || isinf(value(1))), app.Petition.CalibCenter2=[]; return, end
            app.Petition.CalibCenter2=value;
        end

        % Value changed function: CalibLinear2
        function CalibLinear2ValueChanged(app, event)
            value = str2num(app.CalibLinear2.Value); %#ok<ST2NM> 
            if(isempty(value)), app.Petition.CalibLinear2=[]; return, end
            value=value(1);
            if(isnan(value(1)) || isinf(value(1))), app.Petition.CalibLinear2=[]; return, end
            app.Petition.CalibLinear2=value;
        end

        % Value changed function: LastNEditField
        function LastNEditFieldValueChanged(app, event)
            app.Petition.LastN = round(str2num(app.LastNEditField.Value));
        end

        % Value changed function: Rot
        function RotValueChanged(app, event)
            app.Petition.Rotation(3) = app.Rot.Value;
        end

        % Value changed function: flipUD
        function flipUDValueChanged(app, event)
            app.Petition.Rotation(2) = app.flipUD.Value;
        end

        % Value changed function: flipLR
        function flipLRValueChanged(app, event)
            app.Petition.Rotation(1) = app.flipLR.Value;
            
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            app.LogButton.Text=['','-> Log'];
            app.LogButton.Enable='Off';
            Author=app.AuthorEditField.Value; Title=app.TitleEditField.Value; Comment=app.commentEditField.Value;
            try
                if(isempty(app.EditTextFigCheckBox.UserData))
                    app.EditTextFigCheckBox.UserData='';
                end
                app.SendImageToLogbook(app.LogButton.UserData, Author, Title, Comment, app.EditTextFigCheckBox.UserData);
                disp('Image sent to logbook');
            catch
                disp('Failed to send image to logbook');
            end
            try %#ok<TRYNC> 
                close(app.LogButton.UserData);
            end
            app.LogButton.UserData=[];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageandScalarUIFigure = uifigure('Visible', 'off');
            app.ImageandScalarUIFigure.Name = 'Image and Scalar';
            app.ImageandScalarUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImageandScalarUIFigureCloseRequest, true);
            app.XMinEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.XMinEditFieldLabel.Text = 'XMin';
            app.XMinEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.XMinEditField.ValueChangedFcn = createCallbackFcn(app, @XMinEditFieldValueChanged, true);
            app.XMaxEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.XMaxEditFieldLabel.Text = 'XMax';
            app.XMaxEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.XMaxEditField.ValueChangedFcn = createCallbackFcn(app, @XMaxEditFieldValueChanged, true);
            app.YMinEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.YMinEditFieldLabel.Text = 'YMin';
            app.YMinEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.YMinEditField.ValueChangedFcn = createCallbackFcn(app, @YMinEditFieldValueChanged, true);
            app.YMaxEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.YMaxEditFieldLabel.Text = 'YMax';
            app.YMaxEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.YMaxEditField.ValueChangedFcn = createCallbackFcn(app, @YMaxEditFieldValueChanged, true);
            app.AxesFigureButton = uibutton(app.ImageandScalarUIFigure, 'push');
            app.AxesFigureButton.ButtonPushedFcn = createCallbackFcn(app, @AxesFigureButtonPushed, true);
            app.AxesFigureButton.Text = 'Axes Figure';
            app.MoveAxesNearButton = uibutton(app.ImageandScalarUIFigure, 'push');
            app.MoveAxesNearButton.ButtonPushedFcn = createCallbackFcn(app, @MoveAxesNearButtonPushed, true);
            app.MoveAxesNearButton.Text = 'Move Axes Near';
            app.PositionDropDown = uidropdown(app.ImageandScalarUIFigure);
            app.PositionDropDown.Items = {'Right', 'Above', 'Left', 'Below'};
            app.PositionDropDown.Value = 'Right';
            app.DefaultAxesSizeButton = uibutton(app.ImageandScalarUIFigure, 'push');
            app.DefaultAxesSizeButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultAxesSizeButtonPushed, true);
            app.DefaultAxesSizeButton.Text = 'Default Axes Size';
            app.LogbookButton = uibutton(app.ImageandScalarUIFigure, 'push');
            app.LogbookButton.ButtonPushedFcn = createCallbackFcn(app, @LogbookButtonPushed, true);
            app.LogbookButton.Text = 'Logbook';
            app.SaveDataCheckBox = uicheckbox(app.ImageandScalarUIFigure);
            app.SaveDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDataCheckBoxValueChanged, true);
            app.SaveDataCheckBox.Text = 'Save Data';
            app.SaveDataCheckBox.Value = true;
            app.EditTextFigCheckBox = uicheckbox(app.ImageandScalarUIFigure);
            app.EditTextFigCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditTextFigCheckBoxValueChanged, true);
            app.EditTextFigCheckBox.Text = 'Edit Text/Fig';
            app.SaveLabel = uilabel(app.ImageandScalarUIFigure);
            app.SaveLabel.Text = 'Last: XX/XX/XXXX';
            app.TABULA = uitable(app.ImageandScalarUIFigure);
            app.TABULA.ColumnName = {'Variable Name'; 'Minimum'; 'Maximum'; 'Filter1'; 'Filter 2'};
            app.TABULA.RowName = {};
            app.TABULA.CellEditCallback = createCallbackFcn(app, @TABULACellEdit, true);
            app.TABULA.CellSelectionCallback = createCallbackFcn(app, @TABULACellSelection, true);
            app.FilteringoptionsLabel = uilabel(app.ImageandScalarUIFigure);
            app.FilteringoptionsLabel.Text = 'Filtering options:';
            app.AxiscontrolLabel = uilabel(app.ImageandScalarUIFigure);
            app.AxiscontrolLabel.Text = 'Axis control:';
            app.STOP = uibutton(app.ImageandScalarUIFigure, 'push');
            app.STOP.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOP.Text = 'Now Running->Resume Updating';
            app.FilterAddLine = uibutton(app.ImageandScalarUIFigure, 'push');
            app.FilterAddLine.ButtonPushedFcn = createCallbackFcn(app, @FilterAddLineButtonPushed, true);
            app.FilterAddLine.Text = 'Add Line';
            app.FilterRemoveLine = uibutton(app.ImageandScalarUIFigure, 'push');
            app.FilterRemoveLine.ButtonPushedFcn = createCallbackFcn(app, @FilterRemoveLineButtonPushed, true);
            app.FilterRemoveLine.Text = 'Remove Line';
            app.ScalarVariableLabel = uilabel(app.ImageandScalarUIFigure);
            app.ScalarVariableLabel.Text = 'Scalar Variable';
            app.S_Scalar = uidropdown(app.ImageandScalarUIFigure);
            app.S_Scalar.ValueChangedFcn = createCallbackFcn(app, @S_ScalarValueChanged, true);
            app.PIDDelayEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.PIDDelayEditFieldLabel.Text = 'PID Delay';
            app.PIDDelayEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.PIDDelayEditField.ValueChangedFcn = createCallbackFcn(app, @PIDDelayEditFieldValueChanged, true);
            app.PIDDelayEditField.Value = '0';
            app.TypeofPlotDropDownLabel = uilabel(app.ImageandScalarUIFigure);
            app.TypeofPlotDropDownLabel.Text = 'Type of Plot';
            app.TypeofPlotDropDown = uidropdown(app.ImageandScalarUIFigure);
            app.TypeofPlotDropDown.Items = {'Image Only', 'Partition on Scalar', 'Partition Unique Vals'};
            app.TypeofPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofPlotDropDownValueChanged, true);
            app.TypeofPlotDropDown.Value = 'Image Only';
            app.ExpressionEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.ExpressionEditFieldLabel.Text = 'Expression';
            app.ExpressionEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.ExpressionEditField.ValueChangedFcn = createCallbackFcn(app, @ExpressionEditFieldValueChanged, true);
            app.BinsXEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.BinsXEditFieldLabel.Text = 'Bins X';
            app.BinsXEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.BinsXEditField.Value = '25';
            app.BinsYEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.BinsYEditFieldLabel.Text = 'Bins Y';
            app.BinsYEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.BinsYEditField.Value = '25';
            app.PartitionCenterEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.PartitionCenterEditFieldLabel.Text = 'Partition Center';
            app.PartitionCenterEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.PartitionCenterEditField.ValueChangedFcn = createCallbackFcn(app, @PartitionCenterEditFieldValueChanged, true);
            app.PartitionWidthEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.PartitionWidthEditFieldLabel.Text = 'Partition Width';
            app.PartitionWidthEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.PartitionWidthEditField.ValueChangedFcn = createCallbackFcn(app, @PartitionWidthEditFieldValueChanged, true);
            app.ShowonlyEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.ShowonlyEditFieldLabel.Text = 'Show only';
            app.ShowonlyEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.ShowonlyEditField.ValueChangedFcn = createCallbackFcn(app, @ShowonlyEditFieldValueChanged, true);
            app.AutoButton = uibutton(app.ImageandScalarUIFigure, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.AutoPartition = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.ShowstatsCheckBox = uicheckbox(app.ImageandScalarUIFigure);
            app.ShowstatsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowstatsCheckBoxValueChanged, true);
            app.ShowstatsCheckBox.Text = 'Show stats';
            app.S_Vector = uidropdown(app.ImageandScalarUIFigure);
            app.S_Vector.ValueChangedFcn = createCallbackFcn(app, @S_VectorValueChanged, true);
            app.ImageVariableLabel = uilabel(app.ImageandScalarUIFigure);
            app.ImageVariableLabel.Text = 'Image Variable';
            app.AnalysisLabel = uilabel(app.ImageandScalarUIFigure);
            app.AnalysisLabel.Text = 'Analysis:';
            app.FWHMCheckBox = uicheckbox(app.ImageandScalarUIFigure);
            app.FWHMCheckBox.ValueChangedFcn = createCallbackFcn(app, @FWHMCheckBoxValueChanged, true);
            app.FWHMCheckBox.Text = 'FWHM';
            app.PeakandpositionCheckBox = uicheckbox(app.ImageandScalarUIFigure);
            app.PeakandpositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeakandpositionCheckBoxValueChanged, true);
            app.PeakandpositionCheckBox.Text = 'Peak and position';
            app.SubtypeofPlotDropDownLabel = uilabel(app.ImageandScalarUIFigure);
            app.SubtypeofPlotDropDownLabel.Text = 'Subtype of Plot';
            app.SubtypeofPlotDropDown = uidropdown(app.ImageandScalarUIFigure);
            app.SubtypeofPlotDropDown.Items = {'None', 'FWHM', 'Peak Value', 'Peak Location', 'Integral'};
            app.SubtypeofPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @SubtypeofPlotDropDownValueChanged, true);
            app.SubtypeofPlotDropDown.Value = 'None';
            app.TABULA_AREA = uitable(app.ImageandScalarUIFigure);
            app.TABULA_AREA.ColumnName = {'Area'; 'Last N'; 'Style'; 'W'; 'PVBase'; 'Filter 1'; 'Flter 2'};
            app.TABULA_AREA.RowName = {};
            app.TABULA_AREA.CellEditCallback = createCallbackFcn(app, @TABULA_AREACellEdit, true);
            app.TABULA_AREA.CellSelectionCallback = createCallbackFcn(app, @TABULA_AREACellSelection, true);
            app.TABULA_AREA.Enable = 'off';
            app.TABULA_AREA.Visible = 'off';
            app.AreaAddLine = uibutton(app.ImageandScalarUIFigure, 'push');
            app.AreaAddLine.ButtonPushedFcn = createCallbackFcn(app, @VarAddLineButtonPushed, true);
            app.AreaAddLine.Enable = 'off';
            app.AreaAddLine.Visible = 'off';
            app.AreaAddLine.Text = 'Add Line';
            app.areaRemoveLine = uibutton(app.ImageandScalarUIFigure, 'push');
            app.areaRemoveLine.ButtonPushedFcn = createCallbackFcn(app, @VarRemoveLineButtonPushed, true);
            app.areaRemoveLine.Enable = 'off';
            app.areaRemoveLine.Visible = 'off';
            app.areaRemoveLine.Text = 'Remove Line';
            app.AreadefinitionLabel = uilabel(app.ImageandScalarUIFigure);
            app.AreadefinitionLabel.Enable = 'off';
            app.AreadefinitionLabel.Visible = 'off';
            app.AreadefinitionLabel.Text = 'Area definition';
            app.ChangePV = uibutton(app.ImageandScalarUIFigure, 'push');
            app.ChangePV.ButtonPushedFcn = createCallbackFcn(app, @ChangePVButtonPushed, true);
            app.ChangePV.Enable = 'off';
            app.ChangePV.Visible = 'off';
            app.ChangePV.Text = 'Change PV';
            app.vsmodeCheckBox = uicheckbox(app.ImageandScalarUIFigure);
            app.vsmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @vsmodeCheckBoxValueChanged, true);
            app.vsmodeCheckBox.Text = 'vs mode';
            app.PublishstatsCheckBox = uicheckbox(app.ImageandScalarUIFigure);
            app.PublishstatsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PublishstatsCheckBoxValueChanged, true);
            app.PublishstatsCheckBox.Text = 'Publish stats';
            app.IntegralCheckBox = uicheckbox(app.ImageandScalarUIFigure);
            app.IntegralCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntegralCheckBoxValueChanged, true);
            app.IntegralCheckBox.Text = 'Integral';
            app.CalibCenter = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.CalibCenter.ValueChangedFcn = createCallbackFcn(app, @CalibCenterValueChanged, true);
            app.XMaxEditFieldLabel_2 = uilabel(app.ImageandScalarUIFigure);
            app.XMaxEditFieldLabel_2.Text = 'Linear';
            app.CalibLinear = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.CalibLinear.ValueChangedFcn = createCallbackFcn(app, @CalibLinearValueChanged, true);
            app.XMinEditFieldLabel_2 = uilabel(app.ImageandScalarUIFigure);
            app.XMinEditFieldLabel_2.Text = 'Center';
            app.AxiscalibrationLabel = uilabel(app.ImageandScalarUIFigure);
            app.AxiscalibrationLabel.Text = 'Axis calibration:';
            app.UsecalibrationCheckBox = uicheckbox(app.ImageandScalarUIFigure);
            app.UsecalibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsecalibrationCheckBoxValueChanged, true);
            app.UsecalibrationCheckBox.Text = 'Use calibration';
            app.CalibLinear2 = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.CalibLinear2.ValueChangedFcn = createCallbackFcn(app, @CalibLinear2ValueChanged, true);
            app.XMaxEditFieldLabel_3 = uilabel(app.ImageandScalarUIFigure);
            app.XMaxEditFieldLabel_3.Text = 'Linear';
            app.CalibCenter2 = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.CalibCenter2.ValueChangedFcn = createCallbackFcn(app, @CalibCenter2ValueChanged, true);
            app.XMinEditFieldLabel_3 = uilabel(app.ImageandScalarUIFigure);
            app.XMinEditFieldLabel_3.Text = 'Center';
            app.XLabel = uilabel(app.ImageandScalarUIFigure);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.ImageandScalarUIFigure);
            app.YLabel.Text = 'Y:';
            app.Rot = uicheckbox(app.ImageandScalarUIFigure);
            app.Rot.ValueChangedFcn = createCallbackFcn(app, @RotValueChanged, true);
            app.Rot.Text = '90 degrees';
            app.flipUD = uicheckbox(app.ImageandScalarUIFigure);
            app.flipUD.ValueChangedFcn = createCallbackFcn(app, @flipUDValueChanged, true);
            app.flipUD.Text = 'Filp up/down';
            app.flipLR = uicheckbox(app.ImageandScalarUIFigure);
            app.flipLR.ValueChangedFcn = createCallbackFcn(app, @flipLRValueChanged, true);
            app.flipLR.Text = 'Filp left/right';
            app.ImageRotationLabel = uilabel(app.ImageandScalarUIFigure);
            app.ImageRotationLabel.Text = 'Image Rotation:';
            app.AutoCalibration = uibutton(app.ImageandScalarUIFigure, 'push');
            app.AutoCalibration.Text = 'Auto';
            app.ColorCheat = uitable(app.ImageandScalarUIFigure);
            app.ColorCheat.ColumnName = {'Point1'; 'Point2'; 'Point3'; 'Point4'};
            app.ColorCheat.RowName = {};
            app.ColorCheat.ColumnEditable = true;
            app.PchipButton = uibutton(app.ImageandScalarUIFigure, 'push');
            app.PchipButton.ButtonPushedFcn = createCallbackFcn(app, @PchipButtonPushed, true);
            app.PchipButton.Text = 'Pchip';
            app.ratioLabel = uilabel(app.ImageandScalarUIFigure);
            app.ratioLabel.Text = 'ratio';
            app.valueLabel = uilabel(app.ImageandScalarUIFigure);
            app.valueLabel.Text = 'value';
            app.LastNEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.LastNEditFieldLabel.Text = 'Last N';
            app.LastNEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.LastNEditField.ValueChangedFcn = createCallbackFcn(app, @LastNEditFieldValueChanged, true);
            app.LastNEditField.Value = '1';
            app.AuthorEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.AuthorEditFieldLabel.Text = 'Author:';
            app.AuthorEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.TitleEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            app.TitleEditField.Value = 'CVCRCI Image vs Scalar';
            app.LogButton = uibutton(app.ImageandScalarUIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = '-> Log';
            app.commentEditFieldLabel = uilabel(app.ImageandScalarUIFigure);
            app.commentEditFieldLabel.Text = 'comment';
            app.commentEditField = uieditfield(app.ImageandScalarUIFigure, 'text');
            % Show the figure after all components are created
            app.ImageandScalarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVCRCI_Image_and_Scalar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageandScalarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageandScalarUIFigure)
        end
    end
end