classdef CVCRCI_Scalar_vs_Scalar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScalarvsScalarUIFigure          matlab.ui.Figure
        P2                              matlab.ui.container.Panel
        Aexpxx0C2B2y0Label              matlab.ui.control.Label
        AEditFieldLabel                 matlab.ui.control.Label
        AEditField                      matlab.ui.control.EditField
        BEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.EditField
        CEditFieldLabel                 matlab.ui.control.Label
        CEditField                      matlab.ui.control.EditField
        x0EditFieldLabel                matlab.ui.control.Label
        x0EditField                     matlab.ui.control.EditField
        y0EditFieldLabel                matlab.ui.control.Label
        y0EditField                     matlab.ui.control.EditField
        XMinEditFieldLabel              matlab.ui.control.Label
        XMinEditField                   matlab.ui.control.EditField
        XMaxEditFieldLabel              matlab.ui.control.Label
        XMaxEditField                   matlab.ui.control.EditField
        YMinEditFieldLabel              matlab.ui.control.Label
        YMinEditField                   matlab.ui.control.EditField
        YMaxEditFieldLabel              matlab.ui.control.Label
        YMaxEditField                   matlab.ui.control.EditField
        AxesFigureButton                matlab.ui.control.Button
        MoveAxesNearButton              matlab.ui.control.Button
        PositionDropDown                matlab.ui.control.DropDown
        DefaultAxesSizeButton           matlab.ui.control.Button
        LogbookButton                   matlab.ui.control.Button
        SaveDataCheckBox                matlab.ui.control.CheckBox
        EditTextFigCheckBox             matlab.ui.control.CheckBox
        SaveLabel                       matlab.ui.control.Label
        TABULA                          matlab.ui.control.Table
        VariablestoplotonordinateLabel  matlab.ui.control.Label
        FilteringoptionsLabel           matlab.ui.control.Label
        TabellaY                        matlab.ui.control.Table
        AxiscontrolLabel                matlab.ui.control.Label
        STOP                            matlab.ui.control.Button
        VarAddLine                      matlab.ui.control.Button
        VarRemoveLine                   matlab.ui.control.Button
        FilterAddLine                   matlab.ui.control.Button
        FilterRemoveLine                matlab.ui.control.Button
        VariableonXLabel                matlab.ui.control.Label
        S_Scalar                        matlab.ui.control.DropDown
        PIDDelayEditFieldLabel          matlab.ui.control.Label
        PIDDelayEditField               matlab.ui.control.EditField
        TypeofPlotDropDownLabel         matlab.ui.control.Label
        TypeofPlotDropDown              matlab.ui.control.DropDown
        TypeofFitDropDownLabel          matlab.ui.control.Label
        TypeofFitDropDown               matlab.ui.control.DropDown
        P1                              matlab.ui.container.Panel
        OrderEditField_2Label           matlab.ui.control.Label
        OrderPolyFitEdit                matlab.ui.control.EditField
        ExpressionEditFieldLabel        matlab.ui.control.Label
        ExpressionEditField             matlab.ui.control.EditField
        BinsXEditFieldLabel             matlab.ui.control.Label
        BinsXEditField                  matlab.ui.control.EditField
        BinsYEditFieldLabel             matlab.ui.control.Label
        BinsYEditField                  matlab.ui.control.EditField
        PartitionCenterEditFieldLabel   matlab.ui.control.Label
        PartitionCenterEditField        matlab.ui.control.EditField
        PartitionWidthEditFieldLabel    matlab.ui.control.Label
        PartitionWidthEditField         matlab.ui.control.EditField
        ShowonlyEditFieldLabel          matlab.ui.control.Label
        ShowonlyEditField               matlab.ui.control.EditField
        AutoButton                      matlab.ui.control.Button
        AutoPartition                   matlab.ui.control.EditField
        ShowstatsCheckBox               matlab.ui.control.CheckBox
        LogButton                       matlab.ui.control.Button
        AuthorEditFieldLabel            matlab.ui.control.Label
        AuthorEditField                 matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        commentEditFieldLabel           matlab.ui.control.Label
        commentEditField                matlab.ui.control.EditField
        BoxGridCheckBox                 matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Petition % Structure used by the update function .
        DataStructure % Stores Acquisition variable for re initialization.
        ASSE % True axes not UI axes for speed to be located in PGR figure.
        PGR % Patently-slow Graphics Remedy, a figure that supports faster graphics.
        ASSEDefaultSize; %default size when new figure is turned on.
        color; %colors for the panel
        application='Test';
        ToBeDeleted=[];
        Annotations=[];
        Permannotation;
        FIELDNAMES;
    end
    
    properties (Access = public)
        NeedInit=1; % Description
    end
    
    methods (Access = public)
        
        function Update(app,CV,Initialize)
            if(Initialize)
                app.UpdateInitialize(CV);
            else
                if(app.Petition.STOP), return, end
                if(~app.Petition.S_SEL(1)), return, end
                if(~isvalid(app.PGR)), app.CheckExistenceMakeAxes(); disp('Axes were remade because could not be found'); end
                delete(app.ASSE.Children); %if(~isempty(app.Petition.LastSeconds)), MT=-inf; end
                hold(app.ASSE,'on');
                if(app.DataStructure.Synchronous)
                    %%
                    if(CV.Recorder.TimesBufferFilled)
                        if(CV.Recorder.LastWrittenElement==app.DataStructure.Buffersize)
                            TENUTI=1:app.DataStructure.Buffersize;
                        else
                            TENUTI=[(CV.Recorder.LastWrittenElement+1):app.DataStructure.Buffersize,(1:CV.Recorder.LastWrittenElement)];
                        end
                    else
                        TENUTI=1:CV.Recorder.LastWrittenElement;
                    end
                    
                    TF{1}=TENUTI;
                    TF{2}=TENUTI;
                    for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                        if(app.Petition.FilterUse{FN}(1))
                            TF{2}=TF{2}((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{2},app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{2},app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                        end
                    end
                    TF{3}=TENUTI;
                    for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                        if(app.Petition.FilterUse{FN}(2))
                            TF{3}=TF{3}((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{3},app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{3},app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                        end
                    end
                    TF{4}=intersect(TF{2},TF{3},'stable');
                    DATI_Y_timestamped=[];
                    for II=1:length(app.Petition.Quantity)
                        if(app.DataStructure.TimeStamped(app.Petition.Quantity(II).Y_SEL(1)))
                            DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(:,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle; %#ok<AGROW>
                            DATI_Y_timestamped(II)=1; %#ok<AGROW>
                        else
                            DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(:,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            DATI_Y_timestamped(II)=0; %#ok<AGROW>
                            if(app.DataStructure.BSA || app.Petition.Quantity(II).Y_SEL(1)~=1)
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.Quantity(II).Y_SEL(1)}; %#ok<AGROW>
                            else
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.Quantity(II).Y_SEL(1)}(:,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            end
                        end
                    end
                    DATI_X_timestamped=app.DataStructure.TimeStamped(app.Petition.S_SEL(1));
                    if(DATI_X_timestamped)
                        DATI_X=CV.Recorder.Data{app.Petition.S_SEL(1)}(:,app.Petition.S_SEL(2));
                        DATI_X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle;
                    else
                        DATI_X=CV.Recorder.Data{app.Petition.S_SEL(1)}(:,app.Petition.S_SEL(2)); 
                        if(app.DataStructure.BSA || app.Petition.S_SEL(1)~=1)
                            DATI_X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.S_SEL(1)}; 
                        else
                            DATI_X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.S_SEL(1)}(:,app.Petition.S_SEL(2));
                        end
                    end   
                else % Asynchronous mode.
                    for II=1:length(app.Petition.Quantity)
                        if(CV.Acquisition.TimeStamped(app.Petition.Quantity(II).Y_SEL(1)))
                            if(CV.Acquisition.BSA || app.Petition.Quantity(II).Y_SEL(1)~=1)
                                if(CV.Recorder.TimesBufferFilled{app.Petition.Quantity(II).Y_SEL(1)})
                                    if(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}<CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)))
                                        SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}+1):CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}];
                                    else
                                        SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1));
                                    end
                                else
                                    SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)};
                                end
                                DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER); %#ok<AGROW>
                                DATI_Y_timestamped(II)=1; %#ok<AGROW>
                                DATI_Y_timestamps{II}=CV.Recorder.PulseIds{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER); %#ok<AGROW>
                            else
                                if(CV.Recorder.TimesBufferFilled{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2)))
                                    if(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2))<CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)))
                                        SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2))+1):CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2))];
                                    else
                                        SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1));
                                    end
                                else
                                    SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2));
                                end
                                DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                                DATI_Y_timestamped(II)=1; %#ok<AGROW>
                                DATI_Y_timestamps{II}=CV.Recorder.PulseIds{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            end
                        else
                            if(CV.Recorder.AcquisitionNonTimeStampedTimesBufferFilled(app.Petition.Quantity(II).Y_SEL(1)))
                                if(CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1))<CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)))
                                    SORTING_ORDER=[(1+CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1))):CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)),1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1))];
                                else
                                    SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1));
                                end
                            else
                                SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1));
                            end
                            DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            DATI_Y_timestamped(II)=0; %#ok<AGROW>
                            if(CV.Acquisition.BSA || app.Petition.Quantity(II).Y_SEL(1)~=1)
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER); %#ok<AGROW>
                            else
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            end
                        end
                        
                    end

                    if(CV.Acquisition.TimeStamped(app.Petition.S_SEL(1)))
                        DATI_X_timestamped=1;
                        if(CV.Acquisition.BSA || app.Petition.S_SEL(1)~=1)
                            if(CV.Recorder.TimesBufferFilled{app.Petition.S_SEL(1)})
                                if(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}<CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)))
                                    SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}+1):CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}];
                                else
                                    SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1));
                                end
                            else
                                SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)};
                            end
                            DATI_X=CV.Recorder.Data{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2)); 
                            DATI_X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle{app.Petition.S_SEL(1)}(SORTING_ORDER); 
                            DATI_X_timestamps=CV.Recorder.PulseIds{app.Petition.S_SEL(1)}(SORTING_ORDER); 
                        else
                            if(CV.Recorder.TimesBufferFilled{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2)))
                                if(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2))<CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)))
                                    SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2))+1):CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2))];
                                else
                                    SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1));
                                end
                            else
                                SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2));
                            end
                            DATI_X=CV.Recorder.Data{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                            DATI_X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2)); 
                            DATI_X_timestamps=CV.Recorder.PulseIds{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                        end
                    else
                        if(CV.Recorder.AcquisitionNonTimeStampedTimesBufferFilled(app.Petition.S_SEL(1)))
                            if(CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1))<CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)))
                                SORTING_ORDER=[(1+CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1))):CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)),1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1))];
                            else
                                SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1));
                            end
                        else
                            SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1));
                        end
                        DATI_X=CV.Recorder.Data{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                        DATI_X_timestamped=0; 
                        if(CV.Acquisition.BSA || app.Petition.S_SEL(1)~=1)
                            DATI_X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.S_SEL(1)}(SORTING_ORDER);  
                        else
                            DATI_X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                        end
                    end  
                end %End asynchronous
                if(app.Petition.ShowStats)
                    TABRES=cell(length(app.Petition.Quantity)+2,4);
                    TABRES{1,1}='PV |'; TABRES{1,2}='      AVG      | |'; TABRES{1,3}='     STD      | |'; TABRES{1,4}='     Samples     | |';
                end
                if(app.Petition.TypeOfPlot<4) % X/Y dots plot
                    for II=1:length(app.Petition.Quantity)
                        if(~DATI_X_timestamped && ~DATI_Y_timestamped(II)) %plot averages that have same acquisition writing cycle, no filtering is possible.
                            [~,WX,WY]=intersect(DATI_X_AcquisitionWritingCycle,DATI_Y_AcquisitionWritingCycle{II},'stable');
                            if(app.Petition.Quantity(II).LastN<length(WX))
                                DX=DATI_X(WX((end-app.Petition.Quantity(II).LastN+1):end));
                                DY=DATI_Y{II}(WY((end-app.Petition.Quantity(II).LastN+1):end));
                            else
                                DX=DATI_X(WX);
                                DY=DATI_Y{II}(WY);
                            end
                            if(app.Petition.ShowStats) 
                                if(II==1)
                                    if(~isempty(DX))
                                        TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=num2str(mean(DX)); TABRES{II+1,3}=num2str(std(DX)); TABRES{II+1,4}=num2str(length(DX));
                                    else
                                        TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=' - '; TABRES{II+1,3}=' - '; TABRES{II+1,4}=' - ';
                                    end
                                end
                                if(~isempty(DY))
                                    TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}=num2str(mean(DY)); TABRES{II+2,3}=num2str(std(DY)); TABRES{II+2,4}=num2str(length(DY));
                                else
                                    TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}='NaN'; TABRES{II+2,3}='NaN'; TABRES{II+2,4}='NaN';
                                end
                            end
                            if(~isempty(app.Petition.Function)), DX=app.Petition.Function(DX); end
                            if(~isempty(app.Petition.Quantity(II).Function)), DY=app.Petition.Quantity(II).Function(DY); end
                            switch(app.Petition.TypeOfPlot)
                                case 1
                                    plot(app.ASSE, DX, DY, app.Petition.Quantity(II).Stilnovo);
                                case 2
                                    if(II==1)
                                        [Mappa, xax, yax] = CV.DensityMap(DX,DY,app.Petition.binsx,app.Petition.binsy);
                                    end
                                    imagesc(xax,yax,Mappa,'parent',app.ASSE);
                                    break
                                case 3
                                    %[DY, stdPartition, DX, Centers, Widths, Elements]=CV.PartitionPlotScalar(obj, XData, YData, PartitionPos, PartitionWidth);
                                    [DY, stdDY, DX]=CV.PartitionPlotScalar(DX, DY, app.Petition.PartitionPos, app.Petition.PartitionWidth);
                                    if(app.Petition.ShowStats)
                                        if(II==1)
                                            TABRES=[TABRES,cell(size(TABRES,1),3)]; %#ok<AGROW> 
                                            TABRES{1,end}='Max Val'; TABRES{1,end-1}='Max PosID'; TABRES{1,end-2}='Max Pos';
                                            TABRES{2,end}=' - '; TABRES{2,end-1} = ' - '; TABRES{2,end-2} = ' - ';
                                        end
                                        [MaxVal, MaxPos]=max(DY);
                                        MaxX=DX(MaxPos);
                                        TABRES{2+II,end}=num2str(MaxVal);
                                        TABRES{2+II,end-1}=num2str(MaxPos);
                                        TABRES{2+II,end-2}=num2str(MaxX);
                                    end
                                    if(isnan(app.Petition.ShowOnly))
                                        errorbar(app.ASSE,DX,DY,stdDY,app.Petition.Quantity(II).Stilnovo);
                                    else
                                        Display=find(app.Petition.ShowOnly==(1:length(DX)));
                                        if(~isempty(Display))
                                            errorbar(app.ASSE,DX(Display),DY(Display),stdDY(Display),app.Petition.Quantity(II).Stilnovo);
                                        end
                                    end
                            end              
                        elseif(DATI_X_timestamped && DATI_Y_timestamped(II))
                            if(app.DataStructure.Synchronous) %apply filters
                                FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
                                switch(FilterNum)
                                    case 1, ID=1;
                                    case 2, ID=2;
                                    case 3, ID=3;
                                    case 6, ID=4;
                                end
                                DX=DATI_X(TF{ID}); DY=DATI_Y{II}(TF{ID});
                            else %calculate intersection, but do not use filters.
                                [~,WX,WY]=intersect(DATI_X_timestamps,DATI_Y_timestamps{II},'stable');
                                DX=DATI_X(WX);
                                DY=DATI_Y{II}(WY);
                            end
                            if(app.Petition.Quantity(II).LastN<length(DX))
                                DX=DX((end-app.Petition.Quantity(II).LastN+1):end);
                                DY=DY((end-app.Petition.Quantity(II).LastN+1):end);
                            end
                            if(app.Petition.ShowStats)
                                if(II==1)
                                    if(~isempty(DX))
                                        TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=num2str(mean(DX)); TABRES{II+1,3}=num2str(std(DX)); TABRES{II+1,4}=num2str(length(DX));
                                    else
                                        TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=' - '; TABRES{II+1,3}=' - '; TABRES{II+1,4}=' - ';
                                    end
                                end
                                if(~isempty(DY))
                                    TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}=num2str(mean(DY)); TABRES{II+2,3}=num2str(std(DY)); TABRES{II+2,4}=num2str(length(DY));
                                else
                                    TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}='NaN'; TABRES{II+2,3}='NaN'; TABRES{II+2,4}='NaN';
                                end
                            end
                            if(~isempty(app.Petition.Function)), DX=app.Petition.Function(DX); end
                            if(~isempty(app.Petition.Quantity(II).Function)), DY=app.Petition.Quantity(II).Function(DY); end
                            switch(app.Petition.TypeOfPlot)
                                case 1
                                    plot(app.ASSE, DX, DY, app.Petition.Quantity(II).Stilnovo);
                                case 2
                                    if(II==1)
                                        [Mappa, xax, yax] = CV.DensityMap(DX,DY,app.Petition.binsx,app.Petition.binsy);
                                    end
                                    imagesc(xax,yax,Mappa,'parent',app.ASSE);
                                    break
                                case 3
                                    %[DY, stdPartition, DX, Centers, Widths, Elements]=CV.PartitionPlotScalar(obj, XData, YData, PartitionPos, PartitionWidth);
                                    [DY, stdDY, DX]=CV.PartitionPlotScalar(DX, DY, app.Petition.PartitionPos, app.Petition.PartitionWidth);
                                    if(app.Petition.ShowStats)
                                        if(II==1)
                                            TABRES=[TABRES,cell(size(TABRES,1),3)]; %#ok<AGROW> 
                                            TABRES{1,end}='Max Val'; TABRES{1,end-1}='Max PosID'; TABRES{1,end-2}='Max Pos';
                                            TABRES{2,end}=' - '; TABRES{2,end-1} = ' - '; TABRES{2,end-2} = ' - ';
                                        end
                                        [MaxVal, MaxPos]=max(DY);
                                        MaxX=DX(MaxPos);
                                        TABRES{2+II,7}=num2str(MaxVal);
                                        TABRES{2+II,6}=num2str(MaxPos);
                                        TABRES{2+II,5}=num2str(MaxX);
                                    end
                                    if(isnan(app.Petition.ShowOnly))
                                        errorbar(app.ASSE,DX,DY,stdDY,app.Petition.Quantity(II).Stilnovo);
                                    else
                                        Display=find(app.Petition.ShowOnly==(1:length(DX)));
                                        if(~isempty(Display))
                                            errorbar(app.ASSE,DX(Display),DY(Display),stdDY(Display),app.Petition.Quantity(II).Stilnovo);
                                        end
                                    end
                            end 
                        elseif(DATI_X_timestamped)
                            if(app.DataStructure.Synchronous) %apply filters
                                FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
                                switch(FilterNum)
                                    case 1, ID=1;
                                    case 2, ID=2;
                                    case 3, ID=3;
                                    case 6, ID=4;
                                end
                                DX=DATI_X(TF{ID}); WCX=DATI_X_AcquisitionWritingCycle(TF{ID});
                            else
                                DX=DATI_X; WCX=DATI_X_AcquisitionWritingCycle;
                            end
                            DY=CV.NonTimeStamped_On_Timestamped(DATI_Y_AcquisitionWritingCycle{II},DATI_Y{II},WCX);
                            if(app.Petition.Quantity(II).LastN<length(DX))
                                DX=DX((end-app.Petition.Quantity(II).LastN+1):end);
                                DY=DY((end-app.Petition.Quantity(II).LastN+1):end);
                            end
                            if(app.Petition.ShowStats)
                                if(II==1)
                                    if(~isempty(DX))
                                        TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=num2str(mean(DX)); TABRES{II+1,3}=num2str(std(DX)); TABRES{II+1,4}=num2str(length(DX));
                                    else
                                        TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=' - '; TABRES{II+1,3}=' - '; TABRES{II+1,4}=' - ';
                                    end
                                end
                                if(~isempty(DY))
                                    TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}=num2str(mean(DY)); TABRES{II+2,3}=num2str(std(DY)); TABRES{II+2,4}=num2str(length(DY));
                                else
                                    TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}='NaN'; TABRES{II+2,3}='NaN'; TABRES{II+2,4}='NaN';
                                end
                            end
                            if(~isempty(app.Petition.Function)), DX=app.Petition.Function(DX); end
                            if(~isempty(app.Petition.Quantity(II).Function)), DY=app.Petition.Quantity(II).Function(DY); end
                            switch(app.Petition.TypeOfPlot)
                                case 1
                                    plot(app.ASSE, DX, DY, app.Petition.Quantity(II).Stilnovo);
                                case 2
                                    if(II==1)
                                        [Mappa, xax, yax] = CV.DensityMap(DX,DY,app.Petition.binsx,app.Petition.binsy);
                                    end
                                    imagesc(xax,yax,Mappa,'parent',app.ASSE);
                                    break
                                case 3
                                    %[DY, stdPartition, DX, Centers, Widths, Elements]=CV.PartitionPlotScalar(obj, XData, YData, PartitionPos, PartitionWidth);
                                    [DY, stdDY, DX]=CV.PartitionPlotScalar(DX, DY, app.Petition.PartitionPos, app.Petition.PartitionWidth);
                                    if(app.Petition.ShowStats)
                                        if(II==1)
                                            TABRES=[TABRES,cell(size(TABRES,1),3)]; %#ok<AGROW> 
                                            TABRES{1,end}='Max Val'; TABRES{1,end-1}='Max PosID'; TABRES{1,end-2}='Max Pos';
                                            TABRES{2,end}=' - '; TABRES{2,end-1} = ' - '; TABRES{2,end-2} = ' - ';
                                        end
                                        [MaxVal, MaxPos]=max(DY);
                                        MaxX=DX(MaxPos);
                                        TABRES{2+II,end}=num2str(MaxVal);
                                        TABRES{2+II,end-1}=num2str(MaxPos);
                                        TABRES{2+II,end-2}=num2str(MaxX);
                                    end
                                    if(isnan(app.Petition.ShowOnly))
                                        errorbar(app.ASSE,DX,DY,stdDY,app.Petition.Quantity(II).Stilnovo);
                                    else
                                        Display=find(app.Petition.ShowOnly==(1:length(DX)));
                                        if(~isempty(Display))
                                            errorbar(app.ASSE,DX(Display),DY(Display),stdDY(Display),app.Petition.Quantity(II).Stilnovo);
                                        end
                                    end
                            end 
                        elseif(DATI_Y_timestamped(II))
                            if(app.DataStructure.Synchronous) %apply filters
                                FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
                                switch(FilterNum)
                                    case 1, ID=1;
                                    case 2, ID=2;
                                    case 3, ID=3;
                                    case 6, ID=4;
                                end
                                DY=DATI_Y{II}(TF{ID}); WCY=DATI_Y_AcquisitionWritingCycle{II}(TF{ID});
                            else
                                DY=DATI_Y; WCY=DATI_Y_AcquisitionWritingCycle{II};
                            end
                            DX=CV.NonTimeStamped_On_Timestamped(DATI_X_AcquisitionWritingCycle,DATI_X,WCY);
                            if(app.Petition.Quantity(II).LastN<length(DX))
                                DX=DX((end-app.Petition.Quantity(II).LastN+1):end);
                                DY=DY((end-app.Petition.Quantity(II).LastN+1):end);
                            end
                            if(app.Petition.ShowStats)
                                if(II==1)
                                    if(~isempty(DX))
                                        TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=num2str(mean(DX)); TABRES{II+1,3}=num2str(std(DX)); TABRES{II+1,4}=num2str(length(DX));
                                    else
                                        TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=' - '; TABRES{II+1,3}=' - '; TABRES{II+1,4}=' - ';
                                    end
                                end
                                if(~isempty(DY))
                                    TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}=num2str(mean(DY)); TABRES{II+2,3}=num2str(std(DY)); TABRES{II+2,4}=num2str(length(DY));
                                else
                                    TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}='NaN'; TABRES{II+2,3}='NaN'; TABRES{II+2,4}='NaN';
                                end
                            end
                            if(~isempty(app.Petition.Function)), DX=app.Petition.Function(DX); end
                            if(~isempty(app.Petition.Quantity(II).Function)), DY=app.Petition.Quantity(II).Function(DY); end
                            switch(app.Petition.TypeOfPlot)
                                case 1
                                    plot(app.ASSE, DX, DY, app.Petition.Quantity(II).Stilnovo);
                                case 2
                                    if(II==1)
                                        [Mappa, xax, yax] = CV.DensityMap(DX,DY,app.Petition.binsx,app.Petition.binsy);
                                    end
                                    imagesc(xax,yax,Mappa,'parent',app.ASSE);
                                    break
                                case 3
                                    %[DY, stdPartition, DX, Centers, Widths, Elements]=CV.PartitionPlotScalar(obj, XData, YData, PartitionPos, PartitionWidth);
                                    [DY, stdDY, DX]=CV.PartitionPlotScalar(DX, DY, app.Petition.PartitionPos, app.Petition.PartitionWidth);
                                    if(app.Petition.ShowStats)
                                        if(II==1)
                                            TABRES=[TABRES,cell(size(TABRES,1),3)]; %#ok<AGROW> 
                                            TABRES{1,end}='Max Val'; TABRES{1,end-1}='Max PosID'; TABRES{1,end-2}='Max Pos';
                                            TABRES{2,end}=' - '; TABRES{2,end-1} = ' - '; TABRES{2,end-2} = ' - ';
                                        end
                                        [MaxVal, MaxPos]=max(DY);
                                        MaxX=DX(MaxPos);
                                        TABRES{2+II,end}=num2str(MaxVal);
                                        TABRES{2+II,end-1}=num2str(MaxPos);
                                        TABRES{2+II,end-2}=num2str(MaxX);
                                    end
                                    if(isnan(app.Petition.ShowOnly))
                                        errorbar(app.ASSE,DX,DY,stdDY,app.Petition.Quantity(II).Stilnovo);
                                    else
                                        Display=find(app.Petition.ShowOnly==(1:length(DX)));
                                        if(~isempty(Display))
                                            errorbar(app.ASSE,DX(Display),DY(Display),stdDY(Display),app.Petition.Quantity(II).Stilnovo);
                                        end
                                    end
                            end 
                        end
                        switch(app.Petition.TypeOfFit)
                            case 1 % No fit.
                            case 2 % Polynomial fit
                                
                                if(II==1)
                                    if(app.Petition.ShowStats)
                                        if(app.Petition.PolyOrder==2)
                                            TABRES=[TABRES,cell(size(TABRES,1),5)]; %#ok<AGROW> 
                                            TABRES{1,end}='max'; TABRES{1,end-1}='extreme'; TABRES{1,end-4}=' power 0 '; TABRES{1,end-3}=' power 1 ';TABRES{1,end-2}=' power 2 ';
                                            TABRES{2,end}=' - '; TABRES{2,end-1}=' - '; TABRES{2,end-4}=' - '; TABRES{2,end-3}=' - ';TABRES{2,end-2}=' - ';
                                            PosOffset=2;
                                        else
                                            PosOffset=0;   
                                            TABRES=[TABRES,cell(size(TABRES,1),app.Petition.PolyOrder+1)]; %#ok<AGROW> 
                                            for ZZ=app.Petition.PolyOrder:-1:0
                                                TABRES{1,end-ZZ} = ['power ',num2str(app.Petition.PolyOrder-ZZ)];
                                                TABRES{2,end-ZZ}=' - ';
                                            end
                                        end
                                    end
                                    mx=min(DX); MX=max(DX);
                                    x=linspace(mx-abs(MX-mx)/10,MX+abs(MX-mx)/10);
                                end
                                [DX0,DY0]=CV.ReduceToUniqueX(DX,DY);
                                POLY=polyfit(DX0,DY0,app.Petition.PolyOrder);
                                if(app.Petition.ShowStats)
                                    for ZZ=app.Petition.PolyOrder:-1:0
                                       TABRES{II+2,end-ZZ-PosOffset} =num2str(POLY(ZZ+1)); 
                                    end
                                end
                                plot(app.ASSE,x,polyval(POLY,x),regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-'));
                                if(app.Petition.ShowStats)
                                    if(app.Petition.PolyOrder==2)
                                        Extreme=-POLY(2)/2/POLY(1);
                                        TABRES{II+2,end-1} =num2str(Extreme);
                                        TABRES{II+2,end} =num2str(polyval(POLY,Extreme));
                                    end
                                end
                            case 3 % Gaussian fit
                                if(II==1)
                                    TABRES{size(TABRES,1),end+4}='';
                                    TABRES{1,end-4}='A'; TABRES{1,end-3}='B'; TABRES{1,end-2}='C'; TABRES{1,end-1}='x0'; TABRES{1,end}='y0';
                                end
                                PARAM=[app.Petition.A,app.Petition.x0,app.Petition.C,app.Petition.B,app.Petition.y0];
                                [DX0,DY0]=CV.ReduceToUniqueX(DX,DY);
                                GAUSS_PAR=CV.FreeGaussFit(DX0,DY0,PARAM);
                                plot(app.ASSE,GAUSS_PAR.XV,GAUSS_PAR.Fit,regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-'));
                                if(app.Petition.ShowStats)
                                    TABRES{II+2,end-4}=num2str(GAUSS_PAR.Param(1)); TABRES{II+2,end-3}=num2str(GAUSS_PAR.Param(4)); TABRES{II+2,end-2}=num2str(GAUSS_PAR.Param(3)); TABRES{II+2,end-1}=num2str(GAUSS_PAR.Param(2)); TABRES{II+2,end}=num2str(GAUSS_PAR.Param(5));
                                end
                        end
                    end
                end
                
                if(app.Petition.TypeOfPlot==4) % Histogram on X.
                    if(DATI_X_timestamped)
                        if(app.DataStructure.Synchronous) %apply filters
                            FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
                            switch(FilterNum)
                                case 1, ID=1;
                                case 2, ID=2;
                                case 3, ID=3;
                                case 6, ID=4;
                            end
                            DX=DATI_X(TF{ID});
                        else
                            DX=DATI_X;
                        end
                    else
                        DX=DATI_X;
                    end
                    [c,d]=hist(DX,app.Petition.binsx); %#ok<HIST>
                    bar(app.ASSE,c,d);
                end
                
                xlim(app.ASSE,app.Petition.xLim);
                ylim(app.ASSE,app.Petition.yLim);
                    
                if(app.Petition.ShowStats)
                    MT=CV.TextMatrixToTextColumn(TABRES);
                    app.Permannotation.String=MT;
                end
                if(app.Petition.Logbook)
                    app.logBookButtonCall(CV);
                    app.LogbookButton.BackgroundColor=app.color.logbook; app.Petition.Logbook=0;
                end
            end
        end
        
        function [SUCCESS,filename,TargetDir] = writeDataToDisk(app,TimeNow,CVObject,auxillaryData)
            CurrentTime=datevec(TimeNow);
            CurrentYearString=num2str(CurrentTime(1),'%.4d'); CurrentMonthString=num2str(CurrentTime(2),'%.2d');
            CurrentDayString=num2str(CurrentTime(3),'%.2d'); CurrentTempo1String=num2str(CurrentTime(4),'%.2d');
            CurrentTempo2String=num2str(CurrentTime(5),'%.2d'); CurrentTempo3String=num2str(floor(CurrentTime(6)),'%.2d');
            CurrentTempo4String=num2str(round((CurrentTime(6)-floor(CurrentTime(6)))*1000),'%.3d');
            CurrentTimeString=[CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString,'--',CurrentTempo1String,'-',CurrentTempo2String,'-',CurrentTempo3String,'-',CurrentTempo4String];
            filename=['OnlineMonitor-','ScalarAndScalar','-',CurrentTimeString];
            TargetDir=['/u1/lcls/matlab/data/',CurrentYearString,'/',CurrentYearString,'-',CurrentMonthString,'/',CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString];
            if(~isfolder(TargetDir))
                mkdir(TargetDir);
            end
            DataStructure=CVObject.Acquisition; Data=CVObject.Recorder; %#ok<ADPROPLC> 
            try
                save([TargetDir,'/',filename],'Data','DataStructure','auxillaryData')
                SUCCESS=1;
            catch
                SUCCESS=0;
            end
        end
    end
    
    methods (Access = private)
        
        function void = UpdateLimits(app,direction)
            if(direction=='x')
                valuem = str2num(app.XMinEditField.Value); %#ok<ST2NM> 
                valueM = str2num(app.XMaxEditField.Value); %#ok<ST2NM> 
            else
                valuem = str2num(app.YMinEditField.Value); %#ok<ST2NM> 
                valueM = str2num(app.YMaxEditField.Value); %#ok<ST2NM> 
            end
            if(isempty(valuem)), valuem=-inf; end
            if(isempty(valueM)), valueM=+inf; end
            valuem=valuem(1); valueM=valueM(1);
            if(valuem>valueM)
                temp=valuem; valuem=valueM; valueM=temp; 
                if(direction=='x')
                    app.XMinEditField.Value = num2str(valuem); app.XMaxEditField.Value = num2str(valueM);
                else
                    app.YMinEditField.Value = num2str(valuem); app.YMaxEditField.Value = num2str(valueM);
                end
            end
            if(abs(valuem-valueM)<10^-13)
                valueM=valueM+10^-13/2; valuem=valuem-10^-13/2;
            end
            if(direction=='x')
                app.Petition.xLim=[valuem,valueM];
            else
                app.Petition.yLim=[valuem,valueM];
            end
            void=0;
        end
        
        function wasvalid = CheckExistenceMakeAxes(app)
            wasvalid=1;
            if(~isvalid(app.PGR))
                app.PGR=figure('Name',[app.(app.FIELDNAMES{(cellfun(@(x) any(x),strfind(fieldnames(app),'UIFigure')))}).Name,' *-* Display']);
                app.ASSE=axes(app.PGR,'Position',[0.13,0.1,0.7750,0.8150]);
                app.Permannotation=annotation(app.PGR,'textbox',[0 0 1 0.7],'EdgeColor','none','HorizontalAlignment','left','Interpreter','latex');
                %delete(axtoolbar(app.ASSE));
                app.ASSE.Interactions=[];app.ASSE.Toolbar=[];
                app.PGR.MenuBar='none'; app.PGR.ToolBar='none';
                app.ASSE.NextPlot='replacechildren';
                app.ASSEDefaultSize=app.ASSE.Position(3:4);
                wasvalid=0;
                app.ManageFitVisibility(0);
                app.Petition.ShowStats=app.ShowstatsCheckBox.Value;
                if(app.BoxGridCheckBox.Value), box(app.ASSE,'on'); grid(app.ASSE,'on'); else, box(app.ASSE,'off'); grid(app.ASSE,'off'); end 
                if(~app.Petition.ShowStats)
                    set(app.Permannotation,'String','');
                    SA=0;
                    set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                    set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
                else
                    SA=size(app.TabellaY.Data,1)+1;
                    set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                    set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
                end
            end
        end
        
        function moveAxisNearToMe(app)
            app.CheckExistenceMakeAxes();
            switch app.PositionDropDown.Value
                case 'Right'
                    PGRNewPosition=[app.ScalarvsScalarUIFigure.Position(1)+app.ScalarvsScalarUIFigure.Position(3)+1,app.ScalarvsScalarUIFigure.Position(2),app.PGR.Position(3),app.PGR.Position(4)];
                case 'Above'
                    PGRNewPosition=[app.ScalarvsScalarUIFigure.Position(1),app.ScalarvsScalarUIFigure.Position(2)+app.ScalarvsScalarUIFigure.Position(4)+1,app.PGR.Position(3),app.PGR.Position(4)];
                case 'Left'
                    PGRNewPosition=[app.ScalarvsScalarUIFigure.Position(1)-app.PGR.Position(3)-1,app.ScalarvsScalarUIFigure.Position(2),app.PGR.Position(3),app.PGR.Position(4)];
                case 'Below'
                    PGRNewPosition=[app.ScalarvsScalarUIFigure.Position(1),app.ScalarvsScalarUIFigure.Position(2)-app.PGR.Position(4)-1,app.PGR.Position(3),app.PGR.Position(4)];
            end
            app.PGR.Position=PGRNewPosition;
            figure(app.PGR);
        end
        
        function void = getAppColors(app)
            void=0;
            app.color.Idle=app.AxesFigureButton.BackgroundColor;
            app.color.On=[0,1,0];
            app.color.Off=[1,0,0];
            app.color.Wait=[1,1,0];
            app.color.logbook=[0.35,0.86,1];
            app.color.logbook2Steps=[0.3,0.5,1];
        end
        
        function UpdateInitialize(app,CV)
            app.LogButton.Text=['','-> Log'];
            app.LogButton.Enable='Off';
            if(~isequal(CV.Acquisition,app.DataStructure))
                app.DataStructure=CV.Acquisition;
                app.Petition.Logbook=0;
                app.LogbookButton.BackgroundColor=app.color.logbook;
                List=app.EvaluateAllLists();
                %List.SynchScalars List.SynchScalarsPositions 
                %List.Scalars List.ScalarsPositions
                %List.Vectors List.VectorsPositions
                %List.Array2D List.Array2DPositions
                
                app.ManageFilterTable(app.TABULA,List);
                app.ManageScalarsTable(app.TabellaY,List);
                app.ManageScalarsSelection(app.S_Scalar,List,'S_SEL');
                app.ManageFitVisibility(1);
                app.ManageOtherInputs();
                app.ManageTypeOfPlotVisibility(1);
  
                if(isinf(app.Petition.xLim(1))), app.XMinEditField.Value=''; else, app.XMinEditField.Value=num2str(app.Petition.xLim(1)); end
                if(isinf(app.Petition.xLim(2))), app.XMaxEditField.Value=''; else, app.XMaxEditField.Value=num2str(app.Petition.xLim(2)); end
                if(isinf(app.Petition.yLim(1))), app.YMinEditField.Value=''; else, app.YMinEditField.Value=num2str(app.Petition.yLim(1)); end
                if(isinf(app.Petition.yLim(2))), app.YMaxEditField.Value=''; else, app.YMaxEditField.Value=num2str(app.Petition.yLim(2)); end
                
                if(~app.Petition.STOP)
                    app.STOP.Text='Now Running -> Stop Updating';
                    app.STOP.BackgroundColor=app.color.On;
                else
                    app.STOP.Text='Now Stopped -> Resume Updating';
                    app.STOP.BackgroundColor=app.color.Wait;
                end

                if(app.Petition.Save), app.SaveDataCheckBox.Value=1; else, app.SaveDataCheckBox.Value=0; end
                
                if(app.Petition.TwoStepLog), app.EditTextFigCheckBox.Value=1; else, app.EditTextFigCheckBox.Value=0; end
            end
            app.NeedInit=0;
        end
        
        function ManageSynchScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.SynchScalars(:).';
            Bottone.ItemsData=1:length(List.SynchScalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.SynchScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageNonTimestampedScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.NotTimeStampedScalars(:).';
            Bottone.ItemsData=1:length(List.NotTimeStampedScalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.NotTimeStampedScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function List=EvaluateAllLists(app)
           List.SynchScalars=app.DataStructure.Scalars;
           List.SynchScalarsPositions=app.DataStructure.Scalars_Positions;
           Delete=false(size(List.SynchScalarsPositions(:,1)));
           for AA=1:length(app.DataStructure.TimeStamped)
               if(~app.DataStructure.TimeStamped(AA))
                   Delete=Delete | (List.SynchScalarsPositions(:,1)==AA);
               end
           end
           List.NotTimeStampedScalars=List.SynchScalars(Delete);
           List.NotTimeStampedScalarsPositions=List.SynchScalarsPositions(Delete,:);
           List.NotTimeStampedScalars=['OFF';List.NotTimeStampedScalars];
           List.NotTimeStampedScalarsPositions=[[0,0,0];List.NotTimeStampedScalarsPositions];
           List.SynchScalars(Delete)=[];
           List.SynchScalarsPositions(Delete,:)=[];
           List.SynchScalars=['OFF';List.SynchScalars];
           List.SynchScalarsPositions=[[0,0,0];List.SynchScalarsPositions];
           List.Scalars=['OFF';app.DataStructure.Scalars];
           List.ScalarsPositions=[0,0,0;app.DataStructure.Scalars_Positions];
           List.Vectors=['OFF';app.DataStructure.Vectors];
           List.VectorsPositions=[0,0,0;app.DataStructure.Vectors_Positions];
           List.Arrays2D=['OFF';app.DataStructure.Arrays2D];
           List.Arrays2DPositions=[0,0,0;app.DataStructure.Arrays2D_Positions];
        end

        
        function ManageFilterTable(app,FilterTable,List)
            TAB_CF=FilterTable.ColumnFormat;
            TAB_CF{1}=List.SynchScalars(:).';
            TAB_CF{2}='numeric'; TAB_CF{3}='numeric'; TAB_CF{4}='logical'; TAB_CF{5}='logical';
            FilterTable.ColumnFormat=TAB_CF;
            FilterTable.ColumnEditable=[true,true,true,true,true];
            for II=app.Petition.FiltriNumber:-1:1
                IDPOS=find(strcmp(List.SynchScalars,app.Petition.FilterVarName{II}));
                if(isempty(IDPOS)) %needs to remove the filer, the variable is not recorded anymore
                    app.Petition.FiltriNumber=app.Petition.FiltriNumber-1;
                    app.Petition.Filter(II)=[];
                    app.Petition.FilterVarName(II)=[];
                    app.Petition.FilterUse(II)=[];
                else
                    IDPOS=IDPOS(1); %rebuild the filter with new position, even if it is the same one.
                    app.Petition.Filter{II}.SEL=List.SynchScalarsPositions(IDPOS,:);
                end
            end
            
            TABULA_DATA={};
            for II=1:app.Petition.FiltriNumber
                TABULA_DATA{II,1}=app.Petition.FilterVarName{II}; %#ok<AGROW>
                if(isinf(app.Petition.Filter{II}.Range(1)))
                    TABULA_DATA{II,2}=[]; %#ok<AGROW>
                else
                    if(~isnan(app.Petition.Filter{II}.Range(1)))
                        TABULA_DATA{II,2}=app.Petition.Filter{II}.Range(1); %#ok<AGROW>
                    else
                        app.Petition.Filter{II}.Range(1)=+inf;
                        TABULA_DATA{II,2}=[]; %#ok<AGROW>
                    end
                end
                if(isinf(app.Petition.Filter{II}.Range(2)))
                    TABULA_DATA{II,3}=[]; %#ok<AGROW>
                else
                    if(~isnan(app.Petition.Filter{II}.Range(2)))
                        TABULA_DATA{II,3}=app.Petition.Filter{II}.Range(2); %#ok<AGROW>
                    else
                        app.Petition.Filter{II}.Range(2)=+inf;
                        TABULA_DATA{II,3}=[]; %#ok<AGROW>
                    end
                end
                TABULA_DATA{II,4}=app.Petition.FilterUse{II}(1); %#ok<AGROW>
                TABULA_DATA{II,5}=app.Petition.FilterUse{II}(2);  %#ok<AGROW>
            end
          
            FilterTable.Data=TABULA_DATA;
            ReducedList.Positions=List.SynchScalarsPositions;
            ReducedList.Names=List.SynchScalars;
            FilterTable.UserData=ReducedList;
        end
        
        function ManageScalarsTable(app,TAB,List)
            TAB.ColumnName={'Filter 1','Filter 2','Scalar Variable Name','Style','Expression','LastN'};
            TAB_CF=TAB.ColumnFormat;
            TAB_CF{3}=List.Scalars(:).';
            TAB.ColumnFormat=TAB_CF;
            TAB.ColumnEditable=[true,true,true,true,true,true];
            InitData{1,1}=true;InitData{1,2}=true;InitData{1,3}=List.SynchScalars{1};InitData{1,4}='.b';InitData{1,5}='';InitData{1,6}=inf;
            TAB.Data=InitData;
            ins=0;
%             List.Scalars=['OFF';app.DataStructure.Scalars];
%             List.ScalarsPositions=[0,0,0;app.DataStructure.Scalars_Positions];
            if(~isempty(app.Petition.Quantity))
                FoundNone=1;
                for II=1:length(app.Petition.Quantity)
                    %Check if variable still exists...
                    ID=find(strcmp(app.Petition.Quantity(II).Name,List.Scalars));
                    if(isempty(ID))
                        app.Petition.Quantity(II)=[];
                    else
                        FoundNone=0; ins=ins+1;
                        NewQuantity(ins).Filter(1)=app.Petition.Quantity(II).Filter(1); %#ok<AGROW>
                        NewQuantity(ins).Filter(2)=app.Petition.Quantity(II).Filter(2); %#ok<AGROW>
                        NewQuantity(ins).Name=app.Petition.Quantity(II).Name; %#ok<AGROW>
                        NewQuantity(ins).Y_SEL=List.ScalarsPositions(ID,:); %#ok<AGROW>
                        NewQuantity(ins).Stilnovo=app.Petition.Quantity(II).Stilnovo; %#ok<AGROW>
                        TAB.Data{ins,1}=logical(app.Petition.Quantity(II).Filter(1)); 
                        TAB.Data{ins,2}=logical(app.Petition.Quantity(II).Filter(2));
                        TAB.Data{ins,3}=app.Petition.Quantity(II).Name; TAB.Data{ins,4}=app.Petition.Quantity(II).Stilnovo;
                        TAB.Data{ins,5}=''; NewQuantity(ins).Function=''; %#ok<AGROW>
                        NewQuantity(ins).LastN=app.Petition.Quantity(II).LastN; %#ok<AGROW>
                        TAB.Data{ins,6}=app.Petition.Quantity(II).LastN;
                    end
                end
                if(FoundNone)
                    TAB.Data{1,1}=true;TAB.Data{1,2}=true;TAB.Data{1,4}='.b';TAB.Data{1,5}='';TAB.Data{1,6}=inf;
                    TAB.Data{1,3}=List.SynchScalars{1}; app.Petition.Quantity=[];
                else
                    app.Petition.Quantity=NewQuantity;
                end
            else
                TAB.Data{1,1}=true;TAB.Data{1,2}=true;TAB.Data{1,4}='.b';TAB.Data{1,5}='';TAB.Data{1,6}=inf; 
                TAB.Data{1,3}=List.SynchScalars{1}; app.Petition.Quantity=[];
            end
            
            if(app.DataStructure.Synchronous)
                CE=TAB.ColumnEditable; CE(1)=true; CE(2)=true;
                TAB.ColumnEditable=CE;
            else
                CE=TAB.ColumnEditable; CE(1)=false; CE(2)=false;
                TAB.ColumnEditable=CE;
                TAB.Data(:,1:2)={false};
            end
            ReducedList.Positions=List.ScalarsPositions;
            ReducedList.Names=List.Scalars;
            TAB.UserData=ReducedList;
        end
        
        function ManageScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Scalars(:).';
            Bottone.ItemsData=1:length(List.Scalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.ScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end

        function ManageVectorsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Vectors(:).';
            Bottone.ItemsData=1:length(List.Vectors);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.VectorsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageArrays2DSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Arrays2D(:).';
            Bottone.ItemsData=1:length(List.Arrays2D);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.Arrays2DPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageListChange(app,Bottone)
            app.Petition.(Bottone.UserData.PetitionName) = Bottone.UserData.Pos(Bottone.Value,:);
        end
        
        function UpdateTableSelection(app)
            ins=0;
            for II=1:size(app.TabellaY.Data,1)
                QTY = find(strcmp(app.TabellaY.Data{II,3},app.DataStructure.Scalars));
                if(isempty(QTY))
                    continue
                else
                    ins=ins+1;
                    Quantity(ins).Y_SEL=app.DataStructure.Scalars_Positions(QTY,:); %#ok<AGROW> 
                end
                Quantity(ins).Filter(1)=app.TabellaY.Data{II,1}; %#ok<AGROW> 
                Quantity(ins).Filter(2)=app.TabellaY.Data{II,2}; %#ok<AGROW> 
                Quantity(ins).Name=app.TabellaY.Data{II,3}; %#ok<AGROW> 
                Quantity(ins).Stilnovo=app.TabellaY.Data{II,4}; %#ok<AGROW> 
                if(isempty(app.TabellaY.Data{II,5}))
                    Quantity(ins).Function=''; %#ok<AGROW> 
                else
                    Quantity(ins).Function=eval(['@(x) ',vectorize(app.TabellaY.Data{II,5})]); %#ok<AGROW> 
                end
                Quantity(ins).LastN=app.TabellaY.Data{II,6}; %#ok<AGROW> 
            end
            if(ins==0)
                app.Petition.Quantity=[];
            else
                app.Petition.Quantity=Quantity;
            end
        end
        
        function UpdateFilterSelection(app)
            app.Petition.Filter={};app.Petition.FilterUse={}; app.Petition.FilterVarName={}; app.Petition.FiltriNumber=0;
            for II=1:size(app.TABULA.Data,1)
                if((~isempty(app.TABULA.Data{II,2}) && ~isnan(app.TABULA.Data{II,2})) || (~isempty(app.TABULA.Data{II,3}) && ~isnan(app.TABULA.Data{II,3}))) %there is something
                    VAL=app.DataStructure.Scalars_Positions(strcmp(app.TABULA.Data{II,1},app.DataStructure.Scalars),:);
                    if(isempty(VAL))
                        continue
                    end
                    if(~isempty(app.TABULA.Data{II,2}) && ~isnan(app.TABULA.Data{II,2}))
                        MIN=app.TABULA.Data{II,2};
                    else
                        MIN=-inf;
                    end
                    if(~isempty(app.TABULA.Data{II,3}) && ~isnan(app.TABULA.Data{II,3}))
                        MAX=app.TABULA.Data{II,3};
                    else
                        MAX=+inf;
                    end
                    app.Petition.Filter{end+1}.SEL=VAL; app.Petition.Filter{end}.Range=[MIN,MAX];
                    app.Petition.FiltriNumber=app.Petition.FiltriNumber+1;
                    app.Petition.FilterUse{end+1}(1)=app.TABULA.Data{II,4}; app.Petition.FilterUse{end}(2)=app.TABULA.Data{II,5};
                    app.Petition.FilterVarName{end+1}=app.TABULA.Data{II,1};
                end
            end
        end
        
        function logBookButtonCall(app,CVObject)
            TimeNow=now;
            Author=app.AuthorEditField.Value; Title=app.TitleEditField.Value; Comment=app.commentEditField.Value; tablestring=app.RetrieveTableStrings();
            app.Petition.Author=Author; app.Petition.Title=Title; app.Petition.Comment=Comment;
            if(app.SaveDataCheckBox.Value)
                auxillaryData = additionalSavingInformation(app);
                [SUCCESS,filename,~]=app.writeDataToDisk(TimeNow,CVObject,auxillaryData);
                if(SUCCESS)
                    app.SaveLabel.Text=['Last: OK ,',datestr(TimeNow,'yyyy, mmm dd, HH:MM')];
                else
                    app.SaveLabel='Saving Failed';
                    filename='File saving failed.';
                end
            else
                filename='No file was saved';
            end

            if(app.EditTextFigCheckBox.Value) %Two Steps logbook. Make a copy of the figure and open edit boxes.
                NewFigure=figure;
                NewFigure.Position=app.PGR.Position;
                app.DuplicateFigure(app.PGR,NewFigure);
                if(isfield(CVObject.Acquisition,'filename'))
                    annotation(NewFigure,'textbox',[0 .92 1 .08],'string',CVObject.Acquisition.filename,'EdgeColor','none','HorizontalAlignment','center');
                else
                    if(app.SaveDataCheckBox.Value)
                        annotation(NewFigure,'textbox',[0 .92 1 .08],'string',filename,'EdgeColor','none','HorizontalAlignment','center');
                    else
                        annotation(NewFigure,'textbox',[0 .92 1 .08],'string','Image logbook only, data not saved','EdgeColor','none','HorizontalAlignment','center');
                    end
                end
                app.LogButton.Text=[num2str(NewFigure.Number),'-> Log'];
                app.LogButton.Enable='On';
                app.LogButton.UserData=NewFigure;
                app.EditTextFigCheckBox.UserData=filename;
            else
                app.SendImageToLogbook(app.PGR, Author, Title, Comment, filename, tablestring);
            end          
        end
        
        function auxillaryData = additionalSavingInformation(app) %#ok<MANU> 
            auxillaryData=app.Petition;
        end
        
        function DuplicateFigure(app,OriginalFigure,NewFigure) %#ok<INUSL> 
            children_handler_original = get(OriginalFigure, 'children');
            allchildren = allchild(OriginalFigure);
            if isempty(children_handler_original)
                return
            else
                new_handler_copy = copyobj(children_handler_original,NewFigure);
                for KK=1:length(new_handler_copy)
                    if(strcmpi(new_handler_copy(KK).Type,'axes'))
                        delete(axtoolbar(new_handler_copy(KK)));
                    end
                end
                for AA=1:length(allchildren)
                    if(strcmpi(allchildren(AA).Type,'annotationpane'))
                        copyobj(allchildren(AA),NewFigure);
                    end
                end
            end
        end
        
        function DeepDuplicateFigure(app,OriginalFigure,NewFigure)
            children_handler_original = get(OriginalFigure, 'children');
            if isempty(children_handler_original)
                return
            else
                new_handler_copy = copyobj(children_handler_original,NewFigure);
                for KK=1:length(children_handler_original)
                    app.DuplicateFigure(children_handler_original(KK),new_handler_copy(KK));
                end
            end
        end
        
        function ManageFitVisibility(app,CopyParameters)
            switch(app.TypeofFitDropDown.Value)
                case 'No Fit'
                    app.P1.Visible='off'; app.P2.Visible='off';
                    app.Petition.TypeOfFit=1;
                case 'Polynomial'
                    app.P1.Visible='on'; app.P2.Visible='off';
                    app.Petition.TypeOfFit=2;
                case 'Gaussian'
                    app.P1.Visible='off'; app.P2.Visible='on';
                    app.Petition.TypeOfFit=3;
            end
            if(CopyParameters)
                if(isnan(app.Petition.A)), app.AEditField.Value=''; else, app.AEditField.Value=num2str(app.Petition.A); end
                if(isnan(app.Petition.B)), app.BEditField.Value=''; else, app.BEditField.Value=num2str(app.Petition.B); end
                if(isnan(app.Petition.C)), app.CEditField.Value=''; else, app.CEditField.Value=num2str(app.Petition.C); end
                if(isnan(app.Petition.x0)), app.x0EditField.Value=''; else, app.x0EditField.Value=num2str(app.Petition.x0); end
                if(isnan(app.Petition.y0)), app.y0EditField.Value=''; else, app.y0EditField.Value=num2str(app.Petition.y0); end
                if(isnan(app.Petition.PolyOrder)), app.OrderPolyFitEdit.Value=''; else, app.OrderPolyFitEdit.Value=num2str(app.Petition.PolyOrder); end
            end
        end
        
        function ManageTypeOfPlotVisibility(app, CopyParameters)
            switch(app.TypeofPlotDropDown.Value)
                case 'Dots Plot'
                    app.BinsXEditField.Visible='off'; app.BinsXEditFieldLabel.Visible='off';
                    app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
                    app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
                    app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
                    app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
                case 'Density Plot'
                    app.BinsXEditField.Visible='on'; app.BinsXEditFieldLabel.Visible='on';
                    app.BinsYEditField.Visible='on'; app.BinsYEditFieldLabel.Visible='on';
                    app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
                    app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
                    app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
                case 'Partition on X'
                    app.BinsXEditField.Visible='off'; app.BinsXEditFieldLabel.Visible='off';
                    app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
                    app.PartitionCenterEditField.Visible='on'; app.PartitionCenterEditFieldLabel.Visible='on';
                    app.PartitionWidthEditField.Visible='on'; app.PartitionWidthEditFieldLabel.Visible='on';
                    app.ShowonlyEditField.Visible='on'; app.ShowonlyEditFieldLabel.Visible='on';
                case '3D XYZ Plot'
                    app.BinsXEditField.Visible='off'; app.BinsXEditFieldLabel.Visible='off';
                    app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
                    app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
                    app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
                    app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
                case 'Histogram'
                    app.BinsXEditField.Visible='on'; app.BinsXEditFieldLabel.Visible='on';
                    app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
                    app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
                    app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
                    app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
            end
            if(CopyParameters)
                if(isnan(app.Petition.PartitionPos(1))), app.PartitionCenterEditField.Value=''; 
                else
                    string='[';
                    for II=1:length(app.Petition.PartitionPos)
                        string=[string,num2str(app.Petition.PartitionPos(II))]; %#ok<AGROW> 
                        if(II~=length(app.Petition.PartitionPos))
                            string=[string,',']; %#ok<AGROW> 
                        else
                            string=[string,']']; %#ok<AGROW> 
                        end
                    end
                    app.PartitionCenterEditField.Value=string; 
                end
                if(isnan(app.Petition.PartitionWidth(1))), app.PartitionWidthEditField.Value=''; 
                else
                    string='[';
                    for II=1:length(app.Petition.PartitionWidth)
                        string=[string,num2str(app.Petition.PartitionWidth(II))]; %#ok<AGROW> 
                        if(II~=length(app.Petition.PartitionWidth))
                            string=[string,',']; %#ok<AGROW> 
                        else
                            string=[string,']']; %#ok<AGROW> 
                        end
                    end
                    app.PartitionWidthEditField.Value=string; 
                end
                if(isnan(app.Petition.ShowOnly(1))), app.ShowonlyEditField.Value=''; 
                else
                    string='[';
                    for II=1:length(app.Petition.ShowOnly)
                        string=[string,num2str(app.Petition.ShowOnly(II))]; %#ok<AGROW> 
                        if(II~=length(app.Petition.ShowOnly))
                            string=[string,',']; %#ok<AGROW> 
                        else
                            string=[string,']']; %#ok<AGROW> 
                        end
                    end
                    app.ShowonlyEditField.Value=string; 
                end
                if(isnan(app.Petition.binsx)), app.BinsXEditField.Value=''; else, app.BinsXEditField.Value=num2str(app.Petition.binsx); end
                if(isnan(app.Petition.binsy)), app.BinsYEditField.Value=''; else, app.BinsYEditField.Value=num2str(app.Petition.binsy); end
            end
        end
        
        function ManageOtherInputs(app)
            if(isnan(app.Petition.binsx)), app.BinsXEditField.Value=''; else, app.BinsXEditField.Value=num2str(app.Petition.binsx); end
            if(isnan(app.Petition.binsy)), app.BinsYEditField.Value=''; else, app.BinsYEditField.Value=num2str(app.Petition.binsy); end
            app.Petition.AutoBinPartition=0;
            if(isnan(app.Petition.AutoBinNumber)), app.AutoPartition.Value='7';app.Petition.AutoBinNumber=1; else, app.AutoPartition.Value=num2str(app.Petition.AutoBinNumber); end
            app.ShowstatsCheckBox.Value=app.Petition.ShowStats;
        end
        
        function SendImageToLogbook(app, FigureHandle, Author, Title, Comment, Filename,additionalstring)
            Comment=[Comment,char(13),'Filename: ', Filename];
            for II=1:numel(additionalstring)
                if(~isempty(additionalstring{II}))
                    Comment=[Comment,char(13),additionalstring{II}]; %#ok<AGROW> 
                end
            end
            try
                util_printLog(FigureHandle,'title',Title,'author',Author,'text',Comment);
                disp('Image sent successfully to logbook');
            catch
                disp('Failed to send image to logbook');
            end
        end
        
        function tablestring = RetrieveTableStrings(app)
            tablestring{1}=['X - Variable: ' app.S_Scalar.Items{app.S_Scalar.Value}];
            SA=size(app.TabellaY.Data,1);SB=size(app.TABULA.Data,1);
            if(SA>0)
                TAB=app.TabellaY.Data;
                tablestring{end+1}='Y - Variables';
                for II=1:SA
                    tablestring{end+1}=['Var: ',TAB{II,3},'   style: ',TAB{II,4},'   last: ',num2str(TAB{II,6})];
                    if(~isempty(TAB{II,5})), tablestring{end} = [tablestring{end}, '   expr: ',TAB{II,5}]; end
                    if(SB>0)
                        if(TAB{II,1})
                            tablestring{end} = [tablestring{end}, '   filter 1 &'];
                        else
                            tablestring{end} = [tablestring{end}, 'Not Filter 1 &'];
                        end
                        if(TAB{II,2})
                            tablestring{end} = [tablestring{end}, ' filter 2'];
                        else
                            tablestring{end} = [tablestring{end}, ' Not filter 2'];
                        end
                    end
                end
            end
            if(SB>0)
                tablestring{end+1}='Filters';
                TABF=app.TABULA.Data;
                for II=1:SB
                    tablestring{end+1}=['Var: ',TABF{II,1},'   From: ',num2str(TABF{II,2}),'   To: ',num2str(TABF{II,3})];
                    if(TABF{II,4})
                        tablestring{end} = [tablestring{end}, '   1:YES'];
                    else
                        tablestring{end} = [tablestring{end}, '   1:NO '];
                    end
                    if(TABF{II,5})
                        tablestring{end} = [tablestring{end}, ' 2:YES'];
                    else
                        tablestring{end} = [tablestring{end}, ' 2:NO '];
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FIELDNAMES=fieldnames(app);
            app.PGR=figure('Name',[app.(app.FIELDNAMES{(cellfun(@(x) any(x),strfind(fieldnames(app),'UIFigure')))}).Name,' *-* Display']);
            app.ASSE=axes(app.PGR,'Position',[0.13,0.1,0.7750,0.8150]);
            app.Permannotation=annotation(app.PGR,'textbox',[0 0 1 0.7],'EdgeColor','none','HorizontalAlignment','left','Interpreter','latex');
            app.ASSE.Interactions=[];app.ASSE.Toolbar=[];
            app.PGR.MenuBar='none';app.PGR.ToolBar='none'; app.ASSE.NextPlot='replacechildren';
            app.ASSEDefaultSize=app.PGR.Position(3:4);
            if(app.BoxGridCheckBox.Value), box(app.ASSE,'on'); grid(app.ASSE,'on'); else, box(app.ASSE,'off'); grid(app.ASSE,'off'); end 
            app.moveAxisNearToMe();
            app.Petition.xLim=[-inf,inf];
            app.Petition.yLim=[-inf,inf];
            app.getAppColors();
            app.LogbookButton.BackgroundColor=app.color.logbook;
            app.Petition.STOP=0;
            app.Petition.UseAbsoluteTime = 1;
            app.Petition.Save = 1;
            app.Petition.TwoStepLog= 0;
            app.Petition.FiltriNumber=0;
            app.Petition.Quantity=[];
            app.Petition.S_SEL=[0,0,0];
            app.Petition.V_SEL=[0,0,0];
            app.Petition.I_SEL=[0,0,0];
            app.Petition.LastSeconds=[];
            app.Petition.Pid_Delay=0;
            app.Petition.A=NaN;
            app.Petition.B=NaN;
            app.Petition.C=2;
            app.Petition.x0=NaN;
            app.Petition.y0=NaN;
            app.Petition.PolyOrder=2;
            app.Petition.binsx=25;
            app.Petition.binsy=25;
            app.Petition.AutoBinPartition=1;
            app.Petition.AutoBinNumber=7;
            app.Petition.PartitionPos=NaN;
            app.Petition.PartitionWidth=NaN;
            app.Petition.ShowOnly=NaN;
            app.Petition.TypeOfPlot=1;
            app.Petition.TypeOfFit=1;
            app.Petition.Function='';
            app.Petition.ShowStats=0;
        end

        % Value changed function: XMinEditField
        function XMinEditFieldValueChanged(app, event)
            app.UpdateLimits('x');
        end

        % Value changed function: XMaxEditField
        function XMaxEditFieldValueChanged(app, event)
            app.UpdateLimits('x');
        end

        % Value changed function: YMinEditField
        function YMinEditFieldValueChanged(app, event)
            app.UpdateLimits('y');
        end

        % Value changed function: YMaxEditField
        function YMaxEditFieldValueChanged(app, event)
            app.UpdateLimits('y');
        end

        % Button pushed function: AxesFigureButton
        function AxesFigureButtonPushed(app, event)
            app.CheckExistenceMakeAxes();
        end

        % Button pushed function: MoveAxesNearButton
        function MoveAxesNearButtonPushed(app, event)
            app.moveAxisNearToMe();
        end

        % Button pushed function: DefaultAxesSizeButton
        function DefaultAxesSizeButtonPushed(app, event)
            app.PGR.Position(3:4)=app.ASSEDefaultSize;
        end

        % Button pushed function: LogbookButton
        function LogbookButtonPushed(app, event)
            app.LogbookButton.BackgroundColor=app.color.On;
            app.Petition.Logbook=1;
        end

        % Button pushed function: STOP
        function STOPButtonPushed(app, event)
            if(app.Petition.STOP)
                app.Petition.STOP=false; app.STOP.Text='Now Running -> Stop Updating'; app.STOP.BackgroundColor=app.color.On; drawnow
            else
                app.Petition.STOP=true; app.STOP.Text='Now Stopped -> Resume Updating'; app.STOP.BackgroundColor=app.color.Wait; drawnow
            end
        end

        % Callback function
        function UsetimeCheckBoxValueChanged(app, event)
            app.Petition.UseAbsoluteTime = app.UsetimeCheckBox.Value;
        end

        % Value changed function: SaveDataCheckBox
        function SaveDataCheckBoxValueChanged(app, event)
            app.Petition.Save = app.SaveDataCheckBox.Value;
        end

        % Value changed function: EditTextFigCheckBox
        function EditTextFigCheckBoxValueChanged(app, event)
            app.Petition.TwoStepLog= app.EditTextFigCheckBox.Value;
        end

        % Value changed function: S_Scalar
        function S_ScalarValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Callback function
        function S_VectorValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Callback function
        function S_ImageValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Close request function: ScalarvsScalarUIFigure
        function ScalarvsScalarUIFigureCloseRequest(app, event)
            try delete(app.PGR); end %#ok<TRYNC> 
            delete(app);
        end

        % Cell edit callback: TabellaY
        function TabellaYCellEdit(app, event)
            %indices = event.Indices; newData = event.NewData;
            ID=find(strcmp(event.NewData,app.DataStructure.Scalars),1, 'first');
            if(~isempty(ID))
                if(~app.DataStructure.TimeStamped(app.DataStructure.Scalars_Positions(ID,1))) %not timestamped, defaults as non scatter plot like
                    try app.TabellaY.Data{event.Indices(1),4}=regexprep(regexprep(app.TabellaY.Data{event.Indices(1),4},'\.','-'),'b','r'); end %#ok<TRYNC> 
                end
            end
            app.UpdateTableSelection();
        end

        % Button pushed function: VarAddLine
        function VarAddLineButtonPushed(app, event)
            SA=size(app.TabellaY.Data,1);
            switch(SA)
                case 0
                    typestring='.b';
                case 1
                    typestring='.r';
                case 2
                    typestring='.g';
                case 3
                    typestring='.k';
                case 4
                    typestring='.m';
                case 5
                    typestring='.c';
                case 6
                    typestring='.y';
                otherwise
                    typestring='.b';
            end
            if(app.DataStructure.Synchronous)
                NewLine={true,true,'OFF',typestring,'',inf};
            else
                NewLine={false,false,'OFF',typestring,'',inf};
            end
            app.TabellaY.Data(end+1,:)=NewLine;
            if(~app.Petition.ShowStats)
                set(app.Permannotation,'String','');
                SA=0;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            else
                SA=size(app.TabellaY.Data,1)+1;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            end
        end

        % Button pushed function: VarRemoveLine
        function VarRemoveLineButtonPushed(app, event)
            if(~isempty(app.VarRemoveLine.UserData))
                toBeRemoved=unique(app.VarRemoveLine.UserData(:,1),'sorted');
                for II=size(app.TabellaY.Data,1):(-1):1
                    if(any(toBeRemoved==II))
                        app.TabellaY.Data(II,:)=[];
                    end
                end
            end
            app.UpdateTableSelection();
            if(~app.Petition.ShowStats)
                set(app.Permannotation,'String','');
                SA=0;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            else
                SA=size(app.TabellaY.Data,1)+1;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            end
        end

        % Cell selection callback: TabellaY
        function TabellaYCellSelection(app, event)
            indices = event.Indices;
            app.VarRemoveLine.UserData=indices;
        end

        % Button pushed function: FilterAddLine
        function FilterAddLineButtonPushed(app, event)
            NewLine{1,1}=app.TABULA.ColumnFormat{1}{1};
            NewLine{1,2}=[];NewLine{1,3}=[];NewLine{1,4}=true;NewLine{1,5}=true;
            app.TABULA.Data(end+1,:)=NewLine;
        end

        % Button pushed function: FilterRemoveLine
        function FilterRemoveLineButtonPushed(app, event)
            app.FilterRemoveLine.UserData
            if(~isempty(app.FilterRemoveLine.UserData))
                toBeRemoved=unique(app.FilterRemoveLine.UserData(:,1),'sorted');
                for II=size(app.TABULA.Data,1):(-1):1
                    if(any(toBeRemoved==II))
                        app.TABULA.Data(II,:)=[];
                    end
                end
            end
            app.UpdateFilterSelection();
        end

        % Cell selection callback: TABULA
        function TABULACellSelection(app, event)
            indices = event.Indices;
            app.FilterRemoveLine.UserData=indices;
        end

        % Cell edit callback: TABULA
        function TABULACellEdit(app, event)
            app.UpdateFilterSelection();
        end

        % Callback function
        function secondssamplesEditFieldValueChanged(app, event)
            value=app.secondssamplesEditField.Value;
            VAL=str2double(value);
            if(VAL<=0 || isnan(VAL))
                app.secondssamplesEditField.Value='';
                app.Petition.LastSeconds=[];
            else
                app.Petition.LastSeconds=VAL;
            end
        end

        % Value changed function: TypeofFitDropDown
        function TypeofFitDropDownValueChanged(app, event)
            app.ManageFitVisibility(0);
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            app.Petition.AutoBinPartition=1;
            Value=str2num(app.AutoPartition.Value);
            Value=round(Value(1));
            if(Value<1)
                Value=1;
                app.AutoPartition.Value='1';
            end
            app.Petition.AutoBinNumber=Value;
        end

        % Value changed function: ExpressionEditField
        function ExpressionEditFieldValueChanged(app, event)
            value = app.ExpressionEditField.Value;
            if(isempty(value)), app.Petition.Function=''; else 
                app.Petition.Function=eval(['@(x) ',vectorize(value)]);
            end
        end

        % Value changed function: ShowstatsCheckBox
        function ShowstatsCheckBoxValueChanged(app, event)
            app.Petition.ShowStats=app.ShowstatsCheckBox.Value;
            if(~app.Petition.ShowStats)
                set(app.Permannotation,'String','');
                SA=0;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            else
                SA=size(app.TabellaY.Data,1)+1;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            end
        end

        % Value changed function: TypeofPlotDropDown
        function TypeofPlotDropDownValueChanged(app, event)
            switch(app.TypeofPlotDropDown.Value)
                case 'Dots Plot'
                    app.Petition.TypeOfPlot=1;
                case 'Density Plot'
                    app.Petition.TypeOfPlot=2;
                case 'Partition on X'
                    app.Petition.TypeOfPlot=3;
                case 'Histogram'
                    app.Petition.TypeOfPlot=4;
            end
            app.ManageTypeOfPlotVisibility(0);
        end

        % Value changed function: PartitionCenterEditField
        function PartitionCenterEditFieldValueChanged(app, event)
            strvalue = app.PartitionCenterEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.PartitionPos=NaN;
            else
                app.Petition.PartitionPos=numericlist;
            end
        end

        % Value changed function: PartitionWidthEditField
        function PartitionWidthEditFieldValueChanged(app, event)
            strvalue = app.PartitionWidthEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.PartitionWidth=NaN;
            else
                app.Petition.PartitionWidth=numericlist;
            end
        end

        % Value changed function: ShowonlyEditField
        function ShowonlyEditFieldValueChanged(app, event)
            strvalue = app.ShowonlyEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.ShowOnly=NaN;
            else
                numericlist=numericlist(~isnan(numericlist) && ~isinf(numericlist));
                numericlist=round(numericlist);
                numericlist=numericlist(numericlist>1);        
                app.Petition.ShowOnly=numericlist;
            end
        end

        % Value changed function: OrderPolyFitEdit
        function OrderPolyFitEditValueChanged(app, event)
            app.Petition.PolyOrder=str2double(app.OrderPolyFitEdit.Value);
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            app.LogButton.Text=['','-> Log'];
            app.LogButton.Enable='Off';
            Author=app.AuthorEditField.Value; Title=app.TitleEditField.Value; Comment=app.commentEditField.Value;
            try
                if(isempty(app.EditTextFigCheckBox.UserData))
                    app.EditTextFigCheckBox.UserData='';
                end
                app.SendImageToLogbook(app.LogButton.UserData, Author, Title, Comment, app.EditTextFigCheckBox.UserData,{''});
                disp('Image sent to logbook');
            catch
                disp('Failed to send image to logbook');
            end
            try %#ok<TRYNC> 
                close(app.LogButton.UserData);
            end
            app.LogButton.UserData=[];
        end

        % Value changed function: BoxGridCheckBox
        function BoxGridCheckBoxValueChanged(app, event)
            if(app.BoxGridCheckBox.Value)
                try %#ok<TRYNC> 
                    box(app.ASSE,'on'); grid(app.ASSE,'on');
                end
            else
                try %#ok<TRYNC> 
                    box(app.ASSE,'off'); grid(app.ASSE,'off');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScalarvsScalarUIFigure = uifigure('Visible', 'off');
            app.ScalarvsScalarUIFigure.Name = 'Scalar vs Scalar';
            app.ScalarvsScalarUIFigure.CloseRequestFcn = createCallbackFcn(app, @ScalarvsScalarUIFigureCloseRequest, true);
            app.P2 = uipanel(app.ScalarvsScalarUIFigure);
            app.P2.Title = 'Gaussian Fit';
            app.Aexpxx0C2B2y0Label = uilabel(app.P2);
            app.Aexpxx0C2B2y0Label.Text = 'A*exp(-(x-x0)^C/(2*B^2) )+y0';
            app.AEditFieldLabel = uilabel(app.P2);
            app.AEditFieldLabel.Text = 'A=';
            app.AEditField = uieditfield(app.P2, 'text');
            app.BEditFieldLabel = uilabel(app.P2);
            app.BEditFieldLabel.Text = 'B=';
            app.BEditField = uieditfield(app.P2, 'text');
            app.CEditFieldLabel = uilabel(app.P2);
            app.CEditFieldLabel.Text = 'C=';
            app.CEditField = uieditfield(app.P2, 'text');
            app.CEditField.Value = '2';
            app.x0EditFieldLabel = uilabel(app.P2);
            app.x0EditFieldLabel.Text = 'x0=';
            app.x0EditField = uieditfield(app.P2, 'text');
            app.y0EditFieldLabel = uilabel(app.P2);
            app.y0EditFieldLabel.Text = 'y0=';
            app.y0EditField = uieditfield(app.P2, 'text');
            app.XMinEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.XMinEditFieldLabel.Text = 'XMin';
            app.XMinEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.XMinEditField.ValueChangedFcn = createCallbackFcn(app, @XMinEditFieldValueChanged, true);
            app.XMaxEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.XMaxEditFieldLabel.Text = 'XMax';
            app.XMaxEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.XMaxEditField.ValueChangedFcn = createCallbackFcn(app, @XMaxEditFieldValueChanged, true);
            app.YMinEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.YMinEditFieldLabel.Text = 'YMin';
            app.YMinEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.YMinEditField.ValueChangedFcn = createCallbackFcn(app, @YMinEditFieldValueChanged, true);
            app.YMaxEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.YMaxEditFieldLabel.Text = 'YMax';
            app.YMaxEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.YMaxEditField.ValueChangedFcn = createCallbackFcn(app, @YMaxEditFieldValueChanged, true);
            app.AxesFigureButton = uibutton(app.ScalarvsScalarUIFigure, 'push');
            app.AxesFigureButton.ButtonPushedFcn = createCallbackFcn(app, @AxesFigureButtonPushed, true);
            app.AxesFigureButton.Text = 'Axes Figure';
            app.MoveAxesNearButton = uibutton(app.ScalarvsScalarUIFigure, 'push');
            app.MoveAxesNearButton.ButtonPushedFcn = createCallbackFcn(app, @MoveAxesNearButtonPushed, true);
            app.MoveAxesNearButton.Text = 'Move Axes Near';
            app.PositionDropDown = uidropdown(app.ScalarvsScalarUIFigure);
            app.PositionDropDown.Items = {'Right', 'Above', 'Left', 'Below'};
            app.PositionDropDown.Value = 'Right';
            app.DefaultAxesSizeButton = uibutton(app.ScalarvsScalarUIFigure, 'push');
            app.DefaultAxesSizeButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultAxesSizeButtonPushed, true);
            app.DefaultAxesSizeButton.Text = 'Default Axes Size';
            app.LogbookButton = uibutton(app.ScalarvsScalarUIFigure, 'push');
            app.LogbookButton.ButtonPushedFcn = createCallbackFcn(app, @LogbookButtonPushed, true);
            app.LogbookButton.Text = 'Logbook';
            app.SaveDataCheckBox = uicheckbox(app.ScalarvsScalarUIFigure);
            app.SaveDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDataCheckBoxValueChanged, true);
            app.SaveDataCheckBox.Text = 'Save Data';
            app.SaveDataCheckBox.Value = true;
            app.EditTextFigCheckBox = uicheckbox(app.ScalarvsScalarUIFigure);
            app.EditTextFigCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditTextFigCheckBoxValueChanged, true);
            app.EditTextFigCheckBox.Text = 'Edit Text/Fig';
            app.SaveLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.SaveLabel.Text = 'Last: XX/XX/XXXX';
            app.TABULA = uitable(app.ScalarvsScalarUIFigure);
            app.TABULA.ColumnName = {'Variable Name'; 'Minimum'; 'Maximum'; 'Filter1'; 'Filter 2'};
            app.TABULA.RowName = {};
            app.TABULA.CellEditCallback = createCallbackFcn(app, @TABULACellEdit, true);
            app.TABULA.CellSelectionCallback = createCallbackFcn(app, @TABULACellSelection, true);
            app.VariablestoplotonordinateLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.VariablestoplotonordinateLabel.Text = 'Variables to plot on ordinate';
            app.FilteringoptionsLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.FilteringoptionsLabel.Text = 'Filtering options:';
            app.TabellaY = uitable(app.ScalarvsScalarUIFigure);
            app.TabellaY.ColumnName = {'Filter1'; 'Filter2'; 'Variable Name'; 'Style'; 'Expression'; 'Last N'};
            app.TabellaY.RowName = {};
            app.TabellaY.CellEditCallback = createCallbackFcn(app, @TabellaYCellEdit, true);
            app.TabellaY.CellSelectionCallback = createCallbackFcn(app, @TabellaYCellSelection, true);
            app.AxiscontrolLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.AxiscontrolLabel.Text = 'Axis control:';
            app.STOP = uibutton(app.ScalarvsScalarUIFigure, 'push');
            app.STOP.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOP.Text = 'Now Running->Resume Updating';
            app.VarAddLine = uibutton(app.ScalarvsScalarUIFigure, 'push');
            app.VarAddLine.ButtonPushedFcn = createCallbackFcn(app, @VarAddLineButtonPushed, true);
            app.VarAddLine.Text = 'Add Line';
            app.VarRemoveLine = uibutton(app.ScalarvsScalarUIFigure, 'push');
            app.VarRemoveLine.ButtonPushedFcn = createCallbackFcn(app, @VarRemoveLineButtonPushed, true);
            app.VarRemoveLine.Text = 'Remove Line';
            app.FilterAddLine = uibutton(app.ScalarvsScalarUIFigure, 'push');
            app.FilterAddLine.ButtonPushedFcn = createCallbackFcn(app, @FilterAddLineButtonPushed, true);
            app.FilterAddLine.Text = 'Add Line';
            app.FilterRemoveLine = uibutton(app.ScalarvsScalarUIFigure, 'push');
            app.FilterRemoveLine.ButtonPushedFcn = createCallbackFcn(app, @FilterRemoveLineButtonPushed, true);
            app.FilterRemoveLine.Text = 'Remove Line';
            app.VariableonXLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.VariableonXLabel.Text = 'Variable on X';
            app.S_Scalar = uidropdown(app.ScalarvsScalarUIFigure);
            app.S_Scalar.ValueChangedFcn = createCallbackFcn(app, @S_ScalarValueChanged, true);
            app.PIDDelayEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.PIDDelayEditFieldLabel.Text = 'PID Delay';
            app.PIDDelayEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.PIDDelayEditField.Value = '0';
            app.TypeofPlotDropDownLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.TypeofPlotDropDownLabel.Text = 'Type of Plot';
            app.TypeofPlotDropDown = uidropdown(app.ScalarvsScalarUIFigure);
            app.TypeofPlotDropDown.Items = {'Dots Plot', 'Density Plot', 'Partition on X', 'Histogram'};
            app.TypeofPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofPlotDropDownValueChanged, true);
            app.TypeofPlotDropDown.Value = 'Dots Plot';
            app.TypeofFitDropDownLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.TypeofFitDropDownLabel.Text = 'Type of Fit';
            app.TypeofFitDropDown = uidropdown(app.ScalarvsScalarUIFigure);
            app.TypeofFitDropDown.Items = {'No Fit', 'Polynomial', 'Gaussian'};
            app.TypeofFitDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofFitDropDownValueChanged, true);
            app.TypeofFitDropDown.Value = 'No Fit';
            app.P1 = uipanel(app.ScalarvsScalarUIFigure);
            app.P1.Title = 'Polynomial Fit';
            app.OrderEditField_2Label = uilabel(app.P1);
            app.OrderEditField_2Label.Text = 'Order';
            app.OrderPolyFitEdit = uieditfield(app.P1, 'text');
            app.OrderPolyFitEdit.ValueChangedFcn = createCallbackFcn(app, @OrderPolyFitEditValueChanged, true);
            app.OrderPolyFitEdit.Value = '2';
            app.ExpressionEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.ExpressionEditFieldLabel.Text = 'Expression';
            app.ExpressionEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.ExpressionEditField.ValueChangedFcn = createCallbackFcn(app, @ExpressionEditFieldValueChanged, true);
            app.BinsXEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.BinsXEditFieldLabel.Text = 'Bins X';
            app.BinsXEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.BinsXEditField.Value = '25';
            app.BinsYEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.BinsYEditFieldLabel.Text = 'Bins Y';
            app.BinsYEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.BinsYEditField.Value = '25';
            app.PartitionCenterEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.PartitionCenterEditFieldLabel.Text = 'Partition Center';
            app.PartitionCenterEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.PartitionCenterEditField.ValueChangedFcn = createCallbackFcn(app, @PartitionCenterEditFieldValueChanged, true);
            app.PartitionWidthEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.PartitionWidthEditFieldLabel.Text = 'Partition Width';
            app.PartitionWidthEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.PartitionWidthEditField.ValueChangedFcn = createCallbackFcn(app, @PartitionWidthEditFieldValueChanged, true);
            app.ShowonlyEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.ShowonlyEditFieldLabel.Text = 'Show only';
            app.ShowonlyEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.ShowonlyEditField.ValueChangedFcn = createCallbackFcn(app, @ShowonlyEditFieldValueChanged, true);
            app.AutoButton = uibutton(app.ScalarvsScalarUIFigure, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.AutoPartition = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.ShowstatsCheckBox = uicheckbox(app.ScalarvsScalarUIFigure);
            app.ShowstatsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowstatsCheckBoxValueChanged, true);
            app.ShowstatsCheckBox.Text = 'Show stats';
            app.LogButton = uibutton(app.ScalarvsScalarUIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = '-> Log';
            app.AuthorEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.AuthorEditFieldLabel.Text = 'Author:';
            app.AuthorEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.TitleEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.TitleEditField.Value = 'CVCRCI - Scalar vs Scalar';
            app.commentEditFieldLabel = uilabel(app.ScalarvsScalarUIFigure);
            app.commentEditFieldLabel.Text = 'comment';
            app.commentEditField = uieditfield(app.ScalarvsScalarUIFigure, 'text');
            app.BoxGridCheckBox = uicheckbox(app.ScalarvsScalarUIFigure);
            app.BoxGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoxGridCheckBoxValueChanged, true);
            app.BoxGridCheckBox.Text = 'Box&Grid';
            % Show the figure after all components are created
            app.ScalarvsScalarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVCRCI_Scalar_vs_Scalar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScalarvsScalarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScalarvsScalarUIFigure)
        end
    end
end