classdef CVCRCI_Scalar_vs_Time < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScalarvsTimeUIFigure            matlab.ui.Figure
        XMinEditFieldLabel              matlab.ui.control.Label
        XMinEditField                   matlab.ui.control.EditField
        XMaxEditFieldLabel              matlab.ui.control.Label
        XMaxEditField                   matlab.ui.control.EditField
        YMinEditFieldLabel              matlab.ui.control.Label
        YMinEditField                   matlab.ui.control.EditField
        YMaxEditFieldLabel              matlab.ui.control.Label
        YMaxEditField                   matlab.ui.control.EditField
        AxesFigureButton                matlab.ui.control.Button
        MoveAxesNearButton              matlab.ui.control.Button
        PositionDropDown                matlab.ui.control.DropDown
        DefaultAxesSizeButton           matlab.ui.control.Button
        LogbookButton                   matlab.ui.control.Button
        SaveDataCheckBox                matlab.ui.control.CheckBox
        EditTextFigCheckBox             matlab.ui.control.CheckBox
        SaveLabel                       matlab.ui.control.Label
        TABULA                          matlab.ui.control.Table
        VariablestoplotLabel            matlab.ui.control.Label
        FilteringoptionsLabel           matlab.ui.control.Label
        TabellaY                        matlab.ui.control.Table
        AxiscontrolLabel                matlab.ui.control.Label
        MoreoptionsLabel                matlab.ui.control.Label
        UsetimeCheckBox                 matlab.ui.control.CheckBox
        STOP                            matlab.ui.control.Button
        VarAddLine                      matlab.ui.control.Button
        VarRemoveLine                   matlab.ui.control.Button
        FilterAddLine                   matlab.ui.control.Button
        FilterRemoveLine                matlab.ui.control.Button
        AxiscontrollastLabel            matlab.ui.control.Label
        secondssamplesEditFieldLabel    matlab.ui.control.Label
        secondssamplesEditField         matlab.ui.control.EditField
        LogButton                       matlab.ui.control.Button
        AuthorEditFieldLabel            matlab.ui.control.Label
        AuthorEditField                 matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        commentEditFieldLabel           matlab.ui.control.Label
        commentEditField                matlab.ui.control.EditField
        secondssamplesEditFieldLabel_2  matlab.ui.control.Label
        AVGLAST                         matlab.ui.control.EditField
        BoxGridCheckBox                 matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Petition % Structure used by the update function .
        DataStructure % Stores Acquisition variable for re initialization.
        ASSE % True axes not UI axes for speed to be located in PGR figure.
        PGR % Patently-slow Graphics Remedy, a figure that supports faster graphics.
        ASSEDefaultSize; %default size when new figure is turned on.
        color; %colors for the panel
        application='Test';
        ToBeDeleted=[];
        Annotations=[];
        FIELDNAMES;
    end
    
    properties (Access = public)
        NeedInit=1; % Description
    end
    
    methods (Access = public)
        
        function Update(app,CV,Initialize)
            if(Initialize)
                app.UpdateInitialize(CV);
            else
                if(app.Petition.STOP), return, end
                if(~isvalid(app.PGR)), app.CheckExistenceMakeAxes(); disp('Axes were remade because could not be found'); end
                delete(app.ASSE.Children); if(~isempty(app.Petition.LastSeconds)), MT=-inf; end; hold(app.ASSE,'on');
                if(app.DataStructure.Synchronous)
                    %%
                    if(CV.Recorder.TimesBufferFilled)
                        if(CV.Recorder.LastWrittenElement==app.DataStructure.Buffersize)
                            TENUTI=1:app.DataStructure.Buffersize;
                        else
                            TENUTI=[(CV.Recorder.LastWrittenElement+1):app.DataStructure.Buffersize,(1:CV.Recorder.LastWrittenElement)];
                        end
                    else
                        TENUTI=1:CV.Recorder.LastWrittenElement;
                    end
                    
                    TF{1}=TENUTI;
                    TF{2}=TENUTI;
                    for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                        if(app.Petition.FilterUse{FN}(1))
                            TF{2}=TF{2}((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{2},app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{2},app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                        end
                    end
                    TF{3}=TENUTI;
                    for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                        if(app.Petition.FilterUse{FN}(2))
                            TF{3}=TF{3}((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{3},app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{3},app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                        end
                    end
                    TF{4}=intersect(TF{2},TF{3},'stable');
                    DATI_Y_timestamped=[];
                    for II=1:length(app.Petition.Quantity)
                        if(app.DataStructure.TimeStamped(app.Petition.Quantity(II).Y_SEL(1)))
                            DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(:,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle; %#ok<AGROW>
                            DATI_Y_timestamped(II)=1; %#ok<AGROW>
                        else
                            DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(:,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            DATI_Y_timestamped(II)=0; %#ok<AGROW>
                            if(app.DataStructure.BSA || app.Petition.Quantity(II).Y_SEL(1)~=1)
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.Quantity(II).Y_SEL(1)}; %#ok<AGROW>
                            else
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.Quantity(II).Y_SEL(1)}(:,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            end
                        end
                    end
                    
                    %if(isempty(DATI_Y_timestamped))
                    %    set(MyHandle.TABRES,'data',{});
                    %end
                    if(~any(DATI_Y_timestamped))
                        KindOfPlot=0;
                    else
                        KindOfPlot=app.Petition.UseAbsoluteTime;
                    end
                    TABRES=cell(length(app.Petition.Quantity),3);
                    if(KindOfPlot==1) % cast on real time
                        TimeReference=CV.Recorder.TimeStamps;
                        AWCReference=CV.Recorder.AcquisitionWritingCycle;
                        for II=1:length(DATI_Y_timestamped)
                            FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
                            switch(FilterNum)
                                case 1
                                    ID=1;
                                case 2
                                    ID=2;
                                case 3
                                    ID=3;
                                case 6
                                    ID=4;
                            end
                            if(app.Petition.Quantity(II).LastN<length(TF{1}))
                                UseTF=intersect(TF{1}((end-app.Petition.Quantity(II).LastN+1):end),TF{ID},'stable');
                            else
                                UseTF=TF{ID} ;
                            end
                            if(~isempty(UseTF))
                                TR=TimeReference; AWC=AWCReference(UseTF);
                                if(~isreal(TR(1))), Time=double(real(TR)) + double(imag(TR))/10^9; Time=Time(UseTF)-min(Time); else, Time=TR(UseTF)-min(TR); end
                                if(~DATI_Y_timestamped(II))
                                    DATI_Y{II}=CV.NonTimeStamped_On_Timestamped(DATI_Y_AcquisitionWritingCycle{II},DATI_Y{II},AWC);
                                end
                                if(DATI_Y_timestamped(II))
                                    if(isempty(app.Petition.Quantity(II).Function))
                                        plot(app.ASSE,Time,DATI_Y{II}(UseTF),app.Petition.Quantity(II).Stilnovo);
                                        if(~isempty(app.Petition.HistAVG))
                                            a=DATI_Y{II}(UseTF);
                                            AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                            Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                            if(length(a)<app.Petition.HistAVG), plot(app.ASSE,Time(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,Time,AvgData,Style,'LineWidth',2.5);end
                                        end
                                    else
                                        plot(app.ASSE,Time,app.Petition.Quantity(II).Function(DATI_Y{II}(UseTF)),app.Petition.Quantity(II).Stilnovo);
                                        if(~isempty(app.Petition.HistAVG))
                                            a=app.Petition.Quantity(II).Function(DATI_Y{II}(UseTF));
                                            AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                            Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                            if(length(a)<app.Petition.HistAVG), plot(app.ASSE,Time(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,Time,AvgData,Style,'LineWidth',2.5);end
                                        end
                                    end
                                    if(~isempty(app.Petition.LastSeconds)), MT=max(MT,max(Time)); end
                                    TABRES{II,1}=mean(DATI_Y{II}(UseTF)); TABRES{II,2}=std(DATI_Y{II}(UseTF)); TABRES{II,3}=length(UseTF);
                                else
                                    %TEMP=DATI_Y{II}(UseTF);
                                    TEMP=DATI_Y{II}; Del=find(~diff(TEMP))+1;
                                    TEMP(Del)=[]; SOVal3=repmat(Time(end),1,3*length(TEMP)); Temp3Y=NaN*SOVal3;
                                    TEMPTime=Time; TEMPTime(Del)=[];
                                    if(~isempty(app.Petition.Quantity(II).Function)), TEMP=app.Petition.Quantity(II).Function(TEMP); end
                                    SOVal3(1:3:end) = TEMPTime; SOVal3(2:3:(end-3))=TEMPTime(2:end);
                                    Temp3Y(1:3:end) = TEMP; Temp3Y(2:3:end) = Temp3Y(1:3:end);
                                    plot(app.ASSE,SOVal3,Temp3Y,app.Petition.Quantity(II).Stilnovo);
                                    if(~isempty(app.Petition.HistAVG))
                                        a=Temp3Y;
                                        AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                        Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                        if(length(a)<app.Petition.HistAVG), plot(app.ASSE,SOVal3(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,SOVal3,AvgData,Style,'LineWidth',2.5);end
                                    end
                                    TABRES{II,1}=mean(DATI_Y{II}); TABRES{II,2}=std(DATI_Y{II}); TABRES{II,3}=length(UseTF);
                                end
%                                 if(II==length(app.Petition.Quantity))
%                                     app.ASSE.NextPlot='ReplaceChildren';
%                                 elseif(II==1)
%                                     app.ASSE.NextPlot='Add';
%                                 end
                            else
                                TABRES{II,1}=NaN; TABRES{II,2}=NaN; TABRES{II,3}=0;
                            end
                        end
                    end
                    if(KindOfPlot==0)
                        for II=1:length(DATI_Y_timestamped)
                            AWC=DATI_Y_AcquisitionWritingCycle{II}(~isnan(DATI_Y_AcquisitionWritingCycle{II}));
                            [SOVal,SO]=sort(AWC,'ascend');
                            if(app.Petition.Quantity(II).LastN<length(SO))
                                SO=SO((end-app.Petition.Quantity(II).LastN+1):end);
                                SOVal=SOVal((end-app.Petition.Quantity(II).LastN+1):end);
                            end
                            if(~isempty(SO))
                                if(DATI_Y_timestamped(II))
                                    if(isempty(app.Petition.Quantity(II).Function))
                                        plot(app.ASSE,SOVal,DATI_Y{II}(SO),app.Petition.Quantity(II).Stilnovo);
                                        if(~isempty(app.Petition.HistAVG))
                                            a=DATI_Y{II}(SO);
                                            AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                            Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                            if(length(a)<app.Petition.HistAVG), plot(app.ASSE,SOVal(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,SOVal,AvgData,Style,'LineWidth',2.5);end
                                        end
                                    else
                                        plot(app.ASSE,SOVal,app.Petition.Quantity(II).Function(DATI_Y{II}(SO)),app.Petition.Quantity(II).Stilnovo);
                                        if(~isempty(app.Petition.HistAVG))
                                            a=app.Petition.Quantity(II).Function(DATI_Y{II}(SO));
                                            AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                            Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                            if(length(a)<app.Petition.HistAVG), plot(app.ASSE,SOVal(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,SOVal,AvgData,Style,'LineWidth',2.5);end
                                        end
                                    end
                                    if(~isempty(app.Petition.LastSeconds)), MT=max(MT,max(SOVal)); end
                                else
                                    TEMP=DATI_Y{II}(SO); Del=find(~diff(TEMP))+1;
                                    TEMP(Del)=[]; SOVal3=repmat(SOVal(end),1,3*length(TEMP)); Temp3Y=NaN*SOVal3;
                                    TEMPTime=SOVal; TEMPTime(Del)=[];
                                    if(~isempty(app.Petition.Quantity(II).Function)), TEMP=app.Petition.Quantity(II).Function(TEMP); end
                                    SOVal3(1:3:end) = TEMPTime; SOVal3(2:3:(end-3))=TEMPTime(2:end);
                                    Temp3Y(1:3:end) = TEMP; Temp3Y(2:3:end) = Temp3Y(1:3:end);
                                    plot(app.ASSE,SOVal3,Temp3Y,app.Petition.Quantity(II).Stilnovo);
                                    if(~isempty(app.Petition.HistAVG))
                                        a=Temp3Y;
                                        AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                        Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                        if(length(a)<app.Petition.HistAVG), plot(app.ASSE,SOVal3(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,SOVal3,AvgData,Style,'LineWidth',2.5);end
                                    end
                                    if(~isempty(app.Petition.LastSeconds)), MT=max(MT,max(SOVal(end))); end
                                end
                                TABRES{II,1}=mean(DATI_Y{II}(SO)); TABRES{II,2}=std(DATI_Y{II}(SO)); TABRES{II,3}=length(SO);
%                                 if(II==length(app.Petition.Quantity))
%                                     app.ASSE.NextPlot='ReplaceChildren';
%                                 elseif(II==1)
%                                     app.ASSE.NextPlot='Add';
%                                 end
                            else
                                TABRES{II,1}=NaN; TABRES{II,2}=NaN; TABRES{II,3}=0;
                            end
                        end
                    end
                    if(~isempty(app.Petition.LastSeconds))
                        VarXLim=app.Petition.xLim; VarXLim(1) = MT - app.Petition.LastSeconds; xlim(app.ASSE,VarXLim);
                    else
                        xlim(app.ASSE,app.Petition.xLim);
                    end
                    ylim(app.ASSE,app.Petition.yLim);
                else % Asynchronous mode.
                    for II=1:length(app.Petition.Quantity)
                        if(CV.Acquisition.TimeStamped(app.Petition.Quantity(II).Y_SEL(1)))
                            if(CV.Acquisition.BSA || app.Petition.Quantity(II).Y_SEL(1)~=1)
                                if(CV.Recorder.TimesBufferFilled{app.Petition.Quantity(II).Y_SEL(1)})
                                    if(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}<CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)))
                                        SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}+1):CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}];
                                    else
                                        SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1));
                                    end
                                else
                                    SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)};
                                end
                                DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER); %#ok<AGROW>
                                DATI_Y_timestamped(II)=1; %#ok<AGROW>
                                DATI_Y_timestamps{II}=CV.Recorder.TimeStamps{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER); %#ok<AGROW>
                            else
                                if(CV.Recorder.TimesBufferFilled{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2)))
                                    if(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2))<CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)))
                                        SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2))+1):CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2))];
                                    else
                                        SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1));
                                    end
                                else
                                    SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2));
                                end
                                DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                                DATI_Y_timestamped(II)=1; %#ok<AGROW>
                                DATI_Y_timestamps{II}=CV.Recorder.TimeStamps{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            end
                        else
                            if(CV.Recorder.AcquisitionNonTimeStampedTimesBufferFilled(app.Petition.Quantity(II).Y_SEL(1)))
                                if(CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1))<CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)))
                                    SORTING_ORDER=[(1+CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1))):CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)),1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1))];
                                else
                                    SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1));
                                end
                            else
                                SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1));
                            end
                            DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            DATI_Y_timestamped(II)=0; %#ok<AGROW>
                            if(CV.Acquisition.BSA || app.Petition.Quantity(II).Y_SEL(1)~=1)
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER); %#ok<AGROW>
                            else
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            end
                        end
                        
                    end
                    if(~any(DATI_Y_timestamped)), KindOfPlot=0; else, KindOfPlot=app.Petition.UseAbsoluteTime;
                    end
                    TABRES=cell(length(app.Petition.Quantity),3);
                    if(KindOfPlot==1) %There is at least one timestamped, use that as time and cast non-timestamped to it.
                        MINTIME=+inf;
                        for II=1:length(app.Petition.Quantity)
                            if(CV.Acquisition.TimeStamped(app.Petition.Quantity(II).Y_SEL(1)))
                                IDL(II)=length(DATI_Y{II}); %#ok<AGROW>
                                if(~isreal(DATI_Y_timestamps{II}(1)))
                                    TIME{II}=double(real(DATI_Y_timestamps{II})) + double(imag(DATI_Y_timestamps{II}))/10^9; %#ok<AGROW>
                                    MINTIME=min(MINTIME,min(TIME{II}));
                                else
                                    TIME{II}=DATI_Y_timestamps{II}; %#ok<AGROW>
                                    MINTIME=min(MINTIME,min(TIME{II}));
                                end
                            end
                        end
                        [~,MPos]=max(IDL);
                        for II=1:length(app.Petition.Quantity)
                            if(DATI_Y_timestamped(II))
                                if(isempty(DATI_Y{II}))
                                    TABRES{II,1}=NaN; TABRES{II,2}=NaN; TABRES{II,3}=0;
                                else
                                    if(length(DATI_Y{II}) < app.Petition.Quantity(II).LastN)
                                        if(~isempty(app.Petition.Quantity(II).Function))
                                            plot(app.ASSE,TIME{II}-MINTIME,app.Petition.Quantity(II).Function(DATI_Y{II}),app.Petition.Quantity(II).Stilnovo);
                                            if(~isempty(app.Petition.HistAVG))
                                                a=app.Petition.Quantity(II).Function(DATI_Y{II}); TempTime=TIME{II}-MINTIME;
                                                AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                                Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                                if(length(a)<app.Petition.HistAVG), plot(app.ASSE,TempTime(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,TempTime,AvgData,Style,'LineWidth',2.5);end
                                            end
                                        else
                                            plot(app.ASSE,TIME{II}-MINTIME,DATI_Y{II},app.Petition.Quantity(II).Stilnovo);
                                            if(~isempty(app.Petition.HistAVG))
                                                a=app.Petition.Quantity(II).Function(DATI_Y{II}); TempTime=TIME{II}-MINTIME;
                                                AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                                Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                                if(length(a)<app.Petition.HistAVG), plot(app.ASSE,TempTime(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,TempTime,AvgData,Style,'LineWidth',2.5);end
                                            end
                                        end
                                        if(~isempty(app.Petition.LastSeconds)), MT=max(MT,max(TIME{II}-MINTIME)); end
                                        TABRES{II,1}=mean(DATI_Y{II}); TABRES{II,2}=std(DATI_Y{II}); TABRES{II,3}=length(TIME{II});
                                    else
                                        if(~isempty(app.Petition.Quantity(II).Function))
                                            plot(app.ASSE,TIME{II}((end - app.Petition.Quantity(II).LastN+1):end)-MINTIME,app.Petition.Quantity(II).Function(DATI_Y{II}((end - app.Petition.Quantity(II).LastN+1):end)),app.Petition.Quantity(II).Stilnovo);
                                            if(~isempty(app.Petition.HistAVG))
                                                a=app.Petition.Quantity(II).Function(DATI_Y{II}((end - app.Petition.Quantity(II).LastN+1):end)); TempTime=TIME{II}((end - app.Petition.Quantity(II).LastN+1):end)-MINTIME;
                                                AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                                Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                                if(length(a)<app.Petition.HistAVG), plot(app.ASSE,TempTime(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,TempTime,AvgData,Style,'LineWidth',2.5);end
                                            end
                                        else
                                            plot(app.ASSE,TIME{II}((end - app.Petition.Quantity(II).LastN+1):end)-MINTIME,DATI_Y{II}((end - app.Petition.Quantity(II).LastN+1):end),app.Petition.Quantity(II).Stilnovo);
                                            if(~isempty(app.Petition.HistAVG))
                                                a=DATI_Y{II}((end - app.Petition.Quantity(II).LastN+1):end); TempTime=TIME{II}((end - app.Petition.Quantity(II).LastN+1):end)-MINTIME;
                                                AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                                Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                                if(length(a)<app.Petition.HistAVG), plot(app.ASSE,TempTime(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,TempTime,AvgData,Style,'LineWidth',2.5);end
                                            end
                                        end
                                        if(~isempty(app.Petition.LastSeconds)), MT=max(MT,TIME{II}((end - app.Petition.Quantity(II).LastN+1):end)-MINTIME); end
                                        TABRES{II,1}=mean(DATI_Y{II}((end - app.Petition.Quantity(II).LastN+1):end)); TABRES{II,2}=std(DATI_Y{II}((end - app.Petition.Quantity(II).LastN+1):end)); TABRES{II,3}=app.Petition.Quantity(II).LastN;
                                    end
                                end
                            else
                                DATI_Y{II}=CV.NonTimeStamped_On_Timestamped(DATI_Y_AcquisitionWritingCycle{II},DATI_Y{II},DATI_Y_AcquisitionWritingCycle{MPos(1)});
                                TIM=TIME{MPos(1)};%CV.NonTimeStamped_On_Timestamped(DATI_Y_AcquisitionWritingCycle{II},TIME{MPos(1)},DATI_Y_AcquisitionWritingCycle{MPos(1)});
                                if(isempty(DATI_Y{II}))
                                    TABRES{II,1}=NaN; TABRES{II,2}=NaN; TABRES{II,3}=0;
                                else
                                    if(length(DATI_Y{II}) < app.Petition.Quantity(II).LastN)
                                        if(~isempty(app.Petition.Quantity(II).Function))
                                            plot(app.ASSE,TIM-MINTIME,app.Petition.Quantity(II).Function(DATI_Y{II}),app.Petition.Quantity(II).Stilnovo);
                                            if(~isempty(app.Petition.HistAVG))
                                                a=app.Petition.Quantity(II).Function(DATI_Y{II}); TempTime=TIM-MINTIME;
                                                AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                                Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                                if(length(a)<app.Petition.HistAVG), plot(app.ASSE,TempTime(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,TempTime,AvgData,Style,'LineWidth',2.5);end
                                            end
                                        else
                                            plot(app.ASSE,TIM-MINTIME,DATI_Y{II},app.Petition.Quantity(II).Stilnovo);
                                            if(~isempty(app.Petition.HistAVG))
                                                a=DATI_Y{II}; TempTime=TIM-MINTIME;
                                                AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                                Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                                if(length(a)<app.Petition.HistAVG), plot(app.ASSE,TempTime(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,TempTime,AvgData,Style,'LineWidth',2.5);end
                                            end
                                        end
                                        TABRES{II,1}=mean(DATI_Y{II}); TABRES{II,2}=std(DATI_Y{II}); TABRES{II,3}=length(TIM);
                                    else
                                        if(~isempty(app.Petition.Quantity(II).Function))
                                            plot(app.ASSE,TIM((end - app.Petition.Quantity(II).LastN+1):end)-MINTIME,app.Petition.Quantity(II).Function(DATI_Y{II}((end - app.Petition.Quantity(II).LastN+1):end)),app.Petition.Quantity(II).Stilnovo);
                                            if(~isempty(app.Petition.HistAVG))
                                                a=app.Petition.Quantity(II).Function(DATI_Y{II}((end - app.Petition.Quantity(II).LastN+1):end)); TempTime=TIM((end - app.Petition.Quantity(II).LastN+1):end)-MINTIME;
                                                AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                                Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                                if(length(a)<app.Petition.HistAVG), plot(app.ASSE,TempTime(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,TempTime,AvgData,Style,'LineWidth',2.5);end
                                            end
                                        else
                                            plot(app.ASSE,TIM((end - app.Petition.Quantity(II).LastN+1):end)-MINTIME,DATI_Y{II}((end - app.Petition.Quantity(II).LastN+1):end),app.Petition.Quantity(II).Stilnovo);
                                            if(~isempty(app.Petition.HistAVG))
                                                a=DATI_Y{II}((end - app.Petition.Quantity(II).LastN+1):end); TempTime=TIM((end - app.Petition.Quantity(II).LastN+1):end)-MINTIME;
                                                AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                                Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                                if(length(a)<app.Petition.HistAVG), plot(app.ASSE,TempTime(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,TempTime,AvgData,Style,'LineWidth',2.5);end
                                            end
                                        end
                                        TABRES{II,1}=mean(DATI_Y{MPos(1)}((end - app.Petition.Quantity(II).LastN+1):end)); TABRES{II,2}=std(DATI_Y{II}((end - app.Petition.Quantity(II).LastN+1):end)); TABRES{II,3}=app.Petition.Quantity(II).LastN;
                                    end
                                    
                                end
                            end
                            if(II==length(app.Petition.Quantity))
                                app.ASSE.NextPlot='ReplaceChildren';
                            elseif(II==1)
                                app.ASSE.NextPlot='Add';
                            end
                        end
                        if(~isempty(app.Petition.LastSeconds))
                            VarXLim=app.Petition.xLim; VarXLim(1) = MT - app.Petition.LastSeconds; xlim(app.ASSE,VarXLim);
                        else
                            xlim(app.ASSE,app.Petition.xLim);
                        end
                        ylim(app.ASSE,app.Petition.yLim);
                    end
                    if(KindOfPlot==0)
                        for II=1:length(DATI_Y_timestamped)
                            AWC=DATI_Y_AcquisitionWritingCycle{II}(~isnan(DATI_Y_AcquisitionWritingCycle{II}));
                            [SOVal,SO]=sort(AWC,'ascend');
                            if(app.Petition.Quantity(II).LastN<length(SO))
                                SO=SO((end-app.Petition.Quantity(II).LastN+1):end);
                                SOVal=SOVal((end-app.Petition.Quantity(II).LastN+1):end);
                            end
                            if(~isempty(SO))
                                if(isempty(app.Petition.Quantity(II).Function))
                                    plot(app.ASSE,SOVal,DATI_Y{II}(SO),app.Petition.Quantity(II).Stilnovo);
                                    if(~isempty(app.Petition.HistAVG))
                                        a=DATI_Y{II}(SO); TempTime=SOVal;
                                        AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                        Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                        if(length(a)<app.Petition.HistAVG), plot(app.ASSE,TempTime(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,TempTime,AvgData,Style,'LineWidth',2.5);end
                                    end
                                else
                                    plot(app.ASSE,SOVal,app.Petition.Quantity(II).Function(DATI_Y{II}(SO)),app.Petition.Quantity(II).Stilnovo);
                                    if(~isempty(app.Petition.HistAVG))
                                        a=app.Petition.Quantity(II).Function(DATI_Y{II}(SO)); TempTime=SOVal;
                                        AvgData=[mean(a(1:(min(length(a),app.Petition.HistAVG)-1)))*ones((min(length(a),app.Petition.HistAVG)-1),1);conv(a,ones(app.Petition.HistAVG,1)/app.Petition.HistAVG,'valid')];
                                        Style=regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-');
                                        if(length(a)<app.Petition.HistAVG), plot(app.ASSE,TempTime(2:end),AvgData,Style,'LineWidth',2.5); else,  plot(app.ASSE,TempTime,AvgData,Style,'LineWidth',2.5);end
                                    end
                                end
                                if(~isempty(app.Petition.LastSeconds)), MT=max(MT,max(SOVal)); end
                                TABRES{II,1}=mean(DATI_Y{II}(SO)); TABRES{II,2}=std(DATI_Y{II}(SO)); TABRES{II,3}=length(SO);
                            else
                                TABRES{II,1}=NaN; TABRES{II,2}=NaN; TABRES{II,3}=0;
                            end
                            if(II==length(app.Petition.Quantity))
                                app.ASSE.NextPlot='ReplaceChildren';
                            elseif(II==1)
                                app.ASSE.NextPlot='Add';
                            end
                        end
                        if(~isempty(app.Petition.LastSeconds))
                            VarXLim=app.Petition.xLim; VarXLim(1) = MT - app.Petition.LastSeconds; xlim(app.ASSE,VarXLim);
                        else
                            xlim(app.ASSE,app.Petition.xLim);
                        end
                        ylim(app.ASSE,app.Petition.yLim);
                    end
                end %End asynchronous
                if(app.Petition.Logbook)
                    app.logBookButtonCall(CV);
                    app.LogbookButton.BackgroundColor=app.color.logbook; app.Petition.Logbook=0;
                end
            end
        end
        
        function [SUCCESS,filename,TargetDir] = writeDataToDisk(app,TimeNow,CVObject,auxillaryData)
            CurrentTime=datevec(TimeNow);
            CurrentYearString=num2str(CurrentTime(1),'%.4d'); CurrentMonthString=num2str(CurrentTime(2),'%.2d');
            CurrentDayString=num2str(CurrentTime(3),'%.2d'); CurrentTempo1String=num2str(CurrentTime(4),'%.2d');
            CurrentTempo2String=num2str(CurrentTime(5),'%.2d'); CurrentTempo3String=num2str(floor(CurrentTime(6)),'%.2d');
            CurrentTempo4String=num2str(round((CurrentTime(6)-floor(CurrentTime(6)))*1000),'%.3d');
            CurrentTimeString=[CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString,'--',CurrentTempo1String,'-',CurrentTempo2String,'-',CurrentTempo3String,'-',CurrentTempo4String];
            filename=['OnlineMonitor-','ScalarAndTime','-',CurrentTimeString];
            TargetDir=['/u1/lcls/matlab/data/',CurrentYearString,'/',CurrentYearString,'-',CurrentMonthString,'/',CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString];
            if(~isfolder(TargetDir))
                mkdir(TargetDir);
            end
            DataStructure=CVObject.Acquisition; Data=CVObject.Recorder; %#ok<ADPROPLC> 
            try
                save([TargetDir,'/',filename],'Data','DataStructure','auxillaryData')
                SUCCESS=1;
            catch
                SUCCESS=0;
            end
        end
    end
    
    methods (Access = private)
        
        function void = UpdateLimits(app,direction)
            if(direction=='x')
                valuem = str2num(app.XMinEditField.Value); %#ok<ST2NM> 
                valueM = str2num(app.XMaxEditField.Value); %#ok<ST2NM> 
            else
                valuem = str2num(app.YMinEditField.Value); %#ok<ST2NM> 
                valueM = str2num(app.YMaxEditField.Value); %#ok<ST2NM> 
            end
            if(isempty(valuem)), valuem=-inf; end
            if(isempty(valueM)), valueM=+inf; end
            valuem=valuem(1); valueM=valueM(1);
            if(valuem>valueM)
                temp=valuem; valuem=valueM; valueM=temp; 
                if(direction=='x')
                    app.XMinEditField.Value = num2str(valuem); app.XMaxEditField.Value = num2str(valueM);
                else
                    app.YMinEditField.Value = num2str(valuem); app.YMaxEditField.Value = num2str(valueM);
                end
            end
            if(abs(valuem-valueM)<10^-13)
                valueM=valueM+10^-13/2; valuem=valuem-10^-13/2;
            end
            if(direction=='x')
                app.Petition.xLim=[valuem,valueM];
            else
                app.Petition.yLim=[valuem,valueM];
            end
            void=0;
        end
        
        function wasvalid = CheckExistenceMakeAxes(app)
            wasvalid=1;
            if(~isvalid(app.PGR))
                app.PGR=figure('Name',[app.(app.FIELDNAMES{(cellfun(@(x) any(x),strfind(fieldnames(app),'UIFigure')))}).Name,' *-* Display']);
                app.ASSE=axes(app.PGR);
                app.ASSE.Interactions=[];app.ASSE.Toolbar=[];
                app.PGR.MenuBar='none'; app.PGR.ToolBar='none';
                app.ASSE.NextPlot='replacechildren';
                app.ASSEDefaultSize=app.ASSE.Position(3:4);
                wasvalid=0;
                if(app.BoxGridCheckBox.Value), box(app.ASSE,'on'); grid(app.ASSE,'on'); else, box(app.ASSE,'off'); grid(app.ASSE,'off'); end 
            end
        end
        
        function moveAxisNearToMe(app)
            app.CheckExistenceMakeAxes();
            switch app.PositionDropDown.Value
                case 'Right'
                    PGRNewPosition=[app.ScalarvsTimeUIFigure.Position(1)+app.ScalarvsTimeUIFigure.Position(3)+1,app.ScalarvsTimeUIFigure.Position(2),app.PGR.Position(3),app.PGR.Position(4)];
                case 'Above'
                    PGRNewPosition=[app.ScalarvsTimeUIFigure.Position(1),app.ScalarvsTimeUIFigure.Position(2)+app.ScalarvsTimeUIFigure.Position(4)+1,app.PGR.Position(3),app.PGR.Position(4)];
                case 'Left'
                    PGRNewPosition=[app.ScalarvsTimeUIFigure.Position(1)-app.PGR.Position(3)-1,app.ScalarvsTimeUIFigure.Position(2),app.PGR.Position(3),app.PGR.Position(4)];
                case 'Below'
                    PGRNewPosition=[app.ScalarvsTimeUIFigure.Position(1),app.ScalarvsTimeUIFigure.Position(2)-app.PGR.Position(4)-1,app.PGR.Position(3),app.PGR.Position(4)];
            end
            app.PGR.Position=PGRNewPosition;
            figure(app.PGR);
        end
        
        function void = getAppColors(app)
            void=0;
            app.color.Idle=app.AxesFigureButton.BackgroundColor;
            app.color.On=[0,1,0];
            app.color.Off=[1,0,0];
            app.color.Wait=[1,1,0];
            app.color.logbook=[0.35,0.86,1];
            app.color.logbook2Steps=[0.3,0.5,1];
        end
        
        function UpdateInitialize(app,CV)
            app.LogButton.Text=['','-> Log'];
            app.LogButton.Enable='Off';
            if(~isequal(CV.Acquisition,app.DataStructure))
                app.DataStructure=CV.Acquisition;
                app.Petition.Logbook=0;
                app.LogbookButton.BackgroundColor=app.color.logbook;
                List=app.EvaluateAllLists();
                %List.SynchScalars List.SynchScalarsPositions 
                %List.Scalars List.ScalarsPositions
                %List.Vectors List.VectorsPositions
                %List.Array2D List.Array2DPositions
                
                app.ManageFilterTable(app.TABULA,List);
                app.ManageScalarsTable(app.TabellaY,List);
  
                if(isinf(app.Petition.xLim(1))), app.XMinEditField.Value=''; else, app.XMinEditField.Value=num2str(app.Petition.xLim(1)); end
                if(isinf(app.Petition.xLim(2))), app.XMaxEditField.Value=''; else, app.XMaxEditField.Value=num2str(app.Petition.xLim(2)); end
                if(isinf(app.Petition.yLim(1))), app.YMinEditField.Value=''; else, app.YMinEditField.Value=num2str(app.Petition.yLim(1)); end
                if(isinf(app.Petition.yLim(2))), app.YMaxEditField.Value=''; else, app.YMaxEditField.Value=num2str(app.Petition.yLim(2)); end
                
                if(~app.Petition.STOP)
                    app.STOP.Text='Now Running -> Stop Updating';
                    app.STOP.BackgroundColor=app.color.On;
                else
                    app.STOP.Text='Now Stopped -> Resume Updating';
                    app.STOP.BackgroundColor=app.color.Wait;
                end
                
                if(app.Petition.UseAbsoluteTime), app.UsetimeCheckBox.Value=1; else, app.UsetimeCheckBox.Value=0; end
                
                if(app.Petition.Save), app.SaveDataCheckBox.Value=1; else, app.SaveDataCheckBox.Value=0; end
                
                if(app.Petition.TwoStepLog), app.EditTextFigCheckBox.Value=1; else, app.EditTextFigCheckBox.Value=0; end
                
                if(isempty(app.Petition.LastSeconds)), app.secondssamplesEditField.Value=''; else, app.secondssamplesEditField.Value=num2str(app.Petition.LastSeconds); end
                if(isempty(app.Petition.HistAVG)), app.AVGLAST.Value=''; else, app.AVGLAST.Value=num2str(app.Petition.HistAVG); end
                
            end
            app.NeedInit=0;
        end
        
        function ManageSynchScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.SynchScalars(:).';
            Bottone.ItemsData=1:length(List.SynchScalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.SynchScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageNonTimestampedScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.NotTimeStampedScalars(:).';
            Bottone.ItemsData=1:length(List.NotTimeStampedScalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.NotTimeStampedScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function List=EvaluateAllLists(app)
           List.SynchScalars=app.DataStructure.Scalars;
           List.SynchScalarsPositions=app.DataStructure.Scalars_Positions;
           Delete=false(size(List.SynchScalarsPositions(:,1)));
           for AA=1:length(app.DataStructure.TimeStamped)
               if(~app.DataStructure.TimeStamped(AA))
                   Delete=Delete | (List.SynchScalarsPositions(:,1)==AA);
               end
           end
           List.NotTimeStampedScalars=List.SynchScalars(Delete);
           List.NotTimeStampedScalarsPositions=List.SynchScalarsPositions(Delete,:);
           List.NotTimeStampedScalars=['OFF';List.NotTimeStampedScalars];
           List.NotTimeStampedScalarsPositions=[[0,0,0];List.NotTimeStampedScalarsPositions];
           List.SynchScalars(Delete)=[];
           List.SynchScalarsPositions(Delete,:)=[];
           List.SynchScalars=['OFF';List.SynchScalars];
           List.SynchScalarsPositions=[[0,0,0];List.SynchScalarsPositions];
           List.Scalars=['OFF';app.DataStructure.Scalars];
           List.ScalarsPositions=[0,0,0;app.DataStructure.Scalars_Positions];
           List.Vectors=['OFF';app.DataStructure.Vectors];
           List.VectorsPositions=[0,0,0;app.DataStructure.Vectors_Positions];
           List.Arrays2D=['OFF';app.DataStructure.Arrays2D];
           List.Arrays2DPositions=[0,0,0;app.DataStructure.Arrays2D_Positions];
        end

        
        function ManageFilterTable(app,FilterTable,List)
            TAB_CF=FilterTable.ColumnFormat;
            TAB_CF{1}=List.SynchScalars(:).';
            TAB_CF{2}='numeric'; TAB_CF{3}='numeric'; TAB_CF{4}='logical'; TAB_CF{5}='logical';
            FilterTable.ColumnFormat=TAB_CF;
            FilterTable.ColumnEditable=[true,true,true,true,true];
            for II=app.Petition.FiltriNumber:-1:1
                IDPOS=find(strcmp(List.SynchScalars,app.Petition.FilterVarName{II}));
                if(isempty(IDPOS)) %needs to remove the filer, the variable is not recorded anymore
                    app.Petition.FiltriNumber=app.Petition.FiltriNumber-1;
                    app.Petition.Filter(II)=[];
                    app.Petition.FilterVarName(II)=[];
                    app.Petition.FilterUse(II)=[];
                else
                    IDPOS=IDPOS(1); %rebuild the filter with new position, even if it is the same one.
                    app.Petition.Filter{II}.SEL=List.SynchScalarsPositions(IDPOS,:);
                end
            end
            
            TABULA_DATA={};
            for II=1:app.Petition.FiltriNumber
                TABULA_DATA{II,1}=app.Petition.FilterVarName{II}; %#ok<AGROW>
                if(isinf(app.Petition.Filter{II}.Range(1)))
                    TABULA_DATA{II,2}=[]; %#ok<AGROW>
                else
                    if(~isnan(app.Petition.Filter{II}.Range(1)))
                        TABULA_DATA{II,2}=app.Petition.Filter{II}.Range(1); %#ok<AGROW>
                    else
                        app.Petition.Filter{II}.Range(1)=+inf;
                        TABULA_DATA{II,2}=[]; %#ok<AGROW>
                    end
                end
                if(isinf(app.Petition.Filter{II}.Range(2)))
                    TABULA_DATA{II,3}=[]; %#ok<AGROW>
                else
                    if(~isnan(app.Petition.Filter{II}.Range(2)))
                        TABULA_DATA{II,3}=app.Petition.Filter{II}.Range(2); %#ok<AGROW>
                    else
                        app.Petition.Filter{II}.Range(2)=+inf;
                        TABULA_DATA{II,3}=[]; %#ok<AGROW>
                    end
                end
                TABULA_DATA{II,4}=app.Petition.FilterUse{II}(1); %#ok<AGROW>
                TABULA_DATA{II,5}=app.Petition.FilterUse{II}(2);  %#ok<AGROW>
            end
          
            FilterTable.Data=TABULA_DATA;
            ReducedList.Positions=List.SynchScalarsPositions;
            ReducedList.Names=List.SynchScalars;
            FilterTable.UserData=ReducedList;
        end
        
        function ManageScalarsTable(app,TAB,List)
            TAB.ColumnName={'Filter 1','Filter 2','Scalar Variable Name','Style','Expression','LastN'};
            TAB_CF=TAB.ColumnFormat;
            TAB_CF{3}=List.Scalars(:).';
            TAB.ColumnFormat=TAB_CF;
            TAB.ColumnEditable=[true,true,true,true,true,true];
            InitData{1,1}=true;InitData{1,2}=true;InitData{1,3}=List.SynchScalars{1};InitData{1,4}='.b';InitData{1,5}='';InitData{1,6}=inf;
            TAB.Data=InitData;
            ins=0;
%             List.Scalars=['OFF';app.DataStructure.Scalars];
%             List.ScalarsPositions=[0,0,0;app.DataStructure.Scalars_Positions];
            if(~isempty(app.Petition.Quantity))
                FoundNone=1;
                for II=1:length(app.Petition.Quantity)
                    %Check if variable still exists...
                    ID=find(strcmp(app.Petition.Quantity(II).Name,List.Scalars));
                    if(isempty(ID))
                        app.Petition.Quantity(II)=[];
                    else
                        FoundNone=0; ins=ins+1;
                        NewQuantity(ins).Filter(1)=app.Petition.Quantity(II).Filter(1); %#ok<AGROW>
                        NewQuantity(ins).Filter(2)=app.Petition.Quantity(II).Filter(2); %#ok<AGROW>
                        NewQuantity(ins).Name=app.Petition.Quantity(II).Name; %#ok<AGROW>
                        NewQuantity(ins).Y_SEL=List.ScalarsPositions(ID,:); %#ok<AGROW>
                        NewQuantity(ins).Stilnovo=app.Petition.Quantity(II).Stilnovo; %#ok<AGROW>
                        TAB.Data{ins,1}=logical(app.Petition.Quantity(II).Filter(1)); 
                        TAB.Data{ins,2}=logical(app.Petition.Quantity(II).Filter(2));
                        TAB.Data{ins,3}=app.Petition.Quantity(II).Name; TAB.Data{ins,4}=app.Petition.Quantity(II).Stilnovo;
                        TAB.Data{ins,5}=''; NewQuantity(ins).Function=''; %#ok<AGROW>
                        NewQuantity(ins).LastN=app.Petition.Quantity(II).LastN; %#ok<AGROW>
                        TAB.Data{ins,6}=app.Petition.Quantity(II).LastN;
                    end
                end
                if(FoundNone)
                    TAB.Data{1,1}=true;TAB.Data{1,2}=true;TAB.Data{1,4}='.b';TAB.Data{1,5}='';TAB.Data{1,6}=inf;
                    TAB.Data{1,3}=List.SynchScalars{1}; app.Petition.Quantity=[];
                else
                    app.Petition.Quantity=NewQuantity;
                end
            else
                TAB.Data{1,1}=true;TAB.Data{1,2}=true;TAB.Data{1,4}='.b';TAB.Data{1,5}='';TAB.Data{1,6}=inf; 
                TAB.Data{1,3}=List.SynchScalars{1}; app.Petition.Quantity=[];
            end
            
            if(app.DataStructure.Synchronous)
                CE=TAB.ColumnEditable; CE(1)=true; CE(2)=true;
                TAB.ColumnEditable=CE;
            else
                CE=TAB.ColumnEditable; CE(1)=false; CE(2)=false;
                TAB.ColumnEditable=CE;
                TAB.Data(:,1:2)={false};
            end
            ReducedList.Positions=List.ScalarsPositions;
            ReducedList.Names=List.Scalars;
            TAB.UserData=ReducedList;
        end
        
        function ManageScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Scalars(:).';
            Bottone.ItemsData=1:length(List.Scalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.ScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end

        function ManageVectorsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Vectors(:).';
            Bottone.ItemsData=1:length(List.Vectors);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.VectorsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageArrays2DSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Arrays2D(:).';
            Bottone.ItemsData=1:length(List.Arrays2D);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.Arrays2DPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageListChange(app,Bottone)
            app.Petition.(Bottone.UserData.PetitionName) = Bottone.UserData.Pos(Bottone.Value,:);
        end
        
        function UpdateTableSelection(app)
            ins=0;
            for II=1:size(app.TabellaY.Data,1)
                QTY = find(strcmp(app.TabellaY.Data{II,3},app.DataStructure.Scalars));
                if(isempty(QTY))
                    continue
                else
                    ins=ins+1;
                    Quantity(ins).Y_SEL=app.DataStructure.Scalars_Positions(QTY,:); %#ok<AGROW> 
                end
                Quantity(ins).Filter(1)=app.TabellaY.Data{II,1}; %#ok<AGROW> 
                Quantity(ins).Filter(2)=app.TabellaY.Data{II,2}; %#ok<AGROW> 
                Quantity(ins).Name=app.TabellaY.Data{II,3}; %#ok<AGROW> 
                Quantity(ins).Stilnovo=app.TabellaY.Data{II,4}; %#ok<AGROW> 
                if(isempty(app.TabellaY.Data{II,5}))
                    Quantity(ins).Function=''; %#ok<AGROW> 
                else
                    Quantity(ins).Function=eval(['@(x) ',vectorize(app.TabellaY.Data{II,5})]); %#ok<AGROW> 
                end
                Quantity(ins).LastN=app.TabellaY.Data{II,6}; %#ok<AGROW> 
            end
            if(ins==0)
                app.Petition.Quantity=[];
            else
                app.Petition.Quantity=Quantity;
            end
        end
        
        function UpdateFilterSelection(app)
            app.Petition.Filter={};app.Petition.FilterUse={}; app.Petition.FilterVarName={}; app.Petition.FiltriNumber=0;
            for II=1:size(app.TABULA.Data,1)
                if((~isempty(app.TABULA.Data{II,2}) && ~isnan(app.TABULA.Data{II,2})) || (~isempty(app.TABULA.Data{II,3}) && ~isnan(app.TABULA.Data{II,3}))) %there is something
                    VAL=app.DataStructure.Scalars_Positions(strcmp(app.TABULA.Data{II,1},app.DataStructure.Scalars),:);
                    if(isempty(VAL))
                        continue
                    end
                    if(~isempty(app.TABULA.Data{II,2}) && ~isnan(app.TABULA.Data{II,2}))
                        MIN=app.TABULA.Data{II,2};
                    else
                        MIN=-inf;
                    end
                    if(~isempty(app.TABULA.Data{II,3}) && ~isnan(app.TABULA.Data{II,3}))
                        MAX=app.TABULA.Data{II,3};
                    else
                        MAX=+inf;
                    end
                    app.Petition.Filter{end+1}.SEL=VAL; app.Petition.Filter{end}.Range=[MIN,MAX];
                    app.Petition.FiltriNumber=app.Petition.FiltriNumber+1;
                    app.Petition.FilterUse{end+1}(1)=app.TABULA.Data{II,4}; app.Petition.FilterUse{end}(2)=app.TABULA.Data{II,5};
                    app.Petition.FilterVarName{end+1}=app.TABULA.Data{II,1};
                end
            end
        end
        
        function logBookButtonCall(app,CVObject)
            TimeNow=now;
            Author=app.AuthorEditField.Value; Title=app.TitleEditField.Value; Comment=app.commentEditField.Value; tablestring=app.RetrieveTableStrings();
            app.Petition.Author=Author; app.Petition.Title=Title; app.Petition.Comment=Comment;
            if(app.SaveDataCheckBox.Value)
                auxillaryData = additionalSavingInformation(app);
                [SUCCESS,filename,~]=app.writeDataToDisk(TimeNow,CVObject,auxillaryData);
                if(SUCCESS)
                    app.SaveLabel.Text=['Last: OK ,',datestr(TimeNow,'yyyy, mmm dd, HH:MM')];
                else
                    app.SaveLabel='Saving Failed';
                    filename='File saving failed.';
                end
            else
                filename='No file was saved';
            end

            if(app.EditTextFigCheckBox.Value) %Two Steps logbook. Make a copy of the figure and open edit boxes.
                NewFigure=figure;
                NewFigure.Position=app.PGR.Position;
                app.DuplicateFigure(app.PGR,NewFigure);
                if(isfield(CVObject.Acquisition,'filename'))
                    annotation(NewFigure,'textbox',[0 .92 1 .08],'string',CVObject.Acquisition.filename,'EdgeColor','none','HorizontalAlignment','center');
                else
                    if(app.SaveDataCheckBox.Value)
                        annotation(NewFigure,'textbox',[0 .92 1 .08],'string',filename,'EdgeColor','none','HorizontalAlignment','center');
                    else
                        annotation(NewFigure,'textbox',[0 .92 1 .08],'string','Image logbook only, data not saved','EdgeColor','none','HorizontalAlignment','center');
                    end
                end
                app.LogButton.Text=[num2str(NewFigure.Number),'-> Log'];
                app.LogButton.Enable='On';
                app.LogButton.UserData=NewFigure;
                app.EditTextFigCheckBox.UserData=filename;
            else
                app.SendImageToLogbook(app.PGR, Author, Title, Comment, filename, tablestring);
            end          
        end
        
        function auxillaryData = additionalSavingInformation(app) %#ok<MANU> 
            auxillaryData=app.Petition;
        end
        
        function DuplicateFigure(app,OriginalFigure,NewFigure) %#ok<INUSL> 
            children_handler_original = get(OriginalFigure, 'children');
            allchildren = allchild(OriginalFigure);
            if isempty(children_handler_original)
                return
            else
                new_handler_copy = copyobj(children_handler_original,NewFigure);
                for KK=1:length(new_handler_copy)
                    if(strcmpi(new_handler_copy(KK).Type,'axes'))
                        delete(axtoolbar(new_handler_copy(KK)));
                    end
                end
                for AA=1:length(allchildren)
                    if(strcmpi(allchildren(AA).Type,'annotationpane'))
                        copyobj(allchildren(AA),NewFigure);
                    end
                end
            end
        end
        
        function DeepDuplicateFigure(app,OriginalFigure,NewFigure)
            children_handler_original = get(OriginalFigure, 'children');
            if isempty(children_handler_original)
                return
            else
                new_handler_copy = copyobj(children_handler_original,NewFigure);
                for KK=1:length(children_handler_original)
                    app.DuplicateFigure(children_handler_original(KK),new_handler_copy(KK));
                end
            end
        end
        
        function SendImageToLogbook(app, FigureHandle, Author, Title, Comment, Filename,additionalstring)
            Comment=[Comment,char(13),'Filename: ', Filename];
            for II=1:numel(additionalstring)
                if(~isempty(additionalstring{II}))
                    Comment=[Comment,char(13),additionalstring{II}]; %#ok<AGROW> 
                end
            end
            try
                util_printLog(FigureHandle,'title',Title,'author',Author,'text',Comment);
                disp('Image sent successfully to logbook');
            catch
                disp('Failed to send image to logbook');
            end
        end
        
        function tablestring = RetrieveTableStrings(app)
            tablestring={};
            SA=size(app.TabellaY.Data,1);SB=size(app.TABULA.Data,1);
            if(SA>0)
                TAB=app.TabellaY.Data;
                tablestring{end+1}='Variables';
                for II=1:SA
                    tablestring{end+1}=['Var: ',TAB{II,3},'   style: ',TAB{II,4},'   last: ',num2str(TAB{II,6})];
                    if(~isempty(TAB{II,5})), tablestring{end} = [tablestring{end}, '   expr: ',TAB{II,5}]; end
                    if(SB>0)
                        if(TAB{II,1})
                            tablestring{end} = [tablestring{end}, '   filter 1 &'];
                        else
                            tablestring{end} = [tablestring{end}, 'Not Filter 1 &'];
                        end
                        if(TAB{II,2})
                            tablestring{end} = [tablestring{end}, ' filter 2'];
                        else
                            tablestring{end} = [tablestring{end}, ' Not filter 2'];
                        end
                    end
                end
            end
            if(SB>0)
                tablestring{end+1}='Filters';
                TABF=app.TABULA.Data;
                for II=1:SB
                    tablestring{end+1}=['Var: ',TABF{II,1},'   From: ',num2str(TABF{II,2}),'   To: ',num2str(TABF{II,3})];
                    if(TABF{II,4})
                        tablestring{end} = [tablestring{end}, '   1:YES'];
                    else
                        tablestring{end} = [tablestring{end}, '   1:NO '];
                    end
                    if(TABF{II,5})
                        tablestring{end} = [tablestring{end}, ' 2:YES'];
                    else
                        tablestring{end} = [tablestring{end}, ' 2:NO '];
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FIELDNAMES=fieldnames(app);
            app.PGR=figure('Name',[app.(app.FIELDNAMES{(cellfun(@(x) any(x),strfind(fieldnames(app),'UIFigure')))}).Name,' *-* Display']);
            app.ASSE=axes(app.PGR);
            %delete(axtoolbar(app.ASSE));
            app.ASSE.Interactions=[];app.ASSE.Toolbar=[];
            app.PGR.MenuBar='none';app.PGR.ToolBar='none'; app.ASSE.NextPlot='replacechildren';
            app.ASSEDefaultSize=app.PGR.Position(3:4);
            app.moveAxisNearToMe();
            if(app.BoxGridCheckBox.Value), box(app.ASSE,'on'); grid(app.ASSE,'on'); else, box(app.ASSE,'off'); grid(app.ASSE,'off'); end 
            app.Petition.xLim=[-inf,inf];
            app.Petition.yLim=[-inf,inf];
            app.getAppColors();
            app.LogbookButton.BackgroundColor=app.color.logbook;
            app.Petition.STOP=0;
            app.Petition.UseAbsoluteTime = 1;
            app.Petition.Save = 1;
            app.Petition.TwoStepLog= 0;
            app.Petition.FiltriNumber=0;
            app.Petition.Quantity=[];
            app.Petition.S_SEL=[0,0,0];
            app.Petition.V_SEL=[0,0,0];
            app.Petition.I_SEL=[0,0,0];
            app.Petition.LastSeconds=[];
            app.Petition.HistAVG=10;
        end

        % Value changed function: XMinEditField
        function XMinEditFieldValueChanged(app, event)
            app.UpdateLimits('x');
        end

        % Value changed function: XMaxEditField
        function XMaxEditFieldValueChanged(app, event)
            app.UpdateLimits('x');
        end

        % Value changed function: YMinEditField
        function YMinEditFieldValueChanged(app, event)
            app.UpdateLimits('y');
        end

        % Value changed function: YMaxEditField
        function YMaxEditFieldValueChanged(app, event)
            app.UpdateLimits('y');
        end

        % Button pushed function: AxesFigureButton
        function AxesFigureButtonPushed(app, event)
            app.CheckExistenceMakeAxes();
        end

        % Button pushed function: MoveAxesNearButton
        function MoveAxesNearButtonPushed(app, event)
            app.moveAxisNearToMe();
        end

        % Button pushed function: DefaultAxesSizeButton
        function DefaultAxesSizeButtonPushed(app, event)
            app.PGR.Position(3:4)=app.ASSEDefaultSize;
        end

        % Button pushed function: LogbookButton
        function LogbookButtonPushed(app, event)
            app.LogbookButton.BackgroundColor=app.color.On;
            app.Petition.Logbook=1;
        end

        % Button pushed function: STOP
        function STOPButtonPushed(app, event)
            if(app.Petition.STOP)
                app.Petition.STOP=false; app.STOP.Text='Now Running -> Stop Updating'; app.STOP.BackgroundColor=app.color.On; drawnow
            else
                app.Petition.STOP=true; app.STOP.Text='Now Stopped -> Resume Updating'; app.STOP.BackgroundColor=app.color.Wait; drawnow
            end
        end

        % Value changed function: UsetimeCheckBox
        function UsetimeCheckBoxValueChanged(app, event)
            app.Petition.UseAbsoluteTime = app.UsetimeCheckBox.Value;
        end

        % Value changed function: SaveDataCheckBox
        function SaveDataCheckBoxValueChanged(app, event)
            app.Petition.Save = app.SaveDataCheckBox.Value;
        end

        % Value changed function: EditTextFigCheckBox
        function EditTextFigCheckBoxValueChanged(app, event)
            app.Petition.TwoStepLog= app.EditTextFigCheckBox.Value;
        end

        % Callback function
        function S_ScalarValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Callback function
        function S_VectorValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Callback function
        function S_ImageValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Close request function: ScalarvsTimeUIFigure
        function ScalarvsTimeUIFigureCloseRequest(app, event)
            try delete(app.PGR); end %#ok<TRYNC> 
            delete(app);
        end

        % Cell edit callback: TabellaY
        function TabellaYCellEdit(app, event)
            %indices = event.Indices; newData = event.NewData;
            ID=find(strcmp(event.NewData,app.DataStructure.Scalars),1, 'first');
            if(~isempty(ID))
                if(~app.DataStructure.TimeStamped(app.DataStructure.Scalars_Positions(ID,1))) %not timestamped, defaults as non scatter plot like
                    try app.TabellaY.Data{event.Indices(1),4}=regexprep(regexprep(app.TabellaY.Data{event.Indices(1),4},'\.','-'),'b','r'); end %#ok<TRYNC> 
                end
            end
            app.UpdateTableSelection();
        end

        % Button pushed function: VarAddLine
        function VarAddLineButtonPushed(app, event)
            SA=size(app.TabellaY.Data,1);
            switch(SA)
                case 0
                    typestring='.b';
                case 1
                    typestring='.r';
                case 2
                    typestring='.g';
                case 3
                    typestring='.k';
                case 4
                    typestring='.m';
                case 5
                    typestring='.c';
                case 6
                    typestring='.y';
                otherwise
                    typestring='.b';
            end
            if(app.DataStructure.Synchronous)
                NewLine={true,true,'OFF',typestring,'',inf};
            else
                NewLine={false,false,'OFF',typestring,'',inf};
            end
            app.TabellaY.Data(end+1,:)=NewLine;
            app.UpdateTableSelection();
        end

        % Button pushed function: VarRemoveLine
        function VarRemoveLineButtonPushed(app, event)
            if(~isempty(app.VarRemoveLine.UserData))
                toBeRemoved=unique(app.VarRemoveLine.UserData(:,1),'sorted');
                for II=size(app.TabellaY.Data,1):(-1):1
                    if(any(toBeRemoved==II))
                        app.TabellaY.Data(II,:)=[];
                    end
                end
            end
            app.UpdateTableSelection();
        end

        % Cell selection callback: TabellaY
        function TabellaYCellSelection(app, event)
            indices = event.Indices;
            app.VarRemoveLine.UserData=indices;
        end

        % Button pushed function: FilterAddLine
        function FilterAddLineButtonPushed(app, event)
            NewLine{1,1}=app.TABULA.ColumnFormat{1}{1};
            NewLine{1,2}=[];NewLine{1,3}=[];NewLine{1,4}=true;NewLine{1,5}=true;
            app.TABULA.Data(end+1,:)=NewLine;
        end

        % Button pushed function: FilterRemoveLine
        function FilterRemoveLineButtonPushed(app, event)
            app.FilterRemoveLine.UserData
            if(~isempty(app.FilterRemoveLine.UserData))
                toBeRemoved=unique(app.FilterRemoveLine.UserData(:,1),'sorted');
                for II=size(app.TABULA.Data,1):(-1):1
                    if(any(toBeRemoved==II))
                        app.TABULA.Data(II,:)=[];
                    end
                end
            end
            app.UpdateFilterSelection();
        end

        % Cell selection callback: TABULA
        function TABULACellSelection(app, event)
            indices = event.Indices;
            app.FilterRemoveLine.UserData=indices;
        end

        % Cell edit callback: TABULA
        function TABULACellEdit(app, event)
            app.UpdateFilterSelection();
        end

        % Value changed function: secondssamplesEditField
        function secondssamplesEditFieldValueChanged(app, event)
            value=app.secondssamplesEditField.Value;
            VAL=str2double(value);
            if(VAL<=0 || isnan(VAL))
                app.secondssamplesEditField.Value='';
                app.Petition.LastSeconds=[];
            else
                app.Petition.LastSeconds=VAL;
            end
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            app.LogButton.Text=['','-> Log'];
            app.LogButton.Enable='Off';
            Author=app.AuthorEditField.Value; Title=app.TitleEditField.Value; Comment=app.commentEditField.Value;
            try
                if(isempty(app.EditTextFigCheckBox.UserData))
                    app.EditTextFigCheckBox.UserData='';
                end
                app.SendImageToLogbook(app.LogButton.UserData, Author, Title, Comment, app.EditTextFigCheckBox.UserData,{''});
                disp('Image sent to logbook');
            catch
                disp('Failed to send image to logbook');
            end
            try %#ok<TRYNC> 
                close(app.LogButton.UserData);
            end
            app.LogButton.UserData=[];
        end

        % Value changed function: AVGLAST
        function AVGLASTValueChanged(app, event)
            value=app.AVGLAST.Value;
            VAL=str2double(value);
            if(VAL<=0 || isnan(VAL))
                app.secondssamplesEditField.Value='';
                app.Petition.HistAVG=[];
            else
                app.Petition.HistAVG=VAL;
            end
        end

        % Value changed function: BoxGridCheckBox
        function BoxGridCheckBoxValueChanged(app, event)
            if(app.BoxGridCheckBox.Value)
                try %#ok<TRYNC> 
                    box(app.ASSE,'on'); grid(app.ASSE,'on');
                end
            else
                try %#ok<TRYNC> 
                    box(app.ASSE,'off'); grid(app.ASSE,'off');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScalarvsTimeUIFigure = uifigure('Visible', 'off');
            app.ScalarvsTimeUIFigure.Name = 'Scalar vs Time';
            app.ScalarvsTimeUIFigure.CloseRequestFcn = createCallbackFcn(app, @ScalarvsTimeUIFigureCloseRequest, true);
            app.XMinEditFieldLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.XMinEditFieldLabel.Text = 'XMin';
            app.XMinEditField = uieditfield(app.ScalarvsTimeUIFigure, 'text');
            app.XMinEditField.ValueChangedFcn = createCallbackFcn(app, @XMinEditFieldValueChanged, true);
            app.XMaxEditFieldLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.XMaxEditFieldLabel.Text = 'XMax';
            app.XMaxEditField = uieditfield(app.ScalarvsTimeUIFigure, 'text');
            app.XMaxEditField.ValueChangedFcn = createCallbackFcn(app, @XMaxEditFieldValueChanged, true);
            app.YMinEditFieldLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.YMinEditFieldLabel.Text = 'YMin';
            app.YMinEditField = uieditfield(app.ScalarvsTimeUIFigure, 'text');
            app.YMinEditField.ValueChangedFcn = createCallbackFcn(app, @YMinEditFieldValueChanged, true);
            app.YMaxEditFieldLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.YMaxEditFieldLabel.Text = 'YMax';
            app.YMaxEditField = uieditfield(app.ScalarvsTimeUIFigure, 'text');
            app.YMaxEditField.ValueChangedFcn = createCallbackFcn(app, @YMaxEditFieldValueChanged, true);
            app.AxesFigureButton = uibutton(app.ScalarvsTimeUIFigure, 'push');
            app.AxesFigureButton.ButtonPushedFcn = createCallbackFcn(app, @AxesFigureButtonPushed, true);
            app.AxesFigureButton.Text = 'Axes Figure';
            app.MoveAxesNearButton = uibutton(app.ScalarvsTimeUIFigure, 'push');
            app.MoveAxesNearButton.ButtonPushedFcn = createCallbackFcn(app, @MoveAxesNearButtonPushed, true);
            app.MoveAxesNearButton.Text = 'Move Axes Near';
            app.PositionDropDown = uidropdown(app.ScalarvsTimeUIFigure);
            app.PositionDropDown.Items = {'Right', 'Above', 'Left', 'Below'};
            app.PositionDropDown.Value = 'Right';
            app.DefaultAxesSizeButton = uibutton(app.ScalarvsTimeUIFigure, 'push');
            app.DefaultAxesSizeButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultAxesSizeButtonPushed, true);
            app.DefaultAxesSizeButton.Text = 'Default Axes Size';
            app.LogbookButton = uibutton(app.ScalarvsTimeUIFigure, 'push');
            app.LogbookButton.ButtonPushedFcn = createCallbackFcn(app, @LogbookButtonPushed, true);
            app.LogbookButton.Text = 'Logbook';
            app.SaveDataCheckBox = uicheckbox(app.ScalarvsTimeUIFigure);
            app.SaveDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDataCheckBoxValueChanged, true);
            app.SaveDataCheckBox.Text = 'Save Data';
            app.SaveDataCheckBox.Value = true;
            app.EditTextFigCheckBox = uicheckbox(app.ScalarvsTimeUIFigure);
            app.EditTextFigCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditTextFigCheckBoxValueChanged, true);
            app.EditTextFigCheckBox.Text = 'Edit Text/Fig';
            app.SaveLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.SaveLabel.Text = 'Last: XX/XX/XXXX';
            app.TABULA = uitable(app.ScalarvsTimeUIFigure);
            app.TABULA.ColumnName = {'Variable Name'; 'Minimum'; 'Maximum'; 'Filter1'; 'Filter 2'};
            app.TABULA.RowName = {};
            app.TABULA.CellEditCallback = createCallbackFcn(app, @TABULACellEdit, true);
            app.TABULA.CellSelectionCallback = createCallbackFcn(app, @TABULACellSelection, true);
            app.VariablestoplotLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.VariablestoplotLabel.Text = 'Variables to plot:';
            app.FilteringoptionsLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.FilteringoptionsLabel.Text = 'Filtering options:';
            app.TabellaY = uitable(app.ScalarvsTimeUIFigure);
            app.TabellaY.ColumnName = {'Filter1'; 'Filter2'; 'Variable Name'; 'Style'; 'Expression'; 'Last N'};
            app.TabellaY.RowName = {};
            app.TabellaY.CellEditCallback = createCallbackFcn(app, @TabellaYCellEdit, true);
            app.TabellaY.CellSelectionCallback = createCallbackFcn(app, @TabellaYCellSelection, true);
            app.AxiscontrolLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.AxiscontrolLabel.Text = 'Axis control:';
            app.MoreoptionsLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.MoreoptionsLabel.Text = 'More options:';
            app.UsetimeCheckBox = uicheckbox(app.ScalarvsTimeUIFigure);
            app.UsetimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsetimeCheckBoxValueChanged, true);
            app.UsetimeCheckBox.Text = 'Use time';
            app.UsetimeCheckBox.Value = true;
            app.STOP = uibutton(app.ScalarvsTimeUIFigure, 'push');
            app.STOP.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOP.Text = 'Now Running->Resume Updating';
            app.VarAddLine = uibutton(app.ScalarvsTimeUIFigure, 'push');
            app.VarAddLine.ButtonPushedFcn = createCallbackFcn(app, @VarAddLineButtonPushed, true);
            app.VarAddLine.Text = 'Add Line';
            app.VarRemoveLine = uibutton(app.ScalarvsTimeUIFigure, 'push');
            app.VarRemoveLine.ButtonPushedFcn = createCallbackFcn(app, @VarRemoveLineButtonPushed, true);
            app.VarRemoveLine.Text = 'Remove Line';
            app.FilterAddLine = uibutton(app.ScalarvsTimeUIFigure, 'push');
            app.FilterAddLine.ButtonPushedFcn = createCallbackFcn(app, @FilterAddLineButtonPushed, true);
            app.FilterAddLine.Text = 'Add Line';
            app.FilterRemoveLine = uibutton(app.ScalarvsTimeUIFigure, 'push');
            app.FilterRemoveLine.ButtonPushedFcn = createCallbackFcn(app, @FilterRemoveLineButtonPushed, true);
            app.FilterRemoveLine.Text = 'Remove Line';
            app.AxiscontrollastLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.AxiscontrollastLabel.Text = 'Axis control: last';
            app.secondssamplesEditFieldLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.secondssamplesEditFieldLabel.Text = 'seconds/samples';
            app.secondssamplesEditField = uieditfield(app.ScalarvsTimeUIFigure, 'text');
            app.secondssamplesEditField.ValueChangedFcn = createCallbackFcn(app, @secondssamplesEditFieldValueChanged, true);
            app.LogButton = uibutton(app.ScalarvsTimeUIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = '-> Log';
            app.AuthorEditFieldLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.AuthorEditFieldLabel.Text = 'Author:';
            app.AuthorEditField = uieditfield(app.ScalarvsTimeUIFigure, 'text');
            app.TitleEditFieldLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.ScalarvsTimeUIFigure, 'text');
            app.TitleEditField.Value = 'CVCRCI Scalar vs Time';
            app.commentEditFieldLabel = uilabel(app.ScalarvsTimeUIFigure);
            app.commentEditFieldLabel.Text = 'comment';
            app.commentEditField = uieditfield(app.ScalarvsTimeUIFigure, 'text');
            app.secondssamplesEditFieldLabel_2 = uilabel(app.ScalarvsTimeUIFigure);
            app.secondssamplesEditFieldLabel_2.Text = 'show average on last';
            app.AVGLAST = uieditfield(app.ScalarvsTimeUIFigure, 'text');
            app.AVGLAST.ValueChangedFcn = createCallbackFcn(app, @AVGLASTValueChanged, true);
            app.AVGLAST.Value = '10';
            app.BoxGridCheckBox = uicheckbox(app.ScalarvsTimeUIFigure);
            app.BoxGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoxGridCheckBoxValueChanged, true);
            app.BoxGridCheckBox.Text = 'Box&Grid';
            % Show the figure after all components are created
            app.ScalarvsTimeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVCRCI_Scalar_vs_Time

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScalarvsTimeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScalarvsTimeUIFigure)
        end
    end
end