classdef CVCRCI_UndulatorLineOrbit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OrbitDisplayUIFigure            matlab.ui.Figure
        XMinEditFieldLabel              matlab.ui.control.Label
        XMinEditField                   matlab.ui.control.EditField
        XMaxEditFieldLabel              matlab.ui.control.Label
        XMaxEditField                   matlab.ui.control.EditField
        YMinEditFieldLabel              matlab.ui.control.Label
        YMinEditField                   matlab.ui.control.EditField
        YMaxEditFieldLabel              matlab.ui.control.Label
        YMaxEditField                   matlab.ui.control.EditField
        AxesFigureButton                matlab.ui.control.Button
        MoveAxesNearButton              matlab.ui.control.Button
        PositionDropDown                matlab.ui.control.DropDown
        DefaultAxesSizeButton           matlab.ui.control.Button
        LogbookButton                   matlab.ui.control.Button
        SaveDataCheckBox                matlab.ui.control.CheckBox
        EditTextFigCheckBox             matlab.ui.control.CheckBox
        SaveLabel                       matlab.ui.control.Label
        TABULA                          matlab.ui.control.Table
        FilteringoptionsLabel           matlab.ui.control.Label
        AxiscontrolLabel                matlab.ui.control.Label
        STOP                            matlab.ui.control.Button
        FilterAddLine                   matlab.ui.control.Button
        FilterRemoveLine                matlab.ui.control.Button
        ExpressionEditFieldLabel        matlab.ui.control.Label
        ExpressionEditField             matlab.ui.control.EditField
        ShowstatsCheckBox               matlab.ui.control.CheckBox
        LogButton                       matlab.ui.control.Button
        AuthorEditFieldLabel            matlab.ui.control.Label
        AuthorEditField                 matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        commentEditFieldLabel           matlab.ui.control.Label
        commentEditField                matlab.ui.control.EditField
        IntensityVariable1Label         matlab.ui.control.Label
        S_Scalar_1                      matlab.ui.control.DropDown
        AreaDropDownLabel               matlab.ui.control.Label
        AreaDropDown                    matlab.ui.control.DropDown
        LiveorbitaveragesEditFieldLabel  matlab.ui.control.Label
        LiveorbitaveragesEditField      matlab.ui.control.EditField
        LoadfixedOrbitButton            matlab.ui.control.Button
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        ClearfixedorbitsButton          matlab.ui.control.Button
        LoadorbitPatchworkOrbitsButton  matlab.ui.control.Button
        EstimateEnergyFitittakestimeButton  matlab.ui.control.Button
        RequiresBSALabel                matlab.ui.control.Label
        YMaxEditField_2Label            matlab.ui.control.Label
        YMaxEditField_2                 matlab.ui.control.EditField
        YMinEditField_2Label            matlab.ui.control.Label
        YMinEditField_2                 matlab.ui.control.EditField
        XMaxEditField_2Label            matlab.ui.control.Label
        XMaxEditField_2                 matlab.ui.control.EditField
        XMinEditField_2Label            matlab.ui.control.Label
        XMinEditField_2                 matlab.ui.control.EditField
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        TopLabel                        matlab.ui.control.Label
        TopEditField_1                  matlab.ui.control.EditField
        IntensityVariable2Label         matlab.ui.control.Label
        S_Scalar_2                      matlab.ui.control.DropDown
        TopEditField_2Label             matlab.ui.control.Label
        TopEditField_2                  matlab.ui.control.EditField
        AboveEditField_2Label           matlab.ui.control.Label
        AboveEditField_2                matlab.ui.control.EditField
        AboveEditFieldLabel             matlab.ui.control.Label
        AboveEditField_1                matlab.ui.control.EditField
        colorscalesinglesCheckBox_1     matlab.ui.control.CheckBox
        colorscalesinglesCheckBox_2     matlab.ui.control.CheckBox
        UseFilter1CheckBox              matlab.ui.control.CheckBox
        UseFilter2CheckBox              matlab.ui.control.CheckBox
        ALabel                          matlab.ui.control.Label
        MaxEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.EditField
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.EditField
        RelativeCheckBox                matlab.ui.control.CheckBox
        BoxGridCheckBox                 matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Petition % Structure used by the update function .
        DataStructure % Stores Acquisition variable for re initialization.
        ASSE % True axes not UI axes for speed to be located in PGR figure.
        ASSE2 % Used for Y
        PGR % Patently-slow Graphics Remedy, a figure that supports faster graphics.
        ASSEDefaultSize; %default size when new figure is turned on.
        color; %colors for the panel
        application='Test';
        ToBeDeleted=[];
        Annotations=[];
        Permannotation;
        FIELDNAMES;
        ARC;
        BPM_Sorting;
        AdditionalOrbits={};
        ConfPath='/u1/lcls/matlab/VOM_Configs/';
        OP_folder='/u1/lcls/matlab/orbitPatchwork_saves';
        LineColor=[0,0,0; 0,0,1; 1,0,0; 0,1,0; 1,0.5,0; 0,1,1; 1,0,1; 1,1,0;0,1,0.5;0,0.5,1;1,0,0.5;0.5,0,1;0.5,1,0;0.5,0,0;0,0.5,0;0,0,0.5;0.5,0,0.5;0.5,0.5,0;0,0.5,0.5;1/3,2/3,1;2/3,1/3,1;1,1/3,2/3;1,2/3,1/3;1/3,1,2/3;2/3,1,1/3];
    end
    
    properties (Access = public)
        NeedInit=1; % Description
    end
    
    methods (Access = public)
        
        function Update(app,CV,Initialize)
            if(Initialize)
                app.UpdateInitialize(CV);
            else
                if(app.Petition.STOP), return, end
                %if(~app.Petition.S_SEL(1)), return, end
                if(~isvalid(app.PGR)), app.CheckExistenceMakeAxes(); disp('Axes were remade because could not be found'); end
                delete(app.ASSE.Children); %if(~isempty(app.Petition.LastSeconds)), MT=-inf; end
                hold(app.ASSE,'on');
                delete(app.ASSE2.Children); %if(~isempty(app.Petition.LastSeconds)), MT=-inf; end
                hold(app.ASSE2,'on');
                if(app.DataStructure.Synchronous)
                    if(CV.Recorder.TimesBufferFilled)
                        if(CV.Recorder.LastWrittenElement==app.DataStructure.Buffersize)
                            TENUTI=1:app.DataStructure.Buffersize;
                        else
                            TENUTI=[(CV.Recorder.LastWrittenElement+1):app.DataStructure.Buffersize,(1:CV.Recorder.LastWrittenElement)];
                        end
                    else
                        TENUTI=1:CV.Recorder.LastWrittenElement;
                    end
                    FilterNum=2^(app.Petition.UseFilter(1))*3^(app.Petition.UseFilter(2));
                    switch(FilterNum)
                        case 1
                            TF=TENUTI;
                        case 2
                            TF=TENUTI;
                            for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                                if(app.Petition.FilterUse{FN}(1))
                                    TF=TF((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                                end
                            end
                        case 3
                            TF=TENUTI;
                            for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                                if(app.Petition.FilterUse{FN}(2))
                                    TF=TF((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                                end
                            end
                        case 6
                            TF=TENUTI;
                            for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
                                if(any(app.Petition.FilterUse{FN}))
                                    TF=TF((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF,app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
                                end
                            end
                    end
                    if(length(TF)>app.Petition.LastN)
                        TF=TF((end-app.Petition.LastN+1):end);
                    end
                    ExcludeNaN=find(any(isnan(CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF,app.BPM_Sorting(app.Petition.Area).Y.POS)).') | any(isnan(CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF,app.BPM_Sorting(app.Petition.Area).X.POS)).'));
                    if(~isempty(ExcludeNaN))
                        TF(ExcludeNaN)=[];
                    end
                    if(app.Petition.S_SEL1(1) && ~isempty(TF))
                        DATI_S1=CV.Recorder.Data{app.Petition.S_SEL1(1)}(TF,app.Petition.S_SEL1(2));
                        if(app.Petition.ColorScale1)
                            Do_Not_Color1=0;
                            if(isinf(app.Petition.AmpRange(1))), min1=min(DATI_S1); else, min1=app.Petition.AmpRange(1); end
                            if(isinf(app.Petition.AmpRange(2))), max1=max(DATI_S1); else, max1=app.Petition.AmpRange(2); end
                            ColorIndex1=1+round(255*(DATI_S1-min1)*(max1-min1));
                            ColorIndex1(ColorIndex1<1)=1; ColorIndex1(ColorIndex1>256)=256;
                            try
                                Colors1=mat2cell(app.color.ColorScale1(ColorIndex1,:),ones(length(DATI_S1),1),3);
                            catch
                                Do_Not_Color1=1;
                                disp('Problems with the intensity PV, not updating plot');
                            end
                        elseif(~isempty(DATI_S1))
                            SV=sort(DATI_S1,'descend');
                            Threshold=round(length(DATI_S1)*app.Petition.Top1/100);
                            if(Threshold<1)
                                Threshold=1;
                            elseif(Threshold>length(SV))
                                Threshold=length(SV);
                            end
                            KEEP1=(DATI_S1>=SV(Threshold)) & (DATI_S1>=app.Petition.Above1);
                            XOrbit_S1=mean(CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(KEEP1),app.BPM_Sorting(app.Petition.Area).X.POS),1);
                            YOrbit_S1=mean(CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(KEEP1),app.BPM_Sorting(app.Petition.Area).Y.POS),1);
                        else
                            XOrbit_S1=NaN*app.BPM_Sorting(app.Petition.Area).X.z; YOrbit_S1=NaN*app.BPM_Sorting(app.Petition.Area).X.z;
                        end
                        
                    end
                    if(app.Petition.S_SEL2(1) && ~isempty(TF))
                        DATI_S2=CV.Recorder.Data{app.Petition.S_SEL2(1)}(TF,app.Petition.S_SEL2(2));
                        if(app.Petition.ColorScale2)
                            Do_Not_Color2=0;
                            if(isinf(app.Petition.AmpRange(1))), min1=min(DATI_S2); else, min1=app.Petition.AmpRange(1); end
                            if(isinf(app.Petition.AmpRange(2))), max1=max(DATI_S2); else, max1=app.Petition.AmpRange(2); end
                            ColorIndex2=1+round(255*(DATI_S2-min1)*(max1-min1));
                            ColorIndex2(ColorIndex2<1)=1; ColorIndex2(ColorIndex2>256)=256;
                            try
                                Colors2=mat2cell(app.color.ColorScale2(ColorIndex2,:),ones(length(DATI_S2),1),3);
                            catch
                                disp('Problems with the intensity PV, not updating plot');
                                Do_Not_Color2=1;
                            end
                        elseif(~isempty(DATI_S2))
                            SV=sort(DATI_S2,'descend');
                            Threshold=round(length(DATI_S2)*app.Petition.Top2/100);
                            if(Threshold<1)
                                Threshold=1;
                            elseif(Threshold>length(SV))
                                Threshold=length(SV);
                            end
                            KEEP2=(DATI_S2>=SV(Threshold)) & (DATI_S2>=app.Petition.Above2);
                            XOrbit_S2=mean(CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(KEEP2),app.BPM_Sorting(app.Petition.Area).X.POS),1);
                            YOrbit_S2=mean(CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF(KEEP2),app.BPM_Sorting(app.Petition.Area).Y.POS),1);
                        else
                            XOrbit_S2=NaN*app.BPM_Sorting(app.Petition.Area).X.z; YOrbit_S2=NaN*app.BPM_Sorting(app.Petition.Area).X.z;
                        end
                    end
                    XLiveOrbit=mean(CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF,app.BPM_Sorting(app.Petition.Area).X.POS),1);
                    YLiveOrbit=mean(CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF,app.BPM_Sorting(app.Petition.Area).Y.POS),1);
                    
                    if(app.Petition.S_SEL1(1) && ~isempty(TF))
                        if(app.Petition.ColorScale1)
                            if(~Do_Not_Color1)
                                h_temp=plot(app.ASSE2,app.BPM_Sorting(app.Petition.Area).X.z,CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF,app.BPM_Sorting(app.Petition.Area).X.POS));
                                set(h_temp, {'color'}, Colors1);
                                h_temp=plot(app.ASSE,app.BPM_Sorting(app.Petition.Area).Y.z,CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF,app.BPM_Sorting(app.Petition.Area).Y.POS));
                                set(h_temp, {'color'}, Colors1);
                            end
                        elseif(~isempty(DATI_S1))
                            if(app.Petition.Relative)
                                plot(app.ASSE2,app.BPM_Sorting(app.Petition.Area).X.z,XOrbit_S1-XLiveOrbit,':o','Color',app.color.Scalar1);
                                plot(app.ASSE,app.BPM_Sorting(app.Petition.Area).Y.z,YOrbit_S1-YLiveOrbit,':o','Color',app.color.Scalar1);
                            else  
                                plot(app.ASSE2,app.BPM_Sorting(app.Petition.Area).X.z,XOrbit_S1,':o','Color',app.color.Scalar1);
                                plot(app.ASSE,app.BPM_Sorting(app.Petition.Area).Y.z,YOrbit_S1,':o','Color',app.color.Scalar1);
                            end
                        end
                    end
                    if(app.Petition.S_SEL2(1) && ~isempty(TF))
                        if(app.Petition.ColorScale2)
                            if(~Do_Not_Color2)
                                h_temp=plot(app.ASSE2,app.BPM_Sorting(app.Petition.Area).X.z,CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF,app.BPM_Sorting(app.Petition.Area).X.POS));
                                set(h_temp, {'color'}, Colors2);
                                h_temp=plot(app.ASSE,app.BPM_Sorting(app.Petition.Area).Y.z,CV.Recorder.Data{app.BPM_Sorting(app.Petition.Area).DataPosition}(TF,app.BPM_Sorting(app.Petition.Area).Y.POS));
                                set(h_temp, {'color'}, Colors2);
                            end
                        elseif(~isempty(DATI_S2))
                            if(app.Petition.Relative)
                                plot(app.ASSE2,app.BPM_Sorting(app.Petition.Area).X.z,XOrbit_S2-XLiveOrbit,':d','Color',app.color.Scalar2);
                                plot(app.ASSE,app.BPM_Sorting(app.Petition.Area).Y.z,YOrbit_S2-YLiveOrbit,':d','Color',app.color.Scalar2);
                            else
                                plot(app.ASSE2,app.BPM_Sorting(app.Petition.Area).X.z,XOrbit_S2,':d','Color',app.color.Scalar2);
                                plot(app.ASSE,app.BPM_Sorting(app.Petition.Area).Y.z,YOrbit_S2,':d','Color',app.color.Scalar2);
                            end
                        end
                    end
                    if(app.Petition.Relative)
                        plot(app.ASSE2,app.BPM_Sorting(app.Petition.Area).X.z,XLiveOrbit-XLiveOrbit,':.','Color',[0,0,1]);
                        plot(app.ASSE,app.BPM_Sorting(app.Petition.Area).Y.z,YLiveOrbit-YLiveOrbit,':.','Color',[0,0,1]);
                    else
                        plot(app.ASSE2,app.BPM_Sorting(app.Petition.Area).X.z,XLiveOrbit,':.','Color',[0,0,1]);
                        plot(app.ASSE,app.BPM_Sorting(app.Petition.Area).Y.z,YLiveOrbit,':.','Color',[0,0,1]);
                    end
                    
%                     
%                     switch(app.Petition.UseFilter)
%                     TF{1}=TENUTI;
%                     TF{2}=TENUTI;
%                     for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
%                         if(app.Petition.FilterUse{FN}(1))
%                             TF{2}=TF{2}((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{2},app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{2},app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
%                         end
%                     end
%                     TF{3}=TENUTI;
%                     for FN=1:app.Petition.FiltriNumber %subsequently removes from TENUTI
%                         if(app.Petition.FilterUse{FN}(2))
%                             TF{3}=TF{3}((CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{3},app.Petition.Filter{FN}.SEL(2))>=app.Petition.Filter{FN}.Range(1)) & (CV.Recorder.Data{app.Petition.Filter{FN}.SEL(1)}(TF{3},app.Petition.Filter{FN}.SEL(2))<=app.Petition.Filter{FN}.Range(2)));
%                         end
%                     end
%                     TF{4}=intersect(TF{2},TF{3},'stable');


                else % Asynchronous mode.
                    if(CV.Recorder.TimesBufferFilled{app.BPM_Sorting(app.Petition.Area).DataPosition})
                    end
                    
                    for II=1:length(app.Petition.Quantity)
                        if(CV.Acquisition.TimeStamped(app.Petition.Quantity(II).Y_SEL(1)))
                            if(CV.Acquisition.BSA || app.Petition.Quantity(II).Y_SEL(1)~=1)
                                if(CV.Recorder.TimesBufferFilled{app.Petition.Quantity(II).Y_SEL(1)})
                                    if(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}<CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)))
                                        SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}+1):CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}];
                                    else
                                        SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1));
                                    end
                                else
                                    SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)};
                                end
                                DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER); %#ok<AGROW>
                                DATI_Y_timestamped(II)=1; %#ok<AGROW>
                                DATI_Y_timestamps{II}=CV.Recorder.PulseIds{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER); %#ok<AGROW>
                            else
                                if(CV.Recorder.TimesBufferFilled{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2)))
                                    if(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2))<CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)))
                                        SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2))+1):CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2))];
                                    else
                                        SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1));
                                    end
                                else
                                    SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.Quantity(II).Y_SEL(1)}(app.Petition.Quantity(II).Y_SEL(2));
                                end
                                DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                                DATI_Y_timestamped(II)=1; %#ok<AGROW>
                                DATI_Y_timestamps{II}=CV.Recorder.PulseIds{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            end
                        else
                            if(CV.Recorder.AcquisitionNonTimeStampedTimesBufferFilled(app.Petition.Quantity(II).Y_SEL(1)))
                                if(CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1))<CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)))
                                    SORTING_ORDER=[(1+CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1))):CV.Acquisition.VarBuffersize(app.Petition.Quantity(II).Y_SEL(1)),1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1))];
                                else
                                    SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1));
                                end
                            else
                                SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.Quantity(II).Y_SEL(1));
                            end
                            DATI_Y{II}=CV.Recorder.Data{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            DATI_Y_timestamped(II)=0; %#ok<AGROW>
                            if(CV.Acquisition.BSA || app.Petition.Quantity(II).Y_SEL(1)~=1)
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER); %#ok<AGROW>
                            else
                                DATI_Y_AcquisitionWritingCycle{II}=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.Quantity(II).Y_SEL(1)}(SORTING_ORDER,app.Petition.Quantity(II).Y_SEL(2)); %#ok<AGROW>
                            end
                        end
                        
                    end

                    if(CV.Acquisition.TimeStamped(app.Petition.S_SEL(1)))
                        DATI_X_timestamped=1;
                        if(CV.Acquisition.BSA || app.Petition.S_SEL(1)~=1)
                            if(CV.Recorder.TimesBufferFilled{app.Petition.S_SEL(1)})
                                if(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}<CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)))
                                    SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}+1):CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}];
                                else
                                    SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1));
                                end
                            else
                                SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)};
                            end
                            DATI_X=CV.Recorder.Data{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2)); 
                            DATI_X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle{app.Petition.S_SEL(1)}(SORTING_ORDER); 
                            DATI_X_timestamps=CV.Recorder.PulseIds{app.Petition.S_SEL(1)}(SORTING_ORDER); 
                        else
                            if(CV.Recorder.TimesBufferFilled{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2)))
                                if(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2))<CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)))
                                    SORTING_ORDER=[(CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2))+1):CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)), 1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2))];
                                else
                                    SORTING_ORDER=1:CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1));
                                end
                            else
                                SORTING_ORDER=1:CV.Recorder.LastWrittenElement{app.Petition.S_SEL(1)}(app.Petition.S_SEL(2));
                            end
                            DATI_X=CV.Recorder.Data{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                            DATI_X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2)); 
                            DATI_X_timestamps=CV.Recorder.PulseIds{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                        end
                    else
                        if(CV.Recorder.AcquisitionNonTimeStampedTimesBufferFilled(app.Petition.S_SEL(1)))
                            if(CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1))<CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)))
                                SORTING_ORDER=[(1+CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1))):CV.Acquisition.VarBuffersize(app.Petition.S_SEL(1)),1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1))];
                            else
                                SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1));
                            end
                        else
                            SORTING_ORDER=1:CV.Recorder.AcquisitionNonTimeStampedLastWrittenElement(app.Petition.S_SEL(1));
                        end
                        DATI_X=CV.Recorder.Data{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                        DATI_X_timestamped=0; 
                        if(CV.Acquisition.BSA || app.Petition.S_SEL(1)~=1)
                            DATI_X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.S_SEL(1)}(SORTING_ORDER);  
                        else
                            DATI_X_AcquisitionWritingCycle=CV.Recorder.AcquisitionWritingCycle_NonTimeStamped{app.Petition.S_SEL(1)}(SORTING_ORDER,app.Petition.S_SEL(2));
                        end
                    end  
                end %End asynchronous
%                 if(app.Petition.ShowStats)
%                     TABRES=cell(length(app.Petition.Quantity)+2,4);
%                     TABRES{1,1}='PV |'; TABRES{1,2}='      AVG      | |'; TABRES{1,3}='     STD      | |'; TABRES{1,4}='     Samples     | |';
%                 end
%                 if(app.Petition.TypeOfPlot<4) % X/Y dots plot
%                     for II=1:length(app.Petition.Quantity)
%                         if(~DATI_X_timestamped && ~DATI_Y_timestamped(II)) %plot averages that have same acquisition writing cycle, no filtering is possible.
%                             [~,WX,WY]=intersect(DATI_X_AcquisitionWritingCycle,DATI_Y_AcquisitionWritingCycle{II},'stable');
%                             if(app.Petition.Quantity(II).LastN<length(WX))
%                                 DX=DATI_X(WX((end-app.Petition.Quantity(II).LastN+1):end));
%                                 DY=DATI_Y{II}(WY((end-app.Petition.Quantity(II).LastN+1):end));
%                             else
%                                 DX=DATI_X(WX);
%                                 DY=DATI_Y{II}(WY);
%                             end
%                             if(app.Petition.ShowStats) 
%                                 if(II==1)
%                                     if(~isempty(DX))
%                                         TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=num2str(mean(DX)); TABRES{II+1,3}=num2str(std(DX)); TABRES{II+1,4}=num2str(length(DX));
%                                     else
%                                         TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=' - '; TABRES{II+1,3}=' - '; TABRES{II+1,4}=' - ';
%                                     end
%                                 end
%                                 if(~isempty(DY))
%                                     TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}=num2str(mean(DY)); TABRES{II+2,3}=num2str(std(DY)); TABRES{II+2,4}=num2str(length(DY));
%                                 else
%                                     TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}='NaN'; TABRES{II+2,3}='NaN'; TABRES{II+2,4}='NaN';
%                                 end
%                             end
%                             if(~isempty(app.Petition.Function)), DX=app.Petition.Function(DX); end
%                             if(~isempty(app.Petition.Quantity(II).Function)), DY=app.Petition.Quantity(II).Function(DY); end
%                             switch(app.Petition.TypeOfPlot)
%                                 case 1
%                                     plot(app.ASSE, DX, DY, app.Petition.Quantity(II).Stilnovo);
%                                 case 2
%                                     if(II==1)
%                                         [Mappa, xax, yax] = CV.DensityMap(DX,DY,app.Petition.binsx,app.Petition.binsy);
%                                     end
%                                     imagesc(xax,yax,Mappa,'parent',app.ASSE);
%                                     break
%                                 case 3
%                                     %[DY, stdPartition, DX, Centers, Widths, Elements]=CV.PartitionPlotScalar(obj, XData, YData, PartitionPos, PartitionWidth);
%                                     [DY, stdDY, DX]=CV.PartitionPlotScalar(DX, DY, app.Petition.PartitionPos, app.Petition.PartitionWidth);
%                                     if(app.Petition.ShowStats)
%                                         if(II==1)
%                                             TABRES=[TABRES,cell(size(TABRES,1),3)]; %#ok<AGROW> 
%                                             TABRES{1,end}='Max Val'; TABRES{1,end-1}='Max PosID'; TABRES{1,end-2}='Max Pos';
%                                             TABRES{2,end}=' - '; TABRES{2,end-1} = ' - '; TABRES{2,end-2} = ' - ';
%                                         end
%                                         [MaxVal, MaxPos]=max(DY);
%                                         MaxX=DX(MaxPos);
%                                         TABRES{2+II,end}=num2str(MaxVal);
%                                         TABRES{2+II,end-1}=num2str(MaxPos);
%                                         TABRES{2+II,end-2}=num2str(MaxX);
%                                     end
%                                     if(isnan(app.Petition.ShowOnly))
%                                         errorbar(app.ASSE,DX,DY,stdDY,app.Petition.Quantity(II).Stilnovo);
%                                     else
%                                         Display=find(app.Petition.ShowOnly==(1:length(DX)));
%                                         if(~isempty(Display))
%                                             errorbar(app.ASSE,DX(Display),DY(Display),stdDY(Display),app.Petition.Quantity(II).Stilnovo);
%                                         end
%                                     end
%                             end              
%                         elseif(DATI_X_timestamped && DATI_Y_timestamped(II))
%                             if(app.DataStructure.Synchronous) %apply filters
%                                 FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
%                                 switch(FilterNum)
%                                     case 1, ID=1;
%                                     case 2, ID=2;
%                                     case 3, ID=3;
%                                     case 6, ID=4;
%                                 end
%                                 DX=DATI_X(TF{ID}); DY=DATI_Y{II}(TF{ID});
%                             else %calculate intersection, but do not use filters.
%                                 [~,WX,WY]=intersect(DATI_X_timestamps,DATI_Y_timestamps{II},'stable');
%                                 DX=DATI_X(WX);
%                                 DY=DATI_Y{II}(WY);
%                             end
%                             if(app.Petition.Quantity(II).LastN<length(DX))
%                                 DX=DX((end-app.Petition.Quantity(II).LastN+1):end);
%                                 DY=DY((end-app.Petition.Quantity(II).LastN+1):end);
%                             end
%                             if(app.Petition.ShowStats)
%                                 if(II==1)
%                                     if(~isempty(DX))
%                                         TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=num2str(mean(DX)); TABRES{II+1,3}=num2str(std(DX)); TABRES{II+1,4}=num2str(length(DX));
%                                     else
%                                         TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=' - '; TABRES{II+1,3}=' - '; TABRES{II+1,4}=' - ';
%                                     end
%                                 end
%                                 if(~isempty(DY))
%                                     TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}=num2str(mean(DY)); TABRES{II+2,3}=num2str(std(DY)); TABRES{II+2,4}=num2str(length(DY));
%                                 else
%                                     TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}='NaN'; TABRES{II+2,3}='NaN'; TABRES{II+2,4}='NaN';
%                                 end
%                             end
%                             if(~isempty(app.Petition.Function)), DX=app.Petition.Function(DX); end
%                             if(~isempty(app.Petition.Quantity(II).Function)), DY=app.Petition.Quantity(II).Function(DY); end
%                             switch(app.Petition.TypeOfPlot)
%                                 case 1
%                                     plot(app.ASSE, DX, DY, app.Petition.Quantity(II).Stilnovo);
%                                 case 2
%                                     if(II==1)
%                                         [Mappa, xax, yax] = CV.DensityMap(DX,DY,app.Petition.binsx,app.Petition.binsy);
%                                     end
%                                     imagesc(xax,yax,Mappa,'parent',app.ASSE);
%                                     break
%                                 case 3
%                                     %[DY, stdPartition, DX, Centers, Widths, Elements]=CV.PartitionPlotScalar(obj, XData, YData, PartitionPos, PartitionWidth);
%                                     [DY, stdDY, DX]=CV.PartitionPlotScalar(DX, DY, app.Petition.PartitionPos, app.Petition.PartitionWidth);
%                                     if(app.Petition.ShowStats)
%                                         if(II==1)
%                                             TABRES=[TABRES,cell(size(TABRES,1),3)]; %#ok<AGROW> 
%                                             TABRES{1,end}='Max Val'; TABRES{1,end-1}='Max PosID'; TABRES{1,end-2}='Max Pos';
%                                             TABRES{2,end}=' - '; TABRES{2,end-1} = ' - '; TABRES{2,end-2} = ' - ';
%                                         end
%                                         [MaxVal, MaxPos]=max(DY);
%                                         MaxX=DX(MaxPos);
%                                         TABRES{2+II,7}=num2str(MaxVal);
%                                         TABRES{2+II,6}=num2str(MaxPos);
%                                         TABRES{2+II,5}=num2str(MaxX);
%                                     end
%                                     if(isnan(app.Petition.ShowOnly))
%                                         errorbar(app.ASSE,DX,DY,stdDY,app.Petition.Quantity(II).Stilnovo);
%                                     else
%                                         Display=find(app.Petition.ShowOnly==(1:length(DX)));
%                                         if(~isempty(Display))
%                                             errorbar(app.ASSE,DX(Display),DY(Display),stdDY(Display),app.Petition.Quantity(II).Stilnovo);
%                                         end
%                                     end
%                             end 
%                         elseif(DATI_X_timestamped)
%                             if(app.DataStructure.Synchronous) %apply filters
%                                 FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
%                                 switch(FilterNum)
%                                     case 1, ID=1;
%                                     case 2, ID=2;
%                                     case 3, ID=3;
%                                     case 6, ID=4;
%                                 end
%                                 DX=DATI_X(TF{ID}); WCX=DATI_X_AcquisitionWritingCycle(TF{ID});
%                             else
%                                 DX=DATI_X; WCX=DATI_X_AcquisitionWritingCycle;
%                             end
%                             DY=CV.NonTimeStamped_On_Timestamped(DATI_Y_AcquisitionWritingCycle{II},DATI_Y{II},WCX);
%                             if(app.Petition.Quantity(II).LastN<length(DX))
%                                 DX=DX((end-app.Petition.Quantity(II).LastN+1):end);
%                                 DY=DY((end-app.Petition.Quantity(II).LastN+1):end);
%                             end
%                             if(app.Petition.ShowStats)
%                                 if(II==1)
%                                     if(~isempty(DX))
%                                         TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=num2str(mean(DX)); TABRES{II+1,3}=num2str(std(DX)); TABRES{II+1,4}=num2str(length(DX));
%                                     else
%                                         TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=' - '; TABRES{II+1,3}=' - '; TABRES{II+1,4}=' - ';
%                                     end
%                                 end
%                                 if(~isempty(DY))
%                                     TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}=num2str(mean(DY)); TABRES{II+2,3}=num2str(std(DY)); TABRES{II+2,4}=num2str(length(DY));
%                                 else
%                                     TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}='NaN'; TABRES{II+2,3}='NaN'; TABRES{II+2,4}='NaN';
%                                 end
%                             end
%                             if(~isempty(app.Petition.Function)), DX=app.Petition.Function(DX); end
%                             if(~isempty(app.Petition.Quantity(II).Function)), DY=app.Petition.Quantity(II).Function(DY); end
%                             switch(app.Petition.TypeOfPlot)
%                                 case 1
%                                     plot(app.ASSE, DX, DY, app.Petition.Quantity(II).Stilnovo);
%                                 case 2
%                                     if(II==1)
%                                         [Mappa, xax, yax] = CV.DensityMap(DX,DY,app.Petition.binsx,app.Petition.binsy);
%                                     end
%                                     imagesc(xax,yax,Mappa,'parent',app.ASSE);
%                                     break
%                                 case 3
%                                     %[DY, stdPartition, DX, Centers, Widths, Elements]=CV.PartitionPlotScalar(obj, XData, YData, PartitionPos, PartitionWidth);
%                                     [DY, stdDY, DX]=CV.PartitionPlotScalar(DX, DY, app.Petition.PartitionPos, app.Petition.PartitionWidth);
%                                     if(app.Petition.ShowStats)
%                                         if(II==1)
%                                             TABRES=[TABRES,cell(size(TABRES,1),3)]; %#ok<AGROW> 
%                                             TABRES{1,end}='Max Val'; TABRES{1,end-1}='Max PosID'; TABRES{1,end-2}='Max Pos';
%                                             TABRES{2,end}=' - '; TABRES{2,end-1} = ' - '; TABRES{2,end-2} = ' - ';
%                                         end
%                                         [MaxVal, MaxPos]=max(DY);
%                                         MaxX=DX(MaxPos);
%                                         TABRES{2+II,end}=num2str(MaxVal);
%                                         TABRES{2+II,end-1}=num2str(MaxPos);
%                                         TABRES{2+II,end-2}=num2str(MaxX);
%                                     end
%                                     if(isnan(app.Petition.ShowOnly))
%                                         errorbar(app.ASSE,DX,DY,stdDY,app.Petition.Quantity(II).Stilnovo);
%                                     else
%                                         Display=find(app.Petition.ShowOnly==(1:length(DX)));
%                                         if(~isempty(Display))
%                                             errorbar(app.ASSE,DX(Display),DY(Display),stdDY(Display),app.Petition.Quantity(II).Stilnovo);
%                                         end
%                                     end
%                             end 
%                         elseif(DATI_Y_timestamped(II))
%                             if(app.DataStructure.Synchronous) %apply filters
%                                 FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
%                                 switch(FilterNum)
%                                     case 1, ID=1;
%                                     case 2, ID=2;
%                                     case 3, ID=3;
%                                     case 6, ID=4;
%                                 end
%                                 DY=DATI_Y{II}(TF{ID}); WCY=DATI_Y_AcquisitionWritingCycle{II}(TF{ID});
%                             else
%                                 DY=DATI_Y; WCY=DATI_Y_AcquisitionWritingCycle{II};
%                             end
%                             DX=CV.NonTimeStamped_On_Timestamped(DATI_X_AcquisitionWritingCycle,DATI_X,WCY);
%                             if(app.Petition.Quantity(II).LastN<length(DX))
%                                 DX=DX((end-app.Petition.Quantity(II).LastN+1):end);
%                                 DY=DY((end-app.Petition.Quantity(II).LastN+1):end);
%                             end
%                             if(app.Petition.ShowStats)
%                                 if(II==1)
%                                     if(~isempty(DX))
%                                         TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=num2str(mean(DX)); TABRES{II+1,3}=num2str(std(DX)); TABRES{II+1,4}=num2str(length(DX));
%                                     else
%                                         TABRES{II+1,1}=['x: ',app.S_Scalar.Items{app.S_Scalar.Value}];TABRES{II+1,2}=' - '; TABRES{II+1,3}=' - '; TABRES{II+1,4}=' - ';
%                                     end
%                                 end
%                                 if(~isempty(DY))
%                                     TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}=num2str(mean(DY)); TABRES{II+2,3}=num2str(std(DY)); TABRES{II+2,4}=num2str(length(DY));
%                                 else
%                                     TABRES{II+2,1}=app.Petition.Quantity(II).Name;TABRES{II+2,2}='NaN'; TABRES{II+2,3}='NaN'; TABRES{II+2,4}='NaN';
%                                 end
%                             end
%                             if(~isempty(app.Petition.Function)), DX=app.Petition.Function(DX); end
%                             if(~isempty(app.Petition.Quantity(II).Function)), DY=app.Petition.Quantity(II).Function(DY); end
%                             switch(app.Petition.TypeOfPlot)
%                                 case 1
%                                     plot(app.ASSE, DX, DY, app.Petition.Quantity(II).Stilnovo);
%                                 case 2
%                                     if(II==1)
%                                         [Mappa, xax, yax] = CV.DensityMap(DX,DY,app.Petition.binsx,app.Petition.binsy);
%                                     end
%                                     imagesc(xax,yax,Mappa,'parent',app.ASSE);
%                                     break
%                                 case 3
%                                     %[DY, stdPartition, DX, Centers, Widths, Elements]=CV.PartitionPlotScalar(obj, XData, YData, PartitionPos, PartitionWidth);
%                                     [DY, stdDY, DX]=CV.PartitionPlotScalar(DX, DY, app.Petition.PartitionPos, app.Petition.PartitionWidth);
%                                     if(app.Petition.ShowStats)
%                                         if(II==1)
%                                             TABRES=[TABRES,cell(size(TABRES,1),3)]; %#ok<AGROW> 
%                                             TABRES{1,end}='Max Val'; TABRES{1,end-1}='Max PosID'; TABRES{1,end-2}='Max Pos';
%                                             TABRES{2,end}=' - '; TABRES{2,end-1} = ' - '; TABRES{2,end-2} = ' - ';
%                                         end
%                                         [MaxVal, MaxPos]=max(DY);
%                                         MaxX=DX(MaxPos);
%                                         TABRES{2+II,end}=num2str(MaxVal);
%                                         TABRES{2+II,end-1}=num2str(MaxPos);
%                                         TABRES{2+II,end-2}=num2str(MaxX);
%                                     end
%                                     if(isnan(app.Petition.ShowOnly))
%                                         errorbar(app.ASSE,DX,DY,stdDY,app.Petition.Quantity(II).Stilnovo);
%                                     else
%                                         Display=find(app.Petition.ShowOnly==(1:length(DX)));
%                                         if(~isempty(Display))
%                                             errorbar(app.ASSE,DX(Display),DY(Display),stdDY(Display),app.Petition.Quantity(II).Stilnovo);
%                                         end
%                                     end
%                             end 
%                         end
%                         switch(app.Petition.TypeOfFit)
%                             case 1 % No fit.
%                             case 2 % Polynomial fit
%                                 PosOffset=0;
%                                 if(II==1)
%                                     if(app.Petition.ShowStats)
%                                         if(app.Petition.PolyOrder==2)
%                                             TABRES=[TABRES,cell(size(TABRES,1),5)]; %#ok<AGROW> 
%                                             TABRES{1,end}='max'; TABRES{1,end-1}='extreme'; TABRES{1,end-4}=' power 2 '; TABRES{1,end-3}=' power 1 ';TABRES{1,end-2}=' power 0 ';
%                                             TABRES{2,end}=' - '; TABRES{2,end-1}=' - '; TABRES{2,end-4}=' - '; TABRES{2,end-3}=' - ';TABRES{2,end-2}=' - ';
%                                             PosOffset=2;
%                                         else
%                                             TABRES=[TABRES,cell(size(TABRES,1),app.Petition.PolyOrder+1)]; %#ok<AGROW> 
%                                             for ZZ=app.Petition.PolyOrder:-1:0
%                                                 TABRES{1,end-ZZ} = ['power ',num2str(app.Petition.PolyOrder-ZZ)];
%                                                 TABRES{2,end-ZZ}=' - ';
%                                             end
%                                         end
%                                     end
%                                     mx=min(DX); MX=max(DX);
%                                     x=linspace(mx-abs(MX-mx)/10,MX+abs(MX-mx)/10);
%                                 end
%                                 [DX0,DY0]=CV.ReduceToUniqueX(DX,DY);
%                                 POLY=polyfit(DX0,DY0,app.Petition.PolyOrder);
%                                 if(app.Petition.ShowStats)
%                                     for ZZ=app.Petition.PolyOrder:-1:0
%                                        TABRES{II+2,end-ZZ-PosOffset} =num2str(POLY(ZZ+1)); 
%                                     end
%                                 end
%                                 plot(app.ASSE,x,polyval(POLY,x),regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-'));
%                                 if(app.Petition.ShowStats)
%                                     if(app.Petition.PolyOrder==2)
%                                         Extreme=-POLY(2)/2/POLY(1);
%                                         TABRES{II+2,end-1} =num2str(Extreme);
%                                         TABRES{II+2,end} =num2str(polyval(POLY,Extreme));
%                                     end
%                                 end
%                             case 3 % Gaussian fit
%                                 if(II==1)
%                                     TABRES{size(TABRES,1),end+4}='';
%                                     TABRES{1,end-4}='A'; TABRES{1,end-3}='B'; TABRES{1,end-2}='C'; TABRES{1,end-1}='x0'; TABRES{1,end}='y0';
%                                 end
%                                 PARAM=[app.Petition.A,app.Petition.x0,app.Petition.C,app.Petition.B,app.Petition.y0];
%                                 [DX0,DY0]=CV.ReduceToUniqueX(DX,DY);
%                                 GAUSS_PAR=CV.FreeGaussFit(DX0,DY0,PARAM);
%                                 plot(app.ASSE,GAUSS_PAR.XV,GAUSS_PAR.Fit,regexprep(app.Petition.Quantity(II).Stilnovo,'\.','-'));
%                                 if(app.Petition.ShowStats)
%                                     TABRES{II+2,end-4}=num2str(GAUSS_PAR.Param(1)); TABRES{II+2,end-3}=num2str(GAUSS_PAR.Param(4)); TABRES{II+2,end-2}=num2str(GAUSS_PAR.Param(3)); TABRES{II+2,end-1}=num2str(GAUSS_PAR.Param(2)); TABRES{II+2,end}=num2str(GAUSS_PAR.Param(5));
%                                 end
%                         end
%                     end
%                 end
                
%                 if(app.Petition.TypeOfPlot==4) % Histogram on X.
%                     if(DATI_X_timestamped)
%                         if(app.DataStructure.Synchronous) %apply filters
%                             FilterNum=2^(app.Petition.Quantity(II).Filter(1))*3^(app.Petition.Quantity(II).Filter(2));
%                             switch(FilterNum)
%                                 case 1, ID=1;
%                                 case 2, ID=2;
%                                 case 3, ID=3;
%                                 case 6, ID=4;
%                             end
%                             DX=DATI_X(TF{ID});
%                         else
%                             DX=DATI_X;
%                         end
%                     else
%                         DX=DATI_X;
%                     end
%                     [c,d]=hist(DX,app.Petition.binsx); %#ok<HIST>
%                     bar(app.ASSE,c,d);
%                 end

%                 if(app.Petition.ShowStats)
%                     MT=CV.TextMatrixToTextColumn(TABRES);
%                     app.Permannotation.String=MT;
%                 end

                if(~isempty(app.AdditionalOrbits))
                    for II=1:numel(app.AdditionalOrbits)
                        UseX=((app.AdditionalOrbits{II}.z>=app.BPM_Sorting(app.Petition.Area).X.zRange(1)) & (app.AdditionalOrbits{II}.z<=app.BPM_Sorting(app.Petition.Area).X.zRange(2)) );
                        UseY=((app.AdditionalOrbits{II}.z>=app.BPM_Sorting(app.Petition.Area).Y.zRange(1)) & (app.AdditionalOrbits{II}.z<=app.BPM_Sorting(app.Petition.Area).Y.zRange(2)) );
                        plot(app.ASSE2,app.AdditionalOrbits{II}.z(UseX),app.AdditionalOrbits{II}.Xorbit(UseX),'-s','color',app.LineColor(II,:));
                        plot(app.ASSE,app.AdditionalOrbits{II}.z(UseY),app.AdditionalOrbits{II}.Yorbit(UseY),'-s','color',app.LineColor(II,:));
                    end
                end

                xlim(app.ASSE2,app.Petition.xLim);
                ylim(app.ASSE2,app.Petition.yLim);
                xlim(app.ASSE,app.Petition.xLim2);
                ylim(app.ASSE,app.Petition.yLim2);


                if(app.Petition.Logbook)
                    app.logBookButtonCall(CV);
                    app.LogbookButton.BackgroundColor=app.color.logbook; app.Petition.Logbook=0;
                end
            end
        end
        
        function [SUCCESS,filename,TargetDir] = writeDataToDisk(app,TimeNow,CVObject,auxillaryData)
            CurrentTime=datevec(TimeNow);
            CurrentYearString=num2str(CurrentTime(1),'%.4d'); CurrentMonthString=num2str(CurrentTime(2),'%.2d');
            CurrentDayString=num2str(CurrentTime(3),'%.2d'); CurrentTempo1String=num2str(CurrentTime(4),'%.2d');
            CurrentTempo2String=num2str(CurrentTime(5),'%.2d'); CurrentTempo3String=num2str(floor(CurrentTime(6)),'%.2d');
            CurrentTempo4String=num2str(round((CurrentTime(6)-floor(CurrentTime(6)))*1000),'%.3d');
            CurrentTimeString=[CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString,'--',CurrentTempo1String,'-',CurrentTempo2String,'-',CurrentTempo3String,'-',CurrentTempo4String];
            filename=['OnlineMonitor-','ScalarAndScalar','-',CurrentTimeString];
            TargetDir=['/u1/lcls/matlab/data/',CurrentYearString,'/',CurrentYearString,'-',CurrentMonthString,'/',CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString];
            if(~isfolder(TargetDir))
                mkdir(TargetDir);
            end
            DataStructure=CVObject.Acquisition; Data=CVObject.Recorder; %#ok<ADPROPLC> 
            try
                save([TargetDir,'/',filename],'Data','DataStructure','auxillaryData')
                SUCCESS=1;
            catch
                SUCCESS=0;
            end
        end
    end
    
    methods (Access = private)
        
        function void = UpdateLimits(app,direction)
            if(direction=='x')
                valuem = str2num(app.XMinEditField.Value); %#ok<ST2NM> 
                valueM = str2num(app.XMaxEditField.Value); %#ok<ST2NM> 
            else
                valuem = str2num(app.YMinEditField.Value); %#ok<ST2NM> 
                valueM = str2num(app.YMaxEditField.Value); %#ok<ST2NM> 
            end
            if(isempty(valuem)), valuem=-inf; end
            if(isempty(valueM)), valueM=+inf; end
            valuem=valuem(1); valueM=valueM(1);
            if(valuem>valueM)
                temp=valuem; valuem=valueM; valueM=temp; 
                if(direction=='x')
                    app.XMinEditField.Value = num2str(valuem); app.XMaxEditField.Value = num2str(valueM);
                else
                    app.YMinEditField.Value = num2str(valuem); app.YMaxEditField.Value = num2str(valueM);
                end
            end
            if(abs(valuem-valueM)<10^-13)
                valueM=valueM+10^-13/2; valuem=valuem-10^-13/2;
            end
            if(direction=='x')
                app.Petition.xLim=[valuem,valueM];
            else
                app.Petition.yLim=[valuem,valueM];
            end
            void=0;
        end
        
        function void = UpdateLimits2(app,direction)
            if(direction=='x')
                valuem = str2num(app.XMinEditField_2.Value); %#ok<ST2NM> 
                valueM = str2num(app.XMaxEditField_2.Value); %#ok<ST2NM> 
            else
                valuem = str2num(app.YMinEditField_2.Value); %#ok<ST2NM> 
                valueM = str2num(app.YMaxEditField_2.Value); %#ok<ST2NM> 
            end
            if(isempty(valuem)), valuem=-inf; end
            if(isempty(valueM)), valueM=+inf; end
            valuem=valuem(1); valueM=valueM(1);
            if(valuem>valueM)
                temp=valuem; valuem=valueM; valueM=temp; 
                if(direction=='x')
                    app.XMinEditField_2.Value = num2str(valuem); app.XMaxEditField_2.Value = num2str(valueM);
                else
                    app.YMinEditField_2.Value = num2str(valuem); app.YMaxEditField_2.Value = num2str(valueM);
                end
            end
            if(abs(valuem-valueM)<10^-13)
                valueM=valueM+10^-13/2; valuem=valuem-10^-13/2;
            end
            if(direction=='x')
                app.Petition.xLim2=[valuem,valueM];
            else
                app.Petition.yLim2=[valuem,valueM];
            end
            void=0;
        end
        
        function void = UpdateAmpLimits(app)
            valuem = str2num(app.MinEditField.Value); %#ok<ST2NM> 
            valueM = str2num(app.MaxEditField.Value); %#ok<ST2NM> 

            if(isempty(valuem)), valuem=-inf; end
            if(isempty(valueM)), valueM=+inf; end
            valuem=valuem(1); valueM=valueM(1);
            if(valuem>valueM)
                temp=valuem; valuem=valueM; valueM=temp; 
                app.MinEditField.Value = num2str(valuem); app.MaxEditField_2.Value = num2str(valueM);
            end
            if(abs(valuem-valueM)<10^-13)
                valueM=valueM+10^-13/2; valuem=valuem-10^-13/2;
            end
            app.Petition.AmpRange=[valuem,valueM];
            void=0;
        end
        
        function wasvalid = CheckExistenceMakeAxes(app)
            wasvalid=1;
            if(~isvalid(app.PGR))            
                app.PGR=figure('Name',[app.(app.FIELDNAMES{(cellfun(@(x) any(x),strfind(fieldnames(app),'UIFigure')))}).Name,' *-* Display']);
                app.PGR.Position=[app.PGR.Position(1)-440,app.PGR.Position(2)-180,1000,600];
                app.ASSE=axes(app.PGR,'Position',[0.11,0.09,0.8450,0.8250/2]);
                app.ASSE2=axes(app.PGR,'Position',[0.11,0.09+0.8250/2+0.05,0.8450,0.8250/2]);
                app.Permannotation=annotation(app.PGR,'textbox',[0 0 1 0.7],'EdgeColor','none','HorizontalAlignment','left','Interpreter','latex');
                %delete(axtoolbar(app.ASSE));
                app.ASSE.Interactions=[];app.ASSE.Toolbar=[];
                app.ASSE2.Interactions=[];app.ASSE2.Toolbar=[];
                app.PGR.MenuBar='none'; app.PGR.ToolBar='none';
                app.ASSE.NextPlot='replacechildren'; app.ASSE2.NextPlot='replacechildren';
                app.ASSEDefaultSize=app.ASSE.Position(3:4);
                wasvalid=0;
                if(app.BoxGridCheckBox.Value), box(app.ASSE,'on'); grid(app.ASSE,'on'); else, box(app.ASSE,'off'); grid(app.ASSE,'off'); end 
                if(app.BoxGridCheckBox.Value), box(app.ASSE2,'on'); grid(app.ASSE2,'on'); else, box(app.ASSE2,'off'); grid(app.ASSE2,'off'); end 
                app.ManageFitVisibility(0);
                app.Petition.ShowStats=app.ShowstatsCheckBox.Value;
                if(~app.Petition.ShowStats)
                    set(app.Permannotation,'String','');
                    SA=0;
                    set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                    set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
                else
                    SA=size(app.TabellaY.Data,1)+1;
                    set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                    set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
                end
            end
        end
        
        function moveAxisNearToMe(app)
            app.CheckExistenceMakeAxes();
            switch app.PositionDropDown.Value
                case 'Right'
                    PGRNewPosition=[app.OrbitDisplayUIFigure.Position(1)+app.OrbitDisplayUIFigure.Position(3)+1,app.OrbitDisplayUIFigure.Position(2),app.PGR.Position(3),app.PGR.Position(4)];
                case 'Above'
                    PGRNewPosition=[app.OrbitDisplayUIFigure.Position(1),app.OrbitDisplayUIFigure.Position(2)+app.OrbitDisplayUIFigure.Position(4)+1,app.PGR.Position(3),app.PGR.Position(4)];
                case 'Left'
                    PGRNewPosition=[app.OrbitDisplayUIFigure.Position(1)-app.PGR.Position(3)-1,app.OrbitDisplayUIFigure.Position(2),app.PGR.Position(3),app.PGR.Position(4)];
                case 'Below'
                    PGRNewPosition=[app.OrbitDisplayUIFigure.Position(1),app.OrbitDisplayUIFigure.Position(2)-app.PGR.Position(4)-1,app.PGR.Position(3),app.PGR.Position(4)];
            end
            app.PGR.Position=PGRNewPosition;
            figure(app.PGR);
        end
        
        function void = getAppColors(app)
            void=0;
            app.color.Idle=app.AxesFigureButton.BackgroundColor;
            app.color.On=[0,1,0];
            app.color.Off=[1,0,0];
            app.color.Wait=[1,1,0];
            app.color.logbook=[0.35,0.86,1];
            app.color.logbook2Steps=[0.3,0.5,1];
            app.color.Live=[0,0,1];
            app.color.Scalar1=[0,0,0];
            app.color.Scalar2=[1,0,1];
            app.color.Fixed1=[0.8,0.1,0.1];
            app.color.Fixed2=[0.9,0.9,0];
            app.color.Fixed3=[0.3,0.6,1];
            app.color.ColorScale1=jet;%[linspace(0.8,1,256);linspace(0.8,0,256);linspace(0.8,0,256)].';
            app.color.ColorScale2=jet;%[linspace(0.8,0,256);linspace(0.8,0,256);linspace(0.8,1,256)].';
        end
        
        function ManageBPMList(app,CV)
            BPMEnv={'Undulator Line','LTU-DUMP','All BPMs'};
            BPMEnvN=1:length(BPMEnv);
            app.AreaDropDown.Items=BPMEnv;
            app.AreaDropDown.ItemsData=BPMEnvN;
            app.AreaDropDown.Value=app.Petition.Area;
            for II=1:length(BPMEnv)
                switch(II)
                    case 1
                        INDEX=cellfun(@(x) contains(x,'UND'),app.ARC.static.bpmList_e);
                    case 2
                        INDEX=cellfun(@(x) (contains(x,'UND') | contains(x,'LTU') | contains(x,'DMP')),app.ARC.static.bpmList_e);
                    case 3
                        INDEX=cellfun(@(x) (contains(x,'BPMS')),app.ARC.static.bpmList_e); 
                end
                BaseNames=app.ARC.static.bpmList_e(INDEX);
                zBaseNames=app.ARC.static.zBPM(INDEX);
                [~,TempZPos,TempPos]=intersect(strcat(BaseNames,':X'),CV.Acquisition.Scalars,'stable');
                app.BPM_Sorting(II).X.FullPos=CV.Acquisition.Scalars_Positions(TempPos,:);
                app.BPM_Sorting(II).X.z=zBaseNames(TempZPos);
                [~,TempZPos,TempPos]=intersect(strcat(BaseNames,':Y'),CV.Acquisition.Scalars,'stable');
                app.BPM_Sorting(II).Y.FullPos=CV.Acquisition.Scalars_Positions(TempPos,:);
                app.BPM_Sorting(II).Y.z=zBaseNames(TempZPos);
                [~,TempZPos,TempPos]=intersect(strcat(BaseNames,':T'),CV.Acquisition.Scalars,'stable');
                app.BPM_Sorting(II).T.FullPos=CV.Acquisition.Scalars_Positions(TempPos,:);
                app.BPM_Sorting(II).T.z=zBaseNames(TempZPos);
                try
                    app.BPM_Sorting(II).DataPosition=app.BPM_Sorting(II).X.FullPos(1);
                catch
                    app.BPM_Sorting(II).DataPosition=1;
                end
                app.BPM_Sorting(II).X.POS=app.BPM_Sorting(II).X.FullPos(:,2);
                app.BPM_Sorting(II).Y.POS=app.BPM_Sorting(II).Y.FullPos(:,2);
                app.BPM_Sorting(II).T.POS=app.BPM_Sorting(II).T.FullPos(:,2); 
                app.BPM_Sorting(II).X.zRange=[min(app.BPM_Sorting(II).X.z),max(app.BPM_Sorting(II).X.z)];
                app.BPM_Sorting(II).Y.zRange=[min(app.BPM_Sorting(II).Y.z),max(app.BPM_Sorting(II).Y.z)];
                app.BPM_Sorting(II).T.zRange=[min(app.BPM_Sorting(II).T.z),max(app.BPM_Sorting(II).T.z)];
            end
        end
        
        function UpdateInitialize(app,CV)
            app.LogButton.Text=['','-> Log'];
            app.LogButton.Enable='Off';
            if(~isequal(CV.Acquisition,app.DataStructure))
                app.DataStructure=CV.Acquisition;
                app.Petition.Logbook=0;
                app.LogbookButton.BackgroundColor=app.color.logbook;
                List=app.EvaluateAllLists();
                %List.SynchScalars List.SynchScalarsPositions 
                %List.Scalars List.ScalarsPositions
                %List.Vectors List.VectorsPositions
                %List.Array2D List.Array2DPositions
                
                app.ManageFilterTable(app.TABULA,List);
%                 app.ManageScalarsTable(app.TabellaY,List);
                app.ManageScalarsSelection(app.S_Scalar_1,List,'S_SEL1');
                app.ManageScalarsSelection(app.S_Scalar_2,List,'S_SEL2');
                app.ManageFitVisibility(1);
                app.ManageOtherInputs();
                app.ManageTypeOfPlotVisibility(1);
                app.ManageBPMList(CV)
  
                if(isinf(app.Petition.xLim(1))), app.XMinEditField.Value=''; else, app.XMinEditField.Value=num2str(app.Petition.xLim(1)); end
                if(isinf(app.Petition.xLim(2))), app.XMaxEditField.Value=''; else, app.XMaxEditField.Value=num2str(app.Petition.xLim(2)); end
                if(isinf(app.Petition.yLim(1))), app.YMinEditField.Value=''; else, app.YMinEditField.Value=num2str(app.Petition.yLim(1)); end
                if(isinf(app.Petition.yLim(2))), app.YMaxEditField.Value=''; else, app.YMaxEditField.Value=num2str(app.Petition.yLim(2)); end
                
                if(isinf(app.Petition.xLim2(1))), app.XMinEditField.Value=''; else, app.XMinEditField.Value=num2str(app.Petition.xLim2(1)); end
                if(isinf(app.Petition.xLim2(2))), app.XMaxEditField.Value=''; else, app.XMaxEditField.Value=num2str(app.Petition.xLim2(2)); end
                if(isinf(app.Petition.yLim2(1))), app.YMinEditField.Value=''; else, app.YMinEditField.Value=num2str(app.Petition.yLim2(1)); end
                if(isinf(app.Petition.yLim2(2))), app.YMaxEditField.Value=''; else, app.YMaxEditField.Value=num2str(app.Petition.yLim2(2)); end
                
                if(isinf(app.Petition.AmpRange(1))), app.MinEditField.Value=''; else, app.MinEditField.Value=num2str(app.Petition.AmpRange(1)); end
                if(isinf(app.Petition.AmpRange(2))), app.MaxEditField.Value=''; else, app.MaxEditField.Value=num2str(app.Petition.AmpRange(2)); end
                
                if(~app.Petition.STOP)
                    app.STOP.Text='Now Running -> Stop Updating';
                    app.STOP.BackgroundColor=app.color.On;
                else
                    app.STOP.Text='Now Stopped -> Resume Updating';
                    app.STOP.BackgroundColor=app.color.Wait;
                end

                if(app.Petition.Save), app.SaveDataCheckBox.Value=1; else, app.SaveDataCheckBox.Value=0; end
                
                if(app.Petition.TwoStepLog), app.EditTextFigCheckBox.Value=1; else, app.EditTextFigCheckBox.Value=0; end
            end
            app.NeedInit=0;
        end
        
        function List=EvaluateAllLists(app)
           List.SynchScalars=app.DataStructure.Scalars;
           List.SynchScalarsPositions=app.DataStructure.Scalars_Positions;
           Delete=false(size(List.SynchScalarsPositions(:,1)));
           for AA=1:length(app.DataStructure.TimeStamped)
               if(~app.DataStructure.TimeStamped(AA))
                   Delete=Delete | (List.SynchScalarsPositions(:,1)==AA);
               end
           end
           List.NotTimeStampedScalars=List.SynchScalars(Delete);
           List.NotTimeStampedScalarsPositions=List.SynchScalarsPositions(Delete,:);
           List.NotTimeStampedScalars=['OFF';List.NotTimeStampedScalars];
           List.NotTimeStampedScalarsPositions=[[0,0,0];List.NotTimeStampedScalarsPositions];
           List.SynchScalars(Delete)=[];
           List.SynchScalarsPositions(Delete,:)=[];
           List.SynchScalars=['OFF';List.SynchScalars];
           List.SynchScalarsPositions=[[0,0,0];List.SynchScalarsPositions];
           List.Scalars=['OFF';app.DataStructure.Scalars];
           List.ScalarsPositions=[0,0,0;app.DataStructure.Scalars_Positions];
           List.Vectors=['OFF';app.DataStructure.Vectors];
           List.VectorsPositions=[0,0,0;app.DataStructure.Vectors_Positions];
           List.Arrays2D=['OFF';app.DataStructure.Arrays2D];
           List.Arrays2DPositions=[0,0,0;app.DataStructure.Arrays2D_Positions];
        end
        
        function ManageSynchScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.SynchScalars(:).';
            Bottone.ItemsData=1:length(List.SynchScalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.SynchScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageNonTimestampedScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.NotTimeStampedScalars(:).';
            Bottone.ItemsData=1:length(List.NotTimeStampedScalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.NotTimeStampedScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end

        
        function ManageFilterTable(app,FilterTable,List)
            TAB_CF=FilterTable.ColumnFormat;
            TAB_CF{1}=List.SynchScalars(:).';
            TAB_CF{2}='numeric'; TAB_CF{3}='numeric'; TAB_CF{4}='logical'; TAB_CF{5}='logical';
            FilterTable.ColumnFormat=TAB_CF;
            FilterTable.ColumnEditable=[true,true,true,true,true];
            for II=app.Petition.FiltriNumber:-1:1
                IDPOS=find(strcmp(List.SynchScalars,app.Petition.FilterVarName{II}));
                if(isempty(IDPOS)) %needs to remove the filer, the variable is not recorded anymore
                    app.Petition.FiltriNumber=app.Petition.FiltriNumber-1;
                    app.Petition.Filter(II)=[];
                    app.Petition.FilterVarName(II)=[];
                    app.Petition.FilterUse(II)=[];
                else
                    IDPOS=IDPOS(1); %rebuild the filter with new position, even if it is the same one.
                    app.Petition.Filter{II}.SEL=List.SynchScalarsPositions(IDPOS,:);
                end
            end
            
            TABULA_DATA={};
            for II=1:app.Petition.FiltriNumber
                TABULA_DATA{II,1}=app.Petition.FilterVarName{II}; %#ok<AGROW>
                if(isinf(app.Petition.Filter{II}.Range(1)))
                    TABULA_DATA{II,2}=[]; %#ok<AGROW>
                else
                    if(~isnan(app.Petition.Filter{II}.Range(1)))
                        TABULA_DATA{II,2}=app.Petition.Filter{II}.Range(1); %#ok<AGROW>
                    else
                        app.Petition.Filter{II}.Range(1)=+inf;
                        TABULA_DATA{II,2}=[]; %#ok<AGROW>
                    end
                end
                if(isinf(app.Petition.Filter{II}.Range(2)))
                    TABULA_DATA{II,3}=[]; %#ok<AGROW>
                else
                    if(~isnan(app.Petition.Filter{II}.Range(2)))
                        TABULA_DATA{II,3}=app.Petition.Filter{II}.Range(2); %#ok<AGROW>
                    else
                        app.Petition.Filter{II}.Range(2)=+inf;
                        TABULA_DATA{II,3}=[]; %#ok<AGROW>
                    end
                end
                TABULA_DATA{II,4}=app.Petition.FilterUse{II}(1); %#ok<AGROW>
                TABULA_DATA{II,5}=app.Petition.FilterUse{II}(2);  %#ok<AGROW>
            end
          
            FilterTable.Data=TABULA_DATA;
            ReducedList.Positions=List.SynchScalarsPositions;
            ReducedList.Names=List.SynchScalars;
            FilterTable.UserData=ReducedList;
        end
        
        function ManageScalarsTable(app,TAB,List)
            TAB.ColumnName={'Filter 1','Filter 2','Scalar Variable Name','Style','Expression','LastN'};
            TAB_CF=TAB.ColumnFormat;
            TAB_CF{3}=List.Scalars(:).';
            TAB.ColumnFormat=TAB_CF;
            TAB.ColumnEditable=[true,true,true,true,true,true];
            InitData{1,1}=true;InitData{1,2}=true;InitData{1,3}=List.SynchScalars{1};InitData{1,4}='.b';InitData{1,5}='';InitData{1,6}=inf;
            TAB.Data=InitData;
            ins=0;
%             List.Scalars=['OFF';app.DataStructure.Scalars];
%             List.ScalarsPositions=[0,0,0;app.DataStructure.Scalars_Positions];
            if(~isempty(app.Petition.Quantity))
                FoundNone=1;
                for II=1:length(app.Petition.Quantity)
                    %Check if variable still exists...
                    ID=find(strcmp(app.Petition.Quantity(II).Name,List.Scalars));
                    if(isempty(ID))
                        app.Petition.Quantity(II)=[];
                    else
                        FoundNone=0; ins=ins+1;
                        NewQuantity(ins).Filter(1)=app.Petition.Quantity(II).Filter(1); %#ok<AGROW>
                        NewQuantity(ins).Filter(2)=app.Petition.Quantity(II).Filter(2); %#ok<AGROW>
                        NewQuantity(ins).Name=app.Petition.Quantity(II).Name; %#ok<AGROW>
                        NewQuantity(ins).Y_SEL=List.ScalarsPositions(ID,:); %#ok<AGROW>
                        NewQuantity(ins).Stilnovo=app.Petition.Quantity(II).Stilnovo; %#ok<AGROW>
                        TAB.Data{ins,1}=logical(app.Petition.Quantity(II).Filter(1)); 
                        TAB.Data{ins,2}=logical(app.Petition.Quantity(II).Filter(2));
                        TAB.Data{ins,3}=app.Petition.Quantity(II).Name; TAB.Data{ins,4}=app.Petition.Quantity(II).Stilnovo;
                        TAB.Data{ins,5}=''; NewQuantity(ins).Function=''; %#ok<AGROW>
                        NewQuantity(ins).LastN=app.Petition.Quantity(II).LastN; %#ok<AGROW>
                        TAB.Data{ins,6}=app.Petition.Quantity(II).LastN;
                    end
                end
                if(FoundNone)
                    TAB.Data{1,1}=true;TAB.Data{1,2}=true;TAB.Data{1,4}='.b';TAB.Data{1,5}='';TAB.Data{1,6}=inf;
                    TAB.Data{1,3}=List.SynchScalars{1}; app.Petition.Quantity=[];
                else
                    app.Petition.Quantity=NewQuantity;
                end
            else
                TAB.Data{1,1}=true;TAB.Data{1,2}=true;TAB.Data{1,4}='.b';TAB.Data{1,5}='';TAB.Data{1,6}=inf; 
                TAB.Data{1,3}=List.SynchScalars{1}; app.Petition.Quantity=[];
            end
            
            if(app.DataStructure.Synchronous)
                CE=TAB.ColumnEditable; CE(1)=true; CE(2)=true;
                TAB.ColumnEditable=CE;
            else
                CE=TAB.ColumnEditable; CE(1)=false; CE(2)=false;
                TAB.ColumnEditable=CE;
                TAB.Data(:,1:2)={false};
            end
            ReducedList.Positions=List.ScalarsPositions;
            ReducedList.Names=List.Scalars;
            TAB.UserData=ReducedList;
        end
        
        function ManageScalarsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Scalars(:).';
            Bottone.ItemsData=1:length(List.Scalars);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.ScalarsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end

        function ManageVectorsSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Vectors(:).';
            Bottone.ItemsData=1:length(List.Vectors);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.VectorsPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageArrays2DSelection(app,Bottone,List,PetitionName)
            CurrentValue=Bottone.Value;
            if(~ischar(CurrentValue))
                CurrentValue=Bottone.Items{CurrentValue};
            end
            Bottone.Items=List.Arrays2D(:).';
            Bottone.ItemsData=1:length(List.Arrays2D);
            ID=find(strcmp(CurrentValue,Bottone.Items));
            if(isempty(ID)), Bottone.Value=1; else,  Bottone.Value=ID; end
            ButtonUD.Pos=List.Arrays2DPositions;
            ButtonUD.PetitionName=PetitionName;
            Bottone.UserData=ButtonUD;
            app.ManageListChange(Bottone);
        end
        
        function ManageListChange(app,Bottone)
            app.Petition.(Bottone.UserData.PetitionName) = Bottone.UserData.Pos(Bottone.Value,:);
        end
        
        function UpdateTableSelection(app)
            ins=0;
            for II=1:size(app.TabellaY.Data,1)
                QTY = find(strcmp(app.TabellaY.Data{II,3},app.DataStructure.Scalars));
                if(isempty(QTY))
                    continue
                else
                    ins=ins+1;
                    Quantity(ins).Y_SEL=app.DataStructure.Scalars_Positions(QTY,:); %#ok<AGROW> 
                end
                Quantity(ins).Filter(1)=app.TabellaY.Data{II,1}; %#ok<AGROW> 
                Quantity(ins).Filter(2)=app.TabellaY.Data{II,2}; %#ok<AGROW> 
                Quantity(ins).Name=app.TabellaY.Data{II,3}; %#ok<AGROW> 
                Quantity(ins).Stilnovo=app.TabellaY.Data{II,4}; %#ok<AGROW> 
                if(isempty(app.TabellaY.Data{II,5}))
                    Quantity(ins).Function=''; %#ok<AGROW> 
                else
                    Quantity(ins).Function=eval(['@(x) ',vectorize(app.TabellaY.Data{II,5})]); %#ok<AGROW> 
                end
                Quantity(ins).LastN=app.TabellaY.Data{II,6}; %#ok<AGROW> 
            end
            if(ins==0)
                app.Petition.Quantity=[];
            else
                app.Petition.Quantity=Quantity;
            end
        end
        
        function UpdateFilterSelection(app)
            app.Petition.Filter={};app.Petition.FilterUse={}; app.Petition.FilterVarName={}; app.Petition.FiltriNumber=0;
            for II=1:size(app.TABULA.Data,1)
                if((~isempty(app.TABULA.Data{II,2}) && ~isnan(app.TABULA.Data{II,2})) || (~isempty(app.TABULA.Data{II,3}) && ~isnan(app.TABULA.Data{II,3}))) %there is something
                    VAL=app.DataStructure.Scalars_Positions(strcmp(app.TABULA.Data{II,1},app.DataStructure.Scalars),:);
                    if(isempty(VAL))
                        continue
                    end
                    if(~isempty(app.TABULA.Data{II,2}) && ~isnan(app.TABULA.Data{II,2}))
                        MIN=app.TABULA.Data{II,2};
                    else
                        MIN=-inf;
                    end
                    if(~isempty(app.TABULA.Data{II,3}) && ~isnan(app.TABULA.Data{II,3}))
                        MAX=app.TABULA.Data{II,3};
                    else
                        MAX=+inf;
                    end
                    app.Petition.Filter{end+1}.SEL=VAL; app.Petition.Filter{end}.Range=[MIN,MAX];
                    app.Petition.FiltriNumber=app.Petition.FiltriNumber+1;
                    app.Petition.FilterUse{end+1}(1)=app.TABULA.Data{II,4}; app.Petition.FilterUse{end}(2)=app.TABULA.Data{II,5};
                    app.Petition.FilterVarName{end+1}=app.TABULA.Data{II,1};
                end
            end
        end
        
        function logBookButtonCall(app,CVObject)
            TimeNow=now;
            Author=app.AuthorEditField.Value; Title=app.TitleEditField.Value; Comment=app.commentEditField.Value;
            app.Petition.Author=Author; app.Petition.Title=Title; app.Petition.Comment=Comment;
            if(app.SaveDataCheckBox.Value)
                auxillaryData = additionalSavingInformation(app);
                [SUCCESS,filename,~]=app.writeDataToDisk(TimeNow,CVObject,auxillaryData);
                if(SUCCESS)
                    app.SaveLabel.Text=['Last: OK ,',datestr(TimeNow,'yyyy, mmm dd, HH:MM')];
                else
                    app.SaveLabel='Saving Failed';
                    filename='File saving failed.';
                end
            else
                filename='No file was saved';
            end

            if(app.EditTextFigCheckBox.Value) %Two Steps logbook. Make a copy of the figure and open edit boxes.
                NewFigure=figure;
                NewFigure.Position=app.PGR.Position;
                app.DuplicateFigure(app.PGR,NewFigure);
                if(isfield(CVObject.Acquisition,'filename'))
                    annotation(NewFigure,'textbox',[0 .92 1 .08],'string',CVObject.Acquisition.filename,'EdgeColor','none','HorizontalAlignment','center');
                else
                    if(app.SaveDataCheckBox.Value)
                        annotation(NewFigure,'textbox',[0 .92 1 .08],'string',filename,'EdgeColor','none','HorizontalAlignment','center');
                    else
                        annotation(NewFigure,'textbox',[0 .92 1 .08],'string','Image logbook only, data not saved','EdgeColor','none','HorizontalAlignment','center');
                    end
                end
                app.LogButton.Text=[num2str(NewFigure.Number),'-> Log'];
                app.LogButton.Enable='On';
                app.LogButton.UserData=NewFigure;
                app.EditTextFigCheckBox.UserData=filename;
            else
                app.SendImageToLogbook(app.PGR, Author, Title, Comment, filename);
            end          
        end
        
        function auxillaryData = additionalSavingInformation(app) %#ok<MANU> 
            auxillaryData=app.Petition;
        end
        
        function DuplicateFigure(app,OriginalFigure,NewFigure) %#ok<INUSL> 
            children_handler_original = get(OriginalFigure, 'children');
            allchildren = allchild(OriginalFigure);
            if isempty(children_handler_original)
                return
            else
                new_handler_copy = copyobj(children_handler_original,NewFigure);
                for KK=1:length(new_handler_copy)
                    if(strcmpi(new_handler_copy(KK).Type,'axes'))
                        delete(axtoolbar(new_handler_copy(KK)));
                    end
                end
                for AA=1:length(allchildren)
                    if(strcmpi(allchildren(AA).Type,'annotationpane'))
                        copyobj(allchildren(AA),NewFigure);
                    end
                end
            end
        end
        
        function DeepDuplicateFigure(app,OriginalFigure,NewFigure)
            children_handler_original = get(OriginalFigure, 'children');
            if isempty(children_handler_original)
                return
            else
                new_handler_copy = copyobj(children_handler_original,NewFigure);
                for KK=1:length(children_handler_original)
                    app.DuplicateFigure(children_handler_original(KK),new_handler_copy(KK));
                end
            end
        end
        
        function ManageFitVisibility(app,CopyParameters)
%             switch(app.TypeofFitDropDown.Value)
%                 case 'No Fit'
%                     app.P1.Visible='off'; app.P2.Visible='off';
%                     app.Petition.TypeOfFit=1;
%                 case 'Polynomial'
%                     app.P1.Visible='on'; app.P2.Visible='off';
%                     app.Petition.TypeOfFit=2;
%                 case 'Gaussian'
%                     app.P1.Visible='off'; app.P2.Visible='on';
%                     app.Petition.TypeOfFit=3;
%             end
%             if(CopyParameters)
%                 if(isnan(app.Petition.A)), app.AEditField.Value=''; else, app.AEditField.Value=num2str(app.Petition.A); end
%                 if(isnan(app.Petition.B)), app.BEditField.Value=''; else, app.BEditField.Value=num2str(app.Petition.B); end
%                 if(isnan(app.Petition.C)), app.CEditField.Value=''; else, app.CEditField.Value=num2str(app.Petition.C); end
%                 if(isnan(app.Petition.x0)), app.x0EditField.Value=''; else, app.x0EditField.Value=num2str(app.Petition.x0); end
%                 if(isnan(app.Petition.y0)), app.y0EditField.Value=''; else, app.y0EditField.Value=num2str(app.Petition.y0); end
%                 if(isnan(app.Petition.PolyOrder)), app.OrderPolyFitEdit.Value=''; else, app.OrderPolyFitEdit.Value=num2str(app.Petition.PolyOrder); end
%             end
        end
        
        function ManageTypeOfPlotVisibility(app, CopyParameters)
%             switch(app.TypeofPlotDropDown.Value)
%                 case 'Dots Plot'
%                     app.BinsXEditField.Visible='off'; app.BinsXEditFieldLabel.Visible='off';
%                     app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
%                     app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
%                     app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
%                     app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
%                 case 'Density Plot'
%                     app.BinsXEditField.Visible='on'; app.BinsXEditFieldLabel.Visible='on';
%                     app.BinsYEditField.Visible='on'; app.BinsYEditFieldLabel.Visible='on';
%                     app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
%                     app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
%                     app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
%                 case 'Partition on X'
%                     app.BinsXEditField.Visible='off'; app.BinsXEditFieldLabel.Visible='off';
%                     app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
%                     app.PartitionCenterEditField.Visible='on'; app.PartitionCenterEditFieldLabel.Visible='on';
%                     app.PartitionWidthEditField.Visible='on'; app.PartitionWidthEditFieldLabel.Visible='on';
%                     app.ShowonlyEditField.Visible='on'; app.ShowonlyEditFieldLabel.Visible='on';
%                 case '3D XYZ Plot'
%                     app.BinsXEditField.Visible='off'; app.BinsXEditFieldLabel.Visible='off';
%                     app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
%                     app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
%                     app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
%                     app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
%                 case 'Histogram'
%                     app.BinsXEditField.Visible='on'; app.BinsXEditFieldLabel.Visible='on';
%                     app.BinsYEditField.Visible='off'; app.BinsYEditFieldLabel.Visible='off';
%                     app.PartitionCenterEditField.Visible='off'; app.PartitionCenterEditFieldLabel.Visible='off';
%                     app.PartitionWidthEditField.Visible='off'; app.PartitionWidthEditFieldLabel.Visible='off';
%                     app.ShowonlyEditField.Visible='off'; app.ShowonlyEditFieldLabel.Visible='off';
%             end
%             if(CopyParameters)
%                 if(isnan(app.Petition.PartitionPos(1))), app.PartitionCenterEditField.Value=''; 
%                 else
%                     string='[';
%                     for II=1:length(app.Petition.PartitionPos)
%                         string=[string,num2str(app.Petition.PartitionPos(II))]; %#ok<AGROW> 
%                         if(II~=length(app.Petition.PartitionPos))
%                             string=[string,',']; %#ok<AGROW> 
%                         else
%                             string=[string,']']; %#ok<AGROW> 
%                         end
%                     end
%                     app.PartitionCenterEditField.Value=string; 
%                 end
%                 if(isnan(app.Petition.PartitionWidth(1))), app.PartitionWidthEditField.Value=''; 
%                 else
%                     string='[';
%                     for II=1:length(app.Petition.PartitionWidth)
%                         string=[string,num2str(app.Petition.PartitionWidth(II))]; %#ok<AGROW> 
%                         if(II~=length(app.Petition.PartitionWidth))
%                             string=[string,',']; %#ok<AGROW> 
%                         else
%                             string=[string,']']; %#ok<AGROW> 
%                         end
%                     end
%                     app.PartitionWidthEditField.Value=string; 
%                 end
%                 if(isnan(app.Petition.ShowOnly(1))), app.ShowonlyEditField.Value=''; 
%                 else
%                     string='[';
%                     for II=1:length(app.Petition.ShowOnly)
%                         string=[string,num2str(app.Petition.ShowOnly(II))]; %#ok<AGROW> 
%                         if(II~=length(app.Petition.ShowOnly))
%                             string=[string,',']; %#ok<AGROW> 
%                         else
%                             string=[string,']']; %#ok<AGROW> 
%                         end
%                     end
%                     app.ShowonlyEditField.Value=string; 
%                 end
%                 if(isnan(app.Petition.binsx)), app.BinsXEditField.Value=''; else, app.BinsXEditField.Value=num2str(app.Petition.binsx); end
%                 if(isnan(app.Petition.binsy)), app.BinsYEditField.Value=''; else, app.BinsYEditField.Value=num2str(app.Petition.binsy); end
%             end
        end
        
        function ManageOtherInputs(app)
%             if(isnan(app.Petition.binsx)), app.BinsXEditField.Value=''; else, app.BinsXEditField.Value=num2str(app.Petition.binsx); end
%             if(isnan(app.Petition.binsy)), app.BinsYEditField.Value=''; else, app.BinsYEditField.Value=num2str(app.Petition.binsy); end
%             app.Petition.AutoBinPartition=0;
%             if(isnan(app.Petition.AutoBinNumber)), app.AutoPartition.Value='7';app.Petition.AutoBinNumber=1; else, app.AutoPartition.Value=num2str(app.Petition.AutoBinNumber); end
            app.ShowstatsCheckBox.Value=app.Petition.ShowStats;
            app.AboveEditField_1.Value=num2str(app.Petition.Above1);
            app.AboveEditField_2.Value=num2str(app.Petition.Above2);
            app.TopEditField_1.Value=num2str(app.Petition.Top1);
            app.TopEditField_2.Value=num2str(app.Petition.Top2);
            app.colorscalesinglesCheckBox_1.Value=app.Petition.ColorScale1;
            app.colorscalesinglesCheckBox_2.Value=app.Petition.ColorScale2;
            app.LiveorbitaveragesEditField.Value=num2str(app.Petition.LastN);
            app.FilenameEditField.Value=app.Petition.Filename;
            app.UseFilter1CheckBox.Value=logical(app.Petition.UseFilter(1));
            app.UseFilter1CheckBox.Value=logical(app.Petition.UseFilter(2));
            app.RelativeCheckBox.Value=logical(app.Petition.Relative);
        end
        
        function SendImageToLogbook(app, FigureHandle, Author, Title, Comment, Filename)
            Comment=[Comment,char(13),'Filename: ', Filename];
            try
                util_printLog(FigureHandle,'title',Title,'author',Author,'text',Comment);
                disp('Image sent successfully to logbook');
            catch
                disp('Failed to send image to logbook');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            TEMP=load([app.ConfPath,'CVCRCI_OrbitDisplay_ARC.mat']);
            app.ARC=TEMP.ARC;
            app.FIELDNAMES=fieldnames(app);
            app.PGR=figure('Name',[app.(app.FIELDNAMES{(cellfun(@(x) any(x),strfind(fieldnames(app),'UIFigure')))}).Name,' *-* Display']);
            app.PGR.Position=[app.PGR.Position(1)-440,app.PGR.Position(2)-180,1000,600];
            app.ASSE=axes(app.PGR,'Position',[0.11,0.09,0.8450,0.8250/2]);
            app.ASSE2=axes(app.PGR,'Position',[0.11,0.09+0.8250/2+0.05,0.8450,0.8250/2]);
            app.Permannotation=annotation(app.PGR,'textbox',[0 0 1 0.7],'EdgeColor','none','HorizontalAlignment','left','Interpreter','latex');
            annotation(app.PGR,'textbox',[0 0 1 0.35],'EdgeColor','none','HorizontalAlignment','left','String','Y [mm]');
            annotation(app.PGR,'textbox',[0 0 1 0.80],'EdgeColor','none','HorizontalAlignment','left','String','X [mm]');
            app.ASSE.Interactions=[];app.ASSE.Toolbar=[];app.ASSE2.Interactions=[];app.ASSE2.Toolbar=[];
            app.PGR.MenuBar='none';app.PGR.ToolBar='none'; app.ASSE.NextPlot='replacechildren'; app.ASSE2.NextPlot='replacechildren';
            app.ASSEDefaultSize=app.PGR.Position(3:4);
            if(app.BoxGridCheckBox.Value), box(app.ASSE,'on'); grid(app.ASSE,'on'); else, box(app.ASSE,'off'); grid(app.ASSE,'off'); end 
            if(app.BoxGridCheckBox.Value), box(app.ASSE2,'on'); grid(app.ASSE2,'on'); else, box(app.ASSE2,'off'); grid(app.ASSE2,'off'); end 
            app.moveAxisNearToMe();
            app.Petition.xLim=[-inf,inf];
            app.Petition.yLim=[-inf,inf];
            app.Petition.xLim2=[-inf,inf];
            app.Petition.yLim2=[-inf,inf];
            app.Petition.AmpRange=[-inf,inf];
            app.getAppColors();
            app.LogbookButton.BackgroundColor=app.color.logbook;
            app.Petition.Relative=0;
            app.Petition.STOP=0;
            app.Petition.UseAbsoluteTime = 1;
            app.Petition.Save = 1;
            app.Petition.TwoStepLog= 0;
            app.Petition.FiltriNumber=0;
            app.Petition.Quantity=[];
            app.Petition.S_SEL1=[0,0,0];
            app.Petition.S_SEL2=[0,0,0];
            app.Petition.V_SEL=[0,0,0];
            app.Petition.I_SEL=[0,0,0];
            app.Petition.Above1=-inf;
            app.Petition.Above2=-inf;
            app.Petition.Top1=25;
            app.Petition.Top2=25;
            app.Petition.ColorScale1=false;
            app.Petition.ColorScale2=false;
            app.Petition.LastN=120;
            app.Petition.LastSeconds=[];
            app.Petition.Filename='BPM_file.mat';
            app.Petition.Area=1;
            app.Petition.Pid_Delay=0;
            app.Petition.A=NaN;
            app.Petition.B=NaN;
            app.Petition.C=2;
            app.Petition.x0=NaN;
            app.Petition.y0=NaN;
            app.Petition.PolyOrder=2;
            app.Petition.binsx=25;
            app.Petition.binsy=25;
            app.Petition.AutoBinPartition=1;
            app.Petition.AutoBinNumber=7;
            app.Petition.PartitionPos=NaN;
            app.Petition.PartitionWidth=NaN;
            app.Petition.ShowOnly=NaN;
            app.Petition.TypeOfPlot=1;
            app.Petition.TypeOfFit=1;
            app.Petition.Function='';
            app.Petition.ShowStats=0;
            app.Petition.UseFilter=[false,false];
        end

        % Value changed function: XMinEditField
        function XMinEditFieldValueChanged(app, event)
            app.UpdateLimits('x');
        end

        % Value changed function: XMaxEditField
        function XMaxEditFieldValueChanged(app, event)
            app.UpdateLimits('x');
        end

        % Value changed function: YMinEditField
        function YMinEditFieldValueChanged(app, event)
            app.UpdateLimits('y');
        end

        % Value changed function: YMaxEditField
        function YMaxEditFieldValueChanged(app, event)
            app.UpdateLimits('y');
        end

        % Button pushed function: AxesFigureButton
        function AxesFigureButtonPushed(app, event)
            app.CheckExistenceMakeAxes();
        end

        % Button pushed function: MoveAxesNearButton
        function MoveAxesNearButtonPushed(app, event)
            app.moveAxisNearToMe();
        end

        % Button pushed function: DefaultAxesSizeButton
        function DefaultAxesSizeButtonPushed(app, event)
            app.PGR.Position(3:4)=app.ASSEDefaultSize;
        end

        % Button pushed function: LogbookButton
        function LogbookButtonPushed(app, event)
            app.LogbookButton.BackgroundColor=app.color.On;
            app.Petition.Logbook=1;
        end

        % Button pushed function: STOP
        function STOPButtonPushed(app, event)
            if(app.Petition.STOP)
                app.Petition.STOP=false; app.STOP.Text='Now Running -> Stop Updating'; app.STOP.BackgroundColor=app.color.On; drawnow
            else
                app.Petition.STOP=true; app.STOP.Text='Now Stopped -> Resume Updating'; app.STOP.BackgroundColor=app.color.Wait; drawnow
            end
        end

        % Callback function
        function UsetimeCheckBoxValueChanged(app, event)
            app.Petition.UseAbsoluteTime = app.UsetimeCheckBox.Value;
        end

        % Value changed function: SaveDataCheckBox
        function SaveDataCheckBoxValueChanged(app, event)
            app.Petition.Save = app.SaveDataCheckBox.Value;
        end

        % Value changed function: EditTextFigCheckBox
        function EditTextFigCheckBoxValueChanged(app, event)
            app.Petition.TwoStepLog= app.EditTextFigCheckBox.Value;
        end

        % Value changed function: S_Scalar_1, S_Scalar_2
        function S_ScalarValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Callback function
        function S_VectorValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Callback function
        function S_ImageValueChanged(app, event)
            app.ManageListChange(event.Source);
        end

        % Close request function: OrbitDisplayUIFigure
        function OrbitDisplayUIFigureCloseRequest(app, event)
            try delete(app.PGR); end %#ok<TRYNC> 
            delete(app);
        end

        % Callback function
        function TabellaYCellEdit(app, event)
            %indices = event.Indices; newData = event.NewData;
            ID=find(strcmp(event.NewData,app.DataStructure.Scalars),1, 'first');
            if(~isempty(ID))
                if(~app.DataStructure.TimeStamped(app.DataStructure.Scalars_Positions(ID,1))) %not timestamped, defaults as non scatter plot like
                    try app.TabellaY.Data{event.Indices(1),4}=regexprep(regexprep(app.TabellaY.Data{event.Indices(1),4},'\.','-'),'b','r'); end %#ok<TRYNC> 
                end
            end
            app.UpdateTableSelection();
        end

        % Callback function
        function VarAddLineButtonPushed(app, event)
            if(app.DataStructure.Synchronous)
                NewLine={true,true,'OFF','.b','',inf};
            else
                NewLine={false,false,'OFF','.b','',inf};
            end
            app.TabellaY.Data(end+1,:)=NewLine;
            if(~app.Petition.ShowStats)
                set(app.Permannotation,'String','');
                SA=0;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            else
                SA=size(app.TabellaY.Data,1)+1;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            end
        end

        % Callback function
        function VarRemoveLineButtonPushed(app, event)
            if(~isempty(app.VarRemoveLine.UserData))
                toBeRemoved=unique(app.VarRemoveLine.UserData(:,1),'sorted');
                for II=size(app.TabellaY.Data,1):(-1):1
                    if(any(toBeRemoved==II))
                        app.TabellaY.Data(II,:)=[];
                    end
                end
            end
            app.UpdateTableSelection();
            if(~app.Petition.ShowStats)
                set(app.Permannotation,'String','');
                SA=0;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            else
                SA=size(app.TabellaY.Data,1)+1;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            end
        end

        % Callback function
        function TabellaYCellSelection(app, event)
            indices = event.Indices;
            app.VarRemoveLine.UserData=indices;
        end

        % Button pushed function: FilterAddLine
        function FilterAddLineButtonPushed(app, event)
            NewLine{1,1}=app.TABULA.ColumnFormat{1}{1};
            NewLine{1,2}=[];NewLine{1,3}=[];NewLine{1,4}=true;NewLine{1,5}=true;
            app.TABULA.Data(end+1,:)=NewLine;
        end

        % Button pushed function: FilterRemoveLine
        function FilterRemoveLineButtonPushed(app, event)
            app.FilterRemoveLine.UserData
            if(~isempty(app.FilterRemoveLine.UserData))
                toBeRemoved=unique(app.FilterRemoveLine.UserData(:,1),'sorted');
                for II=size(app.TABULA.Data,1):(-1):1
                    if(any(toBeRemoved==II))
                        app.TABULA.Data(II,:)=[];
                    end
                end
            end
            app.UpdateFilterSelection();
        end

        % Cell selection callback: TABULA
        function TABULACellSelection(app, event)
            indices = event.Indices;
            app.FilterRemoveLine.UserData=indices;
        end

        % Cell edit callback: TABULA
        function TABULACellEdit(app, event)
            app.UpdateFilterSelection();
        end

        % Callback function
        function secondssamplesEditFieldValueChanged(app, event)
            value=app.secondssamplesEditField.Value;
            VAL=str2double(value);
            if(VAL<=0 || isnan(VAL))
                app.secondssamplesEditField.Value='';
                app.Petition.LastSeconds=[];
            else
                app.Petition.LastSeconds=VAL;
            end
        end

        % Callback function
        function TypeofFitDropDownValueChanged(app, event)
            app.ManageFitVisibility(0);
        end

        % Callback function
        function AutoButtonPushed(app, event)
            app.Petition.AutoBinPartition=1;
            Value=str2num(app.AutoPartition.Value);
            Value=round(Value(1));
            if(Value<1)
                Value=1;
                app.AutoPartition.Value='1';
            end
            app.Petition.AutoBinNumber=Value;
        end

        % Value changed function: ExpressionEditField
        function ExpressionEditFieldValueChanged(app, event)
            value = app.ExpressionEditField.Value;
            if(isempty(value)), app.Petition.Function=''; else 
                app.Petition.Function=eval(['@(x) ',vectorize(value)]);
            end
        end

        % Value changed function: ShowstatsCheckBox
        function ShowstatsCheckBoxValueChanged(app, event)
            app.Petition.ShowStats=app.ShowstatsCheckBox.Value;
            if(~app.Petition.ShowStats)
                set(app.Permannotation,'String','');
                SA=0;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            else
                SA=size(app.TabellaY.Data,1)+1;
                set(app.ASSE,'Position',[0.13,0.11 + SA*0.025,0.7750,0.8150-SA*0.025])
                set(app.Permannotation,'Position',[0,0,1,0.08+0.025*SA]);
            end
        end

        % Callback function
        function TypeofPlotDropDownValueChanged(app, event)
            switch(app.TypeofPlotDropDown.Value)
                case 'Dots Plot'
                    app.Petition.TypeOfPlot=1;
                case 'Density Plot'
                    app.Petition.TypeOfPlot=2;
                case 'Partition on X'
                    app.Petition.TypeOfPlot=3;
                case 'Histogram'
                    app.Petition.TypeOfPlot=4;
            end
            app.ManageTypeOfPlotVisibility(0);
        end

        % Callback function
        function PartitionCenterEditFieldValueChanged(app, event)
            strvalue = app.PartitionCenterEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.PartitionPos=NaN;
            else
                app.Petition.PartitionPos=numericlist;
            end
        end

        % Callback function
        function PartitionWidthEditFieldValueChanged(app, event)
            strvalue = app.PartitionWidthEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.PartitionWidth=NaN;
            else
                app.Petition.PartitionWidth=numericlist;
            end
        end

        % Callback function
        function ShowonlyEditFieldValueChanged(app, event)
            strvalue = app.ShowonlyEditField.Value;
            numericlist=str2num(strvalue); %#ok<ST2NM> 
            if(isempty(numericlist))
                app.Petition.ShowOnly=NaN;
            else
                numericlist=numericlist(~isnan(numericlist) && ~isinf(numericlist));
                numericlist=round(numericlist);
                numericlist=numericlist(numericlist>1);        
                app.Petition.ShowOnly=numericlist;
            end
        end

        % Callback function
        function OrderPolyFitEditValueChanged(app, event)
            app.Petition.PolyOrder=str2double(app.OrderPolyFitEdit.Value);
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            app.LogButton.Text=['','-> Log'];
            app.LogButton.Enable='Off';
            Author=app.AuthorEditField.Value; Title=app.TitleEditField.Value; Comment=app.commentEditField.Value;
            try
                if(isempty(app.EditTextFigCheckBox.UserData))
                    app.EditTextFigCheckBox.UserData='';
                end
                app.SendImageToLogbook(app.LogButton.UserData, Author, Title, Comment, app.EditTextFigCheckBox.UserData);
                disp('Image sent to logbook');
            catch
                disp('Failed to send image to logbook');
            end
            try %#ok<TRYNC> 
                close(app.LogButton.UserData);
            end
            app.LogButton.UserData=[];
        end

        % Value changed function: XMinEditField_2
        function XMinEditField_2ValueChanged(app, event)
            app.UpdateLimits2('x');
        end

        % Value changed function: XMaxEditField_2
        function XMaxEditField_2ValueChanged(app, event)
            app.UpdateLimits2('x');
        end

        % Value changed function: YMinEditField_2
        function YMinEditField_2ValueChanged(app, event)
            app.UpdateLimits2('y');
        end

        % Value changed function: YMaxEditField_2
        function YMaxEditField_2ValueChanged(app, event)
            app.UpdateLimits2('y');
        end

        % Value changed function: UseFilter1CheckBox
        function UseFilter1CheckBoxValueChanged(app, event)
            app.Petition.UseFilter(1) = app.UseFilter1CheckBox.Value;
        end

        % Value changed function: UseFilter2CheckBox
        function UseFilter2CheckBoxValueChanged(app, event)
            app.Petition.UseFilter(2) = app.UseFilter2CheckBox.Value;
        end

        % Button pushed function: ClearfixedorbitsButton
        function ClearfixedorbitsButtonPushed(app, event)
            app.AdditionalOrbits={};
        end

        % Value changed function: LiveorbitaveragesEditField
        function LiveorbitaveragesEditFieldValueChanged(app, event)
            app.Petition.LastN=str2double(app.LiveorbitaveragesEditField.Value);
        end

        % Value changed function: TopEditField_1
        function TopEditField_1ValueChanged(app, event)
            app.Petition.Top1=str2double(app.TopEditField_1.Value);
        end

        % Value changed function: TopEditField_2
        function TopEditField_2ValueChanged(app, event)
            app.Petition.Top2=str2double(app.TopEditField_2.Value);
        end

        % Value changed function: AboveEditField_1
        function AboveEditField_1ValueChanged(app, event)
            app.Petition.Above1=str2double(app.AboveEditField_1.Value);
        end

        % Value changed function: AboveEditField_2
        function AboveEditField_2ValueChanged(app, event)
            app.Petition.Above2=str2double(app.AboveEditField_2.Value);
        end

        % Value changed function: colorscalesinglesCheckBox_1
        function colorscalesinglesCheckBox_1ValueChanged(app, event)
            app.Petition.ColorScale1 = app.colorscalesinglesCheckBox_1.Value;        
        end

        % Value changed function: colorscalesinglesCheckBox_2
        function colorscalesinglesCheckBox_2ValueChanged(app, event)
            app.Petition.ColorScale2 = app.colorscalesinglesCheckBox_2.Value;   
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            app.UpdateAmpLimits();
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.UpdateAmpLimits();
        end

        % Button pushed function: LoadfixedOrbitButton
        function LoadfixedOrbitButtonPushed(app, event)
            filename=app.FilenameEditField.Value;
            load([app.OP_folder,'/',filename],'MyBPMs');
            if(isempty(app.AdditionalOrbits))
                app.AdditionalOrbits{1}=MyBPMs;
            else
                app.AdditionalOrbits{end+1}=MyBPMs;
            end
        end

        % Button pushed function: LoadorbitPatchworkOrbitsButton
        function LoadorbitPatchworkOrbitsButtonPushed(app, event)
            load([app.OP_folder,'/','HEAD'],'MyBPMs');
            if(isempty(app.AdditionalOrbits))
                app.AdditionalOrbits{1}=MyBPMs;
            else
                app.AdditionalOrbits{end+1}=MyBPMs;
            end
            load([app.OP_folder,'/','TAIL'],'MyBPMs');
            if(isempty(app.AdditionalOrbits))
                app.AdditionalOrbits{1}=MyBPMs;
            else
                app.AdditionalOrbits{end+1}=MyBPMs;
            end
        end

        % Value changed function: RelativeCheckBox
        function RelativeCheckBoxValueChanged(app, event)
            app.Petition.Relative=app.RelativeCheckBox.Value; 
        end

        % Value changed function: BoxGridCheckBox
        function BoxGridCheckBoxValueChanged(app, event)
            if(app.BoxGridCheckBox.Value)
                try %#ok<TRYNC> 
                    box(app.ASSE,'on'); grid(app.ASSE,'on'); box(app.ASSE2,'on'); grid(app.ASSE2,'on');
                end
            else
                try %#ok<TRYNC> 
                    box(app.ASSE,'off'); grid(app.ASSE,'off'); box(app.ASSE2,'off'); grid(app.ASSE2,'off');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OrbitDisplayUIFigure = uifigure('Visible', 'off');
            app.OrbitDisplayUIFigure.Name = 'Orbit Display';
            app.OrbitDisplayUIFigure.CloseRequestFcn = createCallbackFcn(app, @OrbitDisplayUIFigureCloseRequest, true);
            app.XMinEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.XMinEditFieldLabel.Text = 'XMin';
            app.XMinEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.XMinEditField.ValueChangedFcn = createCallbackFcn(app, @XMinEditFieldValueChanged, true);
            app.XMaxEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.XMaxEditFieldLabel.Text = 'XMax';
            app.XMaxEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.XMaxEditField.ValueChangedFcn = createCallbackFcn(app, @XMaxEditFieldValueChanged, true);
            app.YMinEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.YMinEditFieldLabel.Text = 'YMin';
            app.YMinEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.YMinEditField.ValueChangedFcn = createCallbackFcn(app, @YMinEditFieldValueChanged, true);
            app.YMaxEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.YMaxEditFieldLabel.Text = 'YMax';
            app.YMaxEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.YMaxEditField.ValueChangedFcn = createCallbackFcn(app, @YMaxEditFieldValueChanged, true);
            app.AxesFigureButton = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.AxesFigureButton.ButtonPushedFcn = createCallbackFcn(app, @AxesFigureButtonPushed, true);
            app.AxesFigureButton.Text = 'Axes Figure';
            app.MoveAxesNearButton = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.MoveAxesNearButton.ButtonPushedFcn = createCallbackFcn(app, @MoveAxesNearButtonPushed, true);
            app.MoveAxesNearButton.Text = 'Move Axes Near';
            app.PositionDropDown = uidropdown(app.OrbitDisplayUIFigure);
            app.PositionDropDown.Items = {'Right', 'Above', 'Left', 'Below'};
            app.PositionDropDown.Value = 'Right';
            app.DefaultAxesSizeButton = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.DefaultAxesSizeButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultAxesSizeButtonPushed, true);
            app.DefaultAxesSizeButton.Text = 'Default Axes Size';
            app.LogbookButton = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.LogbookButton.ButtonPushedFcn = createCallbackFcn(app, @LogbookButtonPushed, true);
            app.LogbookButton.Text = 'Logbook';
            app.SaveDataCheckBox = uicheckbox(app.OrbitDisplayUIFigure);
            app.SaveDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDataCheckBoxValueChanged, true);
            app.SaveDataCheckBox.Text = 'Save Data';
            app.SaveDataCheckBox.Value = true;
            app.EditTextFigCheckBox = uicheckbox(app.OrbitDisplayUIFigure);
            app.EditTextFigCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditTextFigCheckBoxValueChanged, true);
            app.EditTextFigCheckBox.Text = 'Edit Text/Fig';
            app.SaveLabel = uilabel(app.OrbitDisplayUIFigure);
            app.SaveLabel.Text = 'Last: XX/XX/XXXX';
            app.TABULA = uitable(app.OrbitDisplayUIFigure);
            app.TABULA.ColumnName = {'Variable Name'; 'Minimum'; 'Maximum'; 'Filter1'; 'Filter 2'};
            app.TABULA.RowName = {};
            app.TABULA.CellEditCallback = createCallbackFcn(app, @TABULACellEdit, true);
            app.TABULA.CellSelectionCallback = createCallbackFcn(app, @TABULACellSelection, true);
            app.FilteringoptionsLabel = uilabel(app.OrbitDisplayUIFigure);
            app.FilteringoptionsLabel.Text = 'Filtering options:';
            app.AxiscontrolLabel = uilabel(app.OrbitDisplayUIFigure);
            app.AxiscontrolLabel.Text = 'Axis control:';
            app.STOP = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.STOP.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOP.Text = 'Now Running->Resume Updating';
            app.FilterAddLine = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.FilterAddLine.ButtonPushedFcn = createCallbackFcn(app, @FilterAddLineButtonPushed, true);
            app.FilterAddLine.Text = 'Add Line';
            app.FilterRemoveLine = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.FilterRemoveLine.ButtonPushedFcn = createCallbackFcn(app, @FilterRemoveLineButtonPushed, true);
            app.FilterRemoveLine.Text = 'Remove Line';
            app.ExpressionEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.ExpressionEditFieldLabel.Text = 'Expression';
            app.ExpressionEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.ExpressionEditField.ValueChangedFcn = createCallbackFcn(app, @ExpressionEditFieldValueChanged, true);
            app.ShowstatsCheckBox = uicheckbox(app.OrbitDisplayUIFigure);
            app.ShowstatsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowstatsCheckBoxValueChanged, true);
            app.ShowstatsCheckBox.Text = 'Show stats';
            app.LogButton = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = '-> Log';
            app.AuthorEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.AuthorEditFieldLabel.Text = 'Author:';
            app.AuthorEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.TitleEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.TitleEditField.Value = 'CVCRCI - Undulator Orbit';
            app.commentEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.commentEditFieldLabel.Text = 'comment';
            app.commentEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.IntensityVariable1Label = uilabel(app.OrbitDisplayUIFigure);
            app.IntensityVariable1Label.Text = 'Intensity Variable 1';
            app.S_Scalar_1 = uidropdown(app.OrbitDisplayUIFigure);
            app.S_Scalar_1.ValueChangedFcn = createCallbackFcn(app, @S_ScalarValueChanged, true);
            app.AreaDropDownLabel = uilabel(app.OrbitDisplayUIFigure);
            app.AreaDropDownLabel.Text = 'Area';
            app.AreaDropDown = uidropdown(app.OrbitDisplayUIFigure);
            app.LiveorbitaveragesEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.LiveorbitaveragesEditFieldLabel.Text = 'Live orbit averages';
            app.LiveorbitaveragesEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.LiveorbitaveragesEditField.ValueChangedFcn = createCallbackFcn(app, @LiveorbitaveragesEditFieldValueChanged, true);
            app.LiveorbitaveragesEditField.Value = '120';
            app.LoadfixedOrbitButton = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.LoadfixedOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfixedOrbitButtonPushed, true);
            app.LoadfixedOrbitButton.Text = 'Load fixed Orbit';
            app.FilenameEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.ClearfixedorbitsButton = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.ClearfixedorbitsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearfixedorbitsButtonPushed, true);
            app.ClearfixedorbitsButton.Text = 'Clear fixed orbits';
            app.LoadorbitPatchworkOrbitsButton = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.LoadorbitPatchworkOrbitsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadorbitPatchworkOrbitsButtonPushed, true);
            app.LoadorbitPatchworkOrbitsButton.Text = 'Load orbitPatchwork Orbits';
            app.EstimateEnergyFitittakestimeButton = uibutton(app.OrbitDisplayUIFigure, 'push');
            app.EstimateEnergyFitittakestimeButton.Enable = 'off';
            app.EstimateEnergyFitittakestimeButton.Text = 'Estimate Energy Fit, it takes time!';
            app.RequiresBSALabel = uilabel(app.OrbitDisplayUIFigure);
            app.RequiresBSALabel.Text = 'Requires BSA';
            app.YMaxEditField_2Label = uilabel(app.OrbitDisplayUIFigure);
            app.YMaxEditField_2Label.Text = 'YMax';
            app.YMaxEditField_2 = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.YMaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @YMaxEditField_2ValueChanged, true);
            app.YMinEditField_2Label = uilabel(app.OrbitDisplayUIFigure);
            app.YMinEditField_2Label.Text = 'YMin';
            app.YMinEditField_2 = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.YMinEditField_2.ValueChangedFcn = createCallbackFcn(app, @YMinEditField_2ValueChanged, true);
            app.XMaxEditField_2Label = uilabel(app.OrbitDisplayUIFigure);
            app.XMaxEditField_2Label.Text = 'XMax';
            app.XMaxEditField_2 = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.XMaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @XMaxEditField_2ValueChanged, true);
            app.XMinEditField_2Label = uilabel(app.OrbitDisplayUIFigure);
            app.XMinEditField_2Label.Text = 'XMin';
            app.XMinEditField_2 = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.XMinEditField_2.ValueChangedFcn = createCallbackFcn(app, @XMinEditField_2ValueChanged, true);
            app.XLabel = uilabel(app.OrbitDisplayUIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.OrbitDisplayUIFigure);
            app.YLabel.Text = 'Y';
            app.TopLabel = uilabel(app.OrbitDisplayUIFigure);
            app.TopLabel.Text = 'Top %';
            app.TopEditField_1 = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.TopEditField_1.ValueChangedFcn = createCallbackFcn(app, @TopEditField_1ValueChanged, true);
            app.TopEditField_1.Value = '25';
            app.IntensityVariable2Label = uilabel(app.OrbitDisplayUIFigure);
            app.IntensityVariable2Label.Text = 'Intensity Variable 2';
            app.S_Scalar_2 = uidropdown(app.OrbitDisplayUIFigure);
            app.S_Scalar_2.ValueChangedFcn = createCallbackFcn(app, @S_ScalarValueChanged, true);
            app.TopEditField_2Label = uilabel(app.OrbitDisplayUIFigure);
            app.TopEditField_2Label.Text = 'Top %';
            app.TopEditField_2 = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.TopEditField_2.ValueChangedFcn = createCallbackFcn(app, @TopEditField_2ValueChanged, true);
            app.TopEditField_2.Value = '25';
            app.AboveEditField_2Label = uilabel(app.OrbitDisplayUIFigure);
            app.AboveEditField_2Label.Text = 'Above';
            app.AboveEditField_2 = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.AboveEditField_2.ValueChangedFcn = createCallbackFcn(app, @AboveEditField_2ValueChanged, true);
            app.AboveEditField_2.Value = '-inf';
            app.AboveEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.AboveEditFieldLabel.Text = 'Above';
            app.AboveEditField_1 = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.AboveEditField_1.ValueChangedFcn = createCallbackFcn(app, @AboveEditField_1ValueChanged, true);
            app.AboveEditField_1.Value = '-inf';
            app.colorscalesinglesCheckBox_1 = uicheckbox(app.OrbitDisplayUIFigure);
            app.colorscalesinglesCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @colorscalesinglesCheckBox_1ValueChanged, true);
            app.colorscalesinglesCheckBox_1.Text = 'color scale singles';
            app.colorscalesinglesCheckBox_2 = uicheckbox(app.OrbitDisplayUIFigure);
            app.colorscalesinglesCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @colorscalesinglesCheckBox_2ValueChanged, true);
            app.colorscalesinglesCheckBox_2.Text = 'color scale singles';
            app.UseFilter1CheckBox = uicheckbox(app.OrbitDisplayUIFigure);
            app.UseFilter1CheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFilter1CheckBoxValueChanged, true);
            app.UseFilter1CheckBox.Text = 'Use Filter 1';
            app.UseFilter2CheckBox = uicheckbox(app.OrbitDisplayUIFigure);
            app.UseFilter2CheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFilter2CheckBoxValueChanged, true);
            app.UseFilter2CheckBox.Text = 'Use Filter 2';
            app.ALabel = uilabel(app.OrbitDisplayUIFigure);
            app.ALabel.Text = 'A';
            app.MaxEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MinEditFieldLabel = uilabel(app.OrbitDisplayUIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.OrbitDisplayUIFigure, 'text');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.RelativeCheckBox = uicheckbox(app.OrbitDisplayUIFigure);
            app.RelativeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RelativeCheckBoxValueChanged, true);
            app.RelativeCheckBox.Text = 'Relative';
            app.BoxGridCheckBox = uicheckbox(app.OrbitDisplayUIFigure);
            app.BoxGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoxGridCheckBoxValueChanged, true);
            app.BoxGridCheckBox.Text = 'Box&Grid';
            % Show the figure after all components are created
            app.OrbitDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVCRCI_UndulatorLineOrbit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OrbitDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OrbitDisplayUIFigure)
        end
    end
end