classdef UL_PhotonEnergyControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhotonEnergyChangeUIFigure      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SXRLineEnergyControlTab         matlab.ui.container.Tab
        T1                              matlab.ui.control.Table
        GetReference1                   matlab.ui.control.Button
        FinalizeSetupPanel1             matlab.ui.container.Panel
        FinalizethisSetupconditionButton  matlab.ui.control.Button
        SteerFlatAfterSetting1          matlab.ui.control.CheckBox
        separatewithcommaLabel          matlab.ui.control.Label
        AllowedVernierRangeMeVLabel     matlab.ui.control.Label
        RemoteControlEnergyPVEditFieldLabel  matlab.ui.control.Label
        RemoteControlEnergyPVEditField1  matlab.ui.control.EditField
        MaxEditField_2Label             matlab.ui.control.Label
        MaxVernier1                     matlab.ui.control.EditField
        MinEditField_2Label             matlab.ui.control.Label
        MinVernier1                     matlab.ui.control.EditField
        AllowedEnergyDeltaRangeeVLabel  matlab.ui.control.Label
        UsecellCheckBox                 matlab.ui.control.CheckBox
        ControlModeDropDownLabel        matlab.ui.control.Label
        ControlMode1                    matlab.ui.control.DropDown
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.EditField
        SelfdestructionDropDownLabel    matlab.ui.control.Label
        Selfdestruction1                matlab.ui.control.DropDown
        UndulatorrangetobemovedLabel    matlab.ui.control.Label
        UndulatorrangetobemovedEditField  matlab.ui.control.EditField
        StatusPV0setting1setLabel       matlab.ui.control.Label
        SetDefault1                     matlab.ui.control.DropDown
        Endstation1                     matlab.ui.control.DropDown
        EndStationLabel                 matlab.ui.control.Label
        ReferenceEnergyPVLabel          matlab.ui.control.Label
        ReferenceControlEnergyPVEditField1  matlab.ui.control.EditField
        FormultiplecontrolPVsLabel      matlab.ui.control.Label
        LinearTable1                    matlab.ui.control.Table
        RowsButton1                     matlab.ui.control.Button
        Relative1                       matlab.ui.control.CheckBox
        OverrideBunchenergywithknownphotonenergy1  matlab.ui.control.CheckBox
        KnownPhotonEnergy1              matlab.ui.control.NumericEditField
        ReadfromreferenceButton1        matlab.ui.control.Button
        eVLabel_3                       matlab.ui.control.Label
        CalculateeVRangefromVernierextremes99Panel1  matlab.ui.container.Panel
        MinVernierEditFieldLabel        matlab.ui.control.Label
        MinVernierEditField             matlab.ui.control.NumericEditField
        MaxVernierEditFieldLabel        matlab.ui.control.Label
        MaxVernierEditField             matlab.ui.control.NumericEditField
        CalculateeVRangeButton          matlab.ui.control.Button
        VernierFudge1                   matlab.ui.control.NumericEditField
        VernierFudgeEditFieldLabel_3    matlab.ui.control.Label
        RestoreLastButton               matlab.ui.control.Button
        RestoreOldButton                matlab.ui.control.Button
        HXRLineEnergyControlTab         matlab.ui.container.Tab
        T2                              matlab.ui.control.Table
        GetReference2                   matlab.ui.control.Button
        FinalizeSetupPanel2             matlab.ui.container.Panel
        FinalizethisSetupconditionButton2  matlab.ui.control.Button
        SteerFlatAfterSetting2          matlab.ui.control.CheckBox
        AllowedVernierRangeMeVLabel_2   matlab.ui.control.Label
        RemoteControlEnergyPVEditField_2Label  matlab.ui.control.Label
        RemoteControlEnergyPVEditField2  matlab.ui.control.EditField
        MaxVernier2                     matlab.ui.control.EditField
        MinVernier2                     matlab.ui.control.EditField
        AllowedEnergyDeltaRangeeVLabel_2  matlab.ui.control.Label
        UsecellCheckBox2                matlab.ui.control.CheckBox
        ControlModeDropDownLabel_2      matlab.ui.control.Label
        ControlMode2                    matlab.ui.control.DropDown
        SelfdestructionDropDownLabel_2  matlab.ui.control.Label
        Selfdestruction2                matlab.ui.control.DropDown
        UndulatorrangetobemovedEditField_2Label  matlab.ui.control.Label
        UndulatorrangetobemovedEditField2  matlab.ui.control.EditField
        MinEditField_2Label_3           matlab.ui.control.Label
        MinEnergy2                      matlab.ui.control.EditField
        MaxLabel                        matlab.ui.control.Label
        MaxEnergy2                      matlab.ui.control.EditField
        StatusPV0setting1setLabel_2     matlab.ui.control.Label
        SetDefault2                     matlab.ui.control.DropDown
        Endstation2                     matlab.ui.control.DropDown
        EndStationLabel_2               matlab.ui.control.Label
        ReferenceEnergyPVLabel_2        matlab.ui.control.Label
        ReferenceControlEnergyPVEditField2  matlab.ui.control.EditField
        MinLabelGNU                     matlab.ui.control.Label
        MaxLabelGNU                     matlab.ui.control.Label
        separatewithcommaLabel_2        matlab.ui.control.Label
        FormultiplecontrolPVsLabel_2    matlab.ui.control.Label
        LinearTable2                    matlab.ui.control.Table
        RowsButton2                     matlab.ui.control.Button
        Relative2                       matlab.ui.control.CheckBox
        OverrideBunchenergywithknownphotonenergy2  matlab.ui.control.CheckBox
        KnownPhotonEnergy2              matlab.ui.control.NumericEditField
        ReadfromreferenceButton2        matlab.ui.control.Button
        eVLabel_2                       matlab.ui.control.Label
        CalculateeVRangefromVernierextremes99Panel2  matlab.ui.container.Panel
        MinVernierEditField_2Label      matlab.ui.control.Label
        MinVernierEditField_2           matlab.ui.control.NumericEditField
        MaxVernierEditField_2Label      matlab.ui.control.Label
        MaxVernierEditField_2           matlab.ui.control.NumericEditField
        CalculateeVRangeButton_2        matlab.ui.control.Button
        VernierFudgeEditFieldLabel_2    matlab.ui.control.Label
        VernierFudge2                   matlab.ui.control.NumericEditField
        RestoreLastButton2              matlab.ui.control.Button
        RestoreOldButton2               matlab.ui.control.Button
        HXRSSSelfSeedingTab             matlab.ui.container.Tab
        T3                              matlab.ui.control.Table
        GetReference3                   matlab.ui.control.Button
        FinalizeSetupPanel3             matlab.ui.container.Panel
        Color1                          matlab.ui.control.CheckBox
        Colors2                         matlab.ui.control.CheckBox
        ReflectionhklLabel1             matlab.ui.control.Label
        ReflectionhklLabel2             matlab.ui.control.Label
        hEditFieldLabel                 matlab.ui.control.Label
        h1                              matlab.ui.control.NumericEditField
        kEditFieldLabel                 matlab.ui.control.Label
        k1                              matlab.ui.control.NumericEditField
        lEditFieldLabel                 matlab.ui.control.Label
        l1                              matlab.ui.control.NumericEditField
        RemoteControlEnergyPVEditField_2Label_2  matlab.ui.control.Label
        RemoteControlEnergyPVEditField31  matlab.ui.control.EditField
        AllowedEnergyDeltaRangeLabel3   matlab.ui.control.Label
        MinEditField_2Label_4           matlab.ui.control.Label
        MinEnergy31                     matlab.ui.control.EditField
        MaxLabel_2                      matlab.ui.control.Label
        MaxEnergy31                     matlab.ui.control.EditField
        lEditFieldLabel_2               matlab.ui.control.Label
        l2                              matlab.ui.control.NumericEditField
        kEditFieldLabel_2               matlab.ui.control.Label
        k2                              matlab.ui.control.NumericEditField
        hEditFieldLabel_2               matlab.ui.control.Label
        h2                              matlab.ui.control.NumericEditField
        RemoteControlEnergyPVEditField_2Label_3  matlab.ui.control.Label
        RemoteControlEnergyPVEditField32  matlab.ui.control.EditField
        MaxLabel_3                      matlab.ui.control.Label
        MaxEnergy32                     matlab.ui.control.EditField
        MinEditField_2Label_5           matlab.ui.control.Label
        MinEnergy32                     matlab.ui.control.EditField
        AllowedVernierRangeMeVLabel_3   matlab.ui.control.Label
        MaxEditField_2Label_3           matlab.ui.control.Label
        MaxVernier3                     matlab.ui.control.EditField
        MinEditField_2Label_6           matlab.ui.control.Label
        MinVernier3                     matlab.ui.control.EditField
        ControlModeDropDownLabel_3      matlab.ui.control.Label
        ControlMode3                    matlab.ui.control.DropDown
        SelfdestructionDropDownLabel_3  matlab.ui.control.Label
        Selfdestruction3                matlab.ui.control.DropDown
        StatusPV0setting1setLabel_3     matlab.ui.control.Label
        SetDefault3                     matlab.ui.control.DropDown
        FinalizethisSetupconditionButton3  matlab.ui.control.Button
        SteerFlatAfterSetting3          matlab.ui.control.CheckBox
        UsecellCheckBox3                matlab.ui.control.CheckBox
        UndulatorrangetobemovedEditField_2Label_2  matlab.ui.control.Label
        UndulatorrangetobemovedEditField3  matlab.ui.control.EditField
        Endstation3                     matlab.ui.control.DropDown
        EndStationLabel_3               matlab.ui.control.Label
        ReferenceEnergyPVEditFieldLabel  matlab.ui.control.Label
        ReferenceControlEnergyPVEditField31  matlab.ui.control.EditField
        ReferenceEnergyPVLabel_3        matlab.ui.control.Label
        ReferenceControlEnergyPVEditField32  matlab.ui.control.EditField
        LinearTable3                    matlab.ui.control.Table
        Relative3                       matlab.ui.control.CheckBox
        RowsButton3                     matlab.ui.control.Button
        OverrideBunchenergywithknownphotonenergy3  matlab.ui.control.CheckBox
        KnownPhotonEnergy3              matlab.ui.control.NumericEditField
        ReadfromreferenceButton3        matlab.ui.control.Button
        eVLabel                         matlab.ui.control.Label
        ControlDiamondbyDropDownLabel   matlab.ui.control.Label
        DiamondControl                  matlab.ui.control.DropDown
        VernierFudgeEditFieldLabel      matlab.ui.control.Label
        VernierFudge3                   matlab.ui.control.NumericEditField
        RestoreLastButton3              matlab.ui.control.Button
        RestoreOldButton3               matlab.ui.control.Button
        SXRSSSelfSeedingTab             matlab.ui.container.Tab
        T4                              matlab.ui.control.Table
        GetReference4                   matlab.ui.control.Button
        StatusPV0setting1setLabel_4     matlab.ui.control.Label
        SetDefault4                     matlab.ui.control.DropDown
        SelfdestructionDropDownLabel_4  matlab.ui.control.Label
        Selfdestruction4                matlab.ui.control.DropDown
        RemoteControlandReadoutPanel    matlab.ui.container.Panel
        StartResetListeningtoremotecontrolPVsButton  matlab.ui.control.Button
        RemoteControlPVsLabel           matlab.ui.control.Label
        pollingtimesEditFieldLabel      matlab.ui.control.Label
        pollingtimesEditField           matlab.ui.control.NumericEditField
        STOPremotecontrolButton         matlab.ui.control.Button
        RemotecontrolstoppedLabel       matlab.ui.control.Label
        tabula                          matlab.ui.control.Table
        DateString                      matlab.ui.control.Label
        tabulotta                       matlab.ui.control.Table
        AutomaticScanPanel              matlab.ui.container.Panel
        AutoScanTable                   matlab.ui.control.Table
        SinglePass                      matlab.ui.control.CheckBox
        ZigZag                          matlab.ui.control.CheckBox
        StepsEditFieldLabel             matlab.ui.control.Label
        StepsEditField                  matlab.ui.control.NumericEditField
        waitaftersetsEditFieldLabel     matlab.ui.control.Label
        waitaftersetsEditField          matlab.ui.control.NumericEditField
        STARTButton                     matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        AutoString                      matlab.ui.control.Label
        AutoScanRelative                matlab.ui.control.CheckBox
        MessageList                     matlab.ui.control.ListBox
        ManualSetpointControl           matlab.ui.container.Panel
        GO1                             matlab.ui.control.Button
        VernierToReference              matlab.ui.control.Button
        RestoreReference1               matlab.ui.control.Button
        EnableAll                       matlab.ui.control.Button
        EnableAll_2                     matlab.ui.control.Button
        EnableAll_3                     matlab.ui.control.Button
        OffsetFromReferenceValue        matlab.ui.control.EditField
        OffsetFromReferenceValueABS     matlab.ui.control.EditField
        GO_ABS                          matlab.ui.control.Button
        AbsolutesetpointeVrequiresvalidreferenceLabel  matlab.ui.control.Label
        OffsetfromReferenceeVLabel      matlab.ui.control.Label
        fromendstationLabel             matlab.ui.control.Label
        ReferenceLabel                  matlab.ui.control.Label
        ReferenceValuesForEnergyLabel   matlab.ui.control.Label
        MainReadoutStringLabel1         matlab.ui.control.Label
        FastchangeLabel                 matlab.ui.control.Label
        Energychange_BBB                matlab.ui.control.Button
        Energychange_BB                 matlab.ui.control.Button
        Energychange_B                  matlab.ui.control.Button
        Energychange_F                  matlab.ui.control.Button
        Energychange_FF                 matlab.ui.control.Button
        Energychange_FFF                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        F_amount                        matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        FF_amount                       matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        FFF_amount                      matlab.ui.control.NumericEditField
        MainReadoutStringLabel2         matlab.ui.control.Label
        WritePVs                        matlab.ui.control.CheckBox
        EvalStateButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        UL % Description
        U
        SelfDestructionPV
        ListenList
        ListenLast
        FullySet
        sh
        fh
        static
        s
        color
        PhyConsts
        CD
        mh
        AMGP
        ULDir='/u1/lcls/matlab/ULT_GuiData';
        ULFile='UL.mat';
        StoredConfFolder='/u1/lcls/matlab/UL_Scans';
        HXRSSSaveFileFolder='/u1/lcls/matlab/data/CrystalGUI_StoredData';
        HXRSSStoredMachinesFile='HXRSS_StoredCrystals.mat';
        HXRSS
        SetupStructure
        TIMER
        SetValue
        OldValues
    end
    
    methods (Access = private)
        
        function Timer_Update(app,TimerObject,Type_and_when,MODE,RANGE)
                Nunc=clock; 
                Date_String=['AD ',datestr(Nunc,'yyyy'), datestr(Nunc, ' dddd mmmm dd  HH:MM:SS.FFF')];
                app.DateString.Text=Date_String;
                switch(MODE)
                    case -1
                        app.AddMessage(app.MessageList,[datestr(now),' Timer function Error - Needs to reset listening function. '],50);
                    case 0
                        app.AddMessage(app.MessageList,[datestr(now),' Timer function Stopped - Not Listening anymore'],50);
                    case 1 %Normal Operation
                        SelfDestruction=lcaGetSmart(app.SetupStructure.SelfDestructionPV);
                        if(SelfDestruction~=app.SetupStructure.SelfDestructionVal)
                            app.AddMessage(app.MessageList,[datestr(now),' Self-Destruction of Listen mode activated. ',app.SetupStructure.SelfDestructionPV,' must be ',num2str(app.SetupStructure.SelfDestructionVal), ' was ',num2str(SelfDestruction)],50);
                            try stop(app.TIMER); end %#ok<TRYNC> 
                            try delete(app.TIMER); end %#ok<TRYNC> 
                            set(app.StartResetListeningtoremotecontrolPVsButton,'enable','On');
                            set(app.STOPremotecontrolButton,'enable','Off');
                            return
                        end
                        CurrentValues=lcaGetSmart(app.SetupStructure.ListenPV);
                        if(any(CurrentValues~=app.OldValues))
                            TABULA=get(app.tabula,'data'); MOVE=1;
                            TABULOTTA=get(app.tabulotta,'data');
                            for II=1:numel(app.SetupStructure.ListenPV)
                                TABULOTTA{II,2}=CurrentValues(II);
                                %TABULA{II,5}=CurrentValues(II);
                                if(CurrentValues(II)<TABULA{II,2})
                                    MOVE=0; app.AddMessage(app.MessageList,[datestr(now),' Not Moving: ',TABULA{II,1},' too low ;  ',num2str(CurrentValues(II)), ' < ' num2str(TABULA{II,2})],50);
                                elseif(CurrentValues(II)>TABULA{II,3})
                                    MOVE=0; app.AddMessage(app.MessageList,[datestr(now),' Not Moving: ',TABULA{II,1},' too high ;  ',num2str(CurrentValues(II)), ' > ' num2str(TABULA{II,3})],50);
                                end
                            end
                            set(app.tabulotta,'data',TABULOTTA);
                            if(MOVE)
                                RelativeValuesFromReference=CurrentValues-app.SetupStructure.ReferenceValues;
                                if(numel(CurrentValues)==1)
                                    app.OffsetFromReferenceValue.Value=num2str(RelativeValuesFromReference,'%g');
                                    app.OffsetFromReferenceValueABS.Value=num2str(CurrentValues,'%g');
                                else
                                    stringa=num2str(RelativeValuesFromReference(:).',',%g');stringa(1)='[';stringa(end+1)=']';
                                    stringb=num2str(CurrentValues(:).',',%g');stringa(1)='[';stringa(end+1)=']';
                                    app.OffsetFromReferenceValue.Value=stringa;
                                    app.OffsetFromReferenceValueABS.Value=stringb;
                                end
                                Difference=CurrentValues-app.OldValues;
                                app.OldValues=CurrentValues;
                                app.AddMessage(app.MessageList,[datestr(now),' Moving to ',num2str(CurrentValues(:).')],50);
                                if(app.WritePVs.Value), lcaPutSmart(app.SetupStructure.MovePV,0); else, disp('Executing lcaPutSmart(app.SetupStructure.MovePV,0)'); end
                                if(~isempty(app.SetupStructure.InversePchip))
                                    Target=ppval(app.SetupStructure.InversePchip,RelativeValuesFromReference);
                                else
                                    Target=RelativeValuesFromReference;
                                end
                                if(app.WritePVs.Value), app.SetupStructure.MoveFunction(Target,Difference); else, disp('Executing app.SetupStructure.MoveFunction(Target);'); end
                                if(app.WritePVs.Value), lcaPutSmart(app.SetupStructure.MovePV,1); else, disp('Executing lcaPutSmart(app.SetupStructure.MovePV,1)'); end
                                app.AddMessage(app.MessageList,[datestr(now),' Moving Completed.'],50);
                                for II=1:numel(app.SetupStructure.ListenPV)
                                    TABULOTTA{II,1}=CurrentValues(II);
                                end
                                set(app.tabulotta,'data',TABULOTTA);
                            end
                        end
                    case 2
                        app.AddMessage(app.MessageList,[datestr(now),' Timer function Started - Actively Listening - Setting Listening PV to same as Initial setpoint '],50);
                        app.SetValue=lcaGetSmart(app.SetupStructure.ListenPV);
                        if(app.WritePVs.Value), lcaPutSmart(app.SetupStructure.ListenPV,app.SetValue); else, disp('Executing lcaPutSmart(app.SetupStructure.ListenPV,app.SetValue);'); end
                        pause(0.1); app.OldValues=app.SetValue;
                        CurrentSelfDestruction=lcaGetSmart(app.SetupStructure.SelfDestructionPV);
                        app.SetupStructure.SelfDestructionVal=CurrentSelfDestruction+1;
                        if(app.WritePVs.Value), lcaPutSmart(app.SetupStructure.SelfDestructionPV,app.SetupStructure.SelfDestructionVal); else, disp('Executing lcaPutSmart(app.SetupStructure.SelfDestructionPV,app.SetupStructure.SelfDestructionVal);'); end
                        TABULA=get(app.tabula,'data'); TABULOTTA=get(app.tabulotta,'data');
                        for II=1:numel(app.SetupStructure.ListenPV)
                                TABULA{II,1}=app.SetupStructure.ListenPV{II}; TABULA{II,2}=app.SetupStructure.ReferenceValues+RANGE{II}(1); TABULA{II,3}=app.SetupStructure.ReferenceValues+RANGE{II}(2); 
                                TABULOTTA{II,1}=app.SetValue; TABULOTTA{II,2}=app.SetValue;
                        end
                        set(app.tabula,'data',TABULA); set(app.tabulotta,'data',TABULOTTA);
                        set(app.tabula,'columneditable',[false,true,true]); set(app.tabula,'columnname',{'PV Name','Min Allowed','Max Allowed','Current Val','Requested Val'});
                        set(app.tabulotta,'columneditable',[false,false]); set(app.tabulotta,'columnname',{'Current Value','Requested Value'});
                        app.Timer_Update(0,0,1)
                        
                end
        end

        function [NewTarget,NewDifference]=MOVEDIAMOND(app,Target,Difference)
            if(app.SetupStructure.TwoColor) %always use both
                [Angles,Ene]=handles.CD.FindAnglesTwoColor([app.SetupStructure.Pitch,app.SetupStructure.Yaw], [app.SetupStructure.L1,app.SetupStructure.L2], [app.SetupStructure.Color1Machine+Target(1),app.SetupStructure.Color2Machine+Target(2)]);
            else
                if(app.SetupStructure.HXRSS_MonoMode==1) %pitch
                    [Angles,Ene]=app.CD.FindAnglesOneColor([app.SetupStructure.Pitch,app.SetupStructure.Yaw], app.SetupStructure.L1, 1,app.SetupStructure.Color1Machine+Target);
                elseif(app.SetupStructure.HXRSS_MonoMode==2) %yaw
                    [Angles,Ene]=handles.CD.FindAnglesOneColor([app.SetupStructure.Pitch,app.SetupStructure.Yaw], app.SetupStructure.L1, 0,app.SetupStructure.Color1Machine+Target);
                else %both
                    [Angles,Ene]=handles.CD.FindAnglesTwoColor([app.SetupStructure.Pitch,app.SetupStructure.Yaw], [app.SetupStructure.L1,app.SetupStructure.L1], [app.SetupStructure.Color1Machine+Target,app.SetupStructure.Color1Machine+Target]);
                end
            end
            NewTarget=mean(Target);
            if(nargin<3)
                NewDifference=0;
            else
                NewDifference=mean(Difference);
            end
            if(~isnan(Angles(1)))
                app.SetupStructure.PitchSet(app.SetupStructure.CD.name,Angles(1));
            end
            if(~isnan(Angles(2)))
                app.SetupStructure.YawSet(app.SetupStructure.CD.name,Angles(2));
            end
        end
        
        function MoveDiamondOnly(app,Target,Difference)
            if(nargin<3)
                app.MOVEDIAMOND(Target);
            else
                app.MOVEDIAMOND(Target,Difference);
            end
        end
        
        function MoveDiamondAndK(app,Target,Difference)
            if(nargin<3)
                [NewTarget,NewDifference]=app.MOVEDIAMOND(Target);
                app.MoveEnergyK(NewTarget,NewDifference);
            else
                [NewTarget,NewDifference]=app.MOVEDIAMOND(Target,Difference);
                app.MoveEnergyK(NewTarget,NewDifference);
            end
        end
        
        function MoveDiamondAndVernier(app,Target,Difference)
            if(nargin<3)
                [NewTarget,NewDifference]=app.MOVEDIAMOND(Target);
                app.MoveEnergyVernier(NewTarget,NewDifference);
            else
                [NewTarget,NewDifference]=app.MOVEDIAMOND(Target,Difference);
                app.MoveEnergyVernier(NewTarget,NewDifference);
            end
        end
    
        function MoveDiamondCombo(app,Target,Difference)
            if(nargin<3)
                [NewTarget,NewDifference]=app.MOVEDIAMOND(Target);    
                app.MoveEnergyCombo(NewTarget,NewDifference);
            else
                [NewTarget,NewDifference]=app.MOVEDIAMOND(Target,Difference);    
                app.MoveEnergyCombo(NewTarget,NewDifference);
            end
        end
        
        function MoveEnergyK(app,Target,Difference)
            DELTA=Target;
            DELTAe_Ph_number=DELTA;
            if(isnan(DELTA)), return, end            
            Destination.Cell=[]; Destination.K=[]; Destination.Kend=[];
            for II=1:length(app.SetupStructure.IndexSegmentInUse)
                e_ene_number=app.SetupStructure.Energy*1000;
                K_number=app.SetupStructure.InUseUndulatorsK_ini(II);
                ReciprocoUnoPiuKappaNuovoQuadratoMezziNuovoMenoVecchio = DELTAe_Ph_number/(app.PhyConsts.hplanck * app.PhyConsts.c / (app.U.Basic.Reference_lambda_u/1000/(2*(e_ene_number/(app.PhyConsts.mc2_e/10^6))^2)));
                KnuovoS = sqrt(2*(1./(ReciprocoUnoPiuKappaNuovoQuadratoMezziNuovoMenoVecchio + 1/(1+K_number^2/2)) -1));
                Destination.K(end+1)=KnuovoS;
                K_number=app.SetupStructure.InUseUndulatorsK_end(II);
                ReciprocoUnoPiuKappaNuovoQuadratoMezziNuovoMenoVecchio = DELTAe_Ph_number/(app.PhyConsts.hplanck * app.PhyConsts.c / (app.U.Basic.Reference_lambda_u/1000/(2*(e_ene_number/(app.PhyConsts.mc2_e/10^6))^2)));
                KnuovoE = sqrt(2*(1./(ReciprocoUnoPiuKappaNuovoQuadratoMezziNuovoMenoVecchio + 1/(1+K_number^2/2)) -1));
                Destination.Kend(end+1)=KnuovoE;
                Destination.Cell(end+1)=app.SetupStructure.CellSegmentInUse(II);
            end

            app.AddMessage(app.MessageList,[datestr(now),' Setting new undulator K'],50);
            if(app.WritePVs.Value)
                app.U.f.UndulatorLine_K_set(app.U,Destination,0);
            else
                disp('Executing app.U.f.UndulatorLine_K_set(app.U,Destination,0);')
            end
            
            app.AddMessage(app.MessageList,[datestr(now),' Energy change completed.'],50);
            pause(0.15);
            
            if(app.SetupStructure.SteerFlat)
                app.AddMessage(app.MessageList,[datestr(now),' Steering undulator orbit'],50);
                options.BSA_HB=1; options.AcquisitionTime=1;
                [~,options.startTime]=lcaGetSmart(strcat(app.s.bpmList_e{1},':X'));
                options.fitSVDRatio=0.005;
                Solution=app.sh.steer(app.s,options);
                if(Solution.FAILED)
                    app.AddMessage(app.MessageList,[datestr(now),' Correctors not in range, not applying solution'],50);
                else
                    if(app.WritePVs.Value), lcaPutSmart(strcat(Solution.UsedCorr_e,':BCTRL'),Solution.NewCorr); else, disp('Executing lcaPutSmart(strcat(Solution.UsedCorr_e,'':BCTRL''),Solution.NewCorr);'); end
                    app.AddMessage(app.MessageList,[datestr(now),' Steering finished'],50);
                    pause(0.2);
                end
            end
            
            
        end
        
        function MoveEnergyVernier(app,Target,Difference)
            DELTA=Target;
            DELTAe_Ph_number=DELTA;
            e_ene_number=app.SetupStructure.Energy*1000;
            K_number=app.SetupStructure.InUseUndulatorsK_ini(1);

            if(~isnan(e_ene_number) && ~isnan(K_number) && ~isnan(DELTAe_Ph_number))
                NuovoGammaQuadrato = DELTAe_Ph_number/2/((app.PhyConsts.hplanck * app.PhyConsts.c / (app.U.Basic.Reference_lambda_u/1000 * (1+K_number^2/2)))) + (e_ene_number/(app.PhyConsts.mc2_e/10^6))^2;
                DeltaE= sqrt(NuovoGammaQuadrato) * (app.PhyConsts.mc2_e/10^6);
            end 
            
            app.AddMessage(app.MessageList,[datestr(now),' Setting only new Vernier'],50);
            VernierToSet=app.SetupStructure.Vernier + DeltaE - e_ene_number;
            FudgedVernierToSet=app.SetupStructure.Vernier + (VernierToSet - app.SetupStructure.Vernier)*app.SetupStructure.VernierFudge;
            if(app.WritePVs.Value)
                lcaPutSmart(app.SetupStructure.VernierPV,FudgedVernierToSet);
            else
                disp('Executing lcaPutSmart(app.SetupStructure.VernierPV,VernierToSet)')
            end
            pause(0.15);
            app.AddMessage(app.MessageList,[datestr(now),' Energy change completed.'],50);
            if(app.SetupStructure.SteerFlat)
                app.AddMessage(app.MessageList,[datestr(now),' Steering undulator orbit'],50);
                options.BSA_HB=1; options.AcquisitionTime=1;
                [~,options.startTime]=lcaGetSmart(strcat(app.s.bpmList_e{1},':X'));
                options.fitSVDRatio=0.005;
                Solution=app.sh.steer(app.s,options);
                if(Solution.FAILED)
                    app.AddMessage(app.MessageList,[datestr(now),' Correctors not in range, not applying solution'],50);
                else
                    if(app.WritePVs.Value), lcaPutSmart(strcat(Solution.UsedCorr_e,':BCTRL'),Solution.NewCorr); else, disp('Executing lcaPutSmart(strcat(Solution.UsedCorr_e,'':BCTRL''),Solution.NewCorr);'); end
                    app.AddMessage(app.MessageList,[datestr(now),' Steering finished'],50);
                    pause(0.2);
                end
            end
            
        end
        
        function ReadPhotonEnergy(app,caller)
            switch(caller)
                case 1
                    EnergyPh=lcaGetSmart(app.ReferenceControlEnergyPVEditField1.Value);
                case 2
                    EnergyPh=lcaGetSmart(app.ReferenceControlEnergyPVEditField2.Value);
                case 3
                    EnergyPh=lcaGetSmart(app.ReferenceControlEnergyPVEditField31.Value);
            end
            app.(['KnownPhotonEnergy',num2str(caller)]).Value=EnergyPh;
        end
        
        function app.LowLevelVernierAndK(app,NewValue,Difference)
            KChange=NewValue(1); VernierChange=NewValue(2);
            DELTA=KChange;
            DELTAe_Ph_number=DELTA;
            if(isnan(DELTA)), return, end            
            Destination.Cell=[]; Destination.K=[]; Destination.Kend=[];
            for II=1:length(app.SetupStructure.IndexSegmentInUse)
                e_ene_number=app.SetupStructure.Energy*1000;
                K_number=app.SetupStructure.InUseUndulatorsK_ini(II);
                ReciprocoUnoPiuKappaNuovoQuadratoMezziNuovoMenoVecchio = DELTAe_Ph_number/(app.PhyConsts.hplanck * app.PhyConsts.c / (app.U.Basic.Reference_lambda_u/1000/(2*(e_ene_number/(app.PhyConsts.mc2_e/10^6))^2)));
                KnuovoS = sqrt(2*(1./(ReciprocoUnoPiuKappaNuovoQuadratoMezziNuovoMenoVecchio + 1/(1+K_number^2/2)) -1));
                Destination.K(end+1)=KnuovoS;
                K_number=app.SetupStructure.InUseUndulatorsK_end(II);
                ReciprocoUnoPiuKappaNuovoQuadratoMezziNuovoMenoVecchio = DELTAe_Ph_number/(app.PhyConsts.hplanck * app.PhyConsts.c / (app.U.Basic.Reference_lambda_u/1000/(2*(e_ene_number/(app.PhyConsts.mc2_e/10^6))^2)));
                KnuovoE = sqrt(2*(1./(ReciprocoUnoPiuKappaNuovoQuadratoMezziNuovoMenoVecchio + 1/(1+K_number^2/2)) -1));
                Destination.Kend(end+1)=KnuovoE;
                Destination.Cell(end+1)=app.SetupStructure.CellSegmentInUse(II);
            end
            %Evaluate additional Vernier to do VernierChange from new 1st K
            DELTA=VernierChange;
            DELTAe_Ph_number=DELTA;
            e_ene_number=app.SetupStructure.Energy*1000;
            K_number=Destination.K(1);
            if(~isnan(e_ene_number) && ~isnan(K_number) && ~isnan(DELTAe_Ph_number))
                NuovoGammaQuadrato = DELTAe_Ph_number/2/((app.PhyConsts.hplanck * app.PhyConsts.c / (app.U.Basic.Reference_lambda_u/1000 * (1+K_number^2/2)))) + (e_ene_number/(app.PhyConsts.mc2_e/10^6))^2;
                DeltaE= sqrt(NuovoGammaQuadrato) * (app.PhyConsts.mc2_e/10^6);
            end
            VernierToSet=app.SetupStructure.Vernier + DeltaE - e_ene_number;
            FudgedVernierToSet=app.SetupStructure.Vernier + (VernierToSet - app.SetupStructure.Vernier)*app.SetupStructure.VernierFudge;
            if(Difference(1))
                app.AddMessage(app.MessageList,[datestr(now),' Setting both Vernier and K'],50);
            else
                app.AddMessage(app.MessageList,[datestr(now),' Setting only new Vernier'],50);
            end
            if(app.WritePVs.Value)
                lcaPutSmart(app.SetupStructure.VernierPV,FudgedVernierToSet);
            else
                disp('Executing lcaPutSmart(app.SetupStructure.VernierPV,VernierToSet)')
            end
            if(Difference(1))
                if(app.WritePVs.Value)
                    app.U.f.UndulatorLine_K_set(app.U,Destination,0);
                else
                    disp('Executing app.U.f.UndulatorLine_K_set(app.U,Destination,0);')
                end
            end
            app.AddMessage(app.MessageList,[datestr(now),' Energy change completed'],50);
            if(app.SetupStructure.SteerFlat)
                app.AddMessage(app.MessageList,[datestr(now),' Steering undulator orbit'],50);
                options.BSA_HB=1; options.AcquisitionTime=1;
                [~,options.startTime]=lcaGetSmart(strcat(app.s.bpmList_e{1},':X'));
                options.fitSVDRatio=0.005;
                Solution=app.sh.steer(app.s,options);
                if(Solution.FAILED)
                    app.AddMessage(app.MessageList,[datestr(now),' Correctors not in range, not applying solution'],50);
                else
                    if(app.WritePVs.Value), lcaPutSmart(strcat(Solution.UsedCorr_e,':BCTRL'),Solution.NewCorr); else, disp('Executing lcaPutSmart(strcat(Solution.UsedCorr_e,'':BCTRL''),Solution.NewCorr);'); end
                    app.AddMessage(app.MessageList,[datestr(now),' Steering finished'],50);
                    pause(0.2);
                end
            end
        end
        
        function EvalStateFunc(app)
            CurrentVernier=lcaGetSmart(app.U.Basic.Vernier);
            DeFudgedCurrentVernier= (CurrentVernier - app.SetupStructure.Vernier*(1-app.SetupStructure.VernierFudge))/app.SetupStructure.VernierFudge;%  app.SetupStructure.Vernier + (VernierToSet - app.SetupStructure.Vernier)*app.SetupStructure.VernierFudge;
            LineReadout=app.U.f.Read_all_K_values(app.U);
            KForUsedSegments_Ini=[LineReadout(app.SetupStructure.IndexSegmentInUse).K];
            KForUsedSegments_End=[LineReadout(app.SetupStructure.IndexSegmentInUse).Kend];
            
            ExitCondition= (~any(isnan(KForUsedSegments_Ini))) && (~any(isnan(KForUsedSegments_End)));
            retry=0;
            while(~ExitCondition && (retry<10))
                pause(0.1);
                retry=retry+1;
                LineReadout=app.U.f.Read_all_K_values(app.U);
                KForUsedSegments_Ini=LineReadout(app.SetupStructure.IndexSegmentInUse).K;
                KForUsedSegments_End=LineReadout(app.SetupStructure.IndexSegmentInUse).Kend;
                ExitCondition= (~any(isnan(KForUsedSegments_Ini))) && (~any(isnan(KForUsedSegments_End)));
            end
            if(retry==10)
                app.AddMessage(app.MessageList,[datestr(now),' Required undulator responded with NaN in 10 consecutive retries. Energy change failed.'],50); return;
            end
            
            Lambda_ref_ini=app.U.Basic.Reference_lambda_u./1000.*(1+(app.SetupStructure.InUseUndulatorsK_ini.^2)/2)/2/(((app.SetupStructure.Energy*1000+app.SetupStructure.Vernier)/(app.PhyConsts.mc2_e/10^6)).^2);
            Lambda_ref_end=app.U.Basic.Reference_lambda_u./1000.*(1+(app.SetupStructure.InUseUndulatorsK_end.^2)/2)/2/(((app.SetupStructure.Energy*1000+app.SetupStructure.Vernier)/(app.PhyConsts.mc2_e/10^6)).^2);
            Lambda_now_ini=app.U.Basic.Reference_lambda_u./1000.*(1+(KForUsedSegments_Ini.^2)/2)/2/(((app.SetupStructure.Energy*1000+DeFudgedCurrentVernier)/(app.PhyConsts.mc2_e/10^6)).^2);
            Lambda_now_end=app.U.Basic.Reference_lambda_u./1000.*(1+(KForUsedSegments_End.^2)/2)/2/(((app.SetupStructure.Energy*1000+DeFudgedCurrentVernier)/(app.PhyConsts.mc2_e/10^6)).^2);

            Lambda_now_kOnly_ini=app.U.Basic.Reference_lambda_u./1000.*(1+(KForUsedSegments_Ini.^2)/2)/2/(((app.SetupStructure.Energy*1000)/(app.PhyConsts.mc2_e/10^6)).^2);
            Lambda_now_kOnly_end=app.U.Basic.Reference_lambda_u./1000.*(1+(KForUsedSegments_End.^2)/2)/2/(((app.SetupStructure.Energy*1000)/(app.PhyConsts.mc2_e/10^6)).^2);
            
            Lambda_now=[Lambda_now_ini(:);Lambda_now_end(:)]; Lambda_ref=[Lambda_ref_ini(:);Lambda_ref_end(:)];
            Lambda_now_kOnly=[Lambda_now_kOnly_ini(:);Lambda_now_kOnly_end(:)];
            EstimateCurrentDeltaEnergy=app.PhyConsts.hplanck * app.PhyConsts.c .*(1./Lambda_now - 1./Lambda_ref);
            EstimateCurrentDeltaEnergy_kOnly=app.PhyConsts.hplanck * app.PhyConsts.c .*(1./Lambda_now_kOnly - 1./Lambda_ref);
            MEC=mean(EstimateCurrentDeltaEnergy);
            STD=std(EstimateCurrentDeltaEnergy);
            REMOVE=(abs(EstimateCurrentDeltaEnergy-MEC)>5*STD);
            EstimateCurrentDeltaEnergy(REMOVE)=[];
            EstimateCurrentDeltaEnergy_kOnly(REMOVE)=EstimateCurrentDeltaEnergy_kOnly(REMOVE);
            CurrentDeltaEnergy=EstimateCurrentDeltaEnergy(1);
            CurrentDeltaEnergyKOnly=EstimateCurrentDeltaEnergy_kOnly(1);
            KTEMP=[KForUsedSegments_Ini,KForUsedSegments_End];
            KTEMP(REMOVE)=[]; 
            TheString=['Last call: DE(eV) = ',num2str(CurrentDeltaEnergy),'; K contrib = ',num2str(CurrentDeltaEnergyKOnly),'; Vernier contrib = ',num2str(CurrentDeltaEnergy-CurrentDeltaEnergyKOnly)];
            disp(['CurrentDeltaEnergy From Reference = ',num2str(CurrentDeltaEnergy)]);
            disp(['CurrentDeltaEnergy From Reference K Contribution only = ',num2str(CurrentDeltaEnergyKOnly)]);
            disp(['CurrentDeltaEnergy From Reference Vernier Contribution = ',num2str(CurrentDeltaEnergy-CurrentDeltaEnergyKOnly)]);
            if(isfield(app.SetupStructure,'CD'))
               CrystalStatus=app.SetupStructure.StatusGet(app.SetupStructure.CrystalName);
               Color1Machine=app.CD.PhotonEnergy_all_formula(CrystalStatus.Pitch,CrystalStatus.Yaw,app.SetupStructure.L1);
               disp(['Current Line (',num2str(app.SetupStructure.L1.'),') From resonance = ',num2str(-app.SetupStructure.Color1Machine+ Color1Machine)]);
               TheString=[TheString,'; (',num2str(app.SetupStructure.L1.'),') at ',num2str(-app.SetupStructure.Color1Machine+ Color1Machine)];
               if(app.SetupStructure.TwoColor)
                   Color2Machine=app.CD.PhotonEnergy_all_formula(CrystalStatus.Pitch,CrystalStatus.Yaw,app.SetupStructure.L2);
                   disp(['Current Line (',num2str(app.SetupStructure.L2.'),') From resonance = ',num2str(-app.SetupStructure.Color2Machine+ Color2Machine)]);
                   TheString=[TheString,'; (',num2str(app.SetupStructure.L2.'),') at ',num2str(-app.SetupStructure.Color2Machine+ Color2Machine)];
               end
            else
            end
            app.MainReadoutStringLabel1.Text=TheString;
        end
        
        function ErrorMessage=MoveEnergyCombo(app,NewValue,Difference)
            ErrorMessage={};
            CurrentVernier=lcaGetSmart(app.U.Basic.Vernier);
            DeFudgedCurrentVernier= (CurrentVernier - app.SetupStructure.Vernier*(1-app.SetupStructure.VernierFudge))/app.SetupStructure.VernierFudge;%  app.SetupStructure.Vernier + (VernierToSet - app.SetupStructure.Vernier)*app.SetupStructure.VernierFudge;
            LineReadout=app.U.f.Read_all_K_values(app.U);
            KForUsedSegments_Ini=[LineReadout(app.SetupStructure.IndexSegmentInUse).K];
            KForUsedSegments_End=[LineReadout(app.SetupStructure.IndexSegmentInUse).Kend];
            
            ExitCondition= (~any(isnan(KForUsedSegments_Ini))) && (~any(isnan(KForUsedSegments_End)));
            retry=0;
            while(~ExitCondition && (retry<10))
                pause(0.1);
                retry=retry+1;
                LineReadout=app.U.f.Read_all_K_values(app.U);
                KForUsedSegments_Ini=LineReadout(app.SetupStructure.IndexSegmentInUse).K;
                KForUsedSegments_End=LineReadout(app.SetupStructure.IndexSegmentInUse).Kend;
                ExitCondition= (~any(isnan(KForUsedSegments_Ini))) && (~any(isnan(KForUsedSegments_End)));
            end
            if(retry==10)
                app.AddMessage(app.MessageList,[datestr(now),' Required undulator responded with NaN in 10 consecutive retries. Energy change failed.'],50); return;
            end
            
            Lambda_ref_ini=app.U.Basic.Reference_lambda_u./1000.*(1+(app.SetupStructure.InUseUndulatorsK_ini.^2)/2)/2/(((app.SetupStructure.Energy*1000+app.SetupStructure.Vernier)/(app.PhyConsts.mc2_e/10^6)).^2);
            Lambda_ref_end=app.U.Basic.Reference_lambda_u./1000.*(1+(app.SetupStructure.InUseUndulatorsK_end.^2)/2)/2/(((app.SetupStructure.Energy*1000+app.SetupStructure.Vernier)/(app.PhyConsts.mc2_e/10^6)).^2);
            Lambda_now_ini=app.U.Basic.Reference_lambda_u./1000.*(1+(KForUsedSegments_Ini.^2)/2)/2/(((app.SetupStructure.Energy*1000+DeFudgedCurrentVernier)/(app.PhyConsts.mc2_e/10^6)).^2);
            Lambda_now_end=app.U.Basic.Reference_lambda_u./1000.*(1+(KForUsedSegments_End.^2)/2)/2/(((app.SetupStructure.Energy*1000+DeFudgedCurrentVernier)/(app.PhyConsts.mc2_e/10^6)).^2);

            Lambda_now_kOnly_ini=app.U.Basic.Reference_lambda_u./1000.*(1+(KForUsedSegments_Ini.^2)/2)/2/(((app.SetupStructure.Energy*1000)/(app.PhyConsts.mc2_e/10^6)).^2);
            Lambda_now_kOnly_end=app.U.Basic.Reference_lambda_u./1000.*(1+(KForUsedSegments_End.^2)/2)/2/(((app.SetupStructure.Energy*1000)/(app.PhyConsts.mc2_e/10^6)).^2);
            
            Lambda_now=[Lambda_now_ini(:);Lambda_now_end(:)]; Lambda_ref=[Lambda_ref_ini(:);Lambda_ref_end(:)];
            Lambda_now_kOnly=[Lambda_now_kOnly_ini(:);Lambda_now_kOnly_end(:)];
            EstimateCurrentDeltaEnergy=app.PhyConsts.hplanck * app.PhyConsts.c .*(1./Lambda_now - 1./Lambda_ref);
            EstimateCurrentDeltaEnergy_kOnly=app.PhyConsts.hplanck * app.PhyConsts.c .*(1./Lambda_now_kOnly - 1./Lambda_ref);
            MEC=mean(EstimateCurrentDeltaEnergy);
            STD=std(EstimateCurrentDeltaEnergy);
            REMOVE=(abs(EstimateCurrentDeltaEnergy-MEC)>5*STD);
            EstimateCurrentDeltaEnergy(REMOVE)=[];
            EstimateCurrentDeltaEnergy_kOnly(REMOVE)=EstimateCurrentDeltaEnergy_kOnly(REMOVE);
            CurrentDeltaEnergy=EstimateCurrentDeltaEnergy(1);
            CurrentDeltaEnergyKOnly=EstimateCurrentDeltaEnergy_kOnly(1);
            KTEMP=[KForUsedSegments_Ini,KForUsedSegments_End];
            KTEMP(REMOVE)=[];    
            
            DeltaRequiredFromVernier=NewValue-CurrentDeltaEnergyKOnly;  
            e_ene_number=app.SetupStructure.Energy*1000;
            K_number=KTEMP(1);
            %DELTAe_Ph_number=NewValue; %can Vernier in range make it with K_number?
            
            if(~isnan(e_ene_number) && ~isnan(K_number) && ~isnan(DeltaRequiredFromVernier))
                NuovoGammaQuadrato = DeltaRequiredFromVernier/2/((app.PhyConsts.hplanck * app.PhyConsts.c / (app.U.Basic.Reference_lambda_u/1000 * (1+K_number^2/2)))) + (e_ene_number/(app.PhyConsts.mc2_e/10^6))^2;
                AbsoluteEnergyRequired= sqrt(NuovoGammaQuadrato) * (app.PhyConsts.mc2_e/10^6);
            end 
            VernierToSet=AbsoluteEnergyRequired-e_ene_number;
            FudgedVernierToSet=app.SetupStructure.Vernier + (VernierToSet - app.SetupStructure.Vernier)*app.SetupStructure.VernierFudge;
            
%             if(~isnan(e_ene_number) && ~isnan(K_number) && ~isnan(DELTAe_Ph_number))
%                 NuovoGammaQuadrato = DELTAe_Ph_number/2/((app.PhyConsts.hplanck * app.PhyConsts.c / (app.U.Basic.Reference_lambda_u/1000 * (1+K_number^2/2)))) + (e_ene_number/(app.PhyConsts.mc2_e/10^6))^2;
%                 DeltaE= sqrt(NuovoGammaQuadrato) * (app.PhyConsts.mc2_e/10^6);
%             end 
%             
%             app.AddMessage(app.MessageList,[datestr(now),' Setting only new Vernier'],50);
%             VernierToSet=app.SetupStructure.Vernier + DeltaE - e_ene_number;
            if(nargin<3)
                Difference=0;
            end
            if(length(Difference)==7) %special case for setting Venrier to Reference.
                %MoveEnergyK(app,Target,[1,5,7,1,4,3,1]);
                app.AddMessage(app.MessageList,[datestr(now),' Restoring reference'],50);
                MakeDestination=1;
                VernierToSet=app.SetupStructure.Vernier;
                DeFudgedCurrentVernier= (VernierToSet - app.SetupStructure.Vernier*(1-app.SetupStructure.VernierFudge))/app.SetupStructure.VernierFudge;
                e_ene_number=app.SetupStructure.Energy*1000;
                %K_number=app.SetupStructure.InUseUndulatorsK_ini(1);
                DELTAe_Ph_number=NewValue;
                UnoSuUnoPiuKappaQuadratoMezzi=1./(((e_ene_number + DeFudgedCurrentVernier)./(app.PhyConsts.mc2_e/10^6) ).^2   ).*(DELTAe_Ph_number.*app.U.Basic.Reference_lambda_u/1000/2/app.PhyConsts.c/app.PhyConsts.hplanck + ((((e_ene_number + app.SetupStructure.Vernier)/(app.PhyConsts.mc2_e/10^6)).^2)./(1+app.SetupStructure.InUseUndulatorsK_ini.^2/2) )  );
                Knuovo_ini = sqrt(2*(1./UnoSuUnoPiuKappaQuadratoMezzi-1));%sqrt(2*(1./(UnoSuUnoPiuKappaQuadratoMezzi + 1/(1+K_number^2/2)) -1));
                UnoSuUnoPiuKappaQuadratoMezzi=1./(((e_ene_number + DeFudgedCurrentVernier)./(app.PhyConsts.mc2_e/10^6) ).^2   ).*(DELTAe_Ph_number.*app.U.Basic.Reference_lambda_u/1000/2/app.PhyConsts.c/app.PhyConsts.hplanck + ((((e_ene_number + app.SetupStructure.Vernier)/(app.PhyConsts.mc2_e/10^6)).^2)./(1+app.SetupStructure.InUseUndulatorsK_end.^2/2) )  );
                Knuovo_end = sqrt(2*(1./UnoSuUnoPiuKappaQuadratoMezzi-1));
                FudgedVernierToSet=0;
            else
           
            %Calculate New Vernier. Is in range or not?
            if(FudgedVernierToSet>app.SetupStructure.VernierRange(2)) %Out of range: Vernier too high, adjust K with Vernier Min
                MakeDestination=1;
                VernierToSet=app.SetupStructure.VernierRange(1);
                DeFudgedCurrentVernier= (VernierToSet - app.SetupStructure.Vernier*(1-app.SetupStructure.VernierFudge))/app.SetupStructure.VernierFudge;
                e_ene_number=app.SetupStructure.Energy*1000;
                %K_number=app.SetupStructure.InUseUndulatorsK_ini(1);
                DELTAe_Ph_number=NewValue;
                UnoSuUnoPiuKappaQuadratoMezzi=1./(((e_ene_number + DeFudgedCurrentVernier)./(app.PhyConsts.mc2_e/10^6) ).^2   ).*(DELTAe_Ph_number.*app.U.Basic.Reference_lambda_u/1000/2/app.PhyConsts.c/app.PhyConsts.hplanck + ((((e_ene_number + app.SetupStructure.Vernier)/(app.PhyConsts.mc2_e/10^6)).^2)./(1+app.SetupStructure.InUseUndulatorsK_ini.^2/2) )  );
                Knuovo_ini = sqrt(2*(1./UnoSuUnoPiuKappaQuadratoMezzi-1));%sqrt(2*(1./(UnoSuUnoPiuKappaQuadratoMezzi + 1/(1+K_number^2/2)) -1));
                UnoSuUnoPiuKappaQuadratoMezzi=1./(((e_ene_number + DeFudgedCurrentVernier)./(app.PhyConsts.mc2_e/10^6) ).^2   ).*(DELTAe_Ph_number.*app.U.Basic.Reference_lambda_u/1000/2/app.PhyConsts.c/app.PhyConsts.hplanck + ((((e_ene_number + app.SetupStructure.Vernier)/(app.PhyConsts.mc2_e/10^6)).^2)./(1+app.SetupStructure.InUseUndulatorsK_end.^2/2) )  );
                Knuovo_end = sqrt(2*(1./UnoSuUnoPiuKappaQuadratoMezzi-1));
                FudgedVernierToSet=VernierToSet;
            elseif(FudgedVernierToSet<app.SetupStructure.VernierRange(1)) %Out of range: Vernier too low, adjust K with Vernier Max
                MakeDestination=1;
                VernierToSet=app.SetupStructure.VernierRange(2);
                DeFudgedCurrentVernier= (VernierToSet - app.SetupStructure.Vernier*(1-app.SetupStructure.VernierFudge))/app.SetupStructure.VernierFudge;
                DELTAe_Ph_number=NewValue;
                e_ene_number=app.SetupStructure.Energy*1000;
                UnoSuUnoPiuKappaQuadratoMezzi=1./(((e_ene_number + DeFudgedCurrentVernier)./(app.PhyConsts.mc2_e/10^6) ).^2   ).*(DELTAe_Ph_number.*app.U.Basic.Reference_lambda_u/1000/2/app.PhyConsts.c/app.PhyConsts.hplanck + ((((e_ene_number + app.SetupStructure.Vernier)/(app.PhyConsts.mc2_e/10^6)).^2)./(1+app.SetupStructure.InUseUndulatorsK_ini.^2/2) )  );
                Knuovo_ini = sqrt(2*(1./UnoSuUnoPiuKappaQuadratoMezzi-1));%sqrt(2*(1./(UnoSuUnoPiuKappaQuadratoMezzi + 1/(1+K_number^2/2)) -1));
                UnoSuUnoPiuKappaQuadratoMezzi=1./(((e_ene_number + DeFudgedCurrentVernier)./(app.PhyConsts.mc2_e/10^6) ).^2   ).*(DELTAe_Ph_number.*app.U.Basic.Reference_lambda_u/1000/2/app.PhyConsts.c/app.PhyConsts.hplanck + ((((e_ene_number + app.SetupStructure.Vernier)/(app.PhyConsts.mc2_e/10^6)).^2)./(1+app.SetupStructure.InUseUndulatorsK_end.^2/2) )  );
                Knuovo_end = sqrt(2*(1./UnoSuUnoPiuKappaQuadratoMezzi-1));
                FudgedVernierToSet=VernierToSet;
            else % In range, change Vernier and that's it.
                MakeDestination=0;
            end
            end
            
            if(MakeDestination)
                Destination.Cell=[]; Destination.K=[]; Destination.Kend=[];
                for II=1:length(app.SetupStructure.IndexSegmentInUse)
                    Destination.K(end+1)=Knuovo_ini(II);
                    Destination.Kend(end+1)=Knuovo_end(II);
                    Destination.Cell(end+1)=app.SetupStructure.CellSegmentInUse(II);
                end
            end
            
            if(MakeDestination)
                app.AddMessage(app.MessageList,[datestr(now),' Setting new Vernier and moving undulator K'],50);
                if(app.WritePVs.Value)
                    lcaPutSmart(app.SetupStructure.VernierPV,FudgedVernierToSet);
                    app.U.f.UndulatorLine_K_set(app.U,Destination,0);
                else
                    disp('lcaPutSmart(app.SetupStructure.VernierPV,VernierToSet);');
                    disp('app.U.f.UndulatorLine_K_set(app.U,Destination,0);');
                end
            else
                app.AddMessage(app.MessageList,[datestr(now),' Setting only new Vernier'],50);
                if(app.WritePVs.Value)
                    lcaPutSmart(app.SetupStructure.VernierPV,FudgedVernierToSet);
                else
                    disp('Executing lcaPutSmart(app.SetupStructure.VernierPV,VernierToSet);');
                end
            end
            app.AddMessage(app.MessageList,[datestr(now),' Energy change completed'],50);
            if(app.SetupStructure.SteerFlat)
                app.AddMessage(app.MessageList,[datestr(now),' Steering undulator orbit'],50);
                options.BSA_HB=1; options.AcquisitionTime=1;
                [~,options.startTime]=lcaGetSmart(strcat(app.s.bpmList_e{1},':X'));
                options.fitSVDRatio=0.005;
                Solution=app.sh.steer(app.s,options);
                if(Solution.FAILED)
                    app.AddMessage(app.MessageList,[datestr(now),' Correctors not in range, not applying solution'],50);
                else
                    if(app.WritePVs.Value), lcaPutSmart(strcat(Solution.UsedCorr_e,':BCTRL'),Solution.NewCorr); else, disp('Executing lcaPutSmart(strcat(Solution.UsedCorr_e,'':BCTRL''),Solution.NewCorr);'); end
                    app.AddMessage(app.MessageList,[datestr(now),' Steering finished'],50);
                    pause(0.2);
                end
            end
            
            
        end
        
        function ManageEndStation(app,caller)
            EndStationString=app.(['Endstation',num2str(caller)]).Value;
            switch(caller)
                case 1
                    Fields{1}=app.RemoteControlEnergyPVEditField1;
                    Fields{2}=app.ReferenceControlEnergyPVEditField1;
                case 2
                    Fields{1}=app.RemoteControlEnergyPVEditField2;
                    Fields{2}=app.ReferenceControlEnergyPVEditField2;
                    
                case 3
                    Fields{1}=app.RemoteControlEnergyPVEditField31;
                    Fields{2}=app.RemoteControlEnergyPVEditField32;
                    Fields{3}=app.ReferenceControlEnergyPVEditField31;
                    Fields{4}=app.ReferenceControlEnergyPVEditField32;
            end
            for II=1:numel(Fields)
                String=Fields{II}.Value;
                Comma=find(String==',');
                if(isempty(Comma))
                    Colon=find(String==':',1,'first');
                    if(~isempty(Colon))
                        String(1:(Colon-1))=[];
                        String=[EndStationString,String];
                        if((caller==2) || (caller==3))
                            Fields{II}.Value=regexprep(String,'EPHOTK','EPHOT');
                        else
                            Fields{II}.Value=String;
                        end
                    end
                else
                    Comma(end+1)=(length(String)+1);
                    for JJ=1:length(Comma)
                        if(JJ==1)
                            Name{JJ}=String(1:(Comma(JJ)-1));
                        else
                            Name{JJ}=String((Comma(JJ-1)+1):(Comma(JJ)-1));
                        end
                        Colon=find(Name{JJ}==':',1,'first');
                        if(~isempty(Colon))
                            Name{JJ}(1:(Colon-1))=[];
                            Name{JJ}=[EndStationString,Name{JJ}];
                        end
                    end
                    newname=[];
                    for  JJ=1:numel(Name)
                        newname=[newname,Name{JJ},','];
                    end
                    newname(end)=[];
                    Fields{II}.Value=newname;
                end
            end
            
        end
        
        function EvRange=EvaluateEnergyRange(app,VernierRange,UndulatorRange,UseCellNumber)
            for II=1:app.U.slotlength
                if((UseCellNumber && any(app.U.slotcell(II)==UndulatorRange)) || (~UseCellNumber && any(II==UndulatorRange)) )
                    if(app.U.slot(II).USEG.present)
                        ReferenceK=app.SetupStructure.LineReadout(II).K;
                        break;                     
                    end
                end          
            end
            e_ene_number=app.SetupStructure.Energy*1000;
            UPK2d2=(1+ReferenceK^2/2);
            LAMU=app.U.Basic.Reference_lambda_u/1000;
            LamRef=LAMU*UPK2d2/2/((((e_ene_number + app.SetupStructure.Vernier)./(app.PhyConsts.mc2_e/10^6) ).^2 ));
            LamPlus=LAMU*UPK2d2/2/((((e_ene_number + VernierRange(2))./(app.PhyConsts.mc2_e/10^6) ).^2 ));
            LamMinus=LAMU*UPK2d2/2/((((e_ene_number + VernierRange(1))./(app.PhyConsts.mc2_e/10^6) ).^2 ));
            EneRef=app.PhyConsts.c*app.PhyConsts.hplanck / LamRef;
            EnePlus=app.PhyConsts.c*app.PhyConsts.hplanck / LamPlus;
            EneMinus=app.PhyConsts.c*app.PhyConsts.hplanck / LamMinus;
            EvRange=[EneMinus,EnePlus]-EneRef;
        end
        
        function FinalizeHXRSSEnergyOnly(app)
            app.AddMessage(app.MessageList,[datestr(now),' Finalizing Setup for HXR Self-Seeding on HXR line'],50);
            try app.SetupStructure=rmfield(app.SetupStructure,'ListenPV'); end
            try app.SetupStructure=rmfield(app.SetupStructure,'ReferencePV'); end
            UndulatorRange=str2num(app.UndulatorrangetobemovedEditField3.Value);
            UseCellNumber=app.UsecellCheckBox3.Value;
            app.SetupStructure.ModeOfChange=app.ControlMode3.Value;
            app.SetupStructure.Energy=app.T3.Data{2,2};
            app.SetupStructure.VernierFudge=app.VernierFudge3.Value;
            %app.ControlMode3.Items={'Diamond Only','Diamond + K','Diamond + Vernier','Diamond + K + Vernier Combo'};
            switch app.SetupStructure.ModeOfChange
                case app.ControlMode3.Items{1}
                    app.SetupStructure.MoveFunction=@app.MoveDiamondOnly;
                case app.ControlMode3.Items{2}
                    app.SetupStructure.MoveFunction=@app.MoveDiamondAndK;
                case app.ControlMode3.Items{3}
                    app.SetupStructure.MoveFunction=@app.MoveDiamondAndVernier;
                case app.ControlMode3.Items{4}
                    app.SetupStructure.MoveFunction=@app.MoveDiamondCombo;
            end
            app.SetupStructure.SelfDestructionPV=app.Selfdestruction3.Value;
            CurrentSelfDestruction=lcaGetSmart(app.SetupStructure.SelfDestructionPV);
            app.SetupStructure.SingleColor=app.Color1.Value;
            if(app.SetupStructure.SingleColor)
                LPV=app.RemoteControlEnergyPVEditField31.Value;
                RLPV=app.ReferenceControlEnergyPVEditField31.Value;
                app.SetupStructure.ListenPV=LPV;
                app.SetupStructure.ReferencePV=RLPV;
                app.SetupStructure.L1=[app.h1.Value,app.k1.Value,app.l1.Value].';
            else
                LPV{1}=app.RemoteControlEnergyPVEditField31.Value;
                LPV{2}=app.RemoteControlEnergyPVEditField32.Value;
                RLPV{1}=app.ReferenceControlEnergyPVEditField31.Value;
                RLPV{2}=app.ReferenceControlEnergyPVEditField32.Value;
                app.SetupStructure.ListenPV=LPV(:);
                app.SetupStructure.ReferencePV=RLPV(:);
                app.SetupStructure.L1=[app.h1.Value,app.k1.Value,app.l1.Value].';
                app.SetupStructure.L2=[app.h2.Value,app.k2.Value,app.l2.Value].';
            end
            app.SetupStructure.TwoColor=~app.SetupStructure.SingleColor;
            app.SetupStructure.ReferenceValues=lcaGetSmart(app.SetupStructure.ReferencePV);
            app.ReferenceValuesForEnergyLabel.Text=num2str(app.SetupStructure.ReferenceValues(:).');
            
            VernierRange(1)=str2num(app.MinVernier3.Value);
            VernierRange(2)=str2num(app.MaxVernier3.Value);
            app.SetupStructure.VernierRange=VernierRange;
            app.SetupStructure.VernierPV=app.U.Basic.Vernier;
            
            app.SetupStructure.MID=find(strcmp({app.AMGP.name},'LCLS'));
            app.SetupStructure.M=app.AMGP(app.SetupStructure.MID);
            app.SetupStructure.PitchSet=app.HXRSS.PitchSet;
            app.SetupStructure.YawSet=app.HXRSS.YawSet;
            app.SetupStructure.PitchGet=app.HXRSS.PitchGet;
            app.SetupStructure.YawGet=app.HXRSS.YawGet;
            app.SetupStructure.StatusGet=app.HXRSS.StatusGet;
            app.SetupStructure.InOutSet=app.HXRSS.InOutSet;
            app.SetupStructure.PVsGet=app.HXRSS.PVsGet;
            
            app.SetupStructure.CID=find(strcmp({app.SetupStructure.M.CD.name},app.SetupStructure.CrystalName));
            app.SetupStructure.CD=app.SetupStructure.M.CD(app.SetupStructure.CID);
            app.CD.set_crystal(app.SetupStructure.CD);
            [app.SetupStructure.Color1Machine,app.SetupStructure.Color1MachinePitchDer,app.SetupStructure.Color1MachineYawDer]=app.CD.PhotonEnergy_all_formula(app.SetupStructure.Pitch,app.SetupStructure.Yaw,app.SetupStructure.L1);
            if(app.SetupStructure.TwoColor)
                [app.SetupStructure.Color2Machine,app.SetupStructure.Color2MachinePitchDer,app.SetupStructure.Color2MachineYawDer]=app.CD.PhotonEnergy_all_formula(app.SetupStructure.Pitch,app.SetupStructure.Yaw,app.SetupStructure.L2);
            end
            
            switch app.DiamondControl.Value
                case 'Pitch Only'
                    app.SetupStructure.HXRSS_MonoMode=1;
                case 'Yaw Only'
                    app.SetupStructure.HXRSS_MonoMode=2;
                case 'Pitch + Yaw'
                    app.SetupStructure.HXRSS_MonoMode=3;
            end
            app.SetupStructure.HXRSS_MonoMode_string=app.DiamondControl.Value;

            app.SetupStructure.AllowedEnergyRange(1)=str2num(app.MinEnergy2.Value); app.SetupStructure.AllowedEnergyRange(2)=str2num(app.MaxEnergy2.Value);
            app.SetupStructure.ListenPVAllowdRange{1}=app.SetupStructure.AllowedEnergyRange;
            app.SetupStructure.MovePV=app.SetDefault2.Value;
            app.SetupStructure.SteerFlat=app.SteerFlatAfterSetting2.Value;
            
            app.SetupStructure.CellSegmentInUse=[]; app.SetupStructure.IndexSegmentInUse=[]; app.SetupStructure.InUseUndulatorsK_ini=[]; app.SetupStructure.InUseUndulatorsK_end=[];
            for II=1:app.U.slotlength
                if((UseCellNumber && any(app.U.slotcell(II)==UndulatorRange)) || (~UseCellNumber && any(II==UndulatorRange)) )
                    if(app.U.slot(II).USEG.present)
                        app.SetupStructure.CellSegmentInUse(end+1)=app.U.slotcell(II);
                        app.SetupStructure.IndexSegmentInUse(end+1)=II;
                        app.SetupStructure.InUseUndulatorsK_ini(end+1)=app.SetupStructure.LineReadout(II).K;
                        app.SetupStructure.InUseUndulatorsK_end(end+1)=app.SetupStructure.LineReadout(II).Kend;
                    end
                end          
            end
            app.SetupStructure.Vernier=app.T3.Data{2,2}; 
            if(app.OverrideBunchenergywithknownphotonenergy3.Value)
                app.SetupStructure.Gamma= sqrt((app.KnownPhotonEnergy3.Value*app.U.Basic.Reference_lambda_u/1000*(1+app.SetupStructure.InUseUndulatorsK_ini(1)^2/2) )/2/(app.PhyConsts.hplanck*app.PhyConsts.c));
                app.SetupStructure.Energy= app.SetupStructure.Gamma*app.PhyConsts.mc2_e/10^9 -app.SetupStructure.Vernier/1000;
                app.T3.Data{1,2}=app.SetupStructure.Energy;
            else
                app.SetupStructure.Energy=app.T3.Data{1,2};
            end
            app.GO1.Enable='on';
            app.OffsetFromReferenceValue.Value='0';
            tabauto={};
            if(iscell(app.SetupStructure.ListenPV))
                for II=1:numel(app.SetupStructure.ListenPV)
                    tabauto{II,1}=app.SetupStructure.ListenPV{II};
                    tabauto{II,2}=app.SetupStructure.ListenPVAllowdRange{II}(1);
                    tabauto{II,3}=app.SetupStructure.ListenPVAllowdRange{II}(2);
                end
            else
                tabauto{1,1}=app.SetupStructure.ListenPV;
                tabauto{1,2}=app.SetupStructure.ListenPVAllowdRange{1}(1);
                tabauto{1,3}=app.SetupStructure.ListenPVAllowdRange{1}(2);
            end
            app.AutoScanRelative.Value=1;
            app.AutoScanTable.Data=tabauto; app.AutoScanTable.ColumnEditable=[false,true,true];
            PchipTable=app.LinearTable3.Data; MachineLine=PchipTable(:,1); ObservedLine=PchipTable(:,2);
            if(~app.Relative3.Value)
                MachineLine=MachineLine-app.SetupStructure.ReferenceValues;
                ObservedLine=ObservedLine-app.SetupStructure.ReferenceValues;
            end
            app.SetupStructure.DirectPchip=pchip(MachineLine,ObservedLine);
            app.SetupStructure.InversePchip=pchip(ObservedLine,MachineLine);
            app.AddMessage(app.MessageList,[datestr(now),' Setup finalized.'],50);
        end
        
        function FinalizeHXREnergyOnly(app)
            try app.SetupStructure=rmfield(app.SetupStructure,'ListenPV'); end
            try app.SetupStructure=rmfield(app.SetupStructure,'ReferencePV'); end
            app.AddMessage(app.MessageList,[datestr(now),' Finalizing Setup for HXR line'],50);
            UndulatorRange=str2num(app.UndulatorrangetobemovedEditField2.Value);
            UseCellNumber=app.UsecellCheckBox2.Value;
            app.SetupStructure.ModeOfChange=app.ControlMode2.Value;
            app.SetupStructure.Energy=app.T2.Data{2,2};
            app.AutoScanRelative.Value=1;
            app.SetupStructure.VernierFudge=app.VernierFudge2.Value;
            switch app.SetupStructure.ModeOfChange
                case app.ControlMode2.Items{1}
                    app.SetupStructure.MoveFunction=@app.MoveEnergyK;
                case app.ControlMode2.Items{2}
                    app.SetupStructure.MoveFunction=@app.MoveEnergyVernier;
                case app.ControlMode2.Items{3}
                    app.SetupStructure.MoveFunction=@app.MoveEnergyCombo;
                case app.ControlMode2.Items{4}
                    app.SetupStructure.MoveFunction=@app.LowLevelVernierAndK;
            end
            app.SetupStructure.SelfDestructionPV=app.Selfdestruction2.Value;
            CurrentSelfDestruction=lcaGetSmart(app.SetupStructure.SelfDestructionPV);
            LPV=app.RemoteControlEnergyPVEditField2.Value;
            VernierRange(1)=str2num(app.MinVernier2.Value);
            VernierRange(2)=str2num(app.MaxVernier2.Value);
            app.SetupStructure.VernierPV=app.U.Basic.Vernier;
            app.SetupStructure.VernierRange=VernierRange;
            if(~any(LPV==','))
                app.SetupStructure.ListenPV{1}=LPV;
            else
                Comma=find(LPV==','); app.SetupStructure.ListenPV={};
                for II=1:length(Comma)
                    PVName=regexprep(LPV(1:(Comma(II)-1)),' ','');
                    app.SetupStructure.ListenPV{end+1}=PVName;
                end
                PVName=regexprep(LPV((Comma(end)+1):end),' ','');
                app.SetupStructure.ListenPV{end+1}=PVName;
            end
            
            LPVREFERENCE=app.ReferenceControlEnergyPVEditField2.Value;
            if(~any(LPVREFERENCE==','))
                app.SetupStructure.ReferencePV{1}=LPVREFERENCE;
            else
                Comma=find(LPVREFERENCE==','); app.SetupStructure.ReferencePV={};
                for II=1:length(Comma)
                    PVName=regexprep(LPVREFERENCE(1:(Comma(II)-1)),' ','');
                    app.SetupStructure.ReferencePV{end+1}=PVName;
                end
                PVName=regexprep(LPVREFERENCE((Comma(end)+1):end),' ','');
                app.SetupStructure.ReferencePV{end+1}=PVName;
            end
            app.SetupStructure.ReferenceValues=lcaGetSmart(app.SetupStructure.ReferencePV);
            app.ReferenceValuesForEnergyLabel.Text=num2str(app.SetupStructure.ReferenceValues(:).');
            
            app.SetupStructure.AllowedEnergyRange(1)=str2num(app.MinEnergy2.Value); app.SetupStructure.AllowedEnergyRange(2)=str2num(app.MaxEnergy2.Value);
            app.SetupStructure.ListenPVAllowdRange{1}=app.SetupStructure.AllowedEnergyRange;
            app.SetupStructure.MovePV=app.SetDefault2.Value;
            app.SetupStructure.SteerFlat=app.SteerFlatAfterSetting2.Value;
            
            app.SetupStructure.CellSegmentInUse=[]; app.SetupStructure.IndexSegmentInUse=[]; app.SetupStructure.InUseUndulatorsK_ini=[]; app.SetupStructure.InUseUndulatorsK_end=[];
            for II=1:app.U.slotlength
                if((UseCellNumber && any(app.U.slotcell(II)==UndulatorRange)) || (~UseCellNumber && any(II==UndulatorRange)) )
                    if(app.U.slot(II).USEG.present)
                        app.SetupStructure.CellSegmentInUse(end+1)=app.U.slotcell(II);
                        app.SetupStructure.IndexSegmentInUse(end+1)=II;
                        app.SetupStructure.InUseUndulatorsK_ini(end+1)=app.SetupStructure.LineReadout(II).K;
                        app.SetupStructure.InUseUndulatorsK_end(end+1)=app.SetupStructure.LineReadout(II).Kend;
                    end
                end          
            end
            app.SetupStructure.Vernier=app.T2.Data{2,2};
            if(app.OverrideBunchenergywithknownphotonenergy2.Value)
                app.SetupStructure.Gamma= sqrt((app.KnownPhotonEnergy2.Value*app.U.Basic.Reference_lambda_u/1000*(1+app.SetupStructure.InUseUndulatorsK_ini(1)^2/2) )/2/(app.PhyConsts.hplanck*app.PhyConsts.c));
                app.SetupStructure.Energy= app.SetupStructure.Gamma*app.PhyConsts.mc2_e/10^9 -app.SetupStructure.Vernier/1000;
                app.T2.Data{1,2}=app.SetupStructure.Energy ;
            else
                app.SetupStructure.Energy=app.T2.Data{1,2};
            end
            
            app.GO1.Enable='on';
            app.OffsetFromReferenceValue.Value='0';
            tabauto={};
            for II=1:numel(app.SetupStructure.ListenPV)
                tabauto{II,1}=app.SetupStructure.ListenPV{II};
                tabauto{II,2}=app.SetupStructure.ListenPVAllowdRange{II}(1);
                tabauto{II,3}=app.SetupStructure.ListenPVAllowdRange{II}(2);
            end
            app.AutoScanTable.Data=tabauto; app.AutoScanTable.ColumnEditable=[false,true,true];
            PchipTable=app.LinearTable2.Data; MachineLine=PchipTable(:,1); ObservedLine=PchipTable(:,2);
            if(~app.Relative2.Value)
                MachineLine=MachineLine-app.SetupStructure.ReferenceValues;
                ObservedLine=ObservedLine-app.SetupStructure.ReferenceValues;
            end
            app.SetupStructure.DirectPchip=pchip(MachineLine,ObservedLine);
            app.SetupStructure.InversePchip=pchip(ObservedLine,MachineLine);
            app.AddMessage(app.MessageList,[datestr(now),' Setup finalized.'],50);
        end
        
        function FinalizeSXREnergyOnly(app)
            app.AddMessage(app.MessageList,[datestr(now),' Finalizing Setup for SXR line'],50);
            try app.SetupStructure=rmfield(app.SetupStructure,'ListenPV'); end
            try app.SetupStructure=rmfield(app.SetupStructure,'ReferencePV'); end
            UndulatorRange=str2num(app.UndulatorrangetobemovedEditField.Value);
            UseCellNumber=app.UsecellCheckBox.Value;
            app.SetupStructure.ModeOfChange=app.ControlMode1.Value;
            app.SetupStructure.Energy=app.T1.Data{2,2};
            app.SetupStructure.VernierFudge=app.VernierFudge1.Value;
            switch app.SetupStructure.ModeOfChange
                case app.ControlMode1.Items{1}
                    app.SetupStructure.MoveFunction=@app.MoveEnergyK;
                case app.ControlMode1.Items{2}
                    app.SetupStructure.MoveFunction=@app.MoveEnergyVernier;
                case app.ControlMode1.Items{3}
                    app.SetupStructure.MoveFunction=@app.MoveEnergyCombo;
                case app.ControlMode1.Items{4}
                    app.SetupStructure.MoveFunction=@app.LowLevelVernierAndK;
            end
            app.SetupStructure.SelfDestructionPV=app.Selfdestruction1.Value;
            CurrentSelfDestruction=lcaGetSmart(app.SetupStructure.SelfDestructionPV);
            VernierRange(1)=str2num(app.MinVernier1.Value);
            VernierRange(2)=str2num(app.MaxVernier1.Value);
            app.SetupStructure.VernierRange=VernierRange;
            app.SetupStructure.VernierPV=app.U.Basic.Vernier;
            app.AutoScanRelative.Value=1;
            LPV=app.RemoteControlEnergyPVEditField1.Value;
            if(~any(LPV==','))
                app.SetupStructure.ListenPV{1}=LPV;
            else
                Comma=find(LPV==','); app.SetupStructure.ListenPV={};
                for II=1:length(Comma)
                    PVName=regexprep(LPV(1:(Comma(II)-1)),' ','');
                    app.SetupStructure.ListenPV{end+1}=PVName;
                end
                PVName=regexprep(LPV((Comma(end)+1):end),' ','');
                app.SetupStructure.ListenPV{end+1}=PVName;
            end
            
            LPVREFERENCE=app.ReferenceControlEnergyPVEditField1.Value;
            if(~any(LPVREFERENCE==','))
                app.SetupStructure.ReferencePV{1}=LPVREFERENCE;
            else
                Comma=find(LPVREFERENCE==','); app.SetupStructure.ReferencePV={};
                for II=1:length(Comma)
                    PVName=regexprep(LPVREFERENCE(1:(Comma(II)-1)),' ','');
                    app.SetupStructure.ReferencePV{end+1}=PVName;
                end
                PVName=regexprep(LPVREFERENCE((Comma(end)+1):end),' ','');
                app.SetupStructure.ReferencePV{end+1}=PVName;
            end
            app.SetupStructure.ReferenceValues=lcaGetSmart(app.SetupStructure.ReferencePV);
            app.ReferenceValuesForEnergyLabel.Text=num2str(app.SetupStructure.ReferenceValues(:).');
            app.SetupStructure.AllowedEnergyRange(1)=str2num(app.MinEditField.Value); app.SetupStructure.AllowedEnergyRange(2)=str2num(app.MaxEditField.Value);
            app.SetupStructure.ListenPVAllowdRange{1}=app.SetupStructure.AllowedEnergyRange;
            app.SetupStructure.MovePV=app.SetDefault1.Value;
            app.SetupStructure.SteerFlat=app.SteerFlatAfterSetting1.Value;
            app.SetupStructure.CellSegmentInUse=[]; app.SetupStructure.IndexSegmentInUse=[]; app.SetupStructure.InUseUndulatorsK_ini=[]; app.SetupStructure.InUseUndulatorsK_end=[];

            for II=1:app.U.slotlength
                if((UseCellNumber && any(app.U.slotcell(II)==UndulatorRange)) || (~UseCellNumber && any(II==UndulatorRange)) )
                    if(app.U.slot(II).USEG.present)
                        app.SetupStructure.CellSegmentInUse(end+1)=app.U.slotcell(II);
                        app.SetupStructure.IndexSegmentInUse(end+1)=II;
                        app.SetupStructure.InUseUndulatorsK_ini(end+1)=app.SetupStructure.LineReadout(II).K;
                        app.SetupStructure.InUseUndulatorsK_end(end+1)=app.SetupStructure.LineReadout(II).Kend;
                    end
                end          
            end
            app.SetupStructure.Vernier=app.T1.Data{2,2};
            if(app.OverrideBunchenergywithknownphotonenergy1.Value)
                app.SetupStructure.Gamma= sqrt((app.KnownPhotonEnergy1.Value*app.U.Basic.Reference_lambda_u/1000*(1+app.SetupStructure.InUseUndulatorsK_ini(1)^2/2) )/2/(app.PhyConsts.hplanck*app.PhyConsts.c));
                app.SetupStructure.Energy= app.SetupStructure.Gamma*app.PhyConsts.mc2_e/10^9-app.SetupStructure.Vernier/1000;
                app.T1.Data{1,2}=app.SetupStructure.Energy;
            else
                app.SetupStructure.Energy=app.T1.Data{1,2};
            end
            app.GO1.Enable='on';
            app.OffsetFromReferenceValue.Value='0';
            tabauto={};
            if(~strcmp(app.SetupStructure.ModeOfChange,app.ControlMode1.Items{4}))
                for II=1:numel(app.SetupStructure.ListenPV)
                    tabauto{II,1}=app.SetupStructure.ListenPV{II};
                    tabauto{II,2}=app.SetupStructure.ListenPVAllowdRange{II}(1);
                    tabauto{II,3}=app.SetupStructure.ListenPVAllowdRange{II}(2);
                end
            else
                app.SetupStructure.ListenPVAllowdRange{2}=app.SetupStructure.ListenPVAllowdRange{1};
            end
            app.AutoScanTable.Data=tabauto; app.AutoScanTable.ColumnEditable=[false,true,true];
            PchipTable=app.LinearTable1.Data; MachineLine=PchipTable(:,1); ObservedLine=PchipTable(:,2);
            if(~app.Relative1.Value)
                MachineLine=MachineLine-app.SetupStructure.ReferenceValues;
                ObservedLine=ObservedLine-app.SetupStructure.ReferenceValues;
            end
            app.SetupStructure.DirectPchip=pchip(MachineLine,ObservedLine);
            app.SetupStructure.InversePchip=pchip(ObservedLine,MachineLine);
            app.AddMessage(app.MessageList,[datestr(now),' Setup finalized.'],50);
        end
        
        function ManageVisitiblityOfVernier2(app)
            switch(app.ControlMode2.Value)
                case app.ControlMode2.Items{3} 
                    app.MinVernier2.Visible='On'; app.MaxVernier2.Visible='On';app.AllowedVernierRangeMeVLabel_2.Visible='On';
                    app.MaxLabelGNU.Visible='On'; app.MinLabelGNU.Visible='On';
                    app.VernierToReference.Visible='On'; app.CalculateeVRangefromVernierextremes99Panel2.Visible='Off';
                case app.ControlMode2.Items{1} 
                    app.MinVernier2.Visible='Off'; app.MaxVernier2.Visible='Off';app.AllowedVernierRangeMeVLabel_2.Visible='Off';
                    app.MaxLabelGNU.Visible='Off'; app.MinLabelGNU.Visible='Off';
                    app.VernierToReference.Visible='Off'; app.CalculateeVRangefromVernierextremes99Panel2.Visible='Off';
                case app.ControlMode2.Items{2}
                    app.MinVernier2.Visible='Off'; app.MaxVernier2.Visible='Off';app.AllowedVernierRangeMeVLabel_2.Visible='Off';
                    app.MaxLabelGNU.Visible='Off'; app.MinLabelGNU.Visible='Off';
                    app.VernierToReference.Visible='Off'; app.CalculateeVRangefromVernierextremes99Panel2.Visible='On';
            end    
        end
        
        function ManageVisitiblityOfVernier(app)
            switch(app.ControlMode1.Value)
                case app.ControlMode1.Items{3} 
                    app.MaxEditField_2Label.Visible='On'; app.AllowedVernierRangeMeVLabel.Visible='On'; app.MinVernier1.Visible='On'; app.MaxVernier1.Visible='On'; app.MinEditField_2Label.Visible='On';
                    app.VernierToReference.Visible='On'; app.CalculateeVRangefromVernierextremes99Panel1.Visible='Off';
                case app.ControlMode1.Items{1} 
                    app.MaxEditField_2Label.Visible='Off'; app.AllowedVernierRangeMeVLabel.Visible='Off'; app.MinVernier1.Visible='Off'; app.MaxVernier1.Visible='Off'; app.MinEditField_2Label.Visible='Off';
                    app.VernierToReference.Visible='Off'; app.CalculateeVRangefromVernierextremes99Panel1.Visible='Off';
                case app.ControlMode1.Items{2}
                    app.MaxEditField_2Label.Visible='Off'; app.AllowedVernierRangeMeVLabel.Visible='Off'; app.MinVernier1.Visible='Off'; app.MaxVernier1.Visible='Off'; app.MinEditField_2Label.Visible='Off';
                    app.VernierToReference.Visible='Off'; app.CalculateeVRangefromVernierextremes99Panel1.Visible='On';
            end    
        end
        
        function StartListening(app)
            set(app.StartResetListeningtoremotecontrolPVsButton,'enable','off');
            set(app.STOPremotecontrolButton,'enable','on');
            
            try stop(app.TIMER); end %#ok<TRYNC> 
            try delete(app.TIMER); end %#ok<TRYNC> 
            
            PERIODO=app.pollingtimesEditField.Value;
            
            app.ControlMode1.Items={'Only K','Only Vernier','K and Vernier combo','Low-level Photon Side Control: Total + Vernier'};
            app.ControlMode2.Items={'Only K','Only Vernier','K and Vernier combo','Low-level Photon Side Control: Total + Vernier'};
            app.ControlMode3.Items={'Diamond Only','Diamond + K','Diamond + Vernier','Diamond + K + Vernier Combo'};
            
            switch app.SetupStructure.ModeOfChange
                case app.ControlMode1.Items{1} %'Only K'
                    Range{1}=app.SetupStructure.AllowedEnergyRange;
                case app.ControlMode1.Items{2} %'Only Vernier'
                    Range{1}=app.SetupStructure.AllowedEnergyRange;
                case app.ControlMode1.Items{3} %'K and Vernier combo'
                    Range{1}=app.SetupStructure.AllowedEnergyRange;
                case app.ControlMode1.Items{4} %'Low-level Photon Side Control: Total + Vernier'
                    Range{1}=app.SetupStructure.AllowedEnergyRange;
                    Range{2}=app.SetupStructure.AllowedEnergyRange;
                case app.ControlMode3.Items{1} % 'Diamond Only'
                    Range{1}=app.SetupStructure.AllowedEnergyRange;
                case app.ControlMode3.Items{2} % 'Diamond + K'
                    Range{1}=app.SetupStructure.AllowedEnergyRange;
                case app.ControlMode3.Items{3} % 'Diamond + Vernier'
                    Range{1}=app.SetupStructure.AllowedEnergyRange;
                case app.ControlMode3.Items{4} % 'Diamond + K + Vernier Combo'
                    Range{1}=app.SetupStructure.AllowedEnergyRange;
            end
            app.TIMER=timer('StartDelay', 0, 'Period', PERIODO, 'TasksToExecute', inf, 'ExecutionMode', 'fixedSpacing','Busymode','drop');
            app.TIMER.StartFcn = {@app.Timer_Update,2,Range};
            app.TIMER.StopFcn = {@app.Timer_Update,0};
            app.TIMER.TimerFcn = {@app.Timer_Update,1};
            app.TIMER.ErrorFcn = {@app.Timer_Update,-1};
            
            Seconds=app.pollingtimesEditField.Value;
            set(app.TIMER,'Period',Seconds);
            
            start(app.TIMER);
        end
        
        function ManageHXR2ColorSelfSeedingVisibility(app)
            if(app.Color1.Value)
                app.ReflectionhklLabel2.Visible='Off';
                app.RemoteControlEnergyPVEditField32.Visible='Off';
                app.MinEnergy32.Visible='Off'; app.MaxEnergy32.Visible='Off';
                app.h2.Visible='Off';app.hEditFieldLabel_2.Visible='Off';
                app.k2.Visible='Off';app.kEditFieldLabel_2.Visible='Off';
                app.l2.Visible='Off';app.lEditFieldLabel_2.Visible='Off';
                app.RemoteControlEnergyPVEditField_2Label_3.Visible='Off';
                %app.AllowedEnergyDeltaRangeLabel32.Visible='Off';
                app.ReferenceControlEnergyPVEditField32.Visible='Off';
                app.MinEditField_2Label_5.Visible='Off';
                app.MaxEnergy32.Visible='Off';
                app.MaxLabel_3.Visible='Off'; app.ReferenceEnergyPVLabel_3.Visible='Off';
            elseif(app.Colors2.Value)
                app.ReflectionhklLabel2.Visible='On';
                app.RemoteControlEnergyPVEditField32.Visible='On';
                app.MinEnergy32.Visible='On'; app.MinEnergy31.Visible='On';
                app.h2.Visible='On';app.hEditFieldLabel_2.Visible='On';
                app.k2.Visible='On';app.kEditFieldLabel_2.Visible='On';
                app.l2.Visible='On';app.lEditFieldLabel_2.Visible='On';
                app.RemoteControlEnergyPVEditField_2Label_3.Visible='On';
                %app.AllowedEnergyDeltaRangeLabel32.Visible='On';
                app.MinEditField_2Label_5.Visible='On';
                app.MaxLabel_3.Visible='On';
                app.MaxEnergy32.Visible='On';
                app.ReferenceControlEnergyPVEditField32.Visible='On';
                app.ReferenceEnergyPVLabel_3.Visible='On';
            end
        end
        
        function ManageRelativeInTable(app)
            if(app.AutoScanRelative.Value)
               for II=1:numel(app.SetupStructure.ListenPV)
                   app.AutoScanTable.Data(II,2)=app.AutoScanTable.Data(II,2)-app.SetupStructure.ReferenceValues(II);
                   app.AutoScanTable.Data(II,3)=app.AutoScanTable.Data(II,3)-app.SetupStructure.ReferenceValues(II);
               end
            else
                for II=1:numel(app.SetupStructure.ListenPV)
                   app.AutoScanTable.Data(II,2)=app.AutoScanTable.Data(II,2)+app.SetupStructure.ReferenceValues(II);
                   app.AutoScanTable.Data(II,3)=app.AutoScanTable.Data(II,3)+app.SetupStructure.ReferenceValues(II);
               end
            end
            
        end
            
        
        function StartAutomaticCycle(app)
            app.AutoString.Text='';
            DoSinglePass=app.SinglePass.Value;
            DoZigZag=app.ZigZag.Value;
            DoRelative=app.AutoScanRelative.Value;
            STEPS=app.StepsEditField.Value;
            Wait=app.waitaftersetsEditField.Value;
            TAB=app.AutoScanTable.Data;
            app.STOPButton.BackgroundColor=app.color.Idle; app.STOPButton.Enable='On';
            app.STARTButton.BackgroundColor=app.color.g; app.STARTButton.Enable='Off';
            PointsTable=zeros(numel(app.SetupStructure.ListenPV),STEPS);
            CurrentVal=lcaGetSmart(app.SelfDestructionPV);
            app.SetupStructure.SelfDestructionVal=CurrentVal+1;
            if(app.WritePVs.Value), lcaPutSmart(app.SetupStructure.SelfDestructionPV,app.SetupStructure.SelfDestructionVal); else, disp('Executing lcaPutSmart(app.SetupStructure.SelfDestructionPV,app.SetupStructure.SelfDestructionVal)'); end
            for II=1:numel(app.SetupStructure.ListenPV)
                PointsTable(II,:)=linspace(TAB{II,2},TAB{II,3},STEPS);
            end
            
            POSITION=1; DIREZIONE=1; 
            ContinueCondition=1;
            
            while(ContinueCondition)
                COLOR=app.STOPButton.BackgroundColor;
                if(all(COLOR==app.color.y))
                    app.AddMessage(app.MessageList,[datestr(now),' Automatic Scan stopped for user request.'],50);
                    app.STOPButton.BackgroundColor=app.color.Idle; app.STOPButton.Enable='Off';
                    app.STARTButton.BackgroundColor=app.color.Idle; app.STARTButton.Enable='On';
                    app.AutoString.Text='';
                    return
                end
                SelfDestruction=lcaGetSmart(app.SetupStructure.SelfDestructionPV);
                if(SelfDestruction~=app.SetupStructure.SelfDestructionVal)
                    app.AddMessage(app.MessageList,[datestr(now),' Self-Destruction of Auto-scan mode activated. ',app.SetupStructure.SelfDestructionPV,' must be ',num2str(app.SetupStructure.SelfDestructionVal), ' was ',num2str(SelfDestruction)],50);
                    app.STOPButton.BackgroundColor=app.color.Idle; app.STOPButton.Enable='Off';
                    app.STARTButton.BackgroundColor=app.color.Idle; app.STARTButton.Enable='On';
                    app.AutoString.Text='';
                    return
                end
                for II=1:numel(app.SetupStructure.ListenPV)
                    TAB{II,4}=PointsTable(II,POSITION);
                end
                Destination=PointsTable(:,POSITION);
                
                app.AutoScanTable.Data=TAB; app.AutoString.Text='Moving';
                app.AddMessage(app.MessageList,[datestr(now),' Moving To Target Position ',num2str(POSITION),'   Controls PV: ',num2str(Destination(:).')],50);
                if(app.WritePVs.Value), lcaPutSmart(app.SetupStructure.MovePV,0); else, disp('Executing lcaPutSmart(app.SetupStructure.MovePV,0)'); end
                if(~DoRelative)
                    TargetDestination=Destination-app.SetupStructure.ReferenceValues;
                    if(numel(Destination)==1)
                        app.OffsetFromReferenceValue.Value=num2str(Destination-app.SetupStructure.ReferenceValues,'%g');
                        app.OffsetFromReferenceValueABS.Value=num2str(Destination,'%g');
                    else
                        stringa=num2str(Destination,',%g');stringa(1)='[';stringa(end+1)=']';
                        app.OffsetFromReferenceValue.Value=stringa;
                    end
                else
                    TargetDestination=Destination;
                    if(numel(Destination)==1)
                        app.OffsetFromReferenceValue.Value=num2str(Destination,'%g');
                        app.OffsetFromReferenceValueABS.Value=num2str(Destination+app.SetupStructure.ReferenceValues,'%g');
                    else
                        stringa=num2str(Destination,',%g');stringa(1)='[';stringa(end+1)=']';
                        app.OffsetFromReferenceValue.Value=stringa;
                    end
                end
                if(~isempty(app.SetupStructure.InversePchip))
                    TargetDestination=ppval(app.SetupStructure.InversePchip,Destination);
                end
                
                if(app.WritePVs.Value), app.SetupStructure.MoveFunction(TargetDestination); else, disp('Executing: app.SetupStructure.MoveFunction(TargetDestination);'); end
                if(app.WritePVs.Value), lcaPutSmart(app.SetupStructure.MovePV,1); else, disp('Executing lcaPutSmart(app.SetupStructure.MovePV,1)'); end
                app.AddMessage(app.MessageList,[datestr(now),' Moving to Target Position completed '],50);
                COLOR=app.STOPButton.BackgroundColor;
                if(all(COLOR==app.color.y))
                    app.AddMessage(app.MessageList,[datestr(now),' Automatic Scan stopped for user request.'],50);
                    app.STOPButton.BackgroundColor=app.color.Idle; app.STOPButton.Enable='Off';
                    app.STARTButton.BackgroundColor=app.color.Idle; app.STARTButton.Enable='On';
                    app.AutoString.Text='';
                    return
                end
                SelfDestruction=lcaGetSmart(app.SetupStructure.SelfDestructionPV);
                if(SelfDestruction~=app.SetupStructure.SelfDestructionVal)
                    app.AddMessage(app.MessageList,[datestr(now),' Self-Destruction of Auto-scan mode activated. ',app.SetupStructure.SelfDestructionPV,' must be ',num2str(app.SetupStructure.SelfDestructionVal), ' was ',num2str(SelfDestruction)],50);
                    app.STOPButton.BackgroundColor=app.color.Idle; app.STOPButton.Enable='Off';
                    app.STARTButton.BackgroundColor=app.color.Idle; app.STARTButton.Enable='On';
                    app.AutoString.Text='';
                    return
                end
                app.AddMessage(app.MessageList,[datestr(now),' Waiting for ',num2str(Wait),' s before moving to next position.'],50);
                tic, b=toc;
                while(b<Wait)
                    app.AutoString.Text=['Waiting ',num2str(Wait-b),' more seconds']; drawnow;
                    pause(0.25);
                    b=toc;
                end
                if(DIREZIONE==1)
                    POSITION=POSITION+1;
                else
                    POSITION=POSITION-1;
                end
                if(POSITION>STEPS)
                    if(DoSinglePass && ~DoZigZag)
                        ContinueCondition=0;
                    elseif(~DoZigZag)
                        POSITION=1;
                    else
                        DIREZIONE=-1;
                        POSITION=STEPS-1;
                    end
                end
                if(POSITION==0)
                    if(DoZigZag && DoSinglePass)
                        ContinueCondition=0;
                    end
                    POSITION=2;
                    DIREZIONE=1;
                end
            end
            app.AddMessage(app.MessageList,[datestr(now),' Automatic Scan pattern finished normally.'],50);
            app.STOPButton.BackgroundColor=app.color.Idle; app.STOPButton.Enable='Off';
            app.STARTButton.BackgroundColor=app.color.Idle; app.STARTButton.Enable='On';
            app.AutoString.Text='';
        end
        
        function SaveReference(app,caller)
            CurrentTime=datevec(now);
            CurrentYearString=num2str(CurrentTime(1),'%.4d'); CurrentMonthString=num2str(CurrentTime(2),'%.2d');
            CurrentDayString=num2str(CurrentTime(3),'%.2d'); CurrentTempo1String=num2str(CurrentTime(4),'%.2d');
            CurrentTempo2String=num2str(CurrentTime(5),'%.2d'); CurrentTempo3String=num2str(floor(CurrentTime(6)),'%.2d');
            CurrentTempo4String=num2str(round((CurrentTime(6)-floor(CurrentTime(6)))*1000),'%.3d');
            CurrentTimeString=[CurrentYearString,'-',CurrentMonthString,'-',CurrentDayString,'--',CurrentTempo1String,'-',CurrentTempo2String,'-',CurrentTempo3String,'-',CurrentTempo4String];
            switch(caller)
                case 1
                    Tabula=app.T1.Data;
                    SS=app.SetupStructure;
                    save([app.StoredConfFolder,'/','LastSXR'],'Tabula','SS');
                    save([app.StoredConfFolder,'/','Conf_SXR_',CurrentTimeString],'Tabula','SS');
                case 2
                    Tabula=app.T2.Data;
                    SS=app.SetupStructure;
                    save([app.StoredConfFolder,'/','LastHXR'],'Tabula','SS');
                    save([app.StoredConfFolder,'/','Conf_HXR_',CurrentTimeString],'Tabula','SS');
                case 3
                    Tabula=app.T3.Data;
                    SS=app.SetupStructure;
                    save([app.StoredConfFolder,'/','LastSeedingHXR'],'Tabula','SS');
                    save([app.StoredConfFolder,'/','Conf_SeedingHXR_',CurrentTimeString],'Tabula','SS');
            end
        end
        
        function RestoreLastReference(app,caller)
            switch(caller)
                case 1
                    load([app.StoredConfFolder,'/','LastSXR'],'Tabula','SS');
                    app.T1.Data=Tabula;
                case 2
                    load([app.StoredConfFolder,'/','LastHXR'],'Tabula','SS');
                    app.T2.Data=Tabula;
                case 3
                    load([app.StoredConfFolder,'/','LastSeedingHXR'],'Tabula','SS');
                    app.T3.Data=Tabula;
            end
            app.SetupStructure=SS;
            app.(['T',num2str(caller)]).ColumnEditable=[false,true,true];
            Table=[0,0;0.001,0.001;-0.001,-0.001];
            app.(['LinearTable',num2str(caller)]).Data=Table;
            app.(['LinearTable',num2str(caller)]).ColumnName={'Machine','Observed'};
            app.(['LinearTable',num2str(caller)]).ColumnEditable=[true,true];
            app.(['FinalizeSetupPanel',num2str(caller)]).Visible='On';
            app.RemoteControlandReadoutPanel.Visible='Off';
            app.AutomaticScanPanel.Visible='Off';
        end
        
        function RestoreOlderReference(app,caller)
            switch(caller)
                case 1
                    [filename,filepath]=uigetfile([app.StoredConfFolder,'/','Conf_SXR_*.mat']);
                    load([filepath,'/',filename],'Tabula','SS');
                    app.T1.Data=Tabula;
                case 2
                    [filename,filepath]=uigetfile([app.StoredConfFolder,'/','Conf_HXR_*.mat']);
                    load([filepath,'/',filename],'Tabula','SS');
                    app.T2.Data=Tabula;
                case 3
                    [filename,filepath]=uigetfile([app.StoredConfFolder,'/','Conf_SeedingHXR_*.mat']);
                    load([filepath,'/',filename],'Tabula','SS');
                    app.T3.Data=Tabula;
            end
            app.SetupStructure=SS;
            app.(['T',num2str(caller)]).ColumnEditable=[false,true,true];
            Table=[0,0;0.001,0.001;-0.001,-0.001];
            app.(['LinearTable',num2str(caller)]).Data=Table;
            app.(['LinearTable',num2str(caller)]).ColumnName={'Machine','Observed'};
            app.(['LinearTable',num2str(caller)]).ColumnEditable=[true,true];
            app.(['FinalizeSetupPanel',num2str(caller)]).Visible='On';
            app.RemoteControlandReadoutPanel.Visible='Off';
            app.AutomaticScanPanel.Visible='Off';
        end
        
        function AddMessage(app,List,String,MaxMessages)
            Messaggi=get(List,'Items');
            NumeroMessaggi=numel(Messaggi);
            if(NumeroMessaggi>=MaxMessages)
                Messaggi(2:end)=Messaggi(1:(end-1));
                Messaggi{1}=String;
            else
                Messaggi(2:end+1)=Messaggi(1:end);
                Messaggi{1}=String;
            end
            List.Items=Messaggi;
            List.Value=List.Items{1};
        end
        
        function ManageTabChange(app)
            app.SetupStructure=struct();
            try %#ok<TRYNC> 
                SelfDestruction=lcaGetSmart(app.SetupStructure.SelfDestructionPV);
                if(app.WritePVs.Value), lcaPutSmart(app.SetupStructure.SelfDestructionPV,SelfDestruction+1); else, disp('Executing lcaPutSmart(app.SetupStructure.SelfDestructionPV,SelfDestruction+1);'); end
            end
            selectedTab = app.TabGroup.SelectedTab;
            app.ManualSetpointControl.Visible='Off';
            app.RemoteControlandReadoutPanel.Visible='Off';
            app.AutomaticScanPanel.Visible='Off';
            switch(selectedTab.Title)
                case 'HXR Line Energy Control'
                    app.U=app.UL(1);
                    app.U.Basic.Vernier='FBCK:FB04:LG01:DL2VERNIER';
                    app.s=app.static(1);
                    app.ManageVisitiblityOfVernier();
                    app.FinalizeSetupPanel2.Visible='Off';
                    app.GO1.Enable='Off';
                    app.T2.Data={};
                case 'SXR Line Energy Control'
                    app.U=app.UL(2);
                    app.U.Basic.Vernier='FBCK:FB04:LG01:DL2VERNIER_SXR';
                    app.s=app.static(2);
                    app.RestoreReference1.Enable='Off';
                    app.GO1.Enable='Off';
                    app.FinalizeSetupPanel1.Visible='Off';
                    app.T1.Data={};
                case 'HXRSS Self-Seeding'
                    app.U=app.UL(1);
                    app.U.Basic.Vernier='FBCK:FB04:LG01:DL2VERNIER';
                    app.s=app.static(1);
                    app.FinalizeSetupPanel3.Visible='Off';
                    app.GO1.Enable='Off';
                    app.T3.Data={};
                case 'SXRSS Self-Seeding'
                    app.U=app.UL(2);
                    app.U.Basic.Vernier='FBCK:FB04:LG01:DL2VERNIER_SXR';
                    app.s=app.static(2);
                    app.FinalizeSetupPanel3.Visible='Off';
                    app.GO1.Enable='Off';
                    app.T4.Data={};
            end
        end
        

        function GetReferenceUL(app,caller)
            switch caller
                case 1
                    Energy=lcaGetSmart(app.U.Basic.EBeamEnergyPV);
                    Vernier=lcaGetSmart(app.U.Basic.Vernier);
                    LineReadout=app.U.f.Read_all_K_values(app.U);
                case 2
                    Energy=lcaGetSmart(app.U.Basic.EBeamEnergyPV);
                    Vernier=lcaGetSmart(app.U.Basic.Vernier);
                    LineReadout=app.U.f.Read_all_K_values(app.U);
                case 3
                    app.ManageHXR2ColorSelfSeedingVisibility();
                    Energy=lcaGetSmart(app.U.Basic.EBeamEnergyPV);
                    Vernier=lcaGetSmart(app.U.Basic.Vernier);
                    HXRSSStatus=app.HXRSS.StatusGet(app.HXRSS.CrystalNames);
                    Pitch=NaN;
                    Yaw=NaN;
                    HXRSSName='No Crystal Inserted';
                    CrystalID=NaN;
                    for II=1:numel(HXRSSStatus)
                        if(HXRSSStatus(II).IN)
                            Pitch=HXRSSStatus.Pitch;
                            Yaw=HXRSSStatus.Yaw;
                            HXRSSName=app.HXRSS.CrystalNames{II};
                            CrystalID=II;
                        end
                    end
                    LineReadout=app.U.f.Read_all_K_values(app.U);
            end
            app.RemoteControlandReadoutPanel.Visible='Off';
            app.AutomaticScanPanel.Visible='Off';
            Table{1,1}='Energy [GeV]'; Table{1,2}=Energy;
            Table{2,1}='Vernier [MeV]'; Table{2,2}=Vernier;
            app.SetupStructure.Energy=Energy;
            app.SetupStructure.Vernier=Vernier;
            app.SetupStructure.LineReadout=LineReadout;
            app.SetupStructure.Undulators=[];
            app.SetupStructure.UndulatorsK_ini=[];
            app.SetupStructure.UndulatorsK_end=[];
            for II=1:length(app.U.slot)
                if(app.U.slot(II).USEG.present)
                    app.SetupStructure.Undulators(end+1)=II;
                    app.SetupStructure.UndulatorsK_ini(end+1)=LineReadout(II).K;
                    app.SetupStructure.UndulatorsK_end(end+1)=LineReadout(II).Kend;
                    Table{2+II,2}=LineReadout(II).K;
                    Table{2+II,3}=LineReadout(II).Kend;
                    Table{2+II,1}=['Und#/Cell# = ',num2str(II),',',app.U.slot(II).USEG.Cell_String,''];
                else
                    Table{2+II,2}=NaN;
                    Table{2+II,3}=NaN;
                    Table{2+II,1}='No segment here';
                end
            end
            switch caller
                case 1
                    app.FinalizeSetupPanel1.Visible='On';
                case 2
                    app.FinalizeSetupPanel2.Visible='On';
                case 3
                    app.FinalizeSetupPanel3.Visible='On';
                    Table{2+II+1,1}=HXRSSName; Table{2+II+1,2}=CrystalID;
                    Table{2+II+2,1}='Pitch'; Table{2+II+2,2}=Pitch;
                    Table{2+II+3,1}='Yaw'; Table{2+II+3,2}=Yaw;
                    app.SetupStructure.CrystalName=HXRSSName;
                    app.SetupStructure.Pitch=Pitch;
                    app.SetupStructure.Yaw=Yaw;
            end
            app.(['T',num2str(caller)]).Data=Table;
            app.(['T',num2str(caller)]).ColumnEditable=[false,true,true];
            Table=[0,0;0.001,0.001;-0.001,-0.001];
            app.(['LinearTable',num2str(caller)]).Data=Table;
            app.(['LinearTable',num2str(caller)]).ColumnName={'Machine','Observed'};
            app.(['LinearTable',num2str(caller)]).ColumnEditable=[true,true];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load([app.ULDir,'/',app.ULFile],'UL','static');
            app.fh=ULT_UndulatorLine_functions();
            app.sh=Steering_Functions();
            app.color.y=[1,1,0]; app.color.idle=get(app.FinalizethisSetupconditionButton,'backgroundcolor'); app.color.g=[0,1,0]; app.color.r=[1,0,0]; app.color.Idle=app.color.idle;
            app.SelfDestructionPV=app.Selfdestruction1.Value;
            app.UL=UL; %#ok<ADPROP> 
            app.static=static; %#ok<ADPROP> 
            app.CD=CDO_Class;
            app.mh=HXRSS_Motion_functions();  
            load([app.HXRSSSaveFileFolder,'/',app.HXRSSStoredMachinesFile],'Machine')
            app.AMGP=Machine;
            app.HXRSS.CrystalNames={app.AMGP(1).CD.name};
            app.HXRSS.PitchSet=app.mh.(app.AMGP(1).SetPitch);
            app.HXRSS.YawSet=app.mh.(app.AMGP(1).SetYaw);
            app.HXRSS.PitchGet=app.mh.(app.AMGP(1).GetPitch);
            app.HXRSS.YawGet=app.mh.(app.AMGP(1).GetYaw);
            app.HXRSS.StatusGet=app.mh.(app.AMGP(1).GetStatus);
            app.HXRSS.InOutSet=app.mh.(app.AMGP(1).SetInOut);
            app.HXRSS.PVsGet=app.mh.(app.AMGP(1).GetPVs);
            app.PhyConsts.c=299792458;
            app.PhyConsts.mc2_e=5.109989180000000e+05;
            app.PhyConsts.echarge=1.602176530000000e-19;
            app.PhyConsts.mu_0=1.256637061435917e-06;
            app.PhyConsts.eps_0=8.854187817620391e-12;
            app.PhyConsts.r_e=2.817940318198647e-15;
            app.PhyConsts.Z_0=3.767303134617707e+02;
            app.PhyConsts.h_bar=1.054571682364455e-34; %J s
            app.PhyConsts.alpha=0.007297352554051;
            app.PhyConsts.Avogadro=6.022141500000000e+23;
            app.PhyConsts.k_Boltzmann=1.380650500000000e-23;
            app.PhyConsts.Stefan_Boltzmann=5.670401243654186e-08;
            app.PhyConsts.hplanck=4.135667516*10^-15;
            EndStationsS={'RIX','TMO','MCC'};
            EndStationsH={'XPP','CXI','MEC','MCC'};
            app.Endstation1.Items=EndStationsS;
            app.Endstation2.Items=EndStationsH;
            app.Endstation3.Items=EndStationsH;
            app.ControlMode1.Items={'Only K','Only Vernier','K and Vernier combo','Low-level Photon Side Control: Total + Vernier'};
            app.ControlMode2.Items={'Only K','Only Vernier','K and Vernier combo','Low-level Photon Side Control: Total + Vernier'};
            app.ControlMode3.Items={'Diamond Only','Diamond + K','Diamond + Vernier','Diamond + K + Vernier Combo'};
            app.Selfdestruction1.Items={'SIOC:SYS0:ML07:AO777','SIOC:SYS0:ML07:AO778','SIOC:SYS0:ML07:AO779','SIOC:SYS0:ML07:AO780'};
            app.Selfdestruction2.Items={'SIOC:SYS0:ML07:AO781','SIOC:SYS0:ML07:AO782','SIOC:SYS0:ML07:AO783','SIOC:SYS0:ML07:AO784'};
            app.Selfdestruction3.Items={'SIOC:SYS0:ML07:AO785','SIOC:SYS0:ML07:AO786','SIOC:SYS0:ML07:AO787','SIOC:SYS0:ML07:AO788'};
            app.Selfdestruction4.Items={'SIOC:SYS0:ML07:AO789','SIOC:SYS0:ML07:AO790','SIOC:SYS0:ML07:AO791','SIOC:SYS0:ML07:AO792'};
            app.SetDefault1.Items={'SIOC:SYS0:ML07:AO801','SIOC:SYS0:ML07:AO802','SIOC:SYS0:ML07:AO803','SIOC:SYS0:ML07:AO804'};
            app.SetDefault2.Items={'SIOC:SYS0:ML07:AO805','SIOC:SYS0:ML07:AO806','SIOC:SYS0:ML07:AO807','SIOC:SYS0:ML07:AO808'};
            app.SetDefault3.Items={'SIOC:SYS0:ML07:AO809','SIOC:SYS0:ML07:AO810','SIOC:SYS0:ML07:AO811','SIOC:SYS0:ML07:AO812'};
            app.SetDefault4.Items={'SIOC:SYS0:ML07:AO813','SIOC:SYS0:ML07:AO814','SIOC:SYS0:ML07:AO815','SIOC:SYS0:ML07:AO816'};
            % Update app structure
            Nunc=now;
            ManageTabChange(app);
            set(app.MessageList,'Items',{'Interface setting up ..........','....... . ..... ....... . . ..... ....... . . ..... ....... .'})
            Date_String=['AD ',datestr(Nunc,'yyyy'), datestr(Nunc, ' dddd mmmm dd  HH:MM:SS.FFF')];
            app.AddMessage(app.MessageList,[Date_String,' Scan GUI Started. '],50);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.ManageTabChange();
        end

        % Button pushed function: GetReference1
        function GetReference1ButtonPushed(app, event)
            app.GetReferenceUL(1);
            app.ManageVisitiblityOfVernier();
            app.ManageEndStation(1);
            app.SaveReference(1);
        end

        % Button pushed function: GetReference2
        function GetReference2ButtonPushed(app, event)
            app.GetReferenceUL(2);
            app.ManageVisitiblityOfVernier2();
            app.ManageEndStation(2);
            app.SaveReference(2);
        end

        % Button pushed function: GetReference3
        function GetReference3ButtonPushed(app, event)
            app.GetReferenceUL(3);
            app.ManageEndStation(3);
            app.SaveReference(3);
        end

        % Button pushed function: FinalizethisSetupconditionButton
        function FinalizethisSetupconditionButtonPushed(app, event)
            app.SetupStructure.InversePchip=[]; app.SetupStructure.DirectPchip=[];
            app.FinalizeSXREnergyOnly();
            app.RestoreReference1.Enable='On';
            app.RemoteControlandReadoutPanel.Visible='On';
            app.AutomaticScanPanel.Visible='On';
            app.ManualSetpointControl.Visible='On';
        end

        % Value changed function: ControlMode1
        function ControlMode1ValueChanged(app, event)
            app.ManageVisitiblityOfVernier();
            switch (app.ControlMode1.Value)
                case 'Low-level Photon Side Control: Total + Vernier'
                    app.RemoteControlEnergyPVEditField1.Value='***:USER:MCC:EPHOTK:UND, ***:USER:MCC:EPHOTK:VER';
                    app.ManageEndStation(1);
                otherwise
                    app.RemoteControlEnergyPVEditField1.Value='***:USER:MCC:EPHOTK';
                    app.ManageEndStation(1);
            end
        end

        % Button pushed function: VernierToReference
        function VernierToReferenceButtonPushed(app, event)
            if(app.WritePVs.Value)
                lcaPutSmart(app.SetupStructure.VernierPV,app.SetupStructure.Vernier);
            else
                disp('Executing lcaPutSmart(app.SetupStructure.VernierPV,app.SetupStructure.Vernier)');
            end
            Target=str2num(app.OffsetFromReferenceValue.Value);
            app.MoveEnergyK(Target,[1,5,7,1,4,3,1]);
        end

        % Button pushed function: GO1
        function GO1ButtonPushed(app, event)
            NominalTarget=str2num(app.OffsetFromReferenceValue.Value);
            if(~isempty(app.SetupStructure.InversePchip))
                Target=ppval(app.SetupStructure.InversePchip,NominalTarget);
            else
                Target=NominalTarget;
            end
            if(app.WritePVs.Value), app.SetupStructure.MoveFunction(Target); else, disp('Executing: app.SetupStructure.MoveFunction(Target);'); end 
        end

        % Button pushed function: RestoreReference1
        function RestoreReference1ButtonPushed(app, event)
            if(app.WritePVs.Value)
                lcaPutSmart(app.SetupStructure.VernierPV,app.SetupStructure.Vernier);
            else
                disp('Executing lcaPutSmart(app.SetupStructure.VernierPV,app.SetupStructure.Vernier)')
            end
            Target=0; 
            if(app.WritePVs.Value), lcaPutSmart(app.SetupStructure.VernierPV,app.SetupStructure.Vernier); app.SetupStructure.MoveFunction(Target,[1,5,7,1,4,3,1]); else, disp('Executing: app.SetupStructure.MoveFunction(Target);'); end
        end

        % Button pushed function: 
        % StartResetListeningtoremotecontrolPVsButton
        function StartResetListeningtoremotecontrolPVsButtonPushed(app, event)
            app.StartListening();
        end

        % Button pushed function: EnableAll
        function EnableAllButtonPushed(app, event)
            app.StartResetListeningtoremotecontrolPVsButton.Enable='On';
        end

        % Button pushed function: EnableAll_2
        function EnableAll_2ButtonPushed(app, event)
            app.STARTButton.Enable='On';
        end

        % Button pushed function: EnableAll_3
        function EnableAll_3ButtonPushed(app, event)
            app.Timer_Update([],[],1)
        end

        % Button pushed function: STOPremotecontrolButton
        function STOPremotecontrolButtonPushed(app, event)
            set(app.StartResetListeningtoremotecontrolPVsButton,'enable','on')
            set(app.STOPremotecontrolButton,'enable','off')
            try stop(app.TIMER); end %#ok<TRYNC> 
            try delete(app.TIMER); end %#ok<TRYNC> 
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.StartAutomaticCycle();
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.STOPButtonPushed.BackgroundColor=app.color.y; drawnow;
        end

        % Button pushed function: FinalizethisSetupconditionButton2
        function FinalizethisSetupconditionButton2Pushed(app, event)
            app.SetupStructure.InversePchip=[]; app.SetupStructure.DirectPchip=[];
            app.FinalizeHXREnergyOnly();
            app.RestoreReference1.Enable='On';
            app.RemoteControlandReadoutPanel.Visible='On';
            app.AutomaticScanPanel.Visible='On';
            app.ManualSetpointControl.Visible='On';
        end

        % Value changed function: Selfdestruction2
        function Selfdestruction2ValueChanged(app, event)
            app.SetDefault2.Value=app.SetDefault2.Items{strcmp(app.Selfdestruction2.Items,app.Selfdestruction2.Value)};
        end

        % Value changed function: Selfdestruction1
        function Selfdestruction1ValueChanged(app, event)
            app.SetDefault1.Value=app.SetDefault1.Items{strcmp(app.Selfdestruction1.Items,app.Selfdestruction1.Value)};
        end

        % Value changed function: Selfdestruction3
        function Selfdestruction3ValueChanged(app, event)
            app.SetDefault3.Value=app.SetDefault3.Items{strcmp(app.Selfdestruction3.Items,app.Selfdestruction3.Value)};
        end

        % Value changed function: Selfdestruction4
        function Selfdestruction4ValueChanged(app, event)
            app.SetDefault4.Value=app.SetDefault4.Items{strcmp(app.Selfdestruction4.Items,app.Selfdestruction4.Value)};
        end

        % Value changed function: SetDefault1
        function SetDefault1ValueChanged(app, event)
            app.Selfdestruction1.Value=app.Selfdestruction1.Items{strcmp(app.SetDefault1.Items,app.SetDefault1.Value)};
        end

        % Value changed function: SetDefault2
        function SetDefault2ValueChanged(app, event)
            app.Selfdestruction2.Value=app.Selfdestruction2.Items{strcmp(app.SetDefault2.Items,app.SetDefault2.Value)};
        end

        % Value changed function: SetDefault3
        function SetDefault3ValueChanged(app, event)
            app.Selfdestruction3.Value=app.Selfdestruction3.Items{strcmp(app.SetDefault3.Items,app.SetDefault3.Value)};
        end

        % Value changed function: SetDefault4
        function SetDefault4ValueChanged(app, event)
            app.Selfdestruction4.Value=app.Selfdestruction4.Items{strcmp(app.SetDefault4.Items,app.SetDefault4.Value)};
        end

        % Value changed function: Color1
        function Color1ValueChanged(app, event)
            app.Color1.Value=1;
            app.Colors2.Value=0;
            app.ManageHXR2ColorSelfSeedingVisibility();
            app.DiamondControl.Enable='On';
        end

        % Value changed function: Colors2
        function Colors2ValueChanged(app, event)
            app.Color1.Value=0;
            app.Colors2.Value=1;
            app.ManageHXR2ColorSelfSeedingVisibility();
            app.DiamondControl.Enable='Off';
            app.DiamondControl.Value=app.DiamondControl.Items{3};
        end

        % Button pushed function: FinalizethisSetupconditionButton3
        function FinalizethisSetupconditionButton3Pushed(app, event)
            app.SetupStructure.InversePchip=[]; app.SetupStructure.DirectPchip=[];
            app.FinalizeHXRSSEnergyOnly();
            app.RestoreReference1.Enable='On';
            app.RemoteControlandReadoutPanel.Visible='On';
            app.AutomaticScanPanel.Visible='On';
            app.ManualSetpointControl.Visible='On';
        end

        % Value changed function: Endstation1
        function Endstation1ValueChanged(app, event)
            app.ManageEndStation(1);
        end

        % Value changed function: Endstation2
        function Endstation2ValueChanged(app, event)
            app.ManageEndStation(2);
        end

        % Value changed function: Endstation3
        function Endstation3ValueChanged(app, event)
            app.ManageEndStation(3);
        end

        % Value changed function: ControlMode2
        function ControlMode2ValueChanged(app, event)
            app.ManageVisitiblityOfVernier2();
            switch (app.ControlMode2.Value)
                case 'Low-level Photon Side Control: Total + Vernier'
                    app.RemoteControlEnergyPVEditField2.Value='***:USER:MCC:EPHOTK:UND, ***:USER:MCC:EPHOTK:VER';
                    app.ManageEndStation(2);
                otherwise
                    app.RemoteControlEnergyPVEditField2.Value='***:USER:MCC:EPHOTK';
                    app.ManageEndStation(2);
            end
        end

        % Button pushed function: CalculateeVRangeButton_2
        function CalculateeVRangeButton_2Pushed(app, event)
            VernierRange=[app.MinVernierEditField_2.Value,app.MaxVernierEditField_2.Value];
            VernierFudge=app.VernierFudge2.Value;
            VernierRangeFudged= (VernierRange - app.SetupStructure.Vernier*(1-VernierFudge))./VernierFudge;
            UndulatorRange=str2num(app.UndulatorrangetobemovedEditField2.Value);
            UseCellNumber=app.UsecellCheckBox2.Value;
            EvRange=app.EvaluateEnergyRange(VernierRangeFudged,UndulatorRange,UseCellNumber);
            app.MinEnergy2.Value=num2str(EvRange(1)*0.99); app.MaxEnergy2.Value=num2str(EvRange(2)*0.99);
        end

        % Button pushed function: CalculateeVRangeButton
        function CalculateeVRangeButtonPushed(app, event)
            VernierRange=[app.MinVernierEditField.Value,app.MaxVernierEditField.Value];
            VernierFudge=app.VernierFudge1.Value;
            VernierRangeFudged= (VernierRange - app.SetupStructure.Vernier*(1-VernierFudge))./VernierFudge;
            UndulatorRange=str2num(app.UndulatorrangetobemovedEditField.Value);
            UseCellNumber=app.UsecellCheckBox.Value;
            EvRange=app.EvaluateEnergyRange(VernierRangeFudged,UndulatorRange,UseCellNumber);
            app.MinEditField.Value=num2str(EvRange(1)*0.99); app.MaxEditField.Value=num2str(EvRange(2)*0.99);
        end

        % Button pushed function: RowsButton1
        function RowsButton1Pushed(app, event)
            app.LinearTable1.Data(end+1,1)=0; app.LinearTable1.Data(end,2)=0;
        end

        % Button pushed function: GO_ABS
        function GO_ABSButtonPushed(app, event)
            NominalTarget=str2num(app.OffsetFromReferenceValueABS.Value);
            NominalTarget=NominalTarget-app.SetupStructure.ReferenceValues;
            if(~isempty(app.SetupStructure.InversePchip))
                Target=ppval(app.SetupStructure.InversePchip,NominalTarget);
            else
                Target=NominalTarget;
            end
            if(app.WritePVs.Value), app.SetupStructure.MoveFunction(Target); else, disp('Executing: app.SetupStructure.MoveFunction(Target);'); end
        end

        % Button pushed function: RowsButton2
        function RowsButton2Pushed(app, event)
            app.LinearTable2.Data(end+1,1)=0; app.LinearTable2.Data(end,2)=0;
        end

        % Button pushed function: RowsButton3
        function RowsButton3Pushed(app, event)
            app.LinearTable3.Data(end+1,1)=0; app.LinearTable3.Data(end,2)=0;
        end

        % Value changed function: OffsetFromReferenceValueABS
        function OffsetFromReferenceValueABSValueChanged(app, event)
            value = app.OffsetFromReferenceValueABS.Value;
            
        end

        % Button pushed function: ReadfromreferenceButton1
        function ReadfromreferenceButton1Pushed(app, event)
            app.ReadPhotonEnergy(1);
        end

        % Button pushed function: ReadfromreferenceButton2
        function ReadfromreferenceButton2Pushed(app, event)
            app.ReadPhotonEnergy(2);
        end

        % Button pushed function: ReadfromreferenceButton3
        function ReadfromreferenceButton3Pushed(app, event)
            app.ReadPhotonEnergy(3);
        end

        % Value changed function: AutoScanRelative
        function AutoScanRelativeValueChanged(app, event)
            app.ManageRelativeInTable();
        end

        % Button pushed function: RestoreLastButton
        function RestoreLastButtonPushed(app, event)
            app.RestoreLastReference(1);
        end

        % Button pushed function: RestoreOldButton
        function RestoreOldButtonPushed(app, event)
            app.RestoreOlderReference(1);
        end

        % Button pushed function: RestoreLastButton2
        function RestoreLastButton2Pushed(app, event)
           app.RestoreLastReference(2); 
        end

        % Button pushed function: RestoreOldButton2
        function RestoreOldButton2Pushed(app, event)
            app.RestoreOlderReference(2);
        end

        % Button pushed function: RestoreLastButton3
        function RestoreLastButton3Pushed(app, event)
            app.RestoreLastReference(3); 
        end

        % Button pushed function: RestoreOldButton3
        function RestoreOldButton3Pushed(app, event)
            app.RestoreOlderReference(3);
        end

        % Button pushed function: EvalStateButton
        function EvalStateButtonPushed(app, event)
            app.EvalStateFunc();
        end

        % Button pushed function: Energychange_F
        function Energychange_FButtonPushed(app, event)
            NewValue=str2num(app.OffsetFromReferenceValue.Value)+app.F_amount.Value;
            if(length(NewValue)==1)
                app.OffsetFromReferenceValue.Value=num2str(NewValue,'%14.14g');
            else
                NV='[';
                for II=1:length(NewValue)
                    NV=[NV,num2str(NewValue,'%14.14g'),','];
                end
                NV(end)=']';
                app.OffsetFromReferenceValue.Value=NV;
            end
            NominalTarget=NewValue;
            if(~isempty(app.SetupStructure.InversePchip))
                Target=ppval(app.SetupStructure.InversePchip,NominalTarget);
            else
                Target=NominalTarget;
            end
            if(app.WritePVs.Value), app.SetupStructure.MoveFunction(Target); else, disp('Executing: app.SetupStructure.MoveFunction(Target);'); end 
        end

        % Button pushed function: Energychange_FF
        function Energychange_FFButtonPushed(app, event)
            NewValue=str2num(app.OffsetFromReferenceValue.Value)+app.FF_amount.Value;
            if(length(NewValue)==1)
                app.OffsetFromReferenceValue.Value=num2str(NewValue,'%14.14g');
            else
                NV='[';
                for II=1:length(NewValue)
                    NV=[NV,num2str(NewValue,'%14.14g'),','];
                end
                NV(end)=']';
                app.OffsetFromReferenceValue.Value=NV;
            end
            NominalTarget=NewValue;
            if(~isempty(app.SetupStructure.InversePchip))
                Target=ppval(app.SetupStructure.InversePchip,NominalTarget);
            else
                Target=NominalTarget;
            end
            if(app.WritePVs.Value), app.SetupStructure.MoveFunction(Target); else, disp('Executing: app.SetupStructure.MoveFunction(Target);'); end 
        end

        % Button pushed function: Energychange_FFF
        function Energychange_FFFButtonPushed(app, event)
            NewValue=str2num(app.OffsetFromReferenceValue.Value)+app.FFF_amount.Value;
            if(length(NewValue)==1)
                app.OffsetFromReferenceValue.Value=num2str(NewValue,'%14.14g');
            else
                NV='[';
                for II=1:length(NewValue)
                    NV=[NV,num2str(NewValue,'%14.14g'),','];
                end
                NV(end)=']';
                app.OffsetFromReferenceValue.Value=NV;
            end
            NominalTarget=NewValue;
            if(~isempty(app.SetupStructure.InversePchip))
                Target=ppval(app.SetupStructure.InversePchip,NominalTarget);
            else
                Target=NominalTarget;
            end
            if(app.WritePVs.Value), app.SetupStructure.MoveFunction(Target); else, disp('Executing: app.SetupStructure.MoveFunction(Target);'); end 
        end

        % Button pushed function: Energychange_B
        function Energychange_BButtonPushed(app, event)
            NewValue=str2num(app.OffsetFromReferenceValue.Value)-app.F_amount.Value;
            if(length(NewValue)==1)
                app.OffsetFromReferenceValue.Value=num2str(NewValue,'%14.14g');
            else
                NV='[';
                for II=1:length(NewValue)
                    NV=[NV,num2str(NewValue,'%14.14g'),','];
                end
                NV(end)=']';
                app.OffsetFromReferenceValue.Value=NV;
            end
            NominalTarget=NewValue;
            if(~isempty(app.SetupStructure.InversePchip))
                Target=ppval(app.SetupStructure.InversePchip,NominalTarget);
            else
                Target=NominalTarget;
            end
            if(app.WritePVs.Value), app.SetupStructure.MoveFunction(Target); else, disp('Executing: app.SetupStructure.MoveFunction(Target);'); end 
        end

        % Button pushed function: Energychange_BB
        function Energychange_BBButtonPushed(app, event)
            NewValue=str2num(app.OffsetFromReferenceValue.Value)-app.FF_amount.Value;
            if(length(NewValue)==1)
                app.OffsetFromReferenceValue.Value=num2str(NewValue,'%14.14g');
            else
                NV='[';
                for II=1:length(NewValue)
                    NV=[NV,num2str(NewValue,'%14.14g'),','];
                end
                NV(end)=']';
                app.OffsetFromReferenceValue.Value=NV;
            end
            NominalTarget=NewValue;
            if(~isempty(app.SetupStructure.InversePchip))
                Target=ppval(app.SetupStructure.InversePchip,NominalTarget);
            else
                Target=NominalTarget;
            end
            if(app.WritePVs.Value), app.SetupStructure.MoveFunction(Target); else, disp('Executing: app.SetupStructure.MoveFunction(Target);'); end 
        end

        % Button pushed function: Energychange_BBB
        function Energychange_BBBButtonPushed(app, event)
            NewValue=str2num(app.OffsetFromReferenceValue.Value)-app.FFF_amount.Value;
            if(length(NewValue)==1)
                app.OffsetFromReferenceValue.Value=num2str(NewValue,'%14.14g');
            else
                NV='[';
                for II=1:length(NewValue)
                    NV=[NV,num2str(NewValue,'%14.14g'),','];
                end
                NV(end)=']';
                app.OffsetFromReferenceValue.Value=NV;
            end
            NominalTarget=NewValue;
            if(~isempty(app.SetupStructure.InversePchip))
                Target=ppval(app.SetupStructure.InversePchip,NominalTarget);
            else
                Target=NominalTarget;
            end
            if(app.WritePVs.Value), app.SetupStructure.MoveFunction(Target); else, disp('Executing: app.SetupStructure.MoveFunction(Target);'); end 
        end

        % Value changed function: ControlMode3
        function ControlMode3ValueChanged(app, event)
                %nothing here for now, 1 or 2 PVs are controlled by 1 or 2
                %colors.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhotonEnergyChangeUIFigure = uifigure('Visible', 'off');
            app.PhotonEnergyChangeUIFigure.Name = 'Photon Energy Change';
            app.TabGroup = uitabgroup(app.PhotonEnergyChangeUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SXRLineEnergyControlTab = uitab(app.TabGroup);
            app.SXRLineEnergyControlTab.Title = 'SXR Line Energy Control';
            app.T1 = uitable(app.SXRLineEnergyControlTab);
            app.T1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.T1.RowName = {};
            app.GetReference1 = uibutton(app.SXRLineEnergyControlTab, 'push');
            app.GetReference1.ButtonPushedFcn = createCallbackFcn(app, @GetReference1ButtonPushed, true);
            app.GetReference1.Text = 'Get reference Condition';
            app.FinalizeSetupPanel1 = uipanel(app.SXRLineEnergyControlTab);
            app.FinalizeSetupPanel1.Title = 'Finalize Setup';
            app.FinalizethisSetupconditionButton = uibutton(app.FinalizeSetupPanel1, 'push');
            app.FinalizethisSetupconditionButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizethisSetupconditionButtonPushed, true);
            app.FinalizethisSetupconditionButton.Text = 'Finalize this Setup condition ';
            app.SteerFlatAfterSetting1 = uicheckbox(app.FinalizeSetupPanel1);
            app.SteerFlatAfterSetting1.Text = 'Steer Orbit After Setting';
            app.separatewithcommaLabel = uilabel(app.FinalizeSetupPanel1);
            app.separatewithcommaLabel.Text = 'separate with comma (,)';
            app.AllowedVernierRangeMeVLabel = uilabel(app.FinalizeSetupPanel1);
            app.AllowedVernierRangeMeVLabel.Text = 'Allowed Vernier Range [MeV]';
            app.RemoteControlEnergyPVEditFieldLabel = uilabel(app.FinalizeSetupPanel1);
            app.RemoteControlEnergyPVEditFieldLabel.Text = 'Remote Control Energy PV:';
            app.RemoteControlEnergyPVEditField1 = uieditfield(app.FinalizeSetupPanel1, 'text');
            app.RemoteControlEnergyPVEditField1.Value = '***:USER:MCC:EPHOTK';
            app.MaxEditField_2Label = uilabel(app.FinalizeSetupPanel1);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxVernier1 = uieditfield(app.FinalizeSetupPanel1, 'text');
            app.MaxVernier1.Value = '+15';
            app.MinEditField_2Label = uilabel(app.FinalizeSetupPanel1);
            app.MinEditField_2Label.Text = 'Min';
            app.MinVernier1 = uieditfield(app.FinalizeSetupPanel1, 'text');
            app.MinVernier1.Value = '-15';
            app.AllowedEnergyDeltaRangeeVLabel = uilabel(app.FinalizeSetupPanel1);
            app.AllowedEnergyDeltaRangeeVLabel.Text = 'Allowed Energy Delta Range [eV]:';
            app.UsecellCheckBox = uicheckbox(app.FinalizeSetupPanel1);
            app.UsecellCheckBox.Text = 'Use cell #';
            app.UsecellCheckBox.Value = true;
            app.ControlModeDropDownLabel = uilabel(app.FinalizeSetupPanel1);
            app.ControlModeDropDownLabel.Text = 'Control Mode';
            app.ControlMode1 = uidropdown(app.FinalizeSetupPanel1);
            app.ControlMode1.Items = {};
            app.ControlMode1.ValueChangedFcn = createCallbackFcn(app, @ControlMode1ValueChanged, true);
            app.ControlMode1.Value = {};
            app.MinEditFieldLabel = uilabel(app.FinalizeSetupPanel1);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.FinalizeSetupPanel1, 'text');
            app.MinEditField.Value = '-200';
            app.MaxEditFieldLabel = uilabel(app.FinalizeSetupPanel1);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.FinalizeSetupPanel1, 'text');
            app.MaxEditField.Value = '+200';
            app.SelfdestructionDropDownLabel = uilabel(app.FinalizeSetupPanel1);
            app.SelfdestructionDropDownLabel.Text = 'Self-destruction';
            app.Selfdestruction1 = uidropdown(app.FinalizeSetupPanel1);
            app.Selfdestruction1.Items = {};
            app.Selfdestruction1.ValueChangedFcn = createCallbackFcn(app, @Selfdestruction1ValueChanged, true);
            app.Selfdestruction1.Value = {};
            app.UndulatorrangetobemovedLabel = uilabel(app.FinalizeSetupPanel1);
            app.UndulatorrangetobemovedLabel.Text = 'Undulator range to be moved:';
            app.UndulatorrangetobemovedEditField = uieditfield(app.FinalizeSetupPanel1, 'text');
            app.UndulatorrangetobemovedEditField.Value = '[1:99]';
            app.StatusPV0setting1setLabel = uilabel(app.FinalizeSetupPanel1);
            app.StatusPV0setting1setLabel.Text = 'StatusPV [0 setting 1 set]';
            app.SetDefault1 = uidropdown(app.FinalizeSetupPanel1);
            app.SetDefault1.Items = {};
            app.SetDefault1.ValueChangedFcn = createCallbackFcn(app, @SetDefault1ValueChanged, true);
            app.SetDefault1.Value = {};
            app.Endstation1 = uidropdown(app.FinalizeSetupPanel1);
            app.Endstation1.ValueChangedFcn = createCallbackFcn(app, @Endstation1ValueChanged, true);
            app.EndStationLabel = uilabel(app.FinalizeSetupPanel1);
            app.EndStationLabel.Text = 'EndStation:';
            app.ReferenceEnergyPVLabel = uilabel(app.FinalizeSetupPanel1);
            app.ReferenceEnergyPVLabel.Text = 'Reference Energy PV:';
            app.ReferenceControlEnergyPVEditField1 = uieditfield(app.FinalizeSetupPanel1, 'text');
            app.ReferenceControlEnergyPVEditField1.Value = '***:USER:MCC:EPHOTK:REF1';
            app.FormultiplecontrolPVsLabel = uilabel(app.FinalizeSetupPanel1);
            app.FormultiplecontrolPVsLabel.Text = 'For multiple control PVs,';
            app.LinearTable1 = uitable(app.FinalizeSetupPanel1);
            app.LinearTable1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.LinearTable1.RowName = {};
            app.RowsButton1 = uibutton(app.FinalizeSetupPanel1, 'push');
            app.RowsButton1.ButtonPushedFcn = createCallbackFcn(app, @RowsButton1Pushed, true);
            app.RowsButton1.Text = '+ Rows';
            app.Relative1 = uicheckbox(app.FinalizeSetupPanel1);
            app.Relative1.Text = 'Relative';
            app.Relative1.Value = true;
            app.OverrideBunchenergywithknownphotonenergy1 = uicheckbox(app.FinalizeSetupPanel1);
            app.OverrideBunchenergywithknownphotonenergy1.Text = 'Override Bunch energy with known photon energy';
            app.KnownPhotonEnergy1 = uieditfield(app.FinalizeSetupPanel1, 'numeric');
            app.ReadfromreferenceButton1 = uibutton(app.FinalizeSetupPanel1, 'push');
            app.ReadfromreferenceButton1.ButtonPushedFcn = createCallbackFcn(app, @ReadfromreferenceButton1Pushed, true);
            app.ReadfromreferenceButton1.Text = 'Read from reference';
            app.eVLabel_3 = uilabel(app.FinalizeSetupPanel1);
            app.eVLabel_3.Text = 'eV';
            app.CalculateeVRangefromVernierextremes99Panel1 = uipanel(app.FinalizeSetupPanel1);
            app.CalculateeVRangefromVernierextremes99Panel1.Title = 'Calculate eV Range from Vernier extremes (99%)';
            app.MinVernierEditFieldLabel = uilabel(app.CalculateeVRangefromVernierextremes99Panel1);
            app.MinVernierEditFieldLabel.Text = 'Min Vernier';
            app.MinVernierEditField = uieditfield(app.CalculateeVRangefromVernierextremes99Panel1, 'numeric');
            app.MaxVernierEditFieldLabel = uilabel(app.CalculateeVRangefromVernierextremes99Panel1);
            app.MaxVernierEditFieldLabel.Text = 'Max Vernier';
            app.MaxVernierEditField = uieditfield(app.CalculateeVRangefromVernierextremes99Panel1, 'numeric');
            app.CalculateeVRangeButton = uibutton(app.CalculateeVRangefromVernierextremes99Panel1, 'push');
            app.CalculateeVRangeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateeVRangeButtonPushed, true);
            app.CalculateeVRangeButton.Text = 'Calculate eV Range';
            app.VernierFudge1 = uieditfield(app.FinalizeSetupPanel1, 'numeric');
            app.VernierFudge1.Value = 1;
            app.VernierFudgeEditFieldLabel_3 = uilabel(app.FinalizeSetupPanel1);
            app.VernierFudgeEditFieldLabel_3.Text = 'VernierFudge';
            app.RestoreLastButton = uibutton(app.SXRLineEnergyControlTab, 'push');
            app.RestoreLastButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreLastButtonPushed, true);
            app.RestoreLastButton.Text = 'Restore Last';
            app.RestoreOldButton = uibutton(app.SXRLineEnergyControlTab, 'push');
            app.RestoreOldButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreOldButtonPushed, true);
            app.RestoreOldButton.Text = 'Restore Old';
            app.HXRLineEnergyControlTab = uitab(app.TabGroup);
            app.HXRLineEnergyControlTab.Title = 'HXR Line Energy Control';
            app.T2 = uitable(app.HXRLineEnergyControlTab);
            app.T2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.T2.RowName = {};
            app.GetReference2 = uibutton(app.HXRLineEnergyControlTab, 'push');
            app.GetReference2.ButtonPushedFcn = createCallbackFcn(app, @GetReference2ButtonPushed, true);
            app.GetReference2.Text = 'Get reference Condition';
            app.FinalizeSetupPanel2 = uipanel(app.HXRLineEnergyControlTab);
            app.FinalizeSetupPanel2.Title = 'Finalize Setup';
            app.FinalizethisSetupconditionButton2 = uibutton(app.FinalizeSetupPanel2, 'push');
            app.FinalizethisSetupconditionButton2.ButtonPushedFcn = createCallbackFcn(app, @FinalizethisSetupconditionButton2Pushed, true);
            app.FinalizethisSetupconditionButton2.Text = 'Finalize this Setup condition ';
            app.SteerFlatAfterSetting2 = uicheckbox(app.FinalizeSetupPanel2);
            app.SteerFlatAfterSetting2.Text = 'Steer Flat After Setting';
            app.AllowedVernierRangeMeVLabel_2 = uilabel(app.FinalizeSetupPanel2);
            app.AllowedVernierRangeMeVLabel_2.Text = 'Allowed Vernier Range [MeV]';
            app.RemoteControlEnergyPVEditField_2Label = uilabel(app.FinalizeSetupPanel2);
            app.RemoteControlEnergyPVEditField_2Label.Text = 'Remote Control Energy PV:';
            app.RemoteControlEnergyPVEditField2 = uieditfield(app.FinalizeSetupPanel2, 'text');
            app.RemoteControlEnergyPVEditField2.Value = '***:USER:MCC:EPHOTK';
            app.MaxVernier2 = uieditfield(app.FinalizeSetupPanel2, 'text');
            app.MaxVernier2.Value = '+15';
            app.MinVernier2 = uieditfield(app.FinalizeSetupPanel2, 'text');
            app.MinVernier2.Value = '-15';
            app.AllowedEnergyDeltaRangeeVLabel_2 = uilabel(app.FinalizeSetupPanel2);
            app.AllowedEnergyDeltaRangeeVLabel_2.Text = 'Allowed Energy Delta Range [eV]:';
            app.UsecellCheckBox2 = uicheckbox(app.FinalizeSetupPanel2);
            app.UsecellCheckBox2.Text = 'Use cell #';
            app.UsecellCheckBox2.Value = true;
            app.ControlModeDropDownLabel_2 = uilabel(app.FinalizeSetupPanel2);
            app.ControlModeDropDownLabel_2.Text = 'Control Mode';
            app.ControlMode2 = uidropdown(app.FinalizeSetupPanel2);
            app.ControlMode2.Items = {};
            app.ControlMode2.ValueChangedFcn = createCallbackFcn(app, @ControlMode2ValueChanged, true);
            app.ControlMode2.Value = {};
            app.SelfdestructionDropDownLabel_2 = uilabel(app.FinalizeSetupPanel2);
            app.SelfdestructionDropDownLabel_2.Text = 'Self-destruction';
            app.Selfdestruction2 = uidropdown(app.FinalizeSetupPanel2);
            app.Selfdestruction2.Items = {};
            app.Selfdestruction2.ValueChangedFcn = createCallbackFcn(app, @Selfdestruction2ValueChanged, true);
            app.Selfdestruction2.Value = {};
            app.UndulatorrangetobemovedEditField_2Label = uilabel(app.FinalizeSetupPanel2);
            app.UndulatorrangetobemovedEditField_2Label.Text = 'Undulator range to be moved:';
            app.UndulatorrangetobemovedEditField2 = uieditfield(app.FinalizeSetupPanel2, 'text');
            app.UndulatorrangetobemovedEditField2.Value = '[1:99]';
            app.MinEditField_2Label_3 = uilabel(app.FinalizeSetupPanel2);
            app.MinEditField_2Label_3.Text = 'Min';
            app.MinEnergy2 = uieditfield(app.FinalizeSetupPanel2, 'text');
            app.MinEnergy2.Value = '-500';
            app.MaxLabel = uilabel(app.FinalizeSetupPanel2);
            app.MaxLabel.Text = 'Max';
            app.MaxEnergy2 = uieditfield(app.FinalizeSetupPanel2, 'text');
            app.MaxEnergy2.Value = '500';
            app.StatusPV0setting1setLabel_2 = uilabel(app.FinalizeSetupPanel2);
            app.StatusPV0setting1setLabel_2.Text = 'StatusPV [0 setting 1 set]';
            app.SetDefault2 = uidropdown(app.FinalizeSetupPanel2);
            app.SetDefault2.Items = {};
            app.SetDefault2.ValueChangedFcn = createCallbackFcn(app, @SetDefault2ValueChanged, true);
            app.SetDefault2.Value = {};
            app.Endstation2 = uidropdown(app.FinalizeSetupPanel2);
            app.Endstation2.ValueChangedFcn = createCallbackFcn(app, @Endstation2ValueChanged, true);
            app.EndStationLabel_2 = uilabel(app.FinalizeSetupPanel2);
            app.EndStationLabel_2.Text = 'EndStation:';
            app.ReferenceEnergyPVLabel_2 = uilabel(app.FinalizeSetupPanel2);
            app.ReferenceEnergyPVLabel_2.Text = 'Reference Energy PV:';
            app.ReferenceControlEnergyPVEditField2 = uieditfield(app.FinalizeSetupPanel2, 'text');
            app.ReferenceControlEnergyPVEditField2.Value = '***:USER:MCC:EPHOTK:REF1';
            app.MinLabelGNU = uilabel(app.FinalizeSetupPanel2);
            app.MinLabelGNU.Text = 'Min';
            app.MaxLabelGNU = uilabel(app.FinalizeSetupPanel2);
            app.MaxLabelGNU.Text = 'Max';
            app.separatewithcommaLabel_2 = uilabel(app.FinalizeSetupPanel2);
            app.separatewithcommaLabel_2.Text = 'separate with comma (,)';
            app.FormultiplecontrolPVsLabel_2 = uilabel(app.FinalizeSetupPanel2);
            app.FormultiplecontrolPVsLabel_2.Text = 'For multiple control PVs,';
            app.LinearTable2 = uitable(app.FinalizeSetupPanel2);
            app.LinearTable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.LinearTable2.RowName = {};
            app.RowsButton2 = uibutton(app.FinalizeSetupPanel2, 'push');
            app.RowsButton2.ButtonPushedFcn = createCallbackFcn(app, @RowsButton2Pushed, true);
            app.RowsButton2.Text = '+ Rows';
            app.Relative2 = uicheckbox(app.FinalizeSetupPanel2);
            app.Relative2.Text = 'Relative';
            app.Relative2.Value = true;
            app.OverrideBunchenergywithknownphotonenergy2 = uicheckbox(app.FinalizeSetupPanel2);
            app.OverrideBunchenergywithknownphotonenergy2.Text = 'Override Bunch energy with known photon energy';
            app.KnownPhotonEnergy2 = uieditfield(app.FinalizeSetupPanel2, 'numeric');
            app.ReadfromreferenceButton2 = uibutton(app.FinalizeSetupPanel2, 'push');
            app.ReadfromreferenceButton2.ButtonPushedFcn = createCallbackFcn(app, @ReadfromreferenceButton2Pushed, true);
            app.ReadfromreferenceButton2.Text = 'Read from reference';
            app.eVLabel_2 = uilabel(app.FinalizeSetupPanel2);
            app.eVLabel_2.Text = 'eV';
            app.CalculateeVRangefromVernierextremes99Panel2 = uipanel(app.FinalizeSetupPanel2);
            app.CalculateeVRangefromVernierextremes99Panel2.Title = 'Calculate eV Range from Vernier extremes (99%)';
            app.MinVernierEditField_2Label = uilabel(app.CalculateeVRangefromVernierextremes99Panel2);
            app.MinVernierEditField_2Label.Text = 'Min Vernier';
            app.MinVernierEditField_2 = uieditfield(app.CalculateeVRangefromVernierextremes99Panel2, 'numeric');
            app.MaxVernierEditField_2Label = uilabel(app.CalculateeVRangefromVernierextremes99Panel2);
            app.MaxVernierEditField_2Label.Text = 'Max Vernier';
            app.MaxVernierEditField_2 = uieditfield(app.CalculateeVRangefromVernierextremes99Panel2, 'numeric');
            app.CalculateeVRangeButton_2 = uibutton(app.CalculateeVRangefromVernierextremes99Panel2, 'push');
            app.CalculateeVRangeButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateeVRangeButton_2Pushed, true);
            app.CalculateeVRangeButton_2.Text = 'Calculate eV Range';
            app.VernierFudgeEditFieldLabel_2 = uilabel(app.FinalizeSetupPanel2);
            app.VernierFudgeEditFieldLabel_2.Text = 'VernierFudge';
            app.VernierFudge2 = uieditfield(app.FinalizeSetupPanel2, 'numeric');
            app.VernierFudge2.Value = 1;
            app.RestoreLastButton2 = uibutton(app.HXRLineEnergyControlTab, 'push');
            app.RestoreLastButton2.ButtonPushedFcn = createCallbackFcn(app, @RestoreLastButton2Pushed, true);
            app.RestoreLastButton2.Text = 'Restore Last';
            app.RestoreOldButton2 = uibutton(app.HXRLineEnergyControlTab, 'push');
            app.RestoreOldButton2.ButtonPushedFcn = createCallbackFcn(app, @RestoreOldButton2Pushed, true);
            app.RestoreOldButton2.Text = 'Restore Old';
            app.HXRSSSelfSeedingTab = uitab(app.TabGroup);
            app.HXRSSSelfSeedingTab.Title = 'HXRSS Self-Seeding';
            app.T3 = uitable(app.HXRSSSelfSeedingTab);
            app.T3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.T3.RowName = {};
            app.GetReference3 = uibutton(app.HXRSSSelfSeedingTab, 'push');
            app.GetReference3.ButtonPushedFcn = createCallbackFcn(app, @GetReference3ButtonPushed, true);
            app.GetReference3.Text = 'Get reference Condition';
            app.FinalizeSetupPanel3 = uipanel(app.HXRSSSelfSeedingTab);
            app.FinalizeSetupPanel3.Title = 'Finalize Setup';
            app.Color1 = uicheckbox(app.FinalizeSetupPanel3);
            app.Color1.ValueChangedFcn = createCallbackFcn(app, @Color1ValueChanged, true);
            app.Color1.Text = '1 Color';
            app.Color1.Value = true;
            app.Colors2 = uicheckbox(app.FinalizeSetupPanel3);
            app.Colors2.ValueChangedFcn = createCallbackFcn(app, @Colors2ValueChanged, true);
            app.Colors2.Text = '2 Colors';
            app.ReflectionhklLabel1 = uilabel(app.FinalizeSetupPanel3);
            app.ReflectionhklLabel1.Text = 'Reflection (h,k,l)';
            app.ReflectionhklLabel2 = uilabel(app.FinalizeSetupPanel3);
            app.ReflectionhklLabel2.Text = 'Reflection (h,k,l)';
            app.hEditFieldLabel = uilabel(app.FinalizeSetupPanel3);
            app.hEditFieldLabel.Text = 'h';
            app.h1 = uieditfield(app.FinalizeSetupPanel3, 'numeric');
            app.kEditFieldLabel = uilabel(app.FinalizeSetupPanel3);
            app.kEditFieldLabel.Text = 'k';
            app.k1 = uieditfield(app.FinalizeSetupPanel3, 'numeric');
            app.lEditFieldLabel = uilabel(app.FinalizeSetupPanel3);
            app.lEditFieldLabel.Text = 'l';
            app.l1 = uieditfield(app.FinalizeSetupPanel3, 'numeric');
            app.RemoteControlEnergyPVEditField_2Label_2 = uilabel(app.FinalizeSetupPanel3);
            app.RemoteControlEnergyPVEditField_2Label_2.Text = 'Remote Control Energy PV:';
            app.RemoteControlEnergyPVEditField31 = uieditfield(app.FinalizeSetupPanel3, 'text');
            app.RemoteControlEnergyPVEditField31.Value = '***:USER:MCC:EPHOTK';
            app.AllowedEnergyDeltaRangeLabel3 = uilabel(app.FinalizeSetupPanel3);
            app.AllowedEnergyDeltaRangeLabel3.Text = 'Allowed Energy Delta Range [eV]:';
            app.MinEditField_2Label_4 = uilabel(app.FinalizeSetupPanel3);
            app.MinEditField_2Label_4.Text = 'Min';
            app.MinEnergy31 = uieditfield(app.FinalizeSetupPanel3, 'text');
            app.MinEnergy31.Value = '-500';
            app.MaxLabel_2 = uilabel(app.FinalizeSetupPanel3);
            app.MaxLabel_2.Text = 'Max';
            app.MaxEnergy31 = uieditfield(app.FinalizeSetupPanel3, 'text');
            app.MaxEnergy31.Value = '500';
            app.lEditFieldLabel_2 = uilabel(app.FinalizeSetupPanel3);
            app.lEditFieldLabel_2.Text = 'l';
            app.l2 = uieditfield(app.FinalizeSetupPanel3, 'numeric');
            app.kEditFieldLabel_2 = uilabel(app.FinalizeSetupPanel3);
            app.kEditFieldLabel_2.Text = 'k';
            app.k2 = uieditfield(app.FinalizeSetupPanel3, 'numeric');
            app.hEditFieldLabel_2 = uilabel(app.FinalizeSetupPanel3);
            app.hEditFieldLabel_2.Text = 'h';
            app.h2 = uieditfield(app.FinalizeSetupPanel3, 'numeric');
            app.RemoteControlEnergyPVEditField_2Label_3 = uilabel(app.FinalizeSetupPanel3);
            app.RemoteControlEnergyPVEditField_2Label_3.Text = 'Remote Control Energy PV:';
            app.RemoteControlEnergyPVEditField32 = uieditfield(app.FinalizeSetupPanel3, 'text');
            app.RemoteControlEnergyPVEditField32.Value = '***:USER:MCC:EPHOTK:UND';
            app.MaxLabel_3 = uilabel(app.FinalizeSetupPanel3);
            app.MaxLabel_3.Text = 'Max';
            app.MaxEnergy32 = uieditfield(app.FinalizeSetupPanel3, 'text');
            app.MaxEnergy32.Value = '500';
            app.MinEditField_2Label_5 = uilabel(app.FinalizeSetupPanel3);
            app.MinEditField_2Label_5.Text = 'Min';
            app.MinEnergy32 = uieditfield(app.FinalizeSetupPanel3, 'text');
            app.MinEnergy32.Value = '-500';
            app.AllowedVernierRangeMeVLabel_3 = uilabel(app.FinalizeSetupPanel3);
            app.AllowedVernierRangeMeVLabel_3.Text = 'Allowed Vernier Range MeV';
            app.MaxEditField_2Label_3 = uilabel(app.FinalizeSetupPanel3);
            app.MaxEditField_2Label_3.Text = 'Max';
            app.MaxVernier3 = uieditfield(app.FinalizeSetupPanel3, 'text');
            app.MaxVernier3.Value = '+15';
            app.MinEditField_2Label_6 = uilabel(app.FinalizeSetupPanel3);
            app.MinEditField_2Label_6.Text = 'Min';
            app.MinVernier3 = uieditfield(app.FinalizeSetupPanel3, 'text');
            app.MinVernier3.Value = '-15';
            app.ControlModeDropDownLabel_3 = uilabel(app.FinalizeSetupPanel3);
            app.ControlModeDropDownLabel_3.Text = 'Control Mode';
            app.ControlMode3 = uidropdown(app.FinalizeSetupPanel3);
            app.ControlMode3.Items = {};
            app.ControlMode3.ValueChangedFcn = createCallbackFcn(app, @ControlMode3ValueChanged, true);
            app.ControlMode3.Value = {};
            app.SelfdestructionDropDownLabel_3 = uilabel(app.FinalizeSetupPanel3);
            app.SelfdestructionDropDownLabel_3.Text = 'Self-destruction';
            app.Selfdestruction3 = uidropdown(app.FinalizeSetupPanel3);
            app.Selfdestruction3.Items = {};
            app.Selfdestruction3.ValueChangedFcn = createCallbackFcn(app, @Selfdestruction3ValueChanged, true);
            app.Selfdestruction3.Value = {};
            app.StatusPV0setting1setLabel_3 = uilabel(app.FinalizeSetupPanel3);
            app.StatusPV0setting1setLabel_3.Text = 'StatusPV [0 setting 1 set]';
            app.SetDefault3 = uidropdown(app.FinalizeSetupPanel3);
            app.SetDefault3.Items = {};
            app.SetDefault3.ValueChangedFcn = createCallbackFcn(app, @SetDefault3ValueChanged, true);
            app.SetDefault3.Value = {};
            app.FinalizethisSetupconditionButton3 = uibutton(app.FinalizeSetupPanel3, 'push');
            app.FinalizethisSetupconditionButton3.ButtonPushedFcn = createCallbackFcn(app, @FinalizethisSetupconditionButton3Pushed, true);
            app.FinalizethisSetupconditionButton3.Text = 'Finalize this Setup condition ';
            app.SteerFlatAfterSetting3 = uicheckbox(app.FinalizeSetupPanel3);
            app.SteerFlatAfterSetting3.Text = 'Steer Flat After Setting';
            app.UsecellCheckBox3 = uicheckbox(app.FinalizeSetupPanel3);
            app.UsecellCheckBox3.Text = 'Use cell #';
            app.UsecellCheckBox3.Value = true;
            app.UndulatorrangetobemovedEditField_2Label_2 = uilabel(app.FinalizeSetupPanel3);
            app.UndulatorrangetobemovedEditField_2Label_2.Text = 'Undulator range to be moved:';
            app.UndulatorrangetobemovedEditField3 = uieditfield(app.FinalizeSetupPanel3, 'text');
            app.UndulatorrangetobemovedEditField3.Value = '[1:99]';
            app.Endstation3 = uidropdown(app.FinalizeSetupPanel3);
            app.Endstation3.ValueChangedFcn = createCallbackFcn(app, @Endstation3ValueChanged, true);
            app.EndStationLabel_3 = uilabel(app.FinalizeSetupPanel3);
            app.EndStationLabel_3.Text = 'EndStation:';
            app.ReferenceEnergyPVEditFieldLabel = uilabel(app.FinalizeSetupPanel3);
            app.ReferenceEnergyPVEditFieldLabel.Text = 'Reference Energy PV:';
            app.ReferenceControlEnergyPVEditField31 = uieditfield(app.FinalizeSetupPanel3, 'text');
            app.ReferenceControlEnergyPVEditField31.Value = '***:USER:MCC:EPHOTK:REF1';
            app.ReferenceEnergyPVLabel_3 = uilabel(app.FinalizeSetupPanel3);
            app.ReferenceEnergyPVLabel_3.Text = 'Reference Energy PV:';
            app.ReferenceControlEnergyPVEditField32 = uieditfield(app.FinalizeSetupPanel3, 'text');
            app.ReferenceControlEnergyPVEditField32.Value = '***:USER:MCC:EPHOTK:REF2';
            app.LinearTable3 = uitable(app.FinalizeSetupPanel3);
            app.LinearTable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.LinearTable3.RowName = {};
            app.Relative3 = uicheckbox(app.FinalizeSetupPanel3);
            app.Relative3.Text = 'Relative';
            app.Relative3.Value = true;
            app.RowsButton3 = uibutton(app.FinalizeSetupPanel3, 'push');
            app.RowsButton3.ButtonPushedFcn = createCallbackFcn(app, @RowsButton3Pushed, true);
            app.RowsButton3.Text = '+ Rows';
            app.OverrideBunchenergywithknownphotonenergy3 = uicheckbox(app.FinalizeSetupPanel3);
            app.OverrideBunchenergywithknownphotonenergy3.Text = 'Use photon energy';
            app.KnownPhotonEnergy3 = uieditfield(app.FinalizeSetupPanel3, 'numeric');
            app.ReadfromreferenceButton3 = uibutton(app.FinalizeSetupPanel3, 'push');
            app.ReadfromreferenceButton3.ButtonPushedFcn = createCallbackFcn(app, @ReadfromreferenceButton3Pushed, true);
            app.ReadfromreferenceButton3.Text = 'Read from reference';
            app.eVLabel = uilabel(app.FinalizeSetupPanel3);
            app.eVLabel.Text = 'eV';
            app.ControlDiamondbyDropDownLabel = uilabel(app.FinalizeSetupPanel3);
            app.ControlDiamondbyDropDownLabel.Text = 'Control Diamond by';
            app.DiamondControl = uidropdown(app.FinalizeSetupPanel3);
            app.DiamondControl.Items = {'Pitch Only', 'Yaw Only', 'Pitch + Yaw'};
            app.DiamondControl.Value = 'Pitch Only';
            app.VernierFudgeEditFieldLabel = uilabel(app.FinalizeSetupPanel3);
            app.VernierFudgeEditFieldLabel.Text = 'VernierFudge';
            app.VernierFudge3 = uieditfield(app.FinalizeSetupPanel3, 'numeric');
            app.VernierFudge3.Value = 1;
            app.RestoreLastButton3 = uibutton(app.HXRSSSelfSeedingTab, 'push');
            app.RestoreLastButton3.ButtonPushedFcn = createCallbackFcn(app, @RestoreLastButton3Pushed, true);
            app.RestoreLastButton3.Text = 'Restore Last';
            app.RestoreOldButton3 = uibutton(app.HXRSSSelfSeedingTab, 'push');
            app.RestoreOldButton3.ButtonPushedFcn = createCallbackFcn(app, @RestoreOldButton3Pushed, true);
            app.RestoreOldButton3.Text = 'Restore Old';
            app.SXRSSSelfSeedingTab = uitab(app.TabGroup);
            app.SXRSSSelfSeedingTab.Title = 'SXRSS Self-Seeding';
            app.T4 = uitable(app.SXRSSSelfSeedingTab);
            app.T4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.T4.RowName = {};
            app.GetReference4 = uibutton(app.SXRSSSelfSeedingTab, 'push');
            app.GetReference4.Text = 'Get reference Condition';
            app.StatusPV0setting1setLabel_4 = uilabel(app.SXRSSSelfSeedingTab);
            app.StatusPV0setting1setLabel_4.Text = 'StatusPV [0 setting 1 set]';
            app.SetDefault4 = uidropdown(app.SXRSSSelfSeedingTab);
            app.SetDefault4.Items = {};
            app.SetDefault4.ValueChangedFcn = createCallbackFcn(app, @SetDefault4ValueChanged, true);
            app.SetDefault4.Value = {};
            app.SelfdestructionDropDownLabel_4 = uilabel(app.SXRSSSelfSeedingTab);
            app.SelfdestructionDropDownLabel_4.Text = 'Self-destruction';
            app.Selfdestruction4 = uidropdown(app.SXRSSSelfSeedingTab);
            app.Selfdestruction4.Items = {};
            app.Selfdestruction4.ValueChangedFcn = createCallbackFcn(app, @Selfdestruction4ValueChanged, true);
            app.Selfdestruction4.Value = {};
            app.RemoteControlandReadoutPanel = uipanel(app.PhotonEnergyChangeUIFigure);
            app.RemoteControlandReadoutPanel.Title = 'Remote Control and Readout';
            app.StartResetListeningtoremotecontrolPVsButton = uibutton(app.RemoteControlandReadoutPanel, 'push');
            app.StartResetListeningtoremotecontrolPVsButton.ButtonPushedFcn = createCallbackFcn(app, @StartResetListeningtoremotecontrolPVsButtonPushed, true);
            app.StartResetListeningtoremotecontrolPVsButton.Text = 'Start/Reset Listening to remote control PVs';
            app.RemoteControlPVsLabel = uilabel(app.RemoteControlandReadoutPanel);
            app.RemoteControlPVsLabel.Text = 'RemoteControlPVs';
            app.pollingtimesEditFieldLabel = uilabel(app.RemoteControlandReadoutPanel);
            app.pollingtimesEditFieldLabel.Text = 'polling time (s)';
            app.pollingtimesEditField = uieditfield(app.RemoteControlandReadoutPanel, 'numeric');
            app.pollingtimesEditField.Value = 0.2;
            app.STOPremotecontrolButton = uibutton(app.RemoteControlandReadoutPanel, 'push');
            app.STOPremotecontrolButton.ButtonPushedFcn = createCallbackFcn(app, @STOPremotecontrolButtonPushed, true);
            app.STOPremotecontrolButton.Text = 'STOP remote control';
            app.RemotecontrolstoppedLabel = uilabel(app.RemoteControlandReadoutPanel);
            app.RemotecontrolstoppedLabel.Text = 'Remote control stopped';
            app.tabula = uitable(app.RemoteControlandReadoutPanel);
            app.tabula.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.tabula.RowName = {};
            app.DateString = uilabel(app.RemoteControlandReadoutPanel);
            app.DateString.Text = 'Date-string';
            app.tabulotta = uitable(app.RemoteControlandReadoutPanel);
            app.tabulotta.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.tabulotta.RowName = {};
            app.AutomaticScanPanel = uipanel(app.PhotonEnergyChangeUIFigure);
            app.AutomaticScanPanel.Title = 'Automatic Scan';
            app.AutoScanTable = uitable(app.AutomaticScanPanel);
            app.AutoScanTable.ColumnName = {'PV Name'; 'Start'; 'End'; 'Column 4'};
            app.AutoScanTable.RowName = {};
            app.SinglePass = uicheckbox(app.AutomaticScanPanel);
            app.SinglePass.Text = 'Single Pass';
            app.ZigZag = uicheckbox(app.AutomaticScanPanel);
            app.ZigZag.Text = 'Zig-Zag';
            app.StepsEditFieldLabel = uilabel(app.AutomaticScanPanel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.AutomaticScanPanel, 'numeric');
            app.StepsEditField.Value = 10;
            app.waitaftersetsEditFieldLabel = uilabel(app.AutomaticScanPanel);
            app.waitaftersetsEditFieldLabel.Text = 'wait after set (s)';
            app.waitaftersetsEditField = uieditfield(app.AutomaticScanPanel, 'numeric');
            app.waitaftersetsEditField.Value = 10;
            app.STARTButton = uibutton(app.AutomaticScanPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.AutomaticScanPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.AutoString = uilabel(app.AutomaticScanPanel);
            app.AutoString.Text = {''; ''};
            app.AutoScanRelative = uicheckbox(app.AutomaticScanPanel);
            app.AutoScanRelative.ValueChangedFcn = createCallbackFcn(app, @AutoScanRelativeValueChanged, true);
            app.AutoScanRelative.Text = 'Relative';
            app.AutoScanRelative.Value = true;
            app.MessageList = uilistbox(app.PhotonEnergyChangeUIFigure);
            app.ManualSetpointControl = uipanel(app.PhotonEnergyChangeUIFigure);
            app.ManualSetpointControl.Title = 'Manual Setpoint Control Panel';
            app.GO1 = uibutton(app.ManualSetpointControl, 'push');
            app.GO1.ButtonPushedFcn = createCallbackFcn(app, @GO1ButtonPushed, true);
            app.GO1.Text = 'GO';
            app.VernierToReference = uibutton(app.ManualSetpointControl, 'push');
            app.VernierToReference.ButtonPushedFcn = createCallbackFcn(app, @VernierToReferenceButtonPushed, true);
            app.VernierToReference.Text = 'Vernier to Reference';
            app.RestoreReference1 = uibutton(app.ManualSetpointControl, 'push');
            app.RestoreReference1.ButtonPushedFcn = createCallbackFcn(app, @RestoreReference1ButtonPushed, true);
            app.RestoreReference1.Text = 'Restore Reference';
            app.EnableAll = uibutton(app.ManualSetpointControl, 'push');
            app.EnableAll.ButtonPushedFcn = createCallbackFcn(app, @EnableAllButtonPushed, true);
            app.EnableAll.Text = '';
            app.EnableAll_2 = uibutton(app.ManualSetpointControl, 'push');
            app.EnableAll_2.ButtonPushedFcn = createCallbackFcn(app, @EnableAll_2ButtonPushed, true);
            app.EnableAll_2.Text = '';
            app.EnableAll_3 = uibutton(app.ManualSetpointControl, 'push');
            app.EnableAll_3.ButtonPushedFcn = createCallbackFcn(app, @EnableAll_3ButtonPushed, true);
            app.EnableAll_3.Text = '';
            app.OffsetFromReferenceValue = uieditfield(app.ManualSetpointControl, 'text');
            app.OffsetFromReferenceValue.Value = '0';
            app.OffsetFromReferenceValueABS = uieditfield(app.ManualSetpointControl, 'text');
            app.OffsetFromReferenceValueABS.ValueChangedFcn = createCallbackFcn(app, @OffsetFromReferenceValueABSValueChanged, true);
            app.OffsetFromReferenceValueABS.Value = '0';
            app.GO_ABS = uibutton(app.ManualSetpointControl, 'push');
            app.GO_ABS.ButtonPushedFcn = createCallbackFcn(app, @GO_ABSButtonPushed, true);
            app.GO_ABS.Text = 'GO';
            app.AbsolutesetpointeVrequiresvalidreferenceLabel = uilabel(app.ManualSetpointControl);
            app.AbsolutesetpointeVrequiresvalidreferenceLabel.Text = 'Absolute set point [eV]: requires valid reference';
            app.OffsetfromReferenceeVLabel = uilabel(app.ManualSetpointControl);
            app.OffsetfromReferenceeVLabel.Text = 'Offset from Reference [eV]';
            app.fromendstationLabel = uilabel(app.ManualSetpointControl);
            app.fromendstationLabel.Text = 'from endstation';
            app.ReferenceLabel = uilabel(app.ManualSetpointControl);
            app.ReferenceLabel.Text = 'Reference:';
            app.ReferenceValuesForEnergyLabel = uilabel(app.ManualSetpointControl);
            app.ReferenceValuesForEnergyLabel.Text = 'Reference Values For Energy';
            app.MainReadoutStringLabel1 = uilabel(app.ManualSetpointControl);
            app.MainReadoutStringLabel1.Text = '';
            app.FastchangeLabel = uilabel(app.ManualSetpointControl);
            app.FastchangeLabel.Text = 'Fast change:';
            app.Energychange_BBB = uibutton(app.ManualSetpointControl, 'push');
            app.Energychange_BBB.ButtonPushedFcn = createCallbackFcn(app, @Energychange_BBBButtonPushed, true);
            app.Energychange_BBB.Text = '<<<';
            app.Energychange_BB = uibutton(app.ManualSetpointControl, 'push');
            app.Energychange_BB.ButtonPushedFcn = createCallbackFcn(app, @Energychange_BBButtonPushed, true);
            app.Energychange_BB.Text = '<<';
            app.Energychange_B = uibutton(app.ManualSetpointControl, 'push');
            app.Energychange_B.ButtonPushedFcn = createCallbackFcn(app, @Energychange_BButtonPushed, true);
            app.Energychange_B.Text = '<';
            app.Energychange_F = uibutton(app.ManualSetpointControl, 'push');
            app.Energychange_F.ButtonPushedFcn = createCallbackFcn(app, @Energychange_FButtonPushed, true);
            app.Energychange_F.Text = '>';
            app.Energychange_FF = uibutton(app.ManualSetpointControl, 'push');
            app.Energychange_FF.ButtonPushedFcn = createCallbackFcn(app, @Energychange_FFButtonPushed, true);
            app.Energychange_FF.Text = '>>';
            app.Energychange_FFF = uibutton(app.ManualSetpointControl, 'push');
            app.Energychange_FFF.ButtonPushedFcn = createCallbackFcn(app, @Energychange_FFFButtonPushed, true);
            app.Energychange_FFF.Text = '>>>';
            app.Label = uilabel(app.ManualSetpointControl);
            app.Label.Text = '>';
            app.F_amount = uieditfield(app.ManualSetpointControl, 'numeric');
            app.F_amount.Value = 0.2;
            app.Label_2 = uilabel(app.ManualSetpointControl);
            app.Label_2.Text = '>>';
            app.FF_amount = uieditfield(app.ManualSetpointControl, 'numeric');
            app.FF_amount.Value = 2;
            app.Label_3 = uilabel(app.ManualSetpointControl);
            app.Label_3.Text = '>>>';
            app.FFF_amount = uieditfield(app.ManualSetpointControl, 'numeric');
            app.FFF_amount.Value = 20;
            app.MainReadoutStringLabel2 = uilabel(app.ManualSetpointControl);
            app.MainReadoutStringLabel2.Text = '';
            app.WritePVs = uicheckbox(app.PhotonEnergyChangeUIFigure);
            app.WritePVs.Text = 'Write PVs';
            app.WritePVs.Value = true;
            app.EvalStateButton = uibutton(app.PhotonEnergyChangeUIFigure, 'push');
            app.EvalStateButton.ButtonPushedFcn = createCallbackFcn(app, @EvalStateButtonPushed, true);
            app.EvalStateButton.Text = 'Eval. State';
            % Show the figure after all components are created
            app.PhotonEnergyChangeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UL_PhotonEnergyControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhotonEnergyChangeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhotonEnergyChangeUIFigure)
        end
    end
end