classdef GUIDEAppMigrationTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MigrateButton          matlab.ui.control.Button
        BrowseButton           matlab.ui.control.Button
        LearnMoreButton        matlab.ui.control.Button
        SelectaGUIDEFIGfiletomigrateLabel  matlab.ui.control.Label
        GUIDEFIGfileLabel      matlab.ui.control.Label
        GUIDEFIGfileEditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        GUIDEAppName
        GUIDEAppFullFileName
    end
    
    methods (Access = private)
        
        function enableEditFieldAndMigrateButton(app)
            app.GUIDEFIGfileEditField.Enable = 'on';
            app.GUIDEFIGfileEditField.FontAngle = 'normal';
            app.MigrateButton.Enable = 'on';
        end
        
        function disableEditFieldAndMigrateButton(app)
            app.GUIDEFIGfileEditField.Enable = 'off';
            app.GUIDEFIGfileEditField.Value = getString(message('appmigration:appmigration:SelectFigFile'));
            app.GUIDEFIGfileEditField.FontAngle = 'italic';
            app.MigrateButton.Enable = 'off';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, figFullFileName)
            app.UIFigure.Visible = 'off';
            
            if ~matlab.internal.environment.context.isMATLABOnline
                % movegui is not fully supported in MATLAB Online (g1946357)   
                movegui(app.UIFigure, 'center');
            end
            
            app.UIFigure.Visible = 'on';
            
            % Set initial placeholder text in edit field
            app.GUIDEFIGfileEditField.Value = getString(message('appmigration:appmigration:SelectFigFile'));
            
            if nargin == 2 && ~isempty(figFullFileName)
                app.GUIDEFIGfileEditField.Value = figFullFileName;
                app.enableEditFieldAndMigrateButton();
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.fig', getString(message('appmigration:appmigration:SelectFigFile')));
            
            % Workaround uigetfile focus issue by forcing focus back to app
            figure(app.UIFigure);
            
            fullFileName = fullfile(pathname,filename);
            if ~isequal(filename, 0)
                app.GUIDEFIGfileEditField.Value = fullFileName;
                app.enableEditFieldAndMigrateButton();
            end
            app.GUIDEAppName = filename;
            app.GUIDEAppFullFileName = fullFileName;
        end

        % Button pushed function: MigrateButton
        function MigrateButtonPushed(app, event)
            
            % Check if user edited the file name after loading a file using browse
            editFieldValue = app.GUIDEFIGfileEditField.Value;            
            
            % update app name and fullfilename with edit field value
            [~, fileName] = fileparts(editFieldValue);
            app.GUIDEAppName = fileName;
            app.GUIDEAppFullFileName = editFieldValue;
            
            try
                converter = appmigration.internal.GUIDEAppConverter(app.GUIDEAppFullFileName);
                
                progressDialogMessage = getString(message('appmigration:appmigration:ProgressDialogMessage', converter.AppName));
                progressDialog = uiprogressdlg(app.UIFigure, 'Message', progressDialogMessage, 'Indeterminate', 'on');
                
                conversionResults = converter.convert();
                
            catch exception
                knownExceptionIdentifiers = {'appmigration:appmigration:InvalidFigFileName','appmigration:appmigration:InvalidCodeFileName','appmigration:appmigration:NotGuideCreatedApp','appmigration:appmigration:NotWritableLocation'};
                if any(strcmp(exception.identifier,knownExceptionIdentifiers))
                    title = getString(message('appmigration:appmigration:AppTitle'));
                    uialert(app.UIFigure,exception.message,title);
                    return;
                else
                    rethrow(exception);
                end
            end
            
            reportGenerator = appmigration.internal.AppConversionReportGenerator(converter.FigFullFileName, conversionResults);
            reportGenerator.generateHTMLReport();
            
            % Listen for when the app is created in App Designer upon opening.
            % Launch the app migration tutorial once it is ready.
            listener = addlistener(?appdesigner.internal.model.AppModel, 'InstanceCreated', ...
                @handleAppModelCreated);
            
            mlappFile = conversionResults.MLAPPFullFileName;
            
            % Open the app in App Designer
            appdesigner(mlappFile)
            
            function handleAppModelCreated(~,event)
                delete(listener);
                appModel = event.Instance;
                if(strcmp(appModel.FullFileName,mlappFile))
                    % Start tutorial only if converted app is open
                    appdesigner.internal.application.openTutorial('AppMigration');
                end
            end
            
            delete(progressDialog);
            delete(app);
            
        end

        % Button pushed function: LearnMoreButton
        function LearnMoreButtonPushed(app, event)
            helpview('matlab', 'diffappdguide');
        end

        % Value changed function: GUIDEFIGfileEditField
        function GUIDEFIGfileEditFieldValueChanged(app, event)
            value = app.GUIDEFIGfileEditField.Value;
            if isempty(value)
                app.disableEditFieldAndMigrateButton();
                app.GUIDEAppName = '';
                app.GUIDEAppFullFileName = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'GUIDE to App Designer Migration Tool';
            app.MigrateButton = uibutton(app.UIFigure, 'push');
            app.MigrateButton.ButtonPushedFcn = createCallbackFcn(app, @MigrateButtonPushed, true);
            app.MigrateButton.Enable = 'off';
            app.MigrateButton.Text = 'Migrate';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.LearnMoreButton = uibutton(app.UIFigure, 'push');
            app.LearnMoreButton.ButtonPushedFcn = createCallbackFcn(app, @LearnMoreButtonPushed, true);
            app.LearnMoreButton.Text = 'Learn More';
            app.SelectaGUIDEFIGfiletomigrateLabel = uilabel(app.UIFigure);
            app.SelectaGUIDEFIGfiletomigrateLabel.Text = 'Select a GUIDE FIG file to migrate. ';
            app.GUIDEFIGfileLabel = uilabel(app.UIFigure);
            app.GUIDEFIGfileLabel.Text = 'GUIDE FIG file:';
            app.GUIDEFIGfileEditField = uieditfield(app.UIFigure, 'text');
            app.GUIDEFIGfileEditField.ValueChangedFcn = createCallbackFcn(app, @GUIDEFIGfileEditFieldValueChanged, true);
            app.GUIDEFIGfileEditField.Enable = 'off';
            app.GUIDEFIGfileEditField.Value = 'Select a GUIDE .fig file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIDEAppMigrationTool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end