classdef fix_the_6x6_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        pickatime   matlab.ui.container.ButtonGroup
        tminus5     matlab.ui.control.RadioButton
        tminus10    matlab.ui.control.RadioButton
        tminus30    matlab.ui.control.RadioButton
        tminus60    matlab.ui.control.RadioButton
        enterTime   matlab.ui.control.RadioButton
        getPVpanel  matlab.ui.container.Panel
        L2ampl      matlab.ui.control.Label
        L2phase     matlab.ui.control.Label
        L2ipk       matlab.ui.control.Label
        L3eng       matlab.ui.control.Label
        getVals     matlab.ui.control.Button
        L3phase     matlab.ui.control.Label
        valsTime    matlab.ui.control.Label
        L1Sampl     matlab.ui.control.Label
        L1Sphase    matlab.ui.control.Label
        L0Bampl     matlab.ui.control.Label
        L0Bphase    matlab.ui.control.Label
        L1ipk       matlab.ui.control.Label
        setPVpanel  matlab.ui.container.Panel
        fixit       matlab.ui.control.Button
        helpMe      matlab.ui.control.Button
        whatsUp     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function fix_the_6x6_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to fix_the_6x6 (see VARARGIN)
            
            % Choose default command line output for fix_the_6x6
            handles.output = hObject;
            
            % Defaults the "Mess w 6x6" button to "harmless" mode
            set(handles.fixit,'Enable','off');
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            delete(hObject);
            
            % Exit from Matlab when not running the desktop
            if usejava('desktop')
               % Don't exit from
               disp('Goodbye!')
            else
               exit
            end
        end

        % Button pushed function: fixit
        function fixit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fixit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            % Put some text on the panel to let people know that you started
            set(handles.whatsUp, 'String', 'Doing stuff...','Visible','on','FontAngle','italic');
            
            
            % Put in some stoppers before you do anything heinous
            disp('Putting in BYKIK, soft and hard')
            
            % lcaPut('IOC:BSY0:MP01:MSHUTCTL', 0); Experimenting with NOT doing this!
            lcaPut('IOC:BSY0:MP01:BYKIKCTL', 0);
            lcaPut('IOC:BSY0:MP01:BYKIKSCTL', 0);
            pause(1);
            
            
            % Turn off the longitudinal feedback; BC2 energy, BC2 pk current, DL2
            % energy disabled
            disp('Stopping the 6x6')
            disp('Disabling all feedbacks')
            
            lcaPut('FBCK:FB04:LG01:STATE', 0);
            lcaPut('FBCK:FB04:LG01:S1USED', 0);
            lcaPut('FBCK:FB04:LG01:S2USED', 0);
            lcaPut('FBCK:FB04:LG01:S3USED', 0);
            lcaPut('FBCK:FB04:LG01:S4USED', 0);
            lcaPut('FBCK:FB04:LG01:S5USED', 0);
            lcaPut('FBCK:FB04:LG01:S6USED', 0);
            lcaPut('SIOC:SYS0:ML04:AO404', 0); % Turn off soft BC1 Pk Current
            lcaPut('SIOC:SYS0:ML04:AO408', 0); % Turn off soft energy control PV
            lcaPut('SIOC:SYS0:ML04:AO402', 0); % Turn off soft BC2 Pk Current
                                               % control pv
            pause(1);
            
            
            % Change the setpoints
            disp('Restoring values')
            disp(['     from ' handles.dstr]);
            lcaPut('ACCL:IN20:400:L0B_ADES',handles.l0ba);
            lcaPut('ACCL:IN20:400:L0B_PDES',handles.l0bp);
            if not(isnan(handles.l0ba_s)) % New PVs 11/2021, may not be archived
              lcaPut('ACCL:IN20:400:L0B_ADES2',handles.l0ba_s);
              lcaPut('ACCL:IN20:400:L0B_PDES2',handles.l0bp_s);
            end
            lcaPut('ACCL:LI21:1:L1S_ADES',handles.l1sa);
            lcaPut('ACCL:LI21:1:L1S_PDES',handles.l1sp);
            lcaPut('FBCK:FB04:LG01:S3DES',handles.l1i);
            lcaPut('SIOC:SYS0:ML04:AO403',handles.l1i_s);
            lcaPut('ACCL:LI22:1:ADES',handles.l2a);
            lcaPut('ACCL:LI22:1:PDES',handles.l2p);
            lcaPut('FBCK:FB04:LG01:S5DES',handles.l2i);
            lcaPut('SIOC:SYS0:ML04:AO401',handles.l2i_s);
            lcaPut('ACCL:LI25:1:ADES',handles.l3e);
            lcaPut('SIOC:SYS0:ML04:AO406',handles.l3e_s);
            lcaPut('ACCL:LI25:1:PDES',handles.l3p);
            pause(1);
            
            
            % Start the 6x6 again
            disp('Starting the 6x6')
            
            lcaPut('FBCK:FB04:LG01:STATE', 1);
            pause(1);
            
            
            % And we're done!
            set(handles.whatsUp, 'String', 'Done!','FontAngle','normal');
            
            disp('Done messing with the 6x6')
            disp('Time to enable sub-feedbacks and BYKIK')
            disp('Wishing you a stress-free recovery!')
            disp('<3 <3 <3')
        end

        % Button pushed function: getVals
        function getVals_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to getVals (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Set names for the PVs we're interested in pulling
            l0ba_pv = 'ACCL:IN20:400:L0B_ADES'; % Zimmer added 11/2021
            l0bp_pv = 'ACCL:IN20:400:L0B_PDES'; % Zimmer added
            l0ba_s_pv = 'ACCL:IN20:400:L0B_ADES2'; % Zimmer added
            l0bp_s_pv = 'ACCL:IN20:400:L0B_PDES2'; % Zimmer added
            l1sa_pv = 'ACCL:LI21:1:L1S_ADES'; % Zimmer added
            l1sp_pv = 'ACCL:LI21:1:L1S_PDES'; % Zimmer added
            l1i_pv = 'FBCK:FB04:LG01:S3DES'; % Zimmer added
            l1i_s_pv = 'SIOC:SYS0:ML04:AO403'; % Zimmer added
            l2a_pv = 'ACCL:LI22:1:ADES';
            l2p_pv = 'ACCL:LI22:1:PDES';
            l2i_pv = 'FBCK:FB04:LG01:S5DES';
            l2i_s_pv = 'SIOC:SYS0:ML04:AO401'; % Zimmer added
            l3e_pv = 'ACCL:LI25:1:ADES';
            l3e_s_pv = 'SIOC:SYS0:ML04:AO406'; % Zimmer added
            l3p_pv = 'ACCL:LI25:1:PDES';
            
            
            % Set the time range
            time = clock;  % Get current time
            
            tpick = get(get(handles.pickatime,'SelectedObject'),'Tag'); % Figure out which time preference is selected
            
            switch tpick   % Based on the time preference, set subtraction time
                case 'tminus5'
                    tminus = [ 0 0 0 0 5 0];
                case 'tminus10'
                    tminus = [ 0 0 0 0 10 0];
                case 'tminus30'
                    tminus = [ 0 0 0 0 30 0];
                case 'tminus60'
                    tminus = [ 0 0 0 1 0 0];
            end
            
            deltaT = [ 0 0 0 0 0 30]; % Create a 30-second time window on either side
            
            if strcmp(tpick,'enterTime')
              timeDes = handles.tvec; % Use time entered under "Enter time," or default
            else
              timeDes = time - tminus;
            end
            
            t_lo = timeDes - deltaT;
            t_hi = timeDes + deltaT;
            
            t_lo_str = datestr(t_lo, 'mm/dd/yy HH:MM:SS');
            t_hi_str = datestr(t_hi, 'mm/dd/yy HH:MM:SS');
            
            timeRange = {t_lo_str; t_hi_str};
            
            
            % Get archived data
            [t, l0ba_val] = getHistory(l0ba_pv, timeRange);
            [t, l0bp_val] = getHistory(l0bp_pv, timeRange);
            [t, l0ba_s_val] = getHistory(l0ba_s_pv, timeRange);
            [t, l0bp_s_val] = getHistory(l0bp_s_pv, timeRange);
            [t, l1sa_val] = getHistory(l1sa_pv, timeRange);
            [t, l1sp_val] = getHistory(l1sp_pv, timeRange);
            [t, l1i_val] = getHistory(l1i_pv, timeRange);
            [t, l1i_s_val] = getHistory(l1i_s_pv, timeRange);
            [t, l2a_val] = getHistory(l2a_pv, timeRange);
            [t, l2p_val] = getHistory(l2p_pv, timeRange);
            [t, l2i_val] = getHistory(l2i_pv, timeRange);
            [t, l2i_s_val] = getHistory(l2i_s_pv, timeRange); %Zimmer added
            [t, l3e_val] = getHistory(l3e_pv, timeRange);
            [t, l3e_s_val] = getHistory(l3e_s_pv, timeRange); % Zimmer added 11/2021
            [t, l3p_val] = getHistory(l3p_pv, timeRange);
            
            
            % Find the medians
            l0ba = median(l0ba_val);
            l0bp = median(l0bp_val);
            l0ba_s = median(l0ba_s_val);
            l0bp_s = median(l0bp_s_val);
            l1sa = median(l1sa_val);
            l1sp = median(l1sp_val);
            l1i = median(l1i_val);
            l1i_s = median(l1i_s_val);
            l2a = median(l2a_val);
            l2p = median(l2p_val);
            l2i = median(l2i_val);
            l2i_s = median(l2i_s_val);
            l3e = median(l3e_val);
            l3e_s = median(l3e_s_val);
            l3p = median(l3p_val);
            
            
            % Make some fancy text strings
            l0ba_disp = [ 'L0B ampl: ' mat2str(l0ba, 3) ' / ' mat2str(l0ba_s, 3)];
            l0bp_disp = [ 'L0B phase: ' mat2str(l0bp,3) ' / ' mat2str(l0bp_s, 3)];
            l1sa_disp = [ 'L1S ampl: ' mat2str(l1sa, 4)];
            l1sp_disp = [ 'L1S phase: ' mat2str(l1sp,3)];
            l1i_disp = [ 'L1 pk curr: ' int2str(l1i) ' / ' int2str(l1i_s)];
            l2a_disp = [ 'L2 ampl: ' int2str(l2a)];
            l2p_disp = [ 'L2 phase: ' mat2str(l2p,3)];  % I wanted a digit after the decimal point.
            l2i_disp = [ 'L2 pk curr: ' int2str(l2i) ' / ' int2str(l2i_s)];
            l3e_disp = [ 'L3 energy: ' int2str(l3e) ' / ' int2str(l3e_s)];
            l3p_disp = [ 'L3 phase: ' int2str(l3p)];
            dstr = datestr(timeDes);
            
            
            % Display those fancy text strings
            set(handles.L0Bampl, 'String', l0ba_disp);
            set(handles.L0Bphase, 'String', l0bp_disp);
            set(handles.L1Sampl, 'String', l1sa_disp);
            set(handles.L1Sphase, 'String', l1sp_disp);
            set(handles.L1ipk, 'String', l1i_disp);
            set(handles.L2ampl, 'String', l2a_disp);
            set(handles.L2phase, 'String', l2p_disp);
            set(handles.L2ipk, 'String', l2i_disp);
            set(handles.L3eng, 'String', l3e_disp);
            set(handles.L3phase, 'String', l3p_disp);
            set(handles.valsTime, 'String', dstr,'Visible','on');
            
            
            % Save the pv vals and timeDes to handles so they can be used later
            handles.l0ba = l0ba;
            handles.l0bp = l0bp;
            handles.l0ba_s = l0ba_s;
            handles.l0bp_s = l0bp_s;
            handles.l1sa = l1sa;
            handles.l1sp = l1sp;
            handles.l1i = l1i;
            handles.l1i_s = l1i_s;
            handles.l2a = l2a;
            handles.l2p = l2p;
            handles.l2i = l2i;
            handles.l2i_s = l2i_s;
            handles.l3e = l3e;
            handles.l3e_s = l3e_s;
            handles.l3p = l3p;
            handles.timeDes = timeDes;
            handles.dstr = dstr;
            guidata(hObject, handles)
            
            
            % Enable the "Mess w 6x6" or fixit function
            set(handles.fixit,'Enable','on');
        end

        % Button pushed function: helpMe
        function helpMe_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to helpMe (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Makes a little help page
            
            
            % Stole this neat little sizing/positioning code from Shawn
            pos = get(gcf,'Position');
            set(0,'Units','characters');
            scrnsz=get(0,'ScreenSize');
            set(0,'Units','pixels');
            newx = pos(1) + 110;
            newy = pos(2) + 14.6;
            
            if newx > (scrnsz(3) - 76)
                newx = pos(1) - 76;
            end
            
            
            % Launch a new figure for the help page
            figure('Units','characters','Position',[newx newy 75 28],'Color',[0.763 0.775 1], ...
                            'Name','Help for Fix the 6x6','NumberTitle','off','MenuBar','none','Resize','off');
            uipanel('Title','Fix the 6x6: WTF?','units','characters', ...
                       'Position',[0 0 75 28],'BorderType','none', ...
                        'FontSize',15,'BackgroundColor',[0.85 0.85 0.85],'HighlightColor','white', ...
                        'BorderWidth',1,'TitlePosition','centertop');
            
            
            % All the text nonsense
            props={'Style','text','HorizontalAlignment','left','units','characters'};
            uicontrol(props{:},'String','* What happens when I "Get vals?"', ...
                       'FontSize',10,'FontWeight','bold','Position',[2 23 73 2.8],'BackgroundColor',[0.85 0.85 0.85]);
            uicontrol(props{:},'String','The GUI finds values for L0B amplitude and phase (hard/soft), L1S amplitude and phase (and BC1 peak current), L2 amplitude and phase (and BC2 peak current, hard/soft) and L3 energy (hard/soft) for a 30-second window around your time selected under "Pick a time."  It takes a median of these values, displays them, and saves them for use with "Mess with the 6x6."', ...
                       'FontSize',10,'FontWeight','normal','Position',[5 18 65 6],'BackgroundColor',[0.85 0.85 0.85]);
            uicontrol(props{:},'String','* What happens when I "Mess w 6x6?"', ...
                       'FontSize',10,'FontWeight','bold','Position',[2 14 73 2.8],'BackgroundColor',[0.85 0.85 0.85]);
            uicontrol(props{:},'String','First, the GUI disables BYKIKH and BYKIKS.  It then stops the 6x6 and disables all sub-feedbacks (e.g. DL1, BC1 E and Pk Curr, BC2 E and Pk Curr, DL2).  Next, it pushes the setpoints displayed under "Get PV values" to their respective PVs.  Finally, it restarts the 6x6 (but leaves individual loops disabled).', ...
                       'FontSize',10,'FontWeight','normal','Position',[5 9 65 6],'BackgroundColor',[0.85 0.85 0.85]);
            uicontrol(props{:},'String','* After "messing" with the 6x6, how do I recover the beam?', ...
                       'FontSize',10,'FontWeight','bold','Position',[2 5 73 2.8],'BackgroundColor',[0.85 0.85 0.85]);
            uicontrol(props{:},'String','Possible lower the rate to start with.  Let the beam down to BYKIK and enable feedbacks manually as required.  Enable BYKIKH/S when ready.', ...
                       'FontSize',10,'FontWeight','normal','Position',[5 1 65 5],'BackgroundColor',[0.85 0.85 0.85]);
        end

        % Selection changed function: pickatime
        function pickatime_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in pickatime
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            pickedTime = get(hObject,'Tag');
            
            if strcmp(pickedTime,'enterTime')
                prompt = {'Enter date in mm/dd/yyyy format',...
                        'Enter time in HH:MM format'};
                dlg_title = 'Pick a time';
                num_lines = 1;
                dayago = clock - [0 0 0 13 0 0];
                def_day = datestr(dayago, 'mm/dd/yyyy');
                def_time = datestr(dayago, 'HH:MM');
                def = {def_day, def_time};
                time_input_cell = inputdlg(prompt, dlg_title, num_lines, def);
                if isempty(time_input_cell)
                    time_input_cell = {def_day; def_time};
                end
                time_input_char = char(time_input_cell);
                time_input_cat = [time_input_char(1,:) ' ' time_input_char(2,:)];
                tvec = datevec(time_input_cat, 'mm/dd/yyyy HH:MM');
                handles.tvec = tvec;
                guidata(hObject, handles)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Fix the 6x6';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.pickatime = uibuttongroup(app.figure1);
            app.pickatime.SelectionChangedFcn = createCallbackFcn(app, @pickatime_SelectionChangeFcn, true);
            app.pickatime.Title = 'Pick a time';
            app.pickatime.Tag = 'pickatime';
            app.tminus5 = uiradiobutton(app.pickatime);
            app.tminus5.Tag = 'tminus5';
            app.tminus5.Text = '5 mins ago';
            app.tminus10 = uiradiobutton(app.pickatime);
            app.tminus10.Tag = 'tminus10';
            app.tminus10.Text = '10 mins ago';
            app.tminus10.Value = true;
            app.tminus30 = uiradiobutton(app.pickatime);
            app.tminus30.Tag = 'tminus30';
            app.tminus30.Text = '30 mins ago';
            app.tminus60 = uiradiobutton(app.pickatime);
            app.tminus60.Tag = 'tminus60';
            app.tminus60.Text = '60 mins ago';
            app.enterTime = uiradiobutton(app.pickatime);
            app.enterTime.Tag = 'enterTime';
            app.enterTime.Text = 'Enter time';
            app.getPVpanel = uipanel(app.figure1);
            app.getPVpanel.Title = 'Get PV values';
            app.getPVpanel.Tag = 'getPVpanel';
            app.L2ampl = uilabel(app.getPVpanel);
            app.L2ampl.Tag = 'L2ampl';
            app.L2ampl.Text = 'L2 ampl   ...';
            app.L2phase = uilabel(app.getPVpanel);
            app.L2phase.Tag = 'L2phase';
            app.L2phase.Text = 'L2 phase   ...';
            app.L2ipk = uilabel(app.getPVpanel);
            app.L2ipk.Tag = 'L2ipk';
            app.L2ipk.Text = 'L2 pk curr   ...';
            app.L3eng = uilabel(app.getPVpanel);
            app.L3eng.Tag = 'L3eng';
            app.L3eng.Text = 'L3 energy   ...';
            app.getVals = uibutton(app.getPVpanel, 'push');
            app.getVals.ButtonPushedFcn = createCallbackFcn(app, @getVals_Callback, true);
            app.getVals.Tag = 'getVals';
            app.getVals.Text = 'Get vals';
            app.L3phase = uilabel(app.getPVpanel);
            app.L3phase.Tag = 'L3phase';
            app.L3phase.Text = 'L3 phase   ...';
            app.valsTime = uilabel(app.getPVpanel);
            app.valsTime.Tag = 'valsTime';
            app.valsTime.Visible = 'off';
            app.valsTime.Text = 'Vals Time';
            app.L1Sampl = uilabel(app.getPVpanel);
            app.L1Sampl.Tag = 'L1Sampl';
            app.L1Sampl.Text = 'L1S ampl   ...';
            app.L1Sphase = uilabel(app.getPVpanel);
            app.L1Sphase.Tag = 'L1Sphase';
            app.L1Sphase.Text = 'L1S phase   ...';
            app.L0Bampl = uilabel(app.getPVpanel);
            app.L0Bampl.Tag = 'L0Bampl';
            app.L0Bampl.Text = 'L0B ampl   ...';
            app.L0Bphase = uilabel(app.getPVpanel);
            app.L0Bphase.Tag = 'L0Bphase';
            app.L0Bphase.Text = 'L0B phase   ...';
            app.L1ipk = uilabel(app.getPVpanel);
            app.L1ipk.Tag = 'L1ipk';
            app.L1ipk.Text = 'L1 pk cur   ...';
            app.setPVpanel = uipanel(app.figure1);
            app.setPVpanel.Title = 'Fix the 6x6!';
            app.setPVpanel.Tag = 'setPVpanel';
            app.fixit = uibutton(app.setPVpanel, 'push');
            app.fixit.ButtonPushedFcn = createCallbackFcn(app, @fixit_Callback, true);
            app.fixit.Tag = 'fixit';
            app.fixit.Text = 'Mess w 6x6';
            app.helpMe = uibutton(app.setPVpanel, 'push');
            app.helpMe.ButtonPushedFcn = createCallbackFcn(app, @helpMe_Callback, true);
            app.helpMe.Tag = 'helpMe';
            app.helpMe.Text = 'Help?!';
            app.whatsUp = uilabel(app.setPVpanel);
            app.whatsUp.Tag = 'whatsUp';
            app.whatsUp.Visible = 'off';
            app.whatsUp.Text = 'Doing stuff?';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fix_the_6x6_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)fix_the_6x6_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end